classdef AddBeadLayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DoneCheckBox                  matlab.ui.control.CheckBox
        CreatelayerButton             matlab.ui.control.Button
        SuggestedZpositionEditField   matlab.ui.control.NumericEditField
        SuggestedZpositionLabel       matlab.ui.control.Label
        ImagesizeZumEditField         matlab.ui.control.NumericEditField
        ImagesizeZumEditFieldLabel    matlab.ui.control.Label
        ImageZdimpixelEditField       matlab.ui.control.NumericEditField
        ImageZdimpixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeYumEditField         matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel    matlab.ui.control.Label
        ImagesizeXumEditField         matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel    matlab.ui.control.Label
        ImageYdimpixelEditField       matlab.ui.control.NumericEditField
        ImageYdimpixelEditFieldLabel  matlab.ui.control.Label
        ImageXdimpixelEditField       matlab.ui.control.NumericEditField
        ImageXdimpixelEditFieldLabel  matlab.ui.control.Label
        SuggestedidEditField          matlab.ui.control.NumericEditField
        SuggestedidLabel              matlab.ui.control.Label
        BeadlayeridEditField          matlab.ui.control.NumericEditField
        BeadlayeridEditFieldLabel     matlab.ui.control.Label
        nmEditField_2                 matlab.ui.control.NumericEditField
        nmEditField_2Label            matlab.ui.control.Label
        nmEditField                   matlab.ui.control.NumericEditField
        nmEditFieldLabel              matlab.ui.control.Label
        VirtualSampleDimensionsLabel  matlab.ui.control.Label
        PixelSizenmEditField          matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel     matlab.ui.control.Label
        BinningEditField              matlab.ui.control.NumericEditField
        BinningEditFieldLabel         matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        SavesampleButton              matlab.ui.control.Button
        LoadexistingsampleButton      matlab.ui.control.Button
        BeadlayerZpositionpixelEditField  matlab.ui.control.NumericEditField
        CellshortaxisYpixelLabel      matlab.ui.control.Label
        BeadlayerthicknesspixelEditField  matlab.ui.control.NumericEditField
        BeadlayerthicknesspixelEditFieldLabel  matlab.ui.control.Label
        AddbeadlayerLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        Sample = [] % Virtual sample
        Proj = [] % Z-projection
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]

            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImageXdimpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImageYdimpixelEditField.Value;
            app.ImagesizeZumEditField.Value=1e-3*scale*app.ImageZdimpixelEditField.Value;

            app.nmEditField.Value=scale*app.BeadlayerthicknesspixelEditField.Value;
            app.nmEditField_2.Value=scale*app.BeadlayerZpositionpixelEditField.Value;
        end
        
        function display_sample(app)
            disp('Displaying cells ...')

            figure(1)
            clf
            colormap('jet');

            vol3d('cdata', app.Sample);
            alphamap('default')
            axis image
            view(3);
            xlabel('X [pixel]')
            ylabel('Y [pixel]')
            zlabel('Z [pixel]')

            title('Virtual 3D Sample')


            if ~isempty(app.Proj)
                figure(2)
                clf
                set(gcf,'Color','w')
                imagesc(app.Proj);
                axis image
                colormap('gray')
                xlabel('X [pixel]')
                ylabel('Y [pixel]')
                title('Z-projection')
            end
            disp('Done !')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

            if app.par.qPALM==1
                warndlg('Cannot create bead layer for 3D qPALM virtual samples: use a specific virtual sample for the beads !')
            end

            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];

            app.par.z_pos=app.BeadlayerZpositionpixelEditField.Value;
            app.par.z_thick=app.BeadlayerthicknesspixelEditField.Value;
            app.par.bead_id=[];
            app.par.bead_id_previous=[];

            app.par.sample_loaded=0;
            app.CreatelayerButton.Enable="off";

            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.Choose3DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: BeadlayerthicknesspixelEditField
        function BeadlayerthicknesspixelEditFieldValueChanged(app, event)
            value = app.BeadlayerthicknesspixelEditField.Value;
            app.par.z_thick=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BeadlayerZpositionpixelEditField
        function BeadlayerZpositionpixelEditFieldValueChanged(app, event)
            value = app.BeadlayerZpositionpixelEditField.Value;
            app.par.z_pos=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.Sample)
                kernel_pattern=app.Sample;
                par=app.par.Sample_par; %#ok<ADPROPLC>
                Proj=app.Proj; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.mat','SMIS File Selection',app.par.file);
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving 3D sample ...');
                    kernelfile=fullfile(app.par.pattern_library,virtual_sample_file);
                    save(kernelfile,'kernel_pattern','Proj','par');
                    disp('Done !');
                    if ~isempty(app.Proj)
                        disp('Saving z-projection ...');
                        MyDirProj=fullfile(MyDir,'BACKGROUND IMAGES');
                        if ~exist(MyDirProj,'dir')
                            mkdir(MyDirProj);
                        end
                        projfile=fullfile(MyDirProj,strrep(virtual_sample_file,'.mat','.tif'));
                        imwrite(uint8(app.Proj),projfile,'tif');
                        disp('Done !');
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'kernel_pattern','Proj','par');
                app.par.file=file;
                app.par.InputSample=kernel_pattern;
                app.Proj=Proj; %#ok<ADPROPLC>
                app.par.Sample_par=par; %#ok<ADPROPLC>

                app.ImageXdimpixelEditField.Value=app.par.Sample_par.x_dim;
                app.ImageYdimpixelEditField.Value=app.par.Sample_par.y_dim;
                app.ImageZdimpixelEditField.Value=app.par.Sample_par.z_dim;

                app.par.ids=unique(app.par.InputSample);
                app.SuggestedidEditField.Value=max(app.par.ids)+1;
                app.par.bead_id=app.SuggestedidEditField.Value;
                app.par.bead_id_previous=app.SuggestedidEditField.Value;
                app.BeadlayeridEditField.Value=app.SuggestedidEditField.Value;

                [~,~,z]=ind2sub(size(app.par.InputSample),find(app.par.InputSample>0));

                app.SuggestedZpositionEditField.Value=round(mean(z));
                app.BeadlayerZpositionpixelEditField.Value=app.SuggestedZpositionEditField.Value;
                app.par.z_pos=app.SuggestedZpositionEditField.Value;

                app.par.sample_loaded=1;
                app.CreatelayerButton.Enable="on";

                update_size_evaluation(app)

                app.Sample=app.par.InputSample;
                display_sample(app)
                app.DoneCheckBox.Value=0;
            end
            figure(app.UIFigure)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Callback function
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BeadlayeridEditField
        function BeadlayeridEditFieldValueChanged(app, event)
            value = app.BeadlayeridEditField.Value;
            app.par.bead_id=value;
            app.par.bead_id_previous=event.PreviousValue;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: CreatelayerButton
        function CreatesampleButtonPushed(app, event)
            if (app.par.z_pos-app.par.z_thick/2) < 0 || (app.par.z_pos+app.par.z_thick/2) > app.par.Sample_par.z_dim
                warndlg('Z-layer incompatible with image size !')
            else

                bead_layer = app.par.InputSample(:,:,app.par.z_pos-app.par.z_thick/2+1:app.par.z_pos+app.par.z_thick/2);

                bead_layer(bead_layer==0 | bead_layer==app.par.bead_id_previous)=app.par.bead_id;
                offset=app.par.Sample_par.cell_border_offset;
                
                bead_layer(1:offset,:,:)=0;
                bead_layer(end-offset:end,:,:)=0;
                bead_layer(:,1:offset,:)=0;
                bead_layer(:,end-offset:end,:)=0;

                app.Sample(:,:,app.par.z_pos-app.par.z_thick/2+1:app.par.z_pos+app.par.z_thick/2)=bead_layer;
                app.Proj=sum(app.Sample,3);

                app.DoneCheckBox.Value=true;

                display_sample(app)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddbeadlayerLabel = uilabel(app.UIFigure);
            app.AddbeadlayerLabel.Text = 'Add bead layer';
            app.BeadlayerthicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.BeadlayerthicknesspixelEditFieldLabel.Text = 'Bead layer thickness  [pixel]';
            app.BeadlayerthicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeadlayerthicknesspixelEditField.Limits = [1 Inf];
            app.BeadlayerthicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.BeadlayerthicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @BeadlayerthicknesspixelEditFieldValueChanged, true);
            app.BeadlayerthicknesspixelEditField.Value = 2;
            app.CellshortaxisYpixelLabel = uilabel(app.UIFigure);
            app.CellshortaxisYpixelLabel.Text = 'Bead layer Z position [pixel]';
            app.BeadlayerZpositionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeadlayerZpositionpixelEditField.Limits = [1 Inf];
            app.BeadlayerZpositionpixelEditField.ValueDisplayFormat = '%.0f';
            app.BeadlayerZpositionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @BeadlayerZpositionpixelEditFieldValueChanged, true);
            app.BeadlayerZpositionpixelEditField.Value = 2;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.BeadlayeridEditFieldLabel = uilabel(app.UIFigure);
            app.BeadlayeridEditFieldLabel.Text = 'Bead layer id';
            app.BeadlayeridEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeadlayeridEditField.Limits = [0 Inf];
            app.BeadlayeridEditField.ValueDisplayFormat = '%.0f';
            app.BeadlayeridEditField.ValueChangedFcn = createCallbackFcn(app, @BeadlayeridEditFieldValueChanged, true);
            app.BeadlayeridEditField.Value = 1;
            app.SuggestedidLabel = uilabel(app.UIFigure);
            app.SuggestedidLabel.Enable = 'off';
            app.SuggestedidLabel.Text = '(Suggested id)';
            app.SuggestedidEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuggestedidEditField.Limits = [-90 90];
            app.SuggestedidEditField.ValueDisplayFormat = '%.0f';
            app.SuggestedidEditField.Editable = 'off';
            app.SuggestedidEditField.Enable = 'off';
            app.SuggestedidEditField.Value = 10;
            app.ImageXdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageXdimpixelEditFieldLabel.Text = 'Image X dim [pixel]';
            app.ImageXdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageXdimpixelEditField.Limits = [1 Inf];
            app.ImageXdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageXdimpixelEditField.Editable = 'off';
            app.ImageXdimpixelEditField.Value = 256;
            app.ImageYdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageYdimpixelEditFieldLabel.Text = 'Image Y dim [pixel]';
            app.ImageYdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageYdimpixelEditField.Limits = [1 Inf];
            app.ImageYdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageYdimpixelEditField.Editable = 'off';
            app.ImageYdimpixelEditField.Value = 256;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.ImageZdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageZdimpixelEditFieldLabel.Text = 'Image Z dim [pixel]';
            app.ImageZdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageZdimpixelEditField.Limits = [1 Inf];
            app.ImageZdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageZdimpixelEditField.Editable = 'off';
            app.ImageZdimpixelEditField.Value = 64;
            app.ImagesizeZumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeZumEditFieldLabel.Text = 'Image size Z [um]';
            app.ImagesizeZumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeZumEditField.Limits = [0 Inf];
            app.ImagesizeZumEditField.Editable = 'off';
            app.ImagesizeZumEditField.Value = 2;
            app.SuggestedZpositionLabel = uilabel(app.UIFigure);
            app.SuggestedZpositionLabel.Enable = 'off';
            app.SuggestedZpositionLabel.Text = '(Suggested Z position)';
            app.SuggestedZpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuggestedZpositionEditField.Limits = [-90 90];
            app.SuggestedZpositionEditField.ValueDisplayFormat = '%.0f';
            app.SuggestedZpositionEditField.Editable = 'off';
            app.SuggestedZpositionEditField.Enable = 'off';
            app.SuggestedZpositionEditField.Value = 10;
            app.CreatelayerButton = uibutton(app.UIFigure, 'push');
            app.CreatelayerButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatelayerButton.Text = 'Create layer';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddBeadLayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end