classdef AnalyzeResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DatasetNameEditField            matlab.ui.control.EditField
        DatasetNameEditFieldLabel       matlab.ui.control.Label
        SingleMoleculePanel             matlab.ui.container.Panel
        ForceNBinsCheckBox              matlab.ui.control.CheckBox
        YLogScaleSwitch_2               matlab.ui.control.Switch
        YLogScaleSwitch_2Label          matlab.ui.control.Label
        Info4EditField                  matlab.ui.control.NumericEditField
        Info4EditFieldLabel             matlab.ui.control.Label
        Info3EditField                  matlab.ui.control.NumericEditField
        Info3EditFieldLabel             matlab.ui.control.Label
        ChannelSwitch                   matlab.ui.control.RockerSwitch
        pvalueEditField                 matlab.ui.control.NumericEditField
        pvalueEditFieldLabel            matlab.ui.control.Label
        Fitparameter1EditField_2        matlab.ui.control.NumericEditField
        Fitparameter1EditField_2Label   matlab.ui.control.Label
        HistogramBinsEditField          matlab.ui.control.NumericEditField
        HistogramBinsEditFieldLabel     matlab.ui.control.Label
        AnalyzeDiffusionStatesButton    matlab.ui.control.Button
        ShowSMTracesButton              matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        XLogScaleSwitch_2               matlab.ui.control.Switch
        XLogScaleSwitch_2Label          matlab.ui.control.Label
        Fitparameter3EditField          matlab.ui.control.NumericEditField
        Fitparameter3EditFieldLabel     matlab.ui.control.Label
        Fitparameter1EditField          matlab.ui.control.NumericEditField
        Fitparameter1EditFieldLabel     matlab.ui.control.Label
        Info2EditField                  matlab.ui.control.NumericEditField
        Info2EditFieldLabel             matlab.ui.control.Label
        Info1EditField                  matlab.ui.control.NumericEditField
        Info1EditFieldLabel             matlab.ui.control.Label
        UseonlyactivatedmoleculesSwitch  matlab.ui.control.Switch
        UseonlyactivatedmoleculesSwitchLabel  matlab.ui.control.Label
        UseonlybleachedmoleculesSwitch  matlab.ui.control.Switch
        UseonlybleachedmoleculesSwitchLabel  matlab.ui.control.Label
        CompositefinalFigureSwitch      matlab.ui.control.Switch
        CompositefinalFigureSwitchLabel  matlab.ui.control.Label
        OKButton_2                      matlab.ui.control.Button
        MakefinalfiguresButton_2        matlab.ui.control.Button
        YLimitSlider_2                  matlab.ui.control.Slider
        YLimitSlider_2Label             matlab.ui.control.Label
        StateList_SM                    matlab.ui.control.Table
        XLimitSlider_2                  matlab.ui.control.Slider
        XLimitSlider_2Label             matlab.ui.control.Label
        FontSizeSlider_2                matlab.ui.control.Slider
        FontSizeSlider_2Label           matlab.ui.control.Label
        AxisWidthSlider_2               matlab.ui.control.Slider
        AxisWidthSlider_2Label          matlab.ui.control.Label
        LineWidthSlider_2               matlab.ui.control.Slider
        LineWidthSlider_2Label          matlab.ui.control.Label
        ShowLegendSwitch_2              matlab.ui.control.Switch
        ShowLegendSwitch_2Label         matlab.ui.control.Label
        XaxisSwitch_2                   matlab.ui.control.Switch
        XaxisSwitch_2Label              matlab.ui.control.Label
        PlotFluo_5                      matlab.ui.control.UIAxes
        PlotFluo_4                      matlab.ui.control.UIAxes
        PlotFluo_3                      matlab.ui.control.UIAxes
        FluorophoretoanalyseDropDown    matlab.ui.control.DropDown
        FluorophoretoanalyseDropDownLabel  matlab.ui.control.Label
        NumberoffluorophoresEditField   matlab.ui.control.NumericEditField
        NumberoffluorophoresEditFieldLabel  matlab.ui.control.Label
        TypeofSMISdataSwitch            matlab.ui.control.Switch
        TypeofSMISdataLabel             matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        FluorophorenameEditField        matlab.ui.control.EditField
        FluorophorenameEditFieldLabel   matlab.ui.control.Label
        SMISGroundTruthResultsAnalysisLabel  matlab.ui.control.Label
        EnsemblePanel                   matlab.ui.container.Panel
        SavedatatocsvButton             matlab.ui.control.Button
        YLogScaleSwitch                 matlab.ui.control.Switch
        YLogScaleSwitchLabel            matlab.ui.control.Label
        XLogScaleSwitch                 matlab.ui.control.Switch
        XLogScaleSwitchLabel            matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        MakefinalfiguresButton          matlab.ui.control.Button
        YLimitSlider                    matlab.ui.control.Slider
        YLimitLabel                     matlab.ui.control.Label
        StateList                       matlab.ui.control.Table
        XLimitSlider                    matlab.ui.control.Slider
        XLimitLabel                     matlab.ui.control.Label
        FontSizeSlider                  matlab.ui.control.Slider
        FontSizeSliderLabel             matlab.ui.control.Label
        AxisWidthSlider                 matlab.ui.control.Slider
        AxisWidthSliderLabel            matlab.ui.control.Label
        LineWidthSlider                 matlab.ui.control.Slider
        LineWidthSliderLabel            matlab.ui.control.Label
        ShowLegendSwitch                matlab.ui.control.Switch
        ShowLegendSwitchLabel           matlab.ui.control.Label
        XaxisSwitch                     matlab.ui.control.Switch
        XaxisSwitchLabel                matlab.ui.control.Label
        PlotStates                      matlab.ui.control.UIAxes
        PlotFluo_2                      matlab.ui.control.UIAxes
        PlotFluo                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        smis_gui_parameters=[] % Description
        states_ids=[]; % Ids of the photophysical states
        states_names=[]; % Names of the photophysical states
        n_states=[]; % Number of photophysical states
        TableData=[]; % Description of the plotting parameters
        ens=[] % The ensemble data
        plotted_ens=[] % The plotted ensemble data
        sm_par_ens=[] % The sm_par for ensemble data
        im_par_ens=[] % The im_par for ensemble data
        Color=[]; % Plot color of the different photophysical states
        FinalFigureMode=0; % Tag to plot or not the final figure, initialize to 0;
        sms=[]; % The sms data
        R=[]; % The SM processing Results containing DATA and INFO
        is_convertible=[]; % Flag for photoconvertible fluorophore
        state_types=[] % Cell array containing the types of photophysical states
        n=[]; % Number of molecules for current fluorophore
        fit=[]; % Fitting parameters
        fitted_values=nan; % Fitting results, start with nan
        fit_ok=0; % Success of Fitting, , start with 0
        external_figure=struct('mode',0,'state_id',[]); % Structure to handle external plotting
        dialog_on=1; % 1 if warning dialogs are displayed
        MyFigure=[]; % Id of external figure
        TotalFrameTime=[] % The total frame time (frame time + add time) [s]
    end
    
    properties (Access = public)
        sm=[]; % The current SM
        sm_par=[]; % The sm_par for single molecule data
        im_par=[]; % The im_par for single molecule data
        fluo_id=[]; % # of the fluorophore to plot
        datadir=[]; % Directory where current data are stored
        datafile=[]; % Current file
        par=[]; % Other parameters
        ShowTracesApp=[]; % Call to ShowTraces
        ShowDiffApp=[]; % Call to ShowDiffApp
    end
    
    methods (Access = private)
        function PlotEnsembleData(app)
            if app.FinalFigureMode==0
                Fig1=app.PlotStates;
                Fig2=app.PlotFluo;
                Fig2_2=app.PlotFluo_2;
                
            else % Set external axes
                figure(1); Fig1=gca; set(gcf,'color','w');
                title('Ensemble State Population');
                
                figure(2); Fig2=gca; set(gcf,'color','w');
                title('Fluorescence Signal (Ch1)');
                if app.im_par_ens.two_channel==1
                    figure(3); Fig2_2=gca; set(gcf,'color','w');
                    title('Fluorescence signal (Ch2)');
                end
            end
            
            % Clear the Axes
            cla(Fig1)
            cla(Fig2)
            if app.im_par_ens.two_channel==1
                Fig2_2.Visible=1;
                cla(Fig2_2)
            end
            
            % Handle X label
            if strcmp(app.XaxisSwitch.Value,'Frames')==1
                X=1:app.im_par_ens.n_images;
                xlabel(Fig1,'Frame #');
                xlabel(Fig2,'Frame #');
                if app.im_par_ens.two_channel==1
                    xlabel(Fig2_2,'Frame #');
                end
                app.plotted_ens.X_name={'Frame #'}; 
            else
                X=(1:app.im_par_ens.n_images)*(app.im_par_ens.frametime+app.im_par_ens.addtime)*1e-3;
                xlabel(Fig1,'Time [s]');
                xlabel(Fig2,'Time [s]');
                if app.im_par_ens.two_channel==1
                    xlabel(Fig2_2,'Time [s]');
                end
                app.plotted_ens.X_name={'Time [s]'}; 
            end

            app.plotted_ens.X=X; % Save X data
            

            xlim(Fig1,[0 max(X)*max([0.001, app.XLimitSlider.Value/100])]);
            xlim(Fig2,[0 max(X)*max([0.001, app.XLimitSlider.Value/100])]);
            if app.im_par_ens.two_channel==1
                xlim(Fig2_2,[0 max(X)*max([0.001, app.XLimitSlider.Value/100])]);
            end
            
            % Handle Y label
            ylabel(Fig1,'Normalized Population');
            ylabel(Fig2,'Fluorescence [ph/molecule]');
            if app.im_par_ens.two_channel==1
                ylabel(Fig2_2,'Fluorescence [ph/molecule]');
            end
            ylim(Fig1,[0 max([0.001,app.YLimitSlider.Value/100])]);
            
            % Handle Linewidth
            set(Fig1,'LineWidth',app.AxisWidthSlider.Value);
            set(Fig2,'LineWidth',app.AxisWidthSlider.Value);
            if app.im_par_ens.two_channel==1
                set(Fig2_2,'LineWidth',app.AxisWidthSlider.Value);
            end
            
            % Handle fontsize
            set(Fig1,'FontSize',app.FontSizeSlider.Value);
            set(Fig2,'FontSize',app.FontSizeSlider.Value);
            if app.im_par_ens.two_channel==1
                set(Fig2_2,'FontSize',app.FontSizeSlider.Value);
            end
            
            % Handle Log Scale
            if strcmp(app.XLogScaleSwitch.Value,'On')==1
                set(Fig1,'XScale','log');
                set(Fig2,'XScale','log');
            else
                set(Fig1,'XScale','linear');
                set(Fig2,'XScale','linear');
            end
            
            if strcmp(app.YLogScaleSwitch.Value,'On')==1
                set(Fig1,'YScale','log');
                set(Fig2,'YScale','log');
            else
                set(Fig1,'YScale','linear');
                set(Fig2,'YScale','linear');
            end
            
            
            % Plot the photophysical states
            hold(Fig1,'on')
            
            app.plotted_ens.state_concentration=[];
            app.plotted_ens.fluo_ch1=[];
            app.plotted_ens.tot_fluo_ch1=[];
            
            w_plot=find(app.StateList.Data{:,1}==1); % Take those states to plot
            
            for k=1:app.n_states
                if app.StateList.Data{k,1}==1
                    if app.states_ids(k)>0 % All fluorophore states (filter out app.states_ids=-1 for total fluorescence)
                        plot(Fig1,X,app.ens(app.fluo_id).det_p(app.states_ids(k),:),'LineWidth',app.LineWidthSlider.Value,'Color',app.Color(k,:))
                    end
                end
            end

            app.plotted_ens.state_concentration=app.ens(app.fluo_id).det_p(app.states_ids(w_plot(app.states_ids(w_plot)>0)),:);
            app.plotted_ens.state_names=app.ens(app.fluo_id).state_names(app.states_ids(w_plot(app.states_ids(w_plot)>0)),:);
            
            if strcmp(app.ShowLegendSwitch.Value,'On')==1
                my_legend=app.ens(app.fluo_id).state_names(app.states_ids(w_plot(app.states_ids(w_plot)>0)));
                for j=1:numel(my_legend)
                    my_legend{j}=strrep(my_legend{j},'_','\_');
                end
                legend(Fig1,my_legend)
            else
                legend(Fig1, 'off');
            end
            
            % Plot the fluorescence signal
            % Channel 1
            
            hold(Fig2,'on')
            maxY=0; % Handle the maximum value
            plot_fluo_states=zeros(1,app.sm_par_ens(app.fluo_id).n_fluorescent_states); % Tag for the legend
            for k=1:app.sm_par_ens(app.fluo_id).n_fluorescent_states
                
                % Find the correct state id
                w_id=find(app.states_ids==app.sm_par_ens(app.fluo_id).fluorescent_states(k));

                %Plot if selected
                if any(app.states_ids(w_plot)==w_id)
                    plot_fluo_states(k)=1; % Plot the state
                    c=app.Color(w_id,:);  %Get the right color
                    plot(Fig2,X,app.ens(app.fluo_id).S_ch1(k,:),'LineWidth',app.LineWidthSlider.Value,'Color',c); % Plot the data
                    maxY=max([maxY,app.ens(app.fluo_id).S_ch1(k,:)]);
                end
            end

            app.plotted_ens.fluo_ch1=app.ens(app.fluo_id).S_ch1(plot_fluo_states==1,:);
            app.plotted_ens.fluo_names_ch1=app.ens(app.fluo_id).state_names(app.sm_par_ens(app.fluo_id).fluorescent_states(plot_fluo_states==1));
            
            %Plot total fluorescence if requested
            if any(app.states_ids(w_plot)==-1)
                c=app.Color(end,:);
                plot(Fig2,X,sum(app.ens(app.fluo_id).S_ch1(:,:),1),'LineWidth',app.LineWidthSlider.Value,'Color',c)
                maxY=max(sum(app.ens(app.fluo_id).S_ch1(:,:),1));
                app.plotted_ens.tot_fluo_ch1=sum(app.ens(app.fluo_id).S_ch1(:,:),1);
                app.plotted_ens.tot_fluo_name_ch1={'Total fluorescence_Ch1'};
            else
                app.plotted_ens.tot_fluo_ch1=[];
                app.plotted_ens.tot_fluo_name_ch1=[];
            end
                
            % In case nothing to plot
            if maxY==0 
                maxY=1;
            end
            ylim(Fig2,[0 maxY*1.1*app.YLimitSlider.Value/100]);
            
            % Handle legend
            if strcmp(app.ShowLegendSwitch.Value,'On')==1
                my_legend=app.ens(app.fluo_id).state_names(app.sm_par_ens(app.fluo_id).fluorescent_states(plot_fluo_states==1));
                for j=1:numel(my_legend)
                    my_legend{j}=strrep(my_legend{j},'_','\_');
                end
                if any(app.states_ids(w_plot)==-1)
                    my_legend(end+1)={'Total fluorescence'};
                end
                legend(Fig2,my_legend)
            else
                legend(Fig2, 'off');
            end
            
            % Channel 2
            if app.im_par_ens.two_channel==1

                hold(Fig2_2,'on')

                app.plotted_ens.fluo_ch2=[];
                app.plotted_ens.tot_fluo_ch2=[];

                maxY=0; % Handle the maximum value
                plot_fluo_states=zeros(1,app.sm_par_ens(app.fluo_id).n_fluorescent_states); % Tag for the legend
                for k=1:app.sm_par_ens(app.fluo_id).n_fluorescent_states

                    % Find the correct state id
                    w_id=find(app.states_ids==app.sm_par_ens(app.fluo_id).fluorescent_states(k));

                    %Plot if selected
                    if any(app.states_ids(w_plot)==w_id)
                        plot_fluo_states(k)=1; % Plot the state
                        c=app.Color(w_id,:);  %Get the right color
                        plot(Fig2_2,X,app.ens(app.fluo_id).S_ch2(k,:),'LineWidth',app.LineWidthSlider.Value,'Color',c); % Plot the data
                        maxY=max([maxY,app.ens(app.fluo_id).S_ch2(k,:)]);
                    end
                end

                app.plotted_ens.fluo_ch2=app.ens(app.fluo_id).S_ch2(plot_fluo_states==1,:);
                app.plotted_ens.fluo_names_ch2=app.plotted_ens.fluo_names_ch1;

                %Plot total fluorescence if requested
                if any(app.states_ids(w_plot)==-1)
                    c=app.Color(end,:);
                    plot(Fig2_2,X,sum(app.ens(app.fluo_id).S_ch2(:,:),1),'LineWidth',app.LineWidthSlider.Value,'Color',c)
                    maxY=max(sum(app.ens(app.fluo_id).S_ch2(:,:),1));
                    app.plotted_ens.tot_fluo_ch2=sum(app.ens(app.fluo_id).S_ch2(:,:),1);
                    app.plotted_ens.tot_fluo_name_ch2={'Total fluorescence_Ch2'};
                else
                    app.plotted_ens.tot_fluo_ch2=[];
                    app.plotted_ens.tot_fluo_name_ch2=[];
                end
            

                % In case nothing to plot
                if maxY==0
                    maxY=1;
                end
                ylim(Fig2_2,[0 maxY*1.1*app.YLimitSlider.Value/100]);

                % Handle legend
                if strcmp(app.ShowLegendSwitch.Value,'On')==1
                    my_legend=app.ens(app.fluo_id).state_names(app.sm_par_ens(app.fluo_id).fluorescent_states(plot_fluo_states==1));
                    for j=1:numel(my_legend)
                        my_legend{j}=strrep(my_legend{j},'_','\_');
                    end
                    if any(app.states_ids(w_plot)==-1)
                        my_legend(end+1)={'Total fluorescence'};
                    end
                    legend(Fig2_2,my_legend)
                else
                    legend(Fig2_2, 'off');
                end
            end
            figure(app.UIFigure); % Get the GUI in the front


        end
        
        function SetupEnsembleTable(app)
            
            app.states_ids=unique(app.sm_par_ens(app.fluo_id).state_ids);
            app.states_names=app.ens(app.fluo_id).state_names(app.states_ids);
            app.n_states=numel(app.states_ids);

            % Add total fluorescence channel in case of several fluorescent states 
            if app.sm_par_ens(app.fluo_id).n_fluorescent_states > 1
                app.states_ids(end+1)=-1; % Code for total fluorescence
                app.states_names(end+1)={'Total fluorescence'};
                app.n_states=app.n_states+1;
            end
            
            
            %Setup Colors
            if app.n_states<=7
                app.Color=app.PlotStates.ColorOrder(1:app.n_states,:);
            else
                app.Color=zeros(app.n_states,3);
                app.Color(1:7,:)=app.PlotStates.ColorOrder;
                app.Color(8:app.n_states,:)=rand(app.n_states-7,3);
            end
            
            app.StateList.Visible=1;
            app.StateList.Position=[30,max([380,580-22*app.n_states]),300,min(250,30+22*app.n_states)]; % Adjust position
            
            % Show the names of the fluorescent states
            app.StateList.RowName=app.states_names;
            
            % Initialize the plotting data
            StatesPlot=true(app.n_states,1);
            %StatesColor=false(app.n_states,1);
            StatesColor=cell(app.n_states,1);
            [StatesColor{:}] = deal('');
            %StatesProperty1=zeros(app.n_states,1);
            %StatesProperty2=zeros(app.n_states,1);
            
            app.TableData = table(StatesPlot,StatesColor, ...
                'VariableNames',app.StateList.ColumnName);
            app.StateList.Data=app.TableData;
            
            for k=1:app.n_states
                sb = uistyle('BackgroundColor',app.Color(k,:));
                addStyle(app.StateList,sb,'cell',[k,2]);
            end
            
            
        end
        
        function SetUpSMData(app)
            
            % Get the Single Molecules for current fluorophore
            app.sm=app.sms(app.fluo_id).sm;
            
            % Filter for bleached molecules
            if strcmp(app.UseonlybleachedmoleculesSwitch.Value,'On')==1
                app.sm=app.sm([app.sm.bleached]>0);
            end
            
            %We need to consider states in rapid equilibrium as unique states
            app.states_ids=unique(app.sm_par(app.fluo_id).state_ids);
            app.states_names=app.sm_par(app.fluo_id).state_names(app.states_ids);
            app.n_states=numel(app.states_ids);
            
            % Look if the fluorophore is photoconvertible
            if app.sm_par(app.fluo_id).converted_state>app.sm_par(app.fluo_id).initial_state
                PC_struct=struct('pc_time', [], 'pc_origin', []);
                app.is_convertible=1;
                app.UseonlyactivatedmoleculesSwitch.Visible='on';
                app.UseonlyactivatedmoleculesSwitchLabel.Visible='on';
                % Eventually filter for activated molecules
                if strcmp(app.UseonlyactivatedmoleculesSwitch.Value,'On')==1
                    app.sm=app.sm([app.sm.activated]>0);
                end
                
            else
                PC_struct=[];
                app.is_convertible=0;
                app.UseonlyactivatedmoleculesSwitch.Visible='off';
                app.UseonlyactivatedmoleculesSwitchLabel.Visible='off';
            end
            
            app.n=numel(app.sm); % # of molecules for current fluorophore
            
            %For each state, define its durations, origin states and the # of times it appears
            
            % Get the bleached states
            bleached_states=app.sm_par(app.fluo_id).bleached_states;
            n_bleached_states=numel(bleached_states);
            BLEACH_struct=struct('bleach_time', [], 'bleached_state', [], 'bleached_origin', [], 'active_time', []);
            
            %Get the fluorescent states
            fluo_states=app.sm_par(app.fluo_id).fluorescent_states;
            FLUO_struct(1:app.sm_par(app.fluo_id).n_fluorescent_states)=struct('duration', [], 'origin_state', [], 'N', [], 'active', [], 'total_on', []);
            
            %Get the states possibly in equilibrium with fluorescent states
            associated_dark_states=app.sm_par(app.fluo_id).associated_dark_states;
            
            % Get the Dark states
            % Get photoactive dark states that are not in rapide exchange
            pa_dark_states=setdiff(app.sm_par(app.fluo_id).photoactive_dark_states,app.sm_par(app.fluo_id).associated_dark_states);
            % Get thermal dark states
            th_dark_states=setdiff(unique(app.sm_par(app.fluo_id).state_ids),[app.sm_par(app.fluo_id).photoactive_states,bleached_states]);
            % Group them
            dark_states=sort([pa_dark_states,th_dark_states']);
            dark_states_names=app.sm_par(app.fluo_id).state_names(dark_states);
            
            n_dark_states=numel(dark_states);
            DARK_struct(1:n_dark_states)=struct('duration', [], 'origin_state', [], 'N', []);
            
            %Get the pseudo-experimental on, off, active and total on times for the 2 channels
            % on = duration of all on states in # of frames: whenever fluorescence shows up (even undetectable in practice) during a frame it is counted as an on frame 
            % off = duration of all off states in # of frames: whenever fluorescence does not show up during a frame it is counted as an off frame 
            % active = total duration in # of frames from first to last on frame, ie includes all off times 
            % total_on = total duration in # of frames of all on frames, ie does not include off times 
            EXP_struct(1:2)=struct('on', [], 'off', [], 'N', [], 'active', [], 'total_on', []);
            
            %Get the list of unique states to their state type
            app.state_types=cell(1,app.n_states);
            % Look for fluorescent states
            [~,w_fluo_states]=intersect(app.states_ids,fluo_states);
            app.state_types(w_fluo_states)={'Fluorescent'};
            
            % Look for dark states
            [~,w_dark_states]=intersect(app.states_ids,dark_states);
            app.state_types(w_dark_states)={'Dark'};
            
            % Look for bleached states
            [~,w_bleached_states]=intersect(app.states_ids,bleached_states);
            app.state_types(w_bleached_states)={'Bleached'};
            
            
            % Get the SM Data
            DATA(1:app.n)=struct(...
                'BLEACHED', BLEACH_struct, ...
                'PC', PC_struct, ...
                'FLUO', FLUO_struct, ...
                'DARK', DARK_struct, ...
                'EXP', EXP_struct ...
                );
            
            %Define INFO
            INFO=struct;
            INFO.fluo_names=app.sm_par(app.fluo_id).state_names(fluo_states);
            INFO.dark_names=dark_states_names;
            INFO.dark_states=dark_states;
            INFO.bleach_names=app.sm_par(app.fluo_id).state_names(bleached_states);
            INFO.state_names=app.states_names;
            
            INFO.n_mol=app.n;
            
            % On times for fluorescent states
            INFO.MeanOnTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            INFO.MedianOnTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            
            % Active times for fluorescent states (lifetime between
            % appearance and disappearance)
            INFO.MeanActiveTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            INFO.MedianActiveTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            
            % Total on times for fluorescent states (total on time between
            % appearance and disappearance)
            INFO.MeanTotalOnTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            INFO.MedianTotalOnTime=nan(1,app.sm_par(app.fluo_id).n_fluorescent_states);
            
            % Off times for dark states
            INFO.MeanOffTime=nan(1,n_dark_states);
            INFO.MedianOffTime=nan(1,n_dark_states);
            INFO.MeanNBlink=nan(1,n_dark_states);
            INFO.MedianNBlink=nan(1,n_dark_states);
            
            % Bleaching times for bleached states
            INFO.MeanBleachTime=nan(1,n_bleached_states);
            INFO.MedianBleachTime=nan(1,n_bleached_states);
            
            % Detected photon budget
            INFO.MeanPhBudget=nan(1,2); % For the two channels
            INFO.MedianPhBudget=nan(1,2); % For the two channels
            
            % Detected # photons per single localisation
            INFO.MeanPhLoc=nan(1,2); % For the two channels
            INFO.MedianPhLoc=nan(1,2); % For the two channels
            
            % Detected on and off times (detected on and off times)
            INFO.MeanDetectedOnTime=nan(1,2); % For the two channels
            INFO.MedianDetectedOnTime=nan(1,2); % For the two channels
            INFO.MeanDetectedNBlink=nan(1,2); % For the two channels
            INFO.MedianDetectedNBlink=nan(1,2); % For the two channels
            
            % Detected bleaching times (detected bleaching times)
            INFO.MeanDetectedBleachTime=nan;
            INFO.MedianDetectedBleachTime=nan;

            % Detected active times (detected lifetime between appearance and
            % disappearance)
            INFO.MeanDetectedActiveTime=nan;
            INFO.MedianDetectedActiveTime=nan;

            % Detected total on times (detected total on between appearance and
            % disappearance)
            INFO.MeanDetectedTotalOnTime=nan;
            INFO.MedianDetectedTotalOnTime=nan;
            
            % PC times
            INFO.MeanPCTime=nan;
            INFO.MedianPCTime=nan;
            
            data_set_time=app.im_par.n_images*app.TotalFrameTime; % Total aquisition time [s]
            %% Go through each mol
            for i=1:app.n
                % Get the trace
                st=app.sm(i).tot_state_trace;
                t=st(1,:);
                s=st(2,:);
                
                %Bleaching time, Bleachingstate and Origin of bleaching
                if ismember(s(end), app.sm_par(app.fluo_id).bleached_states)
                    DATA(i).BLEACHED.bleach_time=t(end);
                    DATA(i).BLEACHED.bleached_state=s(end);
                    DATA(i).BLEACHED.bleached_origin=s(end-1);
                else % Molecule did not bleach
                    DATA(i).BLEACHED.bleach_time=nan;
                    DATA(i).BLEACHED.bleached_state=nan;
                    DATA(i).BLEACHED.bleached_origin=nan;
                end
                
                %Activation and Origin of activation
                if app.is_convertible==1
                    w=find(s==app.sm_par(app.fluo_id).converted_state,1);
                    if ~isempty(w) % mol was photoconverted
                        if w>1
                            DATA(i).PC.pc_origin=s(w-1);
                            DATA(i).PC.pc_time=t(w);
                        else
                            DATA(i).PC.pc_origin=s(w);
                            DATA(i).PC.pc_time=t(w);
                        end
                    else % mol was not photoconverted
                        DATA(i).PC.pc_origin=nan;
                        DATA(i).PC.pc_time=nan;
                    end
                end
                
                % Fluorescent states
                if ~isempty(associated_dark_states)
                    for j=1:app.sm_par(app.fluo_id).n_fluorescent_states
                        [DATA(i).FLUO(j).duration, DATA(i).FLUO(j).origin_state, DATA(i).FLUO(j).N, DATA(i).FLUO(j).active]=...
                            get_state_info(s,t,[fluo_states(j),associated_dark_states(j)],data_set_time);
                        DATA(i).FLUO(j).total_on=sum([DATA(i).FLUO(j).duration]);
                    end
                else
                    for j=1:app.sm_par(app.fluo_id).n_fluorescent_states
                        [DATA(i).FLUO(j).duration, DATA(i).FLUO(j).origin_state, DATA(i).FLUO(j).N, DATA(i).FLUO(j).active]=...
                            get_state_info(s,t,fluo_states(j),data_set_time);
                        DATA(i).FLUO(j).total_on=sum([DATA(i).FLUO(j).duration]);
                    end
                end
                             
                % Dark states
                for j=1:n_dark_states
                    [DATA(i).DARK(j).duration, DATA(i).DARK(j).origin_state, DATA(i).DARK(j).N]=...
                        get_state_info(s,t,dark_states(j),data_set_time);
                end

            end
            
            % Assign DATA and INFO
            app.R(app.fluo_id).DATA=DATA;
            app.R(app.fluo_id).INFO=INFO;
            
            %Get Detected On Off Active and Total On Times
            GetDetectedOnOffTimes(app)
        end
        
        function PlotSMData(app)
            
            % Get which state to plot:
            if app.external_figure.mode==false
                state_id=find(app.StateList_SM.Data{:,1}==1);
            else
                state_id=app.external_figure.state_id;
            end
            
            % Clear the 3 axes
            if app.external_figure.mode==false
                ClearAxis(app,app.PlotFluo_3);
                ClearAxis(app,app.PlotFluo_4);
                ClearAxis(app,app.PlotFluo_5);
            end
            
            % Switch the channel switch off
            app.ChannelSwitch.Visible='off';
            
            
            %Case where a photophysical state was selected
            if state_id<=numel(app.state_types)
                state_type=app.state_types{state_id};
                
                %Name of that state
                state_name=strrep(app.StateList_SM.RowName{state_id},'_','\_');
                
                % id in the sublists of the different types of states
                loc_id=find(find(ismember(app.state_types(:)', {state_type}))==state_id); % Cryptic
                
                switch state_type
                    case 'Fluorescent'
                        
                        %Show the first axis
                        ReShape(app,app.PlotFluo_3);
                        % Reshape the second axis
                        ReShape(app,app.PlotFluo_4)
                        %Hide the third axis
                        %Show the third axis
                        ReShape(app,app.PlotFluo_5)
                        
                        % Get the On-Time Data
                        MyData = arrayfun(@(s)s.FLUO(loc_id).duration,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}]; % Get all the on times
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                'Mean On time [s]','Median On time [s]','Mean On time [Frames]','Median On time [Frames]')
                            
                            app.R(app.fluo_id).INFO.MeanOnTime(loc_id)=mean(MyDataToPlot);
                            app.R(app.fluo_id).INFO.MedianOnTime(loc_id)=median(MyDataToPlot);
                        else
                            HideInfoFields12(app);
                        end
                        
                        %Get the plot title and plot
                        MyTitle=[state_name,': On Times'];
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=0; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        % Plot the Origin state
                        MyData = arrayfun(@(s)s.FLUO(loc_id).origin_state,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}]; % Get all the on times
                        
                        %Get the plot title and plot
                        MyTitle=[state_name,': Origin States'];
                        PlotOriginState(app,app.PlotFluo_3,MyDataToPlot,MyTitle);

                         % Plot the Active time histogram
                        MyData = arrayfun(@(s)s.FLUO(loc_id).active,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}];
                        
                        %Get the plot title and plot
                        if ~isempty(MyDataToPlot)
                            MyTitle=[state_name,': Active Times'];
                            
                            FitOn=app.fit.fit_data(state_id);
                            app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                            PlotLifetime(app,app.PlotFluo_5,MyDataToPlot,MyTitle,FitOn);
                        else
                            HideAxis(app,app.PlotFluo_5);
                        end
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields34(app);
                            DisplayInfo34(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                'Mean active time [s]','Median active time [s]')
                            
                            app.R(app.fluo_id).INFO.MeanActiveTime(loc_id)=mean(MyDataToPlot);
                            app.R(app.fluo_id).INFO.MedianActiveTime(loc_id)=median(MyDataToPlot);
                        else
                            HideInfoFields34(app);
                        end
                        
                    case 'Dark'
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Show the first axis
                        ReShape(app,app.PlotFluo_3);
                        
                        %Show the third axis
                        ReShape(app,app.PlotFluo_5)
                        
                        % Get the Off-Time Data
                        MyData = arrayfun(@(s)s.DARK(loc_id).duration,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}];
                        
                        %Get the plot title and plot
                        MyTitle=[state_name,': Off Times'];
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=0; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                'Mean Off time [s]','Median Off time [s]','Mean Off time [Frames]','Median Off time [Frames]')
                            
                            app.R(app.fluo_id).INFO.MeanOffTime(loc_id)=mean(MyDataToPlot);
                            app.R(app.fluo_id).INFO.MedianOffTime(loc_id)=median(MyDataToPlot);
                        else
                            HideInfoFields12(app);
                        end
                        
                        % Plot the Origin state
                        MyData = arrayfun(@(s)s.DARK(loc_id).origin_state,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}];
                        
                        %Get the plot title and plot
                        MyTitle=[state_name,': Origin States'];
                        
                        PlotOriginState(app,app.PlotFluo_3,MyDataToPlot,MyTitle);
                        
                        % Plot the N-blink histogram
                        MyData = arrayfun(@(s)s.DARK(loc_id).N,app.R(app.fluo_id).DATA,'UniformOutput',false);
                        MyDataToPlot=[MyData{:}];
                        
                        %Get the plot title and plot
                        if ~isempty(MyDataToPlot)
                            MyTitle=[state_name,': N-blink Histogram'];
                            
                            FitOn=app.fit.fit_data(state_id);
                            PlotNBlink(app,app.PlotFluo_5,MyDataToPlot,MyTitle,FitOn);
                        else
                            HideAxis(app,app.PlotFluo_5);
                        end
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields34(app);
                            DisplayInfo34(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                'Mean # of blinks','Median # of blinks')
                            
                            app.R(app.fluo_id).INFO.MeanNBlink(loc_id)=mean(MyDataToPlot);
                            app.R(app.fluo_id).INFO.MedianNBlink(loc_id)=median(MyDataToPlot);
                        else
                            HideInfoFields34(app);
                        end


                    case 'Bleached'
                        
                        % Get the Bleach-Time Data
                        MyDataTime = arrayfun(@(s)s.BLEACHED.bleach_time,app.R(app.fluo_id).DATA);
                        
                        % Get the bleaching state
                        MyDataState = arrayfun(@(s)s.BLEACHED.bleached_state,app.R(app.fluo_id).DATA);
                        
                        % Get the Origin of bleaching
                        MyDataOrig = arrayfun(@(s)s.BLEACHED.bleached_origin,app.R(app.fluo_id).DATA);
                        
                        % Select molecules that bleached to state_id
                        w=find(MyDataState==app.states_ids(state_id));
                        if ~isempty(w)
                            MyDataTime=MyDataTime(w);
                            MyDataOrig=MyDataOrig(w);
                            
                            % Reshape the second plot
                            ReShape(app,app.PlotFluo_4)

                            %Show the first axis
                            ReShape(app,app.PlotFluo_3);

                            %Show the third axis
                            ReShape(app,app.PlotFluo_5)

                            % Fill the infos
                            if ~isempty(MyDataTime)
                                ShowInfoFields12(app);
                                DisplayInfo12(app,mean(MyDataTime),median(MyDataTime),...
                                    'Mean Bleach time [s]','Median Bleach time [s]','Mean Bleach time [Frames]','Median Bleach time [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanBleachTime(loc_id)=mean(MyDataTime);
                                app.R(app.fluo_id).INFO.MedianBleachTime(loc_id)=median(MyDataTime);
                            else
                                HideInfoFields12(app);
                            end
                            
                            %Get the plot title
                            MyTitle=[state_name,': Bleach Times'];
                            
                            % Plot the bleaching time histogram
                            FitOn=app.fit.fit_data(state_id);
                            app.par.is_sparse=0; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                            app.par.duration_mode=0; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                            MyDataHist=PlotLifetime(app,app.PlotFluo_4,MyDataTime,MyTitle,FitOn);
                            
                            % Plot the cumulated bleaching
                            PlotCumulated(app,app.PlotFluo_4,MyDataHist,MyDataTime,FitOn);
                            
                            % Plot the Origin state
                            MyTitle=[state_name,': Origin States'];
                            PlotOriginState(app,app.PlotFluo_3,MyDataOrig,MyTitle);


                            % Plot the Active time histogram
                            MyData = arrayfun(@(s)s.FLUO(loc_id).total_on,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyDataToPlot=[MyData{:}];

                            %Get the plot title and plot
                            if ~isempty(MyDataToPlot)
                                MyTitle=[state_name,': Total On Times'];

                                FitOn=app.fit.fit_data(state_id);
                                app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                                PlotLifetime(app,app.PlotFluo_5,MyDataToPlot,MyTitle,FitOn);
                            else
                                HideAxis(app,app.PlotFluo_5);
                            end

                            % Fill the infos
                            if ~isempty(MyDataToPlot)
                                ShowInfoFields34(app);
                                DisplayInfo34(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean total on time [s]','Median total on time [s]')

                                app.R(app.fluo_id).INFO.MeanActiveTime(loc_id)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianActiveTime(loc_id)=median(MyDataToPlot);
                            else
                                HideInfoFields34(app);
                            end
                            
                        else
                            if app.dialog_on==1
                                MyDialog=warndlg('No molecule bleached to that state!','Warning');
                                waitfor(MyDialog)
                            end
                        end
                        
%                         %Hide the third axis
%                         HideAxis(app,app.PlotFluo_5)
                        
                    otherwise
                end
                
            else % Case where other parameters to be plotted
                
                switch app.StateList_SM.RowName{state_id}
                    case 'Photon Budget'
                        
                        if app.im_par.two_channel==1 % Show the channel selection switch if necessary
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            Photon_Budget=[app.sm.tot_phot_det_ch1];
                            MyTitle='Detected Photon Budget (Ch 1)';
                        else
                            Photon_Budget=[app.sm.tot_phot_det_ch2];
                            MyTitle='Detected Photon Budget (Ch 2)';
                        end
                        Photon_Budget=Photon_Budget(~isnan(Photon_Budget)); % Just in case
                        Photon_Budget=Photon_Budget(Photon_Budget>0); % Just in case
                        
                        FitOn=app.fit.fit_data(state_id);
                        PlotNPhot(app,app.PlotFluo_4,Photon_Budget,MyTitle, FitOn);
                        
                        % Fill the infos
                        HideInfoFields12(app);
                        if ~isempty(Photon_Budget)
                            ShowInfoFields34(app);
                            
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo34(app,mean(Photon_Budget),median(Photon_Budget),...
                                    'Mean Photon Budget (Ch1)','Median Photon Budget (Ch1)');
                                
                                app.R(app.fluo_id).INFO.MeanPhBudget(1)=mean(Photon_Budget);
                                app.R(app.fluo_id).INFO.MedianPhBudget(1)=median(Photon_Budget);
                            else
                                DisplayInfo34(app,mean(Photon_Budget),median(Photon_Budget),...
                                    'Mean Photon Budget (Ch2)','Median Photon Budget (Ch2)');
                                app.R(app.fluo_id).INFO.MeanPhBudget(2)=mean(Photon_Budget);
                                app.R(app.fluo_id).INFO.MedianPhBudget(2)=median(Photon_Budget);
                            end
                        else
                            HideInfoFields34(app);
                        end
                        
                    case 'Photons per Loc'
                        
                        % Show the channel selection switch if necessary
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            PhotonPerLoc=[app.sm.n_phot_det_ch1];
                            MyTitle='Det Photons per Localization (Ch 1)';
                        else
                            PhotonPerLoc=[app.sm.n_phot_det_ch2];
                            MyTitle='Det Photons per Localization  (Ch 2)';
                        end
                        PhotonPerLoc=PhotonPerLoc(~isnan(PhotonPerLoc)); % Just in case
                        PhotonPerLoc=PhotonPerLoc(PhotonPerLoc>0); % Just in case
                        
                        FitOn=app.fit.fit_data(state_id);
                        PlotNPhot(app,app.PlotFluo_4,PhotonPerLoc,MyTitle,FitOn);
                        
                        % Fill the infos
                        HideInfoFields12(app);
                        if ~isempty(PhotonPerLoc)
                            ShowInfoFields34(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo34(app,mean(PhotonPerLoc),median(PhotonPerLoc),...
                                    'Mean Ph per Loc (Ch1)','Median Ph per Loc (Ch1)');
                                
                                app.R(app.fluo_id).INFO.MeanPhLoc(1)=mean(PhotonPerLoc);
                                app.R(app.fluo_id).INFO.MedianPhLoc(1)=median(PhotonPerLoc);
                            else
                                DisplayInfo34(app,mean(PhotonPerLoc),median(PhotonPerLoc),...
                                    'Mean Ph per Loc (Ch2)','Median Ph per Loc (Ch2)');
                                
                                app.R(app.fluo_id).INFO.MeanPhLoc(2)=mean(PhotonPerLoc);
                                app.R(app.fluo_id).INFO.MedianPhLoc(2)=median(PhotonPerLoc);
                            end
                        else
                            HideInfoFields34(app);
                        end
                        
                    case 'Detected On Times'
                        
                        % Show the channel selection switch if necessary
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        % Get the On-Time Data
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            MyData = arrayfun(@(s)s.EXP(1).on,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected On Times (Ch1)';
                        else
                            MyData = arrayfun(@(s)s.EXP(2).on,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected On Times (Ch2)';
                        end
                        MyDataToPlot=[MyData{:}]*app.TotalFrameTime; % in [s]
                        
                        %Get the plot title and plot
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=1; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        % Fill the infos
                        HideInfoFields34(app);
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected On time (Ch 1) [s]','Median Detected On time (Ch 1) [s]','Mean Detected On time (Ch 1) [Frames]','Median Detected On time (Ch 1) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedOnTime(1)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedOnTime(1)=median(MyDataToPlot);
                            else
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected On time (Ch 2) [s]','Median Detected On time (Ch 2) [s]','Mean Detected On time (Ch 2) [Frames]','Median Detected On time (Ch 2) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedOnTime(2)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedOnTime(2)=median(MyDataToPlot);
                            end
                        else
                            HideInfoFields12(app);
                        end
                        
                        
                    case 'Detected Off Times'
                        % Show the channel selection switch if necessary
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second axis
                        ReShape(app,app.PlotFluo_4)
                        
                        % Reshape the third axis
                        ReShape(app,app.PlotFluo_5)
                        
                        % Get the Off-Time Data
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            MyData = arrayfun(@(s)s.EXP(1).off,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Off Times (Ch1)';
                        else
                            MyData = arrayfun(@(s)s.EXP(2).off,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Off Times (Ch2)';
                        end
                        MyDataToPlot=[MyData{:}]*app.TotalFrameTime; % in [s]
                        
                        %Get the plot title and plot
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=1; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Off time (Ch 1) [s]','Median Detected Off time (Ch 1) [s]','Mean Detected Off time (Ch 1) [Frames]','Median Detected Off time (Ch 1) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedOffTime(1)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedOffTime(1)=median(MyDataToPlot);
                            else
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Off time (Ch 2) [s]','Median Detected Off time (Ch 2) [s]','Mean Detected Off time (Ch 2) [Frames]','Median Detected Off time (Ch 2) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedOffTime(2)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedOffTime(2)=median(MyDataToPlot);
                            end
                        else
                            HideInfoFields12(app);
                        end
                        
                        % Get the N-blink Data
                        
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            MyData = arrayfun(@(s)s.EXP(1).N,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected N-blink Histogram (Ch1)';
                        else
                            MyData = arrayfun(@(s)s.EXP(2).N,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected N-blink Histogram (Ch2)';
                        end
                        MyDataToPlot=[MyData{:}];
                        
                        % Plot the N-blink histogram
                        if ~isempty(MyDataToPlot)
                            FitOn=app.fit.fit_data(state_id);
                            PlotNBlink(app,app.PlotFluo_5,MyDataToPlot,MyTitle,FitOn);
                        else
                            HideAxis(app,app.PlotFluo_5);
                        end
                        
                        % Fill the infos
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields34(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo34(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean # of blinks (Ch 1)','Median # of blinks (Ch 1)');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedNBlink(1)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedNBlink(1)=median(MyDataToPlot);
                            else
                                DisplayInfo34(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean # of blinks (Ch 2)','Median # of blinks (Ch 2)');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedNBlink(2)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedNBlink(2)=median(MyDataToPlot);
                            end
                        else
                            HideInfoFields34(app);
                        end

                    case 'Detected Active Times'
                        
                        % Show the channel selection switch if necessary
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        % Get the Active-Time Data
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            MyData = arrayfun(@(s)s.EXP(1).active,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Active Times (Ch1)';
                        else
                            MyData = arrayfun(@(s)s.EXP(2).active,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Active Times (Ch2)';
                        end
                        MyDataToPlot=[MyData{:}]*app.TotalFrameTime; % in [s]
                        
                        %Get the plot title and plot
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=1; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        % Fill the infos
                        HideInfoFields34(app);
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Active time (Ch 1) [s]','Median Detected Active time (Ch 1) [s]','Mean Detected Active time (Ch 1) [Frames]','Median Detected Active time (Ch 1) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedActiveTime(1)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedActiveTime(1)=median(MyDataToPlot);
                            else
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Active time (Ch 2) [s]','Median Detected Active time (Ch 2) [s]','Mean Detected Active time (Ch 2) [Frames]','Median Detected Active time (Ch 2) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedActiveTime(2)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedActiveTime(2)=median(MyDataToPlot);
                            end
                        else
                            HideInfoFields12(app);
                        end
                        
                    case 'Detected Total On Times'
                        
                        % Show the channel selection switch if necessary
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        end
                        
                        %Hide the first axis
                        HideAxis(app,app.PlotFluo_3)
                        
                        % Reshape the second plot
                        ReShape(app,app.PlotFluo_4)
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        % Get the Active-Time Data
                        if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                            MyData = arrayfun(@(s)s.EXP(1).total_on,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Total On Times (Ch1)';
                        else
                            MyData = arrayfun(@(s)s.EXP(2).total_on,app.R(app.fluo_id).DATA,'UniformOutput',false);
                            MyTitle='Detected Total On  Times (Ch2)';
                        end
                        MyDataToPlot=[MyData{:}]*app.TotalFrameTime; % in [s]
                        
                        %Get the plot title and plot
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=1; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=1; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        PlotLifetime(app,app.PlotFluo_4,MyDataToPlot,MyTitle,FitOn);
                        
                        % Fill the infos
                        HideInfoFields34(app);
                        if ~isempty(MyDataToPlot)
                            ShowInfoFields12(app);
                            
                            if strcmp(app.ChannelSwitch.Value,'Ch1')==1
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Total On time (Ch 1) [s]','Median Detected Total On time (Ch 1) [s]','Mean Detected Total On time (Ch 1) [Frames]','Median Detected Total On time (Ch 1) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedTotalOnTime(1)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedTotalOnTime(1)=median(MyDataToPlot);
                            else
                                DisplayInfo12(app,mean(MyDataToPlot),median(MyDataToPlot),...
                                    'Mean Detected Total On time (Ch 2) [s]','Median Detected Total On time (Ch 2) [s]','Mean Detected Total On time (Ch 2) [Frames]','Median Detected Total On time (Ch 2) [Frames]');
                                
                                app.R(app.fluo_id).INFO.MeanDetectedTotalOnTime(2)=mean(MyDataToPlot);
                                app.R(app.fluo_id).INFO.MedianDetectedTotalOnTime(2)=median(MyDataToPlot);
                            end
                        else
                            HideInfoFields12(app);
                        end
                        
                        
                    case 'Detected Bleach Times'
                        
                        % Get the Bleach-Time Data
                        MyDataTime = arrayfun(@(s)s.BLEACHED.bleach_time,app.R(app.fluo_id).DATA);
                        MyDataTime=MyDataTime(~isnan(MyDataTime));
                        
                        % Get the Origin of bleaching
                        MyDataOrig = arrayfun(@(s)s.BLEACHED.bleached_origin,app.R(app.fluo_id).DATA);
                        MyDataOrig=MyDataOrig(~isnan(MyDataOrig));
                        
                        %Show the first axis
                        ReShape(app,app.PlotFluo_3);
                        
                        %Show the second axis
                        ReShape(app,app.PlotFluo_4);
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        %Get the plot title
                        MyTitle='Detected Bleach Times';
                        
                        % Plot the bleaching time histogram
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=1; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=0; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        MyDataHist=PlotLifetime(app,app.PlotFluo_4,MyDataTime,MyTitle,FitOn);
                        
                        % Plot the cumulated bleaching
                        PlotCumulated(app,app.PlotFluo_4,MyDataHist,MyDataTime,FitOn);
                        
                        % Plot the Origin state
                        MyTitle='Detected Bleaching Origin States';
                        PlotOriginState(app,app.PlotFluo_3,MyDataOrig,MyTitle);
                        
                        % Fill the infos
                        HideInfoFields34(app);
                        if ~isempty(MyDataTime)
                            ShowInfoFields12(app);
                            DisplayInfo12(app,mean(MyDataTime),median(MyDataTime),...
                                'Mean Detected Bleach time [s]','Median Detected Bleach time [s]','Mean Detected Bleach time [Frames]','Median Detected Bleach time [Frames]');
                            
                            app.R(app.fluo_id).INFO.MeanDetectedBleachTime=mean(MyDataTime);
                            app.R(app.fluo_id).INFO.MedianDetectedBleachTime=median(MyDataTime);
                        else
                            HideInfoFields12(app);
                        end
                        
                        
                    case 'Photoconversion'
                        
                        % Get the PC-Time Data
                        MyDataTime = arrayfun(@(s)s.PC.pc_time,app.R(app.fluo_id).DATA);
                        MyDataTime=MyDataTime(~isnan(MyDataTime));
                        
                        % Get the Origin of PC
                        MyDataOrig = arrayfun(@(s)s.PC.pc_origin,app.R(app.fluo_id).DATA);
                        MyDataOrig=MyDataOrig(~isnan(MyDataOrig));
                        
                        %Show the first axis
                        ReShape(app,app.PlotFluo_3);
                        
                        %Show the second axis
                        ReShape(app,app.PlotFluo_4);
                        
                        %Hide the third axis
                        HideAxis(app,app.PlotFluo_5)
                        
                        %Get the plot title
                        MyTitle='Photoconversion Times';
                        
                        % Plot the photoconversion time histogram
                        FitOn=app.fit.fit_data(state_id);
                        app.par.is_sparse=0; % Set this to change the histogram calculation mode: bins have to be carefully chosen for sparse data (here integer # of frames)
                        app.par.duration_mode=0; % Set this to change the histogram X label from "Duration" to "Time" or "Frames" to "Frame #"
                        MyDataHist=PlotLifetime(app,app.PlotFluo_4,MyDataTime,MyTitle,FitOn);
                        
                        % Plot the cumulated photoconversion
                        PlotCumulated(app,app.PlotFluo_4,MyDataHist,MyDataTime,FitOn);
                        
                        % Plot the Origin state
                        MyTitle='Photoconversion Origin States';
                        PlotOriginState(app,app.PlotFluo_3,MyDataOrig,MyTitle);
                        
                        % Fill the infos
                        HideInfoFields34(app);
                        if ~isempty(MyDataTime)
                            ShowInfoFields12(app);
                            DisplayInfo12(app,mean(MyDataTime),median(MyDataTime),...
                                'Mean Photoconversion time [s]','Median Photoconversion time [s]','Mean Photoconversion time [Frames]','Median Photoconversion time [Frames]');
                            
                            app.R(app.fluo_id).INFO.MeanPCTime=mean(MyDataTime);
                            app.R(app.fluo_id).INFO.MedianPCTime=median(MyDataTime);
                        else
                            HideInfoFields12(app);
                        end
                        
                end
            end
            
            figure(app.UIFigure); % Get the GUI in the front
        end
        
        function SetUpSMTable(app)
            
            %We need to consider states in rapid equilibrium as unique states
            app.states_ids=unique(app.sm_par(app.fluo_id).state_ids);
            app.states_names=app.sm_par(app.fluo_id).state_names(app.states_ids);
            app.n_states=numel(app.states_ids);
            
            app.StateList_SM.Visible=1;
            
            app.StateList_SM.Position=[30,370,700,min(190,30+22*(app.n_states+5))]; % Adjust position
            
            % Show the names of the fluorescent states
            app.StateList_SM.RowName=[app.states_names;{'Photon Budget'};{'Photons per Loc'};{'Detected On Times'};{'Detected Off Times'};{'Detected Active Times'};{'Detected Total On Times'}];
            n_add=6;
            if numel(app.sm_par(app.fluo_id).bleached_states)>1
                app.StateList_SM.RowName=[app.StateList_SM.RowName;{'Detected Bleach Times'}];
                n_add=n_add+1;
            end
            if app.is_convertible==1
                app.StateList_SM.RowName=[app.StateList_SM.RowName ; {'Photoconversion'}];
                n_add=n_add+1;
            end
            
            
            % Initialize the plotting data
            StatesShow=false(app.n_states+n_add,1); StatesShow(1)=true; % Select the first state
            StatesPlotFinal=false(app.n_states+n_add,1);
            StatesFit=false(app.n_states+n_add,1);
            StatesMonoExp=true(app.n_states+n_add,1);
            StatesBiExp=false(app.n_states+n_add,1);
            StatesStretchedExp=false(app.n_states+n_add,1);
            RemoveFirstPoint=false(app.n_states+n_add,1);
            BlinkingGap=nan(app.n_states+n_add,1);
            BlinkingGap(app.n_states+3:app.n_states+4)=0;
            FromFrame=nan(app.n_states+n_add,1);
            FromFrame(app.n_states+3:app.n_states+4)=1;
            
            app.TableData = table(StatesShow,StatesPlotFinal,StatesFit,StatesMonoExp,...
                StatesBiExp,StatesStretchedExp,RemoveFirstPoint,BlinkingGap,FromFrame,'VariableNames',app.StateList_SM.ColumnName);
            app.StateList_SM.Data=app.TableData;
            
            app.fit.fit_data=false(app.n_states+n_add,1); % Do not fit at the beginning.
            
        end
        
        function ClearAxis(~,MyAxis)
            cla(MyAxis);
            MyData = findobj(MyAxis, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
        
        function h=PlotLifetime(app,MyAxis,MyDataToPlot,MyTitle,FitOn)
            
            if isempty(MyDataToPlot)
                MyDialog=warndlg('There is no data to plot in this channel ! ','Warning');
                waitfor(MyDialog)
                h=[];
                HideAxis(app,MyAxis);
                return
            end
            
            if app.external_figure.mode==true
                if app.external_figure.composite==true
                    figure(app.MyFigure)
                    subplot(app.external_figure.n_cols,app.external_figure.n_rows,app.external_figure.pos);
                    MyAxis=gca;
                    app.external_figure.pos=app.external_figure.pos+1; % Prepare for next plot
                else
                    app.MyFigure=figure;
                    set(app.MyFigure,'color','w');
                    MyAxis=gca;
                end
            end
            
            % Change to frames units if required
            if strcmp(app.XaxisSwitch_2.Value,"Frames")==1
                MyDataToPlot=MyDataToPlot/app.TotalFrameTime;
            end

            if app.par.is_sparse==0 % General case where data contain many different values and histograms do not depend much on exact bin edges

                WindowMax=max(MyDataToPlot);
                WindowMin=min(MyDataToPlot);
                if isempty(WindowMax)
                    WindowMin=0; % Arbitrary number
                    WindowMax=1; % Arbitrary number
                end
                if WindowMax==WindowMin
                    WindowMin=WindowMin*0.95;
                    WindowMax=WindowMax*1.05;
                end

                n_bins=app.HistogramBinsEditField.Value;
                force_n_bins=app.ForceNBinsCheckBox.Value;

                MyStep=(WindowMax-WindowMin)/n_bins;
                MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram

                MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
                MyEdges(end)=MyEdges(end)+MyStep*1e-6;

                h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');

                if force_n_bins==false
                    while nnz(h.Values) <= 0.7*h.NumBins
                        n_bins=fix(n_bins*0.8);
                        MyStep=(WindowMax-WindowMin)/n_bins;
                        MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram
                        MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
                        MyEdges(end)=MyEdges(end)+MyStep*1e-6;

                        h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');
                    end

                    if app.dialog_on==1
                        if n_bins<app.HistogramBinsEditField.Value
                            %MyDialog=warndlg(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]  ,'Warning');
                            %waitfor(MyDialog)
                            disp(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]);
                        end
                    end
                end

            else % Case where data do not contain many different values and histograms depend on exact bin edges: the bins should be centered on data values

                WindowMax=max(MyDataToPlot);
                WindowMin=min(MyDataToPlot);
                if isempty(WindowMax)
                    WindowMin=0; % Arbitrary number
                    WindowMax=1; % Arbitrary number
                end
                if WindowMax==WindowMin
                    WindowMin=WindowMin*0.95;
                    WindowMax=WindowMax*1.05;
                end

                %Input bin values
                n_bins=app.HistogramBinsEditField.Value;
                force_n_bins=app.ForceNBinsCheckBox.Value;

                % However the bins should have a size which is a multiple of the
                % frametime
                if strcmp(app.XaxisSwitch_2.Value,"Frames")==1
                    base_n_bins=WindowMax-WindowMin;    
                else
                    base_n_bins=(WindowMax-WindowMin)/app.TotalFrameTime;
                end    

                %Ratio between the 2 and get the # of bins we are going to
                %use
                r_n_bins=base_n_bins/n_bins;
                if r_n_bins>=1
                    used_n_bins=round(base_n_bins/round(r_n_bins));
                else
                    used_n_bins=round(base_n_bins*round(1/r_n_bins));
                end
                disp(['Used # of bins for sparse data: ',num2str(used_n_bins)])

                MyStep=(WindowMax-WindowMin)/used_n_bins;
                MyBaseStep=(WindowMax-WindowMin)/base_n_bins;
                MyEdges=WindowMin+(0:used_n_bins)*MyStep - 0.5*MyBaseStep; % Produce x_values for histogram

                h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');

                if force_n_bins==false % Here we allow a reduction of the input # of bins
                    while nnz(h.Values) <= 0.7*h.NumBins
                        n_bins=fix(n_bins*0.8);

                        %Ratio between the 2 and get the # of bins we are going to
                        %use
                        r_n_bins=base_n_bins/n_bins;
                        if r_n_bins>=1
                            used_n_bins=round(base_n_bins/round(r_n_bins));
                        else
                            used_n_bins=round(base_n_bins*round(1/r_n_bins));
                        end

                        MyStep=(WindowMax-WindowMin)/used_n_bins;
                        MyBaseStep=(WindowMax-WindowMin)/base_n_bins;
                        MyEdges=WindowMin+(0:used_n_bins)*MyStep - 0.5*MyBaseStep; % Produce x_values for histogram


                        h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');
                    end

                    if app.dialog_on==1
                        if n_bins<app.HistogramBinsEditField.Value
                            %MyDialog=warndlg(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]  ,'Warning');
                            %waitfor(MyDialog)
                            disp(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]);
                        end
                    end
                end
            end

            h.LineWidth=app.LineWidthSlider_2.Value;
            h.FaceColor='Cyan';
            h.EdgeColor='Blue';

            if app.par.duration_mode==1
                if strcmp(app.XaxisSwitch_2.Value,"Frames")==1
                    xlabel(MyAxis,'Frames')
                else
                    xlabel(MyAxis,'Duration [s]')
                end
            else
                if strcmp(app.XaxisSwitch_2.Value,"Frames")==1
                    xlabel(MyAxis,'Frame #')
                else
                    xlabel(MyAxis,'Time [s]')
                end
            end
            ylabel(MyAxis,'PDF')
            
            if WindowMax>0
                xlim(MyAxis,[MyEdges(1),MyEdges(1)+(MyEdges(end)-MyEdges(1))*app.XLimitSlider_2.Value/100]);
            end
            
            if strcmp(app.XLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'XScale', 'log')
            else
                set(MyAxis, 'XScale', 'linear')
            end
            
            if strcmp(app.YLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'YScale', 'log')
            else
                set(MyAxis, 'YScale', 'linear')
            end
            
            ylim(MyAxis,[0 max(h.Values)*1.1*app.YLimitSlider_2.Value/100]);
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider_2.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider_2.Value);
            
            MyAxis.Title.String=MyTitle;
            
            %Fit the data if requested
            if FitOn==true
                FitSMData(app,h,MyAxis);
            else % Otherwise hide the fitted values fields
                app.Fitparameter1EditField.Visible='off';
                app.Fitparameter1EditFieldLabel.Visible='off';
                app.Fitparameter1EditField_2.Visible='off';
                app.Fitparameter1EditField_2Label.Visible='off';
                app.Fitparameter3EditField.Visible='off';
                app.Fitparameter3EditFieldLabel.Visible='off';
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
            end
            
            %Handle the legend
            if strcmp(app.ShowLegendSwitch_2.Value,"On")
                if FitOn==true && app.fit_ok>0
                    legend(MyAxis,'Data', ['Fit (', app.fit.fit_type,')']);
                else
                    legend(MyAxis,'Data');
                end
            else
                legend(MyAxis,'off');
            end
        end
        
        function PlotCumulated(app,MyAxis,MyHistToPlot,MyData,FitOn)
            
            MyCumSum=[0, cumsum(MyHistToPlot.Values)*numel(MyData)/app.n];
            
            if app.external_figure.mode==true
                if app.external_figure.composite==true
                    figure(app.MyFigure)
                    subplot(app.external_figure.n_cols,app.external_figure.n_rows,app.external_figure.pos-1);
                    MyAxis=gca;
                else
                    figure(app.MyFigure);
                    MyAxis=gca;
                end
            end
            
            % Get the left axis in blue color
            yyaxis(MyAxis, "left")
            MyAxis.YAxis(1).Color='blue';
            
            % Plot the cumulated bleaching
            yyaxis(MyAxis,"right"); % Go to the right axis
            MyAxis.YAxis(2).Visible='on'; % Show it
            
            hold(MyAxis,'on')
            
            plot(MyAxis,MyHistToPlot.BinEdges,MyCumSum,'red','LineWidth',app.LineWidthSlider_2.Value,'LineStyle',"-")
            ylabel(MyAxis,'Cumulated fraction')
            ylim(MyAxis,[0 1*app.YLimitSlider_2.Value/100]);


            %Handle the legend
            if strcmp(app.ShowLegendSwitch_2.Value,"On")
                if FitOn==true && app.fit_ok>0
                    legend(MyAxis,'Data', ['Fit (', app.fit.fit_type,')'],'Cumulated');
                else
                    legend(MyAxis,'Data','Cumulated');
                end
            else
                legend(MyAxis,'off');
            end
            
            hold(MyAxis,'off')
        end
        
        
        function PlotOriginState(app,MyAxis,MyDataToPlot,MyTitle)
            
            if any(isnan(MyDataToPlot))
                originally_on=1;
                MyDataToPlot(isnan(MyDataToPlot))=0; % Set those molecules initially in the state to an origin of 0
                nbins=app.sm_par(app.fluo_id).n_states+1;
                MyEdges=-0.5+0:nbins;
            else
                originally_on=0; % There is no molecule initially in that state
                nbins=app.sm_par(app.fluo_id).n_states;
                MyEdges=0.5+0:nbins;
            end
            
            if app.external_figure.mode==true
                if app.external_figure.composite==true
                    figure(app.MyFigure)
                    subplot(app.external_figure.n_cols,app.external_figure.n_rows,app.external_figure.pos);
                    MyAxis=gca;
                    app.external_figure.pos=app.external_figure.pos+1; % Prepare for next plot
                else
                    app.MyFigure=figure;
                    set(app.MyFigure,'color','w');
                    MyAxis=gca;
                end
            end
            
            h=histogram(MyAxis,MyDataToPlot,nbins,'BinEdges',MyEdges,'Normalization','probability','FaceColor',[0.5,0.5,0.5]);
            h.LineWidth=app.LineWidthSlider_2.Value;
            h.FaceColor='Green';
            h.EdgeColor=[0 0.8 0];
            
            xlabel(MyAxis,'') % No need for a label here
            ylabel(MyAxis,'Fraction')
            
            xlim(MyAxis,[min(MyEdges), max(MyEdges)]);
            ylim(MyAxis,[0 app.YLimitSlider_2.Value/100]);
            
            set(MyAxis,'XTickLabelRotation',30);
            if originally_on==1
                set(MyAxis, 'XTick',0:app.sm_par(app.fluo_id).n_states,'XTickLabel',[{'Originally On'};app.sm_par(app.fluo_id).state_names]);
            else
                set(MyAxis, 'XTick',1:app.sm_par(app.fluo_id).n_states,'XTickLabel',app.sm_par(app.fluo_id).state_names);
            end
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider_2.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider_2.Value);
            
            MyAxis.Title.String=MyTitle;
            
        end
        
        function PlotNBlink(app,MyAxis,MyDataToPlot, MyTitle, FitOn)
            
            if app.external_figure.mode==true
                if app.external_figure.composite==true
                    figure(app.MyFigure)
                    subplot(app.external_figure.n_cols,app.external_figure.n_rows,app.external_figure.pos);
                    MyAxis=gca;
                    app.external_figure.pos=app.external_figure.pos+1; % Prepare for next plot
                else
                    app.MyFigure=figure;
                    set(app.MyFigure,'color','w');
                    MyAxis=gca;
                end
            end
            
            
            t_window=max(MyDataToPlot);
            if isempty(t_window)
                t_window=1; % Arbitrary number
            end
            
            MyEdges=-0.5:round(1.2*max(MyDataToPlot)+0.5);
            
            h=histogram(MyAxis,MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');
            
            h.LineWidth=app.LineWidthSlider_2.Value;
            h.FaceColor='Red';
            h.EdgeColor=[0.8 0 0];


            if t_window>0
                xlim(MyAxis,[-0.5,t_window*app.XLimitSlider_2.Value/100]);
            end
            
            xlabel(MyAxis,'# of Blinks')
            ylabel(MyAxis,'PDF')
            
            if strcmp(app.XLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'XScale', 'log')
            else
                set(MyAxis, 'XScale', 'linear')
            end
            
            if strcmp(app.YLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'YScale', 'log')
            else
                set(MyAxis, 'YScale', 'linear')
            end
            
            ylim(MyAxis,[0 max(h.Values)*1.1*app.YLimitSlider_2.Value/100]);
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider_2.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider_2.Value);
            
            MyAxis.Title.String=MyTitle;
            
            %Fit the data if requested
            if FitOn==true
                FitNBlinkData(app,h,MyAxis);
            else % Otherwise hide the fitted values fields
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
            end
            
            %Handle the legend
            if strcmp(app.ShowLegendSwitch_2.Value,"On")
                if FitOn==true && app.fit_ok>0
                    legend(MyAxis,'Data', 'Fit');
                else
                    legend(MyAxis,'Data');
                end
            else
                legend(MyAxis,'off');
            end
        end
        
        function PlotNPhot(app,MyAxis,MyDataToPlot, MyTitle, FitOn)

            if isempty(MyDataToPlot)
                MyDialog=warndlg('There is no data to plot in this channel ! ','Warning');
                waitfor(MyDialog)
                HideAxis(app,MyAxis);
                return
            end

            
            if app.external_figure.mode==true
                if app.external_figure.composite==true
                    figure(app.MyFigure)
                    subplot(app.external_figure.n_cols,app.external_figure.n_rows,app.external_figure.pos);
                    MyAxis=gca;
                    app.external_figure.pos=app.external_figure.pos+1; % Prepare for next plot
                else
                    app.MyFigure=figure;
                    set(app.MyFigure,'color','w');
                    MyAxis=gca;
                end
            end
            
            WindowMax=max(MyDataToPlot);
            WindowMin=min(MyDataToPlot);
            if isempty(WindowMax)
                WindowMin=0; % Arbitrary number
                WindowMax=1; % Arbitrary number
            end
            if WindowMax==WindowMin
                WindowMin=WindowMin*0.95;
                WindowMax=WindowMax*1.05;
            end
            
            n_bins=app.HistogramBinsEditField.Value;
            
            MyStep=(WindowMax-WindowMin)/n_bins;
            MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram
            MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
            MyEdges(end)=MyEdges(end)+MyStep*1e-6;
            
            h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');
            
            while nnz(h.Values) <= 0.7*h.NumBins
                n_bins=fix(n_bins*0.8);
                MyStep=(WindowMax-WindowMin)/n_bins;
                MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram
                MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
                MyEdges(end)=MyEdges(end)+MyStep*1e-6;
                
                h=histogram(MyAxis, MyDataToPlot,'BinEdges',MyEdges,'Normalization','probability');
            end
            
            h.LineWidth=app.LineWidthSlider_2.Value;
            h.FaceColor='Cyan';
            h.EdgeColor='Blue';
            
            xlabel(MyAxis,'Number of photons')
            
            if WindowMax>0
                xlim(MyAxis,[WindowMin,WindowMin+(WindowMax-WindowMin)*app.XLimitSlider_2.Value/100]);
            end
            
            ylabel(MyAxis,'Fraction of molecules')
            
            if strcmp(app.XLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'XScale', 'log')
            else
                set(MyAxis, 'XScale', 'linear')
            end
            
            if strcmp(app.YLogScaleSwitch_2.Value,'On')==1
                set(MyAxis, 'YScale', 'log')
            else
                set(MyAxis, 'YScale', 'linear')
            end
            
            ylim(MyAxis,[0 max(h.Values)*1.1*app.YLimitSlider_2.Value/100]);
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider_2.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider_2.Value);
            
            MyAxis.Title.String=MyTitle;
            
            %Fit the data if requested
            if FitOn==true
                FitSMData(app,h,MyAxis);
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
            else % Otherwise hide the fitted values fields
                app.Fitparameter1EditField.Visible='off';
                app.Fitparameter1EditFieldLabel.Visible='off';
                app.Fitparameter1EditField_2.Visible='off';
                app.Fitparameter1EditField_2Label.Visible='off';
                app.Fitparameter3EditField.Visible='off';
                app.Fitparameter3EditFieldLabel.Visible='off';
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
            end
            
            %Handle the legend
            if strcmp(app.ShowLegendSwitch_2.Value,"On")
                if FitOn==true && app.fit_ok>0
                    legend(MyAxis,'Data', ['Fit (', app.fit.fit_type,')']);
                else
                    legend(MyAxis,'Data');
                end
            else
                legend(MyAxis,'off');
            end
        end
        
        

        function FitSMData(app,HistogramData,MyAxis)
            
            fit_data=1; % Do fit the data; can be set to zero to check initial values
            [app.fitted_values,app.fit_ok, Xfit, Yfit]=Fit_MyData(HistogramData, app.fit.fit_type, app.fit.remove_first_point, fit_data);
            
            % Plot the data if fit is ok
            if app.fit_ok>0
                if app.fit.remove_first_point==0
                    line(MyAxis,Xfit,  Yfit,'Color','Blue','LineWidth',app.LineWidthSlider_2.Value);
                else
                    line(MyAxis,Xfit,  Yfit,'Color','Green','LineWidth',app.LineWidthSlider_2.Value);
                end
            end
            
            
            if app.fit_ok>0
                switch app.fit.fit_type
                    
                    case 'monophasic'
                        app.Fitparameter1EditField.Visible='on';
                        app.Fitparameter1EditFieldLabel.Visible='on';
                        if strcmp(app.fit.fit_what,'Time')==1
                            if strcmp(app.XaxisSwitch_2.Value,"Time")==1
                                app.Fitparameter1EditFieldLabel.Text='Rate [s-1]: ';
                            else
                                app.Fitparameter1EditFieldLabel.Text='Rate [Frames-1]: ';
                            end
                            app.Fitparameter1EditField.Value=app.fitted_values.fitted_rate;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.4g';
                        else % Case of fitting N photons histograms
                            app.Fitparameter1EditFieldLabel.Text='Budget [Ph]: ';
                            app.Fitparameter1EditField.Value=1/app.fitted_values.fitted_rate;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.2g';
                        end
                        
                        app.Fitparameter1EditField_2.Visible='off';
                        app.Fitparameter1EditField_2Label.Visible='off';
                        app.Fitparameter3EditField.Visible='off';
                        app.Fitparameter3EditFieldLabel.Visible='off';
                        
                    case 'biphasic'
                        app.Fitparameter1EditField.Visible='on';
                        app.Fitparameter1EditFieldLabel.Visible='on';
                        app.Fitparameter1EditField_2.Visible='on';
                        app.Fitparameter1EditField_2Label.Visible='on';
                        if strcmp(app.fit.fit_what,'Time')==1
                            if strcmp(app.XaxisSwitch_2.Value,"Time")==1
                                app.Fitparameter1EditFieldLabel.Text='Rate 1 [s-1]: ';
                            else
                                app.Fitparameter1EditFieldLabel.Text='Rate 1 [Frames-1]: ';
                            end
                            if strcmp(app.XaxisSwitch_2.Value,"Time")==1
                                app.Fitparameter1EditField_2Label.Text='Rate 2 [s-1]: ';
                            else
                                app.Fitparameter1EditField_2Label.Text='Rate 2 [Frames-1]: ';
                            end
                            app.Fitparameter1EditField.Value=app.fitted_values.fitted_rate_1;
                            app.Fitparameter1EditField_2.Value=app.fitted_values.fitted_rate_2;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.4g';
                            app.Fitparameter1EditField_2.ValueDisplayFormat='%11.4g';
                        else % Case of fitting N photons histograms
                            app.Fitparameter1EditFieldLabel.Text='Budget 1 [Ph]: ';
                            app.Fitparameter1EditField_2Label.Text='Budget 2 [Ph]: ';
                            app.Fitparameter1EditField.Value=1/app.fitted_values.fitted_rate_1;
                            app.Fitparameter1EditField_2.Value=1/app.fitted_values.fitted_rate_2;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.2g';
                            app.Fitparameter1EditField_2.ValueDisplayFormat='%11.2g';
                        end
                        
                        app.Fitparameter3EditField.Visible='on';
                        app.Fitparameter3EditFieldLabel.Visible='on';
                        app.Fitparameter3EditFieldLabel.Text='Fraction: ';
                        app.Fitparameter3EditField.Value=app.fitted_values.fitted_a0_1/app.fitted_values.fitted_a0_2;
                        
                    case 'stretched'
                        app.Fitparameter1EditField.Visible='on';
                        app.Fitparameter1EditFieldLabel.Visible='on';
                        app.Fitparameter1EditField_2.Visible='on';
                        app.Fitparameter1EditField_2Label.Visible='on';
                        if strcmp(app.fit.fit_what,'Time')==1
                            if strcmp(app.XaxisSwitch_2.Value,"Time")==1
                                app.Fitparameter1EditFieldLabel.Text='Rate [s-1]: ';
                            else
                                app.Fitparameter1EditFieldLabel.Text='Rate [Frames-1]: ';
                            end
                            app.Fitparameter1EditField.Value=app.fitted_values.fitted_rate;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.4g';
                        else % Case of fitting N photons histograms
                            app.Fitparameter1EditFieldLabel.Text='Budget 1 [Ph]: ';
                            app.Fitparameter1EditField.Value=1/app.fitted_values.fitted_rate;
                            app.Fitparameter1EditField.ValueDisplayFormat='%11.2g';
                        end
                        
                        app.Fitparameter1EditField_2Label.Text='Beta value: ';
                        app.Fitparameter1EditField_2.Value=app.fitted_values.fitted_beta;
                        app.Fitparameter3EditField.Visible='off';
                        app.Fitparameter3EditFieldLabel.Visible='off';
                end
                
            else
                app.Fitparameter1EditField.Visible='off';
                app.Fitparameter1EditFieldLabel.Visible='off';
                app.Fitparameter1EditField_2.Visible='off';
                app.Fitparameter1EditField_2Label.Visible='off';
                app.Fitparameter3EditField.Visible='off';
                app.Fitparameter3EditFieldLabel.Visible='off';
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
                if app.dialog_on==1
                    MyDialog=warndlg('Could not fit the data ! Consider changing fitting method !','Warning');
                    waitfor(MyDialog)
                end
            end
            
            if app.fit_ok==0.5
                if app.dialog_on==1
                    MyDialog=warndlg('Could not properly fit the data ! Use resuts with caution !','Warning');
                    waitfor(MyDialog)
                end
            end
        end
        
        
        function FitNBlinkData(app,HistogramData,MyAxis)
            
            fit_data=1; % Do fit the data; can be set to zero to check initial values
            app.fit.initial_eta=0.8;
            
            [fitted_eta, ~, app.fit_ok, Xfit, Yfit]=Fit_MyNBlinkData(...
                HistogramData, app.fit.initial_eta, app.fit.remove_first_point, fit_data);
            
            if app.fit_ok==true
                if app.fit.remove_first_point==0
                    line(MyAxis,Xfit, Yfit,'Color','Red','LineWidth',app.LineWidthSlider_2.Value);
                else
                    line(MyAxis,Xfit, Yfit,'Color',1/255*[255,127,0],'LineWidth',app.LineWidthSlider_2.Value);
                end
            end
            
            if app.fit_ok>0
                app.pvalueEditField.Visible='on';
                app.pvalueEditFieldLabel.Visible='on';
                app.pvalueEditField.Value=1-fitted_eta;
                
            else
                app.pvalueEditField.Visible='off';
                app.pvalueEditFieldLabel.Visible='off';
                if app.dialog_on==1
                    MyDialog=warndlg('Could not fit the data !','Warning');
                    waitfor(MyDialog)
                end
            end
            
        end
        
        function ReShape(~,MyAxis)
            MyAxis.Visible='on';
            yyaxis(MyAxis, "left")
            if ~isempty(MyAxis.Legend)
                MyAxis.Legend.Visible='on'; % Seems to be necessary (bug ?)
            end
            MyAxis.YAxis(1).Color='black';
            if numel(MyAxis.YAxis)==2
                MyAxis.YAxis(2).Visible='off'; % Removes right axis
            end
        end
        
        function HideAxis(~,MyAxis)
            MyAxis.Visible='off';
            if ~isempty(MyAxis.Legend)
                MyAxis.Legend.Visible='off'; % Seems to be necessary (bug ?)
            end
            
        end
        
        function ShowAxis(~,MyAxis)
            MyAxis.Visible='on';
            if ~isempty(MyAxis.Legend)
                MyAxis.Legend.Visible='on'; % Seems to be necessary (bug ?)
            end
        end
        
        function FixStateNames(app)
            %Fix the names
            for i=1:app.n_states
                app.sm_par(app.fluo_id).state_names{i}=strrep(app.sm_par(app.fluo_id).state_names{i},'_','\_');
            end
        end
        
        function GetDetectedOnOffTimes(app)
            % Experimental-like on and off times
            if ~isempty(app.StateList_SM.Data)
                gap=app.StateList_SM.Data{app.n_states+3,8}; % [Frames]
                start_frame=app.StateList_SM.Data{app.n_states+3,9};
            else % For initial calculation during loading
                gap=0;
                start_frame=1;
            end
            for i=1:app.n
                frames_on=app.sm(i).frames_on_ch1;
                if ~isempty(frames_on)
                    kept_frames_on=frames_on(frames_on>=start_frame);
                    [app.R(app.fluo_id).DATA(i).EXP(1).on,app.R(app.fluo_id).DATA(i).EXP(1).off,app.R(app.fluo_id).DATA(i).EXP(1).N, ~]=...
                        get_on_off_times_from_frames_on(kept_frames_on,gap);
                    if ~isempty(kept_frames_on)
                        app.R(app.fluo_id).DATA(i).EXP(1).active=1+kept_frames_on(end)-kept_frames_on(1);
                        app.R(app.fluo_id).DATA(i).EXP(1).total_on=numel(kept_frames_on);
                    else
                        app.R(app.fluo_id).DATA(i).EXP(1).active=[];
                        app.R(app.fluo_id).DATA(i).EXP(1).total_on=[];
                    end
                end
                
                if app.im_par.two_channel==1
                    frames_on=app.sm(i).frames_on_ch2;
                    if ~isempty(frames_on)
                        kept_frames_on=frames_on(frames_on>=start_frame);
                        [app.R(app.fluo_id).DATA(i).EXP(2).on,app.R(app.fluo_id).DATA(i).EXP(2).off,app.R(app.fluo_id).DATA(i).EXP(2).N, ~]=...
                            get_on_off_times_from_frames_on(kept_frames_on,gap);
                        if ~isempty(kept_frames_on)
                            app.R(app.fluo_id).DATA(i).EXP(2).active=1+kept_frames_on(end)-kept_frames_on(1);
                            app.R(app.fluo_id).DATA(i).EXP(2).total_on=numel(kept_frames_on);
                        else
                            app.R(app.fluo_id).DATA(i).EXP(2).active=[];
                            app.R(app.fluo_id).DATA(i).EXP(2).total_on=[];
                        end
                    end
                end
            end
                        
        end
        
        function HideInfoFields12(app)
            app.Info1EditField.Visible='Off';
            app.Info1EditFieldLabel.Visible='Off';
            app.Info2EditField.Visible='Off';
            app.Info2EditFieldLabel.Visible='Off';
        end
        
        function HideInfoFields34(app)
            app.Info3EditField.Visible='Off';
            app.Info3EditFieldLabel.Visible='Off';
            app.Info4EditField.Visible='Off';
            app.Info4EditFieldLabel.Visible='Off';
        end
        
        function ShowInfoFields12(app)
            app.Info1EditField.Visible='On';
            app.Info1EditFieldLabel.Visible='On';
            app.Info2EditField.Visible='On';
            app.Info2EditFieldLabel.Visible='On';
        end
        
        function ShowInfoFields34(app)
            app.Info3EditField.Visible='On';
            app.Info3EditFieldLabel.Visible='On';
            app.Info4EditField.Visible='On';
            app.Info4EditFieldLabel.Visible='On';
        end
        
        
        function DisplayInfo12(app,v1,v2,l1,l2,l3,l4)
            if strcmp(app.XaxisSwitch_2.Value,'Time')==1
                app.Info1EditFieldLabel.Text=l1;
                app.Info1EditField.Value=v1;
                app.Info2EditFieldLabel.Text=l2;
                app.Info2EditField.Value=v2;
            else
                app.Info1EditFieldLabel.Text=l3;
                app.Info1EditField.Value=v1/app.TotalFrameTime;
                app.Info2EditFieldLabel.Text=l4;
                app.Info2EditField.Value=v2/app.TotalFrameTime;
            end
        end
        
        function DisplayInfo34(app,v1,v2,l1,l2)
            app.Info3EditFieldLabel.Text=l1;
            app.Info3EditField.Value=v1;
            app.Info4EditFieldLabel.Text=l2;
            app.Info4EditField.Value=v2;
        end
    end
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Get all the parameters
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
            
            app.fit.fit_data=0; % Do not fit at the beginning
            
            app.plotted_ens.dir=[]; % Initialize output directory for saving ensemble data
            app.plotted_ens.file=[];

            app.AnalyzeDiffusionStatesButton.Visible='off';
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app.ShowTracesApp)
                if isvalid(app.ShowTracesApp)
                    delete(app.ShowTracesApp)
                end
            end
            
            if ~isempty(app.ShowDiffApp)
                if isvalid(app.ShowDiffApp)
                    if ~isempty(app.ShowDiffApp.ShowDiffTracesApp)
                        if isvalid(app.ShowDiffApp.ShowDiffTracesApp)
                            if ~isempty(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                if isvalid(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                    delete(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                end
                            end
                            delete(app.ShowDiffApp.ShowDiffTracesApp)
                        end
                    end
                    delete(app.ShowDiffApp)
                end
            end
            close all
            delete(app)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if isempty(app.datadir)
                if exist(app.smis_gui_parameters.DefaultDirs.SMIS_dir,'dir')
                    cd(app.smis_gui_parameters.DefaultDirs.SMIS_dir);
                end
            else
                cd(app.datadir);
            end
            
            
            if strcmp(app.TypeofSMISdataSwitch.Value,'Ensemble')==1
                [file,path] = uigetfile('*.mat','Select an Ensemble SMIS Ground Truth matlab file');
                if isequal(file,0)
                    disp('User selected Cancel');
                    figure(app.UIFigure); % Get the GUI in the front
                else
                    app.datadir=path;
                    app.datafile=file;
                    load(fullfile(path,file),'ens','im_par','sm_par');
                    if ~exist('ens',"var") % Not a good file
                        msgbox('Invalid SMIS ensemble simulation ground truth data file !','icon','warn')
                    else % Go on
                        app.DatasetNameEditField.Value=file;
                        app.ens=ens; %#ok<ADPROPLC>
                        app.sm_par_ens=sm_par; %#ok<ADPROPLC>
                        app.im_par_ens=im_par; %#ok<ADPROPLC>
                        n_fluorophores=numel(app.sm_par_ens);
                        app.NumberoffluorophoresEditField.Value=n_fluorophores;
                        if n_fluorophores>1
                            % Enable drop down menu
                            app.FluorophoretoanalyseDropDown.Visible=1;
                            app.FluorophoretoanalyseDropDownLabel.Visible=1;
                            app.FluorophoretoanalyseDropDown.ItemsData=1:n_fluorophores+1 ;
                            app.FluorophoretoanalyseDropDown.Items(1) = {'Choose'};
                            app.FluorophoretoanalyseDropDown.Items(2:n_fluorophores+1) = {app.sm_par_ens.fluorophore_name};
                        else
                            % Disable drop down menu
                            app.FluorophoretoanalyseDropDown.Visible=0;
                            app.FluorophoretoanalyseDropDownLabel.Visible=0;
                        end
                        
                        %Display the first fluorophore to start with
                        app.FluorophorenameEditField.Value=app.sm_par_ens(1).fluorophore_name;
                        
                        % Handle figure visibility for second channel
                        if app.im_par_ens.two_channel==1
                            app.PlotFluo_2.Visible=1;
                        else
                            MyData = findobj(app.PlotFluo_2, '-not', 'Type', 'Axes');
                            if ~isempty(MyData)
                                delete(MyData);
                            end
                            app.PlotFluo_2.Visible=0;
                        end
                        
                        app.fluo_id=1; % Select the first fluorophore by default
                        
                        %Setup the table for the first fluorophore
                        SetupEnsembleTable(app)
                        
                        %Plot the Data
                        PlotEnsembleData(app);
                    end
                end
            else % Case of single molecule data
                [file,path] = uigetfile('*.mat','Select a Single Molecule SMIS Ground Truth matlab file');
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    disp('Loading file ... ');
                    load(fullfile(path,file),'sms','im_par','sm_par');
                    app.datadir=path;
                    app.datafile=file;
                    if ~exist('sms',"var")
                        msgbox('Invalid SMIS single molecule simulation ground truth data file !','icon','warn')
                    else
                        app.DatasetNameEditField.Value=file;
                        % Load the data
                        app.sms=sms; %#ok<ADPROPLC>
                        app.sm_par=sm_par; %#ok<ADPROPLC>
                        app.im_par=im_par; %#ok<ADPROPLC>
                        n_fluorophores=numel(app.sm_par);
                        
                        % Get total frame time
                        app.TotalFrameTime=(app.im_par.frametime+app.im_par.addtime)*1e-3; % in [s]
                        
                        % Look if diffusion is present
                        if app.im_par.add_diffusion==1
                            app.AnalyzeDiffusionStatesButton.Visible='on';
                        else
                            app.AnalyzeDiffusionStatesButton.Visible='off';
                        end
                        
                        %Define results
                        clear('DATA','INFO','R');
                        R(1:n_fluorophores)=struct('DATA',[],'INFO',[]); %#ok<ADPROPLC>
                        app.R=R; %#ok<ADPROPLC>
                        
                        app.NumberoffluorophoresEditField.Value=n_fluorophores;
                        if n_fluorophores>1
                            % Enable drop down menu
                            app.FluorophoretoanalyseDropDown.Visible=1;
                            app.FluorophoretoanalyseDropDownLabel.Visible=1;
                            app.FluorophoretoanalyseDropDown.ItemsData=1:n_fluorophores+1 ;
                            app.FluorophoretoanalyseDropDown.Items(1) = {'Choose'};
                            app.FluorophoretoanalyseDropDown.Items(2:n_fluorophores+1) = {app.sm_par.fluorophore_name};
                        else
                            % Disable drop down menu
                            app.FluorophoretoanalyseDropDown.Visible=0;
                            app.FluorophoretoanalyseDropDownLabel.Visible=0;
                        end
                        
                        %Display the first fluorophore to start with
                        app.FluorophorenameEditField.Value=app.sm_par(1).fluorophore_name;
                        
                        % Check whether it's a 2-channel exp
                        if app.im_par.two_channel==1
                            app.ChannelSwitch.Visible='on';
                        else
                            app.ChannelSwitch.Visible='off';
                        end
                        
                        % Choose channel 1 by default
                        app.ChannelSwitch.Value='Ch1';
                        
                        
                        app.fluo_id=1; % Select the first fluorophore by default
                        
                        % Get # of states
                        app.states_ids=unique(app.sm_par(app.fluo_id).state_ids);
                        app.states_names=app.sm_par(app.fluo_id).state_names(app.states_ids);
                        app.n_states=numel(app.states_ids);
                        
                        %Fix the state names
                        FixStateNames(app)
                        
                        %Setup the table
                        SetUpSMTable(app)
                        
                        %Setup the SM data
                        disp('Setting up the SM data ... ');
                        SetUpSMData(app)
                        
                        %Plot the Data
                        disp('Plotting the SM data ... ');
                        PlotSMData(app);

                        disp('Done !');
                    end
                end
            end
        end

        % Cell edit callback: StateList
        function StateListCellEdit(app, event)
            
            PlotEnsembleData(app);
            
        end

        % Cell selection callback: StateList
        function StateListCellSelection(app, event)
            
            if size(event.Indices,1)==1 % Do not do anything if entire columns or rows are selected
                if event.Indices(2)==2 % Do not do anything if first column is selected
                    c = uisetcolor;
                    if size(c,2)==3
                        sb = uistyle('BackgroundColor',c);
                        addStyle(app.StateList,sb,'cell',event.Indices);
                        app.Color(event.Indices(1),:)=c;
                        
                        PlotEnsembleData(app);
                    end
                end
            end
        end

        % Value changed function: ShowLegendSwitch
        function ShowLegendSwitchValueChanged(app, event)
            PlotEnsembleData(app);
        end

        % Value changed function: LineWidthSlider
        function LineWidthSliderValueChanged(app, event)
            PlotEnsembleData(app);
        end

        % Value changed function: AxisWidthSlider
        function AxisWidthSliderValueChanged(app, event)
            PlotEnsembleData(app);
        end

        % Value changed function: XaxisSwitch
        function XaxisSwitchValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Value changed function: FontSizeSlider
        function FontSizeSliderValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Value changed function: XLimitSlider
        function XLimitSliderValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Value changed function: YLimitSlider
        function YLimitSliderValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Button pushed function: MakefinalfiguresButton
        function MakefinalfiguresButtonPushed(app, event)
            app.FinalFigureMode=1;
            PlotEnsembleData(app);
            app.FinalFigureMode=0;
        end

        % Value changed function: FluorophoretoanalyseDropDown
        function FluorophoretoanalyseDropDownValueChanged(app, event)
            app.fluo_id = app.FluorophoretoanalyseDropDown.Value-1;


            % Ensemble case
            if strcmp(app.TypeofSMISdataSwitch.Value,'Ensemble')==1
                %Display the first fluorophore to start with
                app.FluorophorenameEditField.Value=app.sm_par_ens(app.fluo_id).fluorophore_name;

                %Setup the table for the first fluorophore
                SetupEnsembleTable(app)
                
                %Plot the Data
                PlotEnsembleData(app);
            else % SM case

                %Display the first fluorophore to start with
                app.FluorophorenameEditField.Value=app.sm_par(app.fluo_id).fluorophore_name;

                app.states_ids=unique(app.sm_par(app.fluo_id).state_ids);
                app.states_names=app.sm_par(app.fluo_id).state_names(app.states_ids);
                app.n_states=numel(app.states_ids);
                
                %Fix the state names
                FixStateNames(app)
                
                %Setup the table
                SetUpSMTable(app)
                
                %Setup the SM data
                SetUpSMData(app)
                
                %Plot the Data
                PlotSMData(app);
            end
        end

        % Value changed function: TypeofSMISdataSwitch
        function TypeofSMISdataSwitchValueChanged(app, event)
            value = app.TypeofSMISdataSwitch.Value;
            if strcmp(value,'Ensemble')
                app.EnsemblePanel.Visible=1; % Show the ensemble panel
                app.SingleMoleculePanel.Visible=0;
            else
                app.EnsemblePanel.Visible=0; % Show the SM panel
                app.SingleMoleculePanel.Visible=1;
            end
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            
            if ~isempty(app.ShowTracesApp)
                if isvalid(app.ShowTracesApp)
                    delete(app.ShowTracesApp)
                end
            end
            
            if ~isempty(app.ShowDiffApp)
                if isvalid(app.ShowDiffApp)
                    if ~isempty(app.ShowDiffApp.ShowDiffTracesApp)
                        if isvalid(app.ShowDiffApp.ShowDiffTracesApp)
                            if ~isempty(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                if isvalid(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                    delete(app.ShowDiffApp.ShowDiffTracesApp.OverlayRawDataApp)
                                end
                            end
                            delete(app.ShowDiffApp.ShowDiffTracesApp)
                        end
                    end
                    delete(app.ShowDiffApp)
                end
            end
            close all
            delete(app)
        end

        % Cell edit callback: StateList_SM
        function StateList_SMCellEdit(app, event)
            state_id = event.Indices(1);
            action_id = event.Indices(2);
            newData = event.NewData;
            
            switch action_id
                case 1 % Show a state
                    app.StateList_SM.Data{:,1}=false;
                    app.StateList_SM.Data{state_id,1}=true;
                    
                    %Set fit parameters
                    if app.StateList_SM.Data{state_id,3}==true % Fit in this case
                        app.fit.fit_data(state_id)=true;
                        app.fit.remove_first_point=app.StateList_SM.Data{state_id,7};
                        fit_method=find(app.StateList_SM.Data{state_id,4:6}==1);
                        switch fit_method
                            case 1
                                app.fit.fit_type='monophasic';
                            case 2
                                app.fit.fit_type='biphasic';
                            case 3
                                app.fit.fit_type='stretched';
                        end
                        
                        %Handle case of fitting N_phot distribution
                        if strcmp(app.StateList_SM.RowName{state_id},'Photon Budget')==1
                            app.fit.fit_what='Phot Budget';
                        elseif strcmp(app.StateList_SM.RowName{state_id},'Photons per Loc')==1
                            app.fit.fit_what='Phot per Loc';
                        else
                            app.fit.fit_what='Time';
                        end
                    else
                        app.fit.fit_data(state_id)=false; % Do not fit in this case
                    end
                    
                    % Authorized the warning  dialogs
                    app.dialog_on=1;
                    
                    % And plot the data
                    PlotSMData(app)
                    
                    % Prevent the warning dialogs for further actions
                    app.dialog_on=0;
                    
                case 3 % Handle fit
                    app.fit.fit_data(state_id)=newData;
                    
                case 4 % Monoexp fit
                    if newData==true % If monoexp is chosen, disable biexp or stretched exp
                        app.StateList_SM.Data{state_id,[5,6]}=false;
                    end
                    
                case 5 % Biexp fit
                    if newData==true % If biexp is chosen, disable Monoexp or stretched exp
                        app.StateList_SM.Data{state_id,[4,6]}=false;
                    end
                    
                case 6 % Stretched exp fit
                    if newData==true % If stretched exp is chosen, disable Monoexp or Biexp
                        app.StateList_SM.Data{state_id,4:5}=false;
                    end
                    
                case 8 % Handle blinking gap for Detected on/off times
                    if state_id==app.n_states+3 || state_id==app.n_states+4
                        if floor(event.NewData)~=event.NewData || event.NewData<0
                            MyDialog=warndlg('Gap must be a positive integer !','Warning') ;
                            waitfor(MyDialog)
                            app.StateList_SM.Data{state_id,action_id}=event.PreviousData;
                        else
                            app.StateList_SM.Data{app.n_states+3,action_id}=event.NewData;
                            app.StateList_SM.Data{app.n_states+4,action_id}=event.NewData;
                            GetDetectedOnOffTimes(app);
                        end
                    else
                        app.StateList_SM.Data{state_id,action_id}=event.PreviousData;
                        warndlg('Only valid for detected on and off times !','Warning')
                    end
                    
                case 9 % Handle analyse from frame for Detected on/off times
                    if state_id==app.n_states+3 || state_id==app.n_states+4
                        if floor(event.NewData)~=event.NewData || event.NewData<1
                            MyDialog=warndlg('Gap must be a positive integer > 0 !','Warning') ;
                            waitfor(MyDialog)
                            app.StateList_SM.Data{state_id,action_id}=event.PreviousData;
                        else
                            app.StateList_SM.Data{app.n_states+3,action_id}=event.NewData;
                            app.StateList_SM.Data{app.n_states+4,action_id}=event.NewData;
                            GetDetectedOnOffTimes(app);
                        end
                    else
                        app.StateList_SM.Data{state_id,action_id}=event.PreviousData;
                        warndlg('Only valid for Detected on and off times !','Warning')
                    end
                otherwise
            end
            
            %Disable fit if no fitting method is chosen
            if ~any(app.StateList_SM.Data{state_id,4:6})
                app.StateList_SM.Data{state_id,3}=false;
            end


        end

        % Value changed function: XLogScaleSwitch_2
        function XLogScaleSwitch_2ValueChanged(app, event)
            PlotSMData(app);
        end

        % Value changed function: HistogramBinsEditField
        function HistogramBinsEditFieldValueChanged(app, event)
            PlotSMData(app);
        end

        % Value changed function: AxisWidthSlider_2
        function AxisWidthSlider_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: LineWidthSlider_2
        function LineWidthSlider_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: FontSizeSlider_2
        function FontSizeSlider_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: YLimitSlider_2
        function YLimitSlider_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: XLimitSlider_2
        function XLimitSlider_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: XaxisSwitch_2
        function XaxisSwitch_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: ShowLegendSwitch_2
        function ShowLegendSwitch_2ValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: ChannelSwitch
        function ChannelSwitchValueChanged(app, event)
            PlotSMData(app)
        end

        % Value changed function: UseonlybleachedmoleculesSwitch
        function UseonlybleachedmoleculesSwitchValueChanged(app, event)
            SetUpSMData(app)
            PlotSMData(app)
        end

        % Value changed function: UseonlyactivatedmoleculesSwitch
        function UseonlyactivatedmoleculesSwitchValueChanged(app, event)
            SetUpSMData(app)
            PlotSMData(app)
        end

        % Button pushed function: MakefinalfiguresButton_2
        function MakefinalfiguresButton_2Pushed(app, event)
            close all; % Close all figures
            app.external_figure.mode=true; % Switch to external figure mode
            StatesToPlot=find(app.StateList_SM.Data{:,2}==true); % States to be plotted
            % Case of individual figures
            if strcmp(app.CompositefinalFigureSwitch.Value,'Off')==1
                app.external_figure.composite=0;
                if ~isempty(StatesToPlot)
                    for k=1:numel(StatesToPlot)
                        app.external_figure.state_id=StatesToPlot(k);
                        PlotSMData(app)
                    end
                    %Show the 3 axis
                    ReShape(app,app.PlotFluo_3);
                    ReShape(app,app.PlotFluo_4)
                    ReShape(app,app.PlotFluo_5);
                end
            else % Case of composite figure
                app.external_figure.composite=1;
                if ~isempty(StatesToPlot)
                    n_sp=0; % # of subplots
                    for k=1:numel(StatesToPlot)
                        % Figure out the number of subplots
                        if StatesToPlot(k)<=numel(app.state_types)
                            state_type=app.state_types{StatesToPlot(k)};
                            
                            switch state_type
                                case 'Fluorescent'
                                    n_sp=n_sp+3;
                                case 'Dark'
                                    n_sp=n_sp+3;
                                case 'Bleached'
                                    n_sp=n_sp+3;
                            end
                        else
                            switch app.StateList_SM.RowName{StatesToPlot(k)}
                                case 'Photon Budget'
                                    n_sp=n_sp+1;
                                case 'Photons per Loc'
                                    n_sp=n_sp+1;
                                case 'Detected On Times'
                                    n_sp=n_sp+1;
                                case 'Detected Off Times'
                                    n_sp=n_sp+2;
                                case 'Detected Active Times'
                                    n_sp=n_sp+1;
                                case 'Detected Total On Times'
                                    n_sp=n_sp+1;
                                case 'Detected Bleach Times'
                                    n_sp=n_sp+2;
                                case 'Photoconversion'
                                    n_sp=n_sp+1;
                            end
                        end
                    end
                    % Figure out the arrangment of subplots
                    subplot_n_cols=ceil(sqrt(n_sp));
                    subplot_n_rows=fix(sqrt(n_sp));
                    if subplot_n_cols*subplot_n_rows < n_sp
                        subplot_n_rows=subplot_n_rows+1;
                    end
                    app.external_figure.n_cols=subplot_n_cols;
                    app.external_figure.n_rows=subplot_n_rows;
                    app.external_figure.pos=1; % Initialize position within composite figure
                    %Define figure
                    FigTitle=['Photophysical States: ',app.sm_par(app.fluo_id).fluorophore_name];
                    app.MyFigure=figure();
                    set(gcf,'Name',FigTitle)
                    set(app.MyFigure,'color','w');
                    % Go on with plotting
                    for k=1:numel(StatesToPlot)
                        app.external_figure.state_id=StatesToPlot(k);
                        PlotSMData(app)
                    end
                    %Show the 3 axis
                    ReShape(app,app.PlotFluo_3);
                    ReShape(app,app.PlotFluo_4)
                    ReShape(app,app.PlotFluo_5);
                end
            end
            app.external_figure.mode=false; % Switch back to internal figure mode
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            SuggestedFile=[app.datafile(1:end-4),'_',app.sm_par(app.fluo_id).fluorophore_name,'_Analysis.mat'];
            
            cd(app.datadir);
            [MyFile,MyDir] = uiputfile('*.mat','SMIS File Selection',SuggestedFile);
            if ~isnumeric(MyDir) || ~isnumeric(MyFile)
                R=app.R; %#ok<ADPROPLC>
                im_par=app.im_par; %#ok<ADPROPLC>
                sm_par=app.sm_par(app.fluo_id); %#ok<ADPROPLC>
                save(fullfile(MyDir,MyFile),'R','im_par','sm_par');
            end
        end

        % Button pushed function: ShowSMTracesButton
        function ShowSMTracesButtonPushed(app, event)
            app.ShowTracesApp=Show_Traces(app);
        end

        % Value changed function: YLogScaleSwitch
        function YLogScaleSwitchValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Value changed function: XLogScaleSwitch
        function XLogScaleSwitchValueChanged(app, event)
            PlotEnsembleData(app)
        end

        % Button pushed function: AnalyzeDiffusionStatesButton
        function AnalyzeDiffusionStatesButtonPushed(app, event)
            app.ShowDiffApp=Show_Diffusion(app);
        end

        % Value changed function: YLogScaleSwitch_2
        function YLogScaleSwitch_2ValueChanged(app, event)
            PlotSMData(app);
        end

        % Value changed function: ForceNBinsCheckBox
        function ForceNBinsCheckBoxValueChanged(app, event)
             PlotSMData(app);           
        end

        % Button pushed function: SavedatatocsvButton
        function SavedatatocsvButtonPushed(app, event)
   
            if isnumeric(app.plotted_ens.dir)
                app.plotted_ens.dir=app.smis_gui_parameters.DefaultDirs.SMIS_dir;
                app.plotted_ens.file='Ensemble_data.csv';
            end

            cd(app.plotted_ens.dir);

            [app.plotted_ens.file,app.plotted_ens.dir] = uiputfile('*.csv','csv File Selection',app.plotted_ens.file);
            if ~isnumeric(app.plotted_ens.dir) || ~isnumeric(app.plotted_ens.file)

                % Make names more precise
                for k=1:numel(app.plotted_ens.fluo_names_ch1)
                    app.plotted_ens.fluo_names_ch1{k}=[app.plotted_ens.fluo_names_ch1{k},'_fluo_Ch1'];
                end

                if app.im_par_ens.two_channel==0
                    headers = [app.plotted_ens.X_name; app.plotted_ens.state_names; app.plotted_ens.fluo_names_ch1; app.plotted_ens.tot_fluo_name_ch1];
                    data = [app.plotted_ens.X; app.plotted_ens.state_concentration; app.plotted_ens.fluo_ch1; app.plotted_ens.tot_fluo_ch1];
                else
                    for k=1:numel(app.plotted_ens.fluo_names_ch2)
                        app.plotted_ens.fluo_names_ch2{k}=[app.plotted_ens.fluo_names_ch2{k},'_fluo_Ch2'];
                    end
                    headers = [app.plotted_ens.X_name; app.plotted_ens.state_names; app.plotted_ens.fluo_names_ch1; app.plotted_ens.tot_fluo_name_ch1, ; app.plotted_ens.fluo_names_ch2; app.plotted_ens.tot_fluo_name_ch2];
                    data = [app.plotted_ens.X; app.plotted_ens.state_concentration; app.plotted_ens.fluo_ch1; app.plotted_ens.tot_fluo_ch1; app.plotted_ens.fluo_ch2; app.plotted_ens.tot_fluo_ch2];
                end

                T = array2table(data', 'VariableNames', headers);

                writetable(T, fullfile(app.plotted_ens.dir,app.plotted_ens.file));

                disp(['Data written to:', fullfile(app.plotted_ens.dir,app.plotted_ens.file)]);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnsemblePanel = uipanel(app.UIFigure);
            app.EnsemblePanel.Title = 'Ensemble ';
            app.PlotFluo = uiaxes(app.EnsemblePanel);
            title(app.PlotFluo, 'Fluorescence Signal (Ch 1)')
            xlabel(app.PlotFluo, 'X')
            ylabel(app.PlotFluo, 'Y')
            zlabel(app.PlotFluo, 'Z')
            app.PlotFluo_2 = uiaxes(app.EnsemblePanel);
            title(app.PlotFluo_2, 'Fluorescence Signal (Ch 2)')
            xlabel(app.PlotFluo_2, 'X')
            ylabel(app.PlotFluo_2, 'Y')
            zlabel(app.PlotFluo_2, 'Z')
            app.PlotStates = uiaxes(app.EnsemblePanel);
            title(app.PlotStates, 'Photophysical States')
            xlabel(app.PlotStates, 'X')
            ylabel(app.PlotStates, 'Y')
            zlabel(app.PlotStates, 'Z')
            app.XaxisSwitchLabel = uilabel(app.EnsemblePanel);
            app.XaxisSwitchLabel.Text = 'X-axis';
            app.XaxisSwitch = uiswitch(app.EnsemblePanel, 'slider');
            app.XaxisSwitch.Items = {'Frames', 'Time'};
            app.XaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @XaxisSwitchValueChanged, true);
            app.XaxisSwitch.Tooltip = {'Use this switch to toggle between Frames and Time'};
            app.XaxisSwitch.Value = 'Frames';
            app.ShowLegendSwitchLabel = uilabel(app.EnsemblePanel);
            app.ShowLegendSwitchLabel.Text = 'Show Legend';
            app.ShowLegendSwitch = uiswitch(app.EnsemblePanel, 'slider');
            app.ShowLegendSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowLegendSwitchValueChanged, true);
            app.ShowLegendSwitch.Tooltip = {'Use this toggle to show or not the legend in plots'};
            app.LineWidthSliderLabel = uilabel(app.EnsemblePanel);
            app.LineWidthSliderLabel.Text = 'Line Width';
            app.LineWidthSlider = uislider(app.EnsemblePanel);
            app.LineWidthSlider.Limits = [1 8];
            app.LineWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.LineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @LineWidthSliderValueChanged, true);
            app.LineWidthSlider.MinorTicks = [];
            app.LineWidthSlider.Tooltip = {'Use this slider to change the line width of the plots'};
            app.LineWidthSlider.Value = 3;
            app.AxisWidthSliderLabel = uilabel(app.EnsemblePanel);
            app.AxisWidthSliderLabel.Text = 'Axis Width';
            app.AxisWidthSlider = uislider(app.EnsemblePanel);
            app.AxisWidthSlider.Limits = [1 8];
            app.AxisWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AxisWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxisWidthSliderValueChanged, true);
            app.AxisWidthSlider.MinorTicks = [];
            app.AxisWidthSlider.Tooltip = {'Use this slider to change the axis width of the plots'};
            app.AxisWidthSlider.Value = 3;
            app.FontSizeSliderLabel = uilabel(app.EnsemblePanel);
            app.FontSizeSliderLabel.Text = 'Font Size';
            app.FontSizeSlider = uislider(app.EnsemblePanel);
            app.FontSizeSlider.Limits = [10 30];
            app.FontSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeSliderValueChanged, true);
            app.FontSizeSlider.MinorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.FontSizeSlider.Tooltip = {'Use this slider to change the font size of the plots'};
            app.FontSizeSlider.Value = 16;
            app.XLimitLabel = uilabel(app.EnsemblePanel);
            app.XLimitLabel.Text = 'X Limit [%]';
            app.XLimitSlider = uislider(app.EnsemblePanel);
            app.XLimitSlider.MajorTicks = [0 20 40 60 80 100];
            app.XLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XLimitSliderValueChanged, true);
            app.XLimitSlider.Tooltip = {'Use this slider to change the X limits of the plots'};
            app.XLimitSlider.Value = 100;
            app.StateList = uitable(app.EnsemblePanel);
            app.StateList.ColumnName = {'Plot'; 'Color'};
            app.StateList.RowName = {'State1,State2'};
            app.StateList.ColumnEditable = [true false];
            app.StateList.CellEditCallback = createCallbackFcn(app, @StateListCellEdit, true);
            app.StateList.CellSelectionCallback = createCallbackFcn(app, @StateListCellSelection, true);
            app.StateList.Tooltip = {'Use this table to choose which photophysical states to plot and with which color. [Caution: you cannot select twice the same cell for color change]'};
            app.YLimitLabel = uilabel(app.EnsemblePanel);
            app.YLimitLabel.Text = 'Y Limit [%]';
            app.YLimitSlider = uislider(app.EnsemblePanel);
            app.YLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YLimitSliderValueChanged, true);
            app.YLimitSlider.Tooltip = {'Use this slider to change the Y limits of the plots'};
            app.YLimitSlider.Value = 100;
            app.MakefinalfiguresButton = uibutton(app.EnsemblePanel, 'push');
            app.MakefinalfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @MakefinalfiguresButtonPushed, true);
            app.MakefinalfiguresButton.Tooltip = {'Click this button to produce figures, based on data selected in the table,  that can be saved on disk.'};
            app.MakefinalfiguresButton.Text = 'Make final figures';
            app.OKButton = uibutton(app.EnsemblePanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main menu'};
            app.OKButton.Text = 'OK';
            app.XLogScaleSwitchLabel = uilabel(app.EnsemblePanel);
            app.XLogScaleSwitchLabel.Text = 'X Log Scale';
            app.XLogScaleSwitch = uiswitch(app.EnsemblePanel, 'slider');
            app.XLogScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @XLogScaleSwitchValueChanged, true);
            app.XLogScaleSwitch.Tooltip = {'Switch to log scale on X-axis'};
            app.YLogScaleSwitchLabel = uilabel(app.EnsemblePanel);
            app.YLogScaleSwitchLabel.Text = 'Y Log Scale';
            app.YLogScaleSwitch = uiswitch(app.EnsemblePanel, 'slider');
            app.YLogScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @YLogScaleSwitchValueChanged, true);
            app.YLogScaleSwitch.Tooltip = {'Switch to log scale on Y-axis'};
            app.SavedatatocsvButton = uibutton(app.EnsemblePanel, 'push');
            app.SavedatatocsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatocsvButtonPushed, true);
            app.SavedatatocsvButton.Tooltip = {'Click this button to produce figures, based on data selected in the table,  that can be saved on disk.'};
            app.SavedatatocsvButton.Text = 'Save data to .csv';
            app.SMISGroundTruthResultsAnalysisLabel = uilabel(app.UIFigure);
            app.SMISGroundTruthResultsAnalysisLabel.Text = 'SMIS Ground Truth Results Analysis ';
            app.FluorophorenameEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophorenameEditFieldLabel.Text = 'Fluorophore name';
            app.FluorophorenameEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophorenameEditField.Tooltip = {'Name of currently analysed fluorophore'};
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Press this button to load a SMIS Ground Truth data set (.mat)'};
            app.LoadDataButton.Text = 'Load Data';
            app.TypeofSMISdataLabel = uilabel(app.UIFigure);
            app.TypeofSMISdataLabel.Text = 'Type of SMIS data:';
            app.TypeofSMISdataSwitch = uiswitch(app.UIFigure, 'slider');
            app.TypeofSMISdataSwitch.Items = {'SM', 'Ensemble'};
            app.TypeofSMISdataSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeofSMISdataSwitchValueChanged, true);
            app.TypeofSMISdataSwitch.Tooltip = {'Use this switch to select either Single Molecule or Ensemble data '};
            app.TypeofSMISdataSwitch.Value = 'SM';
            app.NumberoffluorophoresEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoffluorophoresEditFieldLabel.Text = 'Number of fluorophores';
            app.NumberoffluorophoresEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoffluorophoresEditField.Editable = 'off';
            app.FluorophoretoanalyseDropDownLabel = uilabel(app.UIFigure);
            app.FluorophoretoanalyseDropDownLabel.Text = 'Fluorophore to analyse';
            app.FluorophoretoanalyseDropDown = uidropdown(app.UIFigure);
            app.FluorophoretoanalyseDropDown.ValueChangedFcn = createCallbackFcn(app, @FluorophoretoanalyseDropDownValueChanged, true);
            app.FluorophoretoanalyseDropDown.Tooltip = {'Choose fluorophore to analyse'};
            app.SingleMoleculePanel = uipanel(app.UIFigure);
            app.SingleMoleculePanel.Title = 'Single Molecule';
            app.PlotFluo_3 = uiaxes(app.SingleMoleculePanel);
            title(app.PlotFluo_3, 'Origin State')
            xlabel(app.PlotFluo_3, 'X')
            ylabel(app.PlotFluo_3, 'Y')
            zlabel(app.PlotFluo_3, 'Z')
            app.PlotFluo_4 = uiaxes(app.SingleMoleculePanel);
            title(app.PlotFluo_4, 'State Lifetime')
            xlabel(app.PlotFluo_4, 'X')
            ylabel(app.PlotFluo_4, 'Y')
            zlabel(app.PlotFluo_4, 'Z')
            app.PlotFluo_5 = uiaxes(app.SingleMoleculePanel);
            title(app.PlotFluo_5, 'Cumulated # mol in State')
            xlabel(app.PlotFluo_5, 'X')
            ylabel(app.PlotFluo_5, 'Y')
            zlabel(app.PlotFluo_5, 'Z')
            app.XaxisSwitch_2Label = uilabel(app.SingleMoleculePanel);
            app.XaxisSwitch_2Label.Text = 'X-axis';
            app.XaxisSwitch_2 = uiswitch(app.SingleMoleculePanel, 'slider');
            app.XaxisSwitch_2.Items = {'Frames', 'Time'};
            app.XaxisSwitch_2.ValueChangedFcn = createCallbackFcn(app, @XaxisSwitch_2ValueChanged, true);
            app.XaxisSwitch_2.Tooltip = {'Use this switch to toggle between Frames and Time'};
            app.XaxisSwitch_2.Value = 'Time';
            app.ShowLegendSwitch_2Label = uilabel(app.SingleMoleculePanel);
            app.ShowLegendSwitch_2Label.Text = 'Show Legend';
            app.ShowLegendSwitch_2 = uiswitch(app.SingleMoleculePanel, 'slider');
            app.ShowLegendSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ShowLegendSwitch_2ValueChanged, true);
            app.ShowLegendSwitch_2.Tooltip = {'Use this toggle to show or not the legend in plots'};
            app.LineWidthSlider_2Label = uilabel(app.SingleMoleculePanel);
            app.LineWidthSlider_2Label.Text = 'Line Width';
            app.LineWidthSlider_2 = uislider(app.SingleMoleculePanel);
            app.LineWidthSlider_2.Limits = [1 8];
            app.LineWidthSlider_2.MajorTicks = [1 2 3 4 5 6 7 8];
            app.LineWidthSlider_2.ValueChangedFcn = createCallbackFcn(app, @LineWidthSlider_2ValueChanged, true);
            app.LineWidthSlider_2.MinorTicks = [];
            app.LineWidthSlider_2.Tooltip = {'Use this slider to change the line width of the plots'};
            app.LineWidthSlider_2.Value = 3;
            app.AxisWidthSlider_2Label = uilabel(app.SingleMoleculePanel);
            app.AxisWidthSlider_2Label.Text = 'Axis Width';
            app.AxisWidthSlider_2 = uislider(app.SingleMoleculePanel);
            app.AxisWidthSlider_2.Limits = [1 8];
            app.AxisWidthSlider_2.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AxisWidthSlider_2.ValueChangedFcn = createCallbackFcn(app, @AxisWidthSlider_2ValueChanged, true);
            app.AxisWidthSlider_2.MinorTicks = [];
            app.AxisWidthSlider_2.Tooltip = {'Use this slider to change the axis width of the plots'};
            app.AxisWidthSlider_2.Value = 3;
            app.FontSizeSlider_2Label = uilabel(app.SingleMoleculePanel);
            app.FontSizeSlider_2Label.Text = 'Font Size';
            app.FontSizeSlider_2 = uislider(app.SingleMoleculePanel);
            app.FontSizeSlider_2.Limits = [8 20];
            app.FontSizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @FontSizeSlider_2ValueChanged, true);
            app.FontSizeSlider_2.MinorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.FontSizeSlider_2.Tooltip = {'Use this slider to change the font size of the plots'};
            app.FontSizeSlider_2.Value = 14;
            app.XLimitSlider_2Label = uilabel(app.SingleMoleculePanel);
            app.XLimitSlider_2Label.Text = 'X Limit [%]';
            app.XLimitSlider_2 = uislider(app.SingleMoleculePanel);
            app.XLimitSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.XLimitSlider_2.ValueChangedFcn = createCallbackFcn(app, @XLimitSlider_2ValueChanged, true);
            app.XLimitSlider_2.Tooltip = {'Use this slider to change the X limits of the plots'};
            app.XLimitSlider_2.Value = 100;
            app.StateList_SM = uitable(app.SingleMoleculePanel);
            app.StateList_SM.ColumnName = {'Show'; 'Plot Final'; 'Fit'; 'Mono Exp'; 'Bi Exp'; 'Stretched Exp'; 'Remove First Point'; 'Blinking Gap'; 'Analyze from Frame'};
            app.StateList_SM.RowName = {'State1,State2'};
            app.StateList_SM.ColumnEditable = true;
            app.StateList_SM.CellEditCallback = createCallbackFcn(app, @StateList_SMCellEdit, true);
            app.StateList_SM.Tooltip = {'Use this table to choose which photophysical states to show (withing GUI) plot (final figure), choose fitting parameters, or detected on/off/active/total_on time parameters (gap, analysis from frame #) '};
            app.YLimitSlider_2Label = uilabel(app.SingleMoleculePanel);
            app.YLimitSlider_2Label.Text = 'Y Limit [%]';
            app.YLimitSlider_2 = uislider(app.SingleMoleculePanel);
            app.YLimitSlider_2.ValueChangedFcn = createCallbackFcn(app, @YLimitSlider_2ValueChanged, true);
            app.YLimitSlider_2.Tooltip = {'Use this slider to change the Y limits of the plots'};
            app.YLimitSlider_2.Value = 100;
            app.MakefinalfiguresButton_2 = uibutton(app.SingleMoleculePanel, 'push');
            app.MakefinalfiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @MakefinalfiguresButton_2Pushed, true);
            app.MakefinalfiguresButton_2.Tooltip = {'Click this button to produce figures, based on data selected in the Plot Final column of the table,  that can be saved on disk.'};
            app.MakefinalfiguresButton_2.Text = 'Make final figures';
            app.OKButton_2 = uibutton(app.SingleMoleculePanel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Tooltip = {'Return to the main menu and close all downstream analysis windows'};
            app.OKButton_2.Text = 'OK';
            app.CompositefinalFigureSwitchLabel = uilabel(app.SingleMoleculePanel);
            app.CompositefinalFigureSwitchLabel.Text = 'Composite final Figure ';
            app.CompositefinalFigureSwitch = uiswitch(app.SingleMoleculePanel, 'slider');
            app.CompositefinalFigureSwitch.Tooltip = {'Set to on to produce a composite (single window) output figure recapitulating the data selected in the Plot Final column of the table.'};
            app.UseonlybleachedmoleculesSwitchLabel = uilabel(app.SingleMoleculePanel);
            app.UseonlybleachedmoleculesSwitchLabel.Text = 'Use only bleached molecules ';
            app.UseonlybleachedmoleculesSwitch = uiswitch(app.SingleMoleculePanel, 'slider');
            app.UseonlybleachedmoleculesSwitch.ValueChangedFcn = createCallbackFcn(app, @UseonlybleachedmoleculesSwitchValueChanged, true);
            app.UseonlybleachedmoleculesSwitch.Tooltip = {'Set to On if only bleached molecules are to be taken into consideration'};
            app.UseonlyactivatedmoleculesSwitchLabel = uilabel(app.SingleMoleculePanel);
            app.UseonlyactivatedmoleculesSwitchLabel.Text = 'Use only activated molecules ';
            app.UseonlyactivatedmoleculesSwitch = uiswitch(app.SingleMoleculePanel, 'slider');
            app.UseonlyactivatedmoleculesSwitch.ValueChangedFcn = createCallbackFcn(app, @UseonlyactivatedmoleculesSwitchValueChanged, true);
            app.UseonlyactivatedmoleculesSwitch.Tooltip = {'Set to On if only activated (or photoconverted) molecules are to be taken into consideration'};
            app.Info1EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Info1EditFieldLabel.Text = 'Info1';
            app.Info1EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Info1EditField.Editable = 'off';
            app.Info2EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Info2EditFieldLabel.Text = 'Info2';
            app.Info2EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Info2EditField.Editable = 'off';
            app.Fitparameter1EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Fitparameter1EditFieldLabel.Text = 'Fit parameter 1';
            app.Fitparameter1EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Fitparameter1EditField.Editable = 'off';
            app.Fitparameter1EditField.Tooltip = {'Result of specified fitting parameter'};
            app.Fitparameter3EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Fitparameter3EditFieldLabel.Text = 'Fit parameter 3';
            app.Fitparameter3EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Fitparameter3EditField.Editable = 'off';
            app.Fitparameter3EditField.Tooltip = {'Result of specified fitting parameter'};
            app.XLogScaleSwitch_2Label = uilabel(app.SingleMoleculePanel);
            app.XLogScaleSwitch_2Label.Text = 'X Log Scale';
            app.XLogScaleSwitch_2 = uiswitch(app.SingleMoleculePanel, 'slider');
            app.XLogScaleSwitch_2.ValueChangedFcn = createCallbackFcn(app, @XLogScaleSwitch_2ValueChanged, true);
            app.XLogScaleSwitch_2.Tooltip = {'Switch to log scale on X-axis'};
            app.SavedataButton = uibutton(app.SingleMoleculePanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Tooltip = {'Click this button to produce figures that can be saved on disk.'};
            app.SavedataButton.Text = 'Save data';
            app.ShowSMTracesButton = uibutton(app.SingleMoleculePanel, 'push');
            app.ShowSMTracesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSMTracesButtonPushed, true);
            app.ShowSMTracesButton.Tooltip = {'Click this button to  show single molecule traces'};
            app.ShowSMTracesButton.Text = 'Show SM Traces';
            app.AnalyzeDiffusionStatesButton = uibutton(app.SingleMoleculePanel, 'push');
            app.AnalyzeDiffusionStatesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDiffusionStatesButtonPushed, true);
            app.AnalyzeDiffusionStatesButton.Tooltip = {'Click this button to open a window for the analysis of diffusion (Single Particle Tracking) data'};
            app.AnalyzeDiffusionStatesButton.Text = 'Analyze Diffusion States';
            app.HistogramBinsEditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.HistogramBinsEditFieldLabel.Text = 'Histogram Bins';
            app.HistogramBinsEditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.HistogramBinsEditField.Limits = [2 10000];
            app.HistogramBinsEditField.ValueDisplayFormat = '%.0f';
            app.HistogramBinsEditField.ValueChangedFcn = createCallbackFcn(app, @HistogramBinsEditFieldValueChanged, true);
            app.HistogramBinsEditField.Tooltip = {'Set the number of bins for the histograms'};
            app.HistogramBinsEditField.Value = 25;
            app.Fitparameter1EditField_2Label = uilabel(app.SingleMoleculePanel);
            app.Fitparameter1EditField_2Label.Text = 'Fit parameter 1';
            app.Fitparameter1EditField_2 = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Fitparameter1EditField_2.Editable = 'off';
            app.Fitparameter1EditField_2.Tooltip = {'Result of specified fitting parameter'};
            app.pvalueEditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.pvalueEditField.Editable = 'off';
            app.pvalueEditField.Tooltip = {'Results of p-value (probability to bleach divided by sum of probabilities to blink and bleach)  fitting'};
            app.ChannelSwitch = uiswitch(app.SingleMoleculePanel, 'rocker');
            app.ChannelSwitch.Items = {'Ch1', 'Ch2'};
            app.ChannelSwitch.ValueChangedFcn = createCallbackFcn(app, @ChannelSwitchValueChanged, true);
            app.ChannelSwitch.Tooltip = {'Toggle channel if multichannel experiment'};
            app.ChannelSwitch.Value = 'Ch1';
            app.Info3EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Info3EditFieldLabel.Text = 'Info3';
            app.Info3EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Info3EditField.Editable = 'off';
            app.Info4EditFieldLabel = uilabel(app.SingleMoleculePanel);
            app.Info4EditFieldLabel.Text = 'Info4';
            app.Info4EditField = uieditfield(app.SingleMoleculePanel, 'numeric');
            app.Info4EditField.Editable = 'off';
            app.YLogScaleSwitch_2Label = uilabel(app.SingleMoleculePanel);
            app.YLogScaleSwitch_2Label.Text = 'Y Log Scale';
            app.YLogScaleSwitch_2 = uiswitch(app.SingleMoleculePanel, 'slider');
            app.YLogScaleSwitch_2.ValueChangedFcn = createCallbackFcn(app, @YLogScaleSwitch_2ValueChanged, true);
            app.YLogScaleSwitch_2.Tooltip = {'Switch to log scale on Y-axis'};
            app.ForceNBinsCheckBox = uicheckbox(app.SingleMoleculePanel);
            app.ForceNBinsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceNBinsCheckBoxValueChanged, true);
            app.ForceNBinsCheckBox.Tooltip = {'Tick this box to force using the specified number of bins. Otherwise, the number of bins is automatically adjusted if needed.'};
            app.ForceNBinsCheckBox.Text = 'Force N Bins';
            app.DatasetNameEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetNameEditFieldLabel.Text = 'Dataset Name';
            app.DatasetNameEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetNameEditField.Editable = 'off';
            app.DatasetNameEditField.Tooltip = {'Filename of current SMIS data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyzeResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end