classdef BackgroundFluorescence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TexturedADUperpixelChannel2EditField  matlab.ui.control.NumericEditField
        TexturedADUperpixelChannel2EditFieldLabel  matlab.ui.control.Label
        TexturedADUperpixelChannel1EditField  matlab.ui.control.NumericEditField
        TexturedADUperpixelChannel1EditFieldLabel  matlab.ui.control.Label
        ADUperpixelChannel2EditField    matlab.ui.control.NumericEditField
        ADUperpixelChannel2EditFieldLabel  matlab.ui.control.Label
        ADUperpixelChannel1EditField    matlab.ui.control.NumericEditField
        ADUperpixelChannel1EditFieldLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        UITableICh2Tex                  matlab.ui.control.Table
        UITableICh1Tex                  matlab.ui.control.Table
        UITableICh2                     matlab.ui.control.Table
        UITableICh1                     matlab.ui.control.Table
        TexturedDecayRateEditField      matlab.ui.control.NumericEditField
        TexturedDecayRateEditFieldLabel  matlab.ui.control.Label
        AdjustTexturedBackgroundSwitch  matlab.ui.control.Switch
        AdjustTexturedBackgroundSwitchLabel  matlab.ui.control.Label
        TexturedIntensityChannel2EditField  matlab.ui.control.NumericEditField
        TexturedIntensityChannel2EditFieldLabel  matlab.ui.control.Label
        TexturedIntensityChannel1EditField  matlab.ui.control.NumericEditField
        TexturedIntensityChannel1EditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundFileEditField  matlab.ui.control.EditField
        TexturedBackgroundFileEditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundDirectoryEditField  matlab.ui.control.EditField
        TexturedBackgroundDirectoryEditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundSwitch        matlab.ui.control.Switch
        TexturedBackgroundSwitchLabel   matlab.ui.control.Label
        DecayRateEditField              matlab.ui.control.NumericEditField
        DecayRateEditFieldLabel         matlab.ui.control.Label
        AdjustBackgroundSwitch          matlab.ui.control.Switch
        AdjustBackgroundSwitchLabel     matlab.ui.control.Label
        IntensityChannel2EditField      matlab.ui.control.NumericEditField
        IntensityChannel2EditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        BackgroundFluorescenceLabel     matlab.ui.control.Label
        IntensityChannel1EditField      matlab.ui.control.NumericEditField
        IntensityChannel1EditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        BGpar=[]; % Initialize the BG parameters
        IMpar=[]; % Initialize the Imaging parameters
        % two_channel=[]; % Initialize two_channel
        % lasers=[]; % Initialize lasers
        % laser_names=[]; % Initialize laser names
        % det=[]; % Initialize detector parameters
        % frametime=[]; % Initialize frametime
        TableLength=[]; % Initialize length of tables
        TextureDir=[]; % Initialize directory for texture files
        ImageSize=[]; % Initialize image size for texture files
    end
    
    methods (Access = private)
        
        function UpdateBGIntensity2ADU(app)
            
            
            % Define variables to ensure compatibility with
            % get_background_ch_pct.m 

            det_im.emccd_im_ch1=0; % Set image to single pixel with 0 value 
            det_im.emccd_im_ch2=0;
            im_par.n=app.IMpar.n;
            im_par.m=app.IMpar.m;
            im_par.nz=1;
            im_par.current_frame=1;
            
            im_par.raster=app.IMpar.raster;
            im_par.frametime=app.IMpar.frametime;

            im_par.bg.bg_ch1=app.BGpar.bg_ch1;
            im_par.bg.bg_ch2=app.BGpar.bg_ch2;
            im_par.bg.bg_laser_sensitivity_ch1=app.BGpar.bg_laser_sensitivity_ch1;
            im_par.bg.bg_laser_sensitivity_ch2=app.BGpar.bg_laser_sensitivity_ch2;
            im_par.bg.adjust_bg=app.BGpar.adjust_bg;
            
            im_par.obj.critical_angle=asin(app.IMpar.obj.obj_immersion_sample/app.IMpar.obj.obj_immersion_indice);
            im_par.obj.immersion_indice=app.IMpar.obj.obj_immersion_indice;
            im_par.obj.sample_indice=app.IMpar.obj.obj_immersion_sample;

            im_par.two_channel=0; % 
            attenuation_factor=1; % No attenuation, as value is provided for first frame

            % Do the first channel
            channel=1;

            %This will give the bg per pixel in photons
            [~, bg_level]=get_background_ch_pct(det_im, im_par, attenuation_factor, app.IMpar.lasers, channel);
            
            % Convert to ADUs (see and get_camera_signal_pct.m)
            app.BGpar.bg_ADU_ch1=double((bg_level*app.IMpar.det.det_e2p*app.IMpar.det.det_EMCCD_gain)/app.IMpar.det.det_e2ADU);


            % tmp_signal=get_camera_signal_pct(det_im, im_par);
            % app.BGpar.bg_ADU_ch1=tmp_signal.emccd_im_ch1;    
            

            % Do the second channel if necessary
            if app.IMpar.two_channel==1
                channel=2;

                %This will give the bg per pixel in photons
                [~, bg_level]=get_background_ch_pct(det_im, im_par, attenuation_factor, app.IMpar.lasers, channel);
               
                % Convert to ADUs (see and get_camera_signal_pct.m)
                app.BGpar.bg_ADU_ch2=double((bg_level*app.IMpar.det.det_e2p*app.IMpar.det.det_EMCCD_gain)/app.IMpar.det.det_e2ADU);
            else
                app.BGpar.bg_ADU_ch2=[];
            end


            % Fill up the GUI
            app.IntensityChannel1EditField.Value = app.BGpar.bg_ch1;
            app.IntensityChannel2EditField.Value = app.BGpar.bg_ch2;
            if ~isempty(app.BGpar.bg_ADU_ch1)
                app.ADUperpixelChannel1EditField.Value = app.BGpar.bg_ADU_ch1;
                app.ADUperpixelChannel1EditField.Enable = 'on';
            else
                warndlg('Laser power densities are not yet defined ! Setup lasers to see ADU values ! ','Warning');
                app.ADUperpixelChannel1EditField.Enable = 'off';
                app.ADUperpixelChannel2EditField.Enable = 'off';
            end
            if ~isempty(app.BGpar.bg_ADU_ch2)
                app.ADUperpixelChannel2EditField.Value = app.BGpar.bg_ADU_ch2;
                app.ADUperpixelChannel1EditField.Enable = 'on';
            end
        end
        
        function UpdateTXBGIntensity2ADU(app)
            
            % Define variables to ensure compatibility with
            % get_tx_background_ch_pct.m 

            det_im.emccd_im_ch1=0; % Set image to single pixel with 0 value 
            det_im.emccd_im_ch2=0;
            im_par.n=app.IMpar.n;
            im_par.m=app.IMpar.m;
            im_par.nz=1;
            im_par.current_frame=1;
            
            im_par.raster=app.IMpar.raster;
            im_par.frametime=app.IMpar.frametime;

            im_par.bg.textured_bg_ch1=app.BGpar.textured_bg_ch1;
            im_par.bg.textured_bg_ch2=app.BGpar.textured_bg_ch2;
            im_par.bg.textured_bg_laser_sensitivity_ch1=app.BGpar.textured_bg_laser_sensitivity_ch1;
            im_par.bg.textured_bg_laser_sensitivity_ch2=app.BGpar.textured_bg_laser_sensitivity_ch2;
            im_par.bg.adjust_bg=app.BGpar.adjust_bg;
            im_par.bg.adjust_textured_bg=app.BGpar.adjust_textured_bg;
            
            im_par.obj.critical_angle=asin(app.IMpar.obj.obj_immersion_sample/app.IMpar.obj.obj_immersion_indice);
            im_par.obj.immersion_indice=app.IMpar.obj.obj_immersion_indice;
            im_par.obj.sample_indice=app.IMpar.obj.obj_immersion_sample;

            tx_pattern=app.BGpar.textured_bg_pattern;
          
            % Proceed if there is a tx_pattern
            if ~isempty(tx_pattern)
                im_par.two_channel=0; %
                attenuation_factor=1; % No attenuation, as value is provided for first frame

                % Do the first channel
                channel=1;

                %This will give the bg per pixel in photons
                [~, bg_level]=get_tx_background_ch_pct(det_im, im_par, tx_pattern, attenuation_factor, app.IMpar.lasers, channel);

                % Convert to ADUs (see and get_camera_signal_pct.m)
                app.BGpar.textured_bg_ADU_ch1=double((bg_level*app.IMpar.det.det_e2p*app.IMpar.det.det_EMCCD_gain)/app.IMpar.det.det_e2ADU);


                % tmp_signal=get_camera_signal_pct(det_im, im_par);
                % app.BGpar.bg_ADU_ch1=tmp_signal.emccd_im_ch1;


                % Do the second channel if necessary
                if app.IMpar.two_channel==1
                    channel=2;

                    %This will give the bg per pixel in photons
                    [~, bg_level]=get_tx_background_ch_pct(det_im, im_par, tx_pattern, attenuation_factor, app.IMpar.lasers, channel);

                    % Convert to ADUs (see and get_camera_signal_pct.m)
                    app.BGpar.textured_bg_ADU_ch2=double((bg_level*app.IMpar.det.det_e2p*app.IMpar.det.det_EMCCD_gain)/app.IMpar.det.det_e2ADU);
                else
                    app.BGpar.textured_bg_ADU_ch2=[];
                end
            else
                app.BGpar.textured_bg_ADU_ch1=[];
                app.BGpar.textured_bg_ADU_ch2=[];
            end


            % Fill up the GUI
            app.TexturedIntensityChannel1EditField.Value = app.BGpar.textured_bg_ch1;
            app.TexturedIntensityChannel2EditField.Value = app.BGpar.textured_bg_ch2;
            if ~isempty(app.BGpar.textured_bg_ADU_ch1)
                app.TexturedADUperpixelChannel1EditField.Value = app.BGpar.textured_bg_ADU_ch1;
                app.TexturedADUperpixelChannel1EditField.Enable = 'on';
            else
                % warndlg('Texture image or laser power densities not yet defined !','Warning');
                app.TexturedADUperpixelChannel1EditField.Enable = 'off';
                app.TexturedADUperpixelChannel2EditField.Enable = 'off';
            end
            if ~isempty(app.BGpar.textured_bg_ADU_ch2)
                app.TexturedADUperpixelChannel2EditField.Value = app.BGpar.textured_bg_ADU_ch2;
                app.TexturedADUperpixelChannel2EditField.Enable = 'on';
            end
        end     
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize EMCCDpar
            app.BGpar=mainapp.smis_gui_parameters.BG;
            % Initialize two_channel
            app.IMpar.two_channel=mainapp.smis_gui_parameters.TwoChannel.state;
            % Initialize laser names
            app.IMpar.lasers=mainapp.smis_gui_parameters.lasers;
            app.IMpar.det=mainapp.smis_gui_parameters.EMCCD;
            app.IMpar.lasers_names={app.IMpar.lasers.name};
            app.IMpar.obj=mainapp.smis_gui_parameters.obj_and_psf;
            app.IMpar.raster=mainapp.smis_gui_parameters.raster;
            app.IMpar.frametime=mainapp.smis_gui_parameters.frametime;
            % Initialize directory for texture files
            app.TextureDir=fullfile(mainapp.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D');
            app.IMpar.n_lasers=numel(app.IMpar.lasers);
            app.TableLength=min([100+100*app.IMpar.n_lasers,app.UIFigure.Position(3)-100]); % Used to adjust the length of tables
            
            % Initialize image size for texture files
            app.IMpar.n=mainapp.smis_gui_parameters.image_size.n;
            app.IMpar.m=mainapp.smis_gui_parameters.image_size.m;
            app.ImageSize=mainapp.smis_gui_parameters.binning.*[app.IMpar.n,app.IMpar.m];
            
            %Update lasers to get power density at center
            app.IMpar.lasers = get_beam_profile(app.IMpar.n_lasers, app.IMpar.lasers, mainapp.smis_gui_parameters);
            % Update UI with input values
            % app.IntensityChannel1EditField.Value = app.BGpar.bg_ch1;
            % app.IntensityChannel2EditField.Value = app.BGpar.bg_ch2;
            
            %Get the expected background in ADU units
            UpdateBGIntensity2ADU(app);
            if app.BGpar.add_textured_bg==1
                UpdateTXBGIntensity2ADU(app);
            end

            % app.ADUperpixelChannel1EditField.Value = app.BGpar.bg_ADU_ch1;
            % app.ADUperpixelChannel2EditField.Value = app.BGpar.bg_ADU_ch2;
            
            app.AdjustBackgroundSwitch.Value = on2one(app.BGpar.adjust_bg,'Off','On');
            app.UITableICh1.ColumnName=app.IMpar.lasers_names;
            app.UITableICh1.Data = zeros(1,app.IMpar.n_lasers);
            app.UITableICh1.Data(1,:) = app.BGpar.bg_laser_sensitivity_ch1;
            app.UITableICh2.ColumnName=app.IMpar.lasers_names;
            app.UITableICh2.Data = zeros(1,app.IMpar.n_lasers);
            app.UITableICh2.Data(1,:) = app.BGpar.bg_laser_sensitivity_ch2;
            app.DecayRateEditField.Value = app.BGpar.bg_decay_rate;
            
            app.TexturedBackgroundSwitch.Value = on2one(app.BGpar.add_textured_bg,'Off','On');
            app.TexturedBackgroundDirectoryEditField.Value = app.BGpar.textured_bg_pattern_dir;
            app.TexturedBackgroundFileEditField.Value = app.BGpar.textured_bg_pattern_file;
            app.TexturedIntensityChannel1EditField.Value = app.BGpar.textured_bg_ch1;
            app.TexturedIntensityChannel2EditField.Value = app.BGpar.textured_bg_ch2;
            
            app.AdjustTexturedBackgroundSwitch.Value = on2one(app.BGpar.adjust_textured_bg,'Off','On');
            app.UITableICh1Tex.ColumnName=app.IMpar.lasers_names;
            app.UITableICh1Tex.Data = zeros(1,app.IMpar.n_lasers);
            app.UITableICh1Tex.Data(1,:) = app.BGpar.textured_bg_laser_sensitivity_ch1;
            app.UITableICh2Tex.ColumnName=app.IMpar.lasers_names;
            app.UITableICh2Tex.Data = zeros(1,app.IMpar.n_lasers);
            app.UITableICh2Tex.Data(1,:) = app.BGpar.textured_bg_laser_sensitivity_ch2;
            app.TexturedDecayRateEditField.Value = app.BGpar.textured_bg_decay_rate;
            
            % Make some fields visible
            if app.IMpar.two_channel==1
                app.IntensityChannel2EditField.Visible = 'on';
                app.IntensityChannel2EditFieldLabel.Visible = 'on';
                app.ADUperpixelChannel2EditField.Visible= 'on';
                app.ADUperpixelChannel2EditFieldLabel.Visible= 'on';
            else
                app.IntensityChannel2EditField.Visible = 'off';
                app.IntensityChannel2EditFieldLabel.Visible = 'off';
                app.ADUperpixelChannel2EditField.Visible= 'off';
                app.ADUperpixelChannel2EditFieldLabel.Visible= 'off';
            end
            
            if app.BGpar.adjust_bg==1
                app.UITableICh1.Visible = 'on';
                
                % Setup Table Position size
                app.UITableICh1.Position=[27,513,app.TableLength,51]; % Adjust position
                app.DecayRateEditField.Visible = 'on';
                app.DecayRateEditFieldLabel.Visible = 'on';
                if app.IMpar.two_channel==1
                    app.UITableICh2.Position=[27,456,app.TableLength,51]; % Adjust position
                    app.UITableICh2.Visible = 'on';
                else
                    app.UITableICh2.Visible = 'off';
                end
            else
                app.UITableICh1.Visible = 'off';
                app.UITableICh2.Visible = 'off';
                app.DecayRateEditField.Visible = 'off';
                app.DecayRateEditFieldLabel.Visible = 'off';
            end
            
            if app.BGpar.add_textured_bg==1
                app.LoadFileButton.Visible='on';
                app.TexturedBackgroundDirectoryEditField.Visible = 'on';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'on';
                app.TexturedBackgroundFileEditField.Visible = 'on';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'on';
                app.AdjustTexturedBackgroundSwitch.Visible='on';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='on';
                app.TexturedIntensityChannel1EditField.Visible='on';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='on';
                app.TexturedADUperpixelChannel1EditField.Visible='on';
                app.TexturedADUperpixelChannel1EditFieldLabel.Visible='on';
                if app.IMpar.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='on';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='on';
                    app.TexturedADUperpixelChannel2EditField.Visible='on';
                    app.TexturedADUperpixelChannel2EditFieldLabel.Visible='on';
                else
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                    app.TexturedADUperpixelChannel2EditField.Visible='off';
                    app.TexturedADUperpixelChannel2EditFieldLabel.Visible='off';
                end
                
                if app.BGpar.adjust_textured_bg==1
                    app.UITableICh1Tex.Position=[27,178,app.TableLength,51]; % Adjust position
                    app.UITableICh1Tex.Visible = 'on';
                    app.TexturedDecayRateEditField.Visible = 'on';
                    app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                    if app.IMpar.two_channel==1
                        app.UITableICh2Tex.Position=[27,121,app.TableLength,51]; % Adjust position
                        app.UITableICh2Tex.Visible = 'on';
                    end
                end
                
            else
                app.LoadFileButton.Visible='off';
                app.TexturedBackgroundDirectoryEditField.Visible = 'off';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'off';
                app.TexturedBackgroundFileEditField.Visible = 'off';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'off';
                app.AdjustTexturedBackgroundSwitch.Visible='off';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='off';
                app.TexturedIntensityChannel1EditField.Visible='off';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='off';
                app.TexturedADUperpixelChannel1EditField.Visible='off';
                app.TexturedADUperpixelChannel1EditFieldLabel.Visible='off';
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                app.TexturedIntensityChannel2EditField.Visible='off';
                app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                app.TexturedADUperpixelChannel2EditField.Visible='off';
                app.TexturedADUperpixelChannel2EditFieldLabel.Visible='off';
                app.UITableICh2Tex.Visible = 'off';
            end
            
            if app.BGpar.adjust_textured_bg==1 && app.BGpar.add_textured_bg==1
                app.UITableICh1Tex.Visible = 'on';
                app.TexturedDecayRateEditField.Visible = 'on';
                app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                if app.IMpar.two_channel==1
                    app.UITableICh2Tex.Visible = 'on';
                else
                    app.UITableICh2Tex.Visible = 'off';
                end
            else
                app.UITableICh1Tex.Visible = 'off';
                app.UITableICh2Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Check that the fractional laser dependences at up to 1
            is_OK=1;
            
            if app.BGpar.add_textured_bg==1
                MyFile = app.BGpar.textured_bg_pattern_file;
                MyDir = app.BGpar.textured_bg_pattern_dir;
                % Check that the file exists
                if exist(fullfile(MyDir,MyFile),'file')~=2
                    MyDlg=warndlg('A texture image file has not been loaded !','Warning');
                    waitfor(MyDlg)
                    is_OK=0;
                else
                    if isempty(app.BGpar.textured_bg_pattern)
                        app.BGpar.textured_bg_pattern=imread(fullfile(MyDir,MyFile));
                    end
                end
            end
           
            if is_OK==1
                % Call main app's public function
                updateBG(app.CallingApp, app.BGpar)
                % Delete the dialog box
                delete(app)
            end
        end

        % Value changed function: IntensityChannel1EditField
        function IntensityChannel1EditFieldValueChanged(app, event)
            value = app.IntensityChannel1EditField.Value;
            app.BGpar.bg_ch1=value;
            UpdateBGIntensity2ADU(app);
        end

        % Value changed function: IntensityChannel2EditField
        function IntensityChannel2EditFieldValueChanged(app, event)
            value = app.IntensityChannel2EditField.Value;
            app.BGpar.bg_ch2=value;
            UpdateBGIntensity2ADU(app);
        end

        % Callback function
        function LaserSensitivityChannel1EditFieldValueChanged(app, event)
            value = app.LaserSensitivityChannel1EditField.Value;
            app.BGpar.bg_laser_sensitivity_ch1=value;
        end

        % Callback function
        function LaserSensitivityChannel2EditFieldValueChanged(app, event)
            value = app.LaserSensitivityChannel2EditField.Value;
            app.BGpar.bg_laser_sensitivity_ch2=value;
        end

        % Value changed function: DecayRateEditField
        function DecayRateEditFieldValueChanged(app, event)
            value = app.DecayRateEditField.Value;
            app.BGpar.bg_decay_rate=value;
        end

        % Value changed function: TexturedBackgroundSwitch
        function TexturedBackgroundSwitchValueChanged(app, event)
            value = app.TexturedBackgroundSwitch.Value;
            app.BGpar.add_textured_bg=on2one(value,'Off','On');
            
            if strcmp(value,'On')
                app.LoadFileButton.Visible='on';
                app.TexturedBackgroundDirectoryEditField.Visible = 'on';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'on';
                app.TexturedBackgroundFileEditField.Visible = 'on';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'on';
                app.AdjustTexturedBackgroundSwitch.Visible='on';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='on';
                app.TexturedIntensityChannel1EditField.Visible='on';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='on';
                app.TexturedADUperpixelChannel1EditField.Visible='on';
                app.TexturedADUperpixelChannel1EditFieldLabel.Visible='on';
                if app.IMpar.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='on';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='on';
                    app.TexturedADUperpixelChannel2EditField.Visible='on';
                    app.TexturedADUperpixelChannel2EditFieldLabel.Visible='on';
                else
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                    app.TexturedADUperpixelChannel2EditField.Visible='off';
                    app.TexturedADUperpixelChannel2EditFieldLabel.Visible='off';
                end
                
                if strcmp(app.AdjustTexturedBackgroundSwitch.Value,'On')
                    app.UITableICh1Tex.Visible = 'on';
                    app.TexturedDecayRateEditField.Visible = 'on';
                    app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                    
                    if app.IMpar.two_channel==1
                        app.UITableICh2Tex.Visible = 'on';
                    end
                end
                
            else
                app.LoadFileButton.Visible='off';
                app.TexturedBackgroundDirectoryEditField.Visible = 'off';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'off';
                app.TexturedBackgroundFileEditField.Visible = 'off';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'off';
                app.AdjustTexturedBackgroundSwitch.Visible='off';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='off';
                app.TexturedIntensityChannel1EditField.Visible='off';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='off';
                app.TexturedADUperpixelChannel1EditField.Visible='off';
                app.TexturedADUperpixelChannel1EditFieldLabel.Visible='off';
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                if app.IMpar.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                    app.TexturedADUperpixelChannel2EditField.Visible='off';
                    app.TexturedADUperpixelChannel2EditFieldLabel.Visible='off';
                    app.UITableICh2Tex.Visible = 'off';
                end
                
            end
            UpdateTXBGIntensity2ADU(app);
        end

        % Value changed function: AdjustBackgroundSwitch
        function AdjustBackgroundSwitchValueChanged(app, event)
            value = app.AdjustBackgroundSwitch.Value;
            app.BGpar.adjust_bg=on2one(value,'Off','On');
            if strcmp(value,'On')
                app.UITableICh1.Position=[27,513,app.TableLength,51]; % Adjust position
                app.UITableICh1.Visible = 'on';
                app.DecayRateEditField.Visible = 'on';
                app.DecayRateEditFieldLabel.Visible = 'on';
                if app.IMpar.two_channel==1
                    app.UITableICh2.Position=[27,456,app.TableLength,51]; % Adjust position
                    app.UITableICh2.Visible = 'on';
                end
            else
                app.UITableICh1.Visible = 'off';
                app.DecayRateEditField.Visible = 'off';
                app.DecayRateEditFieldLabel.Visible = 'off';
                if app.IMpar.two_channel==1
                    app.UITableICh2.Visible = 'off';
                end
            end
        end

        % Value changed function: TexturedBackgroundDirectoryEditField
        function TexturedBackgroundDirectoryEditFieldValueChanged(app, event)
            value = app.TexturedBackgroundDirectoryEditField.Value;
            app.BGpar.textured_bg_pattern_dir=value;
        end

        % Value changed function: TexturedBackgroundFileEditField
        function TexturedBackgroundFileEditFieldValueChanged(app, event)
            MyFile = app.TexturedBackgroundFileEditField.Value;
            MyDir = app.BGpar.textured_bg_pattern_dir;
            % Check that the file exists
            if exist(fullfile(MyDir,MyFile),'file')==2
                % Check that image size is compatible
                tx_pattern=imread(fullfile(MyDir,MyFile));
                if ~isempty(app.ImageSize)
                    if size(tx_pattern,1)~=app.ImageSize(1) || size(tx_pattern,2)~=app.ImageSize(2)
                        tx_pattern=imresize(tx_pattern,[app.IMpar.n,app.IMpar.m]);
                        warndlg('Textured background image resized to match main image size in X and Y dimensions !','Warning');
                    end

                    if max(tx_pattern(:))<=0
                        MyDlg=warndlg('The texture image looks empty ! Please load another texture image.','Warning');
                        waitfor(MyDlg)
                    else
                        app.TexturedBackgroundFileEditField.Value=MyFile;
                        app.TexturedBackgroundDirectoryEditField.Value=MyDir;
                        app.BGpar.textured_bg_pattern_file=MyFile;
                        app.BGpar.textured_bg_pattern_dir=MyDir;
                        tx_pattern(tx_pattern>1)=1; % this pattern must be equal to one wherever there is signal
                        app.BGpar.textured_bg_pattern=tx_pattern;
                    end
                else
                    MyDlg=warndlg('A virtual sample must be loaded first !','Warning');
                    waitfor(MyDlg)
                end
            else
                MyDlg=warndlg('File does not exist !','Warning');
                waitfor(MyDlg)
            end
            
            
        end

        % Value changed function: TexturedIntensityChannel1EditField
        function TexturedIntensityChannel1EditFieldValueChanged(app, event)
            value = app.TexturedIntensityChannel1EditField.Value;
            app.BGpar.textured_bg_ch1=value;
            UpdateTXBGIntensity2ADU(app);
        end

        % Value changed function: TexturedIntensityChannel2EditField
        function TexturedIntensityChannel2EditFieldValueChanged(app, event)
            value = app.TexturedIntensityChannel2EditField.Value;
            app.BGpar.textured_bg_ch2=value;
            UpdateTXBGIntensity2ADU(app);
        end

        % Value changed function: AdjustTexturedBackgroundSwitch
        function AdjustTexturedBackgroundSwitchValueChanged(app, event)
            value = app.AdjustTexturedBackgroundSwitch.Value;
            app.BGpar.adjust_textured_bg=on2one(value,'Off','On');
            
            if strcmp(value,'On')
                app.UITableICh1Tex.Position=[27,178,app.TableLength,51]; % Adjust position
                app.UITableICh1Tex.Visible = 'on';
                app.TexturedDecayRateEditField.Visible = 'on';
                app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                
                if app.IMpar.two_channel==1
                    app.UITableICh2Tex.Position=[27,121,app.TableLength,51]; % Adjust position
                    app.UITableICh2Tex.Visible = 'on';
                end
            else
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                
                if app.IMpar.two_channel==1
                    app.UITableICh2Tex.Visible = 'off';
                end
            end
        end

        % Value changed function: TexturedDecayRateEditField
        function TexturedDecayRateEditFieldValueChanged(app, event)
            value = app.TexturedDecayRateEditField.Value;
            app.BGpar.textured_bg_decay_rate=value;
        end

        % Display data changed function: UITableICh1
        function UITableICh1DisplayDataChanged(app, event)
            newDisplayData = app.UITableICh1.DisplayData;
            app.BGpar.bg_laser_sensitivity_ch1=newDisplayData;
            UpdateBGIntensity2ADU(app)
        end

        % Display data changed function: UITableICh2
        function UITableICh2DisplayDataChanged(app, event)
            newDisplayData = app.UITableICh2.DisplayData;
            app.BGpar.bg_laser_sensitivity_ch2=newDisplayData;
            UpdateBGIntensity2ADU(app)
        end

        % Display data changed function: UITableICh1Tex
        function UITableICh1TexDisplayDataChanged(app, event)
            newDisplayData = app.UITableICh1Tex.DisplayData;
            app.BGpar.textured_bg_laser_sensitivity_ch1=newDisplayData;
            UpdateTXBGIntensity2ADU(app)
        end

        % Display data changed function: UITableICh2Tex
        function UITableICh2TexDisplayDataChanged(app, event)
            newDisplayData = app.UITableICh2Tex.DisplayData;
            app.BGpar.textured_bg_laser_sensitivity_ch2=newDisplayData;
            UpdateTXBGIntensity2ADU(app)
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [MyFile,MyDir] = uigetfile('*.tif*','Select a background texture image file');

            if ~isequal(MyFile,0)               
                % Check that image size is compatible
                tx_pattern=imread(fullfile(MyDir,MyFile));

                if ~isempty(app.ImageSize)
                    if size(tx_pattern,1)~=app.ImageSize(1) || size(tx_pattern,2)~=app.ImageSize(2)
                        tx_pattern=imresize(tx_pattern,[app.IMpar.n,app.IMpar.m]);
                        MyDlg=warndlg('Textured background image resized to match main image size in X and Y dimensions !','Warning');
                        waitfor(MyDlg)
                    end
                    if max(tx_pattern(:))<=0
                        MyDlg=warndlg('The texture image looks empty ! Please load another texture image.','Warning');
                        waitfor(MyDlg)
                    else
                        app.TexturedBackgroundFileEditField.Value=MyFile;
                        app.TexturedBackgroundDirectoryEditField.Value=MyDir;
                        app.BGpar.textured_bg_pattern_file=MyFile;
                        app.BGpar.textured_bg_pattern_dir=MyDir;
                        tx_pattern(tx_pattern>1)=1; % this pattern must be equal to one wherever there is signal
                        app.BGpar.textured_bg_pattern=tx_pattern;
                    end
                else
                    MyDlg=warndlg('A virtual sample must be loaded first !','Warning');
                    waitfor(MyDlg)
                end
            else
                disp('User selected Cancel');
            end
            UpdateTXBGIntensity2ADU(app)
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end

        % Value changed function: ADUperpixelChannel1EditField
        function ADUperpixelChannel1EditFieldValueChanged(app, event)
            new_ADU = app.ADUperpixelChannel1EditField.Value;
            old_ADU=event.PreviousValue;
            
            if old_ADU==0 % Use a trick here to handle the case where zero bg was specifie0
                ii=0;
                while old_ADU==0
                    ii=ii+1;
                    app.BGpar.bg_ch1=ii; % Set intensity to 1
                    UpdateBGIntensity2ADU(app); % Recalculate in ADU unit
                    old_ADU=app.BGpar.bg_ADU_ch1; % Grab it
                end
            end
            
            if ~isempty(old_ADU)
                s=new_ADU/old_ADU; % Calculate the ratio
                app.BGpar.bg_ch1=s*app.BGpar.bg_ch1; % Apply the ratio to bg intensity
                app.IntensityChannel1EditField.Value=app.BGpar.bg_ch1; % Display bg intensity
            end
            UpdateBGIntensity2ADU(app); % Display in ADU unit
                        
        end

        % Value changed function: ADUperpixelChannel2EditField
        function ADUperpixelChannel2EditFieldValueChanged(app, event)
            new_ADU = app.ADUperpixelChannel2EditField.Value;
            old_ADU=event.PreviousValue;

            if old_ADU==0 % Use a trick here to handle the case where zero bg was specifie0
                ii=0;
                while old_ADU==0
                    ii=ii+1;
                    app.BGpar.bg_ch2=ii; % Set intensity to 1
                    UpdateBGIntensity2ADU(app); % Recalculate in ADU unit
                    old_ADU=app.BGpar.bg_ADU_ch2; % Grab it
                end
            end
            
            if ~isempty(old_ADU)
                s=new_ADU/old_ADU; % Calculate the ratio
                app.BGpar.bg_ch2=s*app.BGpar.bg_ch2; % Apply the ratio to bg intensity
                app.IntensityChannel1EditField.Value=app.BGpar.bg_ch2; % Display bg intensity
            end
            UpdateBGIntensity2ADU(app); % Display in ADU unit
        end

        % Value changed function: TexturedADUperpixelChannel1EditField
        function TexturedADUperpixelChannel1EditFieldValueChanged(app, event)
            new_ADU = app.TexturedADUperpixelChannel1EditField.Value;
            old_ADU=event.PreviousValue;

            if old_ADU==0 % Use a trick here to handle the case where zero bg was specifie0
                ii=0;
                while old_ADU==0
                    ii=ii+1;
                    app.BGpar.textured_bg_ch1=ii; % Set intensity to 1
                    UpdateTXBGIntensity2ADU(app); % Recalculate in ADU unit
                    old_ADU=app.BGpar.textured_bg_ADU_ch1; % Grab it
                end
            end

            if ~isempty(old_ADU)
                s=new_ADU/old_ADU; % Calculate the ratio
                app.BGpar.textured_bg_ch1=s*app.BGpar.textured_bg_ch1; % Apply the ratio to bg intensity
                app.TexturedIntensityChannel1EditField.Value=app.BGpar.textured_bg_ch1; % Display bg intensity
            end
            UpdateTXBGIntensity2ADU(app); % Display in ADU unit

            
        end

        % Value changed function: TexturedADUperpixelChannel2EditField
        function TexturedADUperpixelChannel2EditFieldValueChanged(app, event)
            new_ADU = app.TexturedADUperpixelChannel2EditField.Value;
            old_ADU=event.PreviousValue;

            if old_ADU==0 % Use a trick here to handle the case where zero bg was specifie0
                ii=0;
                while old_ADU==0
                    ii=ii+1;
                    app.BGpar.textured_bg_ch2=ii; % Set intensity to 1
                    UpdateTXBGIntensity2ADU(app); % Recalculate in ADU unit
                    old_ADU=app.BGpar.textured_bg_ADU_ch2; % Grab it
                end
            end

            if ~isempty(old_ADU)
                s=new_ADU/old_ADU; % Calculate the ratio
                app.BGpar.textured_bg_ch2=s*app.BGpar.textured_bg_ch2; % Apply the ratio to bg intensity
                app.TexturedIntensityChannel2EditField.Value=app.BGpar.textured_bg_ch2; % Display bg intensity
            end
            UpdateTXBGIntensity2ADU(app); % Display in ADU unit

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IntensityChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.IntensityChannel1EditFieldLabel.Text = 'Intensity Channel 1';
            app.IntensityChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityChannel1EditField.Limits = [0 Inf];
            app.IntensityChannel1EditField.RoundFractionalValues = 'on';
            app.IntensityChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityChannel1EditFieldValueChanged, true);
            app.IntensityChannel1EditField.Tooltip = {'Background Intensity in Channel 1 [Ph/100x100nm²/100W/cm²/s]'};
            app.IntensityChannel1EditField.Value = 1;
            app.BackgroundFluorescenceLabel = uilabel(app.UIFigure);
            app.BackgroundFluorescenceLabel.Text = 'Background Fluorescence';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.IntensityChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.IntensityChannel2EditFieldLabel.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/Frame]'};
            app.IntensityChannel2EditFieldLabel.Text = 'Intensity Channel 2 ';
            app.IntensityChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityChannel2EditField.Limits = [0 Inf];
            app.IntensityChannel2EditField.RoundFractionalValues = 'on';
            app.IntensityChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityChannel2EditFieldValueChanged, true);
            app.IntensityChannel2EditField.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/100W/cm²/s]'};
            app.IntensityChannel2EditField.Value = 1;
            app.AdjustBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.AdjustBackgroundSwitchLabel.Text = 'Adjust Background';
            app.AdjustBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdjustBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @AdjustBackgroundSwitchValueChanged, true);
            app.AdjustBackgroundSwitch.Tooltip = {'Adjust background as a function of local laser power density'};
            app.DecayRateEditFieldLabel = uilabel(app.UIFigure);
            app.DecayRateEditFieldLabel.Text = 'Decay Rate ';
            app.DecayRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayRateEditField.Limits = [0 Inf];
            app.DecayRateEditField.ValueChangedFcn = createCallbackFcn(app, @DecayRateEditFieldValueChanged, true);
            app.DecayRateEditField.Tooltip = {'Background decay rate [uJ/100x100nm²/s]'};
            app.DecayRateEditField.Value = 1;
            app.TexturedBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundSwitchLabel.Text = 'Textured Background';
            app.TexturedBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.TexturedBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundSwitchValueChanged, true);
            app.TexturedBackgroundSwitch.Tooltip = {'Use this to force background fluorescence to follow a specific texture defined by an image pattern'};
            app.TexturedBackgroundDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundDirectoryEditFieldLabel.Text = 'Textured Background Directory';
            app.TexturedBackgroundDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.TexturedBackgroundDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundDirectoryEditFieldValueChanged, true);
            app.TexturedBackgroundFileEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundFileEditFieldLabel.Text = 'Textured Background File';
            app.TexturedBackgroundFileEditField = uieditfield(app.UIFigure, 'text');
            app.TexturedBackgroundFileEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundFileEditFieldValueChanged, true);
            app.TexturedIntensityChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedIntensityChannel1EditFieldLabel.Text = 'Textured Intensity Channel 1';
            app.TexturedIntensityChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedIntensityChannel1EditField.Limits = [0 Inf];
            app.TexturedIntensityChannel1EditField.RoundFractionalValues = 'on';
            app.TexturedIntensityChannel1EditField.ValueDisplayFormat = '%11.0d';
            app.TexturedIntensityChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedIntensityChannel1EditFieldValueChanged, true);
            app.TexturedIntensityChannel1EditField.Tooltip = {'Background Intensity in Channel 1 [Ph/100x100nm²/100W/cm²/s]'};
            app.TexturedIntensityChannel1EditField.Value = 1;
            app.TexturedIntensityChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedIntensityChannel2EditFieldLabel.Text = 'Textured Intensity Channel 2';
            app.TexturedIntensityChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedIntensityChannel2EditField.Limits = [0 Inf];
            app.TexturedIntensityChannel2EditField.RoundFractionalValues = 'on';
            app.TexturedIntensityChannel2EditField.ValueDisplayFormat = '%11.0d';
            app.TexturedIntensityChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedIntensityChannel2EditFieldValueChanged, true);
            app.TexturedIntensityChannel2EditField.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/100W/cm²/s]'};
            app.TexturedIntensityChannel2EditField.Value = 1;
            app.AdjustTexturedBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.AdjustTexturedBackgroundSwitchLabel.Text = 'Adjust Textured Background';
            app.AdjustTexturedBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdjustTexturedBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @AdjustTexturedBackgroundSwitchValueChanged, true);
            app.AdjustTexturedBackgroundSwitch.Tooltip = {'Adjust textured background as a function of local laser power density'};
            app.TexturedDecayRateEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedDecayRateEditFieldLabel.Text = 'Textured Decay Rate ';
            app.TexturedDecayRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedDecayRateEditField.Limits = [0 Inf];
            app.TexturedDecayRateEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedDecayRateEditFieldValueChanged, true);
            app.TexturedDecayRateEditField.Tooltip = {'Textured background decay rate [uJ/100x100nm²/s]'};
            app.TexturedDecayRateEditField.Value = 1;
            app.UITableICh1 = uitable(app.UIFigure);
            app.UITableICh1.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh1.RowName = {'Intensity Ch1'};
            app.UITableICh1.ColumnEditable = true;
            app.UITableICh1.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh1DisplayDataChanged, true);
            app.UITableICh1.Tooltip = {'Enter fractional dependencies of background sensitivity on the various lasers in channel 1. '};
            app.UITableICh2 = uitable(app.UIFigure);
            app.UITableICh2.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh2.RowName = {'Intensity Ch2'};
            app.UITableICh2.ColumnEditable = true;
            app.UITableICh2.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh2DisplayDataChanged, true);
            app.UITableICh2.Tooltip = {'Enter fractional dependencies of background sensitivity on the various lasers in channel 2. '};
            app.UITableICh1Tex = uitable(app.UIFigure);
            app.UITableICh1Tex.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh1Tex.RowName = {'Intensity Ch1'};
            app.UITableICh1Tex.ColumnEditable = true;
            app.UITableICh1Tex.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh1TexDisplayDataChanged, true);
            app.UITableICh1Tex.Tooltip = {'Enter fractional dependencies of  textured background sensitivity on the various lasers in channel 1.'};
            app.UITableICh2Tex = uitable(app.UIFigure);
            app.UITableICh2Tex.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh2Tex.RowName = {'Intensity Ch2'};
            app.UITableICh2Tex.ColumnEditable = true;
            app.UITableICh2Tex.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh2TexDisplayDataChanged, true);
            app.UITableICh2Tex.Tooltip = {'Enter fractional dependencies of  textured background sensitivity on the various lasers in channel 2. '};
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load texture image';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ADUperpixelChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.ADUperpixelChannel1EditFieldLabel.Text = 'ADU per pixel Channel 1';
            app.ADUperpixelChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ADUperpixelChannel1EditField.Limits = [0 Inf];
            app.ADUperpixelChannel1EditField.RoundFractionalValues = 'on';
            app.ADUperpixelChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @ADUperpixelChannel1EditFieldValueChanged, true);
            app.ADUperpixelChannel1EditField.Tooltip = {'Corresponding mean number of ADUs per pixel in Channel 1'};
            app.ADUperpixelChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.ADUperpixelChannel2EditFieldLabel.Text = 'ADU per pixel Channel 2';
            app.ADUperpixelChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ADUperpixelChannel2EditField.Limits = [0 Inf];
            app.ADUperpixelChannel2EditField.RoundFractionalValues = 'on';
            app.ADUperpixelChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @ADUperpixelChannel2EditFieldValueChanged, true);
            app.ADUperpixelChannel2EditField.Tooltip = {'Corresponding mean number of ADUs per pixel in Channel 2'};
            app.TexturedADUperpixelChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedADUperpixelChannel1EditFieldLabel.Text = 'Textured ADU per pixel Channel 1';
            app.TexturedADUperpixelChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedADUperpixelChannel1EditField.Limits = [0 Inf];
            app.TexturedADUperpixelChannel1EditField.RoundFractionalValues = 'on';
            app.TexturedADUperpixelChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedADUperpixelChannel1EditFieldValueChanged, true);
            app.TexturedADUperpixelChannel1EditField.Tooltip = {'Corresponding mean number of ADUs per pixel in Channel 1'};
            app.TexturedADUperpixelChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedADUperpixelChannel2EditFieldLabel.Text = 'Textured ADU per pixel Channel 2';
            app.TexturedADUperpixelChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedADUperpixelChannel2EditField.Limits = [0 Inf];
            app.TexturedADUperpixelChannel2EditField.RoundFractionalValues = 'on';
            app.TexturedADUperpixelChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedADUperpixelChannel2EditFieldValueChanged, true);
            app.TexturedADUperpixelChannel2EditField.Tooltip = {'Corresponding mean number of ADUs per pixel in Channel 2'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackgroundFluorescence(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end