classdef CheckFRAPPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ZRasterSpinner      matlab.ui.control.Spinner
        ZRasterSlider       matlab.ui.control.Slider
        ZRasterSliderLabel  matlab.ui.control.Label
        OKButton            matlab.ui.control.Button
        OverlayofvirtualsampleandFRAPpatterninredLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Pattern=[]; % The 2D or 3D Virtutal sample pattern
        FRAP_Pattern=[]; % The 2D FRAP Pattern
        MinDisp=[]; % Min of Pattern
        MaxDisp=[]; % Max of Pattern
        binning=[]; % Binning parameter
    end
    
    methods (Access = private)


        function show2Dsection(app)
            
            ClearImage(app)
            
            colormap(app.UIAxes,'gray')
            
            section=app.Pattern(:,:,round(app.ZRasterSlider.Value));
                        
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            hold(app.UIAxes,'on')
            clims = [app.MinDisp, app.MaxDisp];
            imagesc(app.UIAxes,section,clims);
            axis(app.UIAxes,"image");
            
            w=find(app.FRAP_Pattern~=0);
            [y,x]=ind2sub(size(app.FRAP_Pattern),w);
            plot(app.UIAxes,app.binning*x,app.binning*y,'r.', 'MarkerSize', app.binning*3);
            hold(app.UIAxes,'off')
        end
        
        function ClearImage(app)
            MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
        
        function show2D(app)
            
            ClearImage(app)
            
            colormap(app.UIAxes,'gray')
            
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            hold(app.UIAxes,'on')
            clims = [app.MinDisp, app.MaxDisp];
            imagesc(app.UIAxes,app.Pattern,clims);
            axis(app.UIAxes,"image");
            
            w=find(app.FRAP_Pattern~=0);
            [y,x]=ind2sub(size(app.FRAP_Pattern),w);
            plot(app.UIAxes,app.binning*x,app.binning*y,'r.', 'MarkerSize', app.binning*3);
            hold(app.UIAxes,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Pattern, FRAP_Pattern, binning)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.Pattern=Pattern;
            app.MinDisp=min(app.Pattern(:));
            app.MaxDisp=max(app.Pattern(:));
            app.binning=binning;
            
            app.FRAP_Pattern=FRAP_Pattern;
            s=size(Pattern,3);
            
            if s==1 % 2D case
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                show2D(app)
            elseif s>1 % 3D case
                app.ZRasterSlider.Limits=[1,s];
                app.ZRasterSlider.MajorTicks=[1,(1:4)*s/4];
                app.ZRasterSlider.Value=round(size(Pattern,3)/2);
                app.ZRasterSpinner.Value=app.ZRasterSlider.Value;
                show2Dsection(app);
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ZRasterSlider
        function ZRasterSliderValueChanged(app, event)
            value = app.ZRasterSlider.Value;
            app.ZRasterSpinner.Value=round(value);
            show2Dsection(app);
        end

        % Value changed function: ZRasterSpinner
        function ZRasterSpinnerValueChanged(app, event)
            value = app.ZRasterSpinner.Value;
            app.ZRasterSlider.Value=value;
            show2Dsection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YDir = 'reverse';
            app.OverlayofvirtualsampleandFRAPpatterninredLabel = uilabel(app.UIFigure);
            app.OverlayofvirtualsampleandFRAPpatterninredLabel.Text = 'Overlay of virtual sample and FRAP pattern (in red)';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ZRasterSliderLabel = uilabel(app.UIFigure);
            app.ZRasterSliderLabel.Text = 'Z [Raster]';
            app.ZRasterSlider = uislider(app.UIFigure);
            app.ZRasterSlider.ValueChangedFcn = createCallbackFcn(app, @ZRasterSliderValueChanged, true);
            app.ZRasterSpinner = uispinner(app.UIFigure);
            app.ZRasterSpinner.ValueChangedFcn = createCallbackFcn(app, @ZRasterSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckFRAPPattern(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end