classdef CheckPhotoPhysicalStates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CheckingofFluorophorePhotophysicalStatesLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        FluorophorenameEditFieldLabel   matlab.ui.control.Label
        FluorophorenameEditField        matlab.ui.control.EditField
        pHSensitivitySwitchLabel        matlab.ui.control.Label
        pHSensitivitySwitch             matlab.ui.control.Switch
        pKaTable                        matlab.ui.control.Table
        NumberofphotophysicalstatesEditFieldLabel  matlab.ui.control.Label
        NumberofphotophysicalstatesEditField  matlab.ui.control.NumericEditField
        PhotoactivedarkstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        PhotoactivedarkstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        FluorescentstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        FluorescentstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        NameofconvertedstateEditFieldLabel  matlab.ui.control.Label
        NameofconvertedstateEditField   matlab.ui.control.EditField
        FluorophoreisirreversiblyphotoconvertibleLabel  matlab.ui.control.Label
        FluorescentstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        FluorescentstatesafterphotoconversionEditField  matlab.ui.control.EditField
        BleachedstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        BleachedstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        BleachedstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        BleachedstatesafterphotoconversionEditField  matlab.ui.control.EditField
        PhotoactivedarkstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        PhotoactivedarkstatesafterphotoconversionEditField  matlab.ui.control.EditField
        ThermaldarkstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        ThermaldarkstatesafterphotoconversionEditField  matlab.ui.control.EditField
        ThermaldarkstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        ThermaldarkstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        InitialstateEditFieldLabel      matlab.ui.control.Label
        InitialstateEditField           matlab.ui.control.EditField
        PairsofstatesinrapidequilibriumEditFieldLabel  matlab.ui.control.Label
        PairsofstatesinrapidequilibriumEditField  matlab.ui.control.EditField
        QYTable                         matlab.ui.control.Table
        FluorescenceQuantumYieldsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        
        smis_gui_parameters=[]; % Initialize general parameters
        Fluorophorepar=[]; % Initialize the parameters
        OtherFluorophorepar=[]; % Initialize the parameters
        selected_fluorophore=[] ; % Initialize selected fluorophore
        n_fluorophores=[] ; % Initialize number of fluorophores
        n_states=[] ; % Initialize number of photophysical states
        n_fluo_states=[] ; % Initialize number of fluorescent states
        n_sp=[] ; % Initialize number of sub-patterns
        sp_ids=[] ; % Initialize sub-patterns ids
        state_names=[] ; % Initialize state names
        QY_App=[]; % Phototransformation quantum yield setup App
        QYSetup_Position=[]; % Description
        kT_App=[]; % Phototransformation quantum yield setup App
        kTSetup_Position=[]; % Description
    end
    
    methods (Access = private)
        
        function UpdateFluorophoreSetup(app)
            app.FluorophorenameEditField.Value = app.Fluorophorepar.name;
            
            % Display Oligomer stoichiometry or number of fluorophores
            if app.smis_gui_parameters.distribute_in_clusters==1
                app.OligomerstochiometryEditField.Value=fix(app.Fluorophorepar.N/app.smis_gui_parameters.n_clusters(app.selected_fluorophore)); % In case of qPALM
            else
                app.NumberoffluorophoresEditField.Value=app.Fluorophorepar.N;
            end
            
            % Display photophysical states
            app.n_states=app.Fluorophorepar.Photophysics.n_states; % Number of photophysical states
            app.n_fluo_states=numel(app.Fluorophorepar.Photophysics.fluorescent_states); % Number of fluorescent states
            app.state_names=app.Fluorophorepar.Photophysics.state_names;
            converted_state=app.Fluorophorepar.Photophysics.converted_state;
            initial_state=app.Fluorophorepar.Photophysics.initial_state;
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            photoactive_dark_states=setdiff(app.Fluorophorepar.Photophysics.photoactive_states,fluo_states);
            photoactive_thermal_states=setdiff(1:app.n_states,horzcat(app.Fluorophorepar.Photophysics.photoactive_states,bleached_states));
            states_ids=app.Fluorophorepar.Photophysics.state_ids;
            
            app.NumberofphotophysicalstatesEditField.Value=app.n_states;
            app.InitialstateEditField.Value=app.state_names{initial_state};
            
            if converted_state>1 % Fluorophore is photoconvertible
                % Before photoconversion
                app.FluorophoreisirreversiblyphotoconvertibleLabel.Text='Fluorophore is irreversibly photoconvertible';
                app.NameofconvertedstateEditField.Visible='on';
                app.NameofconvertedstateEditFieldLabel.Visible='on';
                app.NameofconvertedstateEditField.Value=app.state_names{converted_state};
                app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text='Fluorescent states before photoconversion';
                app.FluorescentstatesbeforephotoconversionEditField.Value=get_names(app,fluo_states(fluo_states<converted_state));
                app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text='Photoactive dark states before photoconversion';
                app.PhotoactivedarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_dark_states(photoactive_dark_states<converted_state));
                app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text='Thermal dark states before photoconversion';
                app.ThermaldarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_thermal_states(photoactive_thermal_states<converted_state));
                app.BleachedstatesbeforephotoconversionEditFieldLabel.Text='Bleached states before photoconversion';
                app.BleachedstatesbeforephotoconversionEditField.Value=get_names(app,bleached_states(bleached_states<converted_state));
                
                % After photoconversion
                app.FluorescentstatesafterphotoconversionEditField.Visible='on';
                app.FluorescentstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.FluorescentstatesafterphotoconversionEditField.Value=get_names(app,fluo_states(fluo_states>=converted_state));
                app.PhotoactivedarkstatesafterphotoconversionEditField.Visible='on';
                app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.PhotoactivedarkstatesafterphotoconversionEditField.Value=get_names(app,photoactive_dark_states(photoactive_dark_states>=converted_state));
                app.ThermaldarkstatesafterphotoconversionEditField.Visible='on';
                app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.ThermaldarkstatesafterphotoconversionEditField.Value=get_names(app,photoactive_thermal_states(photoactive_thermal_states>=converted_state));
                app.BleachedstatesafterphotoconversionEditField.Visible='on';
                app.BleachedstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.BleachedstatesafterphotoconversionEditField.Value=get_names(app,bleached_states(bleached_states>=converted_state));
            else
                app.FluorophoreisirreversiblyphotoconvertibleLabel.Text='Fluorophore is not photoconvertible';
                app.NameofconvertedstateEditField.Visible='off';
                app.NameofconvertedstateEditFieldLabel.Visible='off';
                app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text='Fluorescent states';
                app.FluorescentstatesbeforephotoconversionEditField.Value=get_names(app,fluo_states);
                app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text='Photoactive dark states';
                app.PhotoactivedarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_dark_states);
                app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text='Thermal dark states';
                app.ThermaldarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_thermal_states);
                app.BleachedstatesbeforephotoconversionEditFieldLabel.Text='Bleached states';
                app.BleachedstatesbeforephotoconversionEditField.Value=get_names(app,bleached_states);
                app.FluorescentstatesafterphotoconversionEditField.Visible='off';
                app.FluorescentstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.PhotoactivedarkstatesafterphotoconversionEditField.Visible='off';
                app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.ThermaldarkstatesafterphotoconversionEditField.Visible='off';
                app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.BleachedstatesafterphotoconversionEditField.Visible='off';
                app.BleachedstatesafterphotoconversionEditFieldLabel.Visible='off';
            end
            
            % Display states in rapid equilibrium
            w_rapid=setdiff(1:app.n_states, unique(states_ids)); % Set of states in rapid equilibrium
            if ~isempty(w_rapid) % States in rapid equilibrium were found
                S=[];
                for k=1:numel(w_rapid)
                    rapid_ex_state_names=app.state_names(states_ids==states_ids(w_rapid(k)));
                    S=[S,'(',rapid_ex_state_names{1},', ',rapid_ex_state_names{2},') ; '];
                end
                S=S(1:end-2);
                app.PairsofstatesinrapidequilibriumEditField.Visible='on';
                app.PairsofstatesinrapidequilibriumEditFieldLabel.Visible='on';
                app.PairsofstatesinrapidequilibriumEditField.Value=S;
            else
                app.PairsofstatesinrapidequilibriumEditField.Visible='off';
                app.PairsofstatesinrapidequilibriumEditFieldLabel.Visible='off';
            end
            
            % Environmental parameters
            if app.Fluorophorepar.Environment.pH_sensitivity==1
                app.pHSensitivitySwitch.Value=1;
                app.pHofthemediumEditField.Visible='on';
                app.pHofthemediumEditFieldLabel.Visible='on';
                app.pKaTable.Visible='on';
                
                MyTableLength=min([43+80*app.n_fluo_states,500]);
                MyColumnWidth=num2cell(80*ones(1,app.n_fluo_states));
                app.pKaTable.ColumnWidth=MyColumnWidth;
                app.pKaTable.Position=[228,223,MyTableLength,73]; % Adjust position
                
                % Show the names of the fluorescent states
                app.pKaTable.ColumnName=app.state_names(fluo_states);
                % Fill the data
                if ~isempty(app.Fluorophorepar.Environment.pKa)
                    app.pKaTable.Data=[app.Fluorophorepar.Environment.pKa; app.Fluorophorepar.Environment.Hill];
                else
                    app.pKaTable.Data=nan(2, numel(app.Fluorophorepar.Photophysics.fluorescent_states));
                end
            else
                app.pHSensitivitySwitch.Value=0;
                app.pHofthemediumEditField.Visible='off';
                app.pHofthemediumEditFieldLabel.Visible='off';
                app.pKaTable.Visible='off';
            end
            
            % Set up the table for fluorescence Quantum yields
            MyTableLength=min([43+80*app.n_fluo_states,500]);
            MyColumnWidth=num2cell(80*ones(1,app.n_fluo_states));
            app.QYTable.ColumnWidth=MyColumnWidth;
            app.QYTable.Position=[228,335,MyTableLength,51]; % Adjust position
            
            % Show the names of the fluorescent states
            app.QYTable.ColumnName=app.state_names(fluo_states);
            
            % Fill the data if available
            if isempty(app.Fluorophorepar.Photophysics.quantum_yield) || numel(app.Fluorophorepar.Photophysics.quantum_yield)~=app.n_fluo_states
                MyData=ones(1,app.n_fluo_states);
                app.QYTable.Data=MyData;
            else
                app.QYTable.Data=app.Fluorophorepar.Photophysics.quantum_yield;
            end
            
            % Set the table for fluorogenicity
            if app.Fluorophorepar.Environment.fluorogenic==1
                app.FluorogenicitySwitch.Value='On';
                app.FluorogenicityTable.Visible='on';
                MyTableLength=min([43+50*app.n_sp,500]);
                MyColumnWidth=num2cell(50*ones(1,app.n_sp));
                app.FluorogenicityTable.ColumnWidth=MyColumnWidth;
                app.FluorogenicityTable.Position=[228,130,MyTableLength,51]; % Adjust position
                
                % Show the names of the fluorescent states
                app.FluorogenicityTable.ColumnName=num2cell(app.sp_ids);
                
                % Fill the data if available
                if isempty(app.Fluorophorepar.Environment.fluorogenicity==1) || numel(app.Fluorophorepar.Environment.fluorogenicity)~=app.n_sp
                    MyData=ones(1,app.n_sp);
                    app.FluorogenicityTable.Data=MyData;
                else
                    app.FluorogenicityTable.Data=app.Fluorophorepar.Environment.fluorogenicity;
                end
            else
                app.FluorogenicityTable.Visible='off';
                app.FluorogenicitySwitch.Value='Off';
            end
            
            % Set the anisotropy
            if app.Fluorophorepar.Orientation.anisotropy==1
                app.AnisotropySwitch.Value='On';
                
                app.FixeddipoleorientationSwitch.Visible='on';
                app.FixeddipoleorientationSwitchLabel.Visible='on';
                if app.Fluorophorepar.Orientation.dipole_orientation==1
                    app.FixeddipoleorientationSwitch.Value='On';
                else
                    app.FixeddipoleorientationSwitch.Value='Off';
                end
                
                if strcmp(app.FixeddipoleorientationSwitch.Value,'On')==1
                    app.ThetaEditField.Visible='on';
                    app.ThetaEditFieldLabel.Visible='on';
                    app.ThetaEditField.Value=app.Fluorophorepar.Orientation.theta_fixed;
                    app.PhiEditField.Visible='on';
                    app.PhiEditFieldLabel.Visible='on';
                    app.PhiEditField.Value=app.Fluorophorepar.Orientation.phi_fixed;
                else
                    app.ThetaEditField.Visible='off';
                    app.ThetaEditFieldLabel.Visible='off';
                    app.PhiEditField.Visible='off';
                    app.PhiEditFieldLabel.Visible='off';
                end
                
                app.AllowStochasticReorientationSwitch.Visible='on';
                app.AllowStochasticReorientationSwitchLabel.Visible='on';
                if app.Fluorophorepar.Orientation.jump_allowed==1
                    app.AllowStochasticReorientationSwitch.Value='On';
                else
                    app.AllowStochasticReorientationSwitch.Value='Off';
                end
                
                if strcmp(app.AllowStochasticReorientationSwitch.Value,'On')==1
                    app.Jumprates1EditField.Visible='on';
                    app.Jumprates1EditFieldLabel.Visible='on';
                    app.Jumprates1EditField.Value=app.Fluorophorepar.Orientation.jump_rate;
                else
                    app.Jumprates1EditField.Visible='off';
                    app.Jumprates1EditFieldLabel.Visible='off';
                end
            else
                app.AnisotropySwitch.Value='Off';
                app.FixeddipoleorientationSwitch.Visible='off';
                app.FixeddipoleorientationSwitchLabel.Visible='off';
                app.ThetaEditField.Visible='off';
                app.ThetaEditFieldLabel.Visible='off';
                app.PhiEditField.Visible='off';
                app.PhiEditFieldLabel.Visible='off';
                app.AllowStochasticReorientationSwitch.Visible='off';
                app.AllowStochasticReorientationSwitchLabel.Visible='off';
                app.Jumprates1EditField.Visible='off';
                app.Jumprates1EditFieldLabel.Visible='off';
            end
        end
        
        function S = get_names(app,states)
            if ~isempty(states)
                S=[];
                for k=1:numel(states)
                    S=[S,app.state_names{states(k)},' ; '];
                end
                S=S(1:end-2);
            else
                S='-';
            end
            
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selected_fluorophore)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Get all the parameters
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
            
            % Grab the number of the selected fluorophore
            app.selected_fluorophore=selected_fluorophore;
            input_fluorophore_par=app.smis_gui_parameters.Fluorophores;
            
            % Initialize selected fluorophore parameters
            app.Fluorophorepar=input_fluorophore_par(selected_fluorophore);
            
            % Initialize other fluorophore's parameters
            app.OtherFluorophorepar=input_fluorophore_par;
            app.OtherFluorophorepar(selected_fluorophore)=[];
            
            % Get number of fluorophores
            app.n_fluorophores=size(input_fluorophore_par,2);
            
            % Said the correct items data for switches (bug in appdesigner)
            app.pHSensitivitySwitch.ItemsData=0:1;
            
            % Switch between # of fluorophores and oligomer stochiometry if
            % qPALM mode is on
            if app.smis_gui_parameters.distribute_in_clusters==1
                app.NumberoffluorophoresEditField.Visible='off';
                app.NumberoffluorophoresEditFieldLabel.Visible='off';
                app.OligomerstochiometryEditField.Visible='on';
                app.OligomerstochiometryEditFieldLabel.Visible='on';
                app.FractionoffluorophoresineachsubpatternLabel.Visible='off';
                app.FractionInSubpatternsTable.Visible='off';
            else
                app.NumberoffluorophoresEditField.Visible='on';
                app.NumberoffluorophoresEditFieldLabel.Visible='on';
                app.OligomerstochiometryEditField.Visible='off';
                app.OligomerstochiometryEditFieldLabel.Visible='off';
            end
            
            
            % Handle visibility of copy fluorophore option
            if app.n_fluorophores<2
                app.CopySetupFromOtherFluorophoreDropDown.Visible = 'off';
                app.CopySetupFromOtherFluorophoreDropDownLabel.Visible = 'off';
            else
                other_fluorophore_names={app.OtherFluorophorepar.name};
                other_fluorophore_names=horzcat('Choose',other_fluorophore_names);
                app.CopySetupFromOtherFluorophoreDropDown.Items=other_fluorophore_names;
            end
            
            % Set up the table for the sub pattern ids
            app.n_sp=app.smis_gui_parameters.pattern_ids(selected_fluorophore).n_sp; % Number of sub patterns
            app.sp_ids=app.smis_gui_parameters.pattern_ids(selected_fluorophore).sp_ids; % Ids of sub patterns
            
            % Setup Table Position size
            MyTableLength=min([58+50*app.n_sp,500]);
            MyColumnWidth=num2cell(50*ones(1,app.n_sp));
            app.FractionInSubpatternsTable.ColumnWidth=MyColumnWidth;
            app.FractionInSubpatternsTable.Position=[25,455,MyTableLength,51]; % Adjust position
            
            % Show the subpattern's IDs
            app.FractionInSubpatternsTable.ColumnName=num2cell(app.sp_ids);
            
            % Fill the data if available
            if isempty(app.Fluorophorepar.n_sp_fr) || numel(app.Fluorophorepar.n_sp_fr)~=app.n_sp
                MyData=zeros(1,app.n_sp);
                MyData(1)=1;
                app.FractionInSubpatternsTable.Data=MyData;
            else
                app.FractionInSubpatternsTable.Data=app.Fluorophorepar.n_sp_fr;
            end
            
            % Update Edit Field
            app.MaturationlevelEditField.Value=app.Fluorophorepar.maturation_level;
            app.MinimumdistancebetweenfluorophoresnmEditField.Value=app.Fluorophorepar.min_dist;
            
            % Update UI with input values
            UpdateFluorophoreSetup(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Check that fractions of fluorophores in sub-patterns are okay
            if ~isempty(app.Fluorophorepar.n_sp_fr) && sum(app.Fluorophorepar.n_sp_fr)~=1
                warndlg('The sum of fractions of fluorophores in the sub patterns must equal to 1 !','Warning');
            elseif any(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr~=floor(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr))
                warndlg('This Number of fluorophores will result in non-integer number of fluorophores in a sub-pattern ! Check Table of fractions or Number of fluorophores !','Warning');
            else
                % Call main app's public function
                updateFluorophorepar(app.CallingApp, app.Fluorophorepar, app.selected_fluorophore)
                % Delete the dialog box
                delete(app)
            end
        end

        % Display data changed function: pKaTable
        function pKaTableDisplayDataChanged(app, event)
            newDisplayData = app.pKaTable.DisplayData;
            app.Fluorophorepar.Environment.pKa=newDisplayData(1,:);
            app.Fluorophorepar.Environment.Hill=newDisplayData(2,:);
        end

        % Value changed function: InitialstateEditField
        function InitialstateEditFieldValueChanged(app, event)
            value = app.InitialstateEditField.Value;
            w_name=find(strcmp(value,app.state_names),1);
            if isempty(w_name)
                warndlg('This state name does not exist !','Warning');
                app.InitialstateEditField.Value=event.PreviousValue;
            else
                app.Fluorophorepar.Photophysics.initial_state=w_name;
            end
        end

        % Cell edit callback: QYTable
        function QYTableCellEdit(app, event)
            newData = event.NewData;
            
            valid_QY=1; % Initialize
            %Issue warnings when QY value is not possible
            
            if newData<0
                warndlg('Fluorescence Quantum Yield must be >= 0 !','Warning');
                valid_QY=0;
            end
            if newData>1
                warndlg('Fluorescence Quantum Yield must be <= 1 !','Warning');
                valid_QY=0;
            end
            
            if valid_QY==1
                app.Fluorophorepar.Photophysics.quantum_yield = app.QYTable.DisplayData;
            else
                app.QYTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Reset the value to zero
            end
        end

        % Value changed function: FluorophorenameEditField
        function FluorophorenameEditFieldValueChanged(app, event)
            app.Fluorophorepar.name = app.FluorophorenameEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckingofFluorophorePhotophysicalStatesLabel = uilabel(app.UIFigure);
            app.CheckingofFluorophorePhotophysicalStatesLabel.Text = 'Checking of Fluorophore Photophysical States';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FluorophorenameEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophorenameEditFieldLabel.Text = 'Fluorophore name';
            app.FluorophorenameEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophorenameEditField.ValueChangedFcn = createCallbackFcn(app, @FluorophorenameEditFieldValueChanged, true);
            app.pHSensitivitySwitchLabel = uilabel(app.UIFigure);
            app.pHSensitivitySwitchLabel.Text = 'pH Sensitivity';
            app.pHSensitivitySwitch = uiswitch(app.UIFigure, 'slider');
            app.pHSensitivitySwitch.Value = 'On';
            app.pKaTable = uitable(app.UIFigure);
            app.pKaTable.ColumnName = {'Fluo State Name'};
            app.pKaTable.RowName = {'pKa '; 'Hill'};
            app.pKaTable.ColumnEditable = true;
            app.pKaTable.DisplayDataChangedFcn = createCallbackFcn(app, @pKaTableDisplayDataChanged, true);
            app.NumberofphotophysicalstatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofphotophysicalstatesEditFieldLabel.Text = 'Number of photophysical states';
            app.NumberofphotophysicalstatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofphotophysicalstatesEditField.Editable = 'off';
            app.NumberofphotophysicalstatesEditField.Value = 1;
            app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text = 'Photoactive dark states before photoconversion';
            app.PhotoactivedarkstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.PhotoactivedarkstatesbeforephotoconversionEditField.Editable = 'off';
            app.FluorescentstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text = 'Fluorescent states before photoconversion';
            app.FluorescentstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.FluorescentstatesbeforephotoconversionEditField.Editable = 'off';
            app.NameofconvertedstateEditFieldLabel = uilabel(app.UIFigure);
            app.NameofconvertedstateEditFieldLabel.Text = 'Name of converted state';
            app.NameofconvertedstateEditField = uieditfield(app.UIFigure, 'text');
            app.NameofconvertedstateEditField.Editable = 'off';
            app.FluorophoreisirreversiblyphotoconvertibleLabel = uilabel(app.UIFigure);
            app.FluorophoreisirreversiblyphotoconvertibleLabel.Text = 'Fluorophore is irreversibly photoconvertible';
            app.FluorescentstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.FluorescentstatesafterphotoconversionEditFieldLabel.Text = 'Fluorescent states after photoconversion';
            app.FluorescentstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.FluorescentstatesafterphotoconversionEditField.Editable = 'off';
            app.BleachedstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.BleachedstatesbeforephotoconversionEditFieldLabel.Text = 'Bleached states before photoconversion';
            app.BleachedstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.BleachedstatesbeforephotoconversionEditField.Editable = 'off';
            app.BleachedstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.BleachedstatesafterphotoconversionEditFieldLabel.Text = 'Bleached states after photoconversion';
            app.BleachedstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.BleachedstatesafterphotoconversionEditField.Editable = 'off';
            app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Text = 'Photoactive dark states after photoconversion';
            app.PhotoactivedarkstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.PhotoactivedarkstatesafterphotoconversionEditField.Editable = 'off';
            app.ThermaldarkstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Text = 'Thermal dark states after photoconversion';
            app.ThermaldarkstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.ThermaldarkstatesafterphotoconversionEditField.Editable = 'off';
            app.ThermaldarkstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text = 'Thermal dark states before photoconversion';
            app.ThermaldarkstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.ThermaldarkstatesbeforephotoconversionEditField.Editable = 'off';
            app.InitialstateEditFieldLabel = uilabel(app.UIFigure);
            app.InitialstateEditFieldLabel.Text = 'Initial state';
            app.InitialstateEditField = uieditfield(app.UIFigure, 'text');
            app.InitialstateEditField.ValueChangedFcn = createCallbackFcn(app, @InitialstateEditFieldValueChanged, true);
            app.PairsofstatesinrapidequilibriumEditFieldLabel = uilabel(app.UIFigure);
            app.PairsofstatesinrapidequilibriumEditFieldLabel.Text = 'Pairs of states in rapid equilibrium: ';
            app.PairsofstatesinrapidequilibriumEditField = uieditfield(app.UIFigure, 'text');
            app.PairsofstatesinrapidequilibriumEditField.Editable = 'off';
            app.QYTable = uitable(app.UIFigure);
            app.QYTable.ColumnName = {'Fluo State Name'};
            app.QYTable.RowName = {'QY'};
            app.QYTable.ColumnEditable = true;
            app.QYTable.CellEditCallback = createCallbackFcn(app, @QYTableCellEdit, true);
            app.FluorescenceQuantumYieldsLabel = uilabel(app.UIFigure);
            app.FluorescenceQuantumYieldsLabel.Text = 'Fluorescence Quantum Yields';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckPhotoPhysicalStates(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end