classdef CheckSubPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ChosensubpatternisshowninredLabel  matlab.ui.control.Label
        OKButton            matlab.ui.control.Button
        DViewSwitchLabel    matlab.ui.control.Label
        DViewSwitch         matlab.ui.control.Switch
        ZRasterSliderLabel  matlab.ui.control.Label
        ZRasterSlider       matlab.ui.control.Slider
        ZRasterSpinner      matlab.ui.control.Spinner
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Pattern
        chosen_sp_id
        sp_ids
    end
    
    methods (Access = private)
        
        function show3D(app)
            
            ClearImage(app)
            
            view(app.UIAxes,3);
            colormap(app.UIAxes,'gray')
            w=find(app.Pattern==app.chosen_sp_id);
            if numel(w)>100000 % Reduce size to go faster !
                r=round(rand(1,100000)*numel(w));
                w=w(r);
            end
            [x,y,z]=ind2sub(size(app.Pattern),w);
            scatter3(app.UIAxes,x,y,z,2,'MarkerEdgeColor','Red','MarkerFaceColor','Red')
            xlim(app.UIAxes,[1,size(app.Pattern,1)]);
            ylim(app.UIAxes,[1,size(app.Pattern,2)]);
            zlim(app.UIAxes,[1,size(app.Pattern,3)]);
        end
        
        function show2D(app)
            
            ClearImage(app)
            
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
            
            MinDisp=min(app.sp_ids);
            MaxDisp=max(app.sp_ids);
            
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            hold(app.UIAxes,'on')
            clims = [MinDisp, MaxDisp];
            imagesc(app.UIAxes,app.Pattern,clims);
            axis(app.UIAxes,"image");
            
            w=find(app.Pattern==app.chosen_sp_id);
            [y,x]=ind2sub(size(app.Pattern),w);
            plot(app.UIAxes,x,y,'r.', 'MarkerSize', 2);
            hold(app.UIAxes,'off')
        end
        
        function show2Dsection(app)
            
            ClearImage(app)
            
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
            
            section=app.Pattern(:,:,round(app.ZRasterSlider.Value));
            
            MinDisp=min(app.sp_ids);
            MaxDisp=max(app.sp_ids);
            
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            hold(app.UIAxes,'on')
            clims = [MinDisp, MaxDisp];
            imagesc(app.UIAxes,section,clims);
            axis(app.UIAxes,"image");
            
            w=find(section==app.chosen_sp_id);
            [y,x]=ind2sub(size(section),w);
            plot(app.UIAxes,x,y,'r.', 'MarkerSize', 3);
            hold(app.UIAxes,'off')
        end
        
        function ClearImage(app)
            MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Pattern, sp_ids, chosen_sp_id, simul_3D)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.Pattern=Pattern;
            app.chosen_sp_id=chosen_sp_id;
            app.sp_ids=sp_ids;
            
            app.ChosensubpatternisshowninredLabel.Text=['View of sub-pattern id: ',num2str(chosen_sp_id)];
            
            if simul_3D==0
                app.DViewSwitch.Visible='off';
                app.DViewSwitchLabel.Visible='off';
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                
                show2D(app)
                
            else
                app.DViewSwitch.Visible='on';
                app.DViewSwitchLabel.Visible='on';
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                s=size(Pattern,3);
                app.ZRasterSlider.Limits=[1,s];
                app.ZRasterSpinner.Limits=[1,s];
                app.ZRasterSlider.MajorTicks=[1,(1:4)*s/4];
                app.ZRasterSlider.Value=round(size(Pattern,3)/2);
                app.ZRasterSpinner.Value=app.ZRasterSlider.Value;
                
                show3D(app);
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: DViewSwitch
        function DViewSwitchValueChanged(app, event)
            value = app.DViewSwitch.Value;
            if strcmp(value,'On')
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                show3D(app);
            else
                app.ZRasterSlider.Visible='on';
                app.ZRasterSliderLabel.Visible='on';
                app.ZRasterSpinner.Visible='on';
                show2Dsection(app);
            end
        end

        % Value changed function: ZRasterSlider
        function ZRasterSliderValueChanged(app, event)
            value = app.ZRasterSlider.Value;
            app.ZRasterSpinner.Value=round(value);
            show2Dsection(app);
        end

        % Value changed function: ZRasterSpinner
        function ZRasterSpinnerValueChanged(app, event)
            value = app.ZRasterSpinner.Value;
            app.ZRasterSlider.Value=value;
            show2Dsection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChosensubpatternisshowninredLabel = uilabel(app.UIFigure);
            app.ChosensubpatternisshowninredLabel.Text = 'Chosen sub-pattern is shown in red';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DViewSwitchLabel = uilabel(app.UIFigure);
            app.DViewSwitchLabel.Text = '3D View';
            app.DViewSwitch = uiswitch(app.UIFigure, 'slider');
            app.DViewSwitch.ValueChangedFcn = createCallbackFcn(app, @DViewSwitchValueChanged, true);
            app.DViewSwitch.Value = 'On';
            app.ZRasterSliderLabel = uilabel(app.UIFigure);
            app.ZRasterSliderLabel.Text = 'Z [Raster]';
            app.ZRasterSlider = uislider(app.UIFigure);
            app.ZRasterSlider.Limits = [1 100];
            app.ZRasterSlider.ValueChangedFcn = createCallbackFcn(app, @ZRasterSliderValueChanged, true);
            app.ZRasterSlider.Value = 1;
            app.ZRasterSpinner = uispinner(app.UIFigure);
            app.ZRasterSpinner.ValueChangedFcn = createCallbackFcn(app, @ZRasterSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckSubPattern(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end