classdef CreatePattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddbeadlayerButton            matlab.ui.control.Button
        UseqPALM                      matlab.ui.control.Switch
        UseqPALMLabel                 matlab.ui.control.Label
        ChooseqPALM3DsampleDropDown   matlab.ui.control.DropDown
        ChooseqPALM3DsampleDropDownLabel  matlab.ui.control.Label
        ChooseqPALM2DsampleDropDown   matlab.ui.control.DropDown
        ChooseqPALM2DsampleDropDownLabel  matlab.ui.control.Label
        Choose3DsampleDropDown        matlab.ui.control.DropDown
        Choose3DsampleDropDownLabel   matlab.ui.control.Label
        Choose2DsampleDropDown        matlab.ui.control.DropDown
        Choose2DsampleDropDownLabel   matlab.ui.control.Label
        Use3D                         matlab.ui.control.Switch
        UseparallelcomputingSwitchLabel  matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        CreateSMISVirtualSampleLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Refers to main SMIS app
        Status = [] % App status
        simul_3D = 1 % Set to 1 to define virtual samples for 3D
    end
    
    properties (Access = public)
        SMIS_dir =[] % Default SMIS directory for pattern library
        qPALM = 0 % Set to 1 to define virtual samples for qPALM
        raster = [] % Current SMIS raster size [nm]
        binning = [] % Current SMIS binning 
    end
    
    methods (Access = private)
        
        function check_menus(app)
             if app.qPALM==1
                if app.simul_3D==1
                    app.Choose3DsampleDropDown.Enable="off";
                    app.ChooseqPALM3DsampleDropDown.Enable="on";
                    app.Choose2DsampleDropDown.Enable="off";
                    app.ChooseqPALM2DsampleDropDown.Enable="off";
                else
                    app.Choose3DsampleDropDown.Enable="off";
                    app.ChooseqPALM3DsampleDropDown.Enable="off";
                    app.Choose2DsampleDropDown.Enable="off";
                    app.ChooseqPALM2DsampleDropDown.Enable="on";
                end
            else
                if app.simul_3D==1
                    app.Choose3DsampleDropDown.Enable="on";
                    app.ChooseqPALM3DsampleDropDown.Enable="off";
                    app.Choose2DsampleDropDown.Enable="off";
                    app.ChooseqPALM2DsampleDropDown.Enable="off";
                else
                    app.Choose3DsampleDropDown.Enable="off";
                    app.ChooseqPALM3DsampleDropDown.Enable="off";
                    app.Choose2DsampleDropDown.Enable="on";
                    app.ChooseqPALM2DsampleDropDown.Enable="off";
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.simul_3D=mainapp.smis_gui_parameters.simul_3D;
            app.qPALM=mainapp.smis_gui_parameters.distribute_in_clusters;
            app.SMIS_dir=mainapp.smis_gui_parameters.DefaultDirs;
            app.binning=mainapp.smis_gui_parameters.binning;
            app.raster=mainapp.smis_gui_parameters.raster;

            if app.simul_3D==1
                app.Use3D.Value='On';
                app.AddbeadlayerButton.Visible="on";
            else
                app.Use3D.Value='Off';
                app.AddbeadlayerButton.Visible="off";
            end

            if app.qPALM==1
                app.UseqPALM.Value='On';
            else
                app.UseqPALM.Value='Off';
            end

            check_menus(app)

           
        end

        % Value changed function: Choose2DsampleDropDown
        function Choose2DsampleDropDownValueChanged(app, event)
            value = app.Choose2DsampleDropDown.Value;
            
            switch value
                case 'HeLa Cell'
                     % Disable Plot Options button while dialog is open
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DHelaCell(app);
                case 'E. coli bacteria'
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DEColi(app);
                case 'NPCs'
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DNPCs(app);
                case 'Distorted NPCs'
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DDistortedNPCs(app);
                case 'Crossing Lines'
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DCrossingLines(app);
                case 'Squares or Circles'
                    app.Choose2DsampleDropDown.Enable='off';
                    app.Status = Draw2DSquaresCircles(app);
            end          
            
            % Reset Sequence menu to first position
            app.Choose2DsampleDropDown.Value = 'Choose';
            
        end

        % Value changed function: Use3D
        function Use3DValueChanged(app, event)
            value = app.Use3D.Value;
            if strcmp(value,'On')==1
                app.simul_3D=1;
                app.AddbeadlayerButton.Visible="on";
            else
                app.simul_3D=0;
                app.AddbeadlayerButton.Visible="off";
            end

            check_menus(app)
        end

        % Value changed function: UseqPALM
        function UseqPALMValueChanged(app, event)
            value = app.UseqPALM.Value;
            if strcmp(value,'On')==1
                app.qPALM=1;
            else
                app.qPALM=0;
            end

            check_menus(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Reenable(app.CallingApp)
            if ~isempty(app.Status)
                if isvalid(app.Status)
                    delete(app.Status)
                    if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                        close(1)
                    end
                    if ishandle(2) && strcmp(get(2, 'type'), 'figure')
                        close(2)
                    end
                end
            end
            delete(app)
        end

        % Value changed function: ChooseqPALM2DsampleDropDown
        function ChooseqPALM2DsampleDropDownValueChanged(app, event)
            value = app.ChooseqPALM2DsampleDropDown.Value;
            
            switch value
                case 'NPCs'
                    app.ChooseqPALM2DsampleDropDown.Enable='off';
                    app.Status = Draw2DNPCs(app);
                case 'Distorted NPCs' 
                    app.ChooseqPALM2DsampleDropDown.Enable='off';
                    app.Status = Draw2DDistortedNPCs(app);
                case 'Squares or Circles'
                    app.ChooseqPALM2DsampleDropDown.Enable='off';
                    app.Status = Draw2DSquaresCircles(app);
            end          
            
            % Reset Sequence menu to first position
            app.ChooseqPALM2DsampleDropDown.Value = 'Choose';
        end

        % Value changed function: Choose3DsampleDropDown
        function Choose3DsampleDropDownValueChanged(app, event)
            value = app.Choose3DsampleDropDown.Value;
            switch value
                case 'HeLa Cell'
                    % Disable Plot Options button while dialog is open
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DHelaCell(app);
                case 'E. coli bacteria'
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DEColi(app);
                case 'NPCs'
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DNPCs(app);
                case 'Distorted NPCs'
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DDistortedNPCs(app);
                case 'Microtubules'
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DMicrotubules(app);
                case 'Cubes or Spheres'
                    app.Choose3DsampleDropDown.Enable='off';
                    app.Status = Draw3DSpheresCubes(app);
                case 'Cylinder'
            end

            % Reset Sequence menu to first position
            app.Choose3DsampleDropDown.Value = 'Choose';
           
        end

        % Value changed function: ChooseqPALM3DsampleDropDown
        function ChooseqPALM3DsampleDropDownValueChanged(app, event)
            value = app.ChooseqPALM3DsampleDropDown.Value;
            switch value
                case 'NPCs'
                    app.ChooseqPALM3DsampleDropDown.Enable='off';
                    app.Status = Draw3DNPCs(app);
                case 'Distorted NPCs'
                    app.ChooseqPALM3DsampleDropDown.Enable='off';
                    app.Status = Draw3DDistortedNPCs(app);
                case 'Cubes or Spheres'
                    app.ChooseqPALM3DsampleDropDown.Enable='off';
                    app.Status = Draw3DSpheresCubes(app);
            end

            % Reset Sequence menu to first position
            app.ChooseqPALM3DsampleDropDown.Value = 'Choose';
        end

        % Button pushed function: AddbeadlayerButton
        function AddbeadlayerButtonPushed(app, event)
            app.Status = AddBeadLayer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateSMISVirtualSampleLabel = uilabel(app.UIFigure);
            app.CreateSMISVirtualSampleLabel.Text = 'Create SMIS Virtual Sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UseparallelcomputingSwitchLabel = uilabel(app.UIFigure);
            app.UseparallelcomputingSwitchLabel.Text = '3D';
            app.Use3D = uiswitch(app.UIFigure, 'slider');
            app.Use3D.ValueChangedFcn = createCallbackFcn(app, @Use3DValueChanged, true);
            app.Choose2DsampleDropDownLabel = uilabel(app.UIFigure);
            app.Choose2DsampleDropDownLabel.Text = 'Choose 2D sample';
            app.Choose2DsampleDropDown = uidropdown(app.UIFigure);
            app.Choose2DsampleDropDown.Items = {'Choose', 'HeLa Cell', 'E. coli bacteria', 'NPCs', 'Distorted NPCs', 'Crossing Lines', 'Squares or Circles'};
            app.Choose2DsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @Choose2DsampleDropDownValueChanged, true);
            app.Choose2DsampleDropDown.Value = 'Choose';
            app.Choose3DsampleDropDownLabel = uilabel(app.UIFigure);
            app.Choose3DsampleDropDownLabel.Text = 'Choose 3D sample';
            app.Choose3DsampleDropDown = uidropdown(app.UIFigure);
            app.Choose3DsampleDropDown.Items = {'Choose', 'HeLa Cell', 'E. coli bacteria', 'NPCs', 'Distorted NPCs', 'Microtubules', 'Cubes or Spheres'};
            app.Choose3DsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @Choose3DsampleDropDownValueChanged, true);
            app.Choose3DsampleDropDown.Value = 'Choose';
            app.ChooseqPALM2DsampleDropDownLabel = uilabel(app.UIFigure);
            app.ChooseqPALM2DsampleDropDownLabel.Text = 'Choose qPALM 2D sample';
            app.ChooseqPALM2DsampleDropDown = uidropdown(app.UIFigure);
            app.ChooseqPALM2DsampleDropDown.Items = {'Choose', 'NPCs', 'Distorted NPCs', 'Squares or Circles'};
            app.ChooseqPALM2DsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseqPALM2DsampleDropDownValueChanged, true);
            app.ChooseqPALM2DsampleDropDown.Value = 'Choose';
            app.ChooseqPALM3DsampleDropDownLabel = uilabel(app.UIFigure);
            app.ChooseqPALM3DsampleDropDownLabel.Text = 'Choose qPALM 3D sample';
            app.ChooseqPALM3DsampleDropDown = uidropdown(app.UIFigure);
            app.ChooseqPALM3DsampleDropDown.Items = {'Choose', 'NPCs', 'Distorted NPCs', 'Cubes or Spheres'};
            app.ChooseqPALM3DsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseqPALM3DsampleDropDownValueChanged, true);
            app.ChooseqPALM3DsampleDropDown.Value = 'Choose';
            app.UseqPALMLabel = uilabel(app.UIFigure);
            app.UseqPALMLabel.Text = 'qPALM';
            app.UseqPALM = uiswitch(app.UIFigure, 'slider');
            app.UseqPALM.ValueChangedFcn = createCallbackFcn(app, @UseqPALMValueChanged, true);
            app.UseqPALM.Value = 'On';
            app.AddbeadlayerButton = uibutton(app.UIFigure, 'push');
            app.AddbeadlayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddbeadlayerButtonPushed, true);
            app.AddbeadlayerButton.Text = 'Add bead layer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreatePattern(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end