classdef CreateSpectrum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        SavespectrumButton              matlab.ui.control.Button
        rmsnoiseEditField               matlab.ui.control.NumericEditField
        rmsnoiseEditFieldLabel          matlab.ui.control.Label
        AddnoiseSwitch                  matlab.ui.control.Switch
        AddnoiseSwitchLabel             matlab.ui.control.Label
        LoadexisitinginsilicospectrumButton  matlab.ui.control.Button
        atnmEditField_2                 matlab.ui.control.NumericEditField
        atnmEditField_2Label            matlab.ui.control.Label
        ForceextinctioncoefficientM1cm1EditField  matlab.ui.control.NumericEditField
        ForceextinctioncoefficientM1cm1EditFieldLabel  matlab.ui.control.Label
        AddoffsetSwitch                 matlab.ui.control.Switch
        AddoffsetSwitchLabel            matlab.ui.control.Label
        TypeofSpectrumSwitch            matlab.ui.control.Switch
        TypeofSpectrumLabel             matlab.ui.control.Label
        ExcelsheetnumberEditField       matlab.ui.control.NumericEditField
        ExcelsheetnumberEditFieldLabel  matlab.ui.control.Label
        atnmEditField                   matlab.ui.control.NumericEditField
        atnmEditFieldLabel              matlab.ui.control.Label
        ExtinctioncoefficientM1cm1EditField  matlab.ui.control.NumericEditField
        ExtinctioncoefficientM1cm1EditField_2Label  matlab.ui.control.Label
        LambdamaxnmEditField            matlab.ui.control.NumericEditField
        LambdamaxnmEditField_2Label     matlab.ui.control.Label
        LambdaminnmEditField            matlab.ui.control.NumericEditField
        LambdaminnmEditField_2Label     matlab.ui.control.Label
        NumberofGaussiansEditField      matlab.ui.control.NumericEditField
        NumberofGaussiansEditField_2Label  matlab.ui.control.Label
        ExcelcolumnletterEditField      matlab.ui.control.EditField
        ExcelcolumnletterEditFieldLabel  matlab.ui.control.Label
        OutputExcelFileEditField        matlab.ui.control.EditField
        OutputExcelFileEditFieldLabel   matlab.ui.control.Label
        IncludetypicalemissionspectrumSwitch  matlab.ui.control.Switch
        IncludetypicalemissionspectrumSwitchLabel  matlab.ui.control.Label
        IncludetypicalabsorptionspectrumSwitch  matlab.ui.control.Switch
        IncludetypicalabsorptionspectrumSwitchLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        OKButton                        matlab.ui.control.Button
        CreateInSilicoSpectrumLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp   % Main app object
        S=[]; % Spectrum properties
        Saved_OK=0; % Flag to make sure we don't quit the app without saving
    end

    methods (Access = private)

        function calculate_spectrum(app) % Plot the spectrum
            %Reset the save_ok
            app.Saved_OK=0;

            %Reset the spectrum
            x=app.S.lambda_min:app.S.lambda_max;

            n=size(x,2); % Number of point in the spectrum
            y=zeros(1,n);

            %Calculate the Gaussian's
            if app.S.N_Gaussians>0
                for i=1:app.S.N_Gaussians
                    y=y+app.S.Gauss_amp(i)*exp(-(x-app.S.Gauss_lambda_max(i)).^2/(2*(app.S.Gauss_width(i)/2.35)^2));
                end
            end

            % Include the typical absorption spectrum if required
            if strcmp(app.S.include_typical_abs,'Yes')==1
                tx=app.S.typ_abs_spectrum(:,1);
                ty=app.S.typ_abs_spectrum(:,2);

                lambda_eps=app.S.Gauss_lambda_max(end);
                width=app.S.Gauss_width(end);
                amp=app.S.Gauss_amp(end);

                % Remove part below 350 nm and normalize to one
                ty2=ty(tx>350)/max(ty);

                % Find the original FWHM
                wMax=find(ty2==1,1);
                MyEx=abs(ty2(1:wMax)-0.5);
                w1=find(MyEx==min(MyEx),1); % Position of the maximum in the original absorption spectrum
                MyEx=abs(ty2(wMax:end)-0.5);
                w2=wMax-1+find(MyEx==min(MyEx),1); % Position of the maximum in the original absorption spectrum
                step=width/(w2-w1); % This is the step size for the wavelength array
                tx2=0:step:((numel(ty2)-1)*step);
                tx2=tx2+lambda_eps-tx2(wMax); % Shift to place the maximum at lambda_eps
                ty2=ty2'*amp; % Normalize to specified amplitude

                if app.S.lambda_min<tx2(1)
                    tx2=[app.S.lambda_min, tx2];
                    ty2=[0, ty2]; % Add a zero at the beginning of the spectrum
                end
                if app.S.lambda_max>tx2(end)
                    tx2=[tx2, app.S.lambda_max];
                    ty2=[ty2, 0]; % Add a zero at the end of the spectrum
                end

                % Finally interpolate to find the values at the right wavelengths
                y=y+interp1(tx2,ty2,x);
            end


            % Include the typical emission spectrum if required
            if strcmp(app.S.include_typical_em,'Yes')==1
                ty=app.S.typ_em_spectrum(:,2);

                lambda_eps=app.S.Gauss_lambda_max(end);
                width=app.S.Gauss_width(end);
                amp=app.S.Gauss_amp(end);

                % Normalize to one
                ty2=ty/max(ty);

                % Find the original FWHM
                wMax=find(ty2==1,1);
                MyEx=abs(ty2(1:wMax)-0.5);
                w1=find(MyEx==min(MyEx),1); % Position of the maximum in the original absorption spectrum
                MyEx=abs(ty2(wMax:end)-0.5);
                w2=wMax-1+find(MyEx==min(MyEx),1); % Position of the maximum in the original absorption spectrum
                step=width/(w2-w1); % This is the step size for the wavelength array
                tx2=0:step:((numel(ty2)-1)*step);
                tx2=tx2+lambda_eps-tx2(wMax); % Shift to place the maximum at lambda_eps
                ty2=ty2'*amp; % Normalize to specified amplitude

                if app.S.lambda_min<tx2(1)
                    tx2=[app.S.lambda_min, tx2(1)-step, tx2];
                    ty2=[0, 0, ty2]; % Add 2 zeros at the beginning of the spectrum
                end
                if app.S.lambda_max>tx2(end)
                    tx2=[tx2, app.S.lambda_max];
                    ty2=[ty2, 0]; % Add a zero at the end of the spectrum
                end

                % Finally interpolate to find the values at the right wavelengths
                y=y+interp1(tx2,ty2,x);
            end


            % Scale according to the type of spectrum
            if strcmp(app.S.spectrum_type,'Em')==1
                if max(y)>0
                    y=y/max(y); % Normalize to 1
                else
                    warndlg('Spectrum cannot be normalized !','Warning')
                end
            else
                w=find(abs(x-app.S.lambda_eps)==min(abs(x-app.S.lambda_eps)),1);
                if y(w)>0
                    y=app.S.eps*y/y(w);
                else
                    warndlg('Spectrum cannot be normalized !','Warning')
                end
            end

            % Eventually add offset
            if strcmp(app.S.add_offset,'Yes')==1
                if strcmp(app.S.spectrum_type,'Abs/Exc')==1
                    w1=find(abs(x-app.S.lambda_eps)==min(abs(x-app.S.lambda_eps)),1);
                    w2=find(abs(x-app.S.lambda_eps_offset)==min(abs(x-app.S.lambda_eps_offset)),1);
                    y1=y(w1);
                    y2=y(w2);
                    offset=(y2*app.S.eps-y1*app.S.eps_offset)/(y2-y1);
                    alpha=(app.S.eps-offset)/y1;
                else
                    w1=find(y==1,1);
                    w2=find(abs(x-app.S.lambda_eps_offset)==min(abs(x-app.S.lambda_eps_offset)),1);
                    y1=y(w1);
                    y2=y(w2);
                    offset=(y2-y1*app.S.eps_offset)/(y2-y1);
                    alpha=(1-offset)/y1;
                end


                y=alpha*y+offset;
            end


            % Eventually add noise
            if strcmp(app.S.add_noise,'Yes')==1
                y=y+app.S.rms_noise*randn(size(y));
            end

            app.S.x=x;
            app.S.y=y;
        end

        function plot_spectrum(app) % Plot the spectrum
            if strcmp(app.S.spectrum_type,'Abs/Exc')
                app.UIAxes.YLabel.String='Exctinction Coeff [M-1cm-1]';
            elseif strcmp(app.S.spectrum_type,'Em')
                app.UIAxes.YLabel.String='Normalized fluorescence [AU]';
            end
            if max(app.S.y) > 0
                plot(app.UIAxes,  app.S.x, app.S.y, '-','LineWidth',1.5, 'Color','blue');
                xlim(app.UIAxes,[app.S.lambda_min,app.S.lambda_max]);
                ylim(app.UIAxes,[0,1.2*max(app.S.y)]);
            else
                MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
                if ~isempty(MyData)
                    delete(MyData);
                end
                warndlg('Spectrum cannot be plotted !','Warning')
            end
        end

        function updateTable(app)
            % Define the number of rows
            app.S.N_rows=app.S.N_Gaussians+strcmp(app.S.include_typical_abs,'Yes')++strcmp(app.S.include_typical_em,'Yes');
            % Define position of the table
            app.UITable.Position=[30,max([20,170-22*app.S.N_rows]),320,min([195,29+22*app.S.N_rows])]; % Adjust position

            % Update app.S
            app.S.Gauss_lambda_max=app.UITable.Data{:,1};
            app.S.Gauss_width=app.UITable.Data{:,2};
            app.S.Gauss_amp=app.UITable.Data{:,3};
            app.S.TableRowName=app.UITable.RowName;
        end

        function updateApp(app)
            app.TypeofSpectrumSwitch.Value=app.S.spectrum_type;
            app.LambdaminnmEditField.Value=app.S.lambda_min;
            app.LambdamaxnmEditField.Value=app.S.lambda_max;
            app.ExtinctioncoefficientM1cm1EditField.Value=app.S.eps;
            app.atnmEditField.Value=app.S.lambda_eps;
            app.NumberofGaussiansEditField.Value=app.S.N_Gaussians;
            app.IncludetypicalabsorptionspectrumSwitch.Value=app.S.include_typical_abs;
            app.IncludetypicalemissionspectrumSwitch.Value=app.S.include_typical_em;
            app.AddoffsetSwitch.Value=app.S.add_offset;
            app.ForceextinctioncoefficientM1cm1EditField.Value=app.S.eps_offset;
            app.atnmEditField_2.Value=app.S.lambda_eps_offset;
            app.AddnoiseSwitch.Value=app.S.add_noise;
            app.rmsnoiseEditField.Value=app.S.rms_noise;
            app.UITable.Data=table(app.S.Gauss_lambda_max,app.S.Gauss_width,app.S.Gauss_amp);
            app.UITable.RowName=app.S.TableRowName;

            if strcmp(app.S.spectrum_type,'Em')==1
                app.ExtinctioncoefficientM1cm1EditField_2Label.Visible='off';
                app.ExtinctioncoefficientM1cm1EditField.Visible='off';
                app.ForceextinctioncoefficientM1cm1EditFieldLabel.Text='Force fluorescence amplitude [AU]     ';
                app.atnmEditField.Visible='off';
                app.atnmEditFieldLabel.Visible='off';
                app.IncludetypicalabsorptionspectrumSwitchLabel.Visible='off';
                app.IncludetypicalabsorptionspectrumSwitch.Visible='off';
                app.S.include_typical_abs='No';
                app.IncludetypicalemissionspectrumSwitchLabel.Visible='on';
                app.IncludetypicalemissionspectrumSwitch.Visible='on';
                app.IncludetypicalabsorptionspectrumSwitch.Value='No';
                w=find(strcmp(app.UITable.RowName,'Absorption')==1,1);
                app.UITable.Data(w,:)=[];
                app.UITable.RowName(w,:)=[];
            elseif strcmp(app.S.spectrum_type,'Abs/Exc')==1
                app.ExtinctioncoefficientM1cm1EditField_2Label.Visible='on';
                app.ExtinctioncoefficientM1cm1EditField.Visible='on';
                app.ForceextinctioncoefficientM1cm1EditFieldLabel.Text='Force extinction coefficient [M-1cm-1]';
                app.atnmEditField.Visible='on';
                app.atnmEditFieldLabel.Visible='on';
                app.IncludetypicalabsorptionspectrumSwitchLabel.Visible='on';
                app.IncludetypicalabsorptionspectrumSwitch.Visible='on';
                app.IncludetypicalemissionspectrumSwitchLabel.Visible='off';
                app.S.include_typical_em='No';
                app.IncludetypicalemissionspectrumSwitch.Visible='off';
                app.IncludetypicalemissionspectrumSwitch.Value='No';
                w=find(strcmp(app.UITable.RowName,'Emission')==1,1);
                app.UITable.Data(w,:)=[];
                app.UITable.RowName(w,:)=[];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DefineFluorophoreApp, output_dir)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = DefineFluorophoreApp;

            app.S.spectrum_type=app.TypeofSpectrumSwitch.Value;
            app.S.lambda_min=app.LambdaminnmEditField.Value;
            app.S.lambda_max=app.LambdamaxnmEditField.Value;
            app.S.eps=app.ExtinctioncoefficientM1cm1EditField.Value;
            app.S.lambda_eps=app.atnmEditField.Value;
            app.S.N_Gaussians=app.NumberofGaussiansEditField.Value;

            app.UITable.Data=table(488, 20, 1); % Start with one Gaussian centered at 488 nm with 20 nm width and amplitude of 1

            app.S.include_typical_abs=app.IncludetypicalabsorptionspectrumSwitch.Value;
            app.S.include_typical_em=app.IncludetypicalemissionspectrumSwitch.Value;
            app.S.typ_abs_spectrum=[];
            app.S.typ_em_spectrum=[];

            app.S.add_offset=app.AddoffsetSwitch.Value;
            app.S.eps_offset=app.ForceextinctioncoefficientM1cm1EditField.Value;
            app.S.lambda_eps_offset=app.atnmEditField_2.Value;

            app.S.add_noise=app.AddnoiseSwitch.Value;
            app.S.rms_noise=app.rmsnoiseEditField.Value;

            app.S.xls_dir=fullfile(output_dir,'SPECTRA');
            app.S.xls_file=app.OutputExcelFileEditField.Value;
            app.S.xls_sheet=app.ExcelsheetnumberEditField.Value;
            app.S.xls_col=app.ExcelcolumnletterEditField.Value;
            app.S.typ_spectra_dir=fullfile(output_dir,'SPECTRA');
        
            updateTable(app);
            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            quit_ok=1;
            if app.Saved_OK==0
                fig = uifigure;
                fig.Position = [800 600 400 160];
                MySel = uiconfirm(fig,'Do you really want to quit without saving the created spectrum ?','','Icon','warning');
                close(fig)
                if strcmp(MySel,'OK') ~= 1
                    quit_ok=0;
                end
            end
            if quit_ok==1
                updateCancel(app.CallingApp)
                delete(app)
            end
        end

        % Callback function
        function RotationEditFieldValueChanged(app, event)
            value = app.RotationEditField.Value;
            app.TwoChannelpar.deform.Rotation=value;
        end

        % Value changed function: LambdaminnmEditField
        function LambdaminnmEditFieldValueChanged(app, event)
            app.S.lambda_min = app.LambdaminnmEditField.Value;
            if strcmp(app.S.add_offset,'Yes')==1 && app.S.lambda_eps_offset<app.S.lambda_min
                warndlg(['Changing lambda for offset correction to:',num2str(app.S.lambda_min),'nm'],'Warning');
                app.S.lambda_eps_offset=app.S.lambda_min;
                app.atnmEditField_2.Value=app.S.lambda_eps_offset;
            end
            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: LambdamaxnmEditField
        function LambdamaxnmEditFieldValueChanged(app, event)
            app.S.lambda_max = app.LambdamaxnmEditField.Value;
            if strcmp(app.S.add_offset,'Yes')==1 && app.S.lambda_eps_offset>app.S.lambda_max
                warndlg(['Changing lambda for offset correction to:',num2str(app.S.lambda_max),'nm'],'Warning');
                app.S.lambda_eps_offset=app.S.lambda_max;
                app.atnmEditField_2.Value=app.S.lambda_eps_offset;
            end

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: NumberofGaussiansEditField
        function NumberofGaussiansEditFieldValueChanged(app, event)
            old_N_Gaussians=app.S.N_Gaussians;
            new_N_Gaussians=app.NumberofGaussiansEditField.Value;
            proceed_ok=1;

            if new_N_Gaussians==0
                if strcmp(app.S.spectrum_type,'Em')==1 && strcmp(app.S.include_typical_em,'Yes')==0
                    warndlg('Cannot have no Gaussian and no typical emission spectrum !','Warning');
                    proceed_ok=0;
                end

                if strcmp(app.S.spectrum_type,'Em')==0 && strcmp(app.S.include_typical_abs,'Yes')==0
                    warndlg('Cannot have no Gaussian and no typical absorption spectrum !','Warning');
                    proceed_ok=0;
                end
            end

            if proceed_ok==1
                if new_N_Gaussians>old_N_Gaussians % Some Gaussians need to be added
                    DataToAdd=ones(new_N_Gaussians-old_N_Gaussians,1);
                    TableToAdd=table(488*DataToAdd,20*DataToAdd,1*DataToAdd);
                    NamesToAdd=cell(new_N_Gaussians-old_N_Gaussians,1);
                    for i=1:(new_N_Gaussians-old_N_Gaussians)
                        NamesToAdd(i)={['Gaussian ',num2str(i+old_N_Gaussians)]};
                    end

                    if strcmp(app.S.include_typical_abs,'Yes')==1 || strcmp(app.S.include_typical_em,'Yes')==1 % Presence of an additional line in table
                        app.UITable.Data=[app.UITable.Data(1:end-1,:);TableToAdd;app.UITable.Data(end,:)];
                        app.UITable.RowName=[app.UITable.RowName(1:end-1,:);NamesToAdd;app.UITable.RowName(end,:)];
                    else % Only Gaussians to be considered
                        app.UITable.Data=[app.UITable.Data;TableToAdd];
                        app.UITable.RowName=[app.UITable.RowName;NamesToAdd];
                    end
                else % Some Gaussians were removed
                    app.UITable.Data(new_N_Gaussians+1:old_N_Gaussians,:)=[];
                    app.UITable.RowName(new_N_Gaussians+1:old_N_Gaussians,:)=[];
                end

                app.S.N_Gaussians = app.NumberofGaussiansEditField.Value;

                updateTable(app);
                calculate_spectrum(app);
                plot_spectrum(app);
            else
                app.NumberofGaussiansEditField.Value=old_N_Gaussians;
            end
        end

        % Value changed function: atnmEditField
        function atnmEditFieldValueChanged(app, event)
            app.S.lambda_eps = app.atnmEditField.Value;

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: ExtinctioncoefficientM1cm1EditField
        function ExtinctioncoefficientM1cm1EditFieldValueChanged(app, event)
            app.S.eps = app.ExtinctioncoefficientM1cm1EditField.Value;

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: AddoffsetSwitch
        function AddoffsetSwitchValueChanged(app, event)
            app.S.add_offset = app.AddoffsetSwitch.Value;

            if strcmp(app.S.add_offset,'Yes')==1
                app.ForceextinctioncoefficientM1cm1EditField.Visible='on';
                app.ForceextinctioncoefficientM1cm1EditFieldLabel.Visible='on';
                app.atnmEditField_2Label.Visible='on';
                app.atnmEditField_2.Visible='on';
            else
                app.ForceextinctioncoefficientM1cm1EditField.Visible='off';
                app.ForceextinctioncoefficientM1cm1EditFieldLabel.Visible='off';
                app.atnmEditField_2Label.Visible='off';
                app.atnmEditField_2.Visible='off';
            end

            if app.S.lambda_eps_offset>app.S.lambda_max
                app.S.lambda_eps_offset=app.S.lambda_max;
                app.atnmEditField_2.Value=app.S.lambda_eps_offset;
            end

            if app.S.lambda_eps_offset<app.S.lambda_min
                app.S.lambda_eps_offset=app.S.lambda_min;
                app.atnmEditField_2.Value=app.S.lambda_eps_offset;
            end

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: ForceextinctioncoefficientM1cm1EditField
        function ForceextinctioncoefficientM1cm1EditFieldValueChanged(app, event)
            app.S.eps_offset = app.ForceextinctioncoefficientM1cm1EditField.Value;

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: atnmEditField_2
        function atnmEditField_2ValueChanged(app, event)
            app.S.lambda_eps_offset = app.atnmEditField_2.Value;

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: AddnoiseSwitch
        function AddnoiseSwitchValueChanged(app, event)
            app.S.add_noise = app.AddnoiseSwitch.Value;

            if strcmp(app.S.add_noise,'Yes')==1
                app.rmsnoiseEditField.Visible='on';
                app.rmsnoiseEditFieldLabel.Visible='on';
            else
                app.rmsnoiseEditField.Visible='off';
                app.rmsnoiseEditFieldLabel.Visible='off';
            end

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: rmsnoiseEditField
        function rmsnoiseEditFieldValueChanged(app, event)
            app.S.rms_noise = app.rmsnoiseEditField.Value;

            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: ExcelcolumnletterEditField
        function ExcelcolumnletterEditFieldValueChanged(app, event)
            app.S.xls_col = app.ExcelcolumnletterEditField.Value;
        end

        % Value changed function: ExcelsheetnumberEditField
        function ExcelsheetnumberEditFieldValueChanged(app, event)
            app.S.xls_sheet = app.ExcelsheetnumberEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            change_ok=1;
            if indices(2)==1 % Position
                if newData<=app.S.lambda_min || newData>=app.S.lambda_max
                    warndlg('Position must lie within specified spectral range !','Warning')
                    change_ok=0;
                end
            end

            if indices(2)==2 % Width
                if newData<=0
                    warndlg('Width must be positive !','Warning')
                    change_ok=0;
                end
            end

            if indices(2)==3 % Amplitude
                if newData<=0
                    warndlg('Amplitude must be positive !','Warning')
                    change_ok=0;
                end
            end

            if change_ok==1
                updateTable(app);
                calculate_spectrum(app);
                plot_spectrum(app);
            else
                app.UITable.Data(indices(1),indices(2))={event.PreviousData};
            end

        end

        % Value changed function: IncludetypicalabsorptionspectrumSwitch
        function IncludetypicalabsorptionspectrumSwitchValueChanged(app, event)
            app.S.include_typical_abs = app.IncludetypicalabsorptionspectrumSwitch.Value;

            if strcmp(app.S.include_typical_abs,'Yes')==1
                %Load the typical absorption spectrum
                load(fullfile(app.S.typ_spectra_dir,'Typical_abs_em_spectra.mat'),'abs_spectrum');
                app.S.typ_abs_spectrum=abs_spectrum;
                %Increase the table length
                app.UITable.Data(size(app.UITable.Data,1)+1,:)=table(488,10,1);
                app.UITable.RowName=[app.UITable.RowName;{'Absorption'}];
            else
                %Decrease the table length
                w=find(strcmp(app.UITable.RowName,'Absorption')==1,1);
                app.UITable.Data(w,:)=[];
                app.UITable.RowName(w,:)=[];
            end

            updateTable(app);
            calculate_spectrum(app);
            plot_spectrum(app);
        end

        % Value changed function: IncludetypicalemissionspectrumSwitch
        function IncludetypicalemissionspectrumSwitchValueChanged(app, event)
            app.S.include_typical_em  = app.IncludetypicalemissionspectrumSwitch.Value;

            if strcmp(app.S.include_typical_em,'Yes')==1
                %Load the typical absorption spectrum
                load(fullfile(app.S.typ_spectra_dir,'Typical_abs_em_spectra.mat'),'em_spectrum');
                app.S.typ_em_spectrum=em_spectrum;
                %Increase the table length
                app.UITable.Data(size(app.UITable.Data,1)+1,:)=table(510,10,1);
                app.UITable.RowName=[app.UITable.RowName;{'Emission'}];
            else
                %Decrease the table length
                w=find(strcmp(app.UITable.RowName,'Emission')==1,1);
                app.UITable.Data(w,:)=[];
                app.UITable.RowName(w,:)=[];
            end

            updateTable(app);
            calculate_spectrum(app);
            plot_spectrum(app);

        end

        % Button pushed function: LoadexisitinginsilicospectrumButton
        function LoadexisitinginsilicospectrumButtonPushed(app, event)
            %Load matlab file uigetfile
            cd(app.S.xls_dir)
            [file,path] = uigetfile('*.mat','Select a Matlab input file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'S_Data');

                if exist('S_Data','var')

                    typ_spectra_dir=app.S.typ_spectra_dir;

                    app.S=S_Data;
                    if ~isfield(app.S,'typ_spectra_dir') % Patch for SMIS 1.3
                        app.S.typ_spectra_dir=typ_spectra_dir;
                    end


                    if strcmp(app.S.add_offset,'Yes')==1
                        app.ForceextinctioncoefficientM1cm1EditField.Visible='on';
                        app.ForceextinctioncoefficientM1cm1EditFieldLabel.Visible='on';
                        app.atnmEditField_2Label.Visible='on';
                        app.atnmEditField_2.Visible='on';
                    else
                        app.ForceextinctioncoefficientM1cm1EditField.Visible='off';
                        app.ForceextinctioncoefficientM1cm1EditFieldLabel.Visible='off';
                        app.atnmEditField_2Label.Visible='off';
                        app.atnmEditField_2.Visible='off';
                    end


                    updateApp(app);
                    updateTable(app);
                    calculate_spectrum(app);
                    plot_spectrum(app);
                    app.OutputExcelFileEditField.Value=strrep(file,'.mat','.xlsx');
                else
                    MyDlg=warndlg('File contains invalid data !');
                    waitfor(MyDlg)
                end
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: TypeofSpectrumSwitch
        function TypeofSpectrumSwitchValueChanged(app, event)
            app.S.spectrum_type = app.TypeofSpectrumSwitch.Value;

            updateApp(app);
            updateTable(app);
            calculate_spectrum(app);
            plot_spectrum(app);


        end

        % Value changed function: OutputExcelFileEditField
        function OutputExcelFileEditFieldValueChanged(app, event)
            value = app.OutputExcelFileEditField.Value;

            if strcmp(value(end-4:end),'.xlsx')==1
                app.S.xls_file=value;
            else
                app.OutputExcelFileEditField.Value=event.PreviousValue;
                warndlg('Invalid filename. Must end with ''.xlsx''','Warning');
            end
        end

        % Button pushed function: SavespectrumButton
        function SavespectrumButtonPushed(app, event)

            write_OK=1;

            cd(app.S.xls_dir);
            [NewFile,NewDir] = uiputfile('*.xlsx','Excel File Selection',app.S.xls_file);
            if ~isnumeric(NewDir) || ~isnumeric(NewFile)
                app.S.xls_dir=NewDir;
                app.S.xls_file=NewFile;
                OutFileXls=fullfile(app.S.xls_dir,app.S.xls_file);
                OutFileMat=fullfile(app.S.xls_dir,strrep(app.S.xls_file,'.xlsx','.mat'));
            else
                write_OK=0;
            end

            if write_OK==1
                app.OutputExcelFileEditField.Value=app.S.xls_file;
                S_Data=app.S;
                save(OutFileMat,'S_Data');
                disp(['Matlab file written: ',OutFileMat]);
                S_Table=table(app.S.x',app.S.y');
                S_Table.Properties.VariableNames={'x' 'y'};
                S_Range=[app.S.xls_col,'1:',char(app.S.xls_col + 1),num2str(numel(app.S.x)+1)];
                try
                    writetable(S_Table,OutFileXls,'Sheet',app.S.xls_sheet,'Range',S_Range)
                catch
                    warndlg('Excel file could not be written ! (Perhaps already opened in Excel ?)', 'Warning')
                end
                disp(['Excel file written: ',OutFileXls]);
                app.Saved_OK=1;
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Created spectrum')
            xlabel(app.UIAxes, 'Wavelength [nm]')
            ylabel(app.UIAxes, 'Exctinction coeff [M-1cm-1]')
            app.UIAxes.XLim = [200 800];
            app.UIAxes.YLim = [0 100];
            app.CreateInSilicoSpectrumLabel = uilabel(app.UIFigure);
            app.CreateInSilicoSpectrumLabel.Text = 'Create In Silico Spectrum';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Position'; 'Width'; 'Amplitude'};
            app.UITable.RowName = {'Gaussian 1'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.IncludetypicalabsorptionspectrumSwitchLabel = uilabel(app.UIFigure);
            app.IncludetypicalabsorptionspectrumSwitchLabel.Text = 'Include typical absorption spectrum ';
            app.IncludetypicalabsorptionspectrumSwitch = uiswitch(app.UIFigure, 'slider');
            app.IncludetypicalabsorptionspectrumSwitch.Items = {'No', 'Yes'};
            app.IncludetypicalabsorptionspectrumSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludetypicalabsorptionspectrumSwitchValueChanged, true);
            app.IncludetypicalabsorptionspectrumSwitch.Value = 'No';
            app.IncludetypicalemissionspectrumSwitchLabel = uilabel(app.UIFigure);
            app.IncludetypicalemissionspectrumSwitchLabel.Visible = 'off';
            app.IncludetypicalemissionspectrumSwitchLabel.Text = 'Include typical emission spectrum ';
            app.IncludetypicalemissionspectrumSwitch = uiswitch(app.UIFigure, 'slider');
            app.IncludetypicalemissionspectrumSwitch.Items = {'No', 'Yes'};
            app.IncludetypicalemissionspectrumSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludetypicalemissionspectrumSwitchValueChanged, true);
            app.IncludetypicalemissionspectrumSwitch.Visible = 'off';
            app.IncludetypicalemissionspectrumSwitch.Value = 'No';
            app.OutputExcelFileEditFieldLabel = uilabel(app.UIFigure);
            app.OutputExcelFileEditFieldLabel.Text = 'Output Excel File';
            app.OutputExcelFileEditField = uieditfield(app.UIFigure, 'text');
            app.OutputExcelFileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputExcelFileEditFieldValueChanged, true);
            app.OutputExcelFileEditField.Value = 'MySpectrum.xlsx';
            app.ExcelcolumnletterEditFieldLabel = uilabel(app.UIFigure);
            app.ExcelcolumnletterEditFieldLabel.Text = 'Excel column letter';
            app.ExcelcolumnletterEditField = uieditfield(app.UIFigure, 'text');
            app.ExcelcolumnletterEditField.ValueChangedFcn = createCallbackFcn(app, @ExcelcolumnletterEditFieldValueChanged, true);
            app.ExcelcolumnletterEditField.Value = 'A';
            app.NumberofGaussiansEditField_2Label = uilabel(app.UIFigure);
            app.NumberofGaussiansEditField_2Label.Text = 'Number of Gaussians';
            app.NumberofGaussiansEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofGaussiansEditField.Limits = [0 100];
            app.NumberofGaussiansEditField.ValueDisplayFormat = '%.0f';
            app.NumberofGaussiansEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofGaussiansEditFieldValueChanged, true);
            app.NumberofGaussiansEditField.Value = 1;
            app.LambdaminnmEditField_2Label = uilabel(app.UIFigure);
            app.LambdaminnmEditField_2Label.Text = 'Lambda min [nm]';
            app.LambdaminnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambdaminnmEditField.Limits = [100 2000];
            app.LambdaminnmEditField.ValueDisplayFormat = '%.0f';
            app.LambdaminnmEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaminnmEditFieldValueChanged, true);
            app.LambdaminnmEditField.Value = 250;
            app.LambdamaxnmEditField_2Label = uilabel(app.UIFigure);
            app.LambdamaxnmEditField_2Label.Text = 'Lambda max [nm]';
            app.LambdamaxnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambdamaxnmEditField.Limits = [100 2000];
            app.LambdamaxnmEditField.ValueDisplayFormat = '%.0f';
            app.LambdamaxnmEditField.ValueChangedFcn = createCallbackFcn(app, @LambdamaxnmEditFieldValueChanged, true);
            app.LambdamaxnmEditField.Value = 800;
            app.ExtinctioncoefficientM1cm1EditField_2Label = uilabel(app.UIFigure);
            app.ExtinctioncoefficientM1cm1EditField_2Label.Text = 'Extinction coefficient [M-1cm-1]';
            app.ExtinctioncoefficientM1cm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ExtinctioncoefficientM1cm1EditField.Limits = [0 Inf];
            app.ExtinctioncoefficientM1cm1EditField.ValueChangedFcn = createCallbackFcn(app, @ExtinctioncoefficientM1cm1EditFieldValueChanged, true);
            app.ExtinctioncoefficientM1cm1EditField.Value = 30000;
            app.atnmEditFieldLabel = uilabel(app.UIFigure);
            app.atnmEditFieldLabel.Text = 'at [nm]';
            app.atnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.atnmEditField.Limits = [100 2000];
            app.atnmEditField.ValueChangedFcn = createCallbackFcn(app, @atnmEditFieldValueChanged, true);
            app.atnmEditField.Value = 488;
            app.ExcelsheetnumberEditFieldLabel = uilabel(app.UIFigure);
            app.ExcelsheetnumberEditFieldLabel.Text = 'Excel sheet number';
            app.ExcelsheetnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExcelsheetnumberEditField.Limits = [1 200];
            app.ExcelsheetnumberEditField.ValueChangedFcn = createCallbackFcn(app, @ExcelsheetnumberEditFieldValueChanged, true);
            app.ExcelsheetnumberEditField.Value = 1;
            app.TypeofSpectrumLabel = uilabel(app.UIFigure);
            app.TypeofSpectrumLabel.Text = 'Type of Spectrum:';
            app.TypeofSpectrumSwitch = uiswitch(app.UIFigure, 'slider');
            app.TypeofSpectrumSwitch.Items = {'Abs/Exc', 'Em'};
            app.TypeofSpectrumSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeofSpectrumSwitchValueChanged, true);
            app.TypeofSpectrumSwitch.Value = 'Abs/Exc';
            app.AddoffsetSwitchLabel = uilabel(app.UIFigure);
            app.AddoffsetSwitchLabel.Text = 'Add offset';
            app.AddoffsetSwitch = uiswitch(app.UIFigure, 'slider');
            app.AddoffsetSwitch.Items = {'No', 'Yes'};
            app.AddoffsetSwitch.ValueChangedFcn = createCallbackFcn(app, @AddoffsetSwitchValueChanged, true);
            app.AddoffsetSwitch.Value = 'No';
            app.ForceextinctioncoefficientM1cm1EditFieldLabel = uilabel(app.UIFigure);
            app.ForceextinctioncoefficientM1cm1EditFieldLabel.Visible = 'off';
            app.ForceextinctioncoefficientM1cm1EditFieldLabel.Text = 'Force extinction coefficient [M-1cm-1]';
            app.ForceextinctioncoefficientM1cm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceextinctioncoefficientM1cm1EditField.Limits = [0 Inf];
            app.ForceextinctioncoefficientM1cm1EditField.ValueChangedFcn = createCallbackFcn(app, @ForceextinctioncoefficientM1cm1EditFieldValueChanged, true);
            app.ForceextinctioncoefficientM1cm1EditField.Visible = 'off';
            app.ForceextinctioncoefficientM1cm1EditField.Value = 10;
            app.atnmEditField_2Label = uilabel(app.UIFigure);
            app.atnmEditField_2Label.Visible = 'off';
            app.atnmEditField_2Label.Text = 'at [nm]';
            app.atnmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.atnmEditField_2.Limits = [100 2000];
            app.atnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @atnmEditField_2ValueChanged, true);
            app.atnmEditField_2.Visible = 'off';
            app.atnmEditField_2.Value = 650;
            app.LoadexisitinginsilicospectrumButton = uibutton(app.UIFigure, 'push');
            app.LoadexisitinginsilicospectrumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexisitinginsilicospectrumButtonPushed, true);
            app.LoadexisitinginsilicospectrumButton.Text = 'Load exisiting in silico spectrum ';
            app.AddnoiseSwitchLabel = uilabel(app.UIFigure);
            app.AddnoiseSwitchLabel.Text = 'Add noise';
            app.AddnoiseSwitch = uiswitch(app.UIFigure, 'slider');
            app.AddnoiseSwitch.Items = {'No', 'Yes'};
            app.AddnoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @AddnoiseSwitchValueChanged, true);
            app.AddnoiseSwitch.Value = 'No';
            app.rmsnoiseEditFieldLabel = uilabel(app.UIFigure);
            app.rmsnoiseEditFieldLabel.Visible = 'off';
            app.rmsnoiseEditFieldLabel.Text = 'rms noise ';
            app.rmsnoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmsnoiseEditField.Limits = [0 Inf];
            app.rmsnoiseEditField.ValueChangedFcn = createCallbackFcn(app, @rmsnoiseEditFieldValueChanged, true);
            app.rmsnoiseEditField.Visible = 'off';
            app.SavespectrumButton = uibutton(app.UIFigure, 'push');
            app.SavespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SavespectrumButtonPushed, true);
            app.SavespectrumButton.Text = 'Save spectrum';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateSpectrum(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end