classdef DefaultDirectories < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        LoadButton_4                    matlab.ui.control.Button
        SMISSimulationslibraryEditField  matlab.ui.control.EditField
        SMISSimulationslibraryEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton_3                    matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FluorophoreslibraryEditField    matlab.ui.control.EditField
        FluorophoreslibraryEditFieldLabel  matlab.ui.control.Label
        SamplepatternslibraryEditField  matlab.ui.control.EditField
        SamplepatternslibraryEditFieldLabel  matlab.ui.control.Label
        SimulationdataoutputEditField   matlab.ui.control.EditField
        SimulationdataoutputEditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        DefaultDirectoriesLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        DefaultDirspar=[]; % Initialize the parameters
    end
    
    methods (Access = private)
        
        function IsOK = check_dir(~,MyDir)
            IsOK=1;
            if ispc
                backlash_pos=strfind(MyDir,'\');
            else
                backlash_pos=strfind(MyDir,'/');
            end
            if isempty(backlash_pos)
                warndlg('This does not look like a directory path','Warning');
                IsOK=0;
            else
                subdir=MyDir(1:backlash_pos(end-1));
                if ~exist(subdir,'dir') % Check if the directory name makes sense
                    fig = uifigure;
                    fig.Position = [800 600 400 160];
                    MySel = uiconfirm(fig,'Do you really want to create this strange looking directory name ?','','Icon','warning');
                    close(fig)
                    if strcmp(MySel,'OK') ~= 1
                        IsOK=0;
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, input_par)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize DefaultDirspar
            app.DefaultDirspar=input_par;
            % Update UI with input values
            app.SimulationdataoutputEditField.Value = input_par.SMIS_dir;
            app.SamplepatternslibraryEditField.Value = input_par.Pattern_dir;
            app.FluorophoreslibraryEditField.Value = input_par.Fluorophore_dir;
            app.SMISSimulationslibraryEditField.Value = input_par.SMIS_Simulations_dir;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateDefaultDirs(app.CallingApp, app.DefaultDirspar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: SimulationdataoutputEditField
        function SimulationdataoutputEditFieldValueChanged(app, event)
            value = app.SimulationdataoutputEditField.Value;
            if ~exist(value,'dir')
                if check_dir(app,value)==1
                    disp('Creating directory ...')
                    mkdir(value)
                    app.DefaultDirspar.SMIS_dir=value;
                else
                    app.SimulationdataoutputEditField.Value=event.PreviousValue;
                end
                
            else % Accept in any case
                app.DefaultDirspar.SMIS_dir=value;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            MyDir = uigetdir(app.DefaultDirspar.SMIS_dir,'Select a default directory where the SMIS simulation output files will be stored');
            if ~isnumeric(MyDir)
                app.DefaultDirspar.SMIS_dir = MyDir;
            end
            app.SimulationdataoutputEditField.Value=app.DefaultDirspar.SMIS_dir;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            MyDir = uigetdir(app.DefaultDirspar.Pattern_dir,'Select the directory where the input pattern files are stored');
            if ~isnumeric(MyDir)
                app.DefaultDirspar.Pattern_dir = MyDir;
            end
            app.SamplepatternslibraryEditField.Value=app.DefaultDirspar.Pattern_dir;
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            MyDir = uigetdir(app.DefaultDirspar.Fluorophore_dir,'Select the directory where the input fluorophores parameter files are stored');
            if ~isnumeric(MyDir)
                app.DefaultDirspar.Fluorophore_dir = MyDir;
            end
            app.FluorophoreslibraryEditField.Value=app.DefaultDirspar.Fluorophore_dir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            DefaultDirs = struct(...
                'SMIS_GUI_Dir', app.DefaultDirspar.SMIS_GUI_Dir, ...
                'SMIS_Image_Dir', app.DefaultDirspar.SMIS_Image_Dir, ...
                'SMIS_dir', app.SimulationdataoutputEditField.Value, ...
                'SMIS_Simulations_dir', app.SMISSimulationslibraryEditField.Value, ...
                'Pattern_dir', app.SamplepatternslibraryEditField.Value, ...
                'Fluorophore_dir', app.FluorophoreslibraryEditField.Value ...
                );
            MyFile=fullfile(app.DefaultDirspar.SMIS_GUI_Dir,'STARTUP_MAT_FILES','SMIS_DefaultDir.mat');
            save(MyFile,'DefaultDirs');
            disp(['Saved Default SMIS Directories in file: ',MyFile]);
        end

        % Value changed function: SamplepatternslibraryEditField
        function SamplepatternslibraryEditFieldValueChanged(app, event)
            value = app.SamplepatternslibraryEditField.Value;
            if ~exist(value,'dir')
                if check_dir(app,value)==1
                    disp('Creating directory ...')
                    mkdir(value)
                    app.DefaultDirspar.Pattern_dir=value;
                else
                    app.SamplepatternslibraryEditField.Value=event.PreviousValue;
                end
            else % Accept in any case
                app.DefaultDirspar.Pattern_dir=value;
            end
        end

        % Value changed function: FluorophoreslibraryEditField
        function FluorophoreslibraryEditFieldValueChanged(app, event)
            value = app.FluorophoreslibraryEditField.Value;
            if ~exist(value,'dir')
                if check_dir(app,value)==1
                    disp('Creating directory ...')
                    mkdir(value)
                    app.DefaultDirspar.Fluorophore_dir=value;
                else
                    app.FluorophoreslibraryEditField.Value=event.PreviousValue;
                end
                
            else % Accept in any case
                app.DefaultDirspar.Fluorophore_dir=value;
            end
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            MyDir = uigetdir(app.DefaultDirspar.Fluorophore_dir,'Select the directory where SMIS simulations are stored');
            if ~isnumeric(MyDir)
                app.DefaultDirspar.SMIS_Simulations_dir = MyDir;
            end
            app.SMISSimulationslibraryEditField.Value=app.DefaultDirspar.SMIS_Simulations_dir;
        end

        % Value changed function: SMISSimulationslibraryEditField
        function SMISSimulationslibraryEditFieldValueChanged(app, event)
            value = app.SMISSimulationslibraryEditField.Value;
            if ~exist(value,'dir')
                if check_dir(app,value)==1
                    disp('Creating directory ...')
                    mkdir(value)
                    app.DefaultDirspar.SMIS_Simulations_dir=value;
                else
                    app.SMISSimulationslibraryEditField.Value=event.PreviousValue;
                end
            else % Accept in any case
                app.DefaultDirspar.SMIS_Simulations_dir=value;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DefaultDirectoriesLabel = uilabel(app.UIFigure);
            app.DefaultDirectoriesLabel.Text = 'Default Directories ';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SimulationdataoutputEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationdataoutputEditFieldLabel.Text = 'Simulation data output';
            app.SimulationdataoutputEditField = uieditfield(app.UIFigure, 'text');
            app.SimulationdataoutputEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationdataoutputEditFieldValueChanged, true);
            app.SimulationdataoutputEditField.Tooltip = {'Default directory where simulation data will be output'};
            app.SamplepatternslibraryEditFieldLabel = uilabel(app.UIFigure);
            app.SamplepatternslibraryEditFieldLabel.Text = 'Sample patterns library';
            app.SamplepatternslibraryEditField = uieditfield(app.UIFigure, 'text');
            app.SamplepatternslibraryEditField.ValueChangedFcn = createCallbackFcn(app, @SamplepatternslibraryEditFieldValueChanged, true);
            app.SamplepatternslibraryEditField.Tooltip = {'Default directory where SMIS virtual sample patterns are stored'};
            app.FluorophoreslibraryEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophoreslibraryEditFieldLabel.Text = 'Fluorophore''s library';
            app.FluorophoreslibraryEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophoreslibraryEditField.ValueChangedFcn = createCallbackFcn(app, @FluorophoreslibraryEditFieldValueChanged, true);
            app.FluorophoreslibraryEditField.Tooltip = {'Default directory where SMIS fluorophores parameters are stored'};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Click here to save the default directories for future sessions of SMIS'};
            app.SaveButton.Text = 'Save';
            app.SMISSimulationslibraryEditFieldLabel = uilabel(app.UIFigure);
            app.SMISSimulationslibraryEditFieldLabel.Text = 'SMIS Simulations library ';
            app.SMISSimulationslibraryEditField = uieditfield(app.UIFigure, 'text');
            app.SMISSimulationslibraryEditField.ValueChangedFcn = createCallbackFcn(app, @SMISSimulationslibraryEditFieldValueChanged, true);
            app.SMISSimulationslibraryEditField.Tooltip = {'Default directory where SMIS simulations parameters are stored'};
            app.LoadButton_4 = uibutton(app.UIFigure, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefaultDirectories(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end