classdef DefineNewFluorophore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        CreateinsilicospectrumButton   matlab.ui.control.Button
        DoneCheckBox                   matlab.ui.control.CheckBox
        ExtractSpectrafromExcelFileButton  matlab.ui.control.Button
        RemovestateatpositionDropDown  matlab.ui.control.DropDown
        RemovestateatpositionDropDownLabel  matlab.ui.control.Label
        InsertthermaldarkstatebeforepositionDropDown  matlab.ui.control.DropDown
        InsertthermaldarkstatebeforepositionDropDownLabel  matlab.ui.control.Label
        InsertphotoactivedarkstatebeforepositionDropDown  matlab.ui.control.DropDown
        InsertphotoactivedarkstatebeforepositionDropDownLabel  matlab.ui.control.Label
        InsertbleachedstatebeforepositionDropDown  matlab.ui.control.DropDown
        InsertbleachedstatebeforepositionDropDownLabel  matlab.ui.control.Label
        InsertfluorescentstatebeforepositionDropDown  matlab.ui.control.DropDown
        InsertfluorescentstatebeforepositionDropDownLabel  matlab.ui.control.Label
        LoadExistingFluorophoreButton  matlab.ui.control.Button
        SpectradatafileEditField       matlab.ui.control.EditField
        SpectradatafileEditFieldLabel  matlab.ui.control.Label
        ChooseExcelfileforspectraldataButton  matlab.ui.control.Button
        CheckSpectraDropDown           matlab.ui.control.DropDown
        CheckSpectraDropDownLabel      matlab.ui.control.Label
        UIPhotophysicsTable            matlab.ui.control.Table
        NumberofphotophysicalstatesEditField  matlab.ui.control.NumericEditField
        NumberofphotophysicalstatesEditField_2Label  matlab.ui.control.Label
        SaveFluorophoreButton          matlab.ui.control.Button
        SetupthermalexchangeratematrixsButton  matlab.ui.control.Button
        SetupphototransformationsquantumyieldsButton  matlab.ui.control.Button
        pHSensitivitySwitch            matlab.ui.control.Switch
        pHSensitivitySwitchLabel       matlab.ui.control.Label
        FluorophorenameEditField       matlab.ui.control.EditField
        FluorophorenameEditFieldLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        NewFluorophoreDefinitionLabel  matlab.ui.control.Label
        UIAxesEmSpectrum               matlab.ui.control.UIAxes
        UIAxesAbsSpectrum              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        
        Fluorophorepar=[]; % Initialize the parameters
        n_states=[]; % Initialize number of photophysical states
        state_names=[]; % Initialize state names
        TableData=[];
        QY_App=[]; % Phototransformation quantum yield setup App
        kT_App=[]; % Phototransformation quantum yield setup App
        CreateSpectrum_App=[]; % Create Spectrum App
        FluorophoreDir % Description
    end
    
    methods (Access = private)
        
        function UpdateFluorophorePar(app)
            
            app.n_states=app.Fluorophorepar.Photophysics.n_states;
            
            % Define position of the table
            app.UIPhotophysicsTable.Position=[80,max([270,520-22*app.n_states]),1150,min([302,46+22*app.n_states])]; % Adjust position
            
            % Define states names
            RowNames=cell(1,app.n_states);
            for i=1:app.Fluorophorepar.Photophysics.n_states
                RowNames{i}=['State ',num2str(i)];
            end
            app.UIPhotophysicsTable.RowName=RowNames;
            
            % Retrieve the content of the table
            app.state_names=app.Fluorophorepar.Photophysics.state_names;
            IsFluorescent=false(app.n_states,1); IsFluorescent(app.Fluorophorepar.Photophysics.fluorescent_states)=true;
            QY=nan(app.n_states,1); QY(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.quantum_yield;
            
            IsPhotoactiveDark=false(app.n_states,1); IsPhotoactiveDark(app.Fluorophorepar.Photophysics.photoactive_dark_states)=true;
            IsThermalDark=false(app.n_states,1); IsThermalDark(app.Fluorophorepar.Photophysics.thermal_dark_states)=true;
            IsBleached=false(app.n_states,1); IsBleached(app.Fluorophorepar.Photophysics.bleached_states)=true;
            
            AbsSpectrumExcelSheet=nan(app.n_states,1); AbsSpectrumExcelSheet(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.xls_exc_sheet;
            AbsSpectrumExcelSheet(app.Fluorophorepar.Photophysics.photoactive_dark_states)=app.Fluorophorepar.Photophysics.xls_dark_sheet;
            AbsSpectrumExcelRange=cell(app.n_states,1); AbsSpectrumExcelRange(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.xls_exc_range;
            AbsSpectrumExcelRange(app.Fluorophorepar.Photophysics.photoactive_dark_states)=app.Fluorophorepar.Photophysics.xls_dark_range;
            
            EmSpectrumExcelSheet=nan(app.n_states,1); EmSpectrumExcelSheet(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.xls_em_sheet;
            EmSpectrumExcelRange=cell(app.n_states,1); EmSpectrumExcelRange(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.xls_em_range;
            
            Epsilon=nan(app.n_states,1); Epsilon(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.extinction_coeff;
            Epsilon(app.Fluorophorepar.Photophysics.photoactive_dark_states)=app.Fluorophorepar.Photophysics.extinction_coeff_dark;
            LambdaEps=nan(app.n_states,1); LambdaEps(app.Fluorophorepar.Photophysics.fluorescent_states)=app.Fluorophorepar.Photophysics.extinction_coeff_lambda;
            LambdaEps(app.Fluorophorepar.Photophysics.photoactive_dark_states)=app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark;
            
            Photoconverted=false(app.n_states,1);
            if app.Fluorophorepar.Photophysics.converted_state>1 % Otherwise fluorophore is not photoconvertible
                Photoconverted(app.Fluorophorepar.Photophysics.converted_state)=true;
            end
            
            InRapidEquilibrium=false(app.n_states,1);
            PairedStateName=cell(app.n_states,1); PairedStateName(:)={'None'};
            pKa=nan(app.n_states,1);
            Hill=nan(app.n_states,1);
            
            w_rapid=setdiff(1:app.n_states, unique(app.Fluorophorepar.Photophysics.state_ids)); % Set of states in rapid equilibrium
            if ~isempty(w_rapid) % States in rapid equilibrium were found
                for k=1:numel(w_rapid)
                    w_pair=find(app.Fluorophorepar.Photophysics.state_ids==app.Fluorophorepar.Photophysics.state_ids(w_rapid(k)));
                    PairedStateName(w_pair(1))= app.state_names(w_pair(2));
                    PairedStateName(w_pair(2))= app.state_names(w_pair(1));
                    InRapidEquilibrium(w_pair)=true;
                    if app.Fluorophorepar.Environment.pH_sensitivity==1
                        w_pKa=find(ismember(app.Fluorophorepar.Photophysics.fluorescent_states,w_pair)==1,1);
                        pKa(w_pair)= app.Fluorophorepar.Environment.pKa(w_pKa);
                        Hill(w_pair)= app.Fluorophorepar.Environment.Hill(w_pKa);
                    end
                end
            end
            
            % Update the table data
            app.TableData = table(app.state_names,IsFluorescent,QY,IsPhotoactiveDark,IsThermalDark,IsBleached,...
                AbsSpectrumExcelSheet, AbsSpectrumExcelRange, EmSpectrumExcelSheet, EmSpectrumExcelRange, ...
                Epsilon, LambdaEps, Photoconverted, InRapidEquilibrium, PairedStateName, pKa, Hill, ...
                'VariableNames',app.UIPhotophysicsTable.ColumnName);
            
            app.UIPhotophysicsTable.Data=app.TableData;
            
            % Update number of photophysical states
            app.NumberofphotophysicalstatesEditField.Value=app.n_states;
            
            % Update for fluorophore name
            app.FluorophorenameEditField.Value = app.Fluorophorepar.name;
            
            % Update pH sensitivity
            if app.Fluorophorepar.Environment.pH_sensitivity==1
                app.pHSensitivitySwitch.Value = 'On';
                app.UIPhotophysicsTable.ColumnEditable(16:17)=1;
            else
                app.pHSensitivitySwitch.Value = 'Off';
                app.UIPhotophysicsTable.ColumnEditable(16:17)=0;
            end
            
            % Update Excel file
            app.SpectradatafileEditField.Value=app.Fluorophorepar.xls_spectrum_file;
            
            xls_spectrum_file=app.Fluorophorepar.xls_spectrum_file;
            xls_spectrum_dir=app.Fluorophorepar.xls_spectrum_dir;
            spectrum_file = fullfile(xls_spectrum_dir,xls_spectrum_file);
            
            if ~exist(spectrum_file,'file')
                MyDlg=warndlg(['Excel file containing spectra not found: ', newline, spectrum_file], 'warning');
                waitfor(MyDlg)
            end
            
            % Untick the Extract spectra done ! button
            app.DoneCheckBox.Value=0;
            
            % Update drop-down menus
            drop_down_names= cell(1, app.n_states+2);
            drop_down_names(1) = {'Choose'};
            drop_down_names(2:end-1) = app.state_names;
            drop_down_names(end) = {'At end'};
            
            app.InsertfluorescentstatebeforepositionDropDown.Items=drop_down_names;
            app.InsertfluorescentstatebeforepositionDropDown.ItemsData=1:app.n_states+2;
            app.InsertbleachedstatebeforepositionDropDown.Items=drop_down_names;
            app.InsertbleachedstatebeforepositionDropDown.ItemsData=1:app.n_states+2;
            app.InsertphotoactivedarkstatebeforepositionDropDown.Items=drop_down_names;
            app.InsertphotoactivedarkstatebeforepositionDropDown.ItemsData=1:app.n_states+2;
            app.InsertthermaldarkstatebeforepositionDropDown.Items=drop_down_names;
            app.InsertthermaldarkstatebeforepositionDropDown.ItemsData=1:app.n_states+2;
            app.RemovestateatpositionDropDown.Items=drop_down_names(1:end-1);
            app.RemovestateatpositionDropDown.ItemsData=1:app.n_states+1;
            
            IsPhotoactive=IsFluorescent | IsPhotoactiveDark;
            n_photoactive_states=numel(find(IsPhotoactive==1));
            drop_down_names= cell(1, n_photoactive_states+1);
            drop_down_names(1) = {'Choose'};
            drop_down_names(2:end) = app.state_names(IsPhotoactive);
            
            app.CheckSpectraDropDown.Items=drop_down_names(1:end);
            app.CheckSpectraDropDown.ItemsData=1:n_photoactive_states+1;

            figure(app.UIFigure); % Get the GUI in the front
        end
        
        function UpdateTrans_kq(app, selected_state,add)
            
            trans_k=app.Fluorophorepar.Photophysics.trans_k;
            trans_q=app.Fluorophorepar.Photophysics.trans_q;
            
            if add==1 % Add new column and new row
                newcol=zeros(app.n_states-1,1);
                newrow=zeros(1, app.n_states);
                
                if selected_state>1 && selected_state<app.n_states
                    trans_k = [trans_k(:,1:selected_state-1) newcol trans_k(:,selected_state:end)];
                    trans_k = [trans_k(1:selected_state-1,:); newrow; trans_k(selected_state:end,:)];
                    trans_q = [trans_q(:,1:selected_state-1) newcol trans_q(:,selected_state:end)];
                    trans_q = [trans_q(1:selected_state-1,:); newrow; trans_q(selected_state:end,:)];
                elseif selected_state==1
                    trans_k = [newcol trans_k(:,selected_state:end)];
                    trans_k = [newrow; trans_k(selected_state:end,:)];
                    trans_q = [newcol trans_q(:,selected_state:end)];
                    trans_q = [newrow; trans_q(selected_state:end,:)];
                elseif selected_state==app.n_states
                    trans_k = [trans_k(:,1:selected_state-1) newcol];
                    trans_k = [trans_k(1:selected_state-1,:); newrow];
                    trans_q = [trans_q(:,1:selected_state-1) newcol];
                    trans_q = [trans_q(1:selected_state-1,:); newrow];
                end
            else % Remove new column and new row
                trans_k(:,selected_state)=[];
                trans_k(selected_state,:)=[];
                trans_q(:,selected_state)=[];
                trans_q(selected_state,:)=[];
            end
            app.Fluorophorepar.Photophysics.trans_k=trans_k;
            app.Fluorophorepar.Photophysics.trans_q=trans_q;
        end
        
        function results = func6(app)
            
        end
    end
    
    methods (Access = public)
        
        function updateQYSimple(app, QYpar)
            
            % Store inputs as properties
            if ~isempty(QYpar)
                app.Fluorophorepar.Photophysics.trans_q = QYpar;
            end
            
            % Re-enable the button
            app.SetupphototransformationsquantumyieldsButton.Enable='on';
        end
        
        function updatekTSimple(app, kTpar)
            
            % Store inputs as properties
            if ~isempty(kTpar)
                app.Fluorophorepar.Photophysics.trans_k = kTpar;
            end
            
            % Re-enable the button
            app.SetupthermalexchangeratematrixsButton.Enable='on';
        end
        
        function updateCancel(app)
            % Re-enable the buttons
            app.CreateinsilicospectrumButton.Enable='on';
            app.SetupthermalexchangeratematrixsButton.Enable='on';
            app.SetupphototransformationsquantumyieldsButton.Enable='on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = MainApp;
            
            %Get the directory where fluorophores are defined
            app.FluorophoreDir=MainApp.smis_gui_parameters.DefaultDirs.Fluorophore_dir;
            
            % Define a minimal fluorophore with three states
            app.Fluorophorepar.name = 'New Fluorophore'; % Name of the Fluorophore
            app.Fluorophorepar.xls_spectrum_file = 'BasicFP.xlsx'; % Excitation and emission spectra for all states from Excel
            app.Fluorophorepar.xls_spectrum_dir = fullfile(app.FluorophoreDir,'SPECTRA','BASIC_FP'); % Excitation and emission spectra for all states from Excel
            
            %General parameters
            app.Fluorophorepar.maturation_level=1; % Set to 1
            
            % pH
            app.Fluorophorepar.Environment.pH_sensitivity=0; % Set to 1 if dye is pH sensitive. If set to 0 the fluorescent states must not be in equilibrium with non-fluorescent states
            app.Fluorophorepar.Environment.pH=[]; % pH of the medium for the dyes
            %pKa and Hill coefficient
            app.Fluorophorepar.Environment.pKa = nan;
            app.Fluorophorepar.Environment.Hill = nan;
            
            %Fluorogenicity
            app.Fluorophorepar.Environment.fluorogenic = 0; % Set to 1 if fluorophore is fluorogenic, ie has different QY in the different subpatterns
            app.Fluorophorepar.Environment.fluorogenicity = []; % If fluorophore is fluorogenic, degree of fluorogenicity in the different subpatterns corresponding to sm_par(1).n_sp_id(assumed identical for all fluorescent states)
            
            % Total number of photophysical states
            app.Fluorophorepar.Photophysics.n_states=3;
            % 'Fluo'; 'Dark'; 'Bleached';
            % 1     ;  2    ; 3
            app.Fluorophorepar.Photophysics.state_names={'Fluo'; 'Dark'; 'Bleached'}; % ids of all states for the sm: BE CAREFUL: give the same id for states that are in rapid equilibrium
            app.Fluorophorepar.Photophysics.state_ids=(1:app.Fluorophorepar.Photophysics.n_states)'; % ids of all states for the sm: BE CAREFUL: give the same id for states that are in rapid equilibrium
            app.Fluorophorepar.Photophysics.initial_state=1; % # of the initial state for the sm
            app.Fluorophorepar.Photophysics.fluorescent_states=1; % # of the fluorescent state for the sm
            app.Fluorophorepar.Photophysics.converted_state=1; % # of the activated state for the sm
            app.Fluorophorepar.Photophysics.bleached_states=3; % # of the bleached state for the sm
            app.Fluorophorepar.Photophysics.photoactive_dark_states=2; % # of the photoactive dark state for the sm
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            %Spectroscopic signatures
            %1: Fluorescent states
            app.Fluorophorepar.Photophysics.n_fluorescent_states = 1; % number of fluorescent states
            app.Fluorophorepar.Photophysics.exc_name = app.Fluorophorepar.Photophysics.state_names(app.Fluorophorepar.Photophysics.fluorescent_states);
            %Excitation spectra
            app.Fluorophorepar.Photophysics.xls_exc_sheet = 1; % Excel sheet where excitation spectra are found
            app.Fluorophorepar.Photophysics.xls_exc_range= {'A2:B902'}; % Cells where excitation spectra are found
            %Exctinction coeff
            app.Fluorophorepar.Photophysics.extinction_coeff = 50000; %[cm-1Mol-1] epsilon for those excitation spectra in 100% pure state.
            app.Fluorophorepar.Photophysics.extinction_coeff_lambda = 488; % [nm] lambda for those epsilon (maxima taken from spectra in mEos2.xlsx)
            %Emission spectrum green and red
            app.Fluorophorepar.Photophysics.xls_em_sheet = 1; % Excel sheet where green emission spectrum is found
            app.Fluorophorepar.Photophysics.xls_em_range = {'C2:D302'}; % Cells where green emission spectrum is found
            %Fluorescence quantum yield:  give one value for each fluorescent state
            app.Fluorophorepar.Photophysics.quantum_yield = 0.7;
            
            %2: Photoactive dark states
            app.Fluorophorepar.Photophysics.n_photoactive_dark_states = 1; % number of dark states
            app.Fluorophorepar.Photophysics.photoactive_dark_names = app.Fluorophorepar.Photophysics.state_names(setdiff(app.Fluorophorepar.Photophysics.photoactive_states,app.Fluorophorepar.Photophysics.fluorescent_states));
            
            app.Fluorophorepar.Photophysics.xls_dark_sheet = 1; % Excel sheet where dark absorption spectra are found
            app.Fluorophorepar.Photophysics.xls_dark_range = {'E1:F897'}; % Cells where dark absorption spectra are found
            %Exctinction coeff 100% pure state
            app.Fluorophorepar.Photophysics.extinction_coeff_dark = 10000 ; %[cm-1Mol-1]  epsilon for those spectra
            app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark = 405; % [nm] lambda for those epsilon
            
            %3: Non-photoactive dark states
            app.Fluorophorepar.Photophysics.n_thermal_dark_states = 0; % number of dark states
            
            %Thermal transitions (give rates in [s-1], rates should not exceed 100kHz at most)
            app.Fluorophorepar.Photophysics.trans_k=zeros(app.Fluorophorepar.Photophysics.n_states,app.Fluorophorepar.Photophysics.n_states) ; %  thermally activated transition rate  matrice
            app.Fluorophorepar.Photophysics.thermal_dark_states=[];
            
            %Photoactivated transitions (give quantum yields)
            app.Fluorophorepar.Photophysics.trans_q=zeros(app.Fluorophorepar.Photophysics.n_states,app.Fluorophorepar.Photophysics.n_states) ; %  photoactivated transition QY  matrice
            q_FB=1e-7; app.Fluorophorepar.Photophysics.trans_q(1,3)=q_FB; % Bleaching
            q_FD=1e-5; app.Fluorophorepar.Photophysics.trans_q(1,2)=q_FD; % Switching
            q_DF=1e-2; app.Fluorophorepar.Photophysics.trans_q(2,1)=q_DF; % Back Switching
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            Reenable(app.CallingApp)
            % Delete the dialog box
            delete(app)
        end

        % Callback function
        function LoadFluorophoreButtonPushed(app, event)
            if exist(app.smis_gui_parameters.DefaultDirs.Fluorophore_dir,'dir')
                cd(app.smis_gui_parameters.DefaultDirs.Fluorophore_dir);
            end
            [file,path] = uigetfile('*.mat','Select a Fluorophore definition file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'fluorophore');
                if ~exist('fluorophore',"var")
                    msgbox('Invalid Matlab Fluorophore definition file !','icon','warn')
                else
                    %Load the proper fields in Fluorophorepar
                    f=fieldnames(fluorophore);
                    for i=1:numel(f)
                        app.Fluorophorepar.(f{i})=fluorophore.(f{i});
                    end
                    
                    %Fill all values
                    UpdateFluorophorePar(app);
                end
            end
        end

        % Value changed function: pHSensitivitySwitch
        function pHSensitivitySwitchValueChanged(app, event)
            value = app.pHSensitivitySwitch.Value;
            
            if strcmp(value,'On')
                app.Fluorophorepar.Environment.pH_sensitivity=1;
                app.UIPhotophysicsTable.ColumnEditable(16:17)=1;
            else
                app.Fluorophorepar.Environment.pH_sensitivity=0;
                app.UIPhotophysicsTable.ColumnEditable(16:17)=0;
                %Reset the values of the pKa and the Hill coefficient
                app.Fluorophorepar.Environment.pKa=nan(app.Fluorophorepar.Photophysics.n_fluorescent_states,1);
                app.Fluorophorepar.Environment.Hill=nan(app.Fluorophorepar.Photophysics.n_fluorescent_states,1);
            end
            
            UpdateFluorophorePar(app);
        end

        % Button pushed function: 
        % SetupphototransformationsquantumyieldsButton
        function SetupphototransformationsquantumyieldsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupphototransformationsquantumyieldsButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.QY_App = QYSetupSimple(app, app.Fluorophorepar);
        end

        % Button pushed function: SetupthermalexchangeratematrixsButton
        function SetupthermalexchangeratematrixsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupthermalexchangeratematrixsButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.kT_App = kTSetupSimple(app, app.Fluorophorepar);
        end

        % Callback function
        function CopySetupFromOtherFluorophoreDropDownValueChanged(app, event)
            value = app.CopySetupFromOtherFluorophoreDropDown.Value;
            OtherFluorophoreNames={app.OtherFluorophorepar.name}; % Makes this a cell array
            sel=find(strcmp(OtherFluorophoreNames,value)==1,1);
            app.Fluorophorepar=app.OtherFluorophorepar(sel);
            
            % Update UI with input values
            UpdateFluorophorePar(app);
            
            % Reset menu to first position
            app.CopySetupFromOtherFluorophoreDropDown.Value = 'Choose';
        end

        % Value changed function: FluorophorenameEditField
        function FluorophorenameEditFieldValueChanged(app, event)
            app.Fluorophorepar.name = app.FluorophorenameEditField.Value;
        end

        % Button pushed function: SaveFluorophoreButton
        function SaveFluorophoreButtonPushed(app, event)
   
            % Check the pKa and Hill coeff properly defined for rapidly
            % exchanging states

            check_ok=1;
            if app.Fluorophorepar.Environment.pH_sensitivity==1

                w_rapid=setdiff(1:app.n_states, unique(app.Fluorophorepar.Photophysics.state_ids)); % Set of states in rapid equilibrium
                if ~isempty(w_rapid) % States in rapid equilibrium were found
                    for k=1:numel(w_rapid)
                        w_pair=find(app.Fluorophorepar.Photophysics.state_ids==app.Fluorophorepar.Photophysics.state_ids(w_rapid(k)));                      
                        w_pKa=find(ismember(app.Fluorophorepar.Photophysics.fluorescent_states,w_pair)==1,1);
                        if isnan(app.Fluorophorepar.Environment.pKa(w_pKa))
                            msgbox(['Please define pKa for ', app.state_names{w_pair(1)}, ' !'],'icon','warn')
                            check_ok=0;
                        end
                        if isnan(app.Fluorophorepar.Environment.Hill(w_pKa))
                            msgbox(['Please define Hill coefficient for ', app.state_names{w_pair(1)}, ' !'],'icon','warn')
                            check_ok=0;
                        end                       
                    end
                end
            end
            
            if check_ok==1
                if app.DoneCheckBox.Value==0
                    msgbox('Please update fluorophore first by clicking on Exctract Spectra from Excel File !','icon','warn')
                else
                    if ~isempty(app.FluorophoreDir)
                        path=app.FluorophoreDir;
                    else
                        path=pwd;
                    end
                    SMIS_fluo_file=[deblank(app.Fluorophorepar.name),'.mat'];

                    cd(path);
                    [SMIS_fluo_file,SMIS_fluo_path] = uiputfile('*.mat','SMIS Fluorophore File Selection',SMIS_fluo_file);
                    if ~isnumeric(SMIS_fluo_path) || ~isnumeric(SMIS_fluo_file)
                        app.FluorophoreDir=SMIS_fluo_path;

                        fluorophore.name=app.Fluorophorepar.name;
                        fluorophore.xls_spectrum_file=app.Fluorophorepar.xls_spectrum_file;
                        fluorophore.xls_spectrum_dir=app.Fluorophorepar.xls_spectrum_dir;
                        fluorophore.maturation_level=app.Fluorophorepar.maturation_level;
                        fluorophore.Environment=app.Fluorophorepar.Environment;
                        fluorophore.Photophysics=app.Fluorophorepar.Photophysics;

                        save(fullfile(SMIS_fluo_path,SMIS_fluo_file),'fluorophore');
                    end
                end
            end
        end

        % Value changed function: 
        % InsertfluorescentstatebeforepositionDropDown
        function InsertfluorescentstatebeforepositionDropDownValueChanged(app, event)
            selected_state = app.InsertfluorescentstatebeforepositionDropDown.Value-1;
            
            app.n_states=app.n_states+1;
            app.Fluorophorepar.Photophysics.n_states=app.n_states;
            
            % Shift by one the states that are after the new state
            if selected_state<=app.n_states-1
                app.Fluorophorepar.Photophysics.state_names(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_names(selected_state:end);
                app.Fluorophorepar.Photophysics.state_ids(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_ids(selected_state:end)+1;
            end
            
            app.Fluorophorepar.Photophysics.state_names(selected_state)={'New Fluo State'};
            app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
            
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            fluo_states(fluo_states>=selected_state)=fluo_states(fluo_states>=selected_state)+1;
            fluo_states=sort(horzcat(fluo_states,selected_state));
            app.Fluorophorepar.Photophysics.fluorescent_states=fluo_states;
            
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            bleached_states(bleached_states>=selected_state)=bleached_states(bleached_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.bleached_states=bleached_states;
            
            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
            photoactive_dark_states(photoactive_dark_states>=selected_state)=photoactive_dark_states(photoactive_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.photoactive_dark_states=photoactive_dark_states;
            
            thermal_dark_states=app.Fluorophorepar.Photophysics.thermal_dark_states;
            thermal_dark_states(thermal_dark_states>=selected_state)=thermal_dark_states(thermal_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.thermal_dark_states=thermal_dark_states;
            
            if selected_state<=app.Fluorophorepar.Photophysics.converted_state && app.Fluorophorepar.Photophysics.converted_state>1
                app.Fluorophorepar.Photophysics.converted_state=app.Fluorophorepar.Photophysics.converted_state+1;
            end
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            % Update specific features for fluorescent states
            app.Fluorophorepar.Photophysics.n_fluorescent_states = app.Fluorophorepar.Photophysics.n_fluorescent_states+1; % number of fluorescent states
            app.Fluorophorepar.Photophysics.exc_name = app.Fluorophorepar.Photophysics.state_names(app.Fluorophorepar.Photophysics.fluorescent_states);
            
            %Spectroscopic signatures
            w_new_state=find(fluo_states==selected_state,1);
            
            xls_exc_sheet=app.Fluorophorepar.Photophysics.xls_exc_sheet;
            xls_exc_range=app.Fluorophorepar.Photophysics.xls_exc_range;
            extinction_coeff=app.Fluorophorepar.Photophysics.extinction_coeff;
            extinction_coeff_lambda=app.Fluorophorepar.Photophysics.extinction_coeff_lambda;
            xls_em_sheet=app.Fluorophorepar.Photophysics.xls_em_sheet;
            xls_em_range=app.Fluorophorepar.Photophysics.xls_em_range;
            quantum_yield=app.Fluorophorepar.Photophysics.quantum_yield;
            
            if w_new_state<app.Fluorophorepar.Photophysics.n_fluorescent_states
                xls_exc_sheet(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=xls_exc_sheet(w_new_state:end);
                xls_exc_range(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=xls_exc_range(w_new_state:end);
                extinction_coeff(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=extinction_coeff(w_new_state:end);
                extinction_coeff_lambda(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=extinction_coeff_lambda(w_new_state:end);
                xls_em_sheet(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=xls_em_sheet(w_new_state:end);
                xls_em_range(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=xls_em_range(w_new_state:end);
                quantum_yield(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=quantum_yield(w_new_state:end);
            end
            
            xls_exc_sheet(w_new_state)=nan;
            xls_exc_range(w_new_state)={'Range'};
            extinction_coeff(w_new_state)=nan;
            extinction_coeff_lambda(w_new_state)=nan;
            xls_em_sheet(w_new_state)=nan;
            xls_em_range(w_new_state)={'Range'};
            quantum_yield(w_new_state)=nan;
            
            app.Fluorophorepar.Photophysics.xls_exc_sheet=xls_exc_sheet;
            app.Fluorophorepar.Photophysics.xls_exc_range=xls_exc_range;
            app.Fluorophorepar.Photophysics.extinction_coeff=extinction_coeff;
            app.Fluorophorepar.Photophysics.extinction_coeff_lambda=extinction_coeff_lambda;
            app.Fluorophorepar.Photophysics.xls_em_sheet=xls_em_sheet;
            app.Fluorophorepar.Photophysics.xls_em_range=xls_em_range;
            app.Fluorophorepar.Photophysics.quantum_yield=quantum_yield;
            
            % Update environmental values
            pKa=app.Fluorophorepar.Environment.pKa;
            Hill=app.Fluorophorepar.Environment.Hill;
            if w_new_state<app.Fluorophorepar.Photophysics.n_fluorescent_states
                pKa(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=pKa(w_new_state:end);
                Hill(w_new_state+1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=Hill(w_new_state:end);
            end
            pKa(w_new_state)=nan;
            Hill(w_new_state)=nan;
            
            app.Fluorophorepar.Environment.pKa=pKa;
            app.Fluorophorepar.Environment.Hill=Hill;
            
            % Update transformation matrices
            UpdateTrans_kq(app,selected_state,1);
            
            %Reset the drop down menu
            app.InsertfluorescentstatebeforepositionDropDown.Value=1;
            
            %Reset the Check button
            app.DoneCheckBox.Value=0;
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Value changed function: InsertbleachedstatebeforepositionDropDown
        function InsertbleachedstatebeforepositionDropDownValueChanged(app, event)
            selected_state = app.InsertbleachedstatebeforepositionDropDown.Value-1;
            
            app.n_states=app.n_states+1;
            app.Fluorophorepar.Photophysics.n_states=app.n_states;
            
            % Shift by one the states that are after the new state
            if selected_state<=app.n_states-1
                app.Fluorophorepar.Photophysics.state_names(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_names(selected_state:end);
                app.Fluorophorepar.Photophysics.state_ids(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_ids(selected_state:end)+1;
            end
            
            app.Fluorophorepar.Photophysics.state_names(selected_state)={'New Bleached State'};
            app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
            
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            fluo_states(fluo_states>=selected_state)=fluo_states(fluo_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.fluorescent_states=fluo_states;
            
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            bleached_states(bleached_states>=selected_state)=bleached_states(bleached_states>=selected_state)+1;
            bleached_states=sort(horzcat(bleached_states,selected_state));
            app.Fluorophorepar.Photophysics.bleached_states=bleached_states;
            
            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
            photoactive_dark_states(photoactive_dark_states>=selected_state)=photoactive_dark_states(photoactive_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.photoactive_dark_states=photoactive_dark_states;
            
            thermal_dark_states=app.Fluorophorepar.Photophysics.thermal_dark_states;
            thermal_dark_states(thermal_dark_states>=selected_state)=thermal_dark_states(thermal_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.thermal_dark_states=thermal_dark_states;
            
            if selected_state<=app.Fluorophorepar.Photophysics.converted_state && app.Fluorophorepar.Photophysics.converted_state>1
                app.Fluorophorepar.Photophysics.converted_state=app.Fluorophorepar.Photophysics.converted_state+1;
            end
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            % Update transformation matrices
            UpdateTrans_kq(app,selected_state,1);
            
            %Reset the drop down menu
            app.InsertbleachedstatebeforepositionDropDown.Value=1;
            
            %Reset the Check button
            app.DoneCheckBox.Value=0;
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Value changed function: 
        % InsertphotoactivedarkstatebeforepositionDropDown
        function InsertphotoactivedarkstatebeforepositionDropDownValueChanged(app, event)
            selected_state = app.InsertphotoactivedarkstatebeforepositionDropDown.Value-1;
            
            app.n_states=app.n_states+1;
            app.Fluorophorepar.Photophysics.n_states=app.n_states;
            
            % Shift by one the states that are after the new state
            if selected_state<=app.n_states-1
                app.Fluorophorepar.Photophysics.state_names(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_names(selected_state:end);
                app.Fluorophorepar.Photophysics.state_ids(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_ids(selected_state:end)+1;
            end
            
            app.Fluorophorepar.Photophysics.state_names(selected_state)={'New Dark State'};
            app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
            
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            fluo_states(fluo_states>=selected_state)=fluo_states(fluo_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.fluorescent_states=fluo_states;
            
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            bleached_states(bleached_states>=selected_state)=bleached_states(bleached_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.bleached_states=bleached_states;
            
            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
            photoactive_dark_states(photoactive_dark_states>=selected_state)=photoactive_dark_states(photoactive_dark_states>=selected_state)+1;
            photoactive_dark_states=sort(horzcat(photoactive_dark_states,selected_state));
            app.Fluorophorepar.Photophysics.photoactive_dark_states=photoactive_dark_states;
            
            thermal_dark_states=app.Fluorophorepar.Photophysics.thermal_dark_states;
            thermal_dark_states(thermal_dark_states>=selected_state)=thermal_dark_states(thermal_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.thermal_dark_states=thermal_dark_states;
            
            if selected_state<=app.Fluorophorepar.Photophysics.converted_state && app.Fluorophorepar.Photophysics.converted_state>1
                app.Fluorophorepar.Photophysics.converted_state=app.Fluorophorepar.Photophysics.converted_state+1;
            end
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            % Update specific features for photoactive dark states
            app.Fluorophorepar.Photophysics.n_photoactive_dark_states = app.Fluorophorepar.Photophysics.n_photoactive_dark_states+1; % number of fluorescent states
            app.Fluorophorepar.Photophysics.photoactive_dark_names = app.Fluorophorepar.Photophysics.state_names(app.Fluorophorepar.Photophysics.photoactive_dark_states);
            
            %Spectroscopic signatures
            w_new_state=find(photoactive_dark_states==selected_state,1);
            
            xls_dark_sheet=app.Fluorophorepar.Photophysics.xls_dark_sheet;
            xls_dark_range=app.Fluorophorepar.Photophysics.xls_dark_range;
            extinction_coeff_dark=app.Fluorophorepar.Photophysics.extinction_coeff_dark;
            extinction_coeff_lambda_dark=app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark;
            
            if w_new_state<app.Fluorophorepar.Photophysics.n_photoactive_dark_states
                xls_dark_sheet(w_new_state+1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states)=xls_dark_sheet(w_new_state:end);
                xls_dark_range(w_new_state+1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states)=xls_dark_range(w_new_state:end);
                extinction_coeff_dark(w_new_state+1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states)=extinction_coeff_dark(w_new_state:end);
                extinction_coeff_lambda_dark(w_new_state+1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states)=extinction_coeff_lambda_dark(w_new_state:end);
            end
            
            xls_dark_sheet(w_new_state)=nan;
            xls_dark_range(w_new_state)={'Range'};
            extinction_coeff_dark(w_new_state)=nan;
            extinction_coeff_lambda_dark(w_new_state)=nan;
            
            app.Fluorophorepar.Photophysics.xls_dark_sheet=xls_dark_sheet;
            app.Fluorophorepar.Photophysics.xls_dark_range=xls_dark_range;
            app.Fluorophorepar.Photophysics.extinction_coeff_dark=extinction_coeff_dark;
            app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark=extinction_coeff_lambda_dark;
            
            % Update transformation matrices
            UpdateTrans_kq(app,selected_state,1);
            
            %Reset the drop down menu
            app.InsertphotoactivedarkstatebeforepositionDropDown.Value=1;
            
            %Reset the Check button
            app.DoneCheckBox.Value=0;
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Value changed function: 
        % InsertthermaldarkstatebeforepositionDropDown
        function InsertthermaldarkstatebeforepositionDropDownValueChanged(app, event)
            selected_state = app.InsertthermaldarkstatebeforepositionDropDown.Value-1;
            
            app.n_states=app.n_states+1;
            app.Fluorophorepar.Photophysics.n_states=app.n_states;
            
            % Shift by one the states that are after the new state
            if selected_state<=app.n_states-1
                app.Fluorophorepar.Photophysics.state_names(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_names(selected_state:end);
                app.Fluorophorepar.Photophysics.state_ids(selected_state+1:app.n_states,1)=app.Fluorophorepar.Photophysics.state_ids(selected_state:end)+1;
            end
            
            app.Fluorophorepar.Photophysics.state_names(selected_state)={'New Thermal Dark State'};
            app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
            
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            fluo_states(fluo_states>=selected_state)=fluo_states(fluo_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.fluorescent_states=fluo_states;
            
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            bleached_states(bleached_states>=selected_state)=bleached_states(bleached_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.bleached_states=bleached_states;
            
            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
            photoactive_dark_states(photoactive_dark_states>=selected_state)=photoactive_dark_states(photoactive_dark_states>=selected_state)+1;
            app.Fluorophorepar.Photophysics.photoactive_dark_states=photoactive_dark_states;
            
            thermal_dark_states=app.Fluorophorepar.Photophysics.thermal_dark_states;
            thermal_dark_states(thermal_dark_states>=selected_state)=thermal_dark_states(thermal_dark_states>=selected_state)+1;
            thermal_dark_states=sort(horzcat(thermal_dark_states,selected_state));
            app.Fluorophorepar.Photophysics.thermal_dark_states=thermal_dark_states;
            
            if selected_state<=app.Fluorophorepar.Photophysics.converted_state && app.Fluorophorepar.Photophysics.converted_state>1
                app.Fluorophorepar.Photophysics.converted_state=app.Fluorophorepar.Photophysics.converted_state+1;
            end
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            % Update specific features for thermal dark states
            app.Fluorophorepar.Photophysics.n_thermal_dark_states = app.Fluorophorepar.Photophysics.n_thermal_dark_states+1;
            
            % Update transformation matrices
            UpdateTrans_kq(app,selected_state,1);
            
            %Reset the drop down menu
            app.InsertthermaldarkstatebeforepositionDropDown.Value=1;
            
            %Reset the Check button
            app.DoneCheckBox.Value=0;
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Value changed function: RemovestateatpositionDropDown
        function RemovestateatpositionDropDownValueChanged(app, event)
            selected_state = app.RemovestateatpositionDropDown.Value-1;
            
            app.n_states=app.n_states-1;
            app.Fluorophorepar.Photophysics.n_states=app.n_states;
            
            % If the state is in rapid equilibrium undo the pairing
            paired_id = find(strcmp(app.state_names,app.UIPhotophysicsTable.Data{selected_state,15})==1);
            if ~isempty(paired_id)
                if numel(paired_id)>1
                    MyDlg=warndlg(['Several states have the same name: ', app.UIPhotophysicsTable.Data{selected_state,15}, ' Check states in rapid equilibrium !'],'Warning');
                    waitfor(MyDlg)
                end
                app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
                app.Fluorophorepar.Photophysics.state_ids(paired_id)=paired_id;
            end
            
            app.Fluorophorepar.Photophysics.state_names(selected_state)=[];
            app.Fluorophorepar.Photophysics.state_ids(selected_state)=[];
            % Shift by one the states that are after the new state
            if selected_state<=app.n_states
                app.Fluorophorepar.Photophysics.state_ids(selected_state:app.n_states)=app.Fluorophorepar.Photophysics.state_ids(selected_state:app.n_states)-1;
            end
            
            % Find out which type of of state has been removed
            is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
            is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
            is_bleached=ismember(selected_state,app.Fluorophorepar.Photophysics.bleached_states);
            is_thermal_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.thermal_dark_states);
            
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
            thermal_dark_states=app.Fluorophorepar.Photophysics.thermal_dark_states;
            
            if is_fluo==1
                w_old_state=find(fluo_states==selected_state,1);
                
                fluo_states(w_old_state)=[];
                app.Fluorophorepar.Photophysics.n_fluorescent_states = app.Fluorophorepar.Photophysics.n_fluorescent_states-1;
                app.Fluorophorepar.Photophysics.exc_name = app.Fluorophorepar.Photophysics.state_names(fluo_states);
                
                %Spectroscopic signatures
                app.Fluorophorepar.Photophysics.xls_exc_sheet(w_old_state)=[];
                app.Fluorophorepar.Photophysics.xls_exc_range(w_old_state)=[];
                app.Fluorophorepar.Photophysics.extinction_coeff(w_old_state)=[];
                app.Fluorophorepar.Photophysics.extinction_coeff_lambda(w_old_state)=[];
                app.Fluorophorepar.Photophysics.xls_em_sheet(w_old_state)=[];
                app.Fluorophorepar.Photophysics.xls_em_range(w_old_state)=[];
                app.Fluorophorepar.Photophysics.quantum_yield(w_old_state)=[];
                
                app.Fluorophorepar.Environment.pKa(w_old_state)=[];
                app.Fluorophorepar.Environment.Hill(w_old_state)=[];
                
            elseif is_photoactive_dark==1
                w_old_state=find(photoactive_dark_states==selected_state,1);
                
                photoactive_dark_states(w_old_state)=[];
                app.Fluorophorepar.Photophysics.n_photoactive_dark_states = app.Fluorophorepar.Photophysics.n_photoactive_dark_states-1;
                app.Fluorophorepar.Photophysics.photoactive_dark_names = app.Fluorophorepar.Photophysics.state_names(photoactive_dark_states);
                
                %Spectroscopic signatures
                
                app.Fluorophorepar.Photophysics.xls_dark_sheet(w_old_state)=[];
                app.Fluorophorepar.Photophysics.xls_dark_range(w_old_state)=[];
                app.Fluorophorepar.Photophysics.extinction_coeff_dark(w_old_state)=[];
                app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark(w_old_state)=[];
                
            elseif is_bleached==1
                w_old_state=find(bleached_states==selected_state,1);
                bleached_states(w_old_state)=[];
                
            elseif is_thermal_dark==1
                w_old_state=find(thermal_dark_states==selected_state,1);
                thermal_dark_states(w_old_state)=[];
                app.Fluorophorepar.Photophysics.n_thermal_dark_states = app.Fluorophorepar.Photophysics.n_thermal_dark_states-1;
            end
            
            fluo_states(fluo_states>selected_state)=fluo_states(fluo_states>selected_state)-1;
            bleached_states(bleached_states>selected_state)=bleached_states(bleached_states>selected_state)-1;
            photoactive_dark_states(photoactive_dark_states>selected_state)=photoactive_dark_states(photoactive_dark_states>selected_state)-1;
            thermal_dark_states(thermal_dark_states>selected_state)=thermal_dark_states(thermal_dark_states>selected_state)-1;
            
            app.Fluorophorepar.Photophysics.fluorescent_states=fluo_states;
            app.Fluorophorepar.Photophysics.bleached_states=bleached_states;
            app.Fluorophorepar.Photophysics.photoactive_dark_states=photoactive_dark_states;
            app.Fluorophorepar.Photophysics.thermal_dark_states=thermal_dark_states;
            
            if selected_state<=app.Fluorophorepar.Photophysics.converted_state && app.Fluorophorepar.Photophysics.converted_state>1
                app.Fluorophorepar.Photophysics.converted_state=app.Fluorophorepar.Photophysics.converted_state-1;
            end
            
            app.Fluorophorepar.Photophysics.photoactive_states=sort(horzcat(app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states)); % # of the photoactive state for the sm
            
            % Update transformation matrices
            UpdateTrans_kq(app,selected_state,0);
            
            %Reset the drop down menu
            app.RemovestateatpositionDropDown.Value=1;
            
            %Reset the Check button
            app.DoneCheckBox.Value=0;
            
            %Update the GUI
            UpdateFluorophorePar(app);
        end

        % Cell edit callback: UIPhotophysicsTable
        function UIPhotophysicsTableCellEdit(app, event)
            newData = event.NewData;
            
            selected_state = event.Indices(1);
            par_id = event.Indices(2);
            
            
            valid_change=1; % Initialize
            %Issue warnings when transformation is not possible
            
            switch par_id
                
                case 1 % Change the state name
                    % Check that the name does not already exist
                    if ~any(strcmp(newData,app.state_names))
                        app.Fluorophorepar.Photophysics.state_names(selected_state)={newData};
                        is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                        is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                        
                        if is_fluo==1
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.exc_name(w_state) = {newData};
                        elseif is_photoactive_dark==1
                            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                            w_state=find(photoactive_dark_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.photoactive_dark_names(w_state)={newData};
                        end
                    else
                        MyDlg=warndlg('There is already a state with that name !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 3 % Change the quantum yield
                    
                    % Check that the state is fluorescent
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    
                    if is_fluo==1
                        if newData>=0 && newData<=1
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.quantum_yield(w_state)=newData;
                        else
                            MyDlg=warndlg('Quantum yield must be between 0 and 1 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('State is not a fluorescent state ! Cannot set fluorescence quantum yield !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                    
                case 7 % Change Abs/Exc Spectrum Excel Sheet
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                    
                    if is_fluo==1
                        if floor(newData)==newData && newData>0
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.xls_exc_sheet(w_state)=newData;
                        else
                            MyDlg=warndlg('Sheet number must be integer and > 0 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    elseif is_photoactive_dark==1
                        if floor(newData)==newData && newData>0
                            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                            w_state=find(photoactive_dark_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.xls_dark_sheet(w_state)=newData;
                        else
                            MyDlg=warndlg('Sheet number must be integer and > 0 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('State is not photoactive, Abs/Exc spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 8 % Change Abs/Exc Spectrum Excel Range
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                    
                    if is_fluo==1
                        fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                        w_state=find(fluo_states==selected_state,1);
                        app.Fluorophorepar.Photophysics.xls_exc_range(w_state)={newData};
                    elseif is_photoactive_dark==1
                        photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                        w_state=find(photoactive_dark_states==selected_state,1);
                        app.Fluorophorepar.Photophysics.xls_dark_range(w_state)={newData};
                    else
                        MyDlg=warndlg('State is not photoactive, Abs/Exc spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 9 % Change Em Spectrum Excel Sheet
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    
                    if is_fluo==1
                        if floor(newData)==newData && newData>0
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.xls_em_sheet(w_state)=newData;
                        else
                            MyDlg=warndlg('Sheet number must be integer and > 0 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('State is not fluorescent, Em spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 10 % Change Em Spectrum Excel Range
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    
                    if is_fluo==1
                        fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                        w_state=find(fluo_states==selected_state,1);
                        app.Fluorophorepar.Photophysics.xls_em_range(w_state)={newData};
                    else
                        MyDlg=warndlg('State is not fluorescent, Em spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 11 % Change Em Spectrum Epsilon
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                    
                    if is_fluo==1
                        if newData>0
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.extinction_coeff(w_state)=newData;
                        else
                            MyDlg=warndlg('Exctinction coefficient must be > 0 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    elseif is_photoactive_dark==1
                        if newData>0
                            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                            w_state=find(photoactive_dark_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.extinction_coeff_dark(w_state)=newData;
                        else
                            MyDlg=warndlg('Exctinction coefficient must be > 0 !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('State is not photoactive, Abs/Exc spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 12 % Change Em Spectrum Exctinction Coeff Lambda
                    
                    % Check that the state is photoactive
                    is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                    is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                    
                    if is_fluo==1
                        if newData>200 &&  newData<1000
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.extinction_coeff_lambda(w_state)=newData;
                        else
                            MyDlg=warndlg('Exctinction coefficient should be between 200 and 1000 nm ?','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    elseif is_photoactive_dark==1
                        if newData>200 &&  newData<1000
                            photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                            w_state=find(photoactive_dark_states==selected_state,1);
                            app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark(w_state)=newData;
                        else
                            MyDlg=warndlg('Exctinction coefficient should be between 200 and 1000 nm ?','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('State is not photoactive, Abs/Exc spectrum cannot be defined !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 13 % Change photoconverted state
                    if newData==1
                        if selected_state>1
                            app.Fluorophorepar.Photophysics.converted_state=selected_state;
                        else
                            MyDlg=warndlg('Defining first state as photoconverted state is not possible !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('Fluorophore will become non-photoconvertible !','Warning');
                        waitfor(MyDlg)
                        app.Fluorophorepar.Photophysics.converted_state=1;
                    end
                    
                case 14 % Change pairing
                    if newData==0 % Undo the pairing
                        paired_id = find(strcmp(app.state_names,app.UIPhotophysicsTable.Data{selected_state,15})==1);
                        if isscalar(paired_id)
                            app.Fluorophorepar.Photophysics.state_ids(selected_state)=selected_state;
                            app.Fluorophorepar.Photophysics.state_ids(paired_id)=paired_id;


                            % Check with fluorescence state is concerned
                            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                            w_state=find(fluo_states==selected_state,1);
                            app.Fluorophorepar.Environment.pKa(w_state)=nan;
                            app.Fluorophorepar.Environment.Hill(w_state)=nan;

                        else
                            MyDlg=warndlg(['Several states have the same name: ', app.UIPhotophysicsTable.Data{selected_state,15}, ' Update state names !'],'Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    end
                    
                    if newData==1 % Create pairing with the first available unpaired state
                        % Check that pH sensitivity is set on
                        if strcmp(app.pHSensitivitySwitch.Value,'On')
                            
                            %Check that the state is a fluorescent state
                            is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                            if is_fluo==1
                                [~, ind1] = unique(app.Fluorophorepar.Photophysics.state_ids,'first');
                                [~, ind2] = unique(app.Fluorophorepar.Photophysics.state_ids,'last');
                                available_states_ind=ind1(ind1==ind2);
                                
                                available_states_ind(available_states_ind==selected_state)=[];
                                % Keep only the non-bleached states
                                available_states_ind=setdiff(available_states_ind,app.Fluorophorepar.Photophysics.bleached_states);
                                
                                if ~isempty(available_states_ind)
                                    state_distance=abs(available_states_ind-selected_state);
                                    w_available_state=find(state_distance==min(state_distance),1,'last');
                                    available_state=available_states_ind(w_available_state);
                                    app.Fluorophorepar.Photophysics.state_ids(available_state)=min([selected_state,available_state]);
                                    app.Fluorophorepar.Photophysics.state_ids(selected_state)=min([selected_state,available_state]);
                                    % Reset any transformation between the exchanging states
                                    if app.Fluorophorepar.Photophysics.trans_k(available_state,selected_state)~=0 || app.Fluorophorepar.Photophysics.trans_k(selected_state,available_state)~=0
                                        app.Fluorophorepar.Photophysics.trans_k(available_state,selected_state)=0;
                                        app.Fluorophorepar.Photophysics.trans_k(selected_state,available_state)=0;
                                        MyDlg=warndlg('Resetting transformation rates between the exchanging states !','Warning');
                                        waitfor(MyDlg)
                                    end
                                    if app.Fluorophorepar.Photophysics.trans_q(available_state,selected_state)~=0 || app.Fluorophorepar.Photophysics.trans_q(selected_state,available_state)~=0
                                        app.Fluorophorepar.Photophysics.trans_q(available_state,selected_state)=0;
                                        app.Fluorophorepar.Photophysics.trans_q(selected_state,available_state)=0;
                                        MyDlg=warndlg('Resetting transformation QYs between the exchanging states !','Warning');
                                        waitfor(MyDlg)
                                    end
                                else
                                    MyDlg=warndlg('No available state for pairing !','Warning');
                                    waitfor(MyDlg)
                                    valid_change=0;
                                end
                            else
                                MyDlg=warndlg('Only fluorescent states can be selected to be in rapid equilibrium with a following dark state in this version of SMIS !','Warning');
                                waitfor(MyDlg)
                                valid_change=0;
                            end
                        else
                            MyDlg=warndlg('To Define states in (pH dependent) rapid equilibrium, the pH-sensitivity switch must be on !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end   
                    end
                    
                case 16 % Change pKa
                    
                    % Check that there are some states in rapid equilibrium
                    any_pairing=any(app.UIPhotophysicsTable.Data{:,14});
                    
                    if any_pairing==1
                        %Is the selected state a fluorescent state ?
                        is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                        
                        if is_fluo==1
                            if newData>1 && newData<14
                                % Check with fluorescence state is concerned
                                fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                                w_state=find(fluo_states==selected_state,1);
                                app.Fluorophorepar.Environment.pKa(w_state)=newData;
                            else
                                MyDlg=warndlg('pKa cannot take this value!','Warning');
                                waitfor(MyDlg)
                                valid_change=0;
                            end
                        else
                            MyDlg=warndlg('You can only change pKa for a fluorescent state !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('It is nonsense to define a pKa for a non-paired photophysical state !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
                case 17 % Change Hill coefficient
                    
                    % Check that there are some states in rapid equilibrium
                    any_pairing=any(app.UIPhotophysicsTable.Data{:,14});
                    
                    if any_pairing==1
                        %Is the selected state a fluorescent state ?
                        is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                        
                        if is_fluo==1
                            if newData>0 && newData<10
                                % Check with fluorescence state is concerned
                                fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                                w_state=find(fluo_states==selected_state,1);
                                app.Fluorophorepar.Environment.Hill(w_state)=newData;
                            else
                                MyDlg=warndlg('Hill coefficient cannot take this value!','Warning');
                                waitfor(MyDlg)
                                valid_change=0;
                            end
                        else
                            MyDlg=warndlg('You can only change Hill coefficient for a fluorescent state !','Warning');
                            waitfor(MyDlg)
                            valid_change=0;
                        end
                    else
                        MyDlg=warndlg('It is nonsense to define a Hill coefficient for a non-paired photophysical state !','Warning');
                        waitfor(MyDlg)
                        valid_change=0;
                    end
                    
            end
            
            if valid_change==1
                UpdateFluorophorePar(app);
                app.DoneCheckBox.Value=0; % Set this to 0 to ensure proper setting is done before saving fluorophore
            else
                if isnumeric(app.UIPhotophysicsTable.Data{selected_state,par_id})
                    app.UIPhotophysicsTable.Data{selected_state,par_id}=event.PreviousData;
                elseif islogical(app.UIPhotophysicsTable.Data{selected_state,par_id})
                    app.UIPhotophysicsTable.Data{selected_state,par_id}=event.PreviousData;
                else
                    app.UIPhotophysicsTable.Data{selected_state,par_id}={event.PreviousData};
                end
            end
        end

        % Value changed function: CheckSpectraDropDown
        function CheckSpectraDropDownValueChanged(app, event)
            if app.DoneCheckBox.Value==1
                
                photoactive_states=sort([app.Fluorophorepar.Photophysics.fluorescent_states,app.Fluorophorepar.Photophysics.photoactive_dark_states]);
                selected_state = photoactive_states(app.CheckSpectraDropDown.Value-1);
                
                is_fluo=ismember(selected_state,app.Fluorophorepar.Photophysics.fluorescent_states);
                is_photoactive_dark=ismember(selected_state,app.Fluorophorepar.Photophysics.photoactive_dark_states);
                
                if is_fluo==1
                    fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
                    w_state=find(fluo_states==selected_state,1);
                    
                    % Start with the excitation spectrum
                    exc_spectrum=app.Fluorophorepar.Photophysics.exc_spectra(w_state);
                    if ~isempty(exc_spectrum.s) % Check that the spectrum has been loaded
                        if ~isnan(exc_spectrum.eps) && ~isnan(exc_spectrum.l)
                            MyD=abs(exc_spectrum.s(:,1)-exc_spectrum.l);
                            w=find(MyD==min(MyD),1);
                            if exc_spectrum.s(w,2)>0
                                NormSpectrum= exc_spectrum.s(:,2)*exc_spectrum.eps/exc_spectrum.s(w,2);
                                plot(app.UIAxesAbsSpectrum,exc_spectrum.s(:,1),NormSpectrum);
                            else
                                MyDlg=warndlg('Spectrum cannot be normalized at specified wavelength (extinction coefficient is zero). Check Excel file!','Warning');
                                waitfor(MyDlg)
                            end
                        elseif isnan(exc_spectrum.eps)
                            MyDlg=warndlg('Spectrum cannot be normalized: Extinction coefficient is not specified. Run Extract Spectra Button or check Table !','Warning');
                            waitfor(MyDlg)
                        elseif isnan(exc_spectrum.l)
                            MyDlg=warndlg('Spectrum cannot be normalized: Wavelength for extinction coefficient is not specified. Run Extract Spectra Button or check Table !','Warning');
                            waitfor(MyDlg)
                        end
                    else
                        MyData = findobj(app.UIAxesAbsSpectrum, '-not', 'Type', 'Axes');
                        if ~isempty(MyData)
                            delete(MyData);
                        end
                        MyDlg=warndlg('Excitation spectrum not found ! Run Extract Spectra Button or Check Table or Excel file  !','Warning');
                        waitfor(MyDlg)
                    end
                    
                    % Continue with the emission spectrum
                    em_spectrum=app.Fluorophorepar.Photophysics.em_spectra(w_state);
                    if ~isempty(em_spectrum.s)  % Check that the spectrum has been loaded
                        plot(app.UIAxesEmSpectrum,em_spectrum.s(:,1),em_spectrum.s(:,2));
                    else
                        MyData = findobj(app.UIAxesEmSpectrum, '-not', 'Type', 'Axes');
                        if ~isempty(MyData)
                            delete(MyData);
                        end
                        MyDlg=warndlg('Emission spectrum not found ! Run Extract Spectra Button or Check Table or Excel file  !','Warning');
                        waitfor(MyDlg)
                    end
                    
                elseif is_photoactive_dark==1
                    photoactive_dark_states=app.Fluorophorepar.Photophysics.photoactive_dark_states;
                    w_state=find(photoactive_dark_states==selected_state,1);
                    abs_spectrum=app.Fluorophorepar.Photophysics.dark_spectra(w_state);
                    
                    MyData = findobj(app.UIAxesEmSpectrum, '-not', 'Type', 'Axes');
                    if ~isempty(MyData)
                        delete(MyData);
                    end
                    
                    if ~isempty(abs_spectrum.s) % Check that the spectrum has been loaded
                        if ~isnan(abs_spectrum.eps) && ~isnan(abs_spectrum.l)
                            MyD=abs(abs_spectrum.s(:,1)-abs_spectrum.l);
                            w=find(MyD==min(MyD),1);
                            if abs_spectrum.s(w,2)>0
                                NormSpectrum= abs_spectrum.s(:,2)*abs_spectrum.eps/abs_spectrum.s(w,2);
                                plot(app.UIAxesAbsSpectrum,abs_spectrum.s(:,1),NormSpectrum);
                            else
                                MyDlg=warndlg('Spectrum cannot be normalized at specified wavelength (extinction coefficient is zero). Check Excel file!','Warning');
                                waitfor(MyDlg)
                            end
                        elseif isnan(abs_spectrum.eps)
                            MyDlg=warndlg('Spectrum cannot be normalized: Extinction coefficient is not specified. Run Extract Spectra Button or check Table !','Warning');
                            waitfor(MyDlg)
                        elseif isnan(abs_spectrum.l)
                            MyDlg=warndlg('Spectrum cannot be normalized: Wavelength for extinction coefficient is not specified. Run Extract Spectra Button or check Table !','Warning');
                            waitfor(MyDlg)
                        end
                    else
                        MyData = findobj(app.UIAxesAbsSpectrum, '-not', 'Type', 'Axes');
                        if ~isempty(MyData)
                            delete(MyData);
                        end
                        MyDlg=warndlg('Absorption spectrum not found ! Run Extract Spectra Button or Check Table or Excel file  !','Warning');
                        waitfor(MyDlg)
                    end
                end
            else
                MyDlg=warndlg('Proper extraction from Excel file must be done first !','Warning');
                waitfor(MyDlg)
            end
            
            %Reset the drop down menu
            app.CheckSpectraDropDown.Value=1;


        end

        % Button pushed function: ExtractSpectrafromExcelFileButton
        function ExtractSpectrafromExcelFileButtonPushed(app, event)
            checked_ok=1;
            
            xls_spectrum_file=app.Fluorophorepar.xls_spectrum_file;
            xls_spectrum_dir=app.Fluorophorepar.xls_spectrum_dir;
            
            spectrum_file = fullfile(xls_spectrum_dir,xls_spectrum_file);
            
            
            %Read the fluorescence excitation spectrum, normalize at extinction_coeff_lambda
            exc_spectra(1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=struct(...
                's', [], ... % spectrum
                'eps', 0, ...  % reference epsilon
                'l', 0, ...  % reference wavelength for that epsilon
                'name', 0 ...  % reference name for that epsilon
                );
            
            em_spectra(1:app.Fluorophorepar.Photophysics.n_fluorescent_states)=struct(...
                's', [] ... % spectrum
                );
            
            for k=1:app.Fluorophorepar.Photophysics.n_fluorescent_states
                try
                    exc_spectra(k).s = get_xls_spectrum2(spectrum_file, app.Fluorophorepar.Photophysics.xls_exc_sheet(k), ...
                        char(app.Fluorophorepar.Photophysics.xls_exc_range(k)), app.Fluorophorepar.Photophysics.extinction_coeff_lambda(k));
                catch
                    MyName=app.Fluorophorepar.Photophysics.exc_name(k);
                    if isnan(app.Fluorophorepar.Photophysics.extinction_coeff_lambda(k))
                        MyDlg=warndlg(['Could not extract excitation spectrum: The wavelength for specified epsilon is not set for state: ',MyName, ' !'],'Warning');
                        waitfor(MyDlg)
                        checked_ok=0;
                    else
                        MyDlg=warndlg(['Could not extract excitation spectrum: The specified Excel sheet or cell range seems incorrect for state: ',MyName, ' !'],'Warning');
                        waitfor(MyDlg)
                        checked_ok=0;
                    end
                end
                
                if isnan(app.Fluorophorepar.Photophysics.extinction_coeff(k))
                    MyName=app.Fluorophorepar.Photophysics.exc_name(k);
                    MyDlg=warndlg(['Extinction coefficient is not set for state : ',MyName, ' !'],'Warning');
                    waitfor(MyDlg)
                    checked_ok=0;
                end
                exc_spectra(k).eps=app.Fluorophorepar.Photophysics.extinction_coeff(k); %[cm-1Mol-1] epsilon for excitation spectrum
                exc_spectra(k).l=app.Fluorophorepar.Photophysics.extinction_coeff_lambda(k); % [nm] lambda for that epsilon
                exc_spectra(k).name=char(app.Fluorophorepar.Photophysics.exc_name(k)); % Name of that excitation spectrum
                app.Fluorophorepar.Photophysics.exc_spectra(k)=exc_spectra(k);
                
                %Read the fluorescence emission spectrum normalize at max
                
                try
                    em_spectra(k).s = get_xls_spectrum2(spectrum_file, app.Fluorophorepar.Photophysics.xls_em_sheet(k), ...
                        char(app.Fluorophorepar.Photophysics.xls_em_range(k)));
                catch
                    MyName=app.Fluorophorepar.Photophysics.exc_name(k);
                    MyDlg=warndlg(['Could not extract emission spectrum: The specified Excel sheet or cell range seems incorrect for state: ',MyName, ' !'],'Warning');
                    waitfor(MyDlg)
                    checked_ok=0;
                end
                
                app.Fluorophorepar.Photophysics.em_spectra(k)=em_spectra(k);
            end
            
            %Read the absorbance dark states spectra
            dark_spectra(1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states)=struct(...
                's', [], ... % spectrum
                'eps', 0, ...  % reference epsilon
                'l', 0, ...  % reference wavelength for that epsilon
                'name', [] ...  % name of dark state
                );
            
            for k=1:app.Fluorophorepar.Photophysics.n_photoactive_dark_states
                try
                    dark_spectra(k).s = get_xls_spectrum2(spectrum_file, app.Fluorophorepar.Photophysics.xls_dark_sheet(k), ...
                        char(app.Fluorophorepar.Photophysics.xls_dark_range(k)), app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark(k));
                catch
                    MyName=app.Fluorophorepar.Photophysics.photoactive_dark_names(k);
                    if isnan(app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark(k))
                        MyDlg=warndlg(['Could not extract excitation spectrum: The wavelength for specified epsilon is not set for state: ',MyName, ' !'],'Warning');
                        waitfor(MyDlg)
                        checked_ok=0;
                    else
                        MyDlg=warndlg(['Could not extract absorption spectrum: The specified Excel sheet or cell range seems incorrect for state: ',MyName, ' !'],'Warning');
                        waitfor(MyDlg)
                        checked_ok=0;
                    end
                end
                
                if isnan(app.Fluorophorepar.Photophysics.extinction_coeff_dark(k))
                    MyName=app.Fluorophorepar.Photophysics.photoactive_dark_names(k);
                    MyDlg=warndlg(['Extinction coefficient is not set for state : ',MyName, ' !'],'Warning');
                    waitfor(MyDlg)
                    checked_ok=0;
                end
                dark_spectra(k).eps = app.Fluorophorepar.Photophysics.extinction_coeff_dark(k);
                dark_spectra(k).l = app.Fluorophorepar.Photophysics.extinction_coeff_lambda_dark(k);
                dark_spectra(k).name = char(app.Fluorophorepar.Photophysics.photoactive_dark_names(k));
            end
            app.Fluorophorepar.Photophysics.dark_spectra=dark_spectra;
            
            if checked_ok==1
                app.DoneCheckBox.Value=1;
            else
                app.DoneCheckBox.Value=0;
            end
            
        end

        % Button pushed function: ChooseExcelfileforspectraldataButton
        function ChooseExcelfileforspectraldataButtonPushed(app, event)
            if exist(app.Fluorophorepar.xls_spectrum_dir,'dir')
                cd(app.Fluorophorepar.xls_spectrum_dir);
            end
            [MyFile,MyPath] = uigetfile('*.xlsx','Select an Excel file containing fluorophore spectra');
            if isequal(MyFile,0)
                disp('User selected Cancel');
            else
                app.Fluorophorepar.xls_spectrum_file = MyFile;
                app.Fluorophorepar.xls_spectrum_dir = MyPath;
                app.SpectradatafileEditField.Value=MyFile;
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Button pushed function: CreateinsilicospectrumButton
        function CreateinsilicospectrumButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.CreateinsilicospectrumButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.CreateSpectrum_App = CreateSpectrum(app,app.FluorophoreDir);
        end

        % Button pushed function: LoadExistingFluorophoreButton
        function LoadExistingFluorophoreButtonPushed(app, event)
            %Load matlab file uigetfile
            cd(app.FluorophoreDir)
            [file,path] = uigetfile('*.mat','Select a fluorophore definition Matlab input file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'fluorophore');
                app.Fluorophorepar=fluorophore;
                
                UpdateFluorophorePar(app);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Call main app's public function
            Reenable(app.CallingApp)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % updateCancel(app.CallingApp) %#ok<ADMTHDINV>
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesAbsSpectrum = uiaxes(app.UIFigure);
            title(app.UIAxesAbsSpectrum, 'Abs/Exc Spectra')
            xlabel(app.UIAxesAbsSpectrum, 'Wavelength [nm]')
            ylabel(app.UIAxesAbsSpectrum, 'Exctinction Coeff [M-1cm-1]')
            app.UIAxesEmSpectrum = uiaxes(app.UIFigure);
            title(app.UIAxesEmSpectrum, 'Em Spectra')
            xlabel(app.UIAxesEmSpectrum, 'Wavelength [nm]')
            ylabel(app.UIAxesEmSpectrum, 'Normalized Fluo')
            app.NewFluorophoreDefinitionLabel = uilabel(app.UIFigure);
            app.NewFluorophoreDefinitionLabel.Text = 'New Fluorophore Definition ';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FluorophorenameEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophorenameEditFieldLabel.Text = 'Fluorophore name';
            app.FluorophorenameEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophorenameEditField.ValueChangedFcn = createCallbackFcn(app, @FluorophorenameEditFieldValueChanged, true);
            app.pHSensitivitySwitchLabel = uilabel(app.UIFigure);
            app.pHSensitivitySwitchLabel.Text = 'pH Sensitivity';
            app.pHSensitivitySwitch = uiswitch(app.UIFigure, 'slider');
            app.pHSensitivitySwitch.ValueChangedFcn = createCallbackFcn(app, @pHSensitivitySwitchValueChanged, true);
            app.SetupphototransformationsquantumyieldsButton = uibutton(app.UIFigure, 'push');
            app.SetupphototransformationsquantumyieldsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupphototransformationsquantumyieldsButtonPushed, true);
            app.SetupphototransformationsquantumyieldsButton.Text = 'Setup phototransformation''s quantum yields';
            app.SetupthermalexchangeratematrixsButton = uibutton(app.UIFigure, 'push');
            app.SetupthermalexchangeratematrixsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupthermalexchangeratematrixsButtonPushed, true);
            app.SetupthermalexchangeratematrixsButton.Text = 'Setup thermal exchange rate matrix';
            app.SaveFluorophoreButton = uibutton(app.UIFigure, 'push');
            app.SaveFluorophoreButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFluorophoreButtonPushed, true);
            app.SaveFluorophoreButton.Text = 'Save Fluorophore';
            app.NumberofphotophysicalstatesEditField_2Label = uilabel(app.UIFigure);
            app.NumberofphotophysicalstatesEditField_2Label.Text = 'Number of photophysical states';
            app.NumberofphotophysicalstatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofphotophysicalstatesEditField.Editable = 'off';
            app.NumberofphotophysicalstatesEditField.Value = 1;
            app.UIPhotophysicsTable = uitable(app.UIFigure);
            app.UIPhotophysicsTable.ColumnName = {'State name'; 'Fluorescent'; 'Quantum Yield'; 'Photoactive Dark'; 'Thermal Dark'; 'Bleached'; 'Abs/Exc Spectrum Excel Sheet'; 'Abs/Exc Spectrum Excel Range'; 'Em Spectrum Excel Sheet'; 'Em Spectrum Excel Range'; 'Epsilon'; 'Lambda Eps'; 'Photoconverted'; 'In rapid equilibrium'; 'Paired state name'; 'pKa'; 'Hill'};
            app.UIPhotophysicsTable.RowName = {'State1, State2, State3'};
            app.UIPhotophysicsTable.ColumnEditable = [true false true false false false true true true true true true true true false false false];
            app.UIPhotophysicsTable.CellEditCallback = createCallbackFcn(app, @UIPhotophysicsTableCellEdit, true);
            app.CheckSpectraDropDownLabel = uilabel(app.UIFigure);
            app.CheckSpectraDropDownLabel.Text = 'Check Spectra';
            app.CheckSpectraDropDown = uidropdown(app.UIFigure);
            app.CheckSpectraDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckSpectraDropDownValueChanged, true);
            app.ChooseExcelfileforspectraldataButton = uibutton(app.UIFigure, 'push');
            app.ChooseExcelfileforspectraldataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelfileforspectraldataButtonPushed, true);
            app.ChooseExcelfileforspectraldataButton.Text = 'Choose Excel file for spectral  data';
            app.SpectradatafileEditFieldLabel = uilabel(app.UIFigure);
            app.SpectradatafileEditFieldLabel.Text = 'Spectra data file';
            app.SpectradatafileEditField = uieditfield(app.UIFigure, 'text');
            app.SpectradatafileEditField.Editable = 'off';
            app.LoadExistingFluorophoreButton = uibutton(app.UIFigure, 'push');
            app.LoadExistingFluorophoreButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingFluorophoreButtonPushed, true);
            app.LoadExistingFluorophoreButton.Text = 'Load Existing Fluorophore';
            app.InsertfluorescentstatebeforepositionDropDownLabel = uilabel(app.UIFigure);
            app.InsertfluorescentstatebeforepositionDropDownLabel.Text = 'Insert fluorescent state before position';
            app.InsertfluorescentstatebeforepositionDropDown = uidropdown(app.UIFigure);
            app.InsertfluorescentstatebeforepositionDropDown.ValueChangedFcn = createCallbackFcn(app, @InsertfluorescentstatebeforepositionDropDownValueChanged, true);
            app.InsertbleachedstatebeforepositionDropDownLabel = uilabel(app.UIFigure);
            app.InsertbleachedstatebeforepositionDropDownLabel.Text = 'Insert bleached state before position';
            app.InsertbleachedstatebeforepositionDropDown = uidropdown(app.UIFigure);
            app.InsertbleachedstatebeforepositionDropDown.ValueChangedFcn = createCallbackFcn(app, @InsertbleachedstatebeforepositionDropDownValueChanged, true);
            app.InsertphotoactivedarkstatebeforepositionDropDownLabel = uilabel(app.UIFigure);
            app.InsertphotoactivedarkstatebeforepositionDropDownLabel.Text = 'Insert photoactive dark state before position';
            app.InsertphotoactivedarkstatebeforepositionDropDown = uidropdown(app.UIFigure);
            app.InsertphotoactivedarkstatebeforepositionDropDown.ValueChangedFcn = createCallbackFcn(app, @InsertphotoactivedarkstatebeforepositionDropDownValueChanged, true);
            app.InsertthermaldarkstatebeforepositionDropDownLabel = uilabel(app.UIFigure);
            app.InsertthermaldarkstatebeforepositionDropDownLabel.Text = 'Insert thermal dark state before position';
            app.InsertthermaldarkstatebeforepositionDropDown = uidropdown(app.UIFigure);
            app.InsertthermaldarkstatebeforepositionDropDown.ValueChangedFcn = createCallbackFcn(app, @InsertthermaldarkstatebeforepositionDropDownValueChanged, true);
            app.RemovestateatpositionDropDownLabel = uilabel(app.UIFigure);
            app.RemovestateatpositionDropDownLabel.Text = 'Remove state at position';
            app.RemovestateatpositionDropDown = uidropdown(app.UIFigure);
            app.RemovestateatpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @RemovestateatpositionDropDownValueChanged, true);
            app.ExtractSpectrafromExcelFileButton = uibutton(app.UIFigure, 'push');
            app.ExtractSpectrafromExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSpectrafromExcelFileButtonPushed, true);
            app.ExtractSpectrafromExcelFileButton.Text = 'Extract Spectra from Excel File';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done !';
            app.CreateinsilicospectrumButton = uibutton(app.UIFigure, 'push');
            app.CreateinsilicospectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CreateinsilicospectrumButtonPushed, true);
            app.CreateinsilicospectrumButton.Text = 'Create in silico spectrum';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefineNewFluorophore(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end