classdef DiffusionSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HoppingProbabilityMatrixLabel  matlab.ui.control.Label
        HTable                         matlab.ui.control.Table
        HopDiffusionSwitch             matlab.ui.control.Switch
        HopDiffusionSwitchLabel        matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        DITable                        matlab.ui.control.Table
        DiffusionindependanttransitionsSwitch  matlab.ui.control.Switch
        DiffusionindependanttransitionsSwitchLabel  matlab.ui.control.Label
        VelocityMatrixLabel            matlab.ui.control.Label
        DiffusionMatrixLabel           matlab.ui.control.Label
        Resetallratesto0Button         matlab.ui.control.Button
        ExchangerateMatrixsLabel       matlab.ui.control.Label
        FromLabel                      matlab.ui.control.Label
        ToLabel                        matlab.ui.control.Label
        ExTable                        matlab.ui.control.Table
        VTable                         matlab.ui.control.Table
        DirectedMotionSwitch           matlab.ui.control.Switch
        DirectedMotionSwitchLabel      matlab.ui.control.Label
        PatternidsEditField            matlab.ui.control.EditField
        PatternidsEditFieldLabel       matlab.ui.control.Label
        DTable                         matlab.ui.control.Table
        NumberofdiffusionstatesEditField  matlab.ui.control.NumericEditField
        NumberofdiffusionstatesEditFieldLabel  matlab.ui.control.Label
        CopyMotionParametersFromOtherFluorophoreDropDown  matlab.ui.control.DropDown
        CopyMotionParametersFromOtherFluorophoreDropDownLabel  matlab.ui.control.Label
        FluorophorenameEditField       matlab.ui.control.EditField
        FluorophorenameEditFieldLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        FluorophoreDiffusionandDirectedMotionSetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        smis_gui_parameters=[]; % Initialize general parameters
        Fluorophorepar=[]; % Initialize the parameters
        OtherFluorophorepar=[]; % Initialize the parameters
        selected_fluorophore=[] ; % Initialize selected fluorophore
        n_fluorophores=[]; % Initialize number of fluorophores
        n_sp=[] ; % Initialize number of sub-patterns
        sp_ids=[] ; % Initialize sub-patterns ids
        n_diffusion_states; % Initialize number of diffusion states
        TableSize=[] % Description of Table size properties
    end
    
    methods (Access = private)
        function UpdateDiffusionSetup(app)
            
            app.NumberofdiffusionstatesEditField.Value=app.n_diffusion_states;
            if app.n_diffusion_states>=1
                % Make the tables visible
                app.DTable.Visible='on';
                app.DirectedMotionSwitch.Visible='On';
                app.DirectedMotionSwitchLabel.Visible='On';
                app.ExTable.Visible='on';
                app.ToLabel.Visible='on';
                app.FromLabel.Visible='on';
                app.Resetallratesto0Button.Visible='on';
                app.ExchangerateMatrixsLabel.Visible='on';
                app.DiffusionMatrixLabel.Visible='on';
                app.VelocityMatrixLabel.Visible='on';
                app.HoppingProbabilityMatrixLabel.Visible='on';
                
                % Setup diffusion table 
                MyColumnWidth=num2cell(app.TableSize.DataColumnWidth*ones(1,app.n_diffusion_states));
                app.DTable.ColumnWidth=MyColumnWidth;
            
                RowNameColumnWidth=85;
                DTableWidth= min([RowNameColumnWidth+app.TableSize.DataColumnWidth*app.n_diffusion_states,app.TableSize.MaxWidth]);
                DTableHeight=76; 
                DTableBottomPos=588;
                DTableLeftPos=app.TableSize.LeftPos;

                app.DTable.Position=[DTableLeftPos,DTableBottomPos,DTableWidth,DTableHeight]; % Adjust position

                % Show the names of the diffusion states
                for i=1:app.n_diffusion_states % Add new names
                    app.DTable.ColumnName(i) = {['D',num2str(i)]};
                end
                
                % Fill the data if available
                if isempty(app.Fluorophorepar.Motion.D) || numel(app.Fluorophorepar.Motion.D)~=app.n_diffusion_states || isempty(app.Fluorophorepar.Motion.D_confined)
                    MyData=zeros(2,app.n_diffusion_states);
                    MyData(2,1:app.n_sp)=app.sp_ids;
                    app.DTable.Data=MyData;
                else
                    app.DTable.Data=[app.Fluorophorepar.Motion.D_confined;app.Fluorophorepar.Motion.D];
                end
                MyColumnFormat=cell(1,numel(app.DTable.ColumnName));
                [MyColumnFormat{:}]=deal('shortG');
                app.DTable.ColumnFormat = MyColumnFormat;
                
                
                % Setup directed motion table
                if app.Fluorophorepar.Motion.Directed_Motion==1
                    app.DirectedMotionSwitch.Value='On';
                    app.VelocityMatrixLabel.Visible='on';
                    app.VTable.Visible='on';
                    
                    MyColumnWidth=num2cell(app.TableSize.DataColumnWidth*ones(1,app.n_diffusion_states));
                    app.VTable.ColumnWidth=MyColumnWidth;
                  
                    RowNameColumnWidth=148;
                    VTableWidth= min([RowNameColumnWidth+app.TableSize.DataColumnWidth*app.n_diffusion_states,app.TableSize.MaxWidth]);
                    VTableHeight=76; 
                    VTableBottomPos=463;
                    VTableLeftPos=app.TableSize.LeftPos;

                    app.VTable.Position=[VTableLeftPos,VTableBottomPos,VTableWidth,VTableHeight]; % Adjust position

                    % Show the names of the diffusion states
                    for i=1:app.n_diffusion_states % Add new names
                        app.VTable.ColumnName(i) = {['V',num2str(i)]};
                    end
                    
                    % Fill the data if available
                    if isempty(app.Fluorophorepar.Motion.V) || numel(app.Fluorophorepar.Motion.V)~=app.n_diffusion_states || isempty(app.Fluorophorepar.Motion.persistence_length)
                        MyData=zeros(2,app.n_diffusion_states);
                        MyData(2,:)=-1; % Initialize the persistence length
                        app.VTable.Data=MyData;
                        app.Fluorophorepar.Motion.V=MyData(1,:);
                        app.Fluorophorepar.Motion.persistence_length=MyData(2,:);
                    else
                        app.VTable.Data=[app.Fluorophorepar.Motion.V; app.Fluorophorepar.Motion.persistence_length];
                    end
                    MyColumnFormat=cell(1,numel(app.VTable.ColumnName));
                    [MyColumnFormat{:}]=deal('shortG');
                    app.VTable.ColumnFormat = MyColumnFormat;
                    
                else
                    app.DirectedMotionSwitch.Value='Off';
                    app.VelocityMatrixLabel.Visible='off';
                    app.VTable.Visible='off';
                end

                % Setup Hop Diffusion table
                if app.Fluorophorepar.Motion.Hop_Diffusion==1
                    app.HopDiffusionSwitch.Value='On';
                    app.HoppingProbabilityMatrixLabel.Visible='on';
                    app.HTable.Visible='on';
                    
                    MyColumnWidth=num2cell(app.TableSize.DataColumnWidth*ones(1,app.n_diffusion_states));
                    app.HTable.ColumnWidth=MyColumnWidth;

                    RowNameColumnWidth=102;
                    HTableWidth= min([RowNameColumnWidth+app.TableSize.DataColumnWidth*app.n_diffusion_states,app.TableSize.MaxWidth]);
                    HTableLeftPos=app.TableSize.LeftPos;

                    if HTableWidth<app.TableSize.MaxWidth
                        HTableHeight=53;
                        HTableBottomPos=363;
                    else
                        HTableHeight=53+app.TableSize.ScrollbarHeight;
                        HTableBottomPos=363-app.TableSize.ScrollbarHeight;
                    end

                    app.HTable.Position=[HTableLeftPos,HTableBottomPos,HTableWidth,HTableHeight]; % Adjust position

                    % Show the names of the diffusion states
                    for i=1:app.n_diffusion_states % Add new names
                        app.HTable.ColumnName(i) = {['H',num2str(i)]};
                    end
                    
                    % Fill the data if available
                    if numel(app.Fluorophorepar.Motion.Hop_Probability)~=app.n_diffusion_states 
                        MyData=zeros(1,app.n_diffusion_states);
                        app.HTable.Data=MyData;
                        app.Fluorophorepar.Motion.Hop_Probability=MyData(1,:);
                    else
                        app.HTable.Data=app.Fluorophorepar.Motion.Hop_Probability;
                    end
                    MyColumnFormat=cell(1,numel(app.HTable.ColumnName));
                    [MyColumnFormat{:}]=deal('shortG');
                    app.HTable.ColumnFormat = MyColumnFormat;
                    
                else
                    app.HopDiffusionSwitch.Value='Off';
                    app.HoppingProbabilityMatrixLabel.Visible='off';
                    app.HTable.Visible='off';
                end
                
                % Setup exchange rate table                            
                MyColumnWidth=num2cell(app.TableSize.DataColumnWidth*ones(1,app.n_diffusion_states));
                app.ExTable.ColumnWidth=MyColumnWidth;
            
                RowNameColumnWidth=45;
                ExTableWidth= min([RowNameColumnWidth+app.TableSize.DataColumnWidth*app.n_diffusion_states,app.TableSize.MaxWidth]);
                
                ExTableLeftPos=app.TableSize.LeftPos;
                MyTableHeight=min([33+22*app.n_diffusion_states,500]);
                ExTableHeight=min([MyTableHeight,165]);
                ExTableBottomPos=max([90,250-MyTableHeight]);
                app.ExTable.Position=[ExTableLeftPos,ExTableBottomPos,ExTableWidth,ExTableHeight]; % Adjust position

                
                app.ToLabel.Position(1)=app.ExTable.Position(1)+app.ExTable.Position(3)/2;
                app.FromLabel.Position(2)=app.ExTable.Position(2)+app.ExTable.Position(4)/2;
                
                % Show the names of the diffusion states
                for i=1:app.n_diffusion_states % Add new names
                    if app.Fluorophorepar.Motion.Directed_Motion==1
                        app.ExTable.ColumnName(i) = {['D',num2str(i),'/V',num2str(i)]};
                    else
                        app.ExTable.ColumnName(i) = {['D',num2str(i)]};
                    end
                end
                app.ExTable.RowName=app.ExTable.ColumnName;
                
                % Fill the data if available
                if isempty(app.Fluorophorepar.Motion.D_rate_matrix) || size(app.Fluorophorepar.Motion.D_rate_matrix,1)~=app.n_diffusion_states || size(app.Fluorophorepar.Motion.D_rate_matrix,2)~=app.n_diffusion_states
                    MyData=zeros(app.n_diffusion_states,app.n_diffusion_states);
                    if ~isempty(app.Fluorophorepar.Motion.D_rate_matrix)
                        s1=min([app.n_diffusion_states,size(app.Fluorophorepar.Motion.D_rate_matrix,1)]);
                        s2=min([app.n_diffusion_states,size(app.Fluorophorepar.Motion.D_rate_matrix,2)]);
                        MyData(1:s1,1:s2)=app.Fluorophorepar.Motion.D_rate_matrix(1:s1,1:s2);
                    end
                    app.ExTable.Data=MyData;
                    app.Fluorophorepar.Motion.D_rate_matrix=MyData;
                else
                    app.ExTable.Data=app.Fluorophorepar.Motion.D_rate_matrix;
                end
                MyColumnFormat=cell(1,numel(app.ExTable.ColumnName));
                [MyColumnFormat{:}]=deal('shortG');
                app.ExTable.ColumnFormat = MyColumnFormat;
                
                % Setup DI (Diffusion independant transition) table 
                MyColumnWidth=num2cell(app.TableSize.DataColumnWidth*ones(1,app.n_diffusion_states));
                app.DITable.ColumnWidth=MyColumnWidth;

                RowNameColumnWidth=101;
                MaxWidth=app.TableSize.MaxWidth-150;
                DITableWidth= min([RowNameColumnWidth+app.TableSize.DataColumnWidth*app.n_diffusion_states,MaxWidth]);
                DITableLeftPos=app.TableSize.LeftPos;

                if DITableWidth<MaxWidth
                    DITableHeight=53;
                    DITableBottomPos=20;
                else
                    DITableHeight=53+app.TableSize.ScrollbarHeight;
                    DITableBottomPos=10;
                end

                app.DITable.Position=[DITableLeftPos,DITableBottomPos,DITableWidth,DITableHeight]; % Adjust position
              

                % Show the names of the diffusion states
                for i=1:app.n_diffusion_states % Add new names
                    app.DITable.ColumnName(i) = {['D',num2str(i)]};
                end
                
                % Fill the data
                if isempty(app.Fluorophorepar.Motion.DIT) || numel(app.Fluorophorepar.Motion.DIT)~=app.n_diffusion_states
                    MyData=false(1,app.n_diffusion_states);
                    if ~isempty(app.Fluorophorepar.Motion.DIT)
                        s1=min([app.n_diffusion_states,size(app.Fluorophorepar.Motion.DIT)]);
                        MyData(1,1:s1)=app.Fluorophorepar.Motion.DIT(1,1:s1);
                    end
                    app.DITable.Data=logical(MyData);
                    app.Fluorophorepar.Motion.DIT=logical(MyData);
                else
                    app.DITable.Data=logical(app.Fluorophorepar.Motion.DIT);
                end
                
            else
                % Hide everything
                app.DTable.Visible='off';
                app.VTable.Visible='off';
                app.DITable.Visible='off';
                app.DirectedMotionSwitch.Visible='off';
                app.DirectedMotionSwitchLabel.Visible='off';
                app.DiffusionindependanttransitionsSwitch.Visible='off';
                app.ExTable.Visible='off';
                app.ToLabel.Visible='off';
                app.FromLabel.Visible='off';
                app.Resetallratesto0Button.Visible='off';
                app.ExchangerateMatrixsLabel.Visible='off';
                app.DiffusionMatrixLabel.Visible='off';
                app.VelocityMatrixLabel.Visible='off';
                app.HoppingProbabilityMatrixLabel='off';
            end
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selected_fluorophore)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Get all the parameters
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
            
            % Grab the number of the selected fluorophore
            app.selected_fluorophore=selected_fluorophore;
            input_fluorophore_par=app.smis_gui_parameters.Fluorophores;
            
            % Initialize selected fluorophore parameters
            app.Fluorophorepar=input_fluorophore_par(selected_fluorophore);
            
            % Initialize other fluorophore's parameters
            app.OtherFluorophorepar=input_fluorophore_par;
            app.OtherFluorophorepar(selected_fluorophore)=[];
            
            % Get number of fluorophores
            app.n_fluorophores=size(input_fluorophore_par,2);
            
            % Handle visibility of copy fluorophore option
            if app.n_fluorophores<2
                app.CopyMotionParametersFromOtherFluorophoreDropDown.Visible = 'off';
                app.CopyMotionParametersFromOtherFluorophoreDropDownLabel.Visible = 'off';
            else
                other_fluorophore_names={app.OtherFluorophorepar.name};
                other_fluorophore_names=horzcat('Choose',other_fluorophore_names);
                app.CopyMotionParametersFromOtherFluorophoreDropDown.Items=other_fluorophore_names;
            end
            
            % Display the Fluorophore name
            app.FluorophorenameEditField.Value=app.Fluorophorepar.name;
            
            % Display the sub pattern ids
            app.n_sp=app.smis_gui_parameters.pattern_ids(selected_fluorophore).n_sp; % Number of sub patterns
            app.sp_ids=app.smis_gui_parameters.pattern_ids(selected_fluorophore).sp_ids; % Ids of sub patterns
            
            if isempty(app.sp_ids) % A pattern as not been loaded !
                warndlg('A pattern for this fluorophore must be loaded before defining diffusion parameters ! ','Warning');
            end
            
            app.PatternidsEditField.Value=num2str(horzcat(app.sp_ids)');
            app.PatternidsEditField.Position=[401,708,min([35*app.n_sp,400]),22];
                        
            if ~isempty(app.Fluorophorepar.Motion.D_confined) && numel(app.Fluorophorepar.Motion.D_confined)>=app.n_sp % Diffusion parameters exist already
                app.n_diffusion_states=numel(app.Fluorophorepar.Motion.D); % Number of diffusion states
                if numel(app.Fluorophorepar.Motion.V)~=numel(app.Fluorophorepar.Motion.D)
                    app.Fluorophorepar.Motion.V=zeros(1,app.n_diffusion_states);
                    app.Fluorophorepar.Motion.persistence_length=-1*ones(1,app.n_diffusion_states);
                end
            else % Initialize diffusion parameters
                app.n_diffusion_states=app.n_sp;
                app.Fluorophorepar.Motion.D_confined=app.sp_ids';
                app.Fluorophorepar.Motion.D=zeros(1,app.n_sp);
                app.Fluorophorepar.Motion.D(1)=nan;
                app.Fluorophorepar.Motion.V=zeros(1,app.n_sp);
                app.Fluorophorepar.Motion.persistence_length=-1*ones(1,app.n_sp);
                app.Fluorophorepar.Motion.DIT=zeros(1,app.n_sp);
                app.Fluorophorepar.Motion.Hop_Probability=zeros(1,app.n_sp);
            end
            
            % Create if necessary
            if ~isfield(app.Fluorophorepar.Motion,'D_independant_transition')
                app.Fluorophorepar.Motion.D_independant_transition=0;
            end
            
            if ~isfield(app.Fluorophorepar.Motion,'DIT')
                app.Fluorophorepar.Motion.DIT=false(1,app.n_diffusion_states);
            end
            
            if app.Fluorophorepar.Motion.D_independant_transition==1
                app.DiffusionindependanttransitionsSwitch.Value='On';
                app.DITable.Visible='on';
            else
                app.DiffusionindependanttransitionsSwitch.Value='Off';
                app.DITable.Visible='off';
            end

             % Create if necessary
            if ~isfield(app.Fluorophorepar.Motion,'Hop_Diffusion')
                app.Fluorophorepar.Motion.Hop_Diffusion=0;
            end

            if ~isfield(app.Fluorophorepar.Motion,'Hop_Probability')
                app.Fluorophorepar.Motion.Hop_Probability=zeros(1,app.n_diffusion_states);
            end

            if app.Fluorophorepar.Motion.Hop_Diffusion==1
                app.HopDiffusionSwitch.Value='On';
                app.HTable.Visible='on';
            else
                app.HopDiffusionSwitch.Value='Off';
                app.HTable.Visible='off';
            end

            %Define Table size & position properties
            app.TableSize.LeftPos=392;
            app.TableSize.MaxWidth=app.UIFigure.Position(3)-10-app.TableSize.LeftPos;
            app.TableSize.ScrollbarHeight=20;    
            app.TableSize.DataColumnWidth=50;          
            
            % Update UI with input values
            UpdateDiffusionSetup(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateDiffusionpar(app.CallingApp, app.Fluorophorepar, app.selected_fluorophore)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: NumberofdiffusionstatesEditField
        function NumberofdiffusionstatesEditFieldValueChanged(app, event)
            if event.Value>=numel(app.sp_ids) % A minimum of one diffusion state per sub pattern ID must be defined
                if event.Value<event.PreviousValue
                    app.Fluorophorepar.Motion.D = app.Fluorophorepar.Motion.D(1:event.Value);
                    app.Fluorophorepar.Motion.D_confined = app.Fluorophorepar.Motion.D_confined(1:event.Value);
                    app.Fluorophorepar.Motion.V = app.Fluorophorepar.Motion.V(1:event.Value);
                    app.Fluorophorepar.Motion.persistence_length = app.Fluorophorepar.Motion.persistence_length(1:event.Value);
                    app.Fluorophorepar.Motion.DIT = app.Fluorophorepar.Motion.DIT(1:event.Value);
                else
                    app.Fluorophorepar.Motion.D(event.PreviousValue+1:event.Value) = 0;
                    app.Fluorophorepar.Motion.D_confined(event.PreviousValue+1:event.Value) = 0;
                    app.Fluorophorepar.Motion.V(event.PreviousValue+1:event.Value) = 0;
                    app.Fluorophorepar.Motion.persistence_length(event.PreviousValue+1:event.Value) = -1;
                    app.Fluorophorepar.Motion.DIT(event.PreviousValue+1:event.Value) = false;
                end
                app.n_diffusion_states=numel(app.Fluorophorepar.Motion.D);
                UpdateDiffusionSetup(app);
            else
                warndlg('A minimum of 1 diffusion state per sub-pattern ID must be defined !','Warning');
            end
        end

        % Cell edit callback: DTable
        function DTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            valid_D=1; % Initialize
            %Issue warnings when transformation is not possible
            if indices(1)==1 %  Check that the specified sub pattern exists
                if ~ismember(newData,app.sp_ids)
                    warndlg('Specified sub pattern does not exist !','Warning');
                    valid_D=0;
                end
            end
            
            if indices(1)==2 %  Check that the specified diffusion coefficient is positive
                if newData<0
                    warndlg('Specified diffusion coefficient must be positive !','Warning');
                    valid_D=0;
                end
            end
            
            if valid_D==1
                app.Fluorophorepar.Motion.D = app.DTable.DisplayData(2,:);
                app.Fluorophorepar.Motion.D_confined = app.DTable.DisplayData(1,:);
            else
                app.DTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
        end

        % Cell edit callback: ExTable
        function ExTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            valid_Ex=1; % Initialize
            
            %Issue warnings when transformation is not possible
            if indices(1)==indices(2) %  A state cannot exchange to itself
                warndlg('A state cannot exchange to itself !','Warning');
                valid_Ex=0;
            end
            
            if newData<0
                warndlg('Specified rate must be positive !','Warning');
                valid_Ex=0;
            end
            
            if valid_Ex==1
                app.Fluorophorepar.Motion.D_rate_matrix = app.ExTable.DisplayData;
            else
                app.ExTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
            
        end

        % Cell edit callback: VTable
        function VTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            valid_V=1; % Initialize
            %Issue warnings when transformation is not possible
            if indices(1)==1 %  Check that the specified velocity is positive
                if newData<0
                    warndlg('Specified velocity must be positive !','Warning');
                    valid_V=0;
                end
            end
            
            if indices(1)==2 %  Check that the specified persistence length is positive
                if newData<0 && newData~=-1
                    warndlg('Specified persistence length must be positive or equal to -1 !','Warning');
                    valid_V=0;
                end
            end
            
            if valid_V==1
                app.Fluorophorepar.Motion.persistence_length = app.VTable.DisplayData(2,:);
                app.Fluorophorepar.Motion.V = app.VTable.DisplayData(1,:);
            else
                app.VTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
        end

        % Value changed function: DirectedMotionSwitch
        function DirectedMotionSwitchValueChanged(app, event)
            value = app.DirectedMotionSwitch.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Motion.Directed_Motion=1;
                app.VelocityMatrixLabel.Visible='on';
            else
                app.Fluorophorepar.Motion.Directed_Motion=0;
                app.VelocityMatrixLabel.Visible='off';
            end
            
            UpdateDiffusionSetup(app);
        end

        % Button pushed function: Resetallratesto0Button
        function Resetallratesto0ButtonPushed(app, event)
            app.ExTable.Data(:)=0; % Reset
            app.Fluorophorepar.Motion.D_rate_matrix = app.ExTable.DisplayData;
        end

        % Value changed function: DiffusionindependanttransitionsSwitch
        function DiffusionindependanttransitionsSwitchValueChanged(app, event)
            value = app.DiffusionindependanttransitionsSwitch.Value;
            if strcmp(value,'On')
                app.DITable.Visible='on';
                app.Fluorophorepar.Motion.D_independant_transition=1;
            else
                app.DITable.Visible='off';
                app.Fluorophorepar.Motion.D_independant_transition=0;
            end
            UpdateDiffusionSetup(app);
        end

        % Cell edit callback: DITable
        function DITableCellEdit(app, event)
            app.Fluorophorepar.Motion.DIT = app.DITable.DisplayData(1,:);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end

        % Value changed function: HopDiffusionSwitch
        function HopDiffusionSwitchValueChanged(app, event)
            value = app.HopDiffusionSwitch.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Motion.Hop_Diffusion=1;
                app.HoppingProbabilityMatrixLabel.Visible='on';
            else
                app.Fluorophorepar.Motion.Hop_Diffusion=0;
                app.HoppingProbabilityMatrixLabel.Visible='off';
            end
            
            UpdateDiffusionSetup(app);
        end

        % Cell edit callback: HTable
        function HTableCellEdit(app, event)
            newData = event.NewData;
            
            valid_H=1; % Initialize
            %Issue warnings if needed
            if newData<0 || newData>1 
                warndlg('Probability must be between 0 and 1 !','Warning');
                valid_H=0;
            end

            if valid_H==1
                app.Fluorophorepar.Motion.Hop_Probability = app.HTable.DisplayData(1,:);
            else
                app.HTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Don't chan gethe value 
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FluorophoreDiffusionandDirectedMotionSetupLabel = uilabel(app.UIFigure);
            app.FluorophoreDiffusionandDirectedMotionSetupLabel.Text = 'Fluorophore Diffusion and Directed Motion Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FluorophorenameEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophorenameEditFieldLabel.Text = 'Fluorophore name';
            app.FluorophorenameEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophorenameEditField.Editable = 'off';
            app.CopyMotionParametersFromOtherFluorophoreDropDownLabel = uilabel(app.UIFigure);
            app.CopyMotionParametersFromOtherFluorophoreDropDownLabel.Text = 'Copy Motion Parameters From Other Fluorophore ';
            app.CopyMotionParametersFromOtherFluorophoreDropDown = uidropdown(app.UIFigure);
            app.NumberofdiffusionstatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofdiffusionstatesEditFieldLabel.Text = 'Number of diffusion states';
            app.NumberofdiffusionstatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofdiffusionstatesEditField.Limits = [0 Inf];
            app.NumberofdiffusionstatesEditField.ValueDisplayFormat = '%3.1d';
            app.NumberofdiffusionstatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofdiffusionstatesEditFieldValueChanged, true);
            app.NumberofdiffusionstatesEditField.Tooltip = {'A minimum of one diffusion state should be defined for each sub-pattern in the virtual image. '};
            app.DTable = uitable(app.UIFigure);
            app.DTable.ColumnName = {'D1'; 'D2'};
            app.DTable.RowName = {'Subpattern id'; 'D [um²/s]'};
            app.DTable.ColumnEditable = true;
            app.DTable.CellEditCallback = createCallbackFcn(app, @DTableCellEdit, true);
            app.DTable.Tooltip = {'For each diffusion state, give the value of the diffusion coefficient and the ID of the subpattern where the single molecules may take this diffusion state'};
            app.PatternidsEditFieldLabel = uilabel(app.UIFigure);
            app.PatternidsEditFieldLabel.Text = 'Pattern ids';
            app.PatternidsEditField = uieditfield(app.UIFigure, 'text');
            app.PatternidsEditField.Editable = 'off';
            app.DirectedMotionSwitchLabel = uilabel(app.UIFigure);
            app.DirectedMotionSwitchLabel.Text = 'Directed Motion';
            app.DirectedMotionSwitch = uiswitch(app.UIFigure, 'slider');
            app.DirectedMotionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectedMotionSwitchValueChanged, true);
            app.VTable = uitable(app.UIFigure);
            app.VTable.ColumnName = {'V1'; 'V2'};
            app.VTable.RowName = {'V [um/s]'; 'Persistence Length [um]'};
            app.VTable.ColumnEditable = true;
            app.VTable.CellEditCallback = createCallbackFcn(app, @VTableCellEdit, true);
            app.VTable.Tooltip = {'For each diffusion state, give the value of the velocity and corresponding persistence length: the persistence length indicates the typical distance over which the subpattern does not change orientation, ie over which molecules should travel in a straight manner. Use a value of -1 for automated determination of the persistence length. A long straight filament should have a long persistence length whereas the bulk of the cytoplasm of the cell for example should rather have a short persistence length.'};
            app.ExTable = uitable(app.UIFigure);
            app.ExTable.ColumnName = {'D1'; 'D2'};
            app.ExTable.RowName = {'D1,D2'};
            app.ExTable.ColumnEditable = true;
            app.ExTable.CellEditCallback = createCallbackFcn(app, @ExTableCellEdit, true);
            app.ExTable.Tooltip = {'If molecules can change their diffusion state, indicate the exchange rates in this table. This can be from one subpattern to another, or within a single subpattern.'};
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.ExchangerateMatrixsLabel = uilabel(app.UIFigure);
            app.ExchangerateMatrixsLabel.Text = 'Exchange-rate Matrix [s-1]';
            app.Resetallratesto0Button = uibutton(app.UIFigure, 'push');
            app.Resetallratesto0Button.ButtonPushedFcn = createCallbackFcn(app, @Resetallratesto0ButtonPushed, true);
            app.Resetallratesto0Button.Text = 'Reset all rates to 0';
            app.DiffusionMatrixLabel = uilabel(app.UIFigure);
            app.DiffusionMatrixLabel.Text = 'Diffusion Matrix ';
            app.VelocityMatrixLabel = uilabel(app.UIFigure);
            app.VelocityMatrixLabel.Text = 'Velocity Matrix ';
            app.DiffusionindependanttransitionsSwitchLabel = uilabel(app.UIFigure);
            app.DiffusionindependanttransitionsSwitchLabel.Text = 'Diffusion independant transitions';
            app.DiffusionindependanttransitionsSwitch = uiswitch(app.UIFigure, 'slider');
            app.DiffusionindependanttransitionsSwitch.ValueChangedFcn = createCallbackFcn(app, @DiffusionindependanttransitionsSwitchValueChanged, true);
            app.DiffusionindependanttransitionsSwitch.Tooltip = {'Use this option to force a possible transition to a new sub-pattern even if the molecule did not move to that pattern. This mimics molecular unbinding in e.g. PAINT experiments, where the unbinding rate is not diffusion dependent. Thus, this option should only be used for sub-patterns that are very thin, otherwise it does not make sense, as the molecules will jump a long way.'};
            app.DITable = uitable(app.UIFigure);
            app.DITable.ColumnName = {'D1'; 'D2'};
            app.DITable.RowName = {'Force transition'};
            app.DITable.ColumnEditable = true;
            app.DITable.CellEditCallback = createCallbackFcn(app, @DITableCellEdit, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HopDiffusionSwitchLabel = uilabel(app.UIFigure);
            app.HopDiffusionSwitchLabel.Text = 'Hop Diffusion';
            app.HopDiffusionSwitch = uiswitch(app.UIFigure, 'slider');
            app.HopDiffusionSwitch.ValueChangedFcn = createCallbackFcn(app, @HopDiffusionSwitchValueChanged, true);
            app.HopDiffusionSwitch.Tooltip = {'Set to On if you want to allow for hoping motions. Hoping means jumping to a new position through a different pattern ID then the pattern ID where the fluorophore currently resides. This is useful in 2 situations: 1/ the sub pattern has a concave shape and this may lead to artifactual motion steps if hopping diffusion is not prevented (ie hopping probability set to 0); 2/ you want to simulate hopping diffusion, with a certain probability to jump from one confined area to another.'};
            app.HTable = uitable(app.UIFigure);
            app.HTable.ColumnName = {'H1'; 'H2'};
            app.HTable.RowName = {'Hop probability'};
            app.HTable.ColumnEditable = true;
            app.HTable.CellEditCallback = createCallbackFcn(app, @HTableCellEdit, true);
            app.HTable.Tooltip = {'Set the probability for hopping for each diffusion state'};
            app.HoppingProbabilityMatrixLabel = uilabel(app.UIFigure);
            app.HoppingProbabilityMatrixLabel.Text = 'Hopping Probability Matrix ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffusionSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end