classdef DisplayPSF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DistancefromsamplecenternmEditField  matlab.ui.control.EditField
        DistancefromsamplecenternmEditFieldLabel  matlab.ui.control.Label
        FluorescentstateDropDown       matlab.ui.control.DropDown
        FluorescentstateDropDownLabel  matlab.ui.control.Label
        ShowatsamplezcenterButton      matlab.ui.control.Button
        ShowatsampletopButton          matlab.ui.control.Button
        ShowatsamplebottomButton       matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        Showin3DButton                 matlab.ui.control.Button
        FluorophoreDropDown            matlab.ui.control.DropDown
        FluorophoreDropDownLabel       matlab.ui.control.Label
        ChannelSwitch                  matlab.ui.control.Switch
        ChannelSwitchLabel             matlab.ui.control.Label
        ZRasterSpinner                 matlab.ui.control.Spinner
        ZRasterSlider                  matlab.ui.control.Slider
        ZRasterSliderLabel             matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        PSFDispayLabel                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        par=struct; % Parameters
    end
    
    methods (Access = private)
        
        function PSF_figure=show3DPSF(app)
          
            % Create an additional figure with a 3D plot. It should be a
            % regular Matlab figure, otherwise very slow !
            % Create a figure if necessary
            create_figure=1;
            
            % The following code ensures that the right figure is addressed
            % Indeed, it might be that the figure has been created before, then deleted, and the same figure number has been re-created for another purpose and is still open

            % Take a look at the current figures open
            figHandles = findobj('Type', 'figure'); % Get the array of all figures open
            n_fig=size(figHandles,1);
            for k=1:n_fig
                if strcmp(figHandles(k).Name,'Virtual Sample')==1 % Our figure already exists
                    create_figure=0;
                    PSF_figure=figHandles(k);
                end
            end

            % create a figure if necessary
            if create_figure==1
                scrsz=get(0,'ScreenSize');
                w_xsize=scrsz(3)*3/4;
                w_ysize=scrsz(4)*3/4;
                PSF_figure=figure;

                colormap(gray);
                set(PSF_figure,'color','white');
                set(PSF_figure,'Position',[10 scrsz(4)/10 w_xsize w_ysize])
                set(PSF_figure,'color','white');
                set(PSF_figure,'Name','3D PSF');
                set(PSF_figure,'NumberTitle','off');
            else
                figure(PSF_figure);
                clf;
            end

            psf=app.par.psf;
           
            %Limit to 32 pixels
            % size_lim=64; %32
            rbox_x_max=max([psf.rbox_x]);
            rbox_y_max=max([psf.rbox_y]);
            rbox_z=size(psf,2);
            psf3D=zeros(2*rbox_y_max+1,2*rbox_x_max+1,rbox_z);

            for i=1:rbox_z
                psf3D(rbox_y_max-psf(i).rbox_y+1:rbox_y_max+psf(i).rbox_y+1,rbox_x_max-psf(i).rbox_x+1:rbox_x_max+psf(i).rbox_x+1,i)=psf(i).plane;
            end
           
            if strcmp(app.Switch.Value,'Log')==1
                psf3D=log(psf3D);
            end

            vol3d('cdata', psf3D);
            colormap(gray);
            axis image
            xlabel('X Coordinate [raster]','fontsize',16,'fontweight','b')
            ylabel('Y Coordinate [raster]','fontsize',16,'fontweight','b')
            zlabel('Z Coordinate [raster]','fontsize',16,'fontweight','b')
            view(3);
        end
        
        function show2DPSF(app)
            
            ClearImage(app)
            
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
                       
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";          

            psf=app.par.psf;

            if ~any(isnan(psf)) % Check that the PSF does exist
                imagesc(app.UIAxes, psf, [0,max(max(psf))]);
                axis(app.UIAxes,"image");
                xlabel(app.UIAxes,'X Coordinate [raster]','fontsize',14,'fontweight','b')
                ylabel(app.UIAxes,'Y Coordinate [raster]','fontsize',14,'fontweight','b')
                title(app.UIAxes,['Channel ',num2str(app.par.channel),': ',strrep(app.par.current_fluorophore_name,'_',' '),', ', app.par.current_fluorescent_state_name], 'FontWeight','bold','FontSize',14);
            else
                disp('PSF is undefined in this channel ! ')
                title(app.UIAxes,['Undefined PSF for Channel ',num2str(app.par.channel),': ',strrep(app.par.current_fluorophore_name,'_',' '),', ', app.par.current_fluorescent_state_name], 'FontWeight','bold','FontSize',14);
            end
            
           
        end
        
        function show2DPSFsection(app)
            ClearImage(app)
            
            % Show 3D sections in main window
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
            % colormap(app.UIAxes,'jet')

            psf=app.par.psf;
            
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";

            
            % Get the chosen layer
            z=round(app.ZRasterSlider.Value);
            section=psf(z).plane;

            %Update display of distance from sample center
            sample_center_d=app.par.psf_step*(app.ZRasterSlider.Value-app.par.start_slice); % PSF sections are in the middle of pixels
            app.DistancefromsamplecenternmEditField.Value=num2str(sample_center_d);


            if ~any(isnan(section)) % Check that the PSF does exist
                imagesc(app.UIAxes,section);
                axis(app.UIAxes,"image")
                xlabel(app.UIAxes,'X Coordinate [raster]','fontsize',14,'fontweight','b')
                ylabel(app.UIAxes,'Y Coordinate [raster]','fontsize',14,'fontweight','b')
                title(app.UIAxes,['Channel ',num2str(app.par.channel),': ',strrep(app.par.current_fluorophore_name,'_',' '),', ', app.par.current_fluorescent_state_name], 'FontWeight','bold','FontSize',14);
            else
                disp('PSF is undefined in this channel ! ')
                title(app.UIAxes,['Undefined PSF for Channel ',num2str(app.par.channel),': ',strrep(app.par.current_fluorophore_name,'_',' '),', ', app.par.current_fluorescent_state_name], 'FontWeight','bold','FontSize',14);
            end
            
        end
        
        function ClearImage(app)
            MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end


        function drawPSF(app)
            switch app.par.channel
                case 1
                    app.par.psf=app.par.sm_par(app.par.current_fluorophore).psf_par_ch1(app.par.current_fluorescent_state).psf;
                case 2
                    app.par.psf=app.par.sm_par(app.par.current_fluorophore).psf_par_ch2(app.par.current_fluorescent_state).psf;
            end

            if app.par.smis_par.simul_3D==0
                show2DPSF(app);
            else
                % Show the sample in 2D sections
                show2DPSFsection(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.par.smis_par=mainapp.smis_gui_parameters;
            
            % In case a 3D figure will be created and deleted upon closure
            app.par.figure_number=[];

            % Handle the available fluorophores
            n_fluorophores=app.par.smis_par.n_fluorophores;
            Fluorophores=app.par.smis_par.Fluorophores;
            
        
            app.FluorophoreDropDown.ItemsData=1:n_fluorophores ;
            app.FluorophoreDropDown.Items(1:n_fluorophores) = {Fluorophores.name};


            app.par.current_fluorophore=1; % Show the first fluorophore by default
            app.par.current_fluorophore_name=Fluorophores(app.par.current_fluorophore).name;
            % app.PSFDispayLabel.Text=['PSF Display: ', Fluorophores(app.par.current_fluorophore).name];

            % Handle the available fluorescent states for current fluorophore
            fluorescent_states=Fluorophores(app.par.current_fluorophore).Photophysics.fluorescent_states;
            fluorescent_states_names=Fluorophores(app.par.current_fluorophore).Photophysics.state_names(fluorescent_states);
            n_states=numel(fluorescent_states);
            
            app.FluorescentstateDropDown.ItemsData=1:n_states ;
            app.FluorescentstateDropDown.Items(1:n_states) = fluorescent_states_names;

            app.par.current_fluorescent_state=1; % Show the first fluorescent state by default
            app.par.current_fluorescent_state_name=fluorescent_states_names{app.par.current_fluorescent_state}; % Show the first fluorescent state by default
            
            if app.par.smis_par.TwoChannel.state==0
                app.ChannelSwitch.Visible='off';
                app.ChannelSwitchLabel.Visible='off';
            else
                app.ChannelSwitch.Visible='on';
                app.ChannelSwitchLabel.Visible='on';
            end
            app.par.channel=1; % Show the first channel by default

            % Define imaging parameters
            im_par=struct; 
            im_par.filters=app.par.smis_par.TwoChannel;
            im_par.filters.ch1_filter=eval(im_par.filters.em_filter_1);
            im_par.filters.ch2_filter=eval(im_par.filters.em_filter_2);
            im_par.two_channel=app.par.smis_par.TwoChannel.state;
            im_par.obj.na=app.par.smis_par.obj_and_psf.obj_na;
            im_par.raster=app.par.smis_par.raster;
            im_par.two_channel_defocus=app.par.smis_par.TwoChannel.defocus;

            % Additional im_par parameters for 3D
            if app.par.smis_par.simul_3D==1
                im_par.psf_n_zslices=app.par.smis_par.obj_and_psf.psf_n_zslices;
                im_par.depth_of_focus=app.par.smis_par.obj_and_psf.obj_depth_of_focus;
                im_par.sample_zcenter=app.par.smis_par.obj_and_psf.sample_zcenter;
                im_par.nz=app.par.smis_par.image_size.nz;
                im_par.psf_astigmatism_x=app.par.smis_par.obj_and_psf.psf_astigmatism_x;
                im_par.psf_astigmatism_y=app.par.smis_par.obj_and_psf.psf_astigmatism_y;
                im_par.psf_astigmatism_ch1_only=app.par.smis_par.obj_and_psf.psf_ch1_only;
                %handle z_drift to evaluate the z range for psf calculation
                if app.par.smis_par.drift.state==1
                    cz=cumsum(app.par.smis_par.drift.dz); % Cumulative drift in z
                    im_par.drift.z_drift_range=[min(cz),max(cz)];
                else
                    im_par.drift.z_drift_range=[0,0];
                end
            end

            % Calculate all PSF's
            sm_par(1:n_fluorophores)=struct;
            for i=1:n_fluorophores
                sm_par(i).n_fluorescent_states=Fluorophores(i).Photophysics.n_fluorescent_states;
                sm_par(i).spectral_data.em_spectra=Fluorophores(i).Photophysics.em_spectra;
                sm_par(i).spectral_data.n_fluorescent_states=sm_par(i).n_fluorescent_states;
                sm_par(i).fluorophore_name=Fluorophores(i).name;
                sm_par(i).filter_profiles=[];
            end

            %get filter profiles
            for i=1:n_fluorophores
                sm_par(i) = get_sm_filter_profiles(im_par,sm_par(i));
            end

            %get PSF width
            sm_par=get_psf_width(n_fluorophores,sm_par,im_par);

            % get PSFs
            psf_mode='Simple_Gaussian';
            sm_par=get_psf(n_fluorophores, sm_par, im_par, psf_mode, 1, app.par.smis_par.simul_3D);
            if im_par.two_channel==1 % Set psf for 2nd channel
                sm_par=get_psf(n_fluorophores, sm_par, im_par, psf_mode,2, app.par.smis_par.simul_3D);
            end

            % Store the PSFs 
            app.par.sm_par=sm_par;

            % Handle GUI
            if app.par.smis_par.simul_3D==0
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                app.ShowatsamplebottomButton.Visible='off';
                app.ShowatsampletopButton.Visible='off';
                app.ShowatsamplezcenterButton.Visible='off';
                app.Showin3DButton.Visible='off';
                app.DistancefromsamplecenternmEditField.Visible='off';
                app.DistancefromsamplecenternmEditFieldLabel.Visible='off';
                app.Switch.Visible='off';
                app.ChannelSwitchLabel.Visible='off';
                
            else
                app.ZRasterSlider.Visible='on';
                app.ZRasterSliderLabel.Visible='on';
                app.ZRasterSpinner.Visible='on';
                app.ShowatsamplebottomButton.Visible='on';
                app.ShowatsampletopButton.Visible='on';
                app.ShowatsamplezcenterButton.Visible='on';
                app.Showin3DButton.Visible='on';
                app.DistancefromsamplecenternmEditField.Visible='on';
                app.DistancefromsamplecenternmEditFieldLabel.Visible='on';
                app.Switch.Visible='on';
                app.ChannelSwitchLabel.Visible='on';
                
                % # of slices of psf in z
                image_height=im_par.raster*im_par.nz+im_par.drift.z_drift_range(2)-(im_par.drift.z_drift_range(1)<0)*im_par.drift.z_drift_range(1); % in [nm]
                app.par.sample_height=im_par.raster*im_par.nz; % in [nm]
                app.par.n_slices=round(im_par.psf_n_zslices*image_height/(im_par.raster*im_par.nz)); % Scale to take into account extension due to drift                  
                if any(im_par.drift.z_drift_range)
                    nz_with_drift=image_height/im_par.raster;
                    %If nz is even make nslices an odd number
                    if mod(nz_with_drift,2)==0 && mod(app.par.n_slices,2)==0
                        app.par.n_slices=app.par.n_slices+1;
                    end
                    %If nz is odd make nslices an even number
                    if mod(nz_with_drift,2)==1 && mod(app.par.n_slices,2)==1
                        app.par.n_slices=app.par.n_slices+1;
                    end
                end


                %Step between 2 PSF slices [nm]
                app.par.psf_step=image_height/(app.par.n_slices-1);

                app.ZRasterSlider.Limits=[1,app.par.n_slices];
                app.ZRasterSpinner.Limits=[1,app.par.n_slices];
                app.ZRasterSlider.MajorTicks=[1,(1:4)*app.par.n_slices/4];
                
                % Show psf at mid sample height when no drift
                app.par.start_slice=ceil(im_par.psf_n_zslices/2-(im_par.drift.z_drift_range(1)<0)*im_par.drift.z_drift_range(1)/app.par.psf_step);
                app.ZRasterSlider.Value=app.par.start_slice;
                app.ZRasterSpinner.Value=app.ZRasterSlider.Value;
                
            end

            drawPSF(app);
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            delete(app)
        end

        % Value changed function: ZRasterSlider
        function ZRasterSliderValueChanged(app, event)
            value = round(app.ZRasterSlider.Value);
            app.ZRasterSpinner.Value=value;
            app.ZRasterSlider.Value=value;
            drawPSF(app);
        end

        % Value changed function: ZRasterSpinner
        function ZRasterSpinnerValueChanged(app, event)
            value = app.ZRasterSpinner.Value;
            app.ZRasterSlider.Value=value;
            drawPSF(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            delete(app)
        end

        % Value changed function: FluorophoreDropDown
        function FluorophoreDropDownValueChanged(app, event)
            app.par.current_fluorophore = app.FluorophoreDropDown.Value;
            app.par.current_fluorophore_name=app.par.smis_par.Fluorophores(app.par.current_fluorophore).name;
            app.par.current_fluorescent_state = 1; % Reset fluorescent state

            drawPSF(app);
        end

        % Value changed function: FluorescentstateDropDown
        function FluorescentstateDropDownValueChanged(app, event)
            app.par.current_fluorescent_state = app.FluorescentstateDropDown.Value;
            fluorescent_states=app.par.smis_par.Fluorophores(app.par.current_fluorophore).Photophysics.fluorescent_states;
            fluorescent_states_names=app.par.smis_par.Fluorophores(app.par.current_fluorophore).Photophysics.state_names(fluorescent_states);
            app.par.current_fluorescent_state_name=fluorescent_states_names{app.par.current_fluorescent_state};
           
            drawPSF(app);
        end

        % Value changed function: ChannelSwitch
        function ChannelSwitchValueChanged(app, event)
            app.par.channel = eval(app.ChannelSwitch.Value);

            drawPSF(app);
        end

        % Button pushed function: ShowatsamplezcenterButton
        function ShowatsamplezcenterButtonPushed(app, event)
            slice=app.par.start_slice;
            app.ZRasterSpinner.Value=slice;
            app.ZRasterSlider.Value=slice;
            drawPSF(app);
        end

        % Button pushed function: ShowatsamplebottomButton
        function ShowatsamplebottomButtonPushed(app, event)
            slice=round(app.par.start_slice-(app.par.sample_height/2)/app.par.psf_step);
            slice=max([slice,1]);
            app.ZRasterSpinner.Value=slice;
            app.ZRasterSlider.Value=slice;
            drawPSF(app);
        end

        % Button pushed function: ShowatsampletopButton
        function ShowatsampletopButtonPushed(app, event)
            slice=round(app.par.start_slice+(app.par.sample_height/2)/app.par.psf_step);
            slice=min([slice,app.par.n_slices]);
            app.ZRasterSpinner.Value=slice;
            app.ZRasterSlider.Value=slice;
            drawPSF(app);
        end

        % Button pushed function: Showin3DButton
        function Showin3DButtonPushed(app, event)
            app.par.figure_number=show3DPSF(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X [raster]')
            ylabel(app.UIAxes, 'Y [raster]')
            app.PSFDispayLabel = uilabel(app.UIFigure);
            app.PSFDispayLabel.Text = 'PSF Dispay';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ZRasterSliderLabel = uilabel(app.UIFigure);
            app.ZRasterSliderLabel.Text = 'Z [Raster]';
            app.ZRasterSlider = uislider(app.UIFigure);
            app.ZRasterSlider.ValueChangedFcn = createCallbackFcn(app, @ZRasterSliderValueChanged, true);
            app.ZRasterSlider.Value = 1;
            app.ZRasterSpinner = uispinner(app.UIFigure);
            app.ZRasterSpinner.ValueChangedFcn = createCallbackFcn(app, @ZRasterSpinnerValueChanged, true);
            app.ChannelSwitchLabel = uilabel(app.UIFigure);
            app.ChannelSwitchLabel.Text = 'Channel';
            app.ChannelSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChannelSwitch.Items = {'1', '2'};
            app.ChannelSwitch.ValueChangedFcn = createCallbackFcn(app, @ChannelSwitchValueChanged, true);
            app.ChannelSwitch.Value = '1';
            app.FluorophoreDropDownLabel = uilabel(app.UIFigure);
            app.FluorophoreDropDownLabel.Text = 'Fluorophore';
            app.FluorophoreDropDown = uidropdown(app.UIFigure);
            app.FluorophoreDropDown.ValueChangedFcn = createCallbackFcn(app, @FluorophoreDropDownValueChanged, true);
            app.Showin3DButton = uibutton(app.UIFigure, 'push');
            app.Showin3DButton.ButtonPushedFcn = createCallbackFcn(app, @Showin3DButtonPushed, true);
            app.Showin3DButton.Text = 'Show in 3D';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Linear', 'Log'};
            app.Switch.Value = 'Linear';
            app.ShowatsamplebottomButton = uibutton(app.UIFigure, 'push');
            app.ShowatsamplebottomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowatsamplebottomButtonPushed, true);
            app.ShowatsamplebottomButton.Text = 'Show at sample bottom';
            app.ShowatsampletopButton = uibutton(app.UIFigure, 'push');
            app.ShowatsampletopButton.ButtonPushedFcn = createCallbackFcn(app, @ShowatsampletopButtonPushed, true);
            app.ShowatsampletopButton.Text = 'Show at sample top';
            app.ShowatsamplezcenterButton = uibutton(app.UIFigure, 'push');
            app.ShowatsamplezcenterButton.ButtonPushedFcn = createCallbackFcn(app, @ShowatsamplezcenterButtonPushed, true);
            app.ShowatsamplezcenterButton.Text = 'Show at sample z-center';
            app.FluorescentstateDropDownLabel = uilabel(app.UIFigure);
            app.FluorescentstateDropDownLabel.Text = 'Fluorescent state';
            app.FluorescentstateDropDown = uidropdown(app.UIFigure);
            app.FluorescentstateDropDown.ValueChangedFcn = createCallbackFcn(app, @FluorescentstateDropDownValueChanged, true);
            app.DistancefromsamplecenternmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromsamplecenternmEditFieldLabel.Text = 'Distance from sample center [nm]';
            app.DistancefromsamplecenternmEditField = uieditfield(app.UIFigure, 'text');
            app.DistancefromsamplecenternmEditField.Editable = 'off';
            app.DistancefromsamplecenternmEditField.Tooltip = {'Shows the distance of the displayed PSF section to the sample center. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayPSF(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end