classdef DisplayPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImagedisplayparametersLabel  matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        Channel1lowerrangeEditFieldLabel  matlab.ui.control.Label
        Channel1lowerrangeEditField  matlab.ui.control.NumericEditField
        Channel2lowerrangeEditFieldLabel  matlab.ui.control.Label
        Channel2lowerrangeEditField  matlab.ui.control.NumericEditField
        UpperrangeEditFieldLabel     matlab.ui.control.Label
        UpperrangeEditField          matlab.ui.control.NumericEditField
        UpperrangeEditField_2Label   matlab.ui.control.Label
        UpperrangeEditField_2        matlab.ui.control.NumericEditField
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Displaypar=[]; % Initialize the parameters
        TwoChannel=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize Displaypar
            app.Displaypar=mainapp.smis_gui_parameters.ImageDisplay;
            app.TwoChannel=mainapp.smis_gui_parameters.TwoChannel.state;
            
            % Update UI with input values
            app.Channel1lowerrangeEditField.Value=app.Displaypar.disp_lim_ch1(1);
            app.UpperrangeEditField.Value=app.Displaypar.disp_lim_ch1(2);
            app.Channel2lowerrangeEditField.Value=app.Displaypar.disp_lim_ch2(1);
            app.UpperrangeEditField_2.Value=app.Displaypar.disp_lim_ch2(2);
            
            if app.TwoChannel==1
                app.Channel2lowerrangeEditField.Visible='on';
                app.Channel2lowerrangeEditFieldLabel.Visible='on';
                app.UpperrangeEditField_2.Visible='on';
                app.UpperrangeEditField_2Label.Visible='on';
            else
                app.Channel2lowerrangeEditField.Visible='off';
                app.Channel2lowerrangeEditFieldLabel.Visible='off';
                app.UpperrangeEditField_2.Visible='off';
                app.UpperrangeEditField_2Label.Visible='off';
            end
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateDisplayPar(app.CallingApp, app.Displaypar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: Channel1lowerrangeEditField
        function Channel1lowerrangeEditFieldValueChanged(app, event)
            value = app.Channel1lowerrangeEditField.Value;
            
            if value<app.UpperrangeEditField.Value
                app.Displaypar.disp_lim_ch1(1)=value;
            else
                warndlg('Lower range must be lower than upper range','Warning');
                app.Channel1lowerrangeEditField.Value=event.PreviousValue;
            end
        end

        % Value changed function: Channel2lowerrangeEditField
        function Channel2lowerrangeEditFieldValueChanged(app, event)
            value = app.Channel2lowerrangeEditField.Value;
            
            if value<app.UpperrangeEditField_2.Value
                app.Displaypar.disp_lim_ch2(1)=value;
            else
                warndlg('Lower range must be lower than upper range','Warning');
                app.Channel2lowerrangeEditField.Value=event.PreviousValue;
            end
        end

        % Value changed function: UpperrangeEditField
        function UpperrangeEditFieldValueChanged(app, event)
            value = app.UpperrangeEditField.Value;
            
            if value>app.Channel1lowerrangeEditField.Value
                app.Displaypar.disp_lim_ch1(2)=value;
            else
                warndlg('Upper range must be higher than lower range','Warning');
                app.UpperrangeEditField.Value=event.PreviousValue;
            end
        end

        % Value changed function: UpperrangeEditField_2
        function UpperrangeEditField_2ValueChanged(app, event)
            value = app.UpperrangeEditField_2.Value;
            
            if value>app.Channel2lowerrangeEditField.Value
                app.Displaypar.disp_lim_ch2(2)=value;
            else
                warndlg('Upper range must be higher than lower range','Warning');
                app.UpperrangeEditField_2.Value=event.PreviousValue;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagedisplayparametersLabel = uilabel(app.UIFigure);
            app.ImagedisplayparametersLabel.Text = 'Image display parameters';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Channel1lowerrangeEditFieldLabel = uilabel(app.UIFigure);
            app.Channel1lowerrangeEditFieldLabel.Text = 'Channel 1 lower range';
            app.Channel1lowerrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Channel1lowerrangeEditField.Limits = [0 65535];
            app.Channel1lowerrangeEditField.ValueDisplayFormat = '%.0f';
            app.Channel1lowerrangeEditField.ValueChangedFcn = createCallbackFcn(app, @Channel1lowerrangeEditFieldValueChanged, true);
            app.Channel2lowerrangeEditFieldLabel = uilabel(app.UIFigure);
            app.Channel2lowerrangeEditFieldLabel.Text = 'Channel 2 lower range';
            app.Channel2lowerrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Channel2lowerrangeEditField.Limits = [0 65535];
            app.Channel2lowerrangeEditField.ValueDisplayFormat = '%.0f';
            app.Channel2lowerrangeEditField.ValueChangedFcn = createCallbackFcn(app, @Channel2lowerrangeEditFieldValueChanged, true);
            app.UpperrangeEditFieldLabel = uilabel(app.UIFigure);
            app.UpperrangeEditFieldLabel.Text = 'Upper range';
            app.UpperrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperrangeEditField.Limits = [0 65535];
            app.UpperrangeEditField.ValueDisplayFormat = '%.0f';
            app.UpperrangeEditField.ValueChangedFcn = createCallbackFcn(app, @UpperrangeEditFieldValueChanged, true);
            app.UpperrangeEditField_2Label = uilabel(app.UIFigure);
            app.UpperrangeEditField_2Label.Text = 'Upper range';
            app.UpperrangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.UpperrangeEditField_2.Limits = [0 65535];
            app.UpperrangeEditField_2.ValueDisplayFormat = '%.0f';
            app.UpperrangeEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperrangeEditField_2ValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayPar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end