classdef DisplayVirtualSample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CoverslipatnmEditField       matlab.ui.control.EditField
        CoverslipatnmEditFieldLabel  matlab.ui.control.Label
        FocalplaneatnmEditField      matlab.ui.control.EditField
        FocalplaneatnmLabel          matlab.ui.control.Label
        ZRasterSpinner               matlab.ui.control.Spinner
        ZRasterSlider                matlab.ui.control.Slider
        ZRasterSliderLabel           matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        VirtualsampleLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        par=struct; % Parameters
    end
    
    methods (Access = private)
        
        function sample_figure_number=show3D(app)
          
            % Create an additional figure with a 3D plot. It should be a
            % regular Matlab figure, otherwise very slow !
            % Create a figure if necessary
            create_figure=1;
            
            % The following code ensures that the right figure is addressed
            % Indeed, it might be that the figure has been created before, then deleted, and the same figure number has been re-created for another purpose and is still open

            % Take a look at the current figures open
            figHandles = findobj('Type', 'figure'); % Get the array of all figures open
            n_fig=size(figHandles,1);
            for k=1:n_fig
                if strcmp(figHandles(k).Name,'Virtual Sample')==1 % Our figure already exists
                    create_figure=0;
                    sample_figure_number=figHandles(k).Number;
                end
            end

            % create a figure if necessary
            if create_figure==1
                scrsz=get(0,'ScreenSize');
                w_xsize=scrsz(3)*3/4;
                w_ysize=scrsz(4)*3/4;
                sample_figure_number=figure;
                % sample_figure_number=sample_figure.Number;

                colormap(gray);
                set(sample_figure_number,'color','white');
                set(sample_figure_number,'Position',[10 scrsz(4)/10 w_xsize w_ysize])
                set(sample_figure_number,'color','white');
                set(sample_figure_number,'Name','Virtual Sample');
                set(sample_figure_number,'NumberTitle','off');
            else
                figure(sample_figure_number);
                clf;
            end


            sp_ids=app.par.sp_ids;
            n_sp=app.par.n_sp;
            
            %Define the colors
            RGB=zeros(3,max([6,n_sp]));
            RGB(:,1)=[0,1,0]; % Green
            RGB(:,2)=[1,0,0]; % Red
            RGB(:,3)=[0,0,1]; % Blue
            RGB(:,4)=[1,1,0]; % Yellow
            RGB(:,5)=[1,0,1]; % Magenta
            RGB(:,6)=[0,1,1]; % Cyan
            if n_sp>6
                RGB(:,7:end)=rand(3,n_sp-6); % Random color
            end

            %Draw the different sub_patterns
            % hold(app.UIAxes,'on')
            hold on
            sp_ids=sp_ids(sp_ids>0);
            for k=1:numel(sp_ids)
                w=find(app.par.im==sp_ids(k));
                [x,y,z]=ind2sub(size(app.par.im),w);
                % scatter3(app.UIAxes,x,y,z,2,'MarkerEdgeColor',RGB(:,k),'MarkerFaceColor',RGB(:,k))
                scatter3(x,y,z,2,'MarkerEdgeColor',RGB(:,k),'MarkerFaceColor',RGB(:,k))
            end
            hold(app.UIAxes,'off')
   
            % xlim(app.UIAxes,[1,size(app.par.im,1)]);
            % ylim(app.UIAxes,[1,size(app.par.im,2)]);
            % zlim(app.UIAxes,[1,size(app.par.im,3)]);
            xlim([1,size(app.par.im,1)]);
            ylim([1,size(app.par.im,2)]);
            zlim([1,size(app.par.im,3)]);
            % colormap(app.UIAxes,'jet');
            colormap('jet');

            xlabel('X [raster]')
            ylabel('Y [raster]')
            zlabel('Z [raster]')
       
            % Set the colormap to grayscale
            % colormap(app.UIAxes,'jet');

       
            % Adjust the view for better visualization
            % view(app.UIAxes,[45 45]);
            view([45 45]);
            % axis(app.UIAxes,'tight');

        end
        
        function show2D(app)
            
            ClearImage(app)
            
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
                       
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            clims = [app.par.MinDisp, app.par.MaxDisp];
            imagesc(app.UIAxes,app.par.im,clims);
            axis(app.UIAxes,"image");
            
           
        end
        
        function show2Dsection(app)
            ClearImage(app)
            
            % Show 3D sections in mail window
            view(app.UIAxes,2);
            colormap(app.UIAxes,'gray')
            % colormap(app.UIAxes,'jet')

            % Get the chosen layer
            z=round(app.ZRasterSlider.Value);
            section=app.par.im(:,:,z);

            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            clims = [app.par.MinDisp, app.par.MaxDisp];
            imagesc(app.UIAxes,section,clims);

            %Check if layer is at TIRF plane
            if app.par.tirf_mode==1
                app.CoverslipatnmEditField.Value=num2str(app.par.raster/app.par.binning*(app.par.tirf_plane-z));
                if z==round(app.par.tirf_plane)
                    [y,x]=show_tirf_plane(app);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,x,y,'b.', 'MarkerSize', 2);
                    hold(app.UIAxes,'off')
                end
            end

            %Check if layer is at focal plane
            app.FocalplaneatnmEditField.Value=num2str(app.par.raster/app.par.binning*(app.par.focal_plane-z));
            if z==round(app.par.focal_plane)
                [y,x]=show_focal_plane(app);
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x,y,'r.', 'MarkerSize', 2);
                hold(app.UIAxes,'off')
            end

            axis(app.UIAxes,"image");
        end
        
        function ClearImage(app)
            MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end


        function [y,x]=show_focal_plane(app)
            s=app.par.s;
            r0 = 5;

            % Create a mask for the peripheral zone
            peripheralMask = false(s(1), s(2));

            % Set the peripheral zone to true
            peripheralMask(1:r0, :) = true; % Top r0 rows
            peripheralMask(end-r0+1:end, :) = true; % Bottom r0 rows
            peripheralMask(:, 1:r0) = true; % Left r0 columns
            peripheralMask(:, end-r0+1:end) = true; % Right r0 columns

            w=find(peripheralMask==true);
            [y,x]=ind2sub(size(peripheralMask),w);
        end

            function [y,x]=show_tirf_plane(app)
            s=app.par.s;
            borderWidth = 5;

            % Define the center of the ellipse
            centerX = (s(1) + 1) / 2;
            centerY = (s(2) + 1) / 2;

            % Define the semi-major and semi-minor axes of the ellipse
            semiMajorAxis = (s(1) / 2) - borderWidth;
            semiMinorAxis = (s(2) / 2) - borderWidth;

            % Create grid of coordinates
            [X, Y] = meshgrid(1:s(1), 1:s(2));

            % Calculate the elliptical boundary condition
            ellipseCondition = ((X - centerX) / semiMajorAxis) .^ 2 + ((Y - centerY) / semiMinorAxis) .^ 2;
            
            w=find(ellipseCondition>1);
            [y,x]=ind2sub(size(ellipseCondition),w);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, CurrentFluo)
            % Store main app in property for CloseRequestFcn to use
            smis_par=mainapp.smis_gui_parameters;
            app.CallingApp = mainapp;
            app.par.im=smis_par.in_images{CurrentFluo};
            app.par.MinDisp=min(smis_par.pattern_ids(CurrentFluo).sp_ids);
            app.par.MaxDisp=max(smis_par.pattern_ids(CurrentFluo).sp_ids);
            app.par.raster=smis_par.raster;
            app.par.binning=smis_par.binning;

            app.par.fluo_name=smis_par.Fluorophores(CurrentFluo).name;
            
            app.par.Title = ['Virtual sample for fluorophore: ', app.par.fluo_name];
            
            app.VirtualsampleLabel.Text=app.par.Title;
            app.par.figure_number=[];
            
            if smis_par.simul_3D==0
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                app.CoverslipatnmEditField.Visible='off';
                app.CoverslipatnmEditFieldLabel.Visible='off';
                app.FocalplaneatnmEditField.Visible='off';
                app.FocalplaneatnmLabel.Visible='off';
                show2D(app)
                
            else
                app.ZRasterSlider.Visible='on';
                app.ZRasterSliderLabel.Visible='on';
                app.ZRasterSpinner.Visible='on';
                app.FocalplaneatnmEditField.Visible='on';
                app.FocalplaneatnmLabel.Visible='on';

                app.par.s=size(app.par.im);
                s=app.par.s(3);

                app.ZRasterSlider.Limits=[1,s];
                app.ZRasterSpinner.Limits=[1,s];
                app.ZRasterSlider.MajorTicks=[1,(1:4)*s/4];
                app.ZRasterSlider.Value=round(s/2+1);
                app.ZRasterSpinner.Value=app.ZRasterSlider.Value;
                
                app.par.sp_ids=smis_par.pattern_ids(CurrentFluo).sp_ids;
                app.par.n_sp=smis_par.pattern_ids(CurrentFluo).n_sp;

                % Get the focal plane [raster, over full resolution sample]
                app.par.focal_plane=-smis_par.obj_and_psf.sample_zcenter/(smis_par.raster/smis_par.binning)+s/2+1;

                % Check if we are in TIRF/HILO mode and get the TIRF/HILO
                % plane (where the virtual coverslip is put)
                app.par.tirf_mode=0;
                for k=1:smis_par.n_lasers
                    if smis_par.lasers(k).tirf==1
                        app.par.tirf_mode=1;
                        app.par.tirf_plane=smis_par.obj_and_psf.sample_z_coverslip/(smis_par.raster/smis_par.binning)+1; % [raster, over full resolution sample]
                    end
                end
                if app.par.tirf_mode==1
                    app.CoverslipatnmEditField.Visible='on';
                    app.CoverslipatnmEditFieldLabel.Visible='on';
                else
                    app.CoverslipatnmEditField.Visible='off';
                    app.CoverslipatnmEditFieldLabel.Visible='off';
                end
   
                % Show the sample in 3D
                app.par.figure_number=show3D(app);
                
                % Show the sample in 2D sections
                show2Dsection(app);
                
                % Put the figure in the front
                figure(app.par.figure_number)
                
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            delete(app)
        end

        % Value changed function: ZRasterSlider
        function ZRasterSliderValueChanged(app, event)
            value = app.ZRasterSlider.Value;
            app.ZRasterSpinner.Value=round(value);
            show2Dsection(app);
        end

        % Value changed function: ZRasterSpinner
        function ZRasterSpinnerValueChanged(app, event)
            value = app.ZRasterSpinner.Value;
            app.ZRasterSlider.Value=value;
            show2Dsection(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X [raster]')
            ylabel(app.UIAxes, 'Y [raster]')
            app.VirtualsampleLabel = uilabel(app.UIFigure);
            app.VirtualsampleLabel.Text = 'Virtual sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ZRasterSliderLabel = uilabel(app.UIFigure);
            app.ZRasterSliderLabel.Text = 'Z [Raster]';
            app.ZRasterSlider = uislider(app.UIFigure);
            app.ZRasterSlider.ValueChangedFcn = createCallbackFcn(app, @ZRasterSliderValueChanged, true);
            app.ZRasterSlider.Value = 1;
            app.ZRasterSpinner = uispinner(app.UIFigure);
            app.ZRasterSpinner.ValueChangedFcn = createCallbackFcn(app, @ZRasterSpinnerValueChanged, true);
            app.FocalplaneatnmLabel = uilabel(app.UIFigure);
            app.FocalplaneatnmLabel.Text = 'Focal plane at [nm]:';
            app.FocalplaneatnmEditField = uieditfield(app.UIFigure, 'text');
            app.FocalplaneatnmEditField.Editable = 'off';
            app.FocalplaneatnmEditField.Tooltip = {'Shows the distance of the displayed section to the focal plane. The section in perfect focus is at 0 nm and is highlighted with a red square surrounding the displayed image '};
            app.CoverslipatnmEditFieldLabel = uilabel(app.UIFigure);
            app.CoverslipatnmEditFieldLabel.Text = 'Coverslip at [nm]:';
            app.CoverslipatnmEditField = uieditfield(app.UIFigure, 'text');
            app.CoverslipatnmEditField.Editable = 'off';
            app.CoverslipatnmEditField.Tooltip = {'In TIRF mode, shows the distance of the displayed section to the coverslip, where TIRF is maximum. The section at the coverslip is highlighted with a blue ellipse surrounding the displayed image '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayVirtualSample(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end