classdef Draw2DCrossingLines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        nmEditField_9                   matlab.ui.control.NumericEditField
        nmEditField_9Label              matlab.ui.control.Label
        nmEditField_8                   matlab.ui.control.NumericEditField
        nmEditField_8Label              matlab.ui.control.Label
        VerticallinethicknesspixelEditField  matlab.ui.control.NumericEditField
        VerticallinethicknesspixelEditFieldLabel  matlab.ui.control.Label
        HorizontallinethicknesspixelEditField  matlab.ui.control.NumericEditField
        HorizontallinethicknesspixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeYumEditField           matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel      matlab.ui.control.Label
        ImagesizeXumEditField           matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel      matlab.ui.control.Label
        nmEditField_7                   matlab.ui.control.NumericEditField
        nmEditField_7Label              matlab.ui.control.Label
        nmEditField_6                   matlab.ui.control.NumericEditField
        nmEditField_6Label              matlab.ui.control.Label
        IncluderandomlyorientedlinesCheckBox  matlab.ui.control.CheckBox
        VerticallinespacingpixelEditField  matlab.ui.control.NumericEditField
        VerticallinespacingpixelEditFieldLabel  matlab.ui.control.Label
        SafetyborderpixelEditField      matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_5                   matlab.ui.control.NumericEditField
        nmEditField_5Label              matlab.ui.control.Label
        nmEditField_3                   matlab.ui.control.NumericEditField
        nmEditField_3Label              matlab.ui.control.Label
        VirtualSampleDimensionsLabel    matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        RandomlyorientedlinethicknesspixelEditField  matlab.ui.control.NumericEditField
        RandomlyorientedlinethicknesspixelEditFieldLabel  matlab.ui.control.Label
        NumberofrandomlyorientedlinesEditField  matlab.ui.control.NumericEditField
        NumberofrandomlyorientedlinesEditFieldLabel  matlab.ui.control.Label
        DoneCheckBox                    matlab.ui.control.CheckBox
        CreatesampleButton              matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        SavesampleButton                matlab.ui.control.Button
        LoadexistingsampleButton        matlab.ui.control.Button
        HorizontallinespacingpixelEditField  matlab.ui.control.NumericEditField
        HorizontallinespacingpixelEditFieldLabel  matlab.ui.control.Label
        NumberofverticallinesEditField  matlab.ui.control.NumericEditField
        NumberofverticallinesEditFieldLabel  matlab.ui.control.Label
        NumberofhorizontallinesEditField  matlab.ui.control.NumericEditField
        NumberofhorizontallinesEditFieldLabel  matlab.ui.control.Label
        ImagesizeYpixelEditField        matlab.ui.control.NumericEditField
        ImagesizeYpixelEditFieldLabel   matlab.ui.control.Label
        ImagesizeXpixelEditField        matlab.ui.control.NumericEditField
        ImagesizeXpixelEditFieldLabel   matlab.ui.control.Label
        Draw2DCrossingLinesLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
    end

    methods (Access = private)

        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]
            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImagesizeXpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImagesizeYpixelEditField.Value;

          
            app.nmEditField_3.Value=scale*app.HorizontallinespacingpixelEditField.Value;
            app.nmEditField_7.Value=scale*app.VerticallinespacingpixelEditField.Value;

            app.nmEditField_8.Value=scale*app.HorizontallinethicknesspixelEditField.Value;
            app.nmEditField_9.Value=scale*app.VerticallinethicknesspixelEditField.Value;
            
            app.nmEditField_5.Value=scale*app.RandomlyorientedlinethicknesspixelEditField.Value;
            
            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;
            app.par.virtual_sample_name='2D_CrossingLines';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];

            app.par.x_dim=app.ImagesizeXpixelEditField.Value;
            app.par.y_dim=app.ImagesizeYpixelEditField.Value;

            app.par.h_number_of_lines=app.NumberofhorizontallinesEditField.Value;
            app.par.h_line_space=app.HorizontallinespacingpixelEditField.Value;
            app.par.h_line_thick=app.HorizontallinethicknesspixelEditField.Value;

            app.par.v_number_of_lines=app.NumberofverticallinesEditField.Value;
            app.par.v_line_space=app.VerticallinespacingpixelEditField.Value;
            app.par.v_line_thick=app.VerticallinethicknesspixelEditField.Value;

            app.par.include_tilted_lines=app.IncluderandomlyorientedlinesCheckBox.Value;
            app.par.t_number_of_lines=app.NumberofrandomlyorientedlinesEditField.Value;
            app.par.t_line_thick=app.RandomlyorientedlinethicknesspixelEditField.Value;

            app.par.border=app.SafetyborderpixelEditField.Value;

            app.par.simul_3D=0;
            app.par.z_dim=[];
            app.par.pattern_depth=[];

            app.NumberofrandomlyorientedlinesEditField.Visible="off";
            app.NumberofrandomlyorientedlinesEditFieldLabel.Visible="off";
            app.RandomlyorientedlinethicknesspixelEditField.Visible="off";
            app.RandomlyorientedlinethicknesspixelEditFieldLabel.Visible="off";
            app.nmEditField_5.Visible="off";
            app.nmEditField_5Label.Visible="off";

            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2DLinePattern(app.par);
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: ImagesizeYpixelEditField
        function ImagesizeYpixelEditFieldValueChanged(app, event)
            value = app.ImagesizeYpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofhorizontallinesEditField
        function NumberofhorizontallinesEditFieldValueChanged(app, event)
            value = app.NumberofhorizontallinesEditField.Value;
            app.par.h_number_of_lines=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofverticallinesEditField
        function NumberofverticallinesEditFieldValueChanged(app, event)
            value = app.NumberofverticallinesEditField.Value;
            app.par.v_number_of_lines=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC>
                par=app.par; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_CrossingLines');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving image ...');
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    imwrite(uint8(app.MySample),tiffile,'tif');
                    disp('Done !');
                    disp('Saving parameters ...');
                    matfile=strrep(tiffile,'.tif','.mat');
                    save(matfile,'MySample','par');
                    disp('Done !');
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D_CrossingLines')==1
                    app.par=par; %#ok<ADPROPLC>

                    app.ImagesizeXpixelEditField.Value=app.par.x_dim;
                    app.ImagesizeYpixelEditField.Value=app.par.y_dim;
                    
                    app.HorizontallinespacingpixelEditField.Value=app.par.h_line_space;
                    app.NumberofhorizontallinesEditField.Value=app.par.h_number_of_lines;
                    app.HorizontallinethicknesspixelEditField.Value=app.par.h_line_thick;

                    app.NumberofverticallinesEditField.Value=app.par.v_number_of_lines;
                    app.VerticallinespacingpixelEditField.Value=app.par.v_line_space;
                    app.VerticallinethicknesspixelEditField.Value=app.par.v_line_thick;

                    app.IncluderandomlyorientedlinesCheckBox.Value=app.par.include_tilted_lines;
                    app.NumberofrandomlyorientedlinesEditField.Value=app.par.t_number_of_lines;
                    app.RandomlyorientedlinethicknesspixelEditField.Value=app.par.t_line_thick;

                    app.SafetyborderpixelEditField.Value=app.par.border;
                    
                    if app.par.include_tilted_lines==1
                        app.NumberofrandomlyorientedlinesEditField.Visible="on";
                        app.NumberofrandomlyorientedlinesEditFieldLabel.Visible="on";
                        app.RandomlyorientedlinethicknesspixelEditField.Visible="on";
                        app.RandomlyorientedlinethicknesspixelEditFieldLabel.Visible="on";
                        app.nmEditField_5.Visible="on";
                        app.nmEditField_5Label.Visible="on";
                    else
                        app.NumberofrandomlyorientedlinesEditField.Visible="off";
                        app.NumberofrandomlyorientedlinesEditFieldLabel.Visible="off";
                        app.RandomlyorientedlinethicknesspixelEditField.Visible="off";
                        app.RandomlyorientedlinethicknesspixelEditFieldLabel.Visible="off";
                        app.nmEditField_5.Visible="off";
                        app.nmEditField_5Label.Visible="off";
                    end

                    update_size_evaluation(app)

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    title('Virtual NPCs')
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D_NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: HorizontallinespacingpixelEditField
        function HorizontallinespacingpixelEditFieldValueChanged(app, event)
            value = app.HorizontallinespacingpixelEditField.Value;
            app.par.h_line_space=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofrandomlyorientedlinesEditField
        function NumberofrandomlyorientedlinesEditFieldValueChanged(app, event)
            value = app.NumberofrandomlyorientedlinesEditField.Value;
            app.par.t_number_of_lines=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: 
        % RandomlyorientedlinethicknesspixelEditField
        function RandomlyorientedlinethicknesspixelEditFieldValueChanged(app, event)
            value = app.RandomlyorientedlinethicknesspixelEditField.Value;
            app.par.t_line_thick=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: ImagesizeXpixelEditField
        function ImagesizeXpixelEditFieldValueChanged(app, event)
            value = app.ImagesizeXpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: IncluderandomlyorientedlinesCheckBox
        function IncluderandomlyorientedlinesCheckBoxValueChanged(app, event)
            value = app.IncluderandomlyorientedlinesCheckBox.Value;
            app.par.include_tilted_lines=value;
            if app.par.include_tilted_lines==1
                app.NumberofrandomlyorientedlinesEditField.Visible="on";
                app.NumberofrandomlyorientedlinesEditFieldLabel.Visible="on";
                app.RandomlyorientedlinethicknesspixelEditField.Visible="on";
                app.RandomlyorientedlinethicknesspixelEditFieldLabel.Visible="on";
                app.nmEditField_5.Visible="on";
                app.nmEditField_5Label.Visible="on";
            else
                app.NumberofrandomlyorientedlinesEditField.Visible="off";
                app.NumberofrandomlyorientedlinesEditFieldLabel.Visible="off";
                app.RandomlyorientedlinethicknesspixelEditField.Visible="off";
                app.RandomlyorientedlinethicknesspixelEditFieldLabel.Visible="off";
                app.nmEditField_5.Visible="off";
                app.nmEditField_5Label.Visible="off";
            end

        end

        % Value changed function: VerticallinespacingpixelEditField
        function VerticallinespacingpixelEditFieldValueChanged(app, event)
            value = app.VerticallinespacingpixelEditField.Value;
            app.par.v_line_space=value;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.border=value;
            update_size_evaluation(app)
        end

        % Value changed function: HorizontallinethicknesspixelEditField
        function HorizontallinethicknesspixelEditFieldValueChanged(app, event)
            value = app.HorizontallinethicknesspixelEditField.Value;
            app.par.h_line_thick=value;
            update_size_evaluation(app)
        end

        % Value changed function: VerticallinethicknesspixelEditField
        function VerticallinethicknesspixelEditFieldValueChanged(app, event)
            value = app.VerticallinethicknesspixelEditField.Value;
            app.par.v_line_thick=value;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DCrossingLinesLabel = uilabel(app.UIFigure);
            app.Draw2DCrossingLinesLabel.Text = 'Draw 2D Crossing Lines';
            app.ImagesizeXpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXpixelEditFieldLabel.Text = 'Image size X [pixel]';
            app.ImagesizeXpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXpixelEditField.Limits = [16 Inf];
            app.ImagesizeXpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImagesizeXpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeXpixelEditFieldValueChanged, true);
            app.ImagesizeXpixelEditField.Value = 512;
            app.ImagesizeYpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYpixelEditFieldLabel.Text = 'Image size Y [pixel]';
            app.ImagesizeYpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYpixelEditField.Limits = [16 Inf];
            app.ImagesizeYpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImagesizeYpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeYpixelEditFieldValueChanged, true);
            app.ImagesizeYpixelEditField.Value = 512;
            app.NumberofhorizontallinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofhorizontallinesEditFieldLabel.Text = 'Number of horizontal lines';
            app.NumberofhorizontallinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofhorizontallinesEditField.Limits = [0 Inf];
            app.NumberofhorizontallinesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofhorizontallinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofhorizontallinesEditFieldValueChanged, true);
            app.NumberofhorizontallinesEditField.Value = 20;
            app.NumberofverticallinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofverticallinesEditFieldLabel.Text = 'Number of vertical lines';
            app.NumberofverticallinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofverticallinesEditField.Limits = [0 Inf];
            app.NumberofverticallinesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofverticallinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofverticallinesEditFieldValueChanged, true);
            app.HorizontallinespacingpixelEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontallinespacingpixelEditFieldLabel.Text = 'Horizontal line spacing [pixel]';
            app.HorizontallinespacingpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontallinespacingpixelEditField.Limits = [0 Inf];
            app.HorizontallinespacingpixelEditField.ValueDisplayFormat = '%.0f';
            app.HorizontallinespacingpixelEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontallinespacingpixelEditFieldValueChanged, true);
            app.HorizontallinespacingpixelEditField.Value = 5;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.NumberofrandomlyorientedlinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofrandomlyorientedlinesEditFieldLabel.Text = 'Number of randomly oriented lines';
            app.NumberofrandomlyorientedlinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofrandomlyorientedlinesEditField.Limits = [0 Inf];
            app.NumberofrandomlyorientedlinesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofrandomlyorientedlinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofrandomlyorientedlinesEditFieldValueChanged, true);
            app.NumberofrandomlyorientedlinesEditField.Value = 25;
            app.RandomlyorientedlinethicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.RandomlyorientedlinethicknesspixelEditFieldLabel.Text = 'Randomly oriented line thickness [pixel]';
            app.RandomlyorientedlinethicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomlyorientedlinethicknesspixelEditField.Limits = [1 Inf];
            app.RandomlyorientedlinethicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.RandomlyorientedlinethicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @RandomlyorientedlinethicknesspixelEditFieldValueChanged, true);
            app.RandomlyorientedlinethicknesspixelEditField.Value = 5;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            app.nmEditField_5Label = uilabel(app.UIFigure);
            app.nmEditField_5Label.Text = '[nm]';
            app.nmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_5.Limits = [0 Inf];
            app.nmEditField_5.ValueDisplayFormat = '%.0f';
            app.nmEditField_5.Editable = 'off';
            app.nmEditField_5.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.VerticallinespacingpixelEditFieldLabel = uilabel(app.UIFigure);
            app.VerticallinespacingpixelEditFieldLabel.Text = 'Vertical line spacing [pixel]';
            app.VerticallinespacingpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticallinespacingpixelEditField.Limits = [0 Inf];
            app.VerticallinespacingpixelEditField.ValueDisplayFormat = '%.0f';
            app.VerticallinespacingpixelEditField.ValueChangedFcn = createCallbackFcn(app, @VerticallinespacingpixelEditFieldValueChanged, true);
            app.VerticallinespacingpixelEditField.Value = 5;
            app.IncluderandomlyorientedlinesCheckBox = uicheckbox(app.UIFigure);
            app.IncluderandomlyorientedlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncluderandomlyorientedlinesCheckBoxValueChanged, true);
            app.IncluderandomlyorientedlinesCheckBox.Text = 'Include randomly oriented lines';
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.nmEditField_7Label = uilabel(app.UIFigure);
            app.nmEditField_7Label.Text = '[nm]';
            app.nmEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_7.Limits = [0 Inf];
            app.nmEditField_7.ValueDisplayFormat = '%.0f';
            app.nmEditField_7.Editable = 'off';
            app.nmEditField_7.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.HorizontallinethicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontallinethicknesspixelEditFieldLabel.Text = 'Horizontal line thickness [pixel]';
            app.HorizontallinethicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontallinethicknesspixelEditField.Limits = [1 Inf];
            app.HorizontallinethicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.HorizontallinethicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontallinethicknesspixelEditFieldValueChanged, true);
            app.HorizontallinethicknesspixelEditField.Value = 2;
            app.VerticallinethicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.VerticallinethicknesspixelEditFieldLabel.Text = 'Vertical line thickness [pixel]';
            app.VerticallinethicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticallinethicknesspixelEditField.Limits = [1 Inf];
            app.VerticallinethicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.VerticallinethicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @VerticallinethicknesspixelEditFieldValueChanged, true);
            app.VerticallinethicknesspixelEditField.Value = 2;
            app.nmEditField_8Label = uilabel(app.UIFigure);
            app.nmEditField_8Label.Text = '[nm]';
            app.nmEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_8.Limits = [0 Inf];
            app.nmEditField_8.ValueDisplayFormat = '%.0f';
            app.nmEditField_8.Editable = 'off';
            app.nmEditField_8.Value = 2;
            app.nmEditField_9Label = uilabel(app.UIFigure);
            app.nmEditField_9Label.Text = '[nm]';
            app.nmEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_9.Limits = [0 Inf];
            app.nmEditField_9.ValueDisplayFormat = '%.0f';
            app.nmEditField_9.Editable = 'off';
            app.nmEditField_9.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DCrossingLines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end