classdef Draw2DDistortedNPCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResolutionnmEditField          matlab.ui.control.NumericEditField
        ResolutionnmEditFieldLabel     matlab.ui.control.Label
        Cir4MicscsvfileEditField       matlab.ui.control.EditField
        Cir4MicscsvfileEditFieldLabel  matlab.ui.control.Label
        LoadCir4MicscsvfileButton      matlab.ui.control.Button
        nmEditField_5                  matlab.ui.control.NumericEditField
        nmEditField_5Label             matlab.ui.control.Label
        nmEditField_4                  matlab.ui.control.NumericEditField
        nmEditField_4Label             matlab.ui.control.Label
        umEditField_2                  matlab.ui.control.NumericEditField
        umEditField_2Label             matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        umEditField                    matlab.ui.control.NumericEditField
        umEditFieldLabel               matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        SeparationbetweenNPCspixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenNPCspixelEditFieldLabel  matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        NumberofNPCsEditField          matlab.ui.control.NumericEditField
        NumberofNPCsEditFieldLabel     matlab.ui.control.Label
        YimagedimensionpixelEditField  matlab.ui.control.NumericEditField
        YimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        XimagedimensionpixelEditField  matlab.ui.control.NumericEditField
        XimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        Draw2DDistortedNPCsLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [2nm]
            app.umEditField.Value=1e-3*scale*app.XimagedimensionpixelEditField.Value;
            app.umEditField_2.Value=1e-3*scale*app.YimagedimensionpixelEditField.Value;
            app.nmEditField_4.Value=scale*app.SeparationbetweenNPCspixelEditField.Value;
            app.nmEditField_5.Value=scale*app.SafetyborderpixelEditField.Value;
            app.ResolutionnmEditField.Value=app.par.raster/app.par.binning;
        end
        
        function read_cir4mics(app)
            
            % Read the CSV file
            app.par.data = readtable(app.par.csvfile);
            % Check that the right column names exist
            columnNames = app.par.data.Properties.VariableNames;
            hasX = any(strcmpi(columnNames, 'x'));
            hasY = any(strcmpi(columnNames, 'y'));
            % hasZ = any(strcmpi(columnNames, 'z'));
            hasids = any(strcmpi(columnNames, 'particle'));
            read_ok=1;
            if ~hasX || ~hasY || ~hasids
                warningMessage = 'Warning: The CSV file does not contain the required columns.';
                read_ok=0;
            end
            if read_ok==0
                app.par.data=[];
                MyDlg=warndlg(warningMessage, 'Column Check Warning');
                waitfor(MyDlg)
                app.NumberofNPCsEditField.Value=0;
                app.NumberofNPCsEditField.Enable="off";
                app.NumberofNPCsEditFieldLabel.Enable="off";
                app.Cir4MicscsvfileEditField.Value=[];
            else
                app.par.used_data=app.par.data;
                app.Cir4MicscsvfileEditField.Value=app.par.csvfile;
                app.NumberofNPCsEditField.Value=max(app.par.data.particle)+1;
                app.NumberofNPCsEditField.Enable="on";
                app.NumberofNPCsEditFieldLabel.Enable="on";
            end           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;
            
            if app.par.qPALM==1
                app.Draw2DDistortedNPCsLabel.Text='Draw 2D Distorted NPCs (qPALM)';
            else
                app.Draw2DDistortedNPCsLabel.Text='Draw 2D Distorted NPCs';
            end

            app.par.virtual_sample_name='2D_Distorted_NPCs';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.XimagedimensionpixelEditField.Value;
            app.par.y_dim=app.YimagedimensionpixelEditField.Value;
            app.par.N=app.NumberofNPCsEditField.Value;
            app.par.npc_sep=app.SeparationbetweenNPCspixelEditField.Value;
            app.par.border=app.SafetyborderpixelEditField.Value;
            app.par.binning=mainapp.binning;
            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2D_distorded_NPCs(app.par);
            figure(1)
            clf
            set(gcf,'Color','w')
            imagesc(app.MySample);
            axis image
            colormap('gray')
            xlabel('X [pixel]')
            ylabel('Y [pixel]')
            title('Virtual distorted NPCs')
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: XimagedimensionpixelEditField
        function XimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.XimagedimensionpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: YimagedimensionpixelEditField
        function YimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.YimagedimensionpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofNPCsEditField
        function NumberofNPCsEditFieldValueChanged(app, event)
            value = app.NumberofNPCsEditField.Value;
            w=find((app.par.data.particle+1)<=value);
            app.par.used_data = app.par.data(w, :);
            app.par.N=app.NumberofNPCsEditField.Value;
            app.DoneCheckBox.Value=false;
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC> 
                par=app.par; %#ok<ADPROPLC> 
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_Distorted_NPCs');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    if max(app.MySample(:))<=65535
                        % Write the .tif file
                        disp('Saving .tif image ...');
                        imwrite(uint16(app.MySample),tiffile,'tif');
                        disp('Saving .m matlab kernel...');
                        % Write the .m file
                        matfile=strrep(tiffile,'.tif','.mat');
                        save(matfile,'MySample','par');
                        % Write the .csv file
                        disp('Saving .csv coordinate file ...');
                        csvfile=strrep(tiffile,'.tif','.csv');
                        
                        % Find the linear indices of non-zero elements
                        nonZeroIndices = find(app.MySample ~= 0);
                        % Convert linear indices to subscripts (X and Y coordinates)
                        [Y, X] = ind2sub(size(app.MySample), nonZeroIndices);
                        % Extract the values of the non-zero elements
                        ids = app.MySample(nonZeroIndices);
                        
                        %Convert to nm
                        R = app.par.raster/app.par.binning; % Resolution of the produced .tif image
                        X_nm = X * R;
                        Y_nm = Y * R;
                        T = table(X_nm, Y_nm, ids, 'VariableNames', {'X', 'Y', 'ids'});
                        % Write the table to a CSV file
                        writetable(T, csvfile);
                        disp('Done !');
                    else % Image cannot be converted to 16 bits
                        mydlg=warndlg('Virtual sample has pixel values exceeding 16 bits (65535). Cannot save it ! ');
                        waitfor(mydlg);
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D_Distorted_NPCs')==1
                    app.par=par; %#ok<ADPROPLC>

                    app.XimagedimensionpixelEditField.Value=app.par.x_dim;
                    app.YimagedimensionpixelEditField.Value=app.par.y_dim;
                    
                    app.SeparationbetweenNPCspixelEditField.Value=app.par.npc_sep;
                    app.SafetyborderpixelEditField.Value=app.par.border;
                    app.Cir4MicscsvfileEditField.Value=app.par.csvfile;
                    app.NumberofNPCsEditField.Value=app.par.N;
                    app.NumberofNPCsEditField.Enable="on";
                    app.NumberofNPCsEditFieldLabel.Enable="on";
                    update_size_evaluation(app)

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    title('Virtual NPCs')
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D_NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: SeparationbetweenNPCspixelEditField
        function SeparationbetweenNPCspixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenNPCspixelEditField.Value;
            app.par.npc_sep=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.border=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Button pushed function: LoadCir4MicscsvfileButton
        function LoadCir4MicscsvfileButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            app.DoneCheckBox.Value=false;
            [file,path] = uigetfile({'*.csv'},'Select a Cir4Mics .csv file: ');
            if isequal(file,0)
                disp('User selected Cancel');
                app.Cir4MicscsvfileEditField.Value=[];
                app.par.csvfile=[];
                app.NumberofNPCsEditField.Enable="off";
            else
                app.par.csvfile=fullfile(path,file);
                read_cir4mics(app);               
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: Cir4MicscsvfileEditField
        function Cir4MicscsvfileEditFieldValueChanged(app, event)
            file = app.Cir4MicscsvfileEditField.Value;
            app.DoneCheckBox.Value=false;
            if isequal(file,0)
                disp('File does not exist !');
                app.Cir4MicscsvfileEditField.Value=[];
                app.par.csvfile=[];
                app.NumberofNPCsEditField.Enable="off";
            else
                app.par.csvfile=file;
                read_cir4mics(app);               
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DDistortedNPCsLabel = uilabel(app.UIFigure);
            app.Draw2DDistortedNPCsLabel.Text = 'Draw 2D Distorted NPCs';
            app.XimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.XimagedimensionpixelEditFieldLabel.Text = 'X image dimension [pixel]';
            app.XimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.XimagedimensionpixelEditField.Limits = [450 Inf];
            app.XimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.XimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @XimagedimensionpixelEditFieldValueChanged, true);
            app.XimagedimensionpixelEditField.Value = 512;
            app.YimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.YimagedimensionpixelEditFieldLabel.Text = 'Y image dimension [pixel]';
            app.YimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.YimagedimensionpixelEditField.Limits = [465 Inf];
            app.YimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.YimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @YimagedimensionpixelEditFieldValueChanged, true);
            app.YimagedimensionpixelEditField.Value = 512;
            app.NumberofNPCsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNPCsEditFieldLabel.Enable = 'off';
            app.NumberofNPCsEditFieldLabel.Text = 'Number of NPCs';
            app.NumberofNPCsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNPCsEditField.Limits = [0 Inf];
            app.NumberofNPCsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofNPCsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNPCsEditFieldValueChanged, true);
            app.NumberofNPCsEditField.Enable = 'off';
            app.NumberofNPCsEditField.Tooltip = {'Numer of NPCs to be generated. The maximum value is the number of NPCs  contained in the loaded .csv file.'};
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.SeparationbetweenNPCspixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenNPCspixelEditFieldLabel.Text = 'Separation between NPCs [pixel]';
            app.SeparationbetweenNPCspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenNPCspixelEditField.Limits = [0 Inf];
            app.SeparationbetweenNPCspixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenNPCspixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenNPCspixelEditFieldValueChanged, true);
            app.SeparationbetweenNPCspixelEditField.Value = 10;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.umEditFieldLabel = uilabel(app.UIFigure);
            app.umEditFieldLabel.Text = '[um]';
            app.umEditField = uieditfield(app.UIFigure, 'numeric');
            app.umEditField.Limits = [0 Inf];
            app.umEditField.Editable = 'off';
            app.umEditField.Value = 2;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.umEditField_2Label = uilabel(app.UIFigure);
            app.umEditField_2Label.Text = '[um]';
            app.umEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.umEditField_2.Limits = [0 Inf];
            app.umEditField_2.Editable = 'off';
            app.umEditField_2.Value = 2;
            app.nmEditField_4Label = uilabel(app.UIFigure);
            app.nmEditField_4Label.Text = '[nm]';
            app.nmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_4.Limits = [0 Inf];
            app.nmEditField_4.ValueDisplayFormat = '%.0f';
            app.nmEditField_4.Editable = 'off';
            app.nmEditField_4.Value = 2;
            app.nmEditField_5Label = uilabel(app.UIFigure);
            app.nmEditField_5Label.Text = '[nm]';
            app.nmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_5.Limits = [0 Inf];
            app.nmEditField_5.ValueDisplayFormat = '%.0f';
            app.nmEditField_5.Editable = 'off';
            app.nmEditField_5.Value = 2;
            app.LoadCir4MicscsvfileButton = uibutton(app.UIFigure, 'push');
            app.LoadCir4MicscsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCir4MicscsvfileButtonPushed, true);
            app.LoadCir4MicscsvfileButton.Tooltip = {'Load a .csv file generated with cir4mics (https://github.com/uhlmanngroup/cir4mics)'; 'The file should have columns names X, Y. Values should be in nm.'};
            app.LoadCir4MicscsvfileButton.Text = 'Load Cir4Mics .csv file';
            app.Cir4MicscsvfileEditFieldLabel = uilabel(app.UIFigure);
            app.Cir4MicscsvfileEditFieldLabel.Text = 'Cir4Mics .csv file';
            app.Cir4MicscsvfileEditField = uieditfield(app.UIFigure, 'text');
            app.Cir4MicscsvfileEditField.ValueChangedFcn = createCallbackFcn(app, @Cir4MicscsvfileEditFieldValueChanged, true);
            app.ResolutionnmEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionnmEditFieldLabel.Text = 'Resolution [nm]';
            app.ResolutionnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionnmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DDistortedNPCs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end