classdef Draw2DEColi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImagesizeYumEditField           matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel      matlab.ui.control.Label
        ImagesizeXumEditField           matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel      matlab.ui.control.Label
        nmEditField_7                   matlab.ui.control.NumericEditField
        nmEditField_7Label              matlab.ui.control.Label
        nmEditField_6                   matlab.ui.control.NumericEditField
        nmEditField_6Label              matlab.ui.control.Label
        IncludenucleoidCheckBox         matlab.ui.control.CheckBox
        SeparationbetweenbacteriapixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenbacteriapixelEditFieldLabel  matlab.ui.control.Label
        RotatecellsrandomlyCheckBox     matlab.ui.control.CheckBox
        SafetyborderpixelEditField      matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_5                   matlab.ui.control.NumericEditField
        nmEditField_5Label              matlab.ui.control.Label
        nmEditField_4                   matlab.ui.control.NumericEditField
        nmEditField_4Label              matlab.ui.control.Label
        nmEditField_3                   matlab.ui.control.NumericEditField
        nmEditField_3Label              matlab.ui.control.Label
        nmEditField_2                   matlab.ui.control.NumericEditField
        nmEditField_2Label              matlab.ui.control.Label
        nmEditField                     matlab.ui.control.NumericEditField
        nmEditFieldLabel                matlab.ui.control.Label
        VirtualSampleDimensionsLabel    matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        NucleoidshortaxispixelEditField  matlab.ui.control.NumericEditField
        NucleoidshortaxispixelEditFieldLabel  matlab.ui.control.Label
        NucleoidlongaxispixelEditField  matlab.ui.control.NumericEditField
        NucleoidlongaxispixelEditFieldLabel  matlab.ui.control.Label
        DoneCheckBox                    matlab.ui.control.CheckBox
        CreatesampleButton              matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        SavesampleButton                matlab.ui.control.Button
        LoadexistingsampleButton        matlab.ui.control.Button
        PeriplasmthicknesspixelEditField  matlab.ui.control.NumericEditField
        PeriplasmthicknesspixelEditFieldLabel  matlab.ui.control.Label
        CellshortaxispixelEditField     matlab.ui.control.NumericEditField
        CellshortaxispixelEditFieldLabel  matlab.ui.control.Label
        CelllongaxispixelEditField      matlab.ui.control.NumericEditField
        CelllongaxispixelEditFieldLabel  matlab.ui.control.Label
        NumberofbacteriaYdimEditField   matlab.ui.control.NumericEditField
        NumberofbacteriaYdimEditFieldLabel  matlab.ui.control.Label
        NumberofbacteriaXdimEditField   matlab.ui.control.NumericEditField
        NumberofbacteriaXdimEditFieldLabel  matlab.ui.control.Label
        Draw2DEcolibacteriaLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]
            app.ImagesizeXumEditField.Value=1e-3*scale*size(app.MySample,1);
            app.ImagesizeYumEditField.Value=1e-3*scale*size(app.MySample,2);

            app.nmEditField.Value=scale*app.CelllongaxispixelEditField.Value;
            app.nmEditField_2.Value=scale*app.CellshortaxispixelEditField.Value;

            app.nmEditField_3.Value=scale*app.PeriplasmthicknesspixelEditField.Value;
            app.nmEditField_4.Value=scale*app.NucleoidlongaxispixelEditField.Value;
            app.nmEditField_5.Value=scale*app.NucleoidshortaxispixelEditField.Value;
            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
            app.nmEditField_7.Value=scale*app.SeparationbetweenbacteriapixelEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;
            app.par.virtual_sample_name='2D_EColi';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.n_cells_x=app.NumberofbacteriaXdimEditField.Value;
            app.par.n_cells_y=app.NumberofbacteriaYdimEditField.Value;
            app.par.periplasm_thick=app.PeriplasmthicknesspixelEditField.Value;
            app.par.cytoplasm_a=app.CelllongaxispixelEditField.Value;
            app.par.cytoplasm_b=app.CellshortaxispixelEditField.Value;
            app.par.nucleoid_a=app.NucleoidlongaxispixelEditField.Value;
            app.par.nucleoid_b=app.NucleoidshortaxispixelEditField.Value;
            app.par.cell_border_offset=app.SafetyborderpixelEditField.Value;
            app.par.cell_spacing=app.SeparationbetweenbacteriapixelEditField.Value;
            app.par.rotate_cells=app.RotatecellsrandomlyCheckBox.Value;
            app.par.include_nucleoid=app.IncludenucleoidCheckBox.Value;

            app.NucleoidlongaxispixelEditField.Visible="off";
            app.NucleoidlongaxispixelEditFieldLabel.Visible="off";
            app.NucleoidshortaxispixelEditField.Visible="off";
            app.NucleoidshortaxispixelEditFieldLabel.Visible="off";
            app.nmEditField_4.Visible="off";
            app.nmEditField_4Label.Visible="off";
            app.nmEditField_5.Visible="off";
            app.nmEditField_5Label.Visible="off";

            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2D_EColi(app.par);
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: NumberofbacteriaYdimEditField
        function NumberofbacteriaYdimEditFieldValueChanged(app, event)
            value = app.NumberofbacteriaYdimEditField.Value;
            app.par.n_cells_y=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CelllongaxispixelEditField
        function CelllongaxispixelEditFieldValueChanged(app, event)
            value = app.CelllongaxispixelEditField.Value;
            app.par.cytoplasm_a=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CellshortaxispixelEditField
        function CellshortaxispixelEditFieldValueChanged(app, event)
            value = app.CellshortaxispixelEditField.Value;
            app.par.cytoplasm_b=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC> 
                par=app.par; %#ok<ADPROPLC> 
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_EColi');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving image ...');
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    imwrite(uint8(app.MySample),tiffile,'tif');
                    disp('Done !');
                    disp('Saving parameters ...');
                    matfile=strrep(tiffile,'.tif','.mat');
                    save(matfile,'MySample','par');
                    disp('Done !');
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D_EColi')==1
    
                    app.par=par; %#ok<ADPROPLC>

                    app.NumberofbacteriaXdimEditField.Value=app.par.n_cells_x;
                    app.NumberofbacteriaYdimEditField.Value=app.par.n_cells_y;
                    app.PeriplasmthicknesspixelEditField.Value=app.par.periplasm_thick;
                    app.CelllongaxispixelEditField.Value=app.par.cytoplasm_a;
                    app.CellshortaxispixelEditField.Value=app.par.cytoplasm_b;
                    app.NucleoidlongaxispixelEditField.Value=app.par.nucleoid_a;
                    app.NucleoidshortaxispixelEditField.Value=app.par.nucleoid_b;
                    app.SafetyborderpixelEditField.Value=app.par.cell_border_offset;
                    app.SeparationbetweenbacteriapixelEditField.Value=app.par.cell_spacing;
                    app.RotatecellsrandomlyCheckBox.Value=app.par.rotate_cells;
                    app.IncludenucleoidCheckBox.Value=app.par.include_nucleoid;
                    if app.par.include_nucleoid==1
                        app.NucleoidlongaxispixelEditField.Visible="on";
                        app.NucleoidlongaxispixelEditFieldLabel.Visible="on";
                        app.NucleoidshortaxispixelEditField.Visible="on";
                        app.NucleoidshortaxispixelEditFieldLabel.Visible="on";
                        app.nmEditField_4.Visible="on";
                        app.nmEditField_4Label.Visible="on";
                        app.nmEditField_5.Visible="on";
                        app.nmEditField_5Label.Visible="on";
                    else
                        app.NucleoidlongaxispixelEditField.Visible="off";
                        app.NucleoidlongaxispixelEditFieldLabel.Visible="off";
                        app.NucleoidshortaxispixelEditField.Visible="off";
                        app.NucleoidshortaxispixelEditFieldLabel.Visible="off";
                        app.nmEditField_4.Visible="off";
                        app.nmEditField_4Label.Visible="off";
                        app.nmEditField_5.Visible="off";
                        app.nmEditField_5Label.Visible="off";
                    end

                    update_size_evaluation(app)

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    title('Virtual NPCs')
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D_NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: PeriplasmthicknesspixelEditField
        function PeriplasmthicknesspixelEditFieldValueChanged(app, event)
            value = app.PeriplasmthicknesspixelEditField.Value;
            app.par.periplasm_thick=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NucleoidlongaxispixelEditField
        function NucleoidlongaxispixelEditFieldValueChanged(app, event)
            value = app.NucleoidlongaxispixelEditField.Value;
            app.par.nucleoid_a=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NucleoidshortaxispixelEditField
        function NucleoidshortaxispixelEditFieldValueChanged(app, event)
            value = app.NucleoidshortaxispixelEditField.Value;
            app.par.nucleoid_b=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofbacteriaXdimEditField
        function NumberofbacteriaXdimEditFieldValueChanged(app, event)
            value = app.NumberofbacteriaXdimEditField.Value;
            app.par.n_cells_x=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: IncludenucleoidCheckBox
        function IncludenucleoidCheckBoxValueChanged(app, event)
            value = app.IncludenucleoidCheckBox.Value;
            app.par.include_nucleoid=value;
            if app.par.include_nucleoid==1
                app.NucleoidlongaxispixelEditField.Visible="on";
                app.NucleoidlongaxispixelEditFieldLabel.Visible="on";
                app.NucleoidshortaxispixelEditField.Visible="on";
                app.NucleoidshortaxispixelEditFieldLabel.Visible="on";
                app.nmEditField_4.Visible="on";
                app.nmEditField_4Label.Visible="on";
                app.nmEditField_5.Visible="on";
                app.nmEditField_5Label.Visible="on";
            else
                app.NucleoidlongaxispixelEditField.Visible="off";
                app.NucleoidlongaxispixelEditFieldLabel.Visible="off";
                app.NucleoidshortaxispixelEditField.Visible="off";
                app.NucleoidshortaxispixelEditFieldLabel.Visible="off";
                app.nmEditField_4.Visible="off";
                app.nmEditField_4Label.Visible="off";
                app.nmEditField_5.Visible="off";
                app.nmEditField_5Label.Visible="off";
            end

        end

        % Value changed function: RotatecellsrandomlyCheckBox
        function RotatecellsrandomlyCheckBoxValueChanged(app, event)
            value = app.RotatecellsrandomlyCheckBox.Value;
            app.par.rotate_cells=value;
            update_size_evaluation(app)
        end

        % Value changed function: SeparationbetweenbacteriapixelEditField
        function SeparationbetweenbacteriapixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenbacteriapixelEditField.Value;
            app.par.cell_spacing=value;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DEcolibacteriaLabel = uilabel(app.UIFigure);
            app.Draw2DEcolibacteriaLabel.Text = 'Draw 2D E. coli bacteria';
            app.NumberofbacteriaXdimEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbacteriaXdimEditFieldLabel.Text = 'Number of bacteria X dim';
            app.NumberofbacteriaXdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbacteriaXdimEditField.Limits = [1 Inf];
            app.NumberofbacteriaXdimEditField.ValueDisplayFormat = '%.0f';
            app.NumberofbacteriaXdimEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofbacteriaXdimEditFieldValueChanged, true);
            app.NumberofbacteriaXdimEditField.Value = 10;
            app.NumberofbacteriaYdimEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbacteriaYdimEditFieldLabel.Text = 'Number of bacteria Y dim';
            app.NumberofbacteriaYdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbacteriaYdimEditField.Limits = [1 Inf];
            app.NumberofbacteriaYdimEditField.ValueDisplayFormat = '%.0f';
            app.NumberofbacteriaYdimEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofbacteriaYdimEditFieldValueChanged, true);
            app.NumberofbacteriaYdimEditField.Value = 10;
            app.CelllongaxispixelEditFieldLabel = uilabel(app.UIFigure);
            app.CelllongaxispixelEditFieldLabel.Text = 'Cell long axis [pixel]';
            app.CelllongaxispixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CelllongaxispixelEditField.Limits = [1 Inf];
            app.CelllongaxispixelEditField.ValueDisplayFormat = '%.0f';
            app.CelllongaxispixelEditField.ValueChangedFcn = createCallbackFcn(app, @CelllongaxispixelEditFieldValueChanged, true);
            app.CelllongaxispixelEditField.Value = 80;
            app.CellshortaxispixelEditFieldLabel = uilabel(app.UIFigure);
            app.CellshortaxispixelEditFieldLabel.Text = 'Cell short axis  [pixel]';
            app.CellshortaxispixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellshortaxispixelEditField.Limits = [1 Inf];
            app.CellshortaxispixelEditField.ValueDisplayFormat = '%.0f';
            app.CellshortaxispixelEditField.ValueChangedFcn = createCallbackFcn(app, @CellshortaxispixelEditFieldValueChanged, true);
            app.CellshortaxispixelEditField.Value = 20;
            app.PeriplasmthicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.PeriplasmthicknesspixelEditFieldLabel.Text = 'Periplasm thickness [pixel]';
            app.PeriplasmthicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriplasmthicknesspixelEditField.Limits = [1 Inf];
            app.PeriplasmthicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.PeriplasmthicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @PeriplasmthicknesspixelEditFieldValueChanged, true);
            app.PeriplasmthicknesspixelEditField.Value = 2;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.NucleoidlongaxispixelEditFieldLabel = uilabel(app.UIFigure);
            app.NucleoidlongaxispixelEditFieldLabel.Text = 'Nucleoid long axis [pixel]';
            app.NucleoidlongaxispixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NucleoidlongaxispixelEditField.Limits = [1 Inf];
            app.NucleoidlongaxispixelEditField.ValueDisplayFormat = '%.0f';
            app.NucleoidlongaxispixelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleoidlongaxispixelEditFieldValueChanged, true);
            app.NucleoidlongaxispixelEditField.Value = 25;
            app.NucleoidshortaxispixelEditFieldLabel = uilabel(app.UIFigure);
            app.NucleoidshortaxispixelEditFieldLabel.Text = 'Nucleoid short axis [pixel]';
            app.NucleoidshortaxispixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NucleoidshortaxispixelEditField.Limits = [1 Inf];
            app.NucleoidshortaxispixelEditField.ValueDisplayFormat = '%.0f';
            app.NucleoidshortaxispixelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleoidshortaxispixelEditFieldValueChanged, true);
            app.NucleoidshortaxispixelEditField.Value = 5;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            app.nmEditField_4Label = uilabel(app.UIFigure);
            app.nmEditField_4Label.Text = '[nm]';
            app.nmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_4.Limits = [0 Inf];
            app.nmEditField_4.ValueDisplayFormat = '%.0f';
            app.nmEditField_4.Editable = 'off';
            app.nmEditField_4.Value = 2;
            app.nmEditField_5Label = uilabel(app.UIFigure);
            app.nmEditField_5Label.Text = '[nm]';
            app.nmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_5.Limits = [0 Inf];
            app.nmEditField_5.ValueDisplayFormat = '%.0f';
            app.nmEditField_5.Editable = 'off';
            app.nmEditField_5.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.RotatecellsrandomlyCheckBox = uicheckbox(app.UIFigure);
            app.RotatecellsrandomlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatecellsrandomlyCheckBoxValueChanged, true);
            app.RotatecellsrandomlyCheckBox.Text = 'Rotate cells randomly';
            app.SeparationbetweenbacteriapixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenbacteriapixelEditFieldLabel.Text = 'Separation between bacteria [pixel]';
            app.SeparationbetweenbacteriapixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenbacteriapixelEditField.Limits = [0 Inf];
            app.SeparationbetweenbacteriapixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenbacteriapixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenbacteriapixelEditFieldValueChanged, true);
            app.SeparationbetweenbacteriapixelEditField.Value = 10;
            app.IncludenucleoidCheckBox = uicheckbox(app.UIFigure);
            app.IncludenucleoidCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludenucleoidCheckBoxValueChanged, true);
            app.IncludenucleoidCheckBox.Text = 'Include nucleoid';
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.nmEditField_7Label = uilabel(app.UIFigure);
            app.nmEditField_7Label.Text = '[nm]';
            app.nmEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_7.Limits = [0 Inf];
            app.nmEditField_7.ValueDisplayFormat = '%.0f';
            app.nmEditField_7.Editable = 'off';
            app.nmEditField_7.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DEColi(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end