classdef Draw2DHelaCell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        nmEditField_3                   matlab.ui.control.NumericEditField
        nmEditField_3Label              matlab.ui.control.Label
        CellsizeumEditField             matlab.ui.control.NumericEditField
        CellsizeumEditFieldLabel        matlab.ui.control.Label
        umEditField_2                   matlab.ui.control.NumericEditField
        umEditField_2Label              matlab.ui.control.Label
        umEditField                     matlab.ui.control.NumericEditField
        umEditFieldLabel                matlab.ui.control.Label
        nmEditField_2                   matlab.ui.control.NumericEditField
        nmEditField_2Label              matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        nmEditField                     matlab.ui.control.NumericEditField
        nmEditFieldLabel                matlab.ui.control.Label
        VirtualSampleDimensionsLabel    matlab.ui.control.Label
        PositionclustersonButtonGroup   matlab.ui.container.ButtonGroup
        NuclearmembraneButton           matlab.ui.control.ToggleButton
        PlasmicmembraneButton           matlab.ui.control.ToggleButton
        NucleusButton                   matlab.ui.control.ToggleButton
        CytoplasmButton                 matlab.ui.control.ToggleButton
        DoneCheckBox                    matlab.ui.control.CheckBox
        CreatesampleButton              matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        SavesampleButton                matlab.ui.control.Button
        LoadexistingsampleButton        matlab.ui.control.Button
        ClusterdiameterpixelEditField   matlab.ui.control.NumericEditField
        ClusterdiameterpixelEditFieldLabel  matlab.ui.control.Label
        NumberofclustersEditField       matlab.ui.control.NumericEditField
        NumberofclustersEditFieldLabel  matlab.ui.control.Label
        IncludeclustersCheckBox         matlab.ui.control.CheckBox
        ThicknessofnuclearmembranepixelEditField  matlab.ui.control.NumericEditField
        ThicknessofnuclearmembranepixelEditFieldLabel  matlab.ui.control.Label
        ThicknessofplasmicmembranepixelEditField  matlab.ui.control.NumericEditField
        ThicknessofplasmicmembranepixelEditFieldLabel  matlab.ui.control.Label
        YimagedimensionpixelEditField   matlab.ui.control.NumericEditField
        YimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        XimagedimensionpixelEditField   matlab.ui.control.NumericEditField
        XimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        Draw2DHeLaCellLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
        Property3 % Description
    end
    
    methods (Access = private)
        
       function update_size_evaluation(app)
            cell_size=420; % Measured manually
            scale=app.par.raster/app.par.binning; % [raster2nm]
            app.umEditField.Value=1e-3*scale*app.XimagedimensionpixelEditField.Value;
            app.umEditField_2.Value=1e-3*scale*app.YimagedimensionpixelEditField.Value;

            app.nmEditField.Value=scale*app.ThicknessofplasmicmembranepixelEditField.Value;
            app.nmEditField_2.Value=scale*app.ThicknessofnuclearmembranepixelEditField.Value;
            app.nmEditField_3.Value=scale*app.ClusterdiameterpixelEditField.Value;
            app.CellsizeumEditField.Value=1e-3*scale*cell_size;
        end
            
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.virtual_sample_name='2D Hela Cell';
            app.par.image_library=[mainapp.SMIS_dir.SMIS_Image_Dir];
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.XimagedimensionpixelEditField.Value;
            app.par.y_dim=app.YimagedimensionpixelEditField.Value;
            app.par.plasmic_layer_thick=app.ThicknessofplasmicmembranepixelEditField.Value;
            app.par.nuc_layer_thick=app.ThicknessofnuclearmembranepixelEditField.Value;
            app.par.cluster_diam=app.ClusterdiameterpixelEditField.Value;
            app.par.cluster_pos_id=app.PositionclustersonButtonGroup.SelectedObject.Text;
            app.par.n_clusters=0;

            app.par.binning=mainapp.binning;
            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);

            app.NumberofclustersEditField.Visible="off";
            app.NumberofclustersEditFieldLabel.Visible="off";
            app.ClusterdiameterpixelEditField.Visible="off";
            app.ClusterdiameterpixelEditFieldLabel.Visible="off";
            app.PositionclustersonButtonGroup.Visible="off";
            app.nmEditField_3.Visible="off";
            app.nmEditField_3Label.Visible="off";

        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2DHeLaCell(app.par);
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: XimagedimensionpixelEditField
        function XimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.XimagedimensionpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app);
        end

        % Value changed function: YimagedimensionpixelEditField
        function YimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.YimagedimensionpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app);
        end

        % Value changed function: ThicknessofplasmicmembranepixelEditField
        function ThicknessofplasmicmembranepixelEditFieldValueChanged(app, event)
            value = app.ThicknessofplasmicmembranepixelEditField.Value;
            app.par.plasmic_layer_thick=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app);
        end

        % Value changed function: ThicknessofnuclearmembranepixelEditField
        function ThicknessofnuclearmembranepixelEditFieldValueChanged(app, event)
            value = app.ThicknessofnuclearmembranepixelEditField.Value;
            app.par.nuc_layer_thick=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app);
        end

        % Value changed function: IncludeclustersCheckBox
        function IncludeclustersCheckBoxValueChanged(app, event)
            value = app.IncludeclustersCheckBox.Value;
            if value==true
                app.par.n_clusters=app.NumberofclustersEditField.Value;
                app.NumberofclustersEditField.Visible="on";
                app.NumberofclustersEditFieldLabel.Visible="on";
                app.ClusterdiameterpixelEditField.Visible="on";
                app.ClusterdiameterpixelEditFieldLabel.Visible="on";
                app.PositionclustersonButtonGroup.Visible="on";
                app.nmEditField_3.Visible="on";
                app.nmEditField_3Label.Visible="on";
            else
                app.par.n_clusters=0;
                app.NumberofclustersEditField.Visible="off";
                app.NumberofclustersEditFieldLabel.Visible="off";
                app.ClusterdiameterpixelEditField.Visible="off";
                app.ClusterdiameterpixelEditFieldLabel.Visible="off";
                app.PositionclustersonButtonGroup.Visible="off";
                app.nmEditField_3.Visible="off";
                app.nmEditField_3Label.Visible="off";
            end
            app.DoneCheckBox.Value=false;
        end

        % Selection changed function: PositionclustersonButtonGroup
        function PositionclustersonButtonGroupSelectionChanged(app, event)
            app.par.cluster_pos_id = app.PositionclustersonButtonGroup.SelectedObject.Text;
            app.DoneCheckBox.Value=false;
        end

        % Value changed function: NumberofclustersEditField
        function NumberofclustersEditFieldValueChanged(app, event)
            value = app.NumberofclustersEditField.Value;
            app.par.n_clusters=value;
            app.DoneCheckBox.Value=false;
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC>
                par=app.par; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_HelaCell');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving image ...');
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    imwrite(uint8(app.MySample),tiffile,'tif');
                    disp('Done !');
                    disp('Saving parameters ...');
                    matfile=strrep(tiffile,'.tif','.mat');
                    save(matfile,'MySample','par');
                    disp('Done !');
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D Hela Cell')==1
                    app.par=par; %#ok<ADPROPLC>

                    app.XimagedimensionpixelEditField.Value=app.par.x_dim;
                    app.YimagedimensionpixelEditField.Value=app.par.y_dim;

                    app.ThicknessofplasmicmembranepixelEditField.Value=app.par.plasmic_layer_thick;
                    app.ThicknessofnuclearmembranepixelEditField.Value=app.par.nuc_layer_thick;
                    app.ClusterdiameterpixelEditField.Value=app.par.cluster_diam;
                    app.PositionclustersonButtonGroup.SelectedObject.Text=app.par.cluster_pos_id;
                    app.NumberofclustersEditField.Value=app.par.n_clusters;
                    if app.par.n_clusters>0
                        app.IncludeclustersCheckBox.Value=true;
                        app.NumberofclustersEditField.Visible="on";
                        app.NumberofclustersEditFieldLabel.Visible="on";
                        app.ClusterdiameterpixelEditField.Visible="on";
                        app.ClusterdiameterpixelEditFieldLabel.Visible="on";
                        app.PositionclustersonButtonGroup.Visible="on";
                    else
                        app.IncludeclustersCheckBox.Value=false;
                        app.NumberofclustersEditField.Visible="off";
                        app.NumberofclustersEditFieldLabel.Visible="off";
                        app.ClusterdiameterpixelEditField.Visible="off";
                        app.ClusterdiameterpixelEditFieldLabel.Visible="off";
                        app.PositionclustersonButtonGroup.Visible="off";
                    end
                    update_size_evaluation(app);

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    title('Virtual HeLa Cell')
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D Hela Cell');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Choose2DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)            
        end

        % Value changed function: ClusterdiameterpixelEditField
        function ClusterdiameterpixelEditFieldValueChanged(app, event)
            value = app.ClusterdiameterpixelEditField.Value;
            app.par.cluster_diam=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DHeLaCellLabel = uilabel(app.UIFigure);
            app.Draw2DHeLaCellLabel.Text = 'Draw 2D HeLa Cell';
            app.XimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.XimagedimensionpixelEditFieldLabel.Text = 'X image dimension [pixel]';
            app.XimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.XimagedimensionpixelEditField.Limits = [450 Inf];
            app.XimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.XimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @XimagedimensionpixelEditFieldValueChanged, true);
            app.XimagedimensionpixelEditField.Value = 512;
            app.YimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.YimagedimensionpixelEditFieldLabel.Text = 'Y image dimension [pixel]';
            app.YimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.YimagedimensionpixelEditField.Limits = [465 Inf];
            app.YimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.YimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @YimagedimensionpixelEditFieldValueChanged, true);
            app.YimagedimensionpixelEditField.Value = 512;
            app.ThicknessofplasmicmembranepixelEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessofplasmicmembranepixelEditFieldLabel.Text = 'Thickness of plasmic membrane [pixel]';
            app.ThicknessofplasmicmembranepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessofplasmicmembranepixelEditField.Limits = [1 64];
            app.ThicknessofplasmicmembranepixelEditField.ValueDisplayFormat = '%.0f';
            app.ThicknessofplasmicmembranepixelEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofplasmicmembranepixelEditFieldValueChanged, true);
            app.ThicknessofplasmicmembranepixelEditField.Value = 2;
            app.ThicknessofnuclearmembranepixelEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessofnuclearmembranepixelEditFieldLabel.Text = 'Thickness of nuclear membrane [pixel]';
            app.ThicknessofnuclearmembranepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessofnuclearmembranepixelEditField.Limits = [1 64];
            app.ThicknessofnuclearmembranepixelEditField.ValueDisplayFormat = '%.0f';
            app.ThicknessofnuclearmembranepixelEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofnuclearmembranepixelEditFieldValueChanged, true);
            app.ThicknessofnuclearmembranepixelEditField.Value = 2;
            app.IncludeclustersCheckBox = uicheckbox(app.UIFigure);
            app.IncludeclustersCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeclustersCheckBoxValueChanged, true);
            app.IncludeclustersCheckBox.Text = 'Include clusters';
            app.NumberofclustersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofclustersEditFieldLabel.Text = 'Number of clusters';
            app.NumberofclustersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofclustersEditField.Limits = [0 Inf];
            app.NumberofclustersEditField.ValueDisplayFormat = '%.0f';
            app.NumberofclustersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofclustersEditFieldValueChanged, true);
            app.NumberofclustersEditField.Value = 200;
            app.ClusterdiameterpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ClusterdiameterpixelEditFieldLabel.Text = 'Cluster diameter [pixel]';
            app.ClusterdiameterpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClusterdiameterpixelEditField.Limits = [1 64];
            app.ClusterdiameterpixelEditField.ValueDisplayFormat = '%.0f';
            app.ClusterdiameterpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterdiameterpixelEditFieldValueChanged, true);
            app.ClusterdiameterpixelEditField.Value = 2;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.PositionclustersonButtonGroup = uibuttongroup(app.UIFigure);
            app.PositionclustersonButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PositionclustersonButtonGroupSelectionChanged, true);
            app.PositionclustersonButtonGroup.Title = 'Position clusters on';
            app.CytoplasmButton = uitogglebutton(app.PositionclustersonButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.CytoplasmButton.Value = true;
            app.NucleusButton = uitogglebutton(app.PositionclustersonButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.PlasmicmembraneButton = uitogglebutton(app.PositionclustersonButtonGroup);
            app.PlasmicmembraneButton.Text = 'Plasmic membrane';
            app.NuclearmembraneButton = uitogglebutton(app.PositionclustersonButtonGroup);
            app.NuclearmembraneButton.Text = 'Nuclear membrane';
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.umEditFieldLabel = uilabel(app.UIFigure);
            app.umEditFieldLabel.Text = '[um]';
            app.umEditField = uieditfield(app.UIFigure, 'numeric');
            app.umEditField.Limits = [0 Inf];
            app.umEditField.ValueDisplayFormat = '%.0f';
            app.umEditField.Editable = 'off';
            app.umEditField.Value = 2;
            app.umEditField_2Label = uilabel(app.UIFigure);
            app.umEditField_2Label.Text = '[um]';
            app.umEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.umEditField_2.Limits = [0 Inf];
            app.umEditField_2.ValueDisplayFormat = '%.0f';
            app.umEditField_2.Editable = 'off';
            app.umEditField_2.Value = 2;
            app.CellsizeumEditFieldLabel = uilabel(app.UIFigure);
            app.CellsizeumEditFieldLabel.Text = 'Cell size [um]';
            app.CellsizeumEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellsizeumEditField.Limits = [0 Inf];
            app.CellsizeumEditField.Editable = 'off';
            app.CellsizeumEditField.Value = 2;
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DHelaCell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end