classdef Draw2DNPCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        nmEditField_5                  matlab.ui.control.NumericEditField
        nmEditField_5Label             matlab.ui.control.Label
        nmEditField_4                  matlab.ui.control.NumericEditField
        nmEditField_4Label             matlab.ui.control.Label
        nmEditField_3                  matlab.ui.control.NumericEditField
        nmEditField_3Label             matlab.ui.control.Label
        nmEditField_2                  matlab.ui.control.NumericEditField
        nmEditField_2Label             matlab.ui.control.Label
        nmEditField                    matlab.ui.control.NumericEditField
        nmEditFieldLabel               matlab.ui.control.Label
        umEditField_2                  matlab.ui.control.NumericEditField
        umEditField_2Label             matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        umEditField                    matlab.ui.control.NumericEditField
        umEditFieldLabel               matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        SeparationbetweenNPCspixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenNPCspixelEditFieldLabel  matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        Nup96sizepixelEditField        matlab.ui.control.NumericEditField
        Nup96sizepixelEditFieldLabel   matlab.ui.control.Label
        NumberofNPCsEditField          matlab.ui.control.NumericEditField
        NumberofNPCsEditFieldLabel     matlab.ui.control.Label
        NPCouterradiuspixelEditField   matlab.ui.control.NumericEditField
        NPCouterradiuspixelEditFieldLabel  matlab.ui.control.Label
        NPCinnerradiuspixelEditField   matlab.ui.control.NumericEditField
        NPCinnerradiuspixelEditFieldLabel  matlab.ui.control.Label
        YimagedimensionpixelEditField  matlab.ui.control.NumericEditField
        YimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        XimagedimensionpixelEditField  matlab.ui.control.NumericEditField
        XimagedimensionpixelEditFieldLabel  matlab.ui.control.Label
        Draw2DNPCsLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]
            app.umEditField.Value=1e-3*scale*app.XimagedimensionpixelEditField.Value;
            app.umEditField_2.Value=1e-3*scale*app.YimagedimensionpixelEditField.Value;
            app.nmEditField.Value=scale*app.NPCinnerradiuspixelEditField.Value;
            app.nmEditField_2.Value=scale*app.NPCouterradiuspixelEditField.Value;
            app.nmEditField_3.Value=scale*app.Nup96sizepixelEditField.Value;
            app.nmEditField_4.Value=scale*app.SeparationbetweenNPCspixelEditField.Value;
            app.nmEditField_5.Value=scale*app.SafetyborderpixelEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

             if app.par.qPALM==1
                app.Draw2DNPCsLabel.Text='Draw 2D NPCs (qPALM)';
            else
                app.Draw2DNPCsLabel.Text='Draw 2D NPCs';
            end

            app.par.virtual_sample_name='2D_NPCs';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.XimagedimensionpixelEditField.Value;
            app.par.y_dim=app.YimagedimensionpixelEditField.Value;
            app.par.N=app.NumberofNPCsEditField.Value;
            app.par.nup96_size=app.Nup96sizepixelEditField.Value;
            app.par.inner_radius=app.NPCinnerradiuspixelEditField.Value;
            app.par.outer_radius=app.NPCouterradiuspixelEditField.Value;
            app.par.npc_sep=app.SeparationbetweenNPCspixelEditField.Value;
            app.par.border=app.SafetyborderpixelEditField.Value;
            app.par.binning=mainapp.binning;
            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2D_NPCs(app.par);
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: XimagedimensionpixelEditField
        function XimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.XimagedimensionpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: YimagedimensionpixelEditField
        function YimagedimensionpixelEditFieldValueChanged(app, event)
            value = app.YimagedimensionpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NPCinnerradiuspixelEditField
        function NPCinnerradiuspixelEditFieldValueChanged(app, event)
            value = app.NPCinnerradiuspixelEditField.Value;
            app.par.inner_radius=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NPCouterradiuspixelEditField
        function NPCouterradiuspixelEditFieldValueChanged(app, event)
            value = app.NPCouterradiuspixelEditField.Value;
            app.par.outer_radius=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofNPCsEditField
        function NumberofNPCsEditFieldValueChanged(app, event)
            value = app.NumberofNPCsEditField.Value;
            app.par.N=value;
            app.DoneCheckBox.Value=false;
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC> 
                par=app.par; %#ok<ADPROPLC> 
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_NPCs');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving image ...');
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    if max(app.MySample(:))<=65535
                        imwrite(uint16(app.MySample),tiffile,'tif');
                        disp('Done !');
                        disp('Saving parameters ...');
                        matfile=strrep(tiffile,'.tif','.mat');
                        save(matfile,'MySample','par');
                        disp('Done !');
                    else % Image cannot be converted to 16 bits
                        mydlg=warndlg('Virtual sample has pixel values exceeding 16 bits (65535). Cannot save it ! ');
                        waitfor(mydlg);
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D_NPCs')==1
                    app.par=par; %#ok<ADPROPLC>

                    app.XimagedimensionpixelEditField.Value=app.par.x_dim;
                    app.YimagedimensionpixelEditField.Value=app.par.y_dim;
                    app.NumberofNPCsEditField.Value=app.par.N;
                    app.Nup96sizepixelEditField.Value=app.par.nup96_size;
                    app.NPCinnerradiuspixelEditField.Value=app.par.inner_radius;
                    app.NPCouterradiuspixelEditField.Value=app.par.outer_radius;
                    app.SeparationbetweenNPCspixelEditField.Value=app.par.npc_sep;
                    app.SafetyborderpixelEditField.Value=app.par.border;
                    update_size_evaluation(app)

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    title('Virtual NPCs')
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D_NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: Nup96sizepixelEditField
        function Nup96sizepixelEditFieldValueChanged(app, event)
            value = app.Nup96sizepixelEditField.Value;
            app.par.nup96_size=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SeparationbetweenNPCspixelEditField
        function SeparationbetweenNPCspixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenNPCspixelEditField.Value;
            app.par.npc_sep=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.border=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DNPCsLabel = uilabel(app.UIFigure);
            app.Draw2DNPCsLabel.Text = 'Draw 2D NPCs';
            app.XimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.XimagedimensionpixelEditFieldLabel.Text = 'X image dimension [pixel]';
            app.XimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.XimagedimensionpixelEditField.Limits = [450 Inf];
            app.XimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.XimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @XimagedimensionpixelEditFieldValueChanged, true);
            app.XimagedimensionpixelEditField.Value = 512;
            app.YimagedimensionpixelEditFieldLabel = uilabel(app.UIFigure);
            app.YimagedimensionpixelEditFieldLabel.Text = 'Y image dimension [pixel]';
            app.YimagedimensionpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.YimagedimensionpixelEditField.Limits = [465 Inf];
            app.YimagedimensionpixelEditField.ValueDisplayFormat = '%.0f';
            app.YimagedimensionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @YimagedimensionpixelEditFieldValueChanged, true);
            app.YimagedimensionpixelEditField.Value = 512;
            app.NPCinnerradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.NPCinnerradiuspixelEditFieldLabel.Text = 'NPC inner radius [pixel]';
            app.NPCinnerradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPCinnerradiuspixelEditField.Limits = [1 64];
            app.NPCinnerradiuspixelEditField.ValueDisplayFormat = '%.0f';
            app.NPCinnerradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @NPCinnerradiuspixelEditFieldValueChanged, true);
            app.NPCinnerradiuspixelEditField.Value = 5;
            app.NPCouterradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.NPCouterradiuspixelEditFieldLabel.Text = 'NPC outer radius [pixel]';
            app.NPCouterradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPCouterradiuspixelEditField.Limits = [1 64];
            app.NPCouterradiuspixelEditField.ValueDisplayFormat = '%.0f';
            app.NPCouterradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @NPCouterradiuspixelEditFieldValueChanged, true);
            app.NPCouterradiuspixelEditField.Value = 7;
            app.NumberofNPCsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNPCsEditFieldLabel.Text = 'Number of NPCs';
            app.NumberofNPCsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNPCsEditField.Limits = [0 Inf];
            app.NumberofNPCsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofNPCsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNPCsEditFieldValueChanged, true);
            app.NumberofNPCsEditField.Value = 100;
            app.Nup96sizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.Nup96sizepixelEditFieldLabel.Text = 'Nup96 size [pixel]';
            app.Nup96sizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nup96sizepixelEditField.Limits = [1 64];
            app.Nup96sizepixelEditField.ValueDisplayFormat = '%.0f';
            app.Nup96sizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @Nup96sizepixelEditFieldValueChanged, true);
            app.Nup96sizepixelEditField.Value = 1;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.SeparationbetweenNPCspixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenNPCspixelEditFieldLabel.Text = 'Separation between NPCs [pixel]';
            app.SeparationbetweenNPCspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenNPCspixelEditField.Limits = [0 Inf];
            app.SeparationbetweenNPCspixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenNPCspixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenNPCspixelEditFieldValueChanged, true);
            app.SeparationbetweenNPCspixelEditField.Value = 10;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.umEditFieldLabel = uilabel(app.UIFigure);
            app.umEditFieldLabel.Text = '[um]';
            app.umEditField = uieditfield(app.UIFigure, 'numeric');
            app.umEditField.Limits = [0 Inf];
            app.umEditField.Editable = 'off';
            app.umEditField.Value = 2;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.umEditField_2Label = uilabel(app.UIFigure);
            app.umEditField_2Label.Text = '[um]';
            app.umEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.umEditField_2.Limits = [0 Inf];
            app.umEditField_2.Editable = 'off';
            app.umEditField_2.Value = 2;
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            app.nmEditField_4Label = uilabel(app.UIFigure);
            app.nmEditField_4Label.Text = '[nm]';
            app.nmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_4.Limits = [0 Inf];
            app.nmEditField_4.ValueDisplayFormat = '%.0f';
            app.nmEditField_4.Editable = 'off';
            app.nmEditField_4.Value = 2;
            app.nmEditField_5Label = uilabel(app.UIFigure);
            app.nmEditField_5Label.Text = '[nm]';
            app.nmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_5.Limits = [0 Inf];
            app.nmEditField_5.ValueDisplayFormat = '%.0f';
            app.nmEditField_5.Editable = 'off';
            app.nmEditField_5.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DNPCs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end