classdef Draw2DSquaresCircles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        nmEditField_7                  matlab.ui.control.NumericEditField
        nmEditField_7Label             matlab.ui.control.Label
        RandomXYshiftpixelEditField    matlab.ui.control.NumericEditField
        RandomXYshiftpixelEditFieldLabel  matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        ImagesizeYumEditField          matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel     matlab.ui.control.Label
        ImagesizeXumEditField          matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel     matlab.ui.control.Label
        nmEditField_6                  matlab.ui.control.NumericEditField
        nmEditField_6Label             matlab.ui.control.Label
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_3                  matlab.ui.control.NumericEditField
        nmEditField_3Label             matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        SquaresizepixelEditField       matlab.ui.control.NumericEditField
        SquaresizepixelEditFieldLabel  matlab.ui.control.Label
        NumberofsquaresEditField       matlab.ui.control.NumericEditField
        NumberofsquaresEditFieldLabel  matlab.ui.control.Label
        ImagesizeYpixelEditField       matlab.ui.control.NumericEditField
        ImagesizeYpixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeXpixelEditField       matlab.ui.control.NumericEditField
        ImagesizeXpixelEditFieldLabel  matlab.ui.control.Label
        Draw2DSquaresorCirclesLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp = [] % Callin app
        MySample = [] % Virtual sample
        par = [] % Parameters
    end

    methods (Access = private)

        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]
            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImagesizeXpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImagesizeYpixelEditField.Value;
          
            app.nmEditField_3.Value=scale*app.SquaresizepixelEditField.Value;            
            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
 
            app.nmEditField_7.Value=scale*app.RandomXYshiftpixelEditField.Value;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

            if app.par.qPALM==1
                app.Draw2DSquaresorCirclesLabel.Text='Draw 2D Squares or Circles (qPALM)';
            else
                app.Draw2DSquaresorCirclesLabel.Text='Draw 2D Squares or Circles';
            end

            app.par.virtual_sample_name='2D_SquaresCircles';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];

            app.par.x_dim=app.ImagesizeXpixelEditField.Value;
            app.par.y_dim=app.ImagesizeYpixelEditField.Value;

            app.par.n_row=fix(sqrt(app.NumberofsquaresEditField.Value));
            app.par.n_col=round(app.NumberofsquaresEditField.Value/app.par.n_row);
            app.par.s=app.SquaresizepixelEditField.Value;
            app.par.is_square=1;
            app.par.random_shift=app.RandomXYshiftpixelEditField.Value;

            app.par.border=app.SafetyborderpixelEditField.Value;


            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            app.MySample=get_SMIS_2DSquaresCircles(app.par);
            if ~isempty(app.MySample)
                app.DoneCheckBox.Value=true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: ImagesizeYpixelEditField
        function ImagesizeYpixelEditFieldValueChanged(app, event)
            value = app.ImagesizeYpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofsquaresEditField
        function NumberofsquaresEditFieldValueChanged(app, event)
            value = app.NumberofsquaresEditField.Value;
            app.par.n_row=fix(sqrt(value));
            app.par.n_col=round(value/app.par.n_row);
            N=app.par.n_row*app.par.n_col;
            if N~=value
                app.NumberofsquaresEditField.Value=N;
            end
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.MySample)
                MySample=app.MySample; %#ok<ADPROPLC>
                par=app.par; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.tif','SMIS File Selection','2D_SquaresCircles');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving image ...');
                    tiffile=fullfile(app.par.pattern_library,virtual_sample_file);
                    imwrite(uint16(app.MySample),tiffile,'tif');
                    disp('Done !');
                    disp('Saving parameters ...');
                    matfile=strrep(tiffile,'.tif','.mat');
                    save(matfile,'MySample','par');
                    disp('Done !');
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','par');
                app.MySample=MySample; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'2D_SquaresCircles')==1
                    app.par=par; %#ok<ADPROPLC>

                    app.ImagesizeXpixelEditField.Value=app.par.x_dim;
                    app.ImagesizeYpixelEditField.Value=app.par.y_dim;
                    
                    app.SquaresizepixelEditField.Value=app.par.s;
                    app.NumberofsquaresEditField.Value=app.par.n_row*app.par.n_col;
                    
                    app.SafetyborderpixelEditField.Value=app.par.border;
                    app.RandomXYshiftpixelEditField.Value=app.par.random_shift;

                    if app.par.is_square==1
                        app.Switch.Value='Squares';
                        app.NumberofsquaresEditFieldLabel.Text='Number of squares';
                        app.SquaresizepixelEditFieldLabel.Text='Square size [pixel]';
                    else
                        app.Switch.Value='Circles';
                        app.NumberofsquaresEditFieldLabel.Text='Number of circles';
                        app.SquaresizepixelEditFieldLabel.Text='Circle radius [pixel]';
                    end

                    update_size_evaluation(app)

                    figure(1)
                    clf
                    set(gcf,'Color','w')
                    imagesc(app.MySample);
                    axis image
                    colormap('gray')
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    if app.par.is_square==1
                        title('Squares')
                    else
                        title('Circles')
                    end
                else
                    mydlg=warndlg('This file does not contain correct parameters for 2D Squares and Circles');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: SquaresizepixelEditField
        function SquaresizepixelEditFieldValueChanged(app, event)
            value = app.SquaresizepixelEditField.Value;
            app.par.s=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: ImagesizeXpixelEditField
        function ImagesizeXpixelEditFieldValueChanged(app, event)
            value = app.ImagesizeXpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.border=value;
            update_size_evaluation(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value,'Squares')==1
                app.par.is_square=1;
                app.NumberofsquaresEditFieldLabel.Text='Number of squares';
                app.SquaresizepixelEditFieldLabel.Text='Square size [pixel]';
            else
                app.par.is_square=0;
                app.NumberofsquaresEditFieldLabel.Text='Number of circles';
                app.SquaresizepixelEditFieldLabel.Text='Circle radius [pixel]';
            end

        end

        % Value changed function: RandomXYshiftpixelEditField
        function RandomXYshiftpixelEditFieldValueChanged(app, event)
            value = app.RandomXYshiftpixelEditField.Value;
            app.par.random_shift=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM2DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose2DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw2DSquaresorCirclesLabel = uilabel(app.UIFigure);
            app.Draw2DSquaresorCirclesLabel.Text = 'Draw 2D Squares or Circles';
            app.ImagesizeXpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXpixelEditFieldLabel.Text = 'Image size X [pixel]';
            app.ImagesizeXpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXpixelEditField.Limits = [16 Inf];
            app.ImagesizeXpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImagesizeXpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeXpixelEditFieldValueChanged, true);
            app.ImagesizeXpixelEditField.Value = 512;
            app.ImagesizeYpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYpixelEditFieldLabel.Text = 'Image size Y [pixel]';
            app.ImagesizeYpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYpixelEditField.Limits = [16 Inf];
            app.ImagesizeYpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImagesizeYpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeYpixelEditFieldValueChanged, true);
            app.ImagesizeYpixelEditField.Value = 512;
            app.NumberofsquaresEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofsquaresEditFieldLabel.Text = 'Number of squares';
            app.NumberofsquaresEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofsquaresEditField.Limits = [0 Inf];
            app.NumberofsquaresEditField.ValueDisplayFormat = '%.0f';
            app.NumberofsquaresEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsquaresEditFieldValueChanged, true);
            app.NumberofsquaresEditField.Value = 20;
            app.SquaresizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.SquaresizepixelEditFieldLabel.Text = 'Square size [pixel]';
            app.SquaresizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SquaresizepixelEditField.Limits = [0 Inf];
            app.SquaresizepixelEditField.ValueDisplayFormat = '%.0f';
            app.SquaresizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @SquaresizepixelEditFieldValueChanged, true);
            app.SquaresizepixelEditField.Value = 5;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Squares', 'Circles'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Squares';
            app.RandomXYshiftpixelEditFieldLabel = uilabel(app.UIFigure);
            app.RandomXYshiftpixelEditFieldLabel.Text = 'Random XY shift [pixel]';
            app.RandomXYshiftpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomXYshiftpixelEditField.Limits = [0 Inf];
            app.RandomXYshiftpixelEditField.ValueDisplayFormat = '%.0f';
            app.RandomXYshiftpixelEditField.ValueChangedFcn = createCallbackFcn(app, @RandomXYshiftpixelEditFieldValueChanged, true);
            app.nmEditField_7Label = uilabel(app.UIFigure);
            app.nmEditField_7Label.Text = '[nm]';
            app.nmEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_7.Limits = [0 Inf];
            app.nmEditField_7.ValueDisplayFormat = '%.0f';
            app.nmEditField_7.Editable = 'off';
            app.nmEditField_7.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw2DSquaresCircles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end