classdef Draw3DDistortedNPCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofNPCsEditField          matlab.ui.control.NumericEditField
        NumberofNPCsEditFieldLabel     matlab.ui.control.Label
        ResolutionnmEditField          matlab.ui.control.NumericEditField
        ResolutionnmEditFieldLabel     matlab.ui.control.Label
        Cir4MicscsvfileEditField       matlab.ui.control.EditField
        Cir4MicscsvfileEditFieldLabel  matlab.ui.control.Label
        LoadCir4MicscsvfileButton      matlab.ui.control.Button
        nmEditField_13                 matlab.ui.control.NumericEditField
        nmEditField_13Label            matlab.ui.control.Label
        SeparationbetweenNPCspixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenNPCspixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeZumEditField          matlab.ui.control.NumericEditField
        ImagesizeZumEditFieldLabel     matlab.ui.control.Label
        ImageZdimpixelEditField        matlab.ui.control.NumericEditField
        ImageZdimpixelEditFieldLabel   matlab.ui.control.Label
        nmEditField_10                 matlab.ui.control.NumericEditField
        nmEditField_10Label            matlab.ui.control.Label
        ZcenterpixelEditField          matlab.ui.control.NumericEditField
        ZcenterpixelEditFieldLabel     matlab.ui.control.Label
        MovesampletocoverslipCheckBox  matlab.ui.control.CheckBox
        ImagesizeYumEditField          matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel     matlab.ui.control.Label
        ImagesizeXumEditField          matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel     matlab.ui.control.Label
        nmEditField_6                  matlab.ui.control.NumericEditField
        nmEditField_6Label             matlab.ui.control.Label
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        ImageYdimpixelEditField        matlab.ui.control.NumericEditField
        ImageYdimpixelEditFieldLabel   matlab.ui.control.Label
        ImageXdimpixelEditField        matlab.ui.control.NumericEditField
        ImageXdimpixelEditFieldLabel   matlab.ui.control.Label
        Draw3DDistortedNPCsLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        Sample = [] % Virtual sample
        proj = [] % Z-projection
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]

            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImageXdimpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImageYdimpixelEditField.Value;
            app.ImagesizeZumEditField.Value=1e-3*scale*app.ImageZdimpixelEditField.Value;        
            app.nmEditField_10.Value=scale*app.ZcenterpixelEditField.Value;
            app.nmEditField_13.Value=scale*app.SeparationbetweenNPCspixelEditField.Value;
            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
            app.ResolutionnmEditField.Value=scale;
        end
        
        function read_cir4mics(app)
            % Read the CSV file
            app.par.data = readtable(app.par.csvfile);
            % Check that the right column names exist
            columnNames = app.par.data.Properties.VariableNames;
            hasX = any(strcmpi(columnNames, 'x'));
            hasY = any(strcmpi(columnNames, 'y'));
            hasZ = any(strcmpi(columnNames, 'z'));
            hasids = any(strcmpi(columnNames, 'particle'));
            read_ok=1;
            if ~hasX || ~hasY || ~hasZ || ~hasids
                warningMessage = 'Warning: The CSV file does not contain the required columns.';
                read_ok=0;
            end
            if read_ok==0
                app.par.data=[];
                MyDlg=warndlg(warningMessage, 'Column Check Warning');
                waitfor(MyDlg)
                app.NumberofNPCsEditField.Value=0;
                app.NumberofNPCsEditField.Enable="off";
                app.NumberofNPCsEditFieldLabel.Enable="off";
                app.Cir4MicscsvfileEditField.Value=[];
            else
                app.par.used_data=app.par.data;
                app.Cir4MicscsvfileEditField.Value=app.par.csvfile;
                app.NumberofNPCsEditField.Value=max(app.par.data.particle)+1;
                app.NumberofNPCsEditField.Enable="on";
                app.NumberofNPCsEditFieldLabel.Enable="on";
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

            if app.par.qPALM==1
                app.Draw3DDistortedNPCsLabel.Text='Draw 3D Distorted NPCs (qPALM)';
            else
                app.Draw3DDistortedNPCsLabel.Text='Draw 3D Distorted NPCs';
            end

            app.par.virtual_sample_name='3D_Distorted_NPCs';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.ImageXdimpixelEditField.Value;
            app.par.y_dim=app.ImageYdimpixelEditField.Value;
            app.par.z_dim=app.ImageZdimpixelEditField.Value;
            app.par.N=app.NumberofNPCsEditField.Value;
            
            app.par.z_offset=app.ZcenterpixelEditField.Value;
            app.par.move_sample_to_coverslip=app.MovesampletocoverslipCheckBox.Value;

            app.par.min_sep=app.SeparationbetweenNPCspixelEditField.Value;
            
            app.par.cell_border_offset=app.SafetyborderpixelEditField.Value;
            
            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            [app.Sample,app.proj]=get_SMIS_3D_distorted_NPCs(app.par);
            if ~isempty(app.Sample)
                app.DoneCheckBox.Value=true;
                update_size_evaluation(app);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: ImageYdimpixelEditField
        function ImageYdimpixelEditFieldValueChanged(app, event)
            value = app.ImageYdimpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.Sample)
                kernel_pattern=app.Sample; 
                proj=app.proj; %#ok<ADPROPLC> 
                par=app.par; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.mat','SMIS File Selection','3D_Distorted_NPCs');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    matfile=fullfile(app.par.pattern_library,virtual_sample_file);
                    if max(app.Sample(:))<=65535
                        disp('Saving .m matlab kernel...');
                        % Write the .m file
                        save(matfile,'kernel_pattern','proj','par');
                        if ~isempty(app.proj)
                            disp('Saving z-projection ...');
                            projfile=strrep(matfile,'.mat','_proj.tif');
                            imwrite(uint16(app.proj),projfile,'tif');
                            disp('Done !');
                        end
                        % Write the .csv file
                        disp('Saving .csv coordinate file ...');
                        csvfile=strrep(matfile,'.mat','.csv');

                        % Find the linear indices of non-zero elements
                        nonZeroIndices = find(app.Sample ~= 0);
                        % Convert linear indices to subscripts (X and Y coordinates)
                        [Y, X, Z] = ind2sub(size(app.Sample), nonZeroIndices);
                        % Extract the values of the non-zero elements
                        ids = app.Sample(nonZeroIndices);

                        %Convert to nm
                        R = app.par.raster/app.par.binning; % Resolution of the produced .tif image
                        X_nm = X * R;
                        Y_nm = Y * R;
                        Z_nm = Z * R;
                        T = table(X_nm, Y_nm, Z_nm, ids, 'VariableNames', {'X', 'Y', 'Z','ids'});
                        % Write the table to a CSV file
                        writetable(T, csvfile);
                        disp('Done !');
                    else % Image cannot be converted to 16 bits
                        mydlg=warndlg('Virtual sample has pixel values exceeding 16 bits (65535). Cannot save it ! ');
                        waitfor(mydlg);
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'MySample','proj','par');
                app.Sample=MySample;
                app.proj=proj; 
                tmp_par=par; %#ok<ADPROPLC> 

                if strcmp(tmp_par.virtual_sample_name,'3D_NPCs')==1

                    app.par=par; %#ok<ADPROPLC>

                    app.ImageXdimpixelEditField.Value=app.par.x_dim;
                    app.ImageYdimpixelEditField.Value=app.par.y_dim;
                    app.ImageZdimpixelEditField.Value=app.par.z_dim;

                                       
                    app.Cir4MicscsvfileEditField.Value=app.par.csvfile;
                    app.NumberofNPCsEditField.Value=app.par.N;
                    app.NumberofNPCsEditField.Enable="on";
                    app.NumberofNPCsEditFieldLabel.Enable="on";
                 
                  
                    app.SeparationbetweenNPCspixelEditField.Value=app.par.min_sep;
                    app.SafetyborderpixelEditField.Value=app.par.cell_border_offset;
                    
                    app.ZcenterpixelEditField.Value=app.par.z_offset;
                    app.MovesampletocoverslipCheckBox.Value=app.par.move_sample_to_coverslip;

                    if app.par.move_sample_to_coverslip==1
                        app.ZcenterpixelEditField.Visible="off";
                        app.ZcenterpixelEditFieldLabel.Visible="off";
                        app.nmEditField_10.Visible="off";
                        app.nmEditField_10Label.Visible="off";
                    else
                        app.ZcenterpixelEditField.Visible="on";
                        app.ZcenterpixelEditFieldLabel.Visible="on";
                        app.nmEditField_10.Visible="on";
                        app.nmEditField_10Label.Visible="on";
                    end

                    update_size_evaluation(app)
                    
                    disp('Displaying cells ...')

                    figure(1)
                    clf
                    colormap('jet');

                    vol3d('cdata', app.Sample);
                    alphamap('default')
                    axis image
                    view(3);
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    zlabel('Z [pixel]')

                    title('Virtual 3D Distorted NPCs')


                    if ~isempty(app.proj)
                        figure(2)
                        clf
                        set(gcf,'Color','w')
                        imagesc(app.proj);
                        axis image
                        colormap('gray')
                        xlabel('X [pixel]')
                        ylabel('Y [pixel]')
                        title('Z-projection')
                    end
                    disp('Done !')

                else
                    mydlg=warndlg('This file does not contain correct parameters for 3D Distorted NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: ImageXdimpixelEditField
        function ImageXdimpixelEditFieldValueChanged(app, event)
            value = app.ImageXdimpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: MovesampletocoverslipCheckBox
        function MovesampletocoverslipCheckBoxValueChanged(app, event)
            value = app.MovesampletocoverslipCheckBox.Value;
            app.par.move_sample_to_coverslip=value;
            if value==1
                app.ZcenterpixelEditField.Visible="off";
                app.ZcenterpixelEditFieldLabel.Visible="off";
                app.nmEditField_10.Visible="off";
                app.nmEditField_10Label.Visible="off";
            else
                app.ZcenterpixelEditField.Visible="on";
                app.ZcenterpixelEditFieldLabel.Visible="on";
                app.nmEditField_10.Visible="on";
                app.nmEditField_10Label.Visible="on";
            end
        end

        % Value changed function: ZcenterpixelEditField
        function ZcenterpixelEditFieldValueChanged(app, event)
            value = app.ZcenterpixelEditField.Value;
            app.par.z_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: ImageZdimpixelEditField
        function ImageZdimpixelEditFieldValueChanged(app, event)
            value = app.ImageZdimpixelEditField.Value;

            npc_im_size=3*max(app.par.npc_out,app.par.npc_thick)+1;

            % Choose the closest 2^n number
            loc_n=1;
            while npc_im_size>2^loc_n
                loc_n=loc_n+1;
            end
            npc_im_size=2^loc_n;

            if value<npc_im_size
                warndlg('Z-size is too small to accomodate height of NPCs !')
                app.ImageZdimpixelEditField.Value=event.PreviousValue;
            else
                app.par.z_dim=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end
        end

        % Value changed function: SeparationbetweenNPCspixelEditField
        function SeparationbetweenNPCspixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenNPCspixelEditField.Value;
            app.par.min_sep=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Button pushed function: LoadCir4MicscsvfileButton
        function LoadCir4MicscsvfileButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            app.DoneCheckBox.Value=false;
            [file,path] = uigetfile({'*.csv'},'Select a Cir4Mics .csv file: ');
            if isequal(file,0)
                disp('User selected Cancel');
                app.Cir4MicscsvfileEditField.Value=[];
                app.par.csvfile=[];
                app.NumberofNPCsEditField.Enable="off";
            else
                app.par.csvfile=fullfile(path,file);
                read_cir4mics(app);               
            end
        end

        % Value changed function: Cir4MicscsvfileEditField
        function Cir4MicscsvfileEditFieldValueChanged(app, event)
            file = app.Cir4MicscsvfileEditField.Value;
            app.DoneCheckBox.Value=false;
            if isequal(file,0)
                disp('File does not exist !');
                app.Cir4MicscsvfileEditField.Value=[];
                app.par.csvfile=[];
                app.NumberofNPCsEditField.Enable="off";
            else
                app.par.csvfile=file;
                read_cir4mics(app);               
            end
        end

        % Value changed function: NumberofNPCsEditField
        function NumberofNPCsEditFieldValueChanged(app, event)
            value = app.NumberofNPCsEditField.Value;
            w=find((app.par.data.particle+1)<=value);
            app.par.used_data = app.par.data(w, :);
            app.par.N=app.NumberofNPCsEditField.Value;
            app.DoneCheckBox.Value=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw3DDistortedNPCsLabel = uilabel(app.UIFigure);
            app.Draw3DDistortedNPCsLabel.Text = 'Draw 3D Distorted NPCs';
            app.ImageXdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageXdimpixelEditFieldLabel.Text = 'Image X dim [pixel]';
            app.ImageXdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageXdimpixelEditField.Limits = [1 Inf];
            app.ImageXdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageXdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageXdimpixelEditFieldValueChanged, true);
            app.ImageXdimpixelEditField.Value = 256;
            app.ImageYdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageYdimpixelEditFieldLabel.Text = 'Image Y dim [pixel]';
            app.ImageYdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageYdimpixelEditField.Limits = [1 Inf];
            app.ImageYdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageYdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageYdimpixelEditFieldValueChanged, true);
            app.ImageYdimpixelEditField.Value = 256;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 10;
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.MovesampletocoverslipCheckBox = uicheckbox(app.UIFigure);
            app.MovesampletocoverslipCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovesampletocoverslipCheckBoxValueChanged, true);
            app.MovesampletocoverslipCheckBox.Text = 'Move sample to cover slip';
            app.ZcenterpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ZcenterpixelEditFieldLabel.Text = 'Z center [pixel]';
            app.ZcenterpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZcenterpixelEditField.ValueDisplayFormat = '%.0f';
            app.ZcenterpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ZcenterpixelEditFieldValueChanged, true);
            app.nmEditField_10Label = uilabel(app.UIFigure);
            app.nmEditField_10Label.Text = '[nm]';
            app.nmEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_10.ValueDisplayFormat = '%.0f';
            app.nmEditField_10.Editable = 'off';
            app.nmEditField_10.Value = 2;
            app.ImageZdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageZdimpixelEditFieldLabel.Text = 'Image Z dim [pixel]';
            app.ImageZdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageZdimpixelEditField.Limits = [1 Inf];
            app.ImageZdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageZdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageZdimpixelEditFieldValueChanged, true);
            app.ImageZdimpixelEditField.Value = 32;
            app.ImagesizeZumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeZumEditFieldLabel.Text = 'Image size Z [um]';
            app.ImagesizeZumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeZumEditField.Limits = [0 Inf];
            app.ImagesizeZumEditField.Editable = 'off';
            app.ImagesizeZumEditField.Value = 2;
            app.SeparationbetweenNPCspixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenNPCspixelEditFieldLabel.Text = 'Separation between NPCs [pixel]';
            app.SeparationbetweenNPCspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenNPCspixelEditField.Limits = [0 Inf];
            app.SeparationbetweenNPCspixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenNPCspixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenNPCspixelEditFieldValueChanged, true);
            app.SeparationbetweenNPCspixelEditField.Value = 10;
            app.nmEditField_13Label = uilabel(app.UIFigure);
            app.nmEditField_13Label.Text = '[nm]';
            app.nmEditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_13.Limits = [0 Inf];
            app.nmEditField_13.ValueDisplayFormat = '%.0f';
            app.nmEditField_13.Editable = 'off';
            app.nmEditField_13.Value = 2;
            app.LoadCir4MicscsvfileButton = uibutton(app.UIFigure, 'push');
            app.LoadCir4MicscsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCir4MicscsvfileButtonPushed, true);
            app.LoadCir4MicscsvfileButton.Tooltip = {'Load a .csv file generated with cir4mics (https://github.com/uhlmanngroup/cir4mics)'; 'The file should have columns names X, Y. Values should be in nm.'};
            app.LoadCir4MicscsvfileButton.Text = 'Load Cir4Mics .csv file';
            app.Cir4MicscsvfileEditFieldLabel = uilabel(app.UIFigure);
            app.Cir4MicscsvfileEditFieldLabel.Text = 'Cir4Mics .csv file';
            app.Cir4MicscsvfileEditField = uieditfield(app.UIFigure, 'text');
            app.Cir4MicscsvfileEditField.ValueChangedFcn = createCallbackFcn(app, @Cir4MicscsvfileEditFieldValueChanged, true);
            app.ResolutionnmEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionnmEditFieldLabel.Text = 'Resolution [nm]';
            app.ResolutionnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionnmEditField.Editable = 'off';
            app.NumberofNPCsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNPCsEditFieldLabel.Enable = 'off';
            app.NumberofNPCsEditFieldLabel.Text = 'Number of NPCs';
            app.NumberofNPCsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNPCsEditField.Limits = [0 Inf];
            app.NumberofNPCsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofNPCsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNPCsEditFieldValueChanged, true);
            app.NumberofNPCsEditField.Enable = 'off';
            app.NumberofNPCsEditField.Tooltip = {'Numer of NPCs to be generated. The maximum value is the number of NPCs  contained in the loaded .csv file.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw3DDistortedNPCs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end