classdef Draw3DEColi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImagesizeZumEditField          matlab.ui.control.NumericEditField
        ImagesizeZumEditFieldLabel     matlab.ui.control.Label
        ZdimpixelEditField             matlab.ui.control.NumericEditField
        ZdimpixelEditFieldLabel        matlab.ui.control.Label
        nmEditField_10                 matlab.ui.control.NumericEditField
        nmEditField_10Label            matlab.ui.control.Label
        ZcenterpixelEditField          matlab.ui.control.NumericEditField
        ZcenterpixelEditFieldLabel     matlab.ui.control.Label
        MovesampletocoverslipCheckBox  matlab.ui.control.CheckBox
        nmEditField_9                  matlab.ui.control.NumericEditField
        nmEditField_9Label             matlab.ui.control.Label
        NucleoidshortaxisZpixelEditField  matlab.ui.control.NumericEditField
        NucleoidshortaxisZpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_8                  matlab.ui.control.NumericEditField
        nmEditField_8Label             matlab.ui.control.Label
        CellshortaxisZpixelEditField   matlab.ui.control.NumericEditField
        CellshortaxisZpixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeYumEditField          matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel     matlab.ui.control.Label
        ImagesizeXumEditField          matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel     matlab.ui.control.Label
        nmEditField_7                  matlab.ui.control.NumericEditField
        nmEditField_7Label             matlab.ui.control.Label
        nmEditField_6                  matlab.ui.control.NumericEditField
        nmEditField_6Label             matlab.ui.control.Label
        IncludenucleoidCheckBox        matlab.ui.control.CheckBox
        SeparationbetweenbacteriapixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenbacteriapixelEditFieldLabel  matlab.ui.control.Label
        RotatecellsrandomlyCheckBox    matlab.ui.control.CheckBox
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_5                  matlab.ui.control.NumericEditField
        nmEditField_5Label             matlab.ui.control.Label
        nmEditField_4                  matlab.ui.control.NumericEditField
        nmEditField_4Label             matlab.ui.control.Label
        nmEditField_3                  matlab.ui.control.NumericEditField
        nmEditField_3Label             matlab.ui.control.Label
        nmEditField_2                  matlab.ui.control.NumericEditField
        nmEditField_2Label             matlab.ui.control.Label
        nmEditField                    matlab.ui.control.NumericEditField
        nmEditFieldLabel               matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        NucleoidshortaxisYpixelEditField  matlab.ui.control.NumericEditField
        NucleoidshortaxisYpixelEditFieldLabel  matlab.ui.control.Label
        NucleoidlongaxisXpixelEditField  matlab.ui.control.NumericEditField
        NucleoidlongaxisXpixelEditFieldLabel  matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        PeriplasmthicknesspixelEditField  matlab.ui.control.NumericEditField
        PeriplasmthicknesspixelEditFieldLabel  matlab.ui.control.Label
        CellshortaxisYpixelEditField   matlab.ui.control.NumericEditField
        CellshortaxisYpixelLabel       matlab.ui.control.Label
        CelllongaxisXpixelEditField    matlab.ui.control.NumericEditField
        CelllongaxisXpixelEditFieldLabel  matlab.ui.control.Label
        NumberofbacteriaYdimEditField  matlab.ui.control.NumericEditField
        NumberofbacteriaYdimEditFieldLabel  matlab.ui.control.Label
        NumberofbacteriaXdimEditField  matlab.ui.control.NumericEditField
        NumberofbacteriaXdimEditFieldLabel  matlab.ui.control.Label
        Draw3DEcolibacteriaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        Sample = [] % Virtual sample
        Proj = [] % Z-projection
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]
            if ~isempty(app.Sample)
                app.ImagesizeXumEditField.Visible='on';
                app.ImagesizeXumEditFieldLabel.Visible='on';
                app.ImagesizeYumEditField.Visible='on';
                app.ImagesizeYumEditFieldLabel.Visible='on';
                app.ImagesizeXumEditField.Value=1e-3*scale*size(app.Sample,1);
                app.ImagesizeYumEditField.Value=1e-3*scale*size(app.Sample,2);
            else
                app.ImagesizeXumEditField.Visible='off';
                app.ImagesizeXumEditFieldLabel.Visible='off';
                app.ImagesizeYumEditField.Visible='off';
                app.ImagesizeYumEditFieldLabel.Visible='off';
            end


            app.ImagesizeZumEditField.Value=1e-3*scale*app.ZdimpixelEditField.Value;

            app.nmEditField.Value=scale*app.CelllongaxisXpixelEditField.Value;
            app.nmEditField_2.Value=scale*app.CellshortaxisYpixelEditField.Value;
            app.nmEditField_8.Value=scale*app.CellshortaxisZpixelEditField.Value;

            app.nmEditField_3.Value=scale*app.PeriplasmthicknesspixelEditField.Value;
            app.nmEditField_4.Value=scale*app.NucleoidlongaxisXpixelEditField.Value;
            app.nmEditField_5.Value=scale*app.NucleoidshortaxisYpixelEditField.Value;
            app.nmEditField_9.Value=scale*app.NucleoidshortaxisZpixelEditField.Value;
            app.nmEditField_10.Value=scale*app.ZcenterpixelEditField.Value;

            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
            app.nmEditField_7.Value=scale*app.SeparationbetweenbacteriapixelEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;
            app.par.virtual_sample_name='3D_EColi';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.n_cells_x=app.NumberofbacteriaXdimEditField.Value;
            app.par.n_cells_y=app.NumberofbacteriaYdimEditField.Value;
            app.par.z_dim=app.ZdimpixelEditField.Value;
            app.par.periplasm_thick=app.PeriplasmthicknesspixelEditField.Value;
            app.par.cytoplasm_a=app.CelllongaxisXpixelEditField.Value;
            app.par.cytoplasm_b=app.CellshortaxisYpixelEditField.Value;
            app.par.cytoplasm_c=app.CellshortaxisZpixelEditField.Value;
            app.par.nucleoid_a=app.NucleoidlongaxisXpixelEditField.Value;
            app.par.nucleoid_b=app.NucleoidshortaxisYpixelEditField.Value;
            app.par.nucleoid_c=app.NucleoidshortaxisZpixelEditField.Value;

            app.par.z_offset=app.ZcenterpixelEditField.Value;
            app.par.move_sample_to_coverslip=app.MovesampletocoverslipCheckBox.Value;

            app.par.cell_border_offset=app.SafetyborderpixelEditField.Value;
            app.par.cell_spacing=app.SeparationbetweenbacteriapixelEditField.Value;
            app.par.rotate_cells=app.RotatecellsrandomlyCheckBox.Value;
            app.par.include_nucleoid=app.IncludenucleoidCheckBox.Value;

            app.NucleoidlongaxisXpixelEditField.Visible="off";
            app.NucleoidlongaxisXpixelEditFieldLabel.Visible="off";
            app.NucleoidshortaxisYpixelEditField.Visible="off";
            app.NucleoidshortaxisYpixelEditFieldLabel.Visible="off";
            app.NucleoidshortaxisZpixelEditField.Visible="off";
            app.NucleoidshortaxisZpixelEditFieldLabel.Visible="off";
            app.nmEditField_4.Visible="off";
            app.nmEditField_4Label.Visible="off";
            app.nmEditField_5.Visible="off";
            app.nmEditField_5Label.Visible="off";
            app.nmEditField_9.Visible="off";
            app.nmEditField_9Label.Visible="off";

            app.ImagesizeXumEditField.Visible='off';
            app.ImagesizeXumEditFieldLabel.Visible='off';
            app.ImagesizeYumEditField.Visible='off';
            app.ImagesizeYumEditFieldLabel.Visible='off';

            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            [app.Sample,app.Proj]=get_SMIS_3D_EColi(app.par);
            if ~isempty(app.Sample)
                app.DoneCheckBox.Value=true;
                update_size_evaluation(app);
                app.par.x_dim=size(app.Sample,1);
                app.par.y_dim=size(app.Sample,2);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.Choose3DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: NumberofbacteriaYdimEditField
        function NumberofbacteriaYdimEditFieldValueChanged(app, event)
            value = app.NumberofbacteriaYdimEditField.Value;
            app.par.n_cells_y=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CelllongaxisXpixelEditField
        function CelllongaxisXpixelEditFieldValueChanged(app, event)
            value = app.CelllongaxisXpixelEditField.Value;
            app.par.cytoplasm_a=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CellshortaxisYpixelEditField
        function CellshortaxisYpixelEditFieldValueChanged(app, event)
            value = app.CellshortaxisYpixelEditField.Value;
            app.par.cytoplasm_b=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.Sample)
                kernel_pattern=app.Sample;
                par=app.par; %#ok<ADPROPLC>
                Proj=app.Proj; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.mat','SMIS File Selection','3D_Ecoli_Cells');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving 3D sample ...');
                    kernelfile=fullfile(app.par.pattern_library,virtual_sample_file);
                    save(kernelfile,'kernel_pattern','Proj','par');
                    disp('Done !');
                    if ~isempty(app.Proj)
                        disp('Saving z-projection ...');
                        MyDirProj=fullfile(MyDir,'BACKGROUND IMAGES');
                        if ~exist(MyDirProj,'dir')
                            mkdir(MyDirProj);
                        end
                        projfile=fullfile(MyDirProj,strrep(virtual_sample_file,'.mat','.tif'));
                        imwrite(uint8(app.Proj),projfile,'tif');
                        disp('Done !');
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'kernel_pattern','Proj','par');
                app.Sample=kernel_pattern;
                app.Proj=Proj; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC>

                if strcmp(tmp_par.virtual_sample_name,'3D_EColi')==1
                    
                    app.par=par; %#ok<ADPROPLC>

                    app.NumberofbacteriaXdimEditField.Value=app.par.n_cells_x;
                    app.NumberofbacteriaYdimEditField.Value=app.par.n_cells_y;
                    app.ZdimpixelEditField.Value=app.par.z_dim;
                    app.PeriplasmthicknesspixelEditField.Value=app.par.periplasm_thick;
                    app.CelllongaxisXpixelEditField.Value=app.par.cytoplasm_a;
                    app.CellshortaxisYpixelEditField.Value=app.par.cytoplasm_b;
                    app.CellshortaxisZpixelEditField.Value=app.par.cytoplasm_c;
                    app.NucleoidlongaxisXpixelEditField.Value=app.par.nucleoid_a;
                    app.NucleoidshortaxisYpixelEditField.Value=app.par.nucleoid_b;
                    app.NucleoidshortaxisZpixelEditField.Value=app.par.nucleoid_c;
                    app.SafetyborderpixelEditField.Value=app.par.cell_border_offset;
                    app.SeparationbetweenbacteriapixelEditField.Value=app.par.cell_spacing;
                    app.RotatecellsrandomlyCheckBox.Value=app.par.rotate_cells;
                    app.IncludenucleoidCheckBox.Value=app.par.include_nucleoid;

                    app.ZcenterpixelEditField.Value=app.par.z_offset;
                    app.MovesampletocoverslipCheckBox.Value=app.par.move_sample_to_coverslip;

                    if app.par.move_sample_to_coverslip==1
                        app.ZcenterpixelEditField.Visible="off";
                        app.ZcenterpixelEditFieldLabel.Visible="off";
                        app.nmEditField_10.Visible="off";
                        app.nmEditField_10Label.Visible="off";
                    else
                        app.ZcenterpixelEditField.Visible="on";
                        app.ZcenterpixelEditFieldLabel.Visible="on";
                        app.nmEditField_10.Visible="on";
                        app.nmEditField_10Label.Visible="on";
                    end

                    if app.par.include_nucleoid==1
                        app.NucleoidlongaxisXpixelEditField.Visible="on";
                        app.NucleoidlongaxisXpixelEditFieldLabel.Visible="on";
                        app.NucleoidshortaxisYpixelEditField.Visible="on";
                        app.NucleoidshortaxisYpixelEditFieldLabel.Visible="on";
                        app.NucleoidshortaxisZpixelEditField.Visible="on";
                        app.NucleoidshortaxisZpixelEditFieldLabel.Visible="on";
                        app.nmEditField_4.Visible="on";
                        app.nmEditField_4Label.Visible="on";
                        app.nmEditField_5.Visible="on";
                        app.nmEditField_5Label.Visible="on";
                        app.nmEditField_9.Visible="on";
                        app.nmEditField_9Label.Visible="on";
                    else
                        app.NucleoidlongaxisXpixelEditField.Visible="off";
                        app.NucleoidlongaxisXpixelEditFieldLabel.Visible="off";
                        app.NucleoidshortaxisYpixelEditField.Visible="off";
                        app.NucleoidshortaxisYpixelEditFieldLabel.Visible="off";
                        app.NucleoidshortaxisZpixelEditField.Visible="off";
                        app.NucleoidshortaxisZpixelEditFieldLabel.Visible="off";
                        app.nmEditField_4.Visible="off";
                        app.nmEditField_4Label.Visible="off";
                        app.nmEditField_5.Visible="off";
                        app.nmEditField_5Label.Visible="off";
                        app.nmEditField_9.Visible="off";
                        app.nmEditField_9Label.Visible="off";
                    end

                    update_size_evaluation(app)
                    
                    disp('Displaying cells ...')

                    figure(1)
                    clf
                    colormap('jet');

                    vol3d('cdata', app.Sample);
                    alphamap('default')
                    axis image
                    view(3);
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    zlabel('Z [pixel]')

                    title('Virtual 3D E.coli')


                    if ~isempty(app.Proj)
                        figure(2)
                        clf
                        set(gcf,'Color','w')
                        imagesc(app.Proj);
                        axis image
                        colormap('gray')
                        xlabel('X [pixel]')
                        ylabel('Y [pixel]')
                        title('Z-projection')
                    end
                    disp('Done !')

                else
                    mydlg=warndlg('This file does not contain correct parameters for 3D E.coli');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: PeriplasmthicknesspixelEditField
        function PeriplasmthicknesspixelEditFieldValueChanged(app, event)
            value = app.PeriplasmthicknesspixelEditField.Value;

            if app.par.z_dim<(app.par.cytoplasm_c+value)
                warndlg('Z-size is too small to accomodate height of bacteria !')
                app.PeriplasmthicknesspixelEditField.Value=event.PreviousValue;
            else
                app.par.periplasm_thick=value;
                update_size_evaluation(app)
                app.DoneCheckBox.Value=false;
            end
        end

        % Value changed function: NucleoidlongaxisXpixelEditField
        function NucleoidlongaxisXpixelEditFieldValueChanged(app, event)
            value = app.NucleoidlongaxisXpixelEditField.Value;
            app.par.nucleoid_a=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NucleoidshortaxisYpixelEditField
        function NucleoidshortaxisYpixelEditFieldValueChanged(app, event)
            value = app.NucleoidshortaxisYpixelEditField.Value;
            app.par.nucleoid_b=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofbacteriaXdimEditField
        function NumberofbacteriaXdimEditFieldValueChanged(app, event)
            value = app.NumberofbacteriaXdimEditField.Value;
            app.par.n_cells_x=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: IncludenucleoidCheckBox
        function IncludenucleoidCheckBoxValueChanged(app, event)
            value = app.IncludenucleoidCheckBox.Value;
            app.par.include_nucleoid=value;
            if app.par.include_nucleoid==1
                app.NucleoidlongaxisXpixelEditField.Visible="on";
                app.NucleoidlongaxisXpixelEditFieldLabel.Visible="on";
                app.NucleoidshortaxisYpixelEditField.Visible="on";
                app.NucleoidshortaxisYpixelEditFieldLabel.Visible="on";
                app.NucleoidshortaxisZpixelEditField.Visible="on";
                app.NucleoidshortaxisZpixelEditFieldLabel.Visible="on";
                app.nmEditField_4.Visible="on";
                app.nmEditField_4Label.Visible="on";
                app.nmEditField_5.Visible="on";
                app.nmEditField_5Label.Visible="on";
                app.nmEditField_9.Visible="on";
                app.nmEditField_9Label.Visible="on";
            else
                app.NucleoidlongaxisXpixelEditField.Visible="off";
                app.NucleoidlongaxisXpixelEditFieldLabel.Visible="off";
                app.NucleoidshortaxisYpixelEditField.Visible="off";
                app.NucleoidshortaxisYpixelEditFieldLabel.Visible="off";
                app.NucleoidshortaxisZpixelEditField.Visible="off";
                app.NucleoidshortaxisZpixelEditFieldLabel.Visible="off";
                app.nmEditField_4.Visible="off";
                app.nmEditField_4Label.Visible="off";
                app.nmEditField_5.Visible="off";
                app.nmEditField_5Label.Visible="off";
                app.nmEditField_9.Visible="off";
                app.nmEditField_9Label.Visible="off";
            end

        end

        % Value changed function: RotatecellsrandomlyCheckBox
        function RotatecellsrandomlyCheckBoxValueChanged(app, event)
            value = app.RotatecellsrandomlyCheckBox.Value;
            app.par.rotate_cells=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SeparationbetweenbacteriapixelEditField
        function SeparationbetweenbacteriapixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenbacteriapixelEditField.Value;
            app.par.cell_spacing=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CellshortaxisZpixelEditField
        function CellshortaxisZpixelEditFieldValueChanged(app, event)
            value = app.CellshortaxisZpixelEditField.Value;
           
            if app.par.z_dim<(value+app.par.periplasm_thick)
                warndlg('Z-size is too small to accomodate height of bacteria !')
                app.CellshortaxisZpixelEditField.Value=event.PreviousValue;
            else
                app.par.cytoplasm_c=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end

        end

        % Value changed function: NucleoidshortaxisZpixelEditField
        function NucleoidshortaxisZpixelEditFieldValueChanged(app, event)
            value = app.NucleoidshortaxisZpixelEditField.Value;
            app.par.nucleoid_c=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: MovesampletocoverslipCheckBox
        function MovesampletocoverslipCheckBoxValueChanged(app, event)
            value = app.MovesampletocoverslipCheckBox.Value;
            app.par.move_sample_to_coverslip=value;
            if value==1
                app.ZcenterpixelEditField.Visible="off";
                app.ZcenterpixelEditFieldLabel.Visible="off";
                app.nmEditField_10.Visible="off";
                app.nmEditField_10Label.Visible="off";
            else
                app.ZcenterpixelEditField.Visible="on";
                app.ZcenterpixelEditFieldLabel.Visible="on";
                app.nmEditField_10.Visible="on";
                app.nmEditField_10Label.Visible="on";
            end
        end

        % Value changed function: ZcenterpixelEditField
        function ZcenterpixelEditFieldValueChanged(app, event)
            value = app.ZcenterpixelEditField.Value;
            app.par.z_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: ZdimpixelEditField
        function ZdimpixelEditFieldValueChanged(app, event)
            value = app.ZdimpixelEditField.Value;
            if value<(app.par.cytoplasm_c+app.par.periplasm_thick)
                warndlg('Z-size is too small to accomodate height of bacteria !')
                app.ZdimpixelEditField.Value=event.PreviousValue;
            else
                app.par.z_dim=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Choose3DsampleDropDown.Enable='on';
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw3DEcolibacteriaLabel = uilabel(app.UIFigure);
            app.Draw3DEcolibacteriaLabel.Text = 'Draw 3D E. coli bacteria';
            app.NumberofbacteriaXdimEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbacteriaXdimEditFieldLabel.Text = 'Number of bacteria X dim';
            app.NumberofbacteriaXdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbacteriaXdimEditField.Limits = [1 Inf];
            app.NumberofbacteriaXdimEditField.ValueDisplayFormat = '%.0f';
            app.NumberofbacteriaXdimEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofbacteriaXdimEditFieldValueChanged, true);
            app.NumberofbacteriaXdimEditField.Value = 2;
            app.NumberofbacteriaYdimEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbacteriaYdimEditFieldLabel.Text = 'Number of bacteria Y dim';
            app.NumberofbacteriaYdimEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbacteriaYdimEditField.Limits = [1 Inf];
            app.NumberofbacteriaYdimEditField.ValueDisplayFormat = '%.0f';
            app.NumberofbacteriaYdimEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofbacteriaYdimEditFieldValueChanged, true);
            app.NumberofbacteriaYdimEditField.Value = 2;
            app.CelllongaxisXpixelEditFieldLabel = uilabel(app.UIFigure);
            app.CelllongaxisXpixelEditFieldLabel.Text = 'Cell long axis X [pixel]';
            app.CelllongaxisXpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CelllongaxisXpixelEditField.Limits = [1 Inf];
            app.CelllongaxisXpixelEditField.ValueDisplayFormat = '%.0f';
            app.CelllongaxisXpixelEditField.ValueChangedFcn = createCallbackFcn(app, @CelllongaxisXpixelEditFieldValueChanged, true);
            app.CelllongaxisXpixelEditField.Value = 80;
            app.CellshortaxisYpixelLabel = uilabel(app.UIFigure);
            app.CellshortaxisYpixelLabel.Text = 'Cell short axis Y [pixel]';
            app.CellshortaxisYpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellshortaxisYpixelEditField.Limits = [1 Inf];
            app.CellshortaxisYpixelEditField.ValueDisplayFormat = '%.0f';
            app.CellshortaxisYpixelEditField.ValueChangedFcn = createCallbackFcn(app, @CellshortaxisYpixelEditFieldValueChanged, true);
            app.CellshortaxisYpixelEditField.Value = 20;
            app.PeriplasmthicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.PeriplasmthicknesspixelEditFieldLabel.Text = 'Periplasm thickness [pixel]';
            app.PeriplasmthicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriplasmthicknesspixelEditField.Limits = [0 Inf];
            app.PeriplasmthicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.PeriplasmthicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @PeriplasmthicknesspixelEditFieldValueChanged, true);
            app.PeriplasmthicknesspixelEditField.Value = 2;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.NucleoidlongaxisXpixelEditFieldLabel = uilabel(app.UIFigure);
            app.NucleoidlongaxisXpixelEditFieldLabel.Text = 'Nucleoid long axis X [pixel]';
            app.NucleoidlongaxisXpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NucleoidlongaxisXpixelEditField.Limits = [1 Inf];
            app.NucleoidlongaxisXpixelEditField.ValueDisplayFormat = '%.0f';
            app.NucleoidlongaxisXpixelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleoidlongaxisXpixelEditFieldValueChanged, true);
            app.NucleoidlongaxisXpixelEditField.Value = 25;
            app.NucleoidshortaxisYpixelEditFieldLabel = uilabel(app.UIFigure);
            app.NucleoidshortaxisYpixelEditFieldLabel.Text = 'Nucleoid short axis Y [pixel]';
            app.NucleoidshortaxisYpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NucleoidshortaxisYpixelEditField.Limits = [1 Inf];
            app.NucleoidshortaxisYpixelEditField.ValueDisplayFormat = '%.0f';
            app.NucleoidshortaxisYpixelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleoidshortaxisYpixelEditFieldValueChanged, true);
            app.NucleoidshortaxisYpixelEditField.Value = 5;
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.nmEditField_3Label = uilabel(app.UIFigure);
            app.nmEditField_3Label.Text = '[nm]';
            app.nmEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_3.Limits = [0 Inf];
            app.nmEditField_3.ValueDisplayFormat = '%.0f';
            app.nmEditField_3.Editable = 'off';
            app.nmEditField_3.Value = 2;
            app.nmEditField_4Label = uilabel(app.UIFigure);
            app.nmEditField_4Label.Text = '[nm]';
            app.nmEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_4.Limits = [0 Inf];
            app.nmEditField_4.ValueDisplayFormat = '%.0f';
            app.nmEditField_4.Editable = 'off';
            app.nmEditField_4.Value = 2;
            app.nmEditField_5Label = uilabel(app.UIFigure);
            app.nmEditField_5Label.Text = '[nm]';
            app.nmEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_5.Limits = [0 Inf];
            app.nmEditField_5.ValueDisplayFormat = '%.0f';
            app.nmEditField_5.Editable = 'off';
            app.nmEditField_5.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 20;
            app.RotatecellsrandomlyCheckBox = uicheckbox(app.UIFigure);
            app.RotatecellsrandomlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatecellsrandomlyCheckBoxValueChanged, true);
            app.RotatecellsrandomlyCheckBox.Text = 'Rotate cells randomly';
            app.SeparationbetweenbacteriapixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenbacteriapixelEditFieldLabel.Text = 'Separation between bacteria [pixel]';
            app.SeparationbetweenbacteriapixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenbacteriapixelEditField.Limits = [0 Inf];
            app.SeparationbetweenbacteriapixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenbacteriapixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenbacteriapixelEditFieldValueChanged, true);
            app.SeparationbetweenbacteriapixelEditField.Value = 10;
            app.IncludenucleoidCheckBox = uicheckbox(app.UIFigure);
            app.IncludenucleoidCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludenucleoidCheckBoxValueChanged, true);
            app.IncludenucleoidCheckBox.Text = 'Include nucleoid';
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.nmEditField_7Label = uilabel(app.UIFigure);
            app.nmEditField_7Label.Text = '[nm]';
            app.nmEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_7.Limits = [0 Inf];
            app.nmEditField_7.ValueDisplayFormat = '%.0f';
            app.nmEditField_7.Editable = 'off';
            app.nmEditField_7.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.CellshortaxisZpixelEditFieldLabel = uilabel(app.UIFigure);
            app.CellshortaxisZpixelEditFieldLabel.Text = 'Cell short axis Z [pixel]';
            app.CellshortaxisZpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellshortaxisZpixelEditField.Limits = [1 Inf];
            app.CellshortaxisZpixelEditField.ValueDisplayFormat = '%.0f';
            app.CellshortaxisZpixelEditField.ValueChangedFcn = createCallbackFcn(app, @CellshortaxisZpixelEditFieldValueChanged, true);
            app.CellshortaxisZpixelEditField.Value = 20;
            app.nmEditField_8Label = uilabel(app.UIFigure);
            app.nmEditField_8Label.Text = '[nm]';
            app.nmEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_8.Limits = [0 Inf];
            app.nmEditField_8.ValueDisplayFormat = '%.0f';
            app.nmEditField_8.Editable = 'off';
            app.nmEditField_8.Value = 2;
            app.NucleoidshortaxisZpixelEditFieldLabel = uilabel(app.UIFigure);
            app.NucleoidshortaxisZpixelEditFieldLabel.Text = 'Nucleoid short axis Z [pixel]';
            app.NucleoidshortaxisZpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NucleoidshortaxisZpixelEditField.Limits = [1 Inf];
            app.NucleoidshortaxisZpixelEditField.ValueDisplayFormat = '%.0f';
            app.NucleoidshortaxisZpixelEditField.ValueChangedFcn = createCallbackFcn(app, @NucleoidshortaxisZpixelEditFieldValueChanged, true);
            app.NucleoidshortaxisZpixelEditField.Value = 5;
            app.nmEditField_9Label = uilabel(app.UIFigure);
            app.nmEditField_9Label.Text = '[nm]';
            app.nmEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_9.Limits = [0 Inf];
            app.nmEditField_9.ValueDisplayFormat = '%.0f';
            app.nmEditField_9.Editable = 'off';
            app.nmEditField_9.Value = 2;
            app.MovesampletocoverslipCheckBox = uicheckbox(app.UIFigure);
            app.MovesampletocoverslipCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovesampletocoverslipCheckBoxValueChanged, true);
            app.MovesampletocoverslipCheckBox.Text = 'Move sample to cover slip';
            app.ZcenterpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ZcenterpixelEditFieldLabel.Text = 'Z center [pixel]';
            app.ZcenterpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZcenterpixelEditField.ValueDisplayFormat = '%.0f';
            app.ZcenterpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ZcenterpixelEditFieldValueChanged, true);
            app.nmEditField_10Label = uilabel(app.UIFigure);
            app.nmEditField_10Label.Text = '[nm]';
            app.nmEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_10.ValueDisplayFormat = '%.0f';
            app.nmEditField_10.Editable = 'off';
            app.nmEditField_10.Value = 2;
            app.ZdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ZdimpixelEditFieldLabel.Text = 'Z dim [pixel]';
            app.ZdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZdimpixelEditField.Limits = [1 Inf];
            app.ZdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ZdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ZdimpixelEditFieldValueChanged, true);
            app.ZdimpixelEditField.Value = 50;
            app.ImagesizeZumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeZumEditFieldLabel.Text = 'Image size Z [um]';
            app.ImagesizeZumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeZumEditField.Limits = [0 Inf];
            app.ImagesizeZumEditField.Editable = 'off';
            app.ImagesizeZumEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw3DEColi(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end