classdef Draw3DNPCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        nmEditField_13                  matlab.ui.control.NumericEditField
        nmEditField_13Label             matlab.ui.control.Label
        SeparationbetweenNPCspixelEditField  matlab.ui.control.NumericEditField
        SeparationbetweenNPCspixelEditFieldLabel  matlab.ui.control.Label
        ZrotationdegreesEditField       matlab.ui.control.NumericEditField
        ZrotationdegreesEditFieldLabel  matlab.ui.control.Label
        nmEditField_12                  matlab.ui.control.NumericEditField
        nmEditField_12Label             matlab.ui.control.Label
        nmEditField_11                  matlab.ui.control.NumericEditField
        nmEditField_11Label             matlab.ui.control.Label
        Nup96ZsizepixelEditField        matlab.ui.control.NumericEditField
        Nup96ZsizepixelEditFieldLabel   matlab.ui.control.Label
        Nup96XYsizepixelEditField       matlab.ui.control.NumericEditField
        Nup96XYsizepixelEditFieldLabel  matlab.ui.control.Label
        RotateNPCsCheckBox              matlab.ui.control.CheckBox
        XYrotationdegreesEditField      matlab.ui.control.NumericEditField
        XYrotationdegreesEditFieldLabel  matlab.ui.control.Label
        NumberofNPCsEditField           matlab.ui.control.NumericEditField
        NumberofNPCsEditFieldLabel      matlab.ui.control.Label
        ImagesizeZumEditField           matlab.ui.control.NumericEditField
        ImagesizeZumEditFieldLabel      matlab.ui.control.Label
        ImageZdimpixelEditField         matlab.ui.control.NumericEditField
        ImageZdimpixelEditFieldLabel    matlab.ui.control.Label
        nmEditField_10                  matlab.ui.control.NumericEditField
        nmEditField_10Label             matlab.ui.control.Label
        ZcenterpixelEditField           matlab.ui.control.NumericEditField
        ZcenterpixelEditFieldLabel      matlab.ui.control.Label
        MovesampletocoverslipCheckBox   matlab.ui.control.CheckBox
        nmEditField_8                   matlab.ui.control.NumericEditField
        nmEditField_8Label              matlab.ui.control.Label
        NPCthicknesspixelEditField      matlab.ui.control.NumericEditField
        NPCthicknesspixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeYumEditField           matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel      matlab.ui.control.Label
        ImagesizeXumEditField           matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel      matlab.ui.control.Label
        nmEditField_6                   matlab.ui.control.NumericEditField
        nmEditField_6Label              matlab.ui.control.Label
        SafetyborderpixelEditField      matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_2                   matlab.ui.control.NumericEditField
        nmEditField_2Label              matlab.ui.control.Label
        nmEditField                     matlab.ui.control.NumericEditField
        nmEditFieldLabel                matlab.ui.control.Label
        VirtualSampleDimensionsLabel    matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        DoneCheckBox                    matlab.ui.control.CheckBox
        CreatesampleButton              matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        SavesampleButton                matlab.ui.control.Button
        LoadexistingsampleButton        matlab.ui.control.Button
        NPCouterradiuspixelEditField    matlab.ui.control.NumericEditField
        CellshortaxisYpixelLabel        matlab.ui.control.Label
        NPCinnerradiuspixelEditField    matlab.ui.control.NumericEditField
        NPCinnerradiuspixelEditFieldLabel  matlab.ui.control.Label
        ImageYdimpixelEditField         matlab.ui.control.NumericEditField
        ImageYdimpixelEditFieldLabel    matlab.ui.control.Label
        ImageXdimpixelEditField         matlab.ui.control.NumericEditField
        ImageXdimpixelEditFieldLabel    matlab.ui.control.Label
        Draw3DNPCsLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        Sample = [] % Virtual sample
        Proj = [] % Z-projection
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]

            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImageXdimpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImageYdimpixelEditField.Value;
            app.ImagesizeZumEditField.Value=1e-3*scale*app.ImageZdimpixelEditField.Value;

            app.nmEditField.Value=scale*app.NPCinnerradiuspixelEditField.Value;
            app.nmEditField_2.Value=scale*app.NPCouterradiuspixelEditField.Value;
            app.nmEditField_8.Value=scale*app.NPCthicknesspixelEditField.Value;

            app.nmEditField_11.Value=scale*app.Nup96XYsizepixelEditField.Value;
            app.nmEditField_12.Value=scale*app.Nup96ZsizepixelEditField.Value;

            app.nmEditField_10.Value=scale*app.ZcenterpixelEditField.Value;
            app.nmEditField_13.Value=scale*app.SeparationbetweenNPCspixelEditField.Value;

            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

            if app.par.qPALM==1
                app.Draw3DNPCsLabel.Text='Draw 3D NPCs (qPALM)';
            else
                app.Draw3DNPCsLabel.Text='Draw 3D NPCs';
            end

            app.par.virtual_sample_name='3D_NPCs';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.ImageXdimpixelEditField.Value;
            app.par.y_dim=app.ImageYdimpixelEditField.Value;
            app.par.z_dim=app.ImageZdimpixelEditField.Value;
            
            app.par.N=app.NumberofNPCsEditField.Value;
            app.par.npc_in=app.NPCinnerradiuspixelEditField.Value;
            app.par.npc_out=app.NPCouterradiuspixelEditField.Value;
            app.par.npc_thick=app.NPCthicknesspixelEditField.Value;
                        
            app.par.nup96_size_xy=app.Nup96XYsizepixelEditField.Value;
            app.par.nup96_size_z=app.Nup96ZsizepixelEditField.Value;
            
            app.par.allow_rotation=app.RotateNPCsCheckBox.Value;
            app.par.rot_xy=app.XYrotationdegreesEditField.Value;
            app.par.rot_z=app.ZrotationdegreesEditField.Value;
            
            app.XYrotationdegreesEditField.Visible="off";
            app.XYrotationdegreesEditFieldLabel.Visible="off";
            app.ZrotationdegreesEditField.Visible="off";
            app.ZrotationdegreesEditFieldLabel.Visible="off";

            app.par.z_offset=app.ZcenterpixelEditField.Value;
            app.par.move_sample_to_coverslip=app.MovesampletocoverslipCheckBox.Value;

            app.par.min_sep=app.SeparationbetweenNPCspixelEditField.Value;
            
            app.par.cell_border_offset=app.SafetyborderpixelEditField.Value;
            
            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            [app.Sample,app.Proj]=get_SMIS_3D_NPCs(app.par);
            if ~isempty(app.Sample)
                app.DoneCheckBox.Value=true;
                update_size_evaluation(app);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: ImageYdimpixelEditField
        function ImageYdimpixelEditFieldValueChanged(app, event)
            value = app.ImageYdimpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NPCinnerradiuspixelEditField
        function NPCinnerradiuspixelEditFieldValueChanged(app, event)
            value = app.NPCinnerradiuspixelEditField.Value;
            app.par.npc_in=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NPCouterradiuspixelEditField
        function NPCouterradiuspixelEditFieldValueChanged(app, event)
            value = app.NPCouterradiuspixelEditField.Value;

            npc_im_size=3*max(value,app.par.npc_thick)+1;

            % Choose the closest 2^n number
            loc_n=1;
            while npc_im_size>2^loc_n
                loc_n=loc_n+1;
            end
            npc_im_size=2^loc_n;

            if app.par.z_dim<npc_im_size
                warndlg('Z-size is too small to accomodate height of NPCs !')
                app.NPCouterradiuspixelEditField.Value=event.PreviousValue;
            else
                app.par.npc_out=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.Sample)
                kernel_pattern=app.Sample;
                par=app.par; %#ok<ADPROPLC>
                Proj=app.Proj; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.mat','SMIS File Selection','3D_NPCs');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving 3D sample ...');
                    kernelfile=fullfile(app.par.pattern_library,virtual_sample_file);
                    save(kernelfile,'kernel_pattern','Proj','par');
                    disp('Done !');
                    if ~isempty(app.Proj)
                        disp('Saving z-projection ...');
                        MyDirProj=fullfile(MyDir,'BACKGROUND IMAGES');
                        if ~exist(MyDirProj,'dir')
                            mkdir(MyDirProj);
                        end
                        projfile=fullfile(MyDirProj,strrep(virtual_sample_file,'.mat','.tif'));
                        imwrite(uint16(app.Proj),projfile,'tif');
                        disp('Done !');
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'kernel_pattern','Proj','par');
                app.Sample=kernel_pattern;
                app.Proj=Proj; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC> 

                if strcmp(tmp_par.virtual_sample_name,'3D_NPCs')==1

                    app.par=par; %#ok<ADPROPLC>

                    app.ImageXdimpixelEditField.Value=app.par.x_dim;
                    app.ImageYdimpixelEditField.Value=app.par.y_dim;
                    app.ImageZdimpixelEditField.Value=app.par.z_dim;

                    app.NumberofNPCsEditField.Value=app.par.N;
                    
                    app.NPCinnerradiuspixelEditField.Value=app.par.npc_in;
                    app.NPCouterradiuspixelEditField.Value=app.par.npc_out;
                    app.NPCthicknesspixelEditField.Value=app.par.npc_thick;
                    
                    app.Nup96XYsizepixelEditField.Value=app.par.nup96_size_xy;
                    app.Nup96ZsizepixelEditField.Value=app.par.nup96_size_z;

                    if app.par.allow_rotation==1
                        app.RotateNPCsCheckBox.Value=1;
                        app.XYrotationdegreesEditField.Visible="on";
                        app.XYrotationdegreesEditFieldLabel.Visible="on";
                        app.ZrotationdegreesEditField.Visible="on";
                        app.ZrotationdegreesEditFieldLabel.Visible="on";
                    else
                        app.RotateNPCsCheckBox.Value=0;
                        app.XYrotationdegreesEditField.Visible="off";
                        app.XYrotationdegreesEditFieldLabel.Visible="off";
                        app.ZrotationdegreesEditField.Visible="off";
                        app.ZrotationdegreesEditFieldLabel.Visible="off";
                    end

                    app.XYrotationdegreesEditField.Value=app.par.rot_xy;
                    app.ZrotationdegreesEditField.Value=app.par.rot_z;

                    app.SeparationbetweenNPCspixelEditField.Value=app.par.min_sep;
                    app.SafetyborderpixelEditField.Value=app.par.cell_border_offset;
                    
                    app.ZcenterpixelEditField.Value=app.par.z_offset;
                    app.MovesampletocoverslipCheckBox.Value=app.par.move_sample_to_coverslip;

                    if app.par.move_sample_to_coverslip==1
                        app.ZcenterpixelEditField.Visible="off";
                        app.ZcenterpixelEditFieldLabel.Visible="off";
                        app.nmEditField_10.Visible="off";
                        app.nmEditField_10Label.Visible="off";
                    else
                        app.ZcenterpixelEditField.Visible="on";
                        app.ZcenterpixelEditFieldLabel.Visible="on";
                        app.nmEditField_10.Visible="on";
                        app.nmEditField_10Label.Visible="on";
                    end

                    update_size_evaluation(app)
                    
                    disp('Displaying cells ...')

                    figure(1)
                    clf
                    colormap('jet');

                    vol3d('cdata', app.Sample);
                    alphamap('default')
                    axis image
                    view(3);
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    zlabel('Z [pixel]')

                    title('Virtual 3D NPCs')


                    if ~isempty(app.Proj)
                        figure(2)
                        clf
                        set(gcf,'Color','w')
                        imagesc(app.Proj);
                        axis image
                        colormap('gray')
                        xlabel('X [pixel]')
                        ylabel('Y [pixel]')
                        title('Z-projection')
                    end
                    disp('Done !')

                else
                    mydlg=warndlg('This file does not contain correct parameters for 3D NPCs');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: ImageXdimpixelEditField
        function ImageXdimpixelEditFieldValueChanged(app, event)
            value = app.ImageXdimpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NPCthicknesspixelEditField
        function NPCthicknesspixelEditFieldValueChanged(app, event)
            value = app.NPCthicknesspixelEditField.Value;
 
            npc_im_size=3*max(app.par.npc_out,value)+1;

            % Choose the closest 2^n number
            loc_n=1;
            while npc_im_size>2^loc_n
                loc_n=loc_n+1;
            end
            npc_im_size=2^loc_n;         

            if app.par.z_dim<npc_im_size
                warndlg('Z-size is too small to accomodate height of NPCs !')
                app.NPCthicknesspixelEditField.Value=event.PreviousValue;
            else
                app.par.npc_thick=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end

        end

        % Value changed function: MovesampletocoverslipCheckBox
        function MovesampletocoverslipCheckBoxValueChanged(app, event)
            value = app.MovesampletocoverslipCheckBox.Value;
            app.par.move_sample_to_coverslip=value;
            if value==1
                app.ZcenterpixelEditField.Visible="off";
                app.ZcenterpixelEditFieldLabel.Visible="off";
                app.nmEditField_10.Visible="off";
                app.nmEditField_10Label.Visible="off";
            else
                app.ZcenterpixelEditField.Visible="on";
                app.ZcenterpixelEditFieldLabel.Visible="on";
                app.nmEditField_10.Visible="on";
                app.nmEditField_10Label.Visible="on";
            end
        end

        % Value changed function: ZcenterpixelEditField
        function ZcenterpixelEditFieldValueChanged(app, event)
            value = app.ZcenterpixelEditField.Value;
            app.par.z_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: ImageZdimpixelEditField
        function ImageZdimpixelEditFieldValueChanged(app, event)
            value = app.ImageZdimpixelEditField.Value;

            npc_im_size=3*max(app.par.npc_out,app.par.npc_thick)+1;

            % Choose the closest 2^n number
            loc_n=1;
            while npc_im_size>2^loc_n
                loc_n=loc_n+1;
            end
            npc_im_size=2^loc_n;

            if value<npc_im_size
                warndlg('Z-size is too small to accomodate height of NPCs !')
                app.ImageZdimpixelEditField.Value=event.PreviousValue;
            else
                app.par.z_dim=value;
                app.DoneCheckBox.Value=false;
                update_size_evaluation(app)
            end
        end

        % Value changed function: NumberofNPCsEditField
        function NumberofNPCsEditFieldValueChanged(app, event)
            value = app.NumberofNPCsEditField.Value;
            app.par.N=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: XYrotationdegreesEditField
        function XYrotationdegreesEditFieldValueChanged(app, event)
            value = app.XYrotationdegreesEditField.Value;
            app.par.rot_xy=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: ZrotationdegreesEditField
        function ZrotationdegreesEditFieldValueChanged(app, event)
            value = app.ZrotationdegreesEditField.Value;
            app.par.rot_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: Nup96XYsizepixelEditField
        function Nup96XYsizepixelEditFieldValueChanged(app, event)
            value = app.Nup96XYsizepixelEditField.Value;
            app.par.nup96_size_xy=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: Nup96ZsizepixelEditField
        function Nup96ZsizepixelEditFieldValueChanged(app, event)
            value = app.Nup96ZsizepixelEditField.Value;
            app.par.nup96_size_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: RotateNPCsCheckBox
        function RotateNPCsCheckBoxValueChanged(app, event)
            value = app.RotateNPCsCheckBox.Value;
            app.par.allow_rotation=value;
            if value==1
                app.XYrotationdegreesEditField.Visible="on";
                app.XYrotationdegreesEditFieldLabel.Visible="on";
                app.ZrotationdegreesEditField.Visible="on";
                app.ZrotationdegreesEditFieldLabel.Visible="on";
            else
                app.XYrotationdegreesEditField.Visible="off";
                app.XYrotationdegreesEditFieldLabel.Visible="off";
                app.ZrotationdegreesEditField.Visible="off";
                app.ZrotationdegreesEditFieldLabel.Visible="off";
            end
        end

        % Value changed function: SeparationbetweenNPCspixelEditField
        function SeparationbetweenNPCspixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenNPCspixelEditField.Value;
            app.par.min_sep=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw3DNPCsLabel = uilabel(app.UIFigure);
            app.Draw3DNPCsLabel.Text = 'Draw 3D NPCs';
            app.ImageXdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageXdimpixelEditFieldLabel.Text = 'Image X dim [pixel]';
            app.ImageXdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageXdimpixelEditField.Limits = [1 Inf];
            app.ImageXdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageXdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageXdimpixelEditFieldValueChanged, true);
            app.ImageXdimpixelEditField.Value = 256;
            app.ImageYdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageYdimpixelEditFieldLabel.Text = 'Image Y dim [pixel]';
            app.ImageYdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageYdimpixelEditField.Limits = [1 Inf];
            app.ImageYdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageYdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageYdimpixelEditFieldValueChanged, true);
            app.ImageYdimpixelEditField.Value = 256;
            app.NPCinnerradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.NPCinnerradiuspixelEditFieldLabel.Text = 'NPC inner radius [pixel]';
            app.NPCinnerradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPCinnerradiuspixelEditField.Limits = [1 Inf];
            app.NPCinnerradiuspixelEditField.ValueDisplayFormat = '%.0f';
            app.NPCinnerradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @NPCinnerradiuspixelEditFieldValueChanged, true);
            app.NPCinnerradiuspixelEditField.Value = 5;
            app.CellshortaxisYpixelLabel = uilabel(app.UIFigure);
            app.CellshortaxisYpixelLabel.Text = 'NPC outer radius [pixel]';
            app.NPCouterradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPCouterradiuspixelEditField.Limits = [1 Inf];
            app.NPCouterradiuspixelEditField.ValueDisplayFormat = '%.0f';
            app.NPCouterradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @NPCouterradiuspixelEditFieldValueChanged, true);
            app.NPCouterradiuspixelEditField.Value = 7;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 10;
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.NPCthicknesspixelEditFieldLabel = uilabel(app.UIFigure);
            app.NPCthicknesspixelEditFieldLabel.Text = 'NPC thickness [pixel] ';
            app.NPCthicknesspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.NPCthicknesspixelEditField.Limits = [1 Inf];
            app.NPCthicknesspixelEditField.ValueDisplayFormat = '%.0f';
            app.NPCthicknesspixelEditField.ValueChangedFcn = createCallbackFcn(app, @NPCthicknesspixelEditFieldValueChanged, true);
            app.NPCthicknesspixelEditField.Value = 6;
            app.nmEditField_8Label = uilabel(app.UIFigure);
            app.nmEditField_8Label.Text = '[nm]';
            app.nmEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_8.Limits = [0 Inf];
            app.nmEditField_8.ValueDisplayFormat = '%.0f';
            app.nmEditField_8.Editable = 'off';
            app.nmEditField_8.Value = 2;
            app.MovesampletocoverslipCheckBox = uicheckbox(app.UIFigure);
            app.MovesampletocoverslipCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovesampletocoverslipCheckBoxValueChanged, true);
            app.MovesampletocoverslipCheckBox.Text = 'Move sample to cover slip';
            app.ZcenterpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ZcenterpixelEditFieldLabel.Text = 'Z center [pixel]';
            app.ZcenterpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZcenterpixelEditField.ValueDisplayFormat = '%.0f';
            app.ZcenterpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ZcenterpixelEditFieldValueChanged, true);
            app.nmEditField_10Label = uilabel(app.UIFigure);
            app.nmEditField_10Label.Text = '[nm]';
            app.nmEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_10.ValueDisplayFormat = '%.0f';
            app.nmEditField_10.Editable = 'off';
            app.nmEditField_10.Value = 2;
            app.ImageZdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageZdimpixelEditFieldLabel.Text = 'Image Z dim [pixel]';
            app.ImageZdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageZdimpixelEditField.Limits = [1 Inf];
            app.ImageZdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageZdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageZdimpixelEditFieldValueChanged, true);
            app.ImageZdimpixelEditField.Value = 32;
            app.ImagesizeZumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeZumEditFieldLabel.Text = 'Image size Z [um]';
            app.ImagesizeZumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeZumEditField.Limits = [0 Inf];
            app.ImagesizeZumEditField.Editable = 'off';
            app.ImagesizeZumEditField.Value = 2;
            app.NumberofNPCsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNPCsEditFieldLabel.Text = 'Number of NPCs';
            app.NumberofNPCsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNPCsEditField.Limits = [1 Inf];
            app.NumberofNPCsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofNPCsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofNPCsEditFieldValueChanged, true);
            app.NumberofNPCsEditField.Value = 10;
            app.XYrotationdegreesEditFieldLabel = uilabel(app.UIFigure);
            app.XYrotationdegreesEditFieldLabel.Text = 'XY rotation [degrees] ';
            app.XYrotationdegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYrotationdegreesEditField.Limits = [-90 90];
            app.XYrotationdegreesEditField.ValueDisplayFormat = '%.0f';
            app.XYrotationdegreesEditField.ValueChangedFcn = createCallbackFcn(app, @XYrotationdegreesEditFieldValueChanged, true);
            app.XYrotationdegreesEditField.Value = 25;
            app.RotateNPCsCheckBox = uicheckbox(app.UIFigure);
            app.RotateNPCsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateNPCsCheckBoxValueChanged, true);
            app.RotateNPCsCheckBox.Text = 'Rotate NPCs';
            app.Nup96XYsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.Nup96XYsizepixelEditFieldLabel.Text = 'Nup96 XY size [pixel]';
            app.Nup96XYsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nup96XYsizepixelEditField.Limits = [1 Inf];
            app.Nup96XYsizepixelEditField.ValueDisplayFormat = '%.0f';
            app.Nup96XYsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @Nup96XYsizepixelEditFieldValueChanged, true);
            app.Nup96XYsizepixelEditField.Value = 1;
            app.Nup96ZsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.Nup96ZsizepixelEditFieldLabel.Text = 'Nup96 Z size [pixel]';
            app.Nup96ZsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nup96ZsizepixelEditField.Limits = [1 Inf];
            app.Nup96ZsizepixelEditField.ValueDisplayFormat = '%.0f';
            app.Nup96ZsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @Nup96ZsizepixelEditFieldValueChanged, true);
            app.Nup96ZsizepixelEditField.Value = 1;
            app.nmEditField_11Label = uilabel(app.UIFigure);
            app.nmEditField_11Label.Text = '[nm]';
            app.nmEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_11.Limits = [0 Inf];
            app.nmEditField_11.ValueDisplayFormat = '%.0f';
            app.nmEditField_11.Editable = 'off';
            app.nmEditField_11.Value = 2;
            app.nmEditField_12Label = uilabel(app.UIFigure);
            app.nmEditField_12Label.Text = '[nm]';
            app.nmEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_12.Limits = [0 Inf];
            app.nmEditField_12.ValueDisplayFormat = '%.0f';
            app.nmEditField_12.Editable = 'off';
            app.nmEditField_12.Value = 2;
            app.ZrotationdegreesEditFieldLabel = uilabel(app.UIFigure);
            app.ZrotationdegreesEditFieldLabel.Text = 'Z rotation [degrees] ';
            app.ZrotationdegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZrotationdegreesEditField.Limits = [-90 90];
            app.ZrotationdegreesEditField.ValueDisplayFormat = '%.0f';
            app.ZrotationdegreesEditField.ValueChangedFcn = createCallbackFcn(app, @ZrotationdegreesEditFieldValueChanged, true);
            app.ZrotationdegreesEditField.Value = 45;
            app.SeparationbetweenNPCspixelEditFieldLabel = uilabel(app.UIFigure);
            app.SeparationbetweenNPCspixelEditFieldLabel.Text = 'Separation between NPCs [pixel]';
            app.SeparationbetweenNPCspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparationbetweenNPCspixelEditField.Limits = [0 Inf];
            app.SeparationbetweenNPCspixelEditField.ValueDisplayFormat = '%.0f';
            app.SeparationbetweenNPCspixelEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationbetweenNPCspixelEditFieldValueChanged, true);
            app.SeparationbetweenNPCspixelEditField.Value = 10;
            app.nmEditField_13Label = uilabel(app.UIFigure);
            app.nmEditField_13Label.Text = '[nm]';
            app.nmEditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_13.Limits = [0 Inf];
            app.nmEditField_13.ValueDisplayFormat = '%.0f';
            app.nmEditField_13.Editable = 'off';
            app.nmEditField_13.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw3DNPCs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end