classdef Draw3DSpheresCubes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofcubesZEditField        matlab.ui.control.NumericEditField
        NumberofcubesZEditFieldLabel   matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        nmEditField_11                 matlab.ui.control.NumericEditField
        nmEditField_11Label            matlab.ui.control.Label
        RandomZshiftpixelEditField     matlab.ui.control.NumericEditField
        RandomZshiftpixelEditFieldLabel  matlab.ui.control.Label
        NumberofcubesXYEditField       matlab.ui.control.NumericEditField
        NumberofcubesXYEditFieldLabel  matlab.ui.control.Label
        ImagesizeZumEditField          matlab.ui.control.NumericEditField
        ImagesizeZumEditFieldLabel     matlab.ui.control.Label
        ImageZdimpixelEditField        matlab.ui.control.NumericEditField
        ImageZdimpixelEditFieldLabel   matlab.ui.control.Label
        nmEditField_10                 matlab.ui.control.NumericEditField
        nmEditField_10Label            matlab.ui.control.Label
        ZcenterpixelEditField          matlab.ui.control.NumericEditField
        ZcenterpixelEditFieldLabel     matlab.ui.control.Label
        MovesampletocoverslipCheckBox  matlab.ui.control.CheckBox
        nmEditField_8                  matlab.ui.control.NumericEditField
        nmEditField_8Label             matlab.ui.control.Label
        RandomXYshiftpixelEditField    matlab.ui.control.NumericEditField
        RandomXYshiftpixelEditFieldLabel  matlab.ui.control.Label
        ImagesizeYumEditField          matlab.ui.control.NumericEditField
        ImagesizeYumEditFieldLabel     matlab.ui.control.Label
        ImagesizeXumEditField          matlab.ui.control.NumericEditField
        ImagesizeXumEditFieldLabel     matlab.ui.control.Label
        nmEditField_6                  matlab.ui.control.NumericEditField
        nmEditField_6Label             matlab.ui.control.Label
        SafetyborderpixelEditField     matlab.ui.control.NumericEditField
        SafetyborderpixelEditFieldLabel  matlab.ui.control.Label
        nmEditField_2                  matlab.ui.control.NumericEditField
        nmEditField_2Label             matlab.ui.control.Label
        nmEditField                    matlab.ui.control.NumericEditField
        nmEditFieldLabel               matlab.ui.control.Label
        VirtualSampleDimensionsLabel   matlab.ui.control.Label
        PixelSizenmEditField           matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel      matlab.ui.control.Label
        BinningEditField               matlab.ui.control.NumericEditField
        BinningEditFieldLabel          matlab.ui.control.Label
        DoneCheckBox                   matlab.ui.control.CheckBox
        CreatesampleButton             matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        SavesampleButton               matlab.ui.control.Button
        LoadexistingsampleButton       matlab.ui.control.Button
        CubeZsizepixelEditField        matlab.ui.control.NumericEditField
        CubeZsizepixelEditFieldLabel   matlab.ui.control.Label
        CubeXYsizepixelEditField       matlab.ui.control.NumericEditField
        CubeXYsizepixelEditFieldLabel  matlab.ui.control.Label
        ImageYdimpixelEditField        matlab.ui.control.NumericEditField
        ImageYdimpixelEditFieldLabel   matlab.ui.control.Label
        ImageXdimpixelEditField        matlab.ui.control.NumericEditField
        ImageXdimpixelEditFieldLabel   matlab.ui.control.Label
        Draw3DSpheresandCubesLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp = [] % Callin app
        Sample = [] % Virtual sample
        Proj = [] % Z-projection
        par = [] % Parameters
    end
    
    methods (Access = private)
        
        function update_size_evaluation(app)
            scale=app.par.raster/app.par.binning; % [raster2nm]

            app.ImagesizeXumEditField.Value=1e-3*scale*app.ImageXdimpixelEditField.Value;
            app.ImagesizeYumEditField.Value=1e-3*scale*app.ImageYdimpixelEditField.Value;
            app.ImagesizeZumEditField.Value=1e-3*scale*app.ImageZdimpixelEditField.Value;

            app.nmEditField.Value=scale*app.CubeXYsizepixelEditField.Value;
            app.nmEditField_2.Value=scale*app.CubeZsizepixelEditField.Value;
         
            app.nmEditField_8.Value=scale*app.RandomXYshiftpixelEditField.Value;
            app.nmEditField_11.Value=scale*app.RandomZshiftpixelEditField.Value;
           
            app.nmEditField_10.Value=scale*app.ZcenterpixelEditField.Value;
    
            app.nmEditField_6.Value=scale*app.SafetyborderpixelEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            app.par.qPALM=mainapp.qPALM;

            if app.par.qPALM==1
                app.Draw3DSpheresandCubesLabel.Text='Draw 3D Spheres or Cubes (qPALM)';
            else
                app.Draw3DSpheresandCubesLabel.Text='Draw 3D Spheres or Cubes';
            end

            app.par.is_cube=1;

            app.par.virtual_sample_name='3D_SpheresCubes';
            app.par.pattern_library=[mainapp.SMIS_dir.Pattern_dir];
            app.par.x_dim=app.ImageXdimpixelEditField.Value;
            app.par.y_dim=app.ImageYdimpixelEditField.Value;
            app.par.z_dim=app.ImageZdimpixelEditField.Value;
            
            app.par.n_row=fix(sqrt(app.NumberofcubesXYEditField.Value));
            app.par.n_col=round(app.NumberofcubesXYEditField.Value/app.par.n_row);
            app.par.n_col_z=app.NumberofcubesZEditField.Value;

            app.par.s_xy=app.CubeXYsizepixelEditField.Value;
            app.par.s_z=app.CubeZsizepixelEditField.Value;
            
            app.par.random_xy_shift=app.RandomXYshiftpixelEditField.Value;
            app.par.random_z_shift=app.RandomZshiftpixelEditField.Value;
            
            app.par.z_offset=app.ZcenterpixelEditField.Value;
            app.par.move_sample_to_coverslip=app.MovesampletocoverslipCheckBox.Value;

            app.par.cell_border_offset=app.SafetyborderpixelEditField.Value;
            
            app.par.binning=mainapp.binning;

            app.par.raster=mainapp.raster;
            app.PixelSizenmEditField.Value=app.par.raster;
            app.BinningEditField.Value=app.par.binning;
            update_size_evaluation(app);
        end

        % Button pushed function: CreatesampleButton
        function CreatesampleButtonPushed(app, event)
            app.DoneCheckBox.Value=false;
            [app.Sample,app.Proj]=get_SMIS_3DCubesSpheres(app.par);
            if ~isempty(app.Sample)
                app.DoneCheckBox.Value=true;
                update_size_evaluation(app);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end

        % Value changed function: ImageYdimpixelEditField
        function ImageYdimpixelEditFieldValueChanged(app, event)
            value = app.ImageYdimpixelEditField.Value;
            app.par.y_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CubeXYsizepixelEditField
        function CubeXYsizepixelEditFieldValueChanged(app, event)
            value = app.CubeXYsizepixelEditField.Value;
            app.par.s_xy=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: CubeZsizepixelEditField
        function CubeZsizepixelEditFieldValueChanged(app, event)
            value = app.CubeZsizepixelEditField.Value;
            app.par.s_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            if ~isempty(app.Sample)
                kernel_pattern=app.Sample;
                par=app.par; %#ok<ADPROPLC>
                Proj=app.Proj; %#ok<ADPROPLC>
                if exist(app.par.pattern_library,'dir')
                    cd(app.par.pattern_library);
                end
                [virtual_sample_file,MyDir] = uiputfile('*.mat','SMIS File Selection','3D_SpheresCubes');
                if ~isnumeric(MyDir) && ~isnumeric(virtual_sample_file)
                    app.par.pattern_library=MyDir;
                    disp('Saving 3D sample ...');
                    kernelfile=fullfile(app.par.pattern_library,virtual_sample_file);
                    save(kernelfile,'kernel_pattern','Proj','par');
                    disp('Done !');
                    if ~isempty(app.Proj)
                        disp('Saving z-projection ...');
                        MyDirProj=fullfile(MyDir,'BACKGROUND IMAGES');
                        if ~exist(MyDirProj,'dir')
                            mkdir(MyDirProj);
                        end
                        projfile=fullfile(MyDirProj,strrep(virtual_sample_file,'.mat','.tif'));
                        imwrite(uint8(app.Proj),projfile,'tif');
                        disp('Done !');
                    end
                end
            else
                warndlg('Virtual Sample was not created !')
            end
        end

        % Button pushed function: LoadexistingsampleButton
        function LoadexistingsampleButtonPushed(app, event)
            if exist(app.par.pattern_library,'dir')
                cd(app.par.pattern_library);
            end
            [file,path] = uigetfile('*.mat','Select a parameter file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'kernel_pattern','Proj','par');
                app.Sample=kernel_pattern;
                app.Proj=Proj; %#ok<ADPROPLC>
                tmp_par=par; %#ok<ADPROPLC> 

                if strcmp(tmp_par.virtual_sample_name,'3D_SpheresCubes')==1

                    app.par=par; %#ok<ADPROPLC>

                    app.ImageXdimpixelEditField.Value=app.par.x_dim;
                    app.ImageYdimpixelEditField.Value=app.par.y_dim;
                    app.ImageZdimpixelEditField.Value=app.par.z_dim;

                    app.NumberofcubesXYEditField.Value=app.par.n_row*app.par.n_col;
                    app.NumberofcubesZEditField.Value=app.par.n_col_z;
                    
                    app.CubeXYsizepixelEditField.Value=app.par.s_xy;
                    app.CubeZsizepixelEditField.Value=app.par.s_z;

                    app.RandomXYshiftpixelEditField.Value=app.par.random_xy_shift;
                    app.RandomZshiftpixelEditField.Value=app.par.random_z_shift;
                    
                    app.SafetyborderpixelEditField.Value=app.par.random_xy_shift;
                    
                    if app.par.is_cube==1
                        app.Switch.Value='Cubes';
                        app.NumberofcubesXYEditFieldLabel.Text='Number of cubes XY';
                        app.CubeXYsizepixelEditFieldLabel.Text='Cube XY size [pixel]';
                        app.NumberofcubesZEditFieldLabel.Text='Number of cubes Z';
                        app.CubeZsizepixelEditFieldLabel.Text='Cube Z size [pixel]';
                    else
                        app.Switch.Value='Spheres';
                        app.NumberofcubesXYEditFieldLabel.Text='Number of spheres XY';
                        app.CubeXYsizepixelEditFieldLabel.Text='Sphere XY radius [pixel]';
                        app.NumberofcubesZEditFieldLabel.Text='Number of spheres Z';
                        app.CubeZsizepixelEditFieldLabel.Text='Sphere Z radius [pixel]';
                    end
                   
                    app.ZcenterpixelEditField.Value=app.par.z_offset;
                    app.MovesampletocoverslipCheckBox.Value=app.par.move_sample_to_coverslip;

                    if app.par.move_sample_to_coverslip==1
                        app.ZcenterpixelEditField.Visible="off";
                        app.ZcenterpixelEditFieldLabel.Visible="off";
                        app.nmEditField_10.Visible="off";
                        app.nmEditField_10Label.Visible="off";
                    else
                        app.ZcenterpixelEditField.Visible="on";
                        app.ZcenterpixelEditFieldLabel.Visible="on";
                        app.nmEditField_10.Visible="on";
                        app.nmEditField_10Label.Visible="on";
                    end

                    update_size_evaluation(app)
                    
                    disp('Displaying cells ...')

                    figure(1)
                    clf
                    colormap('jet');

                    vol3d('cdata', app.Sample);
                    alphamap('default')
                    axis image
                    view(3);
                    xlabel('X [pixel]')
                    ylabel('Y [pixel]')
                    zlabel('Z [pixel]')

                    title('Virtual 3D SpheresCubes')


                    if ~isempty(app.Proj)
                        figure(2)
                        clf
                        set(gcf,'Color','w')
                        imagesc(app.Proj);
                        axis image
                        colormap('gray')
                        xlabel('X [pixel]')
                        ylabel('Y [pixel]')
                        title('Z-projection')
                    end
                    disp('Done !')

                else
                    mydlg=warndlg('This file does not contain correct parameters for 3D SpheresCubes');
                    waitfor(mydlg);
                end
            end
            figure(app.UIFigure)
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.par.binning=value;
            update_size_evaluation(app)
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.par.raster=value;
            update_size_evaluation(app)
        end

        % Value changed function: ImageXdimpixelEditField
        function ImageXdimpixelEditFieldValueChanged(app, event)
            value = app.ImageXdimpixelEditField.Value;
            app.par.x_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: SafetyborderpixelEditField
        function SafetyborderpixelEditFieldValueChanged(app, event)
            value = app.SafetyborderpixelEditField.Value;
            app.par.cell_border_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: RandomXYshiftpixelEditField
        function RandomXYshiftpixelEditFieldValueChanged(app, event)
            value = app.RandomXYshiftpixelEditField.Value;
            app.par.random_xy_shift=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: MovesampletocoverslipCheckBox
        function MovesampletocoverslipCheckBoxValueChanged(app, event)
            value = app.MovesampletocoverslipCheckBox.Value;
            app.par.move_sample_to_coverslip=value;
            if value==1
                app.ZcenterpixelEditField.Visible="off";
                app.ZcenterpixelEditFieldLabel.Visible="off";
                app.nmEditField_10.Visible="off";
                app.nmEditField_10Label.Visible="off";
            else
                app.ZcenterpixelEditField.Visible="on";
                app.ZcenterpixelEditFieldLabel.Visible="on";
                app.nmEditField_10.Visible="on";
                app.nmEditField_10Label.Visible="on";
            end
        end

        % Value changed function: ZcenterpixelEditField
        function ZcenterpixelEditFieldValueChanged(app, event)
            value = app.ZcenterpixelEditField.Value;
            app.par.z_offset=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: ImageZdimpixelEditField
        function ImageZdimpixelEditFieldValueChanged(app, event)
            value = app.ImageZdimpixelEditField.Value;
            app.par.z_dim=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: NumberofcubesXYEditField
        function NumberofcubesXYEditFieldValueChanged(app, event)
            value = app.NumberofcubesXYEditField.Value;
            app.par.n_row=fix(sqrt(value));
            app.par.n_col=round(value/app.par.n_row);
            N=app.par.n_row*app.par.n_col;
            if N~=value
                app.NumberofcubesXYEditField.Value=N;
            end
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Callback function
        function XYrotationdegreesEditFieldValueChanged(app, event)
            value = app.XYrotationdegreesEditField.Value;
            app.par.rot_xy=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Callback function
        function ZrotationdegreesEditFieldValueChanged(app, event)
            value = app.ZrotationdegreesEditField.Value;
            app.par.rot_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: RandomZshiftpixelEditField
        function RandomZshiftpixelEditFieldValueChanged(app, event)
            value = app.RandomZshiftpixelEditField.Value;
            app.par.random_z_shift=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Callback function
        function Nup96ZsizepixelEditFieldValueChanged(app, event)
            value = app.Nup96ZsizepixelEditField.Value;
            app.par.nup96_size_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Callback function
        function RotateNPCsCheckBoxValueChanged(app, event)
            value = app.RotateNPCsCheckBox.Value;
            app.par.allow_rotation=value;
            if value==1
                app.XYrotationdegreesEditField.Visible="on";
                app.XYrotationdegreesEditFieldLabel.Visible="on";
                app.ZrotationdegreesEditField.Visible="on";
                app.ZrotationdegreesEditFieldLabel.Visible="on";
            else
                app.XYrotationdegreesEditField.Visible="off";
                app.XYrotationdegreesEditFieldLabel.Visible="off";
                app.ZrotationdegreesEditField.Visible="off";
                app.ZrotationdegreesEditFieldLabel.Visible="off";
            end
        end

        % Callback function
        function SeparationbetweenNPCspixelEditFieldValueChanged(app, event)
            value = app.SeparationbetweenNPCspixelEditField.Value;
            app.par.min_sep=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value,'Cubes')==1
                app.par.is_cube=1;
                app.NumberofcubesXYEditFieldLabel.Text='Number of cubes XY';
                app.CubeXYsizepixelEditFieldLabel.Text='Cube XY size [pixel]';
                app.NumberofcubesZEditFieldLabel.Text='Number of cubes Z';
                app.CubeZsizepixelEditFieldLabel.Text='Cube Z size [pixel]';
            else
                app.par.is_cube=0;
                app.NumberofcubesXYEditFieldLabel.Text='Number of spheres XY';
                app.CubeXYsizepixelEditFieldLabel.Text='Sphere XY radius [pixel]';
                app.NumberofcubesZEditFieldLabel.Text='Number of spheres Z';
                app.CubeZsizepixelEditFieldLabel.Text='Sphere Z radius [pixel]';
            end

        end

        % Value changed function: NumberofcubesZEditField
        function NumberofcubesZEditFieldValueChanged(app, event)
            value = app.NumberofcubesZEditField.Value;
            app.par.n_col_z=value;
            app.DoneCheckBox.Value=false;
            update_size_evaluation(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.par.qPALM==1
                app.CallingApp.ChooseqPALM3DsampleDropDown.Enable='on';
            else
                app.CallingApp.Choose3DsampleDropDown.Enable='on';
            end
            if ishandle(1) && strcmp(get(1, 'type'), 'figure')
                close(1)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Draw3DSpheresandCubesLabel = uilabel(app.UIFigure);
            app.Draw3DSpheresandCubesLabel.Text = 'Draw 3D Spheres and Cubes';
            app.ImageXdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageXdimpixelEditFieldLabel.Text = 'Image X dim [pixel]';
            app.ImageXdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageXdimpixelEditField.Limits = [1 Inf];
            app.ImageXdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageXdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageXdimpixelEditFieldValueChanged, true);
            app.ImageXdimpixelEditField.Value = 128;
            app.ImageYdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageYdimpixelEditFieldLabel.Text = 'Image Y dim [pixel]';
            app.ImageYdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageYdimpixelEditField.Limits = [1 Inf];
            app.ImageYdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageYdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageYdimpixelEditFieldValueChanged, true);
            app.ImageYdimpixelEditField.Value = 128;
            app.CubeXYsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.CubeXYsizepixelEditFieldLabel.Text = 'Cube XY size [pixel]';
            app.CubeXYsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CubeXYsizepixelEditField.Limits = [1 Inf];
            app.CubeXYsizepixelEditField.ValueDisplayFormat = '%.0f';
            app.CubeXYsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @CubeXYsizepixelEditFieldValueChanged, true);
            app.CubeXYsizepixelEditField.Value = 5;
            app.CubeZsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.CubeZsizepixelEditFieldLabel.Text = 'Cube Z size [pixel]';
            app.CubeZsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CubeZsizepixelEditField.Limits = [1 Inf];
            app.CubeZsizepixelEditField.ValueDisplayFormat = '%.0f';
            app.CubeZsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @CubeZsizepixelEditFieldValueChanged, true);
            app.CubeZsizepixelEditField.Value = 5;
            app.LoadexistingsampleButton = uibutton(app.UIFigure, 'push');
            app.LoadexistingsampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingsampleButtonPushed, true);
            app.LoadexistingsampleButton.Text = 'Load existing sample';
            app.SavesampleButton = uibutton(app.UIFigure, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Text = 'Save sample';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CreatesampleButton = uibutton(app.UIFigure, 'push');
            app.CreatesampleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatesampleButtonPushed, true);
            app.CreatesampleButton.Text = 'Create sample';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Enable = 'off';
            app.DoneCheckBox.Text = 'Done';
            app.BinningEditFieldLabel = uilabel(app.UIFigure);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningEditField.Limits = [1 Inf];
            app.BinningEditField.ValueDisplayFormat = '%.0f';
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 2;
            app.PixelSizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size [nm]';
            app.PixelSizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizenmEditField.Limits = [1 Inf];
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.VirtualSampleDimensionsLabel = uilabel(app.UIFigure);
            app.VirtualSampleDimensionsLabel.Text = 'Virtual Sample Dimensions ';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '[nm]';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.ValueDisplayFormat = '%.0f';
            app.nmEditField.Editable = 'off';
            app.nmEditField.Value = 2;
            app.nmEditField_2Label = uilabel(app.UIFigure);
            app.nmEditField_2Label.Text = '[nm]';
            app.nmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_2.Limits = [0 Inf];
            app.nmEditField_2.ValueDisplayFormat = '%.0f';
            app.nmEditField_2.Editable = 'off';
            app.nmEditField_2.Value = 2;
            app.SafetyborderpixelEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyborderpixelEditFieldLabel.Text = 'Safety border [pixel]';
            app.SafetyborderpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyborderpixelEditField.Limits = [0 Inf];
            app.SafetyborderpixelEditField.ValueDisplayFormat = '%.0f';
            app.SafetyborderpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyborderpixelEditFieldValueChanged, true);
            app.SafetyborderpixelEditField.Value = 10;
            app.nmEditField_6Label = uilabel(app.UIFigure);
            app.nmEditField_6Label.Text = '[nm]';
            app.nmEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_6.Limits = [0 Inf];
            app.nmEditField_6.ValueDisplayFormat = '%.0f';
            app.nmEditField_6.Editable = 'off';
            app.nmEditField_6.Value = 2;
            app.ImagesizeXumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeXumEditFieldLabel.Text = 'Image size X [um]';
            app.ImagesizeXumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeXumEditField.Limits = [0 Inf];
            app.ImagesizeXumEditField.Editable = 'off';
            app.ImagesizeXumEditField.Value = 2;
            app.ImagesizeYumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeYumEditFieldLabel.Text = 'Image size Y [um]';
            app.ImagesizeYumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeYumEditField.Limits = [0 Inf];
            app.ImagesizeYumEditField.Editable = 'off';
            app.ImagesizeYumEditField.Value = 2;
            app.RandomXYshiftpixelEditFieldLabel = uilabel(app.UIFigure);
            app.RandomXYshiftpixelEditFieldLabel.Text = 'Random XY shift [pixel]';
            app.RandomXYshiftpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomXYshiftpixelEditField.Limits = [0 Inf];
            app.RandomXYshiftpixelEditField.ValueDisplayFormat = '%.0f';
            app.RandomXYshiftpixelEditField.ValueChangedFcn = createCallbackFcn(app, @RandomXYshiftpixelEditFieldValueChanged, true);
            app.nmEditField_8Label = uilabel(app.UIFigure);
            app.nmEditField_8Label.Text = '[nm]';
            app.nmEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_8.Limits = [0 Inf];
            app.nmEditField_8.ValueDisplayFormat = '%.0f';
            app.nmEditField_8.Editable = 'off';
            app.nmEditField_8.Value = 2;
            app.MovesampletocoverslipCheckBox = uicheckbox(app.UIFigure);
            app.MovesampletocoverslipCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovesampletocoverslipCheckBoxValueChanged, true);
            app.MovesampletocoverslipCheckBox.Text = 'Move sample to cover slip';
            app.ZcenterpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ZcenterpixelEditFieldLabel.Text = 'Z center [pixel]';
            app.ZcenterpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZcenterpixelEditField.ValueDisplayFormat = '%.0f';
            app.ZcenterpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ZcenterpixelEditFieldValueChanged, true);
            app.nmEditField_10Label = uilabel(app.UIFigure);
            app.nmEditField_10Label.Text = '[nm]';
            app.nmEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_10.ValueDisplayFormat = '%.0f';
            app.nmEditField_10.Editable = 'off';
            app.nmEditField_10.Value = 2;
            app.ImageZdimpixelEditFieldLabel = uilabel(app.UIFigure);
            app.ImageZdimpixelEditFieldLabel.Text = 'Image Z dim [pixel]';
            app.ImageZdimpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageZdimpixelEditField.Limits = [1 Inf];
            app.ImageZdimpixelEditField.ValueDisplayFormat = '%.0f';
            app.ImageZdimpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ImageZdimpixelEditFieldValueChanged, true);
            app.ImageZdimpixelEditField.Value = 32;
            app.ImagesizeZumEditFieldLabel = uilabel(app.UIFigure);
            app.ImagesizeZumEditFieldLabel.Text = 'Image size Z [um]';
            app.ImagesizeZumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImagesizeZumEditField.Limits = [0 Inf];
            app.ImagesizeZumEditField.Editable = 'off';
            app.ImagesizeZumEditField.Value = 2;
            app.NumberofcubesXYEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcubesXYEditFieldLabel.Text = 'Number of cubes XY';
            app.NumberofcubesXYEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcubesXYEditField.Limits = [1 Inf];
            app.NumberofcubesXYEditField.ValueDisplayFormat = '%.0f';
            app.NumberofcubesXYEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcubesXYEditFieldValueChanged, true);
            app.NumberofcubesXYEditField.Value = 3;
            app.RandomZshiftpixelEditFieldLabel = uilabel(app.UIFigure);
            app.RandomZshiftpixelEditFieldLabel.Text = 'Random Z shift [pixel]';
            app.RandomZshiftpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomZshiftpixelEditField.Limits = [0 Inf];
            app.RandomZshiftpixelEditField.ValueDisplayFormat = '%.0f';
            app.RandomZshiftpixelEditField.ValueChangedFcn = createCallbackFcn(app, @RandomZshiftpixelEditFieldValueChanged, true);
            app.nmEditField_11Label = uilabel(app.UIFigure);
            app.nmEditField_11Label.Text = '[nm]';
            app.nmEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.nmEditField_11.Limits = [0 Inf];
            app.nmEditField_11.ValueDisplayFormat = '%.0f';
            app.nmEditField_11.Editable = 'off';
            app.nmEditField_11.Value = 2;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Cubes', 'Spheres'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Cubes';
            app.NumberofcubesZEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcubesZEditFieldLabel.Text = 'Number of cubes Z';
            app.NumberofcubesZEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcubesZEditField.Limits = [1 Inf];
            app.NumberofcubesZEditField.ValueDisplayFormat = '%.0f';
            app.NumberofcubesZEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcubesZEditFieldValueChanged, true);
            app.NumberofcubesZEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw3DSpheresCubes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end