classdef DrawFRAPPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveROIButton             matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        DrawROIButton             matlab.ui.control.Button
        AssistedFreeHandCheckBox  matlab.ui.control.CheckBox
        FreeHandCheckBox          matlab.ui.control.CheckBox
        RectangleCheckBox         matlab.ui.control.CheckBox
        EllipseCheckBox           matlab.ui.control.CheckBox
        CircleCheckBox            matlab.ui.control.CheckBox
        DeleteROIButton           matlab.ui.control.Button
        ZRasterSpinner            matlab.ui.control.Spinner
        ZRasterSlider             matlab.ui.control.Slider
        ZRasterSliderLabel        matlab.ui.control.Label
        OKButton                  matlab.ui.control.Button
        DrawFRAPROIonvirtualsampleLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Pattern=[]; % The 2D or 3D Virtutal sample pattern
        MinDisp=[]; % Min of Pattern
        MaxDisp=[]; % Max of Pattern
        par=[]; % App parameters
    end
    
    properties (Access = public)
        smis_gui_parameters=[] ; % Smis_gui_parameters
    end
    
    methods (Access = private)
        function show2Dsection(app)
            
            ClearImage(app)
            
            colormap(app.UIAxes,'gray')
            
            section=app.Pattern(:,:,round(app.ZRasterSlider.Value));
                        
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            % hold(app.UIAxes,'on')
            clims = [app.MinDisp, app.MaxDisp];
            app.par.ImObj=imagesc(app.UIAxes,section,clims);
            axis(app.UIAxes,"image");
        end
        
        function ClearImage(app)
            MyData = findobj(app.UIAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
        
        function show2D(app)
            
            ClearImage(app)
            
            colormap(app.UIAxes,'gray')
            
            app.UIAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
            app.UIAxes.XLimMode="auto";
            
            clims = [app.MinDisp, app.MaxDisp];
            app.par.ImObj=imagesc(app.UIAxes,app.Pattern,clims);
            axis(app.UIAxes,"image");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Pattern)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.Pattern=Pattern;
            app.MinDisp=min(app.Pattern(:));
            app.MaxDisp=max(app.Pattern(:));
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
            app.par.image_size=[app.smis_gui_parameters.image_size.n,app.smis_gui_parameters.image_size.m,app.smis_gui_parameters.image_size.nz];
            app.par.ROI_type=1; % Start with Circle
            app.par.FRAP_path=''; 
            app.par.FRAP_file=''; 

            s=size(Pattern,3);
            
            if s==1 % 2D case
                app.ZRasterSlider.Visible='off';
                app.ZRasterSliderLabel.Visible='off';
                app.ZRasterSpinner.Visible='off';
                show2D(app)
            elseif s>1 % 3D case
                app.ZRasterSlider.Limits=[1,s];
                app.ZRasterSlider.MajorTicks=[1,(1:4)*s/4];
                app.ZRasterSlider.Value=round(size(Pattern,3)/2);
                app.ZRasterSpinner.Value=app.ZRasterSlider.Value;
                show2Dsection(app);
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if exist(fullfile(app.par.FRAP_path,app.par.FRAP_file),'file')~=2
                MyDlg=warndlg('A FRAP ROI has not been created!','Warning');
                waitfor(MyDlg)
                is_OK=0;
            else
                is_OK=1;
                updateFRAP_mask(app.CallingApp, app.par)
            end
              
            if is_OK==1
                delete(app)
            end
        end

        % Value changed function: ZRasterSlider
        function ZRasterSliderValueChanged(app, event)
            value = app.ZRasterSlider.Value;
            app.ZRasterSpinner.Value=round(value);
            show2Dsection(app);
        end

        % Value changed function: ZRasterSpinner
        function ZRasterSpinnerValueChanged(app, event)
            value = app.ZRasterSpinner.Value;
            app.ZRasterSlider.Value=value;
            show2Dsection(app);
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            switch app.par.ROI_type
                case 1
                    app.par.ROI=drawcircle(app.UIAxes);
                case 2
                    app.par.ROI=drawellipse(app.UIAxes);
                case 3
                    app.par.ROI =drawrectangle(app.UIAxes);
                case 4
                    app.par.ROI =drawfreehand(app.UIAxes);
                case 5
                    app.par.ROI =drawassisted(app.par.ImObj);
            end
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            app.par.ROI.Visible='off';
            app.par.ROI=[];
        end

        % Value changed function: CircleCheckBox
        function CircleCheckBoxValueChanged(app, event)
            value = app.CircleCheckBox.Value;
           if value==true
                % app.CircleCheckBox.Value=false;
                app.EllipseCheckBox.Value=false;
                app.RectangleCheckBox.Value=false; 
                app.FreeHandCheckBox.Value=false;
                app.AssistedFreeHandCheckBox.Value=false;
                app.par.ROI_type=1;
                app.DrawROIButton.Enable='on';
                app.par.ROI.Visible='off';
                app.par.ROI=[];
            end
           if app.CircleCheckBox.Value==false && app.EllipseCheckBox.Value==false && app.RectangleCheckBox.Value==false && app.FreeHandCheckBox.Value==false && app.AssistedFreeHandCheckBox.Value==false
                app.DrawROIButton.Enable='off';
           end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
           
            % save the Frap pattern
            Mask = createMask(app.par.ROI);
            app.par.Mask = imresize(Mask,[app.par.image_size(1) app.par.image_size(2)]);
            
            [app.par.FRAP_file,app.par.FRAP_path] = uiputfile('*.tif','FRAP ROI File Selection');
            if ~isnumeric(app.par.FRAP_path) || ~isnumeric(app.par.FRAP_file)
                imwrite(app.par.Mask,fullfile(app.par.FRAP_path,app.par.FRAP_file),"tif");
            end
            figure(app.UIFigure); % Get the GUI in the front

        end

        % Value changed function: EllipseCheckBox
        function EllipseCheckBoxValueChanged(app, event)
            value = app.EllipseCheckBox.Value;
            if value==true
                app.CircleCheckBox.Value=false;
                % app.EllipseCheckBox.Value=false;
                app.RectangleCheckBox.Value=false; 
                app.FreeHandCheckBox.Value=false;
                app.AssistedFreeHandCheckBox.Value=false;
                app.par.ROI_type=2;
                app.DrawROIButton.Enable='on';
                app.par.ROI.Visible='off';
                app.par.ROI=[];
            end
           if app.CircleCheckBox.Value==false && app.EllipseCheckBox.Value==false && app.RectangleCheckBox.Value==false && app.FreeHandCheckBox.Value==false && app.AssistedFreeHandCheckBox.Value==false
                app.DrawROIButton.Enable='off';
           end
        end

        % Value changed function: RectangleCheckBox
        function RectangleCheckBoxValueChanged(app, event)
            value = app.RectangleCheckBox.Value;
            if value==true
                app.CircleCheckBox.Value=false;
                app.EllipseCheckBox.Value=false;
                % app.RectangleCheckBox.Value=false; 
                app.FreeHandCheckBox.Value=false;
                app.AssistedFreeHandCheckBox.Value=false;
                app.par.ROI_type=3;
                app.DrawROIButton.Enable='on';
                app.par.ROI.Visible='off';
                app.par.ROI=[];
            end
           if app.CircleCheckBox.Value==false && app.EllipseCheckBox.Value==false && app.RectangleCheckBox.Value==false && app.FreeHandCheckBox.Value==false && app.AssistedFreeHandCheckBox.Value==false
                app.DrawROIButton.Enable='off';
           end
        end

        % Value changed function: FreeHandCheckBox
        function FreeHandCheckBoxValueChanged(app, event)
            value = app.FreeHandCheckBox.Value;
            if value==true
                app.CircleCheckBox.Value=false;
                app.EllipseCheckBox.Value=false;
                app.RectangleCheckBox.Value=false; 
                % app.FreeHandCheckBox.Value=false;
                app.AssistedFreeHandCheckBox.Value=false;
                app.par.ROI_type=4;
                app.DrawROIButton.Enable='on';
                app.par.ROI.Visible='off';
                app.par.ROI=[];
            end
           if app.CircleCheckBox.Value==false && app.EllipseCheckBox.Value==false && app.RectangleCheckBox.Value==false && app.FreeHandCheckBox.Value==false && app.AssistedFreeHandCheckBox.Value==false
                app.DrawROIButton.Enable='off';
           end
        end

        % Value changed function: AssistedFreeHandCheckBox
        function AssistedFreeHandCheckBoxValueChanged(app, event)
            value = app.AssistedFreeHandCheckBox.Value;
            if value==true
                app.CircleCheckBox.Value=false;
                app.EllipseCheckBox.Value=false;
                app.RectangleCheckBox.Value=false; 
                app.FreeHandCheckBox.Value=false;
                % app.AssistedFreeHandCheckBox.Value=false;
                app.par.ROI_type=5;
                app.DrawROIButton.Enable='on';
                app.par.ROI.Visible='off';
                app.par.ROI=[];
            end
           if app.CircleCheckBox.Value==false && app.EllipseCheckBox.Value==false && app.RectangleCheckBox.Value==false && app.FreeHandCheckBox.Value==false && app.AssistedFreeHandCheckBox.Value==false
                app.DrawROIButton.Enable='off';
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DrawFRAPROIonvirtualsampleLabel = uilabel(app.UIFigure);
            app.DrawFRAPROIonvirtualsampleLabel.Text = 'Draw FRAP ROI on virtual sample ';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ZRasterSliderLabel = uilabel(app.UIFigure);
            app.ZRasterSliderLabel.Text = 'Z [Raster]';
            app.ZRasterSlider = uislider(app.UIFigure);
            app.ZRasterSlider.ValueChangedFcn = createCallbackFcn(app, @ZRasterSliderValueChanged, true);
            app.ZRasterSpinner = uispinner(app.UIFigure);
            app.ZRasterSpinner.ValueChangedFcn = createCallbackFcn(app, @ZRasterSpinnerValueChanged, true);
            app.DeleteROIButton = uibutton(app.UIFigure, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.CircleCheckBox = uicheckbox(app.UIFigure);
            app.CircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircleCheckBoxValueChanged, true);
            app.CircleCheckBox.Text = 'Circle';
            app.CircleCheckBox.Value = true;
            app.EllipseCheckBox = uicheckbox(app.UIFigure);
            app.EllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipseCheckBoxValueChanged, true);
            app.EllipseCheckBox.Text = 'Ellipse';
            app.RectangleCheckBox = uicheckbox(app.UIFigure);
            app.RectangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectangleCheckBoxValueChanged, true);
            app.RectangleCheckBox.Text = 'Rectangle';
            app.FreeHandCheckBox = uicheckbox(app.UIFigure);
            app.FreeHandCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreeHandCheckBoxValueChanged, true);
            app.FreeHandCheckBox.Text = 'FreeHand';
            app.AssistedFreeHandCheckBox = uicheckbox(app.UIFigure);
            app.AssistedFreeHandCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssistedFreeHandCheckBoxValueChanged, true);
            app.AssistedFreeHandCheckBox.Text = 'Assisted FreeHand';
            app.DrawROIButton = uibutton(app.UIFigure, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveROIButton = uibutton(app.UIFigure, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Text = 'Save ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrawFRAPPattern(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end