classdef Drift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SavedriftdataButton           matlab.ui.control.Button
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        RotFunctionEditField          matlab.ui.control.EditField
        RotFunctionEditFieldLabel     matlab.ui.control.Label
        ZFunctionEditField            matlab.ui.control.EditField
        ZFunctionEditFieldLabel       matlab.ui.control.Label
        YFunctionEditField            matlab.ui.control.EditField
        YFunctionEditFieldLabel       matlab.ui.control.Label
        XFunctionEditField            matlab.ui.control.EditField
        XFunctionEditFieldLabel       matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        NoisenmEditField              matlab.ui.control.NumericEditField
        NoisenmEditFieldLabel         matlab.ui.control.Label
        stOrdernmEditField            matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel_3     matlab.ui.control.Label
        ConstantnmEditField           matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel_3    matlab.ui.control.Label
        ndOrdernmEditField            matlab.ui.control.NumericEditField
        ndOrdernmEditFieldLabel       matlab.ui.control.Label
        ZTranslationalDriftLabel      matlab.ui.control.Label
        RotationNoiseEditField        matlab.ui.control.NumericEditField
        NoiseLabel                    matlab.ui.control.Label
        RotationEditField             matlab.ui.control.NumericEditField
        RotationperframeLabel         matlab.ui.control.Label
        YRotationCenterEditField      matlab.ui.control.NumericEditField
        YCenterofRotationRasterLabel  matlab.ui.control.Label
        XRotationCenterEditField      matlab.ui.control.NumericEditField
        XCenterofRotationRasterLabel  matlab.ui.control.Label
        RotationalDriftLabel          matlab.ui.control.Label
        YNoisefractionalEditField     matlab.ui.control.NumericEditField
        NoisenmLabel_2                matlab.ui.control.Label
        Y2ndOrdernmEditField          matlab.ui.control.NumericEditField
        stOrdernmEditField_2Label_2   matlab.ui.control.Label
        Y1stOrdernmEditField          matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel_2     matlab.ui.control.Label
        YConstantnmEditField          matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel_2    matlab.ui.control.Label
        YTranslationalDriftLabel      matlab.ui.control.Label
        XTranslationalDriftLabel      matlab.ui.control.Label
        XNoisefractionalEditField     matlab.ui.control.NumericEditField
        NoisenmLabel                  matlab.ui.control.Label
        X2ndOrdernmEditField          matlab.ui.control.NumericEditField
        stOrdernmEditField_2Label     matlab.ui.control.Label
        X1stOrdernmEditField          matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel       matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        DriftSetupLabel               matlab.ui.control.Label
        XConstantnmEditField          matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Driftpar=[]; % Initialize the parameters
        MyDrift=[]; % Local Drift sequence
        simul_3D=[];
        ImageSize=[];
        raster=[];
        n_images=[]; % # of frames
    end
    
    methods (Access = private)
        
        function PlotDrift(app)
            [cdx,cdy,cdz]=CalculateDrift(app);
            app.MyDrift.cdx=cdx;
            app.MyDrift.cdy=cdy;    
      
            plot(app.UIAxes, 1:app.n_images,app.MyDrift.cdx);
            hold(app.UIAxes,"on")
            plot(app.UIAxes, 1:app.n_images,app.MyDrift.cdy);
            app.UIAxes.XLim=[1,app.n_images];
            if app.simul_3D==1
                app.MyDrift.cdz=cdz;
                plot(app.UIAxes, 1:app.n_images,app.MyDrift.cdz);
                app.UIAxes.YLim=[min([0,min([app.MyDrift.cdx,app.MyDrift.cdy,app.MyDrift.cdz])]),max([max([app.MyDrift.cdx,app.MyDrift.cdy,app.MyDrift.cdz]),min([app.MyDrift.cdx,app.MyDrift.cdy,app.MyDrift.cdz])+1])];
                legend(app.UIAxes,'X','Y','Z')
            else
                app.UIAxes.YLim=[min([0,min([app.MyDrift.cdx,app.MyDrift.cdy])]),max([max([app.MyDrift.cdx,app.MyDrift.cdy]),min([app.MyDrift.cdx,app.MyDrift.cdy])+1])];
                legend(app.UIAxes,'X','Y')
            end
            hold(app.UIAxes,"off")
            if app.Driftpar.rot_theta~=0 || app.Driftpar.rot_n~=0 || strcmp(app.Driftpar.rot_eval,'')~=1
                app.XSlider.Visible='on';
                app.XSliderLabel.Visible='on';
                app.YSlider.Visible='on';
                app.YSliderLabel.Visible='on';
            else
                app.XSlider.Visible='off';
                app.XSliderLabel.Visible='off';
                app.YSlider.Visible='off';
                app.YSliderLabel.Visible='of';
            end            
        end
        
        function [cdx,cdy,cdz] = CalculateDrift(app)
            x_pos=app.XSlider.Value;
            y_pos=app.YSlider.Value;
            [app.Driftpar.dx,app.Driftpar.dy,app.Driftpar.dz,app.Driftpar.dtheta,cdx,cdy,cdz]=calculate_drift(app.Driftpar,app.n_images,app.simul_3D,x_pos,y_pos,app.raster);
            if ~isempty(cdz) % Handle z-drift range for PSF calculation 
                app.Driftpar.z_drift_range=[min(cdz),max(cdz)];
            else
                app.Driftpar.z_drift_range=[0,0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize Driftpar
            app.Driftpar=mainapp.smis_gui_parameters.drift;
            app.Driftpar.state=1; % Make sure the state is one in case it was reset to 0 when modifying the # of frames 
            % Initialize 3D option
            app.simul_3D=mainapp.smis_gui_parameters.simul_3D;
            % Initialize Image size
            app.ImageSize=mainapp.smis_gui_parameters.image_size;
            % Initialize # of frames
            app.n_images=mainapp.smis_gui_parameters.n_images;
            % Initialize raster
            app.raster=mainapp.smis_gui_parameters.raster;
            app.MyDrift.dir=mainapp.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir;
            
            % Setup limits for Sliders
            if ~isempty(app.ImageSize.n)
                app.XSlider.Limits=[1 app.ImageSize.n];
                app.YSlider.Limits=[1 app.ImageSize.m];
            end

            % Update UI with input values
            app.XConstantnmEditField.Value = app.Driftpar.x1;
            app.X1stOrdernmEditField.Value = app.Driftpar.x2;
            app.X2ndOrdernmEditField.Value = app.Driftpar.x3;
            app.XNoisefractionalEditField.Value = app.Driftpar.x_n;
            app.XFunctionEditField.Value=app.Driftpar.x_eval;
            
            app.YConstantnmEditField.Value = app.Driftpar.y1;
            app.Y1stOrdernmEditField.Value = app.Driftpar.y2;
            app.Y2ndOrdernmEditField.Value = app.Driftpar.y3;
            app.YNoisefractionalEditField.Value = app.Driftpar.y_n;
            app.YFunctionEditField.Value=app.Driftpar.y_eval;

            app.XRotationCenterEditField.Value = app.Driftpar.rot_x0;
            app.YRotationCenterEditField.Value = app.Driftpar.rot_y0;
            app.RotationEditField.Value = app.Driftpar.rot_theta;
            app.RotationNoiseEditField.Value = app.Driftpar.rot_n;
            app.RotFunctionEditField.Value=app.Driftpar.rot_eval;
            
            % Handles 3D option
            if app.simul_3D==1
                app.ZTranslationalDriftLabel.Visible='on';
                app.ConstantnmEditField.Visible='on';
                app.ConstantnmEditFieldLabel_3.Visible='on';
                app.stOrdernmEditField.Visible='on';
                app.stOrdernmEditFieldLabel_3.Visible='on';
                app.ndOrdernmEditField.Visible='on';
                app.ndOrdernmEditFieldLabel.Visible='on';
                app.NoisenmEditField.Visible='on';
                app.NoisenmEditFieldLabel.Visible='on';
                app.ZFunctionEditField.Visible='on';
                app.ZFunctionEditFieldLabel.Visible='on';
                app.ConstantnmEditField.Value = app.Driftpar.z1;
                app.stOrdernmEditField.Value = app.Driftpar.z2;
                app.ndOrdernmEditField.Value = app.Driftpar.z3;
                app.NoisenmEditField.Value = app.Driftpar.z_n;
                app.ZFunctionEditField.Value=app.Driftpar.z_eval;

            else
                app.ZTranslationalDriftLabel.Visible='off';
                app.stOrdernmEditField.Visible='off';
                app.stOrdernmEditFieldLabel_3.Visible='off';
                app.ndOrdernmEditField.Visible='off';
                app.ndOrdernmEditFieldLabel.Visible='off';
                app.ConstantnmEditField.Visible='off';
                app.ConstantnmEditFieldLabel_3.Visible='off';
                app.NoisenmEditField.Visible='off';
                app.NoisenmEditFieldLabel.Visible='off';
                app.ZFunctionEditField.Visible='off';
                app.ZFunctionEditFieldLabel.Visible='off';
            end

            PlotDrift(app)
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateDrift(app.CallingApp, app.Driftpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: XConstantnmEditField
        function XConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.x1 = app.XConstantnmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: X1stOrdernmEditField
        function X1stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.x2 = app.X1stOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: X2ndOrdernmEditField
        function X2ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.x3 = app.X2ndOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: XNoisefractionalEditField
        function XNoisefractionalEditFieldValueChanged(app, event)
            app.Driftpar.x_n = app.XNoisefractionalEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: YConstantnmEditField
        function YConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.y1 = app.YConstantnmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: Y1stOrdernmEditField
        function Y1stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.y2 = app.Y1stOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: Y2ndOrdernmEditField
        function Y2ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.y3 = app.Y2ndOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: YNoisefractionalEditField
        function YNoisefractionalEditFieldValueChanged(app, event)
            app.Driftpar.y_n = app.YNoisefractionalEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: XRotationCenterEditField
        function XRotationCenterEditFieldValueChanged(app, event)
            value = app.XRotationCenterEditField.Value;
            if isempty(app.ImageSize.n)
                warndlg('Define image pattern first !','Warning');
            elseif value<=app.ImageSize.n
                app.Driftpar.rot_x0 = app.XRotationCenterEditField.Value;
                PlotDrift(app);
            else
                warndlg('Center of rotation must be within image !','Warning');
            end
        end

        % Value changed function: YRotationCenterEditField
        function YRotationCenterEditFieldValueChanged(app, event)
            value = app.YRotationCenterEditField.Value;
            if value<=app.ImageSize.m
                app.Driftpar.rot_y0 = app.YRotationCenterEditField.Value;
                PlotDrift(app);
            else
                warndlg('Center of rotation must be within image !','Warning');
            end
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            app.Driftpar.rot_theta = app.RotationEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: RotationNoiseEditField
        function RotationNoiseEditFieldValueChanged(app, event)
            app.Driftpar.rot_n  = app.RotationNoiseEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: ndOrdernmEditField
        function ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.z3 = app.ndOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: ConstantnmEditField
        function ConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.z1 = app.ConstantnmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: stOrdernmEditField
        function stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.z2 = app.stOrdernmEditField.Value;
            PlotDrift(app);
        end

        % Value changed function: NoisenmEditField
        function NoisenmEditFieldValueChanged(app, event)
            app.Driftpar.z_n = app.NoisenmEditField.Value;
            PlotDrift(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end

        % Value changed function: XFunctionEditField
        function XFunctionEditFieldValueChanged(app, event)
            value = app.XFunctionEditField.Value;            
            formula_ok=1;

            if ~isempty(value)
                try
                    n=app.n_images; %#ok<NASGU>
                    x_seq=eval(value);
                    app.Driftpar.x_eval=value;
                catch
                    MyDlg=errordlg('Error in Matlab formula, please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
                if numel(x_seq)~=app.n_images
                    MyDlg=errordlg('Error in Matlab formula (number of elements differs from number of images), please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
            else
                app.Driftpar.x_eval=[];
            end
           
            if formula_ok==1
                PlotDrift(app)
            end

        end

        % Value changed function: YFunctionEditField
        function YFunctionEditFieldValueChanged(app, event)
            value = app.YFunctionEditField.Value;
            formula_ok=1;

            if ~isempty(value)
                try
                    n=app.n_images; %#ok<NASGU>
                    y_seq=eval(value);
                    app.Driftpar.y_eval=value;
                catch
                    MyDlg=errordlg('Error in Matlab formula, please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
                if numel(y_seq)~=app.n_images
                    MyDlg=errordlg('Error in Matlab formula (number of elements differs from number of images), please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
            else
                app.Driftpar.y_eval=[];
            end
           
            if formula_ok==1
                PlotDrift(app)
            end
        end

        % Value changed function: ZFunctionEditField
        function ZFunctionEditFieldValueChanged(app, event)
            value = app.ZFunctionEditField.Value;
            formula_ok=1;

            if ~isempty(value)
                try
                    n=app.n_images; %#ok<NASGU>
                    z_seq=eval(value);
                    app.Driftpar.z_eval=value;
                catch
                    MyDlg=errordlg('Error in Matlab formula, please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
                if numel(z_seq)~=app.n_images
                    MyDlg=errordlg('Error in Matlab formula (number of elements differs from number of images), please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
            else
                app.Driftpar.z_eval=[];
            end

           
            if formula_ok==1
                PlotDrift(app)
            end

        end

        % Value changed function: RotFunctionEditField
        function RotFunctionEditFieldValueChanged(app, event)
            value = app.RotFunctionEditField.Value;
            formula_ok=1;

            if ~isempty(value)
                try
                    n=app.n_images; %#ok<NASGU>
                    rot_seq=eval(value);
                    app.Driftpar.rot_eval=value;
                catch
                    MyDlg=errordlg('Error in Matlab formula, please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
                if numel(rot_seq)~=app.n_images
                    MyDlg=errordlg('Error in Matlab formula (number of elements differs from number of images), please check ! ','Warning');
                    waitfor(MyDlg)
                    formula_ok = 0;
                end
            else
                app.Driftpar.rot_eval=[];
            end

           
            if formula_ok==1
                PlotDrift(app)
            end

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            PlotDrift(app)
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            PlotDrift(app)
        end

        % Button pushed function: SavedriftdataButton
        function SavedriftdataButtonPushed(app, event)
            if exist(app.MyDrift.dir,'dir')
                cd(app.MyDrift.dir);
            end
            [drift_file,MyDir] = uiputfile('*.txt','SMIS File Selection','Drift_data');
            if ~isnumeric(MyDir) && ~isnumeric(drift_file)
                cdx=app.MyDrift.cdx;
                cdy=app.MyDrift.cdy;

                if app.simul_3D==1
                    cdz=app.MyDrift.cdz;
                    drift_matrix=[cdx;cdy;cdz]';
                    drift_table=array2table(drift_matrix,'VariableNames',{'x_drift [nm]','y_drift [nm]','z_drift [nm]'});
                else
                    drift_matrix=[cdx;cdy]';
                    drift_table=array2table(drift_matrix,'VariableNames',{'x_drift [nm]','y_drift [nm]'});
                end
                
                app.MyDrift.dir=MyDir;
                disp('Saving drift data ...');
                drift_file=fullfile(MyDir,drift_file);
                writetable(drift_table, drift_file);
                disp('Done !');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Drift')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'X, Y drift [nm]')
            zlabel(app.UIAxes, 'Z')
            app.ConstantnmEditFieldLabel = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel.Text = 'Constant [nm]';
            app.XConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XConstantnmEditField.ValueDisplayFormat = '%11.3g';
            app.XConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @XConstantnmEditFieldValueChanged, true);
            app.XConstantnmEditField.Tooltip = {'Constant drift per frame in x direction in nm'};
            app.DriftSetupLabel = uilabel(app.UIFigure);
            app.DriftSetupLabel.Text = 'Drift Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.stOrdernmEditFieldLabel = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel.Text = '1st Order [nm]';
            app.X1stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X1stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @X1stOrdernmEditFieldValueChanged, true);
            app.X1stOrdernmEditField.Tooltip = {'Linear drift per frame in x direction in nm: value will be multiplied by n/n_frames where n is the current frame number'};
            app.stOrdernmEditField_2Label = uilabel(app.UIFigure);
            app.stOrdernmEditField_2Label.Text = '2st Order [nm]';
            app.X2ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X2ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @X2ndOrdernmEditFieldValueChanged, true);
            app.X2ndOrdernmEditField.Tooltip = {'Quadratic drift per frame in x direction in nm: value will be multiplied by (n/n_frames)² where n is the current frame number'};
            app.NoisenmLabel = uilabel(app.UIFigure);
            app.NoisenmLabel.Text = 'Noise [nm]';
            app.XNoisefractionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.XNoisefractionalEditField.Limits = [0 Inf];
            app.XNoisefractionalEditField.ValueChangedFcn = createCallbackFcn(app, @XNoisefractionalEditFieldValueChanged, true);
            app.XNoisefractionalEditField.Tooltip = {'Random drift per frame in x direction in nm. A normal distribution will be used.'};
            app.XTranslationalDriftLabel = uilabel(app.UIFigure);
            app.XTranslationalDriftLabel.Text = 'X Translational Drift';
            app.YTranslationalDriftLabel = uilabel(app.UIFigure);
            app.YTranslationalDriftLabel.Text = 'Y Translational Drift';
            app.ConstantnmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel_2.Text = 'Constant [nm]';
            app.YConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @YConstantnmEditFieldValueChanged, true);
            app.YConstantnmEditField.Tooltip = {'Constant drift per frame in y direction in nm'};
            app.stOrdernmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel_2.Text = '1st Order [nm]';
            app.Y1stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @Y1stOrdernmEditFieldValueChanged, true);
            app.Y1stOrdernmEditField.Tooltip = {'Linear drift per frame in y direction in nm: value will be multiplied by n/n_frames where n is the current frame number'};
            app.stOrdernmEditField_2Label_2 = uilabel(app.UIFigure);
            app.stOrdernmEditField_2Label_2.Text = '2st Order [nm]';
            app.Y2ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @Y2ndOrdernmEditFieldValueChanged, true);
            app.Y2ndOrdernmEditField.Tooltip = {'Quadratic drift per frame in y direction in nm: value will be multiplied by (n/n_frames)² where n is the current frame number'};
            app.NoisenmLabel_2 = uilabel(app.UIFigure);
            app.NoisenmLabel_2.Text = 'Noise [nm]';
            app.YNoisefractionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.YNoisefractionalEditField.Limits = [0 Inf];
            app.YNoisefractionalEditField.ValueChangedFcn = createCallbackFcn(app, @YNoisefractionalEditFieldValueChanged, true);
            app.YNoisefractionalEditField.Tooltip = {'Random drift per frame in y direction in nm. A normal distribution will be used.'};
            app.RotationalDriftLabel = uilabel(app.UIFigure);
            app.RotationalDriftLabel.Text = ' Rotational Drift';
            app.XCenterofRotationRasterLabel = uilabel(app.UIFigure);
            app.XCenterofRotationRasterLabel.Text = 'X Center of Rotation [Raster]';
            app.XRotationCenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRotationCenterEditField.Limits = [0 Inf];
            app.XRotationCenterEditField.ValueChangedFcn = createCallbackFcn(app, @XRotationCenterEditFieldValueChanged, true);
            app.XRotationCenterEditField.Tooltip = {'X center of rotation for rotational drift'};
            app.YCenterofRotationRasterLabel = uilabel(app.UIFigure);
            app.YCenterofRotationRasterLabel.Text = 'Y Center of Rotation [Raster]';
            app.YRotationCenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.YRotationCenterEditField.Limits = [0 Inf];
            app.YRotationCenterEditField.ValueChangedFcn = createCallbackFcn(app, @YRotationCenterEditFieldValueChanged, true);
            app.YRotationCenterEditField.Tooltip = {'Y center of rotation for rotational drift'};
            app.RotationperframeLabel = uilabel(app.UIFigure);
            app.RotationperframeLabel.Text = 'Rotation per frame [°]';
            app.RotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationEditField.Limits = [-360 360];
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            app.RotationEditField.Tooltip = {'Constant rotational drift in ° per frame'};
            app.NoiseLabel = uilabel(app.UIFigure);
            app.NoiseLabel.Text = 'Noise [°]';
            app.RotationNoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationNoiseEditField.Limits = [0 Inf];
            app.RotationNoiseEditField.ValueChangedFcn = createCallbackFcn(app, @RotationNoiseEditFieldValueChanged, true);
            app.RotationNoiseEditField.Tooltip = {'Random rotational drift per frame in °. A normal distribution will be used.'};
            app.ZTranslationalDriftLabel = uilabel(app.UIFigure);
            app.ZTranslationalDriftLabel.Text = 'Z Translational Drift';
            app.ndOrdernmEditFieldLabel = uilabel(app.UIFigure);
            app.ndOrdernmEditFieldLabel.Text = '2nd Order [nm]';
            app.ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @ndOrdernmEditFieldValueChanged, true);
            app.ndOrdernmEditField.Tooltip = {'Quadratic drift per frame in z direction in nm: value will be multiplied by (n/n_frames)² where n is the current frame number'};
            app.ConstantnmEditFieldLabel_3 = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel_3.Text = 'Constant [nm]';
            app.ConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantnmEditFieldValueChanged, true);
            app.ConstantnmEditField.Tooltip = {'Constant drift per frame in z direction in nm'};
            app.stOrdernmEditFieldLabel_3 = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel_3.Text = '1st Order [nm]';
            app.stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @stOrdernmEditFieldValueChanged, true);
            app.stOrdernmEditField.Tooltip = {'Linear drift per frame in z direction in nm: value will be multiplied by n/n_frames where n is the current frame number'};
            app.NoisenmEditFieldLabel = uilabel(app.UIFigure);
            app.NoisenmEditFieldLabel.Text = 'Noise [nm]';
            app.NoisenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisenmEditField.Limits = [0 Inf];
            app.NoisenmEditField.ValueChangedFcn = createCallbackFcn(app, @NoisenmEditFieldValueChanged, true);
            app.NoisenmEditField.Tooltip = {'Random drift per frame in z direction in nm. A normal distribution will be used.'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.XFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.XFunctionEditFieldLabel.Text = 'Function';
            app.XFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.XFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @XFunctionEditFieldValueChanged, true);
            app.XFunctionEditField.Tooltip = {'A Matlab function that describes the drift in x direction.'; 'Examples are:'; '0.1*randn(1,n)'; 'smooth(0.1*randn(1,n),fix(n/10))'; '0.2*sin((1:n)/(pi*n))'};
            app.YFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.YFunctionEditFieldLabel.Text = 'Function';
            app.YFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.YFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @YFunctionEditFieldValueChanged, true);
            app.YFunctionEditField.Tooltip = {'A Matlab function that describes the drift in y direction.'; 'Examples are:'; '0.1*randn(1,n)'; 'smooth(0.1*randn(1,n),fix(n/10))'; '0.2*sin((1:n)/(pi*n))'};
            app.ZFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.ZFunctionEditFieldLabel.Text = 'Function';
            app.ZFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ZFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ZFunctionEditFieldValueChanged, true);
            app.ZFunctionEditField.Tooltip = {'A Matlab function that describes the drift in z direction.'; 'Examples are:'; '0.1*randn(1,n)'; 'smooth(0.1*randn(1,n),fix(n/10))'; '0.2*sin((1:n)/(pi*n))'};
            app.RotFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.RotFunctionEditFieldLabel.Text = 'Function';
            app.RotFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.RotFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @RotFunctionEditFieldValueChanged, true);
            app.RotFunctionEditField.Tooltip = {'A Matlab function that describes the rotational drift'; 'Examples are:'; '0.1*randn(1,n)'; 'smooth(0.1*randn(1,n),fix(n/10))'; '0.2*sin((1:n)/(pi*n))'};
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Tooltip = {'When there is rotational drift, the drift depends on pixel location: change x position here'};
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.Tooltip = {'When there is rotational drift, the drift depends on pixel location: change y position here'};
            app.SavedriftdataButton = uibutton(app.UIFigure, 'push');
            app.SavedriftdataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedriftdataButtonPushed, true);
            app.SavedriftdataButton.Text = 'Save drift data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drift(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end