classdef EMCCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CancelButton                  matlab.ui.control.Button
        EMCCDConversionFactoreADUEditField  matlab.ui.control.NumericEditField
        EMCCDConversionFactoreADUEditFieldLabel  matlab.ui.control.Label
        EMCCDConversionFactorephEditField  matlab.ui.control.NumericEditField
        EMCCDConversionFactorephEditFieldLabel  matlab.ui.control.Label
        EMCCDDynamicRangeEditField    matlab.ui.control.NumericEditField
        EMCCDDynamicRangeEditFieldLabel  matlab.ui.control.Label
        EMCCDReadoutNoiseeEditField   matlab.ui.control.NumericEditField
        EMCCDReadoutNoiseeEditFieldLabel  matlab.ui.control.Label
        EMCCDClockInducedChargeeEditField  matlab.ui.control.NumericEditField
        EMCCDClockInducedChargeeEditFieldLabel  matlab.ui.control.Label
        EMCCDOffsetADUEditField       matlab.ui.control.NumericEditField
        EMCCDOffsetADUEditFieldLabel  matlab.ui.control.Label
        EMCCDQuantumEfficiencyEditField  matlab.ui.control.NumericEditField
        EMCCDQuantumEfficiencyEditFieldLabel  matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        EMCCDSetupLabel               matlab.ui.control.Label
        EMCCDGainEditField            matlab.ui.control.NumericEditField
        EMCCDGainEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        EMCCDpar=[]; % Initialize the parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, input_par)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize EMCCDpar
            app.EMCCDpar=input_par;
            % Update UI with input values
            app.EMCCDGainEditField.Value = input_par.det_EMCCD_gain;
            app.EMCCDQuantumEfficiencyEditField.Value = input_par.det_QE;
            app.EMCCDOffsetADUEditField.Value = input_par.det_offset;
            app.EMCCDClockInducedChargeeEditField.Value = input_par.det_c;
            app.EMCCDReadoutNoiseeEditField.Value = input_par.det_readout_noise;
            app.EMCCDDynamicRangeEditField.Value = input_par.det_dynamic_range;
            app.EMCCDConversionFactorephEditField.Value = input_par.det_e2p;
            app.EMCCDConversionFactoreADUEditField.Value = input_par.det_e2ADU;
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateEMCCD(app.CallingApp, app.EMCCDpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: EMCCDGainEditField
        function EMCCDGainEditFieldValueChanged(app, event)
            value = app.EMCCDGainEditField.Value;
            app.EMCCDpar.det_EMCCD_gain=value;
        end

        % Value changed function: EMCCDQuantumEfficiencyEditField
        function EMCCDQuantumEfficiencyEditFieldValueChanged(app, event)
            value = app.EMCCDQuantumEfficiencyEditField.Value;
            app.EMCCDpar.det_QE=value;
        end

        % Value changed function: EMCCDOffsetADUEditField
        function EMCCDOffsetADUEditFieldValueChanged(app, event)
            value = app.EMCCDOffsetADUEditField.Value;
            app.EMCCDpar.det_offset=value;
        end

        % Value changed function: EMCCDClockInducedChargeeEditField
        function EMCCDClockInducedChargeeEditFieldValueChanged(app, event)
            value = app.EMCCDClockInducedChargeeEditField.Value;
            app.EMCCDpar.det_c=value;
        end

        % Value changed function: EMCCDReadoutNoiseeEditField
        function EMCCDReadoutNoiseeEditFieldValueChanged(app, event)
            value = app.EMCCDReadoutNoiseeEditField.Value;
            app.EMCCDpar.det_readout_noise=value;
        end

        % Value changed function: EMCCDDynamicRangeEditField
        function EMCCDDynamicRangeEditFieldValueChanged(app, event)
            value = app.EMCCDDynamicRangeEditField.Value;
            app.EMCCDpar.det_dynamic_range=value;
        end

        % Value changed function: EMCCDConversionFactorephEditField
        function EMCCDConversionFactorephEditFieldValueChanged(app, event)
            value = app.EMCCDConversionFactorephEditField.Value;
            app.EMCCDpar.det_e2p=value;
        end

        % Value changed function: EMCCDConversionFactoreADUEditField
        function EMCCDConversionFactoreADUEditFieldValueChanged(app, event)
            value = app.EMCCDConversionFactoreADUEditField.Value;
            app.EMCCDpar.det_e2ADU=value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EMCCDGainEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDGainEditFieldLabel.Text = 'EMCCD Gain';
            app.EMCCDGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDGainEditField.Limits = [0 Inf];
            app.EMCCDGainEditField.ValueDisplayFormat = '%11.0d';
            app.EMCCDGainEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDGainEditFieldValueChanged, true);
            app.EMCCDGainEditField.Tooltip = {'Define here the EMCCD gain : the signal (and clock induced charge noise) will be amplified by this factor before detector readout.'};
            app.EMCCDGainEditField.Value = 200;
            app.EMCCDSetupLabel = uilabel(app.UIFigure);
            app.EMCCDSetupLabel.Text = 'EMCCD Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Returns to the main menu'};
            app.OKButton.Text = 'OK';
            app.EMCCDQuantumEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDQuantumEfficiencyEditFieldLabel.Text = 'EMCCD Quantum Efficiency';
            app.EMCCDQuantumEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDQuantumEfficiencyEditField.Limits = [0 1];
            app.EMCCDQuantumEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDQuantumEfficiencyEditFieldValueChanged, true);
            app.EMCCDQuantumEfficiencyEditField.Tooltip = {'Define here the EMCCD quantum efficiency. If for example set to 0.9, only 90% of the photons will be detected.'};
            app.EMCCDQuantumEfficiencyEditField.Value = 0.9;
            app.EMCCDOffsetADUEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDOffsetADUEditFieldLabel.Text = 'EMCCD  Offset [ADU]';
            app.EMCCDOffsetADUEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDOffsetADUEditField.Limits = [0 Inf];
            app.EMCCDOffsetADUEditField.ValueDisplayFormat = '%11.0d';
            app.EMCCDOffsetADUEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDOffsetADUEditFieldValueChanged, true);
            app.EMCCDOffsetADUEditField.Tooltip = {'Define here the EMCCD offset (number of ADU’s recorded per pixel in the absence of any signal)'};
            app.EMCCDOffsetADUEditField.Value = 500;
            app.EMCCDClockInducedChargeeEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDClockInducedChargeeEditFieldLabel.Text = 'EMCCD Clock Induced Charge [e]';
            app.EMCCDClockInducedChargeeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDClockInducedChargeeEditField.Limits = [0 Inf];
            app.EMCCDClockInducedChargeeEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDClockInducedChargeeEditFieldValueChanged, true);
            app.EMCCDClockInducedChargeeEditField.Tooltip = {'Define here the EMCCD clock induced charge in electrons. This is an electronic noise in CCD detectors that adds up to the input signal and that will be amplified together with it depending on the set EMCCD gain'};
            app.EMCCDClockInducedChargeeEditField.Value = 0.002;
            app.EMCCDReadoutNoiseeEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDReadoutNoiseeEditFieldLabel.Text = 'EMCCD  Readout Noise [e-]';
            app.EMCCDReadoutNoiseeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDReadoutNoiseeEditField.Limits = [0 Inf];
            app.EMCCDReadoutNoiseeEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDReadoutNoiseeEditFieldValueChanged, true);
            app.EMCCDReadoutNoiseeEditField.Tooltip = {'Define here the EMCCD readout noise in electrons : this is the electronic noise that is induced by the reading of the detector (independent of EMCCD gain).'};
            app.EMCCDReadoutNoiseeEditField.Value = 74;
            app.EMCCDDynamicRangeEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDDynamicRangeEditFieldLabel.Text = 'EMCCD Dynamic Range';
            app.EMCCDDynamicRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDDynamicRangeEditField.Limits = [0 Inf];
            app.EMCCDDynamicRangeEditField.ValueDisplayFormat = '%11.4d';
            app.EMCCDDynamicRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDDynamicRangeEditFieldValueChanged, true);
            app.EMCCDDynamicRangeEditField.Tooltip = {'Define here the EMCCD dynamic range (typically 16 bits)'};
            app.EMCCDDynamicRangeEditField.Value = 65535;
            app.EMCCDConversionFactorephEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDConversionFactorephEditFieldLabel.Text = 'EMCCD Conversion Factor (e-/ph)';
            app.EMCCDConversionFactorephEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDConversionFactorephEditField.Limits = [0 Inf];
            app.EMCCDConversionFactorephEditField.ValueDisplayFormat = '%11.0d';
            app.EMCCDConversionFactorephEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDConversionFactorephEditFieldValueChanged, true);
            app.EMCCDConversionFactorephEditField.Tooltip = {'Define here the EMCCD conversion from photons to electrons (ie : number of electrons produced by one photon)'};
            app.EMCCDConversionFactorephEditField.Value = 6;
            app.EMCCDConversionFactoreADUEditFieldLabel = uilabel(app.UIFigure);
            app.EMCCDConversionFactoreADUEditFieldLabel.Text = 'EMCCD Conversion Factor (e-/ADU)';
            app.EMCCDConversionFactoreADUEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMCCDConversionFactoreADUEditField.Limits = [0 Inf];
            app.EMCCDConversionFactoreADUEditField.ValueDisplayFormat = '%11.0d';
            app.EMCCDConversionFactoreADUEditField.ValueChangedFcn = createCallbackFcn(app, @EMCCDConversionFactoreADUEditFieldValueChanged, true);
            app.EMCCDConversionFactoreADUEditField.Tooltip = {'Define here the EMCCD conversion factor from electrons to ADU (ie : number of electrons to produce one ADU)'};
            app.EMCCDConversionFactoreADUEditField.Value = 45;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Returns to the main menu'};
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMCCD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end