classdef FiltersAndChannels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowspectraButton               matlab.ui.control.Button
        LoadfilterButton_3              matlab.ui.control.Button
        FilterdefinitionEditField_3     matlab.ui.control.EditField
        FilterdefinitionEditField_3Label  matlab.ui.control.Label
        CommercialfilterSwitch_3        matlab.ui.control.Switch
        CommercialfilterSwitch_3Label   matlab.ui.control.Label
        DichroicfilterLabel_2           matlab.ui.control.Label
        LoadfilterButton_2              matlab.ui.control.Button
        Channel2Label                   matlab.ui.control.Label
        Channel1Label                   matlab.ui.control.Label
        CommercialfilterSwitch_2        matlab.ui.control.Switch
        CommercialfilterSwitch_2Label   matlab.ui.control.Label
        LoadfilterButton                matlab.ui.control.Button
        CommercialfilterSwitch          matlab.ui.control.Switch
        CommercialfilterSwitchLabel     matlab.ui.control.Label
        AdddichroicfilterSwitch         matlab.ui.control.Switch
        AdddichroicfilterSwitchLabel    matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        SingleCCDSwitch                 matlab.ui.control.Switch
        SingleCCDSwitchLabel            matlab.ui.control.Label
        XShiftrasterEditField           matlab.ui.control.NumericEditField
        XShiftrasterEditFieldLabel      matlab.ui.control.Label
        Channel2DeformationLabel        matlab.ui.control.Label
        YStretchfractionEditField       matlab.ui.control.NumericEditField
        YStretchfractionEditFieldLabel  matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        RotationEditField               matlab.ui.control.NumericEditField
        RotationEditFieldLabel          matlab.ui.control.Label
        XStretchfractionEditField       matlab.ui.control.NumericEditField
        XStretchfractionEditFieldLabel  matlab.ui.control.Label
        YShiftrasterEditField           matlab.ui.control.NumericEditField
        YShiftrasterEditFieldLabel      matlab.ui.control.Label
        Channel2DefocusEditField        matlab.ui.control.NumericEditField
        Channel2DefocusEditFieldLabel   matlab.ui.control.Label
        FilterdefinitionEditField_2     matlab.ui.control.EditField
        FilterdefinitionEditField_2Label  matlab.ui.control.Label
        FilterdefinitionEditField       matlab.ui.control.EditField
        FilterdefinitionEditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        EmissionFiltersandTwoChannelOptionsLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        par=struct; % Parameters
    end
    
    methods (Access = private)
        
        function plot_filters(app)
            % Plot the filters
            

            % Plot Channel 1
            % Case of non_commercial filter
            if strcmp(app.CommercialfilterSwitch.Value,'On')==0
                ch1_filter=app.FilterdefinitionEditField.Value;

                formula_ok=1;
                try
                    ch1_f=eval(ch1_filter);
                catch
                    warndlg('Error in Matlab formula for Filter 1, please check ! ','Warning');
                    formula_ok = 0;
                end

                if size(ch1_f,2) ~= 3
                    warndlg('Filter definition must have 3 parameters for each band !','Warning');
                    formula_ok = 0;
                end

                if any(ch1_f(:,3)>1) || any(ch1_f(:,3)<0)
                    warndlg('Filter transmission must be between 0 and 1 !','Warning');
                    formula_ok = 0;
                end

                if formula_ok==1
                    [x_ch1,y_ch1,lambda] = get_filter_profile(ch1_f);
                    loc_c=wavelength2rgb(-lambda);
                    plot(app.UIAxes_1,  x_ch1, 100*y_ch1, '-','LineWidth',1.5, 'Color',loc_c); %Plot Emission filter for channel 1
                    app.UIAxes_1.XLim = [200 max([800,min([1000,max(x_ch1)])])];
                end
            else  % Case of commercial filter
                if ~isempty(app.par.TwoChannel.exp_ch1_filter)
                    x_ch1=app.par.TwoChannel.exp_ch1_filter(1,:);
                    y_ch1=app.par.TwoChannel.exp_ch1_filter(2,:);
                    lambda=round(sum(x_ch1.*y_ch1)/sum(y_ch1));
                    loc_c=wavelength2rgb(-lambda);
                    plot(app.UIAxes_1,  x_ch1, 100*y_ch1, '-','LineWidth',1.5, 'Color',loc_c); %Plot Emission filter for channel 1
                    app.UIAxes_1.XLim = [200 max([800,min([1000,max(x_ch1)])])];
                else
                    cla(app.UIAxes_1) 
                end
            end


            if app.par.TwoChannel.state==1

                % Plot Channel 2
                % Case of non_commercial filter
                if strcmp(app.CommercialfilterSwitch_2.Value,'On')==0
                    ch2_filter=app.FilterdefinitionEditField_2.Value;

                    formula_ok=1;
                    try
                        ch2_f=eval(ch2_filter);
                    catch
                        warndlg('Error in Matlab formula for Filter 2, please check ! ','Warning');
                        formula_ok = 0;
                    end

                    if size(ch2_f,2) ~= 3
                        warndlg('Filter definition must have 3 parameters for each band !','Warning');
                        formula_ok = 0;
                    end

                    if any(ch2_f(:,3)>1) || any(ch2_f(:,3)<0)
                        warndlg('Filter transmission must be between 0 and 1 !','Warning');
                        formula_ok = 0;
                    end

                    if formula_ok==1
                        [x_ch2,y_ch2,lambda] = get_filter_profile(ch2_f);
                        loc_c=wavelength2rgb(-lambda);
                        plot(app.UIAxes_2,  x_ch2, 100*y_ch2, '-','LineWidth',1.5, 'Color',loc_c); %Plot Emission filter for channel 1
                        app.UIAxes_2.XLim = [200 max([800,min([1000,max(x_ch2)])])];
                    end
                else  % Case of commercial filter
                    if ~isempty(app.par.TwoChannel.exp_ch2_filter)
                        x_ch2=app.par.TwoChannel.exp_ch2_filter(1,:);
                        y_ch2=app.par.TwoChannel.exp_ch2_filter(2,:);
                        lambda=round(sum(x_ch2.*y_ch2)/sum(y_ch2));
                        loc_c=wavelength2rgb(-lambda);
                        plot(app.UIAxes_2,  x_ch2, 100*y_ch2, '-','LineWidth',1.5, 'Color',loc_c); %Plot Emission filter for channel 1
                        app.UIAxes_2.XLim = [200 max([800,min([1000,max(x_ch2)])])];
                    else
                        cla(app.UIAxes_2)
                    end
                end

                if strcmp(app.AdddichroicfilterSwitch.Value,"On")==1

                    % Plot Dichroic
                    % Case of non_commercial filter
                    if strcmp(app.CommercialfilterSwitch_3.Value,'On')==0
                        dichroic_filter=app.FilterdefinitionEditField_3.Value;

                        formula_ok=1;
                        try
                            dichroic_filter=eval(dichroic_filter);
                        catch
                            errordlg('Error in Matlab formula for dichroic_filter, please check ! ','Warning');
                            formula_ok = 0;
                        end

                        if size(dichroic_filter,2) ~= 4
                            warndlg('Dichroic filter definition must have 4 parameters !','Warning');
                            formula_ok = 0;
                        end

                        if dichroic_filter(3)<0 || dichroic_filter(3)>1 || dichroic_filter(4)<0 || dichroic_filter(4)>1
                            warndlg('Dichroic transmission must be between 0 and 1 !','Warning');
                            formula_ok = 0;
                        end

                        if formula_ok==1
                            [x_df,y_df,lambda] = get_dichroic_filter_profile(dichroic_filter);
                            loc_c=wavelength2rgb(-lambda);
                            plot(app.UIAxes_3,  x_df, 100*y_df, '-','LineWidth',1.5, 'Color',loc_c); %Plot dichroic filter
                            app.UIAxes_3.XLim = [200 max([800,min([1000,max(x_df)])])];
                        end
                    else % Case of commercial filter
                        if ~isempty(app.par.TwoChannel.exp_dichroic_filter)
                            x_df=app.par.TwoChannel.exp_dichroic_filter(1,:);
                            y_df=app.par.TwoChannel.exp_dichroic_filter(2,:);
                            lambda=round(sum(x_df.*y_df)/sum(y_df));
                            loc_c=wavelength2rgb(-lambda);
                            plot(app.UIAxes_3,  x_df, 100*y_df, '-','LineWidth',1.5, 'Color',loc_c); %Plot dichroic filter
                            app.UIAxes_3.XLim = [200 max([800,min([1000,max(x_df)])])];
                        else
                            cla(app.UIAxes_3)
                        end
                    end
                end
            end
        end
        
        function check_spectra(app)

            % Check if figure exists
            figure_is_present=false;
            figHandles = findobj('Type', 'figure'); % Get the array of all figures open
            n_fig=size(figHandles,1);
            for k=1:n_fig
                if strcmp(figHandles(k).Name,'Abs/Exc and Emission Spectra')==1 % Our figure already exists
                    figure_is_present=true;
                end
            end

            % If yes update filters on the figure 
            if figure_is_present
                local_par=struct;
                local_par.TwoChannel=app.par.TwoChannel;
                local_par.Fluorophores=app.par.smis_par.Fluorophores;
                app.par.figure_number=DisplaySpectra(local_par);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, input_par)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % In case a figure will be created and deleted upon closure
            app.par.figure_number=[];

            % Get all smis parameters 
            app.par.smis_par=mainapp.smis_gui_parameters;
            
            % Initialize par.TwoChannel
            app.par.TwoChannel=input_par.TwoChannel;
            
                      
            % Initialize filter directory
            if isempty(app.par.TwoChannel.filter_path)
                app.par.TwoChannel.filter_path=strrep(input_par.DefaultDirs.SMIS_GUI_Dir,'\GUI','\FILTERS');
            end

            app.Channel2DefocusEditField.Value = app.par.TwoChannel.defocus;
            if app.par.TwoChannel.single_CCD==1
                app.SingleCCDSwitch.Value = 'On';
            else
                app.SingleCCDSwitch.Value = 'Off';
            end
            app.XShiftrasterEditField.Value = app.par.TwoChannel.deform.X_shift;
            app.YShiftrasterEditField.Value = app.par.TwoChannel.deform.Y_shift;
            app.XStretchfractionEditField.Value = app.par.TwoChannel.deform.X_Stretch;
            app.YStretchfractionEditField.Value = app.par.TwoChannel.deform.Y_Stretch;
            app.RotationEditField.Value = app.par.TwoChannel.deform.Rotation;
            app.NoiseEditField.Value = app.par.TwoChannel.deform.Noise;
            
            
            app.FilterdefinitionEditField.Value = app.par.TwoChannel.em_filter_1;
            app.FilterdefinitionEditField_2.Value = app.par.TwoChannel.em_filter_2;
            app.FilterdefinitionEditField_3.Value=app.par.TwoChannel.dichroic_filter;

            % Make some fields visible
            if app.par.TwoChannel.use_exp_ch1_filter==1
                app.LoadfilterButton.Visible="on";
                app.FilterdefinitionEditField.Visible="off";
                app.FilterdefinitionEditFieldLabel.Visible="off";
                app.CommercialfilterSwitch.Value="On";
            else
                app.LoadfilterButton.Visible="off";
                app.FilterdefinitionEditField.Visible="on";
                app.FilterdefinitionEditFieldLabel.Visible="on";
                app.CommercialfilterSwitch.Value="Off";
            end

            if app.par.TwoChannel.state==1
                %Turn channel 2 on
                app.Channel2Label.Visible="on";
                app.UIAxes_2.Visible="on";
                app.CommercialfilterSwitch_2.Visible="on";
                app.CommercialfilterSwitch_2Label.Visible="on";
                if app.par.TwoChannel.use_exp_ch2_filter==1
                    app.LoadfilterButton_2.Visible="on";
                    app.FilterdefinitionEditField_2.Visible="off";
                    app.FilterdefinitionEditField_2Label.Visible="off";
                    app.CommercialfilterSwitch_2.Value="On";
                else
                    app.LoadfilterButton_2.Visible="off";
                    app.FilterdefinitionEditField_2.Visible="on";
                    app.FilterdefinitionEditField_2Label.Visible="on";
                    app.CommercialfilterSwitch_2.Value="Off";
                end

                app.Channel2DefocusEditField.Visible = 'on';
                app.Channel2DefocusEditFieldLabel.Visible = 'on';
                app.SingleCCDSwitch.Visible = 'on';
                app.SingleCCDSwitchLabel.Visible = 'on';
                app.XShiftrasterEditField.Visible = 'on';
                app.XShiftrasterEditFieldLabel.Visible = 'on';
                app.YShiftrasterEditField.Visible = 'on';
                app.YShiftrasterEditFieldLabel.Visible = 'on';
                app.XStretchfractionEditField.Visible = 'on';
                app.XStretchfractionEditFieldLabel.Visible = 'on';
                app.YStretchfractionEditField.Visible = 'on';
                app.YStretchfractionEditFieldLabel.Visible = 'on';
                app.RotationEditField.Visible = 'on';
                app.RotationEditFieldLabel.Visible = 'on';
                app.NoiseEditField.Visible = 'on';
                app.NoiseEditFieldLabel.Visible = 'on';
                app.Channel2DeformationLabel.Visible = 'on';

                %Make dichroic filter possible
                app.AdddichroicfilterSwitch.Visible="on";
                app.AdddichroicfilterSwitchLabel.Visible="on";
                if app.par.TwoChannel.add_dichroic==1
                    app.DichroicfilterLabel_2.Visible="on";
                    app.AdddichroicfilterSwitch.Value="On";
                    app.CommercialfilterSwitch_3.Visible="on";
                    app.CommercialfilterSwitch_3Label.Visible="on";
                    app.UIAxes_3.Visible="on";
                    if app.par.TwoChannel.use_exp_dichroic==1
                        app.LoadfilterButton_3.Visible="on";
                        app.FilterdefinitionEditField_3.Visible="off";
                        app.FilterdefinitionEditField_3Label.Visible="off";
                        app.CommercialfilterSwitch_3.Value="On";
                    else
                        app.LoadfilterButton_3.Visible="off";
                        app.FilterdefinitionEditField_3.Visible="on";
                        app.FilterdefinitionEditField_3Label.Visible="on";
                        app.CommercialfilterSwitch_3.Value="Off";
                    end
                else
                    app.DichroicfilterLabel_2.Visible="off";
                    app.AdddichroicfilterSwitch.Value="Off";
                    app.UIAxes_3.Visible="off";
                    app.LoadfilterButton_3.Visible="off";
                    app.CommercialfilterSwitch_3.Visible="off";
                    app.CommercialfilterSwitch_3Label.Visible="off";
                    app.FilterdefinitionEditField_3.Visible="off";
                    app.FilterdefinitionEditField_3Label.Visible="off";
                end
            else
                %Turn channel 2 off 
                app.Channel2Label.Visible="off";
                app.UIAxes_2.Visible = 'off';
                app.FilterdefinitionEditField_2.Visible = 'off';
                app.FilterdefinitionEditField_2Label.Visible = 'off';
                app.CommercialfilterSwitch_2.Visible="off";
                app.CommercialfilterSwitch_2Label.Visible="off";
                app.LoadfilterButton_2.Visible="off";
                
                %Turn defocus and distortion off
                app.Channel2DefocusEditField.Visible = 'off';
                app.Channel2DefocusEditFieldLabel.Visible = 'off';
                app.SingleCCDSwitch.Visible = 'off';
                app.SingleCCDSwitchLabel.Visible = 'off';
                app.XShiftrasterEditField.Visible = 'off';
                app.XShiftrasterEditFieldLabel.Visible = 'off';
                app.YShiftrasterEditField.Visible = 'off';
                app.YShiftrasterEditFieldLabel.Visible = 'off';
                app.XStretchfractionEditField.Visible = 'off';
                app.XStretchfractionEditFieldLabel.Visible = 'off';
                app.YStretchfractionEditField.Visible = 'off';
                app.YStretchfractionEditFieldLabel.Visible = 'off';
                app.RotationEditField.Visible = 'off';
                app.RotationEditFieldLabel.Visible = 'off';
                app.NoiseEditField.Visible = 'off';
                app.NoiseEditFieldLabel.Visible = 'off';
                app.Channel2DeformationLabel.Visible = 'off';

                %Turn dichroic filter off 
                app.DichroicfilterLabel_2.Visible="off";
                app.AdddichroicfilterSwitch.Visible="off";
                app.AdddichroicfilterSwitchLabel.Visible="off";
                app.CommercialfilterSwitch_3.Visible="off";
                app.CommercialfilterSwitch_3Label.Visible="off";
                app.UIAxes_3.Visible="off";
                app.LoadfilterButton_3.Visible="off";
                app.FilterdefinitionEditField_3.Visible="off";
                app.FilterdefinitionEditField_3Label.Visible="off";
            end
            
            % Plot the filters
            plot_filters(app)
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            is_ok=1;

            if app.par.TwoChannel.use_exp_dichroic && isempty(app.par.TwoChannel.exp_dichroic_filter)
                warndlg('Undefined dichroic filter !')
                is_ok=0;
            end

            if app.par.TwoChannel.use_exp_ch1_filter && isempty(app.par.TwoChannel.exp_ch1_filter)
                warndlg('Undefined filter for Channel 1!')
                is_ok=0;
            end

            if app.par.TwoChannel.use_exp_ch2_filter && isempty(app.par.TwoChannel.exp_ch2_filter)
                warndlg('Undefined filter for Channel 2!')
                is_ok=0;
            end

            
            if is_ok==1
                if ~isempty(app.par.figure_number)
                    if isvalid(app.par.figure_number)
                        close(app.par.figure_number);
                    end
                end
                % Call main app's public function
                updateTwoChannels(app.CallingApp, app.par.TwoChannel)
                % Delete the dialog box
                delete(app)
            end
        end

        % Value changed function: Channel2DefocusEditField
        function Channel2DefocusEditFieldValueChanged(app, event)
            value = app.Channel2DefocusEditField.Value;
            app.par.TwoChannel.defocus=value;
        end

        % Value changed function: XShiftrasterEditField
        function XShiftrasterEditFieldValueChanged(app, event)
            value = app.XShiftrasterEditField.Value;
            app.par.TwoChannel.deform.X_shift=value;
        end

        % Value changed function: YShiftrasterEditField
        function YShiftrasterEditFieldValueChanged(app, event)
            value = app.YShiftrasterEditField.Value;
            app.par.TwoChannel.deform.Y_shift=value;
        end

        % Value changed function: XStretchfractionEditField
        function XStretchfractionEditFieldValueChanged(app, event)
            value = app.XStretchfractionEditField.Value;
            app.par.TwoChannel.deform.X_Stretch=value;
        end

        % Value changed function: YStretchfractionEditField
        function YStretchfractionEditFieldValueChanged(app, event)
            value = app.YStretchfractionEditField.Value;
            app.par.TwoChannel.deform.Y_Stretch=value;
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            value = app.RotationEditField.Value;
            app.par.TwoChannel.deform.Rotation=value;
        end

        % Value changed function: NoiseEditField
        function NoiseEditFieldValueChanged(app, event)
            value = app.NoiseEditField.Value;
            app.par.TwoChannel.deform.Noise=value;
        end

        % Value changed function: FilterdefinitionEditField
        function FilterdefinitionEditFieldValueChanged(app, event)
            value = app.FilterdefinitionEditField.Value;
            app.par.TwoChannel.em_filter_1=value;
            
            % Plot the filters
            plot_filters(app)

            % Check spectra
            check_spectra(app)
        end

        % Value changed function: FilterdefinitionEditField_2
        function FilterdefinitionEditField_2ValueChanged(app, event)
            value = app.FilterdefinitionEditField_2.Value;
            app.par.TwoChannel.em_filter_2=value;
            
            % Plot the filters
            plot_filters(app)

            % Check spectra
            check_spectra(app)
        end

        % Value changed function: SingleCCDSwitch
        function SingleCCDSwitchValueChanged(app, event)
            value = app.SingleCCDSwitch.Value;
            if strcmp(value,'On')==1
                app.par.TwoChannel.single_CCD=1;
            else
                app.par.TwoChannel.single_CCD=0;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: FilterdefinitionEditField_3
        function FilterdefinitionEditField_3ValueChanged(app, event)
            value = app.FilterdefinitionEditField_3.Value;
            app.par.TwoChannel.dichroic_filter=value;
            
            % Plot the filters
            plot_filters(app)

            % Check spectra
            check_spectra(app)
        end

        % Value changed function: CommercialfilterSwitch
        function CommercialfilterSwitchValueChanged(app, event)
            value = app.CommercialfilterSwitch.Value;
            if strcmp(value,'On')
                app.LoadfilterButton.Visible="on";
                app.FilterdefinitionEditField.Visible="off";
                app.FilterdefinitionEditFieldLabel.Visible="off";
                app.par.TwoChannel.use_exp_ch1_filter=1;
            else
                app.LoadfilterButton.Visible="off";
                app.FilterdefinitionEditField.Visible="on";
                app.FilterdefinitionEditFieldLabel.Visible="on";
                app.par.TwoChannel.use_exp_ch1_filter=0;
            end
            % Plot the filters
            plot_filters(app)
            % Check spectra
            check_spectra(app)
        end

        % Button pushed function: LoadfilterButton
        function LoadfilterButtonPushed(app, event)
            if exist(app.par.TwoChannel.filter_path,'dir')
                cd(app.par.TwoChannel.filter_path);
            end
            [MyFile,MyPath] = uigetfile('*.txt','Select a .txt input file containing filter definition');
            
            if isequal(MyFile,0)
                disp('User selected Cancel');
            else
                app.par.TwoChannel.filter_path=MyPath;
                [x_ch,y_ch,~] = get_exp_filter_profile(fullfile(MyPath,MyFile));
                app.par.TwoChannel.exp_ch1_filter=[x_ch;y_ch];
                % Plot the filters
                plot_filters(app)
                % Check spectra
                check_spectra(app)
            end
            figure(app.UIFigure)
        end

        % Value changed function: CommercialfilterSwitch_2
        function CommercialfilterSwitch_2ValueChanged(app, event)
            value = app.CommercialfilterSwitch_2.Value;
            if strcmp(value,'On')
                app.LoadfilterButton_2.Visible="on";
                app.FilterdefinitionEditField_2.Visible="off";
                app.FilterdefinitionEditField_2Label.Visible="off";
                app.par.TwoChannel.use_exp_ch2_filter=1;
            else
                app.LoadfilterButton_2.Visible="off";
                app.FilterdefinitionEditField_2.Visible="on";
                app.FilterdefinitionEditField_2Label.Visible="on";
                app.par.TwoChannel.use_exp_ch2_filter=0;
            end
            % Plot the filters
            plot_filters(app)
            % Check spectra
            check_spectra(app)
        end

        % Button pushed function: LoadfilterButton_2
        function LoadfilterButton_2Pushed(app, event)
            if exist(app.par.TwoChannel.filter_path,'dir')
                cd(app.par.TwoChannel.filter_path);
            end
            [MyFile,MyPath] = uigetfile('*.txt','Select a .txt input file containing filter definition');

            if isequal(MyFile,0)
                disp('User selected Cancel');
            else
                app.par.TwoChannel.filter_path=MyPath;
                [x_ch,y_ch,~] = get_exp_filter_profile(fullfile(MyPath,MyFile));
                app.par.TwoChannel.exp_ch2_filter=[x_ch;y_ch];
                % Plot the filters
                plot_filters(app)
                % Check spectra
                check_spectra(app)
            end
            figure(app.UIFigure)
        end

        % Button pushed function: LoadfilterButton_3
        function LoadfilterButton_3Pushed(app, event)
            if exist(app.par.TwoChannel.filter_path,'dir')
                cd(app.par.TwoChannel.filter_path);
            end
            [MyFile,MyPath] = uigetfile('*.txt','Select a .txt input file containing filter definition');

            if isequal(MyFile,0)
                disp('User selected Cancel');
            else
                app.par.TwoChannel.filter_path=MyPath;
                [x_ch,y_ch,~] = get_exp_filter_profile(fullfile(MyPath,MyFile));
                app.par.TwoChannel.exp_dichroic_filter=[x_ch;y_ch];
                % Plot the filters
                plot_filters(app)
                % Check spectra
                check_spectra(app)
            end
            figure(app.UIFigure)
        end

        % Value changed function: CommercialfilterSwitch_3
        function CommercialfilterSwitch_3ValueChanged(app, event)
            value = app.CommercialfilterSwitch_3.Value;
            if strcmp(value,'On')
                app.LoadfilterButton_3.Visible="on";
                app.FilterdefinitionEditField_3.Visible="off";
                app.FilterdefinitionEditField_3Label.Visible="off";
                app.par.TwoChannel.use_exp_dichroic=1;
            else
                app.LoadfilterButton_3.Visible="off";
                app.FilterdefinitionEditField_3.Visible="on";
                app.FilterdefinitionEditField_3Label.Visible="on";
                app.par.TwoChannel.use_exp_dichroic=0;
            end
            % Plot the filters
            plot_filters(app)
            % Check spectra
            check_spectra(app)
        end

        % Value changed function: AdddichroicfilterSwitch
        function AdddichroicfilterSwitchValueChanged(app, event)
            value = app.AdddichroicfilterSwitch.Value;
            if strcmp(value,'On')==1
                app.par.TwoChannel.add_dichroic=1;
                app.DichroicfilterLabel_2.Visible="on";
                app.CommercialfilterSwitch_3.Visible="on";
                app.CommercialfilterSwitch_3Label.Visible="on";
                app.UIAxes_3.Visible="on";
                if app.par.TwoChannel.use_exp_dichroic==1
                    app.LoadfilterButton_3.Visible="on";
                    app.FilterdefinitionEditField_3.Visible="off";
                    app.FilterdefinitionEditField_3Label.Visible="off";
                    app.CommercialfilterSwitch_3.Value="On";
                else
                    app.LoadfilterButton_3.Visible="off";
                    app.FilterdefinitionEditField_3.Visible="on";
                    app.FilterdefinitionEditField_3Label.Visible="on";
                    app.CommercialfilterSwitch_3.Value="Off";
                end
                % Plot the filters
                plot_filters(app)
                % Check spectra
                check_spectra(app)
            else
                app.par.TwoChannel.add_dichroic=0;
                app.DichroicfilterLabel_2.Visible="off";
                cla(app.UIAxes_3)
                app.UIAxes_3.Visible="off";
                app.LoadfilterButton_3.Visible="off";
                app.CommercialfilterSwitch_3.Visible="off";
                app.CommercialfilterSwitch_3Label.Visible="off";
                app.FilterdefinitionEditField_3.Visible="off";
                app.FilterdefinitionEditField_3Label.Visible="off";
                % Check spectra
                check_spectra(app)
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.par.figure_number)
                if isvalid(app.par.figure_number)
                    close(app.par.figure_number);
                end
            end
            Reenable(app.CallingApp)
            delete(app)            
        end

        % Button pushed function: ShowspectraButton
        function ShowspectraButtonPushed(app, event)
            local_par=struct;
            local_par.TwoChannel=app.par.TwoChannel;
            local_par.Fluorophores=app.par.smis_par.Fluorophores;
            app.par.figure_number=DisplaySpectra(local_par);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Emission Filter Channel 1')
            xlabel(app.UIAxes_1, 'Wavelength [nm]')
            ylabel(app.UIAxes_1, 'Transmission [%]')
            app.UIAxes_1.XLim = [200 800];
            app.UIAxes_1.YLim = [0 100];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Dichroic Filter')
            xlabel(app.UIAxes_3, 'Wavelength [nm]')
            ylabel(app.UIAxes_3, 'Transmission [%]')
            app.UIAxes_3.XLim = [200 800];
            app.UIAxes_3.YLim = [0 100];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Emission Filter Channel 2')
            xlabel(app.UIAxes_2, 'Wavelength [nm]')
            ylabel(app.UIAxes_2, 'Transmission [%]')
            app.UIAxes_2.XLim = [200 800];
            app.UIAxes_2.YLim = [0 100];
            app.EmissionFiltersandTwoChannelOptionsLabel = uilabel(app.UIFigure);
            app.EmissionFiltersandTwoChannelOptionsLabel.Text = 'Emission Filters and Two Channel Options';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Returns to the main menu'};
            app.OKButton.Text = 'OK';
            app.FilterdefinitionEditFieldLabel = uilabel(app.UIFigure);
            app.FilterdefinitionEditFieldLabel.Text = 'Filter definition';
            app.FilterdefinitionEditField = uieditfield(app.UIFigure, 'text');
            app.FilterdefinitionEditField.ValueChangedFcn = createCallbackFcn(app, @FilterdefinitionEditFieldValueChanged, true);
            app.FilterdefinitionEditField.Tooltip = {'Use Matlab format to define emission filter: '; 'single band filter: eg [500 540 0.8] where: '; '500 nm is the low-limit wavelength,'; '540 nm is the high-limit wavelength and 0.8 is the filter transmission'; 'multiband filter: eg [[500 540 0.8]; [700 inf 0.6]]. You can also use the format eg [520 40 0.8] where: '; '520 nm is the central wavelength,'; '40 nm is the bandpass and 0.8 is the filter transmission'};
            app.FilterdefinitionEditField_2Label = uilabel(app.UIFigure);
            app.FilterdefinitionEditField_2Label.Text = 'Filter definition';
            app.FilterdefinitionEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FilterdefinitionEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilterdefinitionEditField_2ValueChanged, true);
            app.FilterdefinitionEditField_2.Tooltip = {'Use Matlab format to define emission filter: '; 'single band filter: eg [500 540 0.8] where: '; '500 nm is the low-limit wavelength,'; '540 nm is the high-limit wavelength and 0.8 is the filter transmission'; 'multiband filter: eg [[500 540 0.8]; [700 inf 0.6]]. You can also use the format eg [520 40 0.8] where: '; '520 nm is the central wavelength,'; '40 nm is the bandpass and 0.8 is the filter transmission'};
            app.Channel2DefocusEditFieldLabel = uilabel(app.UIFigure);
            app.Channel2DefocusEditFieldLabel.Text = 'Channel 2 Defocus';
            app.Channel2DefocusEditField = uieditfield(app.UIFigure, 'numeric');
            app.Channel2DefocusEditField.Limits = [1 5];
            app.Channel2DefocusEditField.ValueChangedFcn = createCallbackFcn(app, @Channel2DefocusEditFieldValueChanged, true);
            app.Channel2DefocusEditField.Tooltip = {'The two channels may not be both perfectly in focus. Set here the defocus of the second channel relative to the first. The PSF width will be multiplied by the entered value in the second channel. Only valid for 2D simulations.'};
            app.Channel2DefocusEditField.Value = 1;
            app.YShiftrasterEditFieldLabel = uilabel(app.UIFigure);
            app.YShiftrasterEditFieldLabel.Text = 'Y-Shift [raster] ';
            app.YShiftrasterEditField = uieditfield(app.UIFigure, 'numeric');
            app.YShiftrasterEditField.ValueChangedFcn = createCallbackFcn(app, @YShiftrasterEditFieldValueChanged, true);
            app.XStretchfractionEditFieldLabel = uilabel(app.UIFigure);
            app.XStretchfractionEditFieldLabel.Text = 'X-Stretch [fraction] ';
            app.XStretchfractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XStretchfractionEditField.ValueChangedFcn = createCallbackFcn(app, @XStretchfractionEditFieldValueChanged, true);
            app.RotationEditFieldLabel = uilabel(app.UIFigure);
            app.RotationEditFieldLabel.Text = 'Rotation [°] ';
            app.RotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Text = 'Noise [%]';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseEditFieldValueChanged, true);
            app.NoiseEditField.Tooltip = {'Noise is applied to all shift, stretch and rotation parameters'};
            app.YStretchfractionEditFieldLabel = uilabel(app.UIFigure);
            app.YStretchfractionEditFieldLabel.Text = 'Y-Stretch [fraction] ';
            app.YStretchfractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YStretchfractionEditField.ValueChangedFcn = createCallbackFcn(app, @YStretchfractionEditFieldValueChanged, true);
            app.Channel2DeformationLabel = uilabel(app.UIFigure);
            app.Channel2DeformationLabel.Tooltip = {'To simulate effects such as chromatic aberration, the second channel might be deformed relative to the first, through X and Y shift, X and Y stretch, and image rotation. A certain amount of noise in these deformations can be introduced.'};
            app.Channel2DeformationLabel.Text = 'Channel 2 Deformation';
            app.XShiftrasterEditFieldLabel = uilabel(app.UIFigure);
            app.XShiftrasterEditFieldLabel.Text = 'X-Shift [raster] ';
            app.XShiftrasterEditField = uieditfield(app.UIFigure, 'numeric');
            app.XShiftrasterEditField.ValueChangedFcn = createCallbackFcn(app, @XShiftrasterEditFieldValueChanged, true);
            app.SingleCCDSwitchLabel = uilabel(app.UIFigure);
            app.SingleCCDSwitchLabel.Text = 'Single CCD';
            app.SingleCCDSwitch = uiswitch(app.UIFigure, 'slider');
            app.SingleCCDSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleCCDSwitchValueChanged, true);
            app.SingleCCDSwitch.Tooltip = {'If this switch is set to On a single .tif stack will be produced where the images of the two channels are adjacent (equivalent of split view).'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Returns to the main menu'};
            app.CancelButton.Text = 'Cancel';
            app.AdddichroicfilterSwitchLabel = uilabel(app.UIFigure);
            app.AdddichroicfilterSwitchLabel.Text = 'Add dichroic filter';
            app.AdddichroicfilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdddichroicfilterSwitch.ValueChangedFcn = createCallbackFcn(app, @AdddichroicfilterSwitchValueChanged, true);
            app.AdddichroicfilterSwitch.Tooltip = {'Use this switch to add a dichroic filter'};
            app.CommercialfilterSwitchLabel = uilabel(app.UIFigure);
            app.CommercialfilterSwitchLabel.Text = 'Commercial filter';
            app.CommercialfilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.CommercialfilterSwitch.ValueChangedFcn = createCallbackFcn(app, @CommercialfilterSwitchValueChanged, true);
            app.CommercialfilterSwitch.Tooltip = {'Use this switch to use a commercially available filter instead of a manual filter definition'};
            app.LoadfilterButton = uibutton(app.UIFigure, 'push');
            app.LoadfilterButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilterButtonPushed, true);
            app.LoadfilterButton.Tooltip = {'Load a commercially available filter (.txt file)'};
            app.LoadfilterButton.Text = 'Load filter';
            app.CommercialfilterSwitch_2Label = uilabel(app.UIFigure);
            app.CommercialfilterSwitch_2Label.Text = 'Commercial filter';
            app.CommercialfilterSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.CommercialfilterSwitch_2.ValueChangedFcn = createCallbackFcn(app, @CommercialfilterSwitch_2ValueChanged, true);
            app.CommercialfilterSwitch_2.Tooltip = {'Use this switch to use a commercially available filter instead of a manual filter definition'};
            app.Channel1Label = uilabel(app.UIFigure);
            app.Channel1Label.Text = 'Channel 1';
            app.Channel2Label = uilabel(app.UIFigure);
            app.Channel2Label.Text = 'Channel 2';
            app.LoadfilterButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadfilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadfilterButton_2Pushed, true);
            app.LoadfilterButton_2.Tooltip = {'Load a commercially available filter (.txt file)'};
            app.LoadfilterButton_2.Text = 'Load filter';
            app.DichroicfilterLabel_2 = uilabel(app.UIFigure);
            app.DichroicfilterLabel_2.Text = 'Dichroic filter';
            app.CommercialfilterSwitch_3Label = uilabel(app.UIFigure);
            app.CommercialfilterSwitch_3Label.Text = 'Commercial filter';
            app.CommercialfilterSwitch_3 = uiswitch(app.UIFigure, 'slider');
            app.CommercialfilterSwitch_3.ValueChangedFcn = createCallbackFcn(app, @CommercialfilterSwitch_3ValueChanged, true);
            app.CommercialfilterSwitch_3.Tooltip = {'Use this switch to use a commercially available filter instead of a manual filter definition'};
            app.FilterdefinitionEditField_3Label = uilabel(app.UIFigure);
            app.FilterdefinitionEditField_3Label.Text = 'Filter definition';
            app.FilterdefinitionEditField_3 = uieditfield(app.UIFigure, 'text');
            app.FilterdefinitionEditField_3.ValueChangedFcn = createCallbackFcn(app, @FilterdefinitionEditField_3ValueChanged, true);
            app.FilterdefinitionEditField_3.Tooltip = {'Use Matlab format to define dichroic filter: '; 'eg [600 10 0.1 0.9] where: '; '600 nm is the cutoff wavelength,'; '10 nm is the transition bandpass, 0.1 is the lowpass transmission and 0.9 is the longpass transmission'};
            app.LoadfilterButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadfilterButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadfilterButton_3Pushed, true);
            app.LoadfilterButton_3.Tooltip = {'Load a commercially available filter (.txt file)'};
            app.LoadfilterButton_3.Text = 'Load filter';
            app.ShowspectraButton = uibutton(app.UIFigure, 'push');
            app.ShowspectraButton.ButtonPushedFcn = createCallbackFcn(app, @ShowspectraButtonPushed, true);
            app.ShowspectraButton.Tooltip = {'Click this button to show Absorption/Excitation and fluorescence emission spectra of the fluorophores (as in main SMIS window)'};
            app.ShowspectraButton.Text = 'Show spectra';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiltersAndChannels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end