classdef FluorophorePairing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        PairingTable                   matlab.ui.control.Table
        OKButton                       matlab.ui.control.Button
        FluorophorespairingsetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        FluorophorePairingpar=[];
        FluorophoreNames=[];
        TableData=[];
        n_fluorophores=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.FluorophorePairingpar=mainapp.smis_gui_parameters.Fluorophore_Pairing;
            
            app.FluorophoreNames={mainapp.smis_gui_parameters.Fluorophores.name};
            
            app.n_fluorophores=mainapp.smis_gui_parameters.n_fluorophores;
            
            % Setup Table Position size
            
            app.PairingTable.Position=[75,max([50,350-20*app.n_fluorophores]),800,min([350,30+20*app.n_fluorophores])]; % Adjust position
            % Show the names of the fluorescent states
            app.PairingTable.RowName=app.FluorophoreNames;
            
            paired_fluorophores_ids=app.FluorophorePairingpar.pairs(:);
            
            paired=false(app.n_fluorophores,1);
            if ~isempty(app.FluorophorePairingpar.pairs)
                paired(paired_fluorophores_ids)=true;
            end
            
            d1d2_dist=nan(app.n_fluorophores,1);
            d1d2_dist_sig=nan(app.n_fluorophores,1);
            d2_constrained=false(app.n_fluorophores,1);
            paired_names=cell(app.n_fluorophores,1);
           
            % Go through all fluorophores
            for i=1:app.n_fluorophores
                if ismember(i,paired_fluorophores_ids) % Fluorophore is paired
                    w=find(app.FluorophorePairingpar.pairs'==i,1);
                    w_row=ceil(w/2);
                    d1d2_dist(i)=app.FluorophorePairingpar.d1d2_dist(w_row);
                    d1d2_dist_sig(i)=app.FluorophorePairingpar.d1d2_dist_sig(w_row);
                    d2_constrained(i)=app.FluorophorePairingpar.d2_constrained(w_row);
                    paired_id=setdiff(app.FluorophorePairingpar.pairs(w_row,:),i);
                    paired_names(i)=app.FluorophoreNames(paired_id);
                end
            end
            
            app.TableData = table(paired,paired_names,d1d2_dist,d1d2_dist_sig,d2_constrained,'VariableNames',app.PairingTable.ColumnName);
            
            % Fill the data
            app.PairingTable.Data=app.TableData;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateFluorophorePairingpar(app.CallingApp, app.FluorophorePairingpar)
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: PairingTable
        function PairingTableCellEdit(app, event)
            newData = event.NewData;
            
            fluo_id = event.Indices(1);
            par_id = event.Indices(2);
            
            
            valid_change=1; % Initialize
            %Issue warnings when transformation is not possible
            
            
            switch par_id
                case 1 % Change the pairing set up
                    if newData==0 % Undo the pairing
                        paired_fluo_id = find(strcmp(app.FluorophoreNames,app.PairingTable.Data{fluo_id,2})==1,1);
                        app.PairingTable.Data(fluo_id,:)={false,'None',nan,nan,false};
                        app.PairingTable.Data(paired_fluo_id,:)={false,'None',nan,nan,false};
                    end
                    
                    if newData==1 % Create pairing with the first available unpaired fluorophore
                        possible_ids=1:app.n_fluorophores;
                        possible_ids(fluo_id)=[];
                        possible_paired_ids=setdiff(possible_ids,app.FluorophorePairingpar.pairs(:));
                        if ~isempty(possible_paired_ids) % A fluorophore has been found for possible pairing
                            paired_fluo_id=possible_paired_ids(1); % Take the first one available
                            paired_fluo_id_name=app.FluorophoreNames{paired_fluo_id};
                            app.PairingTable.Data(fluo_id,:)={true,paired_fluo_id_name,3.0,0.1,false};
                            app.PairingTable.Data(paired_fluo_id,:)={true,app.FluorophoreNames{fluo_id},3.0,0.1,false};
                        else
                            warndlg('There is no fluorophore left for pairing !','Warning');
                            app.PairingTable.Data{fluo_id,par_id}=0;
                            valid_change=0;
                        end
                    end
                    
                    
                case 2 % Change paired fluorophore name
                    paired_fluo_id=find(strcmp(app.FluorophoreNames,newData)==1,1); % Find out the paired fluorophore ID
                    if ~isempty(paired_fluo_id)
                        app.PairingTable.Data(paired_fluo_id,3:end)=app.PairingTable.Data(fluo_id,3:end);
                        app.PairingTable.Data(paired_fluo_id,1)=app.PairingTable.Data(fluo_id,1);
                        app.PairingTable.Data{paired_fluo_id,2}=app.FluorophoreNames(fluo_id);
                        % Unpair the previously paired fluorophore
                        previous_paired_fluo_id=find(strcmp(app.FluorophoreNames,event.PreviousData)==1,1); % Find out the paired fluorophore ID
                        app.PairingTable.Data(previous_paired_fluo_id,:)={false,'None',nan,nan,false};
                    else
                        warndlg('Paired fluorophore name does not exist (spelling mistake ?) !','Warning');
                        app.PairingTable.Data{fluo_id,par_id}={event.PreviousData};
                        valid_change=0;
                    end
            end
            
            if ismember(par_id,3:5)
                if app.PairingTable.Data{fluo_id,1}==1 % Valid case
                    if ~isnan(newData)
                        paired_fluo_id = find(strcmp(app.FluorophoreNames,app.PairingTable.Data{fluo_id,2})==1,1);
                        app.PairingTable.Data{paired_fluo_id,par_id}=newData;
                    else
                        warndlg('Entered value is not a number !','Warning');
                        app.PairingTable.Data{fluo_id,par_id}=event.PreviousData;
                    end
                else
                    warndlg('Fluorophore is not paired !','Warning');
                    app.PairingTable.Data{fluo_id,par_id}=event.PreviousData;
                    valid_change=0;
                end
            end
            
            if valid_change==1
                % Find out the paired fluorophores
                paired_fluorophores_ids=find(app.PairingTable.Data{:,1}==1);
                
                if ~isempty(paired_fluorophores_ids) % Some fluorophores are paired
                    n_pairs=numel(paired_fluorophores_ids)/2;
                    pairs=zeros(n_pairs,2);
                    d1d2_dist=zeros(n_pairs,1);
                    d1d2_dist_sig=zeros(n_pairs,1);
                    d2_constrained=zeros(n_pairs,1);
                    
                    processed_ids=zeros(1,numel(paired_fluorophores_ids));
                    k=1;
                    for i=1:numel(paired_fluorophores_ids) % Find out the pairs
                        id=paired_fluorophores_ids(i);
                        if processed_ids(i)==0
                            paired_id=find(strcmp(app.FluorophoreNames,app.PairingTable.Data{id,2})==1,1); % Find out the paired fluorophore ID
                            pairs(k,:)=[id,paired_id];
                            d1d2_dist(k)=app.PairingTable.Data{id,3};
                            d1d2_dist_sig(k)=app.PairingTable.Data{id,4};
                            d2_constrained(k)=app.PairingTable.Data{id,5};
                            processed_ids([i,find(paired_fluorophores_ids==paired_id,1)])=1;
                            k=k+1;
                        end
                    end
                    
                    app.FluorophorePairingpar.pairs=pairs;
                    app.FluorophorePairingpar.d1d2_dist=d1d2_dist;
                    app.FluorophorePairingpar.d1d2_dist_sig=d1d2_dist_sig;
                    app.FluorophorePairingpar.d2_constrained=d2_constrained;
                    
                else % No pairing of fluorophores
                    app.FluorophorePairingpar.pairs=[];
                    app.FluorophorePairingpar.d1d2_dist=[];
                    app.FluorophorePairingpar.d1d2_dist_sig=[];
                    app.FluorophorePairingpar.d2_constrained=[];
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FluorophorespairingsetupLabel = uilabel(app.UIFigure);
            app.FluorophorespairingsetupLabel.Text = 'Fluorophores pairing setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PairingTable = uitable(app.UIFigure);
            app.PairingTable.ColumnName = {'Paired'; 'Paired Fluorophore Name'; 'Pair Distance [nm]'; 'Pair Distance Fluctuation [nm]'; 'Constrained in pattern'};
            app.PairingTable.RowName = {'Fluorophore1'; 'Fluorophore2'};
            app.PairingTable.ColumnEditable = true;
            app.PairingTable.CellEditCallback = createCallbackFcn(app, @PairingTableCellEdit, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluorophorePairing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end