classdef FluorophoreSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LinkagetargetsubpatternidsLabel  matlab.ui.control.Label
        LinkageControlTable             matlab.ui.control.Table
        SubpatterncontrolSwitch         matlab.ui.control.Switch
        SubpatterncontrolSwitchLabel    matlab.ui.control.Label
        FixedovertimeCheckBox           matlab.ui.control.CheckBox
        LinkagestdnmEditField           matlab.ui.control.NumericEditField
        LinkagestdnmEditFieldLabel      matlab.ui.control.Label
        LinkagelengthnmEditField        matlab.ui.control.NumericEditField
        LinkagelengthnmEditFieldLabel   matlab.ui.control.Label
        LinkageerrorSwitch              matlab.ui.control.Switch
        LinkageerrorSwitchLabel         matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        ChecksubpatternsDropDown        matlab.ui.control.DropDown
        ChecksubpatternsDropDownLabel   matlab.ui.control.Label
        OligomerstochiometryEditField   matlab.ui.control.NumericEditField
        OligomerstochiometryEditFieldLabel  matlab.ui.control.Label
        DefinenewFluorophoreButton      matlab.ui.control.Button
        PhiEditField                    matlab.ui.control.NumericEditField
        PhiEditFieldLabel               matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        FluorescenceQuantumYieldsLabel  matlab.ui.control.Label
        QYTable                         matlab.ui.control.Table
        PairsofstatesinrapidequilibriumEditField  matlab.ui.control.EditField
        PairsofstatesinrapidequilibriumEditFieldLabel  matlab.ui.control.Label
        SaveFluorophoreButton           matlab.ui.control.Button
        InitialstateEditField           matlab.ui.control.EditField
        InitialstateEditFieldLabel      matlab.ui.control.Label
        ThermaldarkstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        ThermaldarkstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        ThermaldarkstatesafterphotoconversionEditField  matlab.ui.control.EditField
        ThermaldarkstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        PhotoactivedarkstatesafterphotoconversionEditField  matlab.ui.control.EditField
        PhotoactivedarkstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        BleachedstatesafterphotoconversionEditField  matlab.ui.control.EditField
        BleachedstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        BleachedstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        BleachedstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        FluorescentstatesafterphotoconversionEditField  matlab.ui.control.EditField
        FluorescentstatesafterphotoconversionEditFieldLabel  matlab.ui.control.Label
        FluorophoreisirreversiblyphotoconvertibleLabel  matlab.ui.control.Label
        NameofconvertedstateEditField   matlab.ui.control.EditField
        NameofconvertedstateEditFieldLabel  matlab.ui.control.Label
        FluorescentstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        FluorescentstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        FractionInSubpatternsTable      matlab.ui.control.Table
        FluorogenicityTable             matlab.ui.control.Table
        FluorogenicitySwitch            matlab.ui.control.Switch
        FluorogenicitySwitchLabel       matlab.ui.control.Label
        SetupthermalexchangeratematrixsButton  matlab.ui.control.Button
        SetupphototransformationsquantumyieldsButton  matlab.ui.control.Button
        MinimumdistancebetweenfluorophoresnmEditField  matlab.ui.control.NumericEditField
        MinimumdistancebetweenfluorophoresnmEditFieldLabel  matlab.ui.control.Label
        PhotoactivedarkstatesbeforephotoconversionEditField  matlab.ui.control.EditField
        PhotoactivedarkstatesbeforephotoconversionEditFieldLabel  matlab.ui.control.Label
        AllowStochasticReorientationSwitch  matlab.ui.control.Switch
        AllowStochasticReorientationSwitchLabel  matlab.ui.control.Label
        Jumprates1EditField             matlab.ui.control.NumericEditField
        Jumprates1EditFieldLabel        matlab.ui.control.Label
        UniquedipoleorientationSwitch   matlab.ui.control.Switch
        UniquedipoleorientationSwitchLabel  matlab.ui.control.Label
        FixeddipoleorientationSwitch_2  matlab.ui.control.Switch
        FixeddipoleorientationSwitch_2Label  matlab.ui.control.Label
        NumberofphotophysicalstatesEditField  matlab.ui.control.NumericEditField
        NumberofphotophysicalstatesEditFieldLabel  matlab.ui.control.Label
        LoadFluorophoreButton           matlab.ui.control.Button
        pKaTable                        matlab.ui.control.Table
        pHofthemediumEditField          matlab.ui.control.NumericEditField
        pHofthemediumEditFieldLabel     matlab.ui.control.Label
        pHSensitivitySwitch             matlab.ui.control.Switch
        pHSensitivitySwitchLabel        matlab.ui.control.Label
        FractionoffluorophoresineachsubpatternLabel  matlab.ui.control.Label
        MaturationlevelEditField        matlab.ui.control.NumericEditField
        MaturationlevelEditFieldLabel   matlab.ui.control.Label
        NumberofmoleculesEditField      matlab.ui.control.NumericEditField
        NumberofmoleculesEditFieldLabel  matlab.ui.control.Label
        CopySetupFromOtherFluorophoreDropDown  matlab.ui.control.DropDown
        CopySetupFromOtherFluorophoreDropDownLabel  matlab.ui.control.Label
        FluorophorenameEditField        matlab.ui.control.EditField
        FluorophorenameEditFieldLabel   matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        FluorophorePhotophysicsSetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        smis_gui_parameters = [] % Structure with all parameters
        Fluorophorepar=[]; % Initialize the parameters
        DefaultDir=[]; % Default dir for fluorophores
        OtherFluorophorepar=[]; % Initialize the parameters
        selected_fluorophore=[] ; % Initialize selected fluorophore
        n_fluorophores=[] ; % Initialize number of fluorophores
        n_states=[] ; % Initialize number of photophysical states
        n_fluo_states=[] ; % Initialize number of fluorescent states
        n_sp=[] ; % Initialize number of sub-patterns
        sp_ids=[] ; % Initialize sub-patterns ids
        state_names=[] ; % Initialize state names
        QY_App=[]; % Phototransformation quantum yield setup App
        QYSetup_Position=[]; % Description
        kT_App=[]; % Phototransformation quantum yield setup App
        kTSetup_Position=[]; % Description
    end
    
    properties (Access = public)

    end
    
    methods (Access = private)
        
        function UpdateFluorophoreSetup(app)
            app.FluorophorenameEditField.Value = app.Fluorophorepar.name;
            
            % Display Oligomer stoichiometry or number of fluorophores
            if app.smis_gui_parameters.distribute_in_clusters==1
                app.OligomerstochiometryEditField.Value=fix(app.Fluorophorepar.N/app.smis_gui_parameters.n_clusters(app.selected_fluorophore)); % In case of qPALM
            else
                app.NumberofmoleculesEditField.Value=app.Fluorophorepar.N;
            end
            
            % Display photophysical states
            app.n_states=app.Fluorophorepar.Photophysics.n_states; % Number of photophysical states
            app.n_fluo_states=numel(app.Fluorophorepar.Photophysics.fluorescent_states); % Number of fluorescent states
            app.state_names=app.Fluorophorepar.Photophysics.state_names;
            converted_state=app.Fluorophorepar.Photophysics.converted_state;
            initial_state=app.Fluorophorepar.Photophysics.initial_state;
            fluo_states=app.Fluorophorepar.Photophysics.fluorescent_states;
            bleached_states=app.Fluorophorepar.Photophysics.bleached_states;
            photoactive_dark_states=setdiff(app.Fluorophorepar.Photophysics.photoactive_states,fluo_states);
            photoactive_thermal_states=setdiff(1:app.n_states,horzcat(app.Fluorophorepar.Photophysics.photoactive_states,bleached_states));
            states_ids=app.Fluorophorepar.Photophysics.state_ids;
            
            app.NumberofphotophysicalstatesEditField.Value=app.n_states;
            app.InitialstateEditField.Value=app.state_names{initial_state};
            
            if converted_state>1 % Fluorophore is photoconvertible
                % Before photoconversion
                app.FluorophoreisirreversiblyphotoconvertibleLabel.Text='Fluorophore is irreversibly photoconvertible';
                app.NameofconvertedstateEditField.Visible='on';
                app.NameofconvertedstateEditFieldLabel.Visible='on';
                app.NameofconvertedstateEditField.Value=app.state_names{converted_state};
                app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text='Fluorescent states before photoconversion';
                app.FluorescentstatesbeforephotoconversionEditField.Value=get_names(app,fluo_states(fluo_states<converted_state));
                app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text='Photoactive dark states before photoconversion';
                app.PhotoactivedarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_dark_states(photoactive_dark_states<converted_state));
                app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text='Thermal dark states before photoconversion';
                app.ThermaldarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_thermal_states(photoactive_thermal_states<converted_state));
                app.BleachedstatesbeforephotoconversionEditFieldLabel.Text='Bleached states before photoconversion';
                app.BleachedstatesbeforephotoconversionEditField.Value=get_names(app,bleached_states(bleached_states<converted_state));
                
                % After photoconversion
                app.FluorescentstatesafterphotoconversionEditField.Visible='on';
                app.FluorescentstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.FluorescentstatesafterphotoconversionEditField.Value=get_names(app,fluo_states(fluo_states>=converted_state));
                app.PhotoactivedarkstatesafterphotoconversionEditField.Visible='on';
                app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.PhotoactivedarkstatesafterphotoconversionEditField.Value=get_names(app,photoactive_dark_states(photoactive_dark_states>=converted_state));
                app.ThermaldarkstatesafterphotoconversionEditField.Visible='on';
                app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.ThermaldarkstatesafterphotoconversionEditField.Value=get_names(app,photoactive_thermal_states(photoactive_thermal_states>=converted_state));
                app.BleachedstatesafterphotoconversionEditField.Visible='on';
                app.BleachedstatesafterphotoconversionEditFieldLabel.Visible='on';
                app.BleachedstatesafterphotoconversionEditField.Value=get_names(app,bleached_states(bleached_states>=converted_state));
            else
                app.FluorophoreisirreversiblyphotoconvertibleLabel.Text='Fluorophore is not photoconvertible';
                app.NameofconvertedstateEditField.Visible='off';
                app.NameofconvertedstateEditFieldLabel.Visible='off';
                app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text='Fluorescent states';
                app.FluorescentstatesbeforephotoconversionEditField.Value=get_names(app,fluo_states);
                app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text='Photoactive dark states';
                app.PhotoactivedarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_dark_states);
                app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text='Thermal dark states';
                app.ThermaldarkstatesbeforephotoconversionEditField.Value=get_names(app,photoactive_thermal_states);
                app.BleachedstatesbeforephotoconversionEditFieldLabel.Text='Bleached states';
                app.BleachedstatesbeforephotoconversionEditField.Value=get_names(app,bleached_states);
                app.FluorescentstatesafterphotoconversionEditField.Visible='off';
                app.FluorescentstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.PhotoactivedarkstatesafterphotoconversionEditField.Visible='off';
                app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.ThermaldarkstatesafterphotoconversionEditField.Visible='off';
                app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Visible='off';
                app.BleachedstatesafterphotoconversionEditField.Visible='off';
                app.BleachedstatesafterphotoconversionEditFieldLabel.Visible='off';
            end
            
            % Display states in rapid equilibrium
            w_rapid=setdiff(1:app.n_states, unique(states_ids)); % Set of states in rapid equilibrium
            if ~isempty(w_rapid) % States in rapid equilibrium were found
                S=[];
                for k=1:numel(w_rapid)
                    rapid_ex_state_names=app.state_names(states_ids==states_ids(w_rapid(k)));
                    S=[S,'(',rapid_ex_state_names{1},', ',rapid_ex_state_names{2},') ; ']; %#ok<AGROW>
                end
                S=S(1:end-2);
                app.PairsofstatesinrapidequilibriumEditField.Visible='on';
                app.PairsofstatesinrapidequilibriumEditFieldLabel.Visible='on';
                app.PairsofstatesinrapidequilibriumEditField.Value=S;
            else
                app.PairsofstatesinrapidequilibriumEditField.Visible='off';
                app.PairsofstatesinrapidequilibriumEditFieldLabel.Visible='off';
            end
            
            % Environmental parameters
            if app.Fluorophorepar.Environment.pH_sensitivity==1
                app.pHSensitivitySwitch.Value=1;
                app.pHofthemediumEditField.Visible='on';
                app.pHofthemediumEditFieldLabel.Visible='on';
                if ~isempty(app.Fluorophorepar.Environment.pH)
                    app.pHofthemediumEditField.Value=app.Fluorophorepar.Environment.pH;
                else
                    app.Fluorophorepar.Environment.pH=app.pHofthemediumEditField.Value;
                end
                app.pKaTable.Visible='on';
                
                MyColumnWidth=num2cell(80*ones(1,app.n_fluo_states));
                app.pKaTable.ColumnWidth=MyColumnWidth;

                pKaTableWidth=min([43+80*app.n_fluo_states,350]);
                if (43+80*app.n_fluo_states)>350 % Handle scroll bar
                    pKaTableHeight=90;
                else
                    pKaTableHeight=73;
                end

                pKaTableBottomPos=127;
                pKaTableLeftPos=795;

                app.pKaTable.Position=[pKaTableLeftPos,pKaTableBottomPos,pKaTableWidth,pKaTableHeight]; % Adjust position

                % Show the names of the fluorescent states
                app.pKaTable.ColumnName=app.state_names(fluo_states);
                % Fill the data
                if ~isempty(app.Fluorophorepar.Environment.pKa)
                    app.pKaTable.Data=[app.Fluorophorepar.Environment.pKa; app.Fluorophorepar.Environment.Hill];
                else
                    app.pKaTable.Data=nan(2, numel(app.Fluorophorepar.Photophysics.fluorescent_states));
                end
            else
                app.pHSensitivitySwitch.Value=0;
                app.pHofthemediumEditField.Visible='off';
                app.pHofthemediumEditFieldLabel.Visible='off';
                app.pKaTable.Visible='off';
            end
            
            % Set up the table for fluorescence Quantum yields
            MyColumnWidth=num2cell(80*ones(1,app.n_fluo_states));
            app.QYTable.ColumnWidth=MyColumnWidth;

            QYTableWidth=min([43+80*app.n_fluo_states,300]);
            if (43+80*app.n_fluo_states)>300 % Handle scroll bar
                QYTableHeight=70;
            else
                QYTableHeight=51;
            end
            QYTableBottomPos=127;
            QYTableLeftPos=228;

            app.QYTable.Position=[QYTableLeftPos,QYTableBottomPos,QYTableWidth,QYTableHeight]; % Adjust position

           
            % Show the names of the fluorescent states
            app.QYTable.ColumnName=app.state_names(fluo_states);
            
            % Fill the data if available
            if isempty(app.Fluorophorepar.Photophysics.quantum_yield) || numel(app.Fluorophorepar.Photophysics.quantum_yield)~=app.n_fluo_states
                MyData=ones(1,app.n_fluo_states);
                app.QYTable.Data=MyData;
                app.Fluorophorepar.Photophysics.quantum_yield=MyData;
            else
                app.QYTable.Data=app.Fluorophorepar.Photophysics.quantum_yield;
            end
            
            % Set the table for fluorogenicity
            if app.Fluorophorepar.Environment.fluorogenic==1
                app.FluorogenicitySwitch.Value='On';
                app.FluorogenicityTable.Visible='on';
                MyTableLength=min([43+50*app.n_sp,500]);
                MyColumnWidth=num2cell(50*ones(1,app.n_sp));
                app.FluorogenicityTable.ColumnWidth=MyColumnWidth;
                app.FluorogenicityTable.Position=[228,217,MyTableLength,51]; % Adjust position
                
                % Show the names of the fluorescent states
                app.FluorogenicityTable.ColumnName=num2cell(app.sp_ids);
                
                % Fill the data if available
                if isempty(app.Fluorophorepar.Environment.fluorogenicity==1) || numel(app.Fluorophorepar.Environment.fluorogenicity)~=app.n_sp
                    MyData=ones(1,app.n_sp);
                    app.FluorogenicityTable.Data=MyData;
                    app.Fluorophorepar.Environment.fluorogenicity=MyData;
                else
                    app.FluorogenicityTable.Data=app.Fluorophorepar.Environment.fluorogenicity;
                end
            else
                app.FluorogenicityTable.Visible='off';
                app.FluorogenicitySwitch.Value='Off';
            end
            
            % Set the anisotropy
            if app.Fluorophorepar.Orientation.anisotropy==1
                app.FixeddipoleorientationSwitch_2.Value='On';
                
                app.UniquedipoleorientationSwitch.Visible='on';
                app.UniquedipoleorientationSwitchLabel.Visible='on';
                if app.Fluorophorepar.Orientation.dipole_orientation==1
                    app.UniquedipoleorientationSwitch.Value='On';
                else
                    app.UniquedipoleorientationSwitch.Value='Off';
                end
                
                if strcmp(app.UniquedipoleorientationSwitch.Value,'On')==1
                    app.ThetaEditField.Visible='on';
                    app.ThetaEditFieldLabel.Visible='on';
                    app.ThetaEditField.Value=app.Fluorophorepar.Orientation.theta_fixed;
                    app.PhiEditField.Visible='on';
                    app.PhiEditFieldLabel.Visible='on';
                    app.PhiEditField.Value=app.Fluorophorepar.Orientation.phi_fixed;
                else
                    app.ThetaEditField.Visible='off';
                    app.ThetaEditFieldLabel.Visible='off';
                    app.PhiEditField.Visible='off';
                    app.PhiEditFieldLabel.Visible='off';
                end
                
                app.AllowStochasticReorientationSwitch.Visible='on';
                app.AllowStochasticReorientationSwitchLabel.Visible='on';
                if app.Fluorophorepar.Orientation.jump_allowed==1
                    app.AllowStochasticReorientationSwitch.Value='On';
                else
                    app.AllowStochasticReorientationSwitch.Value='Off';
                end
                
                if strcmp(app.AllowStochasticReorientationSwitch.Value,'On')==1
                    app.Jumprates1EditField.Visible='on';
                    app.Jumprates1EditFieldLabel.Visible='on';
                    app.Jumprates1EditField.Value=app.Fluorophorepar.Orientation.jump_rate;
                else
                    app.Jumprates1EditField.Visible='off';
                    app.Jumprates1EditFieldLabel.Visible='off';
                end
            else
                app.FixeddipoleorientationSwitch_2.Value='Off';
                app.UniquedipoleorientationSwitch.Visible='off';
                app.UniquedipoleorientationSwitchLabel.Visible='off';
                app.ThetaEditField.Visible='off';
                app.ThetaEditFieldLabel.Visible='off';
                app.PhiEditField.Visible='off';
                app.PhiEditFieldLabel.Visible='off';
                app.AllowStochasticReorientationSwitch.Visible='off';
                app.AllowStochasticReorientationSwitchLabel.Visible='off';
                app.Jumprates1EditField.Visible='off';
                app.Jumprates1EditFieldLabel.Visible='off';
            end
        end
        
        function S = get_names(app,states)
            if ~isempty(states)
                S=[];
                for k=1:numel(states)
                    S=[S,app.state_names{states(k)},' ; ']; %#ok<AGROW>
                end
                S=S(1:end-2);
            else
                S='-';
            end
            
        end
    end
    
    methods (Access = public)
        
        function updateQY(app, QYpar, App_Position)
            %Keep memory of the QYsetup app position
            app.QYSetup_Position=App_Position;
            
            % Store inputs as properties
            if ~isempty(QYpar)
                app.Fluorophorepar.Photophysics.trans_q = QYpar;
            end
            
            % Re-enable the button
            app.SetupphototransformationsquantumyieldsButton.Enable='on';
        end
        
        function updatekT(app, kTpar, App_Position)
            %Keep memory of the kTsetup app position
            app.kTSetup_Position=App_Position;
            
            % Store inputs as properties
            if ~isempty(kTpar)
                app.Fluorophorepar.Photophysics.trans_k = kTpar;
            end
            
            % Re-enable the button
            app.SetupthermalexchangeratematrixsButton.Enable='on';
        end
        
        function updateCancel(app)
            % Re-enable the buttons
            app.SetupthermalexchangeratematrixsButton.Enable='on';
            app.SetupphototransformationsquantumyieldsButton.Enable='on';
            app.DefinenewFluorophoreButton.Enable='on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selected_fluorophore)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Get all the parameters
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
           
            % Grab the number of the selected fluorophore
            app.selected_fluorophore=selected_fluorophore;
            input_fluorophore_par=app.smis_gui_parameters.Fluorophores;
            
            % Initialize selected fluorophore parameters
            app.Fluorophorepar=input_fluorophore_par(selected_fluorophore);
            
            % Get the default dir
            app.DefaultDir=app.smis_gui_parameters.DefaultDirs.Fluorophore_dir;

            % Initialize other fluorophore's parameters
            app.OtherFluorophorepar=input_fluorophore_par;
            app.OtherFluorophorepar(selected_fluorophore)=[];
            
            
            % Get number of fluorophores
            app.n_fluorophores=size(input_fluorophore_par,2);
            
            % Said the correct items data for switches (bug in appdesigner)
            app.pHSensitivitySwitch.ItemsData=0:1;
            
            % Switch between # of fluorophores and oligomer stochiometry if
            % qPALM mode is on
            if app.smis_gui_parameters.distribute_in_clusters==1
                app.NumberofmoleculesEditField.Visible='off';
                app.NumberofmoleculesEditFieldLabel.Visible='off';
                app.OligomerstochiometryEditField.Visible='on';
                app.OligomerstochiometryEditFieldLabel.Visible='on';
                app.FractionoffluorophoresineachsubpatternLabel.Visible='off';
                app.FractionInSubpatternsTable.Visible='off';
                app.ChecksubpatternsDropDown.Visible='off';
                app.ChecksubpatternsDropDownLabel.Visible='off';
                app.FluorogenicitySwitch.Visible='off';
                app.FluorogenicitySwitchLabel.Visible='off';
                app.Fluorophorepar.Environment.fluorogenic=0;
                
                % Boost the number of fluorophore to make at least 1
                % fluorophore per cluster.
                if app.Fluorophorepar.N<app.smis_gui_parameters.n_clusters(app.selected_fluorophore)
                    app.Fluorophorepar.N=app.smis_gui_parameters.n_clusters(app.selected_fluorophore);
                end
                
            else
                app.NumberofmoleculesEditField.Visible='on';
                app.NumberofmoleculesEditFieldLabel.Visible='on';
                app.OligomerstochiometryEditField.Visible='off';
                app.OligomerstochiometryEditFieldLabel.Visible='off';
                app.ChecksubpatternsDropDown.Visible='on';
                app.ChecksubpatternsDropDownLabel.Visible='on';
                app.FluorogenicitySwitch.Visible='on';
                app.FluorogenicitySwitchLabel.Visible='on';
            end
            
            
            % Handle visibility of copy fluorophore option
            if app.n_fluorophores<2
                app.CopySetupFromOtherFluorophoreDropDown.Visible = 'off';
                app.CopySetupFromOtherFluorophoreDropDownLabel.Visible = 'off';
            else
                other_fluorophore_names={app.OtherFluorophorepar.name};
                other_fluorophore_names=horzcat('Choose',other_fluorophore_names);
                app.CopySetupFromOtherFluorophoreDropDown.Items=other_fluorophore_names;
            end
            
            app.n_sp=app.smis_gui_parameters.pattern_ids(selected_fluorophore).n_sp; % Number of sub patterns
            app.sp_ids=app.smis_gui_parameters.pattern_ids(selected_fluorophore).sp_ids; % Ids of sub patterns
            % Set up the table for the sub pattern ids if qPALM option is
            % off
            if app.smis_gui_parameters.distribute_in_clusters~=1 && ~isempty(app.n_sp)

                % Setup Subpattern Table 
                MyTableWidth=min([56+50*app.n_sp,500]); 
                if MyTableWidth>=500
                    MyTableHeight=67; 
                    MyTableBottomPos=463;
                else
                    MyTableHeight=51; 
                    MyTableBottomPos=478;
                end
                MyTableLeftPos=25;
                app.FractionInSubpatternsTable.Position=[MyTableLeftPos,MyTableBottomPos,MyTableWidth,MyTableHeight]; % Adjust position

                MyColumnWidth=num2cell(50*ones(1,app.n_sp));
                app.FractionInSubpatternsTable.ColumnWidth=MyColumnWidth;

                % Show the subpattern's IDs
                app.FractionInSubpatternsTable.ColumnName=num2cell(app.sp_ids);

                % Fill the data if available
                if isempty(app.Fluorophorepar.n_sp_fr) || numel(app.Fluorophorepar.n_sp_fr)~=app.n_sp
                    MyData=zeros(1,app.n_sp);
                    MyData(1)=nan;
                    app.FractionInSubpatternsTable.Data=MyData;
                    app.Fluorophorepar.n_sp_fr=MyData;
                else
                    app.FractionInSubpatternsTable.Data=app.Fluorophorepar.n_sp_fr;
                end


                % Set up the Linkage control table
                MyTableWidth=min([58+50*app.n_sp,500]); 
                if MyTableWidth>=500
                    MyTableHeight=67; 
                    MyTableBottomPos=368;
                else
                    MyTableHeight=51; 
                    MyTableBottomPos=384;
                end
                MyTableLeftPos=25;
                app.LinkageControlTable.Position=[MyTableLeftPos,MyTableBottomPos,MyTableWidth,MyTableHeight]; % Adjust position

                MyColumnWidth=num2cell(50*ones(1,app.n_sp));
                app.LinkageControlTable.ColumnWidth=MyColumnWidth;

                % Show the subpattern's IDs
                app.LinkageControlTable.ColumnName=num2cell(app.sp_ids);

                % Fill the data if available
                if isempty(app.Fluorophorepar.Linkage.sp_id) || numel(app.Fluorophorepar.Linkage.sp_id)~=app.n_sp
                    MyData=nan(1,app.n_sp);
                    app.LinkageControlTable.Data=MyData;
                    app.Fluorophorepar.Linkage.sp_id=MyData;
                else
                    app.LinkageControlTable.Data=app.Fluorophorepar.Linkage.sp_id;
                end
            end

            %Initialize linkage
            if app.smis_gui_parameters.fret_on==1 % Disable linkage setup if FRET is on
                app.LinkageerrorSwitch.Enable='off';
                app.LinkageerrorSwitchLabel.Enable='off';
                app.Fluorophorepar.Linkage.set=0;
            end

            if app.smis_gui_parameters.add_diffusion==1 % Disable fixed linkage if sptPALM is on
                app.FixedovertimeCheckBox.Enable='off';
                app.FixedovertimeCheckBox.Value=0;
                app.Fluorophorepar.Linkage.fixed=0;
            end


            if app.Fluorophorepar.Linkage.set==1
                app.LinkageerrorSwitch.Value='On';
                app.LinkagelengthnmEditField.Visible="on";
                app.LinkagelengthnmEditField.Value=app.Fluorophorepar.Linkage.length;
                app.LinkagelengthnmEditFieldLabel.Visible="on";
                app.LinkagestdnmEditField.Visible="on";
                app.LinkagestdnmEditField.Value=app.Fluorophorepar.Linkage.std;
                app.LinkagestdnmEditFieldLabel.Visible="on";
                app.FixedovertimeCheckBox.Visible="on";
                app.FixedovertimeCheckBox.Value=app.Fluorophorepar.Linkage.fixed;
                if app.smis_gui_parameters.distribute_in_clusters~=1 % Sub pattern controlk not available for qPALM.
                    app.SubpatterncontrolSwitch.Visible="on";
                    app.SubpatterncontrolSwitchLabel.Visible="on";
                    if app.Fluorophorepar.Linkage.pattern_control==1
                        app.SubpatterncontrolSwitch.Value='On';
                        app.LinkagetargetsubpatternidsLabel.Visible="on";
                        app.LinkageControlTable.Visible="on";
                    else
                        app.SubpatterncontrolSwitch.Value='Off';
                        app.LinkagetargetsubpatternidsLabel.Visible="off";
                        app.LinkageControlTable.Visible="off";
                    end
                else
                    app.SubpatterncontrolSwitch.Visible="off";
                    app.SubpatterncontrolSwitchLabel.Visible="off";
                    app.LinkagetargetsubpatternidsLabel.Visible="off";
                    app.LinkageControlTable.Visible="off";
                    app.Fluorophorepar.Linkage.pattern_control=0;
                end
            else
                app.LinkageerrorSwitch.Value='Off';
                app.LinkagelengthnmEditField.Visible="off";
                app.LinkagelengthnmEditFieldLabel.Visible="off";
                app.LinkagestdnmEditField.Visible="off";
                app.LinkagestdnmEditFieldLabel.Visible="off";
                app.FixedovertimeCheckBox.Visible="off";
                app.SubpatterncontrolSwitch.Visible="off";
                app.SubpatterncontrolSwitchLabel.Visible="off";
                app.LinkageControlTable.Visible="off";
                app.LinkagetargetsubpatternidsLabel.Visible="off";
            end % Linkage error set to off

          
            % Initialize the check sub-patterns drop down menu if qPALM
            % option is off
            if app.smis_gui_parameters.distribute_in_clusters~=1
                if ~isempty(app.sp_ids)
                    sp_ids_str=cell(1,app.n_sp);
                    for i=1:app.n_sp
                        sp_ids_str{i}=num2str(app.sp_ids(i));
                    end
                    app.ChecksubpatternsDropDown.Items=horzcat('Choose',sp_ids_str);
                else
                    app.ChecksubpatternsDropDown.Items={'Choose'};
                end
            end
            
            % Update Edit Field
            app.MaturationlevelEditField.Value=app.Fluorophorepar.maturation_level;
            app.MinimumdistancebetweenfluorophoresnmEditField.Value=app.Fluorophorepar.min_dist;
            
            % Check pH
            if isempty(app.Fluorophorepar.Environment.pH)
                app.Fluorophorepar.Environment.pH=app.pHofthemediumEditField.Value; % Set current pH value
            end
            
            % Update UI with input values
            UpdateFluorophoreSetup(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Check that fractions of fluorophores in sub-patterns are okay
            % if not in qPALM mode
            if app.smis_gui_parameters.distribute_in_clusters~=1 && ~isempty(app.sp_ids) && ~isempty(app.Fluorophorepar.n_sp_fr) && sum(app.Fluorophorepar.n_sp_fr)~=1
                warndlg('The sum of fractions of fluorophores in the sub patterns must equal to 1 !','Warning');
            elseif app.smis_gui_parameters.distribute_in_clusters~=1 && ~isempty(app.sp_ids) && any(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr~=floor(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr))
                warndlg('This Number of fluorophores will result in non-integer number of fluorophores in a sub-pattern ! Check Table of fractions or Number of fluorophores !','Warning');
            elseif  app.smis_gui_parameters.distribute_in_clusters~=1 && any(app.Fluorophorepar.Linkage.pattern_control) && any(isnan(app.Fluorophorepar.Linkage.sp_id))
                warndlg('Linkage control pattern ids not properly set ! Check Table of linkage sub-pattern ids !','Warning');
            else
                % Call main app's public function
                updateFluorophorepar(app.CallingApp, app.Fluorophorepar, app.selected_fluorophore, app.DefaultDir)
                % Delete the dialog box
                delete(app)
            end
        end

        % Button pushed function: LoadFluorophoreButton
        function LoadFluorophoreButtonPushed(app, event)
            if exist(app.DefaultDir,'dir')
                cd(app.DefaultDir);
            end
            [file,path] = uigetfile('*.mat','Select a Fluorophore definition file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                app.DefaultDir=path; % % Update the SMIS_Simulations_dir
                load(fullfile(path,file),'fluorophore');
                if ~exist('fluorophore',"var")
                    msgbox('Invalid Matlab Fluorophore definition file !','icon','warn')
                else
                    %Load the proper fields in Fluorophorepar
                    f=fieldnames(fluorophore);
                    for i=1:numel(f)
                        app.Fluorophorepar.(f{i})=fluorophore.(f{i});
                    end
                    
                    %Fill all values
                    UpdateFluorophoreSetup(app);
                end
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: pHSensitivitySwitch
        function pHSensitivitySwitchValueChanged(app, event)
            value = app.pHSensitivitySwitch.Value;
            
            if value==1
                if strcmp(app.PairsofstatesinrapidequilibriumEditFieldLabel.Visible,'on')==1
                    app.Fluorophorepar.Environment.pH_sensitivity=value;
                    app.Fluorophorepar.Environment.pH=app.pHofthemediumEditField.Value; % Set current pH value
                    UpdateFluorophoreSetup(app);
                else
                    warndlg('pH sensitivity can only be used when pairs of photophysical states in rapid exchange are defined','Warning');
                    app.pHSensitivitySwitch.Value=0;
                end
            else
                app.Fluorophorepar.Environment.pH_sensitivity=value;
                UpdateFluorophoreSetup(app);
            end
        end

        % Value changed function: pHofthemediumEditField
        function pHofthemediumEditFieldValueChanged(app, event)
            value = app.pHofthemediumEditField.Value;
            app.Fluorophorepar.Environment.pH=value;
        end

        % Display data changed function: pKaTable
        function pKaTableDisplayDataChanged(app, event)
            newDisplayData = app.pKaTable.DisplayData;
            app.Fluorophorepar.Environment.pKa=newDisplayData(1,:);
            app.Fluorophorepar.Environment.Hill=newDisplayData(2,:);
        end

        % Button pushed function: 
        % SetupphototransformationsquantumyieldsButton
        function SetupphototransformationsquantumyieldsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupphototransformationsquantumyieldsButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.QY_App = QYSetup(app, app.Fluorophorepar, app.QYSetup_Position);
        end

        % Button pushed function: SetupthermalexchangeratematrixsButton
        function SetupthermalexchangeratematrixsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupthermalexchangeratematrixsButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.kT_App = kTSetup(app, app.Fluorophorepar, app.kTSetup_Position);
        end

        % Value changed function: MaturationlevelEditField
        function MaturationlevelEditFieldValueChanged(app, event)
            value=app.MaturationlevelEditField.Value;
            if value==0
                warndlg('It is nonsense to define a maturation level of zero ! Suppress fluorophore instead !','Warning')
                app.MaturationlevelEditField.Value=event.PreviousValue;
            else
                app.Fluorophorepar.maturation_level = app.MaturationlevelEditField.Value;
            end
        end

        % Value changed function: NumberofmoleculesEditField
        function NumberofmoleculesEditFieldValueChanged(app, event)
            app.Fluorophorepar.N = app.NumberofmoleculesEditField.Value;
            if any(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr~=floor(app.Fluorophorepar.N*app.Fluorophorepar.n_sp_fr))
                warndlg('This Number of fluorophores will result in non-integer number of fluorophores in a sub-pattern ! Check Table of fraction below !','Warning');
            end
        end

        % Value changed function: 
        % MinimumdistancebetweenfluorophoresnmEditField
        function MinimumdistancebetweenfluorophoresnmEditFieldValueChanged(app, event)
            app.Fluorophorepar.min_dist = app.MinimumdistancebetweenfluorophoresnmEditField.Value;
        end

        % Value changed function: InitialstateEditField
        function InitialstateEditFieldValueChanged(app, event)
            value = app.InitialstateEditField.Value;
            w_name=find(strcmp(value,app.state_names),1);
            if isempty(w_name)
                warndlg('This state name does not exist !','Warning');
                app.InitialstateEditField.Value=event.PreviousValue;
            else
                app.Fluorophorepar.Photophysics.initial_state=w_name;
            end
        end

        % Cell edit callback: FractionInSubpatternsTable
        function FractionInSubpatternsTableCellEdit(app, event)
            newData = event.NewData;
            
            valid_fraction=1; % Initialize
            
            %Issue warnings when fraction is not possible
            if isempty(app.sp_ids) % A pattern as not been loaded !
                warndlg('A pattern for this fluorophore must be loaded first ! ','Warning');
                valid_fraction=0;
            end
            
            if newData<0
                warndlg('Fraction of fluorophore must be >= 0 !','Warning');
                valid_fraction=0;
            end
            if newData>1
                warndlg('Fraction of fluorophore must be <= 1 !','Warning');
                valid_fraction=0;
            end
            
            if floor(newData*app.Fluorophorepar.N)~=newData*app.Fluorophorepar.N
                warndlg('Number of fluorophores in sub-pattern must be an integer value !','Warning');
                valid_fraction=0;
            end
            
            if valid_fraction==1
                app.Fluorophorepar.n_sp_fr = app.FractionInSubpatternsTable.DisplayData;
            else
                app.FractionInSubpatternsTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Reset the value to zero
            end
        end

        % Cell edit callback: QYTable
        function QYTableCellEdit(app, event)
            newData = event.NewData;
            
            valid_QY=1; % Initialize
            %Issue warnings when QY value is not possible
            
            if newData<0
                warndlg('Fluorescence Quantum Yield must be >= 0 !','Warning');
                valid_QY=0;
            end
            if newData>1
                warndlg('Fluorescence Quantum Yield must be <= 1 !','Warning');
                valid_QY=0;
            end
            
            if valid_QY==1
                app.Fluorophorepar.Photophysics.quantum_yield = app.QYTable.DisplayData;
            else
                app.QYTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Reset the value to zero
            end
        end

        % Value changed function: FluorogenicitySwitch
        function FluorogenicitySwitchValueChanged(app, event)
            value = app.FluorogenicitySwitch.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Environment.fluorogenic=1;
            else
                app.Fluorophorepar.Environment.fluorogenic=0;
            end
            
            UpdateFluorophoreSetup(app);
        end

        % Cell edit callback: FluorogenicityTable
        function FluorogenicityTableCellEdit(app, event)
            newData = event.NewData;
            
            valid_fluorogenicity=1; % Initialize
            %Issue warnings when fraction is not possible
            
            if isempty(app.sp_ids) % A pattern as not been loaded !
                warndlg('A pattern for this fluorophore must be loaded first ! ','Warning');
                valid_fluorogenicity=0;
            end
            
            if newData<0
                warndlg('Fluorogenicity must be >= 0 !','Warning');
                valid_fluorogenicity=0;
            end
            if newData>1
                warndlg('Fluorogenicity must be <= 1 !','Warning');
                valid_fluorogenicity=0;
            end
            
            if valid_fluorogenicity==1
                app.Fluorophorepar.Environment.fluorogenicity = app.FluorogenicityTable.DisplayData;
            else
                app.FluorogenicityTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Reset the value to zero
            end
            
        end

        % Value changed function: FixeddipoleorientationSwitch_2
        function FixeddipoleorientationSwitch_2ValueChanged(app, event)
            value = app.FixeddipoleorientationSwitch_2.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Orientation.anisotropy=1;
            else
                app.Fluorophorepar.Orientation.anisotropy=0;
            end
            
            UpdateFluorophoreSetup(app);
        end

        % Value changed function: Jumprates1EditField
        function Jumprates1EditFieldValueChanged(app, event)
            app.Fluorophorepar.Orientation.jump_rate = app.Jumprates1EditField.Value;
        end

        % Value changed function: UniquedipoleorientationSwitch
        function UniquedipoleorientationSwitchValueChanged(app, event)
            value = app.UniquedipoleorientationSwitch.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Orientation.dipole_orientation=1;
            else
                app.Fluorophorepar.Orientation.dipole_orientation=0;
            end
            
            UpdateFluorophoreSetup(app);
        end

        % Value changed function: AllowStochasticReorientationSwitch
        function AllowStochasticReorientationSwitchValueChanged(app, event)
            value = app.AllowStochasticReorientationSwitch.Value;
            if strcmp(value,'On')
                app.Fluorophorepar.Orientation.jump_allowed=1;
            else
                app.Fluorophorepar.Orientation.jump_allowed=0;
            end
            
            UpdateFluorophoreSetup(app);
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.Fluorophorepar.Orientation.theta_fixed = app.ThetaEditField.Value;
        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            app.Fluorophorepar.Orientation.phi_fixed = app.PhiEditField.Value;
        end

        % Value changed function: CopySetupFromOtherFluorophoreDropDown
        function CopySetupFromOtherFluorophoreDropDownValueChanged(app, event)
            value = app.CopySetupFromOtherFluorophoreDropDown.Value;
            OtherFluorophoreNames={app.OtherFluorophorepar.name}; % Makes this a cell array
            sel=find(strcmp(OtherFluorophoreNames,value)==1,1);
            app.Fluorophorepar=app.OtherFluorophorepar(sel);
            
            % Update UI with input values
            UpdateFluorophoreSetup(app);
            
            % Reset menu to first position
            app.CopySetupFromOtherFluorophoreDropDown.Value = 'Choose';
        end

        % Value changed function: FluorophorenameEditField
        function FluorophorenameEditFieldValueChanged(app, event)
            value = app.FluorophorenameEditField.Value;
            if ~isnan(str2double(value(1)))
                disp('Fluorophore name must start with a letter ! ; Adding "Fluorophore" at the beginning of the name')
                value=strcat('Fluorophore',value);
                app.FluorophorenameEditField.Value=value;
            end
            app.Fluorophorepar.name = value;
        end

        % Button pushed function: SaveFluorophoreButton
        function SaveFluorophoreButtonPushed(app, event)
            if exist(app.smis_gui_parameters.DefaultDirs.Fluorophore_dir,'dir')
                path=app.smis_gui_parameters.DefaultDirs.Fluorophore_dir;
            else
                path=pwd;
            end
            SMIS_fluo_file=[deblank(app.Fluorophorepar.name),'.mat'];
            
            cd(path);
            [SMIS_fluo_file,SMIS_fluo_path] = uiputfile('*.mat','SMIS Fluorophore File Selection',SMIS_fluo_file);
            if ~isnumeric(SMIS_fluo_path) || ~isnumeric(SMIS_fluo_file)
                app.smis_gui_parameters.DefaultDirs.Fluorophore_dir=SMIS_fluo_path;
                
                fluorophore.name=app.Fluorophorepar.name;
                fluorophore.maturation_level=app.Fluorophorepar.maturation_level;
                fluorophore.xls_spectrum_file=app.Fluorophorepar.xls_spectrum_file;
                fluorophore.xls_spectrum_dir=app.Fluorophorepar.xls_spectrum_dir;
                fluorophore.Environment=app.Fluorophorepar.Environment;
                fluorophore.Photophysics=app.Fluorophorepar.Photophysics;
                
                save(fullfile(SMIS_fluo_path,SMIS_fluo_file),'fluorophore');
            end
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: OligomerstochiometryEditField
        function OligomerstochiometryEditFieldValueChanged(app, event)
            value = app.OligomerstochiometryEditField.Value;
            app.Fluorophorepar.N=app.smis_gui_parameters.n_clusters(app.selected_fluorophore)*value;
            app.NumberofmoleculesEditField.Value=app.Fluorophorepar.N;
        end

        % Button pushed function: DefinenewFluorophoreButton
        function DefinenewFluorophoreButtonPushed(app, event)
            DefineNewFluorophore(app);
            app.DefinenewFluorophoreButton.Enable='off';
        end

        % Value changed function: ChecksubpatternsDropDown
        function ChecksubpatternsDropDownValueChanged(app, event)
            value = app.ChecksubpatternsDropDown.Value;
            chosen_sp_id=str2double(value);
            
            MyPath=app.smis_gui_parameters.in_images_dir{app.selected_fluorophore};
            MyFile=app.smis_gui_parameters.in_images_names{app.selected_fluorophore};
            
            if isfile(fullfile(cellstr(MyPath), cellstr(MyFile)))
                
                % Read the pattern
                [Pattern,~,~,~,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                Pattern=squeeze(Pattern);
                
                CheckSubPattern(app,Pattern,app.smis_gui_parameters.pattern_ids(app.selected_fluorophore).sp_ids,chosen_sp_id, app.smis_gui_parameters.simul_3D);
            else
                warndlg(['Image pattern for fluorophore: ',app.Fluorophorepar.name, ' has not been loaded !'],'Warning');
            end
            
            % Reset menu
            app.ChecksubpatternsDropDown.Value='Choose';
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: LinkageerrorSwitch
        function LinkageerrorSwitchValueChanged(app, event)
            value = app.LinkageerrorSwitch.Value;
            if strcmp(value,'On')==1 % Linkage error set to on
                app.Fluorophorepar.Linkage.set=1;
                if isempty(app.Fluorophorepar.Linkage.length)
                    app.Fluorophorepar.Linkage.length=app.LinkagelengthnmEditField.Value;
                else
                    app.LinkagelengthnmEditField.Value=app.Fluorophorepar.Linkage.length;
                end
                if isempty(app.Fluorophorepar.Linkage.std)
                    app.Fluorophorepar.Linkage.std=app.LinkagestdnmEditField.Value;
                else
                    app.LinkagestdnmEditField.Value=app.Fluorophorepar.Linkage.std;
                end
                if isempty(app.Fluorophorepar.Linkage.fixed)
                    app.Fluorophorepar.Linkage.fixed=app.FixedovertimeCheckBox.Value;
                else
                    app.FixedovertimeCheckBox.Value=app.Fluorophorepar.Linkage.fixed;
                end
                if isempty(app.Fluorophorepar.Linkage.pattern_control)
                    app.Fluorophorepar.Linkage.pattern_control=strcmp(app.SubpatterncontrolSwitch.Value,"On")==1;
                else
                    if app.Fluorophorepar.Linkage.pattern_control==1
                        app.SubpatterncontrolSwitch.Value="On";
                    else
                        app.SubpatterncontrolSwitch.Value="Off";
                    end
                end
                app.LinkagelengthnmEditField.Visible="on";
                app.LinkagelengthnmEditFieldLabel.Visible="on";
                app.LinkagestdnmEditField.Visible="on";
                app.LinkagestdnmEditFieldLabel.Visible="on";
                app.FixedovertimeCheckBox.Visible="on";
                if app.smis_gui_parameters.distribute_in_clusters~=1 % Sub pattern controlk not available for qPALM.
                    app.SubpatterncontrolSwitch.Visible="on";
                    app.SubpatterncontrolSwitchLabel.Visible="on";
                    if strcmp(app.SubpatterncontrolSwitch.Value,"On")==1
                        app.LinkagetargetsubpatternidsLabel.Visible="on";
                        app.LinkageControlTable.Visible="on";
                    else
                        app.LinkagetargetsubpatternidsLabel.Visible="off";
                        app.LinkageControlTable.Visible="off";
                    end
                end
            else
                app.Fluorophorepar.Linkage.set=0;
                app.LinkagelengthnmEditField.Visible="off";
                app.LinkagelengthnmEditFieldLabel.Visible="off";
                app.LinkagestdnmEditField.Visible="off";
                app.LinkagestdnmEditFieldLabel.Visible="off";
                app.FixedovertimeCheckBox.Visible="off";
                app.SubpatterncontrolSwitch.Visible="off";
                app.SubpatterncontrolSwitchLabel.Visible="off";
                app.LinkagetargetsubpatternidsLabel.Visible="off";
                app.LinkageControlTable.Visible="off";
            end % Linkage error set to off
        end

        % Value changed function: SubpatterncontrolSwitch
        function SubpatterncontrolSwitchValueChanged(app, event)
            value = app.SubpatterncontrolSwitch.Value;
            if strcmp(value,'On')==1 % Control linkage pattern on
                app.LinkagetargetsubpatternidsLabel.Visible="on";
                app.Fluorophorepar.Linkage.pattern_control=1;
                app.LinkageControlTable.Visible="on";
            else % Control linkage pattern on
                app.LinkagetargetsubpatternidsLabel.Visible="off";
                app.Fluorophorepar.Linkage.pattern_control=0;
                app.LinkageControlTable.Visible="off";
            end
        end

        % Cell edit callback: LinkageControlTable
        function LinkageControlTableCellEdit(app, event)
            newData = event.NewData;
            valid_sp=1;

            %Issue warnings 
            if isempty(app.sp_ids) % A pattern as not been loaded !
                warndlg('A pattern for this fluorophore must be loaded first ! ','Warning');
                valid_sp=0;
            end
            
            if ~any(ismember(newData,app.sp_ids))
                warndlg('Invalid sub pattern id !','Warning');
                valid_sp=0;
            end          
            
            if valid_sp==1
                app.Fluorophorepar.Linkage.sp_id = app.LinkageControlTable.DisplayData;
            else
                app.LinkageControlTable.Data(event.Indices(1),event.Indices(2))=event.PreviousData; % Reset the value to zero
            end

        end

        % Value changed function: LinkagelengthnmEditField
        function LinkagelengthnmEditFieldValueChanged(app, event)
            value = app.LinkagelengthnmEditField.Value;
            app.Fluorophorepar.Linkage.length = value;

            digital_res=app.smis_gui_parameters.raster/app.smis_gui_parameters.binning;
            if value<digital_res
                warndlg(['Linkage error < virtual sample digital resolution (', num2str(digital_res),' [nm]) ! Such linkage error will not change simulation outcome ! Increase binning or linkage error !'],'Warning');
            end
        
        end

        % Value changed function: LinkagestdnmEditField
        function LinkagestdnmEditFieldValueChanged(app, event)
            value = app.LinkagestdnmEditField.Value;
            app.Fluorophorepar.Linkage.std= value;
        end

        % Value changed function: FixedovertimeCheckBox
        function FixedovertimeCheckBoxValueChanged(app, event)
            value = app.FixedovertimeCheckBox.Value;
            app.Fluorophorepar.Linkage.fixed= value;
            if value==true && app.smis_gui_parameters.add_diffusion==1
                warndlg('Fixed linkage error not allowed for sptPALM simulations !','Warning');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FluorophorePhotophysicsSetupLabel = uilabel(app.UIFigure);
            app.FluorophorePhotophysicsSetupLabel.Text = 'Fluorophore Photophysics Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main menu'};
            app.OKButton.Text = 'OK';
            app.FluorophorenameEditFieldLabel = uilabel(app.UIFigure);
            app.FluorophorenameEditFieldLabel.Text = 'Fluorophore name';
            app.FluorophorenameEditField = uieditfield(app.UIFigure, 'text');
            app.FluorophorenameEditField.ValueChangedFcn = createCallbackFcn(app, @FluorophorenameEditFieldValueChanged, true);
            app.FluorophorenameEditField.Tooltip = {'You can change the name of the fluorophore here'};
            app.CopySetupFromOtherFluorophoreDropDownLabel = uilabel(app.UIFigure);
            app.CopySetupFromOtherFluorophoreDropDownLabel.Text = 'Copy Setup From Other Fluorophore ';
            app.CopySetupFromOtherFluorophoreDropDown = uidropdown(app.UIFigure);
            app.CopySetupFromOtherFluorophoreDropDown.ValueChangedFcn = createCallbackFcn(app, @CopySetupFromOtherFluorophoreDropDownValueChanged, true);
            app.CopySetupFromOtherFluorophoreDropDown.Tooltip = {'Use this menu to copy all parameters from another defined fluorophore to the current fluorophore. This is in particular useful when you want to delete some fluorophores and only keep some that are at the end of the list: in that case copy the setting of the fluorophores you want to keep to those of the first fluorophores in the list.'};
            app.NumberofmoleculesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofmoleculesEditFieldLabel.Text = 'Number of molecules';
            app.NumberofmoleculesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofmoleculesEditField.Limits = [0 Inf];
            app.NumberofmoleculesEditField.ValueDisplayFormat = '%8d';
            app.NumberofmoleculesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmoleculesEditFieldValueChanged, true);
            app.NumberofmoleculesEditField.Tooltip = {'Number of fluorophores that will label the chosen virtual sample (computation time will be directly proportional to this number)'};
            app.NumberofmoleculesEditField.Value = 1;
            app.MaturationlevelEditFieldLabel = uilabel(app.UIFigure);
            app.MaturationlevelEditFieldLabel.Text = 'Maturation level';
            app.MaturationlevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaturationlevelEditField.Limits = [0 1];
            app.MaturationlevelEditField.ValueChangedFcn = createCallbackFcn(app, @MaturationlevelEditFieldValueChanged, true);
            app.MaturationlevelEditField.Tooltip = {'Maturation level of the fluorophore: in practice this is only useful for qPALM simulations, where each complex will be labeled by a varying number of fluorophores if maturation level is lower than 1. '};
            app.MaturationlevelEditField.Value = 1;
            app.FractionoffluorophoresineachsubpatternLabel = uilabel(app.UIFigure);
            app.FractionoffluorophoresineachsubpatternLabel.Tooltip = {'Fluorophores will label each particular part of the virtual sample according to the fractions specified in this table. The ids given as column names correspond to the different pixel values of the segmented virtual sample.  Lower ids correspond to black regions and higher ids to white regions in the virtual sample. Typically the id of 0 correspond to the background region (outside of the virtual sample).'};
            app.FractionoffluorophoresineachsubpatternLabel.Text = 'Fraction of fluorophores in each sub pattern';
            app.pHSensitivitySwitchLabel = uilabel(app.UIFigure);
            app.pHSensitivitySwitchLabel.Text = 'pH Sensitivity';
            app.pHSensitivitySwitch = uiswitch(app.UIFigure, 'slider');
            app.pHSensitivitySwitch.ValueChangedFcn = createCallbackFcn(app, @pHSensitivitySwitchValueChanged, true);
            app.pHSensitivitySwitch.Tooltip = {'Use this switch to enable pH sensitivity of the fluorophore. This is only possible for fluorophores that are defined with rapidly exchanging states between protonated (nonfluorescent) and ionic (fluorescent) states. To do so go in "Define new fluorophore" and modify the main table after loading the current fluorophore.'};
            app.pHSensitivitySwitch.Value = 'On';
            app.pHofthemediumEditFieldLabel = uilabel(app.UIFigure);
            app.pHofthemediumEditFieldLabel.Text = 'pH of the medium';
            app.pHofthemediumEditField = uieditfield(app.UIFigure, 'numeric');
            app.pHofthemediumEditField.Limits = [1 15];
            app.pHofthemediumEditField.ValueChangedFcn = createCallbackFcn(app, @pHofthemediumEditFieldValueChanged, true);
            app.pHofthemediumEditField.Tooltip = {'Use this to set the pH of the medium where the fluorophore resides'};
            app.pHofthemediumEditField.Value = 7.5;
            app.pKaTable = uitable(app.UIFigure);
            app.pKaTable.ColumnName = {'Fluo State Name'};
            app.pKaTable.RowName = {'pKa '; 'Hill'};
            app.pKaTable.ColumnEditable = true;
            app.pKaTable.DisplayDataChangedFcn = createCallbackFcn(app, @pKaTableDisplayDataChanged, true);
            app.pKaTable.Tooltip = {'You can change the pKa of the fluorophore in each fluorescent state in this table.'};
            app.LoadFluorophoreButton = uibutton(app.UIFigure, 'push');
            app.LoadFluorophoreButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFluorophoreButtonPushed, true);
            app.LoadFluorophoreButton.Tooltip = {'Press this button to load a previously defined fluorophore'};
            app.LoadFluorophoreButton.Text = 'Load Fluorophore';
            app.NumberofphotophysicalstatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofphotophysicalstatesEditFieldLabel.Text = 'Number of photophysical states';
            app.NumberofphotophysicalstatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofphotophysicalstatesEditField.Editable = 'off';
            app.NumberofphotophysicalstatesEditField.Tooltip = {'This and all the names of the photophysical states displayed below are for information only and cannot be modified here. To modify them, go in "Define new fluorophore", load your fluorophore and edit the main table.'};
            app.NumberofphotophysicalstatesEditField.Value = 1;
            app.FixeddipoleorientationSwitch_2Label = uilabel(app.UIFigure);
            app.FixeddipoleorientationSwitch_2Label.Text = 'Fixed dipole orientation';
            app.FixeddipoleorientationSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.FixeddipoleorientationSwitch_2.ValueChangedFcn = createCallbackFcn(app, @FixeddipoleorientationSwitch_2ValueChanged, true);
            app.FixeddipoleorientationSwitch_2.Tooltip = {'Set to On if fluorophores have a fixed dipole orientation. Set to Off if fluorophores tumble more rapidly than the acquisition rate.'};
            app.FixeddipoleorientationSwitch_2.Value = 'On';
            app.UniquedipoleorientationSwitchLabel = uilabel(app.UIFigure);
            app.UniquedipoleorientationSwitchLabel.Text = 'Unique dipole orientation';
            app.UniquedipoleorientationSwitch = uiswitch(app.UIFigure, 'slider');
            app.UniquedipoleorientationSwitch.ValueChangedFcn = createCallbackFcn(app, @UniquedipoleorientationSwitchValueChanged, true);
            app.UniquedipoleorientationSwitch.Tooltip = {'If set to Off, all fluorophores will have a random fixed orientation. If set to On, all fluorophores will have the same fixed orientation, defined by the angles Theta and Phi '};
            app.UniquedipoleorientationSwitch.Value = 'On';
            app.Jumprates1EditFieldLabel = uilabel(app.UIFigure);
            app.Jumprates1EditFieldLabel.Text = 'Jump rate [s-1]';
            app.Jumprates1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Jumprates1EditField.Limits = [0 1000];
            app.Jumprates1EditField.ValueChangedFcn = createCallbackFcn(app, @Jumprates1EditFieldValueChanged, true);
            app.Jumprates1EditField.Value = 10;
            app.AllowStochasticReorientationSwitchLabel = uilabel(app.UIFigure);
            app.AllowStochasticReorientationSwitchLabel.Text = 'Allow Stochastic Reorientation ';
            app.AllowStochasticReorientationSwitch = uiswitch(app.UIFigure, 'slider');
            app.AllowStochasticReorientationSwitch.ValueChangedFcn = createCallbackFcn(app, @AllowStochasticReorientationSwitchValueChanged, true);
            app.AllowStochasticReorientationSwitch.Tooltip = {'Said this parameter to On if fluorophores are allowed to change their orientation stochastically at a rate specified by the jump rate'};
            app.AllowStochasticReorientationSwitch.Value = 'On';
            app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoactivedarkstatesbeforephotoconversionEditFieldLabel.Text = 'Photoactive dark states before photoconversion';
            app.PhotoactivedarkstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.PhotoactivedarkstatesbeforephotoconversionEditField.Editable = 'off';
            app.MinimumdistancebetweenfluorophoresnmEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumdistancebetweenfluorophoresnmEditFieldLabel.Text = 'Minimum distance between fluorophores [nm]';
            app.MinimumdistancebetweenfluorophoresnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumdistancebetweenfluorophoresnmEditField.Limits = [0.1 Inf];
            app.MinimumdistancebetweenfluorophoresnmEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumdistancebetweenfluorophoresnmEditFieldValueChanged, true);
            app.MinimumdistancebetweenfluorophoresnmEditField.Tooltip = {'Change this number to affect the sparsity of labeling. If a high number of fluorophores are to label a small feature, the minimum distance between fluorophores must be reduced accordingly. '};
            app.MinimumdistancebetweenfluorophoresnmEditField.Value = 4;
            app.SetupphototransformationsquantumyieldsButton = uibutton(app.UIFigure, 'push');
            app.SetupphototransformationsquantumyieldsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupphototransformationsquantumyieldsButtonPushed, true);
            app.SetupphototransformationsquantumyieldsButton.Tooltip = {'Click on this button to change all the photo transformation quantum yields for your fluorophore; spectral data can also be inspected there.'};
            app.SetupphototransformationsquantumyieldsButton.Text = 'Setup phototransformation''s quantum yields';
            app.SetupthermalexchangeratematrixsButton = uibutton(app.UIFigure, 'push');
            app.SetupthermalexchangeratematrixsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupthermalexchangeratematrixsButtonPushed, true);
            app.SetupthermalexchangeratematrixsButton.Tooltip = {'Click on this button to change thermal exchange rates between photophysical states of your fluorophore.'};
            app.SetupthermalexchangeratematrixsButton.Text = 'Setup thermal exchange rate matrix';
            app.FluorogenicitySwitchLabel = uilabel(app.UIFigure);
            app.FluorogenicitySwitchLabel.Text = 'Fluorogenicity';
            app.FluorogenicitySwitch = uiswitch(app.UIFigure, 'slider');
            app.FluorogenicitySwitch.ValueChangedFcn = createCallbackFcn(app, @FluorogenicitySwitchValueChanged, true);
            app.FluorogenicitySwitch.Tooltip = {'Use this switch to enable fluorogenicity of the fluorophore, that is, fluorescence quantum yield that are dependent on the part of the virtual sample (sub pattern) in which the fluorophore resides. This is typically useful for fluorophores that are only fluorescent when they e.g. are bound to a membrane.'};
            app.FluorogenicityTable = uitable(app.UIFigure);
            app.FluorogenicityTable.ColumnName = {'sp_id1'; 'sp_id2'};
            app.FluorogenicityTable.RowName = {'Level '};
            app.FluorogenicityTable.ColumnEditable = true;
            app.FluorogenicityTable.CellEditCallback = createCallbackFcn(app, @FluorogenicityTableCellEdit, true);
            app.FluorogenicityTable.Tooltip = {'Use this table to assign the different fluorescence quantum yields in the different sub-patterns of the virtual sample.'};
            app.FluorogenicityTable.Visible = 'off';
            app.FractionInSubpatternsTable = uitable(app.UIFigure);
            app.FractionInSubpatternsTable.ColumnName = {'sp_id1'; 'sp_id2'};
            app.FractionInSubpatternsTable.RowName = {'Fraction  '};
            app.FractionInSubpatternsTable.ColumnEditable = true;
            app.FractionInSubpatternsTable.CellEditCallback = createCallbackFcn(app, @FractionInSubpatternsTableCellEdit, true);
            app.FractionInSubpatternsTable.Tooltip = {'Input fraction of fluorophores that are to be placed on parts of the pattern that have pixel values corresponding to the values given in the column names'};
            app.FluorescentstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.FluorescentstatesbeforephotoconversionEditFieldLabel.Text = 'Fluorescent states before photoconversion';
            app.FluorescentstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.FluorescentstatesbeforephotoconversionEditField.Editable = 'off';
            app.NameofconvertedstateEditFieldLabel = uilabel(app.UIFigure);
            app.NameofconvertedstateEditFieldLabel.Text = 'Name of converted state';
            app.NameofconvertedstateEditField = uieditfield(app.UIFigure, 'text');
            app.NameofconvertedstateEditField.Editable = 'off';
            app.FluorophoreisirreversiblyphotoconvertibleLabel = uilabel(app.UIFigure);
            app.FluorophoreisirreversiblyphotoconvertibleLabel.Text = 'Fluorophore is irreversibly photoconvertible';
            app.FluorescentstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.FluorescentstatesafterphotoconversionEditFieldLabel.Text = 'Fluorescent states after photoconversion';
            app.FluorescentstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.FluorescentstatesafterphotoconversionEditField.Editable = 'off';
            app.BleachedstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.BleachedstatesbeforephotoconversionEditFieldLabel.Text = 'Bleached states before photoconversion';
            app.BleachedstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.BleachedstatesbeforephotoconversionEditField.Editable = 'off';
            app.BleachedstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.BleachedstatesafterphotoconversionEditFieldLabel.Text = 'Bleached states after photoconversion';
            app.BleachedstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.BleachedstatesafterphotoconversionEditField.Editable = 'off';
            app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.PhotoactivedarkstatesafterphotoconversionEditFieldLabel.Text = 'Photoactive dark states after photoconversion';
            app.PhotoactivedarkstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.PhotoactivedarkstatesafterphotoconversionEditField.Editable = 'off';
            app.ThermaldarkstatesafterphotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.ThermaldarkstatesafterphotoconversionEditFieldLabel.Text = 'Thermal dark states after photoconversion';
            app.ThermaldarkstatesafterphotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.ThermaldarkstatesafterphotoconversionEditField.Editable = 'off';
            app.ThermaldarkstatesbeforephotoconversionEditFieldLabel = uilabel(app.UIFigure);
            app.ThermaldarkstatesbeforephotoconversionEditFieldLabel.Text = 'Thermal dark states before photoconversion';
            app.ThermaldarkstatesbeforephotoconversionEditField = uieditfield(app.UIFigure, 'text');
            app.ThermaldarkstatesbeforephotoconversionEditField.Editable = 'off';
            app.InitialstateEditFieldLabel = uilabel(app.UIFigure);
            app.InitialstateEditFieldLabel.Text = 'Initial state';
            app.InitialstateEditField = uieditfield(app.UIFigure, 'text');
            app.InitialstateEditField.ValueChangedFcn = createCallbackFcn(app, @InitialstateEditFieldValueChanged, true);
            app.InitialstateEditField.Tooltip = {'You can modify here the initial photophysical state in which the fluorophores will be set at the beginning of the simulation. For example you could start a simulation with a photoconvertible fluorescent protein that is already fully convertible to the red state. If you want a fluorophore to start in a mixture of different states, duplicate the fluorophore and set each of the copies to a different initial starting state.'};
            app.SaveFluorophoreButton = uibutton(app.UIFigure, 'push');
            app.SaveFluorophoreButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFluorophoreButtonPushed, true);
            app.SaveFluorophoreButton.Tooltip = {'Click this button to save all currently defined photophysical parameters of the fluorophore in Matlab format .'};
            app.SaveFluorophoreButton.Text = 'Save Fluorophore';
            app.PairsofstatesinrapidequilibriumEditFieldLabel = uilabel(app.UIFigure);
            app.PairsofstatesinrapidequilibriumEditFieldLabel.Text = 'Pairs of states in rapid equilibrium: ';
            app.PairsofstatesinrapidequilibriumEditField = uieditfield(app.UIFigure, 'text');
            app.PairsofstatesinrapidequilibriumEditField.Editable = 'off';
            app.QYTable = uitable(app.UIFigure);
            app.QYTable.ColumnName = {'Fluo State Name'};
            app.QYTable.RowName = {'QY'};
            app.QYTable.ColumnEditable = true;
            app.QYTable.CellEditCallback = createCallbackFcn(app, @QYTableCellEdit, true);
            app.FluorescenceQuantumYieldsLabel = uilabel(app.UIFigure);
            app.FluorescenceQuantumYieldsLabel.Tooltip = {'You can change the fluorescence quantum yield for each fluorescent state of the fluorophore in this table'};
            app.FluorescenceQuantumYieldsLabel.Text = 'Fluorescence Quantum Yields';
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta [°]';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.Limits = [0 90];
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.ThetaEditField.Tooltip = {'Polar angle: 0° means along the optical axis, 90° means within the imaging plane'};
            app.ThetaEditField.Value = 90;
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi [°]';
            app.PhiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField.Limits = [0 359];
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.PhiEditField.Tooltip = {'Angle within the imaging plane: 0° means along the X axis'};
            app.DefinenewFluorophoreButton = uibutton(app.UIFigure, 'push');
            app.DefinenewFluorophoreButton.ButtonPushedFcn = createCallbackFcn(app, @DefinenewFluorophoreButtonPushed, true);
            app.DefinenewFluorophoreButton.Tooltip = {'Click this button to enter a new menu that will allow you to define a fluorophore from scratch, or to modify a previously defined fluorophore, notably the number and nature of the photophysical states.'};
            app.DefinenewFluorophoreButton.Text = 'Define new Fluorophore';
            app.OligomerstochiometryEditFieldLabel = uilabel(app.UIFigure);
            app.OligomerstochiometryEditFieldLabel.Text = 'Oligomer stochiometry';
            app.OligomerstochiometryEditField = uieditfield(app.UIFigure, 'numeric');
            app.OligomerstochiometryEditField.Limits = [0 Inf];
            app.OligomerstochiometryEditField.ValueDisplayFormat = '%8d';
            app.OligomerstochiometryEditField.ValueChangedFcn = createCallbackFcn(app, @OligomerstochiometryEditFieldValueChanged, true);
            app.OligomerstochiometryEditField.Tooltip = {'Give the qPALM stoichiometry, ie the number of fluorophores per protein complex'};
            app.OligomerstochiometryEditField.Value = 1;
            app.ChecksubpatternsDropDownLabel = uilabel(app.UIFigure);
            app.ChecksubpatternsDropDownLabel.Text = 'Check sub patterns';
            app.ChecksubpatternsDropDown = uidropdown(app.UIFigure);
            app.ChecksubpatternsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChecksubpatternsDropDownValueChanged, true);
            app.ChecksubpatternsDropDown.Tooltip = {'Use this to check exactly the location of the sub patterns'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main menu'};
            app.CancelButton.Text = 'Cancel';
            app.LinkageerrorSwitchLabel = uilabel(app.UIFigure);
            app.LinkageerrorSwitchLabel.Text = 'Linkage error ';
            app.LinkageerrorSwitch = uiswitch(app.UIFigure, 'slider');
            app.LinkageerrorSwitch.ValueChangedFcn = createCallbackFcn(app, @LinkageerrorSwitchValueChanged, true);
            app.LinkageerrorSwitch.Tooltip = {'Turn this on if you want to add a linkage error (distance between fluorophore and actual target). Be careful: a linkage error only makes sense if its value is greater than the digital resolution of the used virtual sample. This is typically not the case, unless you use a highly resolved virtual sample. You can also artificially increase the binning value, or use a big linkage error, but this might not make a lot of sense. Linkage errors are not enabled in the case of FRET simulations.'};
            app.LinkagelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.LinkagelengthnmEditFieldLabel.Text = 'Linkage length [nm]';
            app.LinkagelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkagelengthnmEditField.Limits = [0 Inf];
            app.LinkagelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @LinkagelengthnmEditFieldValueChanged, true);
            app.LinkagelengthnmEditField.Tooltip = {'Length of the linkage error '};
            app.LinkagestdnmEditFieldLabel = uilabel(app.UIFigure);
            app.LinkagestdnmEditFieldLabel.Text = 'Linkage std [nm]';
            app.LinkagestdnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkagestdnmEditField.Limits = [0 Inf];
            app.LinkagestdnmEditField.ValueChangedFcn = createCallbackFcn(app, @LinkagestdnmEditFieldValueChanged, true);
            app.LinkagestdnmEditField.Tooltip = {'Standard deviation of the linkage error'};
            app.FixedovertimeCheckBox = uicheckbox(app.UIFigure);
            app.FixedovertimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedovertimeCheckBoxValueChanged, true);
            app.FixedovertimeCheckBox.Tooltip = {'Tick this if the linkage error remains constant (in length and orientation) over the whole data acquisition: typically the case for a fixed sample, but not for a live sample or in sptPALM experiments. '};
            app.FixedovertimeCheckBox.Text = 'Fixed over time';
            app.SubpatterncontrolSwitchLabel = uilabel(app.UIFigure);
            app.SubpatterncontrolSwitchLabel.Text = 'Sub pattern control  ';
            app.SubpatterncontrolSwitch = uiswitch(app.UIFigure, 'slider');
            app.SubpatterncontrolSwitch.ValueChangedFcn = createCallbackFcn(app, @SubpatterncontrolSwitchValueChanged, true);
            app.SubpatterncontrolSwitch.Tooltip = {'Turn this on if you want that the fluorophore linked to a target with a linkage error be positioned on a specified sub-pattern of the virtual sample. In that case fill the "linkage target sub pattern ids" table. You might want to do this for example if the target is a membrane protein and the fluorophore should stand either inside the cytoplasm or in the external medium. Do not use this option when the target is positioned inside a large sub pattern, because then the fluorophore would never reach the desired sub pattern. Thus, be careful if you use this option !'};
            app.LinkageControlTable = uitable(app.UIFigure);
            app.LinkageControlTable.ColumnName = {'sp_id1'; 'sp_id2'};
            app.LinkageControlTable.RowName = {'Target Id'};
            app.LinkageControlTable.ColumnEditable = true;
            app.LinkageControlTable.CellEditCallback = createCallbackFcn(app, @LinkageControlTableCellEdit, true);
            app.LinkageControlTable.Tooltip = {'Input fraction of fluorophores that are to be placed on parts of the pattern that have pixel values corresponding to the values given in the column names'};
            app.LinkagetargetsubpatternidsLabel = uilabel(app.UIFigure);
            app.LinkagetargetsubpatternidsLabel.Tooltip = {'Table containing the id of the sub-patterns where fluorophores with linkage errors should be positioned when the target itself is positioned on the sub- pattern indicated in the 1st row of the table. '};
            app.LinkagetargetsubpatternidsLabel.Text = 'Linkage target sub pattern ids';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluorophoreSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end