classdef GeneralPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        MinnumberofmoleculesforparallelcomputingEditField  matlab.ui.control.NumericEditField
        MinnumberofmoleculesforparallelcomputingEditFieldLabel  matlab.ui.control.Label
        CancelButton  matlab.ui.control.Button
        ApplyPoissonstatisticsforlasersSwitch  matlab.ui.control.Switch
        ApplyPoissonstatisticsforlasersSwitchLabel  matlab.ui.control.Label
        GeneratestochasticemissionspectraSwitch  matlab.ui.control.Switch
        GeneratestochasticemissionspectraSwitchLabel  matlab.ui.control.Label
        OKButton      matlab.ui.control.Button
        OthergeneralSMISparametersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Generalpar=[]; % Initialize the parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize Generalpar
            app.Generalpar=mainapp.smis_gui_parameters.General;
            
            % Update UI with input values
            if app.Generalpar.stochastic_spectrum_off==1
                app.GeneratestochasticemissionspectraSwitch.Value='Off';
            else
                app.GeneratestochasticemissionspectraSwitch.Value='On';
            end
            
            if app.Generalpar.apply_poisson_stat_for_lasers==1
                app.ApplyPoissonstatisticsforlasersSwitch.Value='On';
            else
                app.ApplyPoissonstatisticsforlasersSwitch.Value='Off';
            end
            if app.Generalpar.use_parallel_computing==-1
                app.MinnumberofmoleculesforparallelcomputingEditField.Visible="off";
                app.MinnumberofmoleculesforparallelcomputingEditFieldLabel.Visible="off";
            else
                app.MinnumberofmoleculesforparallelcomputingEditField.Visible="on";
                app.MinnumberofmoleculesforparallelcomputingEditFieldLabel.Visible="on";
            end
            app.MinnumberofmoleculesforparallelcomputingEditField.Value=app.Generalpar.min_n_mol_pct;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateGeneralPar(app.CallingApp, app.Generalpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: GeneratestochasticemissionspectraSwitch
        function GeneratestochasticemissionspectraSwitchValueChanged(app, event)
            value = app.GeneratestochasticemissionspectraSwitch.Value;
            if strcmp(value,'On')
                app.Generalpar.stochastic_spectrum_off=0; % Be careful, the value is inverted !
            else
                app.Generalpar.stochastic_spectrum_off=1;
            end
        end

        % Value changed function: ApplyPoissonstatisticsforlasersSwitch
        function ApplyPoissonstatisticsforlasersSwitchValueChanged(app, event)
            value = app.ApplyPoissonstatisticsforlasersSwitch.Value;
            if strcmp(value,'On')
                app.Generalpar.apply_poisson_stat_for_lasers=1;
            else
                app.Generalpar.apply_poisson_stat_for_lasers=0;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: 
        % MinnumberofmoleculesforparallelcomputingEditField
        function MinnumberofmoleculesforparallelcomputingEditFieldValueChanged(app, event)
            value = app.MinnumberofmoleculesforparallelcomputingEditField.Value;
            app.Generalpar.min_n_mol_pct=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OthergeneralSMISparametersLabel = uilabel(app.UIFigure);
            app.OthergeneralSMISparametersLabel.Text = 'Other general SMIS parameters';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.GeneratestochasticemissionspectraSwitchLabel = uilabel(app.UIFigure);
            app.GeneratestochasticemissionspectraSwitchLabel.Text = 'Generate stochastic emission spectra';
            app.GeneratestochasticemissionspectraSwitch = uiswitch(app.UIFigure, 'slider');
            app.GeneratestochasticemissionspectraSwitch.ValueChangedFcn = createCallbackFcn(app, @GeneratestochasticemissionspectraSwitchValueChanged, true);
            app.GeneratestochasticemissionspectraSwitch.Tooltip = {'If this option is turned on, stochastic emission spectra will be calculated for each non-bleached molecule at every frame. For a high number of emitted photons, these spectra will resemble the ensemble emission spectrum of the fluorophore input in the fluorophore definition. This option generates a lot of data and should not be used for simulations involving many molecules and/or many frames.'};
            app.ApplyPoissonstatisticsforlasersSwitchLabel = uilabel(app.UIFigure);
            app.ApplyPoissonstatisticsforlasersSwitchLabel.Text = 'Apply Poisson statistics for lasers';
            app.ApplyPoissonstatisticsforlasersSwitch = uiswitch(app.UIFigure, 'slider');
            app.ApplyPoissonstatisticsforlasersSwitch.ValueChangedFcn = createCallbackFcn(app, @ApplyPoissonstatisticsforlasersSwitchValueChanged, true);
            app.ApplyPoissonstatisticsforlasersSwitch.Tooltip = {'If this option is turned on, the number of photons absorbed by the single molecules upon laser illumination will be Poisson-distributed instead of calculated in a deterministic manner. '};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MinnumberofmoleculesforparallelcomputingEditFieldLabel = uilabel(app.UIFigure);
            app.MinnumberofmoleculesforparallelcomputingEditFieldLabel.Text = 'Min number of molecules for parallel computing';
            app.MinnumberofmoleculesforparallelcomputingEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinnumberofmoleculesforparallelcomputingEditField.Limits = [0 Inf];
            app.MinnumberofmoleculesforparallelcomputingEditField.ValueDisplayFormat = '%.0f';
            app.MinnumberofmoleculesforparallelcomputingEditField.ValueChangedFcn = createCallbackFcn(app, @MinnumberofmoleculesforparallelcomputingEditFieldValueChanged, true);
            app.MinnumberofmoleculesforparallelcomputingEditField.Tooltip = {'Enter minimum number to run parallel computing: below this number, even if parallel computing is set on, it will be turned off because it will be very inefficient. During a simulation starting with a high number of molecules, when the number of unbleached molecules will become below the entered minimum number, parallel computing will be automatically switched off.'};
            app.MinnumberofmoleculesforparallelcomputingEditField.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralPar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end