classdef LaserCustomProfileEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        MatlabexpressionEditField       matlab.ui.control.EditField
        MatlabexpressionEditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        LaserCustomProfileEditorLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        n_images=[]; % Initialize the parameters
        sequence=[];
        CustomPar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, n_images, CustomPar)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize n_images
            app.n_images=n_images;
            % Initialize CustomPar
            app.CustomPar=CustomPar;
            % Update UI with input values
            app.MatlabexpressionEditField.Value = CustomPar;
            n=app.n_images;
            app.sequence=100*eval(CustomPar);
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateCustom(app.CallingApp, app.CustomPar,app.sequence)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: MatlabexpressionEditField
        function MatlabexpressionEditFieldValueChanged(app, event)
            value = app.MatlabexpressionEditField.Value;
            
            formula_ok=1;
            try
                n=app.n_images; %#ok<NASGU>
                MySequence=100*eval(value);
            catch
                MySequence=app.sequence;
                plot(app.UIAxes, 1:app.n_images,MySequence);
                app.UIAxes.XLim=[1,app.n_images];
                app.UIAxes.YLim=[min([0,min([MySequence])]),max([MySequence])];
                errordlg('Error in Matlab formula, please check ! ','Warning');
                formula_ok = 0;
            end
            if numel(MySequence)~=app.n_images
                errordlg('Error in Matlab formula (number of elements differs from number of images), please check ! ','Warning');
                formula_ok = 0;
            end
            if any([MySequence]<0) && formula_ok == 1
                plot(app.UIAxes, 1:app.n_images,MySequence);
                app.UIAxes.XLim=[1,app.n_images];
                app.UIAxes.YLim=[min([0,min([MySequence])]),max([max([MySequence]),min([MySequence])+1])];
                formula_ok = 0;
                errordlg('Laser value cannot be negative, please check formula ! ','Warning');
            end
            if formula_ok==1
                app.CustomPar=value;
                plot(app.UIAxes, 1:app.n_images,MySequence);
                app.UIAxes.XLim=[1,app.n_images];
                app.UIAxes.YLim=[min([0,min([MySequence])]),max([max([MySequence]),min([MySequence])+1])];
                app.sequence=MySequence;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Laser Custom Profile')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'Fraction of Max Power [%]')
            app.LaserCustomProfileEditorLabel = uilabel(app.UIFigure);
            app.LaserCustomProfileEditorLabel.Text = 'Laser Custom Profile Editor';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MatlabexpressionEditFieldLabel = uilabel(app.UIFigure);
            app.MatlabexpressionEditFieldLabel.Text = 'Matlab expression : ';
            app.MatlabexpressionEditField = uieditfield(app.UIFigure, 'text');
            app.MatlabexpressionEditField.ValueChangedFcn = createCallbackFcn(app, @MatlabexpressionEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaserCustomProfileEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end