classdef LaserFermiProfileEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CancelButton                  matlab.ui.control.Button
        LengthFramesEditField         matlab.ui.control.NumericEditField
        LengthFramesEditFieldLabel    matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        LaserFermiProfileEditorLabel  matlab.ui.control.Label
        SharpnessFramesEditField_2    matlab.ui.control.NumericEditField
        SharpnessFramesEditField_2Label  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        n_images=[]; % Initialize the parameters
        sequence=[];
        FermiPar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, n_images, FermiPar)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize n_images
            app.n_images=n_images;
            % Initialize FermiPar
            app.FermiPar=FermiPar;
            % Update UI with input values
            app.SharpnessFramesEditField_2.Value = FermiPar.NFermiFrames;
            app.LengthFramesEditField.Value = FermiPar.NfallofFrames;
            app.sequence=100*get_fermi_profile(app.n_images,FermiPar.NfallofFrames,FermiPar.NFermiFrames,0); % Use for Fermi profile;
            plot(app.UIAxes, 1:app.n_images,app.sequence/100);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateFermi(app.CallingApp, app.FermiPar,app.sequence)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: SharpnessFramesEditField_2
        function SharpnessFramesEditField_2ValueChanged(app, event)
            value = app.SharpnessFramesEditField_2.Value;
            app.sequence=100*get_fermi_profile(app.n_images,app.LengthFramesEditField.Value,value,0); % Use for Fermi profile;
            app.FermiPar.NFermiFrames=value;
            plot(app.UIAxes, 1:app.n_images,app.sequence/100);
        end

        % Value changed function: LengthFramesEditField
        function LengthFramesEditFieldValueChanged(app, event)
            value = app.LengthFramesEditField.Value;
            app.sequence=100*get_fermi_profile(app.n_images,value,app.SharpnessFramesEditField_2.Value,0); % Use for Fermi profile;
            app.FermiPar.NfallofFrames=value;
            plot(app.UIAxes, 1:app.n_images,app.sequence/100);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Laser Fermi Profile')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'Fraction of Max Power [%]')
            app.SharpnessFramesEditField_2Label = uilabel(app.UIFigure);
            app.SharpnessFramesEditField_2Label.Text = 'Sharpness [Frames]';
            app.SharpnessFramesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SharpnessFramesEditField_2.Limits = [0 Inf];
            app.SharpnessFramesEditField_2.ValueDisplayFormat = '%11.0d';
            app.SharpnessFramesEditField_2.ValueChangedFcn = createCallbackFcn(app, @SharpnessFramesEditField_2ValueChanged, true);
            app.SharpnessFramesEditField_2.Tooltip = {''; ''; 'This represents approximately the number of frames during which the family profile will switch from its lower to its higher values'};
            app.SharpnessFramesEditField_2.Value = 200;
            app.LaserFermiProfileEditorLabel = uilabel(app.UIFigure);
            app.LaserFermiProfileEditorLabel.Text = 'Laser Fermi Profile Editor';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.LengthFramesEditFieldLabel = uilabel(app.UIFigure);
            app.LengthFramesEditFieldLabel.Text = 'Length [Frames]';
            app.LengthFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthFramesEditField.Limits = [0 Inf];
            app.LengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @LengthFramesEditFieldValueChanged, true);
            app.LengthFramesEditField.Tooltip = {'This represents approximately the number of frames before the Fermi profile starts to rise sharply.'};
            app.LengthFramesEditField.Value = 0.9;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaserFermiProfileEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end