classdef LaserOffSelectedEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CancelButton                  matlab.ui.control.Button
        SelectedFramesEditField       matlab.ui.control.EditField
        SelectedFramesEditFieldLabel  matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        LaserOffonSelectedFramesEditorLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        n_images=[]; % Initialize the parameters
        sequence=[];
        OffSelectedPar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, n_images, OffSelectedPar)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize n_images
            app.n_images=n_images;
            % Initialize OffSelectedPar
            app.OffSelectedPar=OffSelectedPar;
            % Update UI with input values
            app.SelectedFramesEditField.Value = app.OffSelectedPar;
            app.sequence=100*ones(1,app.n_images);
            app.sequence(eval(app.OffSelectedPar))=0;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateOffSelected(app.CallingApp, app.OffSelectedPar,app.sequence)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: SelectedFramesEditField
        function SelectedFramesEditFieldValueChanged(app, event)
            value = app.SelectedFramesEditField.Value;
            app.OffSelectedPar=value;
            app.sequence=100*ones(1,app.n_images);
            app.sequence(eval(app.OffSelectedPar))=0;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Laser Profile')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'Fraction of Max Power [%]')
            app.LaserOffonSelectedFramesEditorLabel = uilabel(app.UIFigure);
            app.LaserOffonSelectedFramesEditorLabel.Text = 'Laser Off on Selected Frames Editor';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SelectedFramesEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedFramesEditFieldLabel.Text = 'Selected Frames';
            app.SelectedFramesEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedFramesEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedFramesEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaserOffSelectedEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end