classdef LaserRampProfileEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CancelButton                  matlab.ui.control.Button
        EndPowerEditField             matlab.ui.control.NumericEditField
        EndPowerEditFieldLabel        matlab.ui.control.Label
        StartPowerEditField           matlab.ui.control.NumericEditField
        StartPowerEditFieldLabel      matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        LaserRampProfileEditorLabel   matlab.ui.control.Label
        StepsizeFramesEditField       matlab.ui.control.NumericEditField
        StepsizeFramesEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        n_images=[]; % Initialize the parameters
        sequence=[];
        RampPar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, n_images, RampPar)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize n_images
            app.n_images=n_images;
            % Initialize RampPar
            app.RampPar=RampPar;
            % Update UI with input values
            app.StepsizeFramesEditField.Value = RampPar.RampStepSize;
            app.StartPowerEditField.Value = RampPar.RampStart;
            app.EndPowerEditField.Value = RampPar.RampEnd;
            app.sequence=get_ramp_profile(app.n_images, RampPar.RampStart, RampPar.RampEnd, RampPar.RampStepSize, 0); % Use for Ramp profile;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateRamp(app.CallingApp, app.RampPar,app.sequence)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: StepsizeFramesEditField
        function StepsizeFramesEditFieldValueChanged(app, event)
            value = app.StepsizeFramesEditField.Value;
            app.sequence=get_ramp_profile(app.n_images, app.StartPowerEditField.Value, app.EndPowerEditField.Value, app.StepsizeFramesEditField.Value, 0);
            app.RampPar.RampStepSize=value;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Value changed function: StartPowerEditField
        function StartPowerEditFieldValueChanged(app, event)
            value = app.StartPowerEditField.Value;
            app.sequence=get_ramp_profile(app.n_images, app.StartPowerEditField.Value, app.EndPowerEditField.Value, app.StepsizeFramesEditField.Value, 0);
            app.RampPar.RampStart=value;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Value changed function: EndPowerEditField
        function EndPowerEditFieldValueChanged(app, event)
            value = app.EndPowerEditField.Value;
            app.sequence=get_ramp_profile(app.n_images, app.StartPowerEditField.Value, app.EndPowerEditField.Value, app.StepsizeFramesEditField.Value, 0);
            app.RampPar.RampEnd=value;
            plot(app.UIAxes, 1:app.n_images,app.sequence);
            app.UIAxes.XLim=[1,app.n_images];
            app.UIAxes.YLim=[0,100];
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Laser Ramp Profile')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'Fraction of Max Power [%]')
            app.StepsizeFramesEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizeFramesEditFieldLabel.Text = 'Step size [Frames]';
            app.StepsizeFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizeFramesEditField.Limits = [0 Inf];
            app.StepsizeFramesEditField.ValueDisplayFormat = '%11.0d';
            app.StepsizeFramesEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeFramesEditFieldValueChanged, true);
            app.StepsizeFramesEditField.Value = 1;
            app.LaserRampProfileEditorLabel = uilabel(app.UIFigure);
            app.LaserRampProfileEditorLabel.Text = 'Laser Ramp Profile Editor';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.StartPowerEditFieldLabel = uilabel(app.UIFigure);
            app.StartPowerEditFieldLabel.Text = 'Start Power [%]';
            app.StartPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartPowerEditField.Limits = [0 Inf];
            app.StartPowerEditField.ValueChangedFcn = createCallbackFcn(app, @StartPowerEditFieldValueChanged, true);
            app.StartPowerEditField.Value = 0.9;
            app.EndPowerEditFieldLabel = uilabel(app.UIFigure);
            app.EndPowerEditFieldLabel.Text = 'End Power [%]';
            app.EndPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndPowerEditField.Limits = [0 Inf];
            app.EndPowerEditField.ValueChangedFcn = createCallbackFcn(app, @EndPowerEditFieldValueChanged, true);
            app.EndPowerEditField.Value = 0.9;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaserRampProfileEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end