classdef LaserSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProfileDropDown                 matlab.ui.control.DropDown
        BeamprofileLabel                matlab.ui.control.Label
        DrawFRAPpatternontovirtualsampleDropDown  matlab.ui.control.DropDown
        DrawFRAPpatternontovirtualsampleDropDownLabel  matlab.ui.control.Label
        DrawROISwitch                   matlab.ui.control.Switch
        DrawROISwitchLabel              matlab.ui.control.Label
        dnmEditField                    matlab.ui.control.NumericEditField
        dnmEditFieldLabel               matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        OverlayFRAPpatternontovirtualsampleDropDown  matlab.ui.control.DropDown
        OverlayFRAPpatternontovirtualsampleDropDownLabel  matlab.ui.control.Label
        LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel  matlab.ui.control.Label
        TIRFModeLabel                   matlab.ui.control.Label
        CriticalangleLabel              matlab.ui.control.Label
        FRAPMaskDirectoryEditField      matlab.ui.control.EditField
        FRAPMaskDirectoryEditFieldLabel  matlab.ui.control.Label
        LoadFRAPpatternButton           matlab.ui.control.Button
        LaserProfileLabel               matlab.ui.control.Label
        Powerdensityatcenterat100WcmEditField  matlab.ui.control.EditField
        Powerdensityatcenterat100WcmEditFieldLabel  matlab.ui.control.Label
        FWHMumEditField                 matlab.ui.control.NumericEditField
        FWHMumEditFieldLabel            matlab.ui.control.Label
        SequenceDropDown                matlab.ui.control.DropDown
        SequenceDropDownLabel           matlab.ui.control.Label
        CopySetupFromOtherLaserDropDown  matlab.ui.control.DropDown
        CopySetupFromOtherLaserDropDownLabel  matlab.ui.control.Label
        FRAPMaskFileEditField           matlab.ui.control.EditField
        FRAPMaskFileEditFieldLabel      matlab.ui.control.Label
        PhiAngleEditField               matlab.ui.control.NumericEditField
        PhiAngleLabel                   matlab.ui.control.Label
        PolarizationSwitch              matlab.ui.control.Switch
        PolarizationSwitchLabel         matlab.ui.control.Label
        FRAPModeSwitch                  matlab.ui.control.Switch
        FRAPModeSwitchLabel             matlab.ui.control.Label
        TIRFAngleEditField              matlab.ui.control.NumericEditField
        TIRFAngleEditFieldLabel         matlab.ui.control.Label
        TIRFHILOModeSwitch              matlab.ui.control.Switch
        TIRFHILOModeSwitchLabel         matlab.ui.control.Label
        LaserONBetweenFramesSwitch      matlab.ui.control.Switch
        LaserONBetweenFramesSwitchLabel  matlab.ui.control.Label
        LaserNameEditField              matlab.ui.control.EditField
        LaserNameEditFieldLabel         matlab.ui.control.Label
        LaserONDuringFramesSwitch       matlab.ui.control.Switch
        LaserONDuringFramesSwitchLabel  matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        LaserSetupLabel                 matlab.ui.control.Label
        PowermWEditField                matlab.ui.control.NumericEditField
        PowermWEditFieldLabel           matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Fermi_App   % Fermi setup App
        Custom_App   % Custom setup App
        Ramp_App   % Ramp setup App
        OnSelected_App % On selected setup App
        OffSelected_App % Off selected setup App
        DrawFRAPPattern_App % DrawFRAPPattern setup App
        CheckFRAPPattern_App % CheckFRAPPattern setup App
        OtherLaserpar=[]; % Initialize the parameters
        selected_laser=[] ; % Initialize selected laser
        Laserpar=[]; % Initialize the laser parameters
        n_images=[] ; % Initialize number of images
        simul_3D=[] ; % Initialize 3D option
        n_lasers=[] ; % Initialize Number of lasers
        image_size=[]; % Initialize Image size
        raster=[]; % Initialize Raster size [nm]
        critical_angle= []; % Initialize critical angle [°]
        addtime= []; % Initialize addtime
        FrapPattern= []; % Initialize FrapPattern
        beam_profile=[]; % Laser beam profile
        exp_beam_profile=[]; % Experimental Laser beam profile
        clims=[]; % Display limits
    end
    
    properties (Access = public)
         smis_gui_parameters=[] ; % Initialize smis_gui_parameters
    end
    
    methods (Access = public)
        function updateFermi(app, FermiPar, sequence)
            % Store inputs as properties
            app.Laserpar.FermiPar = FermiPar;
            app.Laserpar.sequence = sequence;
            
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end
        
        function updateCustom(app, CustomPar, sequence)
            % Store inputs as properties
            app.Laserpar.CustomPar = CustomPar;
            app.Laserpar.sequence = sequence;
            
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end
        
        function updateRamp(app, RampPar, sequence)
            % Store inputs as properties
            app.Laserpar.RampPar = RampPar;
            app.Laserpar.sequence = sequence;
            
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end
        
        function updateOnSelected(app, OnSelectedPar, sequence)
            % Store inputs as properties
            app.Laserpar.OnSelectedPar = OnSelectedPar;
            app.Laserpar.sequence = sequence;
            
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end
        
        function updateOffSelected(app, OffSelectedPar, sequence)
            % Store inputs as properties
            app.Laserpar.OffSelectedPar = OffSelectedPar;
            app.Laserpar.sequence = sequence;
            
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end

        function updateFRAP_mask(app, mask_par)

            app.Laserpar.mask_file= mask_par.FRAP_file;
            app.Laserpar.mask_dir=mask_par.FRAP_path;
            app.Laserpar.mask_pattern=mask_par.Mask;
            app.FrapPattern=mask_par.Mask;
            app.Laserpar.mask_size=[mask_par.image_size(1),mask_par.image_size(2)];
            
            if ~isempty(app.Laserpar.mask_pattern)
                app.FRAPMaskFileEditField.Visible ='on';
                app.FRAPMaskFileEditFieldLabel.Visible ='on';
                app.FRAPMaskDirectoryEditField.Visible ='on';
                app.FRAPMaskDirectoryEditFieldLabel.Visible ='on';
                app.FRAPMaskDirectoryEditField.Value=app.Laserpar.mask_dir;
                app.FRAPMaskFileEditField.Value=app.Laserpar.mask_file;
            else
                app.FRAPMaskFileEditField.Visible ='off';
                app.FRAPMaskFileEditFieldLabel.Visible ='off';
                app.FRAPMaskDirectoryEditField.Visible ='off';
                app.FRAPMaskDirectoryEditFieldLabel.Visible ='off';
            end
            % Re-enable the Plot Options button
            app.DrawFRAPpatternontovirtualsampleDropDown.Value=1;
            app.DrawFRAPpatternontovirtualsampleDropDown.Enable = 'on';
            UpdateLaserSetup(app)           
            
        end
        
        function updateCancel(app)
            % Re-enable the Plot Options button
            app.SequenceDropDown.Enable = 'on';
            app.DrawFRAPpatternontovirtualsampleDropDown.Enable = 'on';
        end
    end
    
    methods (Access = private)
        
        function UpdateLaserSetup(app)
            app.LaserNameEditField.Value = app.Laserpar.name;
            app.PowermWEditField.Value = app.Laserpar.power;
            app.FWHMumEditField.Value = app.Laserpar.fwhm;
            app.WavelengthnmEditField.Value = app.Laserpar.wavelength;
            app.PolarizationSwitch.Value = on2one(app.Laserpar.polarization, 'Circular', 'Linear');
            app.PhiAngleEditField.Value = app.Laserpar.phi;
            app.LaserONDuringFramesSwitch.Value = on2one(app.Laserpar.on_during_frametime, 'Off','On');
            app.LaserONBetweenFramesSwitch.Value = on2one(app.Laserpar.on_during_addtime, 'Off','On');
            app.TIRFHILOModeSwitch.Value = on2one(app.Laserpar.tirf, 'Off','On');
            app.TIRFAngleEditField.Value = app.Laserpar.tirf_angle;
            app.FRAPModeSwitch.Value = on2one(app.Laserpar.frap_mode, 'Off','On');
            app.FRAPMaskDirectoryEditField.Value = app.Laserpar.mask_dir;
            app.FRAPMaskFileEditField.Value = app.Laserpar.mask_file;
          
            if app.Laserpar.frap_mode==1 
                app.DrawROISwitch.Visible = 'on';
                app.DrawROISwitchLabel.Visible = 'on';

                app.FRAPMaskFileEditField.Visible ='on';
                app.FRAPMaskFileEditFieldLabel.Visible ='on';
                app.FRAPMaskDirectoryEditField.Visible = 'on';
                app.FRAPMaskDirectoryEditFieldLabel.Visible = 'on';

                if strcmp(app.DrawROISwitch.Value,'Off')==1
                    app.LoadFRAPpatternButton.Visible='on';
                    app.DrawFRAPpatternontovirtualsampleDropDown.Visible = 'off';
                    app.DrawFRAPpatternontovirtualsampleDropDownLabel.Visible = 'off';
                else
                    app.LoadFRAPpatternButton.Visible='off';
                    app.DrawFRAPpatternontovirtualsampleDropDown.Visible = 'on';
                    app.DrawFRAPpatternontovirtualsampleDropDownLabel.Visible = 'on';
                end

                if ~isempty(app.Laserpar.mask_pattern)
                    app.OverlayFRAPpatternontovirtualsampleDropDown.Visible = 'on';
                    app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible = 'on';
                else
                    app.OverlayFRAPpatternontovirtualsampleDropDown.Visible = 'off';
                    app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible = 'off';
                end
            
            else
                app.DrawROISwitch.Visible = 'off';
                app.DrawROISwitchLabel.Visible = 'off';
                app.LoadFRAPpatternButton.Visible='off';
                app.FRAPMaskFileEditField.Visible ='off';
                app.FRAPMaskFileEditFieldLabel.Visible ='off';
                app.FRAPMaskDirectoryEditField.Visible = 'off';
                app.FRAPMaskDirectoryEditFieldLabel.Visible = 'off';
                app.OverlayFRAPpatternontovirtualsampleDropDown.Visible = 'off';
                app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible = 'off';
                app.DrawFRAPpatternontovirtualsampleDropDown.Visible = 'off';
                app.DrawFRAPpatternontovirtualsampleDropDownLabel.Visible = 'off';
            end
          
            if app.Laserpar.polarization==1
                app.PhiAngleEditField.Visible ='on';
                app.PhiAngleLabel.Visible ='on';
            else
                app.PhiAngleEditField.Visible='off';
                app.PhiAngleLabel.Visible='off';
            end
            
            if app.simul_3D==1
                app.TIRFHILOModeSwitch.Visible ='on';
                app.TIRFHILOModeSwitchLabel.Visible ='on';
            else
                app.TIRFHILOModeSwitch.Visible ='off';
                app.TIRFHILOModeSwitchLabel.Visible ='off';
            end
 
            switch app.Laserpar.mode
                case 'Gaussian'
                    app.FWHMumEditFieldLabel.Text ='FWHM [um]';
                    app.FWHMumEditFieldLabel.Visible="on";
                    app.FWHMumEditField.Visible="on";
                case 'Flat'
                    app.FWHMumEditFieldLabel.Text ='Size [um]';
                    app.FWHMumEditFieldLabel.Visible="on";
                    app.FWHMumEditField.Visible="on";
                case 'Exp Profile'
                    app.FWHMumEditFieldLabel.Text ='-----';
                    app.FWHMumEditFieldLabel.Visible="off";
                    app.FWHMumEditField.Visible="off";
                case 'Load Profile'
                    app.FWHMumEditFieldLabel.Text ='-----';
                    app.FWHMumEditFieldLabel.Visible="off";
                    app.FWHMumEditField.Visible="off";
            end
          
            if app.Laserpar.tirf==1 && app.simul_3D==1
                app.CriticalangleLabel.Visible='on';
                app.TIRFModeLabel.Visible='on';
                app.CriticalangleLabel.Text=['Critical angle [°]: ',num2str(app.critical_angle)];
                app.TIRFAngleEditField.Visible ='on';
                app.TIRFAngleEditFieldLabel.Visible ='on';
                app.dnmEditField.Visible ='on';
                app.dnmEditFieldLabel.Visible ='on';
                theta=app.TIRFAngleEditField.Value*pi/180; % theta angle in rad
                sample_indice=app.smis_gui_parameters.obj_and_psf.obj_immersion_sample;
                immersion_indice=app.smis_gui_parameters.obj_and_psf.obj_immersion_indice;
                
                if app.TIRFAngleEditField.Value>=app.critical_angle %This is TIRF mode
                    app.TIRFModeLabel.Text='(TIRF Mode)';
                    % Calculate the characteristic depth
                    lambda=app.WavelengthnmEditField.Value;
                    d=lambda/(4*pi)/sqrt(immersion_indice^2*(sin(theta))^2-sample_indice^2); % in [nm]
                    app.dnmEditField.Value=d;
                    
                else %This is HILO mode
                    app.TIRFModeLabel.Text='(HILO Mode)';
                    % Calculate the characteristic depth
                    theta_sample=asin(immersion_indice/sample_indice*sin(theta));
                    d=1000*app.Laserpar.fwhm/tan(theta_sample); % in [nm]
                    app.dnmEditField.Value=d;
                end
            else
                app.CriticalangleLabel.Visible='off';
                app.TIRFModeLabel.Visible='off';
                app.TIRFAngleEditField.Visible ='off';
                app.TIRFAngleEditFieldLabel.Visible ='off';
                app.dnmEditField.Visible ='off';
                app.dnmEditFieldLabel.Visible ='off';
            end
            
            % Plot the laser sequence
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
            
            % Get the power density at center
            if ~isempty(app.image_size)
                % Plot the profile if a sample pattern has been loaded !
                app.LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel.Visible='off';
                app.ImageAxes.Visible='on';
                app.LaserProfileLabel.Visible='on';
                app.LaserProfileLabel.Text='Laser profile';
                get_laser_beam(app)
            else
                app.LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel.Visible='on';
                app.ImageAxes.Visible='off';
                app.LaserProfileLabel.Visible='off';
                ClearImage(app)
            end

            figure(app.UIFigure); % Get the Laser panel in the front
        end
        
        function get_laser_beam(app)
            % Function is the same as get_beam_profile.m in Main simulation program
            
            n=app.image_size(1); m=app.image_size(2);
            
            if app.Laserpar.power>0
                switch app.Laserpar.mode
                    case char('Gaussian')
                        c=[m/2+0.5 n/2+0.5];
                        [xpix,ypix] = meshgrid(1:m,1:n);
                        xpix=xpix-c(1);
                        ypix=ypix-c(2);
                        sigmax = 1000*app.Laserpar.fwhm/app.raster/2.35; % in pixels
                        sigmay = 1000*app.Laserpar.fwhm/app.raster/2.35; % in pixels
                        u=((xpix).*(xpix))/(2*sigmax^2)+((ypix).*(ypix))/(2*sigmay^2);
                        app.beam_profile=exp(-u);
                        %normalize to the actual total energy [J]
                        %maybe the laser spot extends far out of the image, so we need to normalize
                        %relative to the full laser spot, which we take at 10 sigmas
                        c_ref=[round(10*sigmax)/2+0.5 round(10*sigmay)/2+0.5];
                        [xpix_ref,ypix_ref] = meshgrid(1:round(10*sigmax),1:round(10*sigmay));
                        xpix_ref=xpix_ref-c_ref(1);
                        ypix_ref=ypix_ref-c_ref(2);
                        u_ref=((xpix_ref).*(xpix_ref))/(2*sigmax^2)+((ypix_ref).*(ypix_ref))/(2*sigmay^2);
                        beam_profile_ref=exp(-u_ref);
                        %normalize to the actual total energy [J]
                        app.beam_profile=app.beam_profile/sum(sum(beam_profile_ref))*app.Laserpar.power*1e-03; % Available power [W] in camera FOV
                        beam_profile_ok=1;
                        app.LaserProfileLabel.Text='Gaussian Laser Profile';
                    case char('Flat')
                        app.beam_profile=ones(n,m);
                        sxy = 1000*app.Laserpar.fwhm/app.raster; % laser size in pixels
                        if sxy<n % If laser size smaller than FOV, set to 0 the missing part
                            app.beam_profile(1:round((n-sxy)/2),:)=0;
                            app.beam_profile(n-round((n-sxy)/2):n,:)=0;
                        end
                        if sxy<m % If laser size smaller than FOV, set to 0 the missing part
                            app.beam_profile(:,1:round((m-sxy)/2))=0;
                            app.beam_profile(:,m-round((m-sxy)/2):m)=0;
                        end
                        %normalize to the actual total energy [J]
                        app.beam_profile=app.beam_profile/(sxy^2)*app.Laserpar.power*1e-03; % Available power [W] in camera FOV
                        beam_profile_ok=1;
                        app.LaserProfileLabel.Text='Flat Laser Profile';

                    case char('Exp Profile')
                        if ~isempty(app.exp_beam_profile)
                            app.exp_beam_profile=app.exp_beam_profile/sum(sum(app.exp_beam_profile))*app.Laserpar.power*1e-03; % Available power [W] in camera FOV
                            app.beam_profile=app.exp_beam_profile;
                            beam_profile_ok=CheckBeamProfile(app);
                            app.LaserProfileLabel.Text=['Exp Laser Profile: ',app.Laserpar.beam_profile_file];
                        else
                            mydlg = warndlg('Experimental beam profile not yet loaded ! ','Warning');
                            waitfor(mydlg);
                            app.beam_profile=[];
                            beam_profile_ok=0;
                        end

                    case char('Load Profile')

                        if exist(app.Laserpar.beam_profile_dir,'dir')
                            cd(app.Laserpar.beam_profile_dir);
                        else
                            cd(app.smis_gui_parameters.DefaultDirs.Pattern_dir);
                        end
                        [MyFile,MyPath] = uigetfile('*.tif*','Select a Laser profile file');

                        if ~isequal(MyFile,0)
                            % Read the pattern if found
                            app.Laserpar.beam_profile_dir=MyPath;
                            app.Laserpar.beam_profile_file=MyFile;
                            app.exp_beam_profile=double(imread(fullfile(MyPath,MyFile)));
                            app.exp_beam_profile=app.exp_beam_profile/sum(sum(app.exp_beam_profile))*app.Laserpar.power*1e-03; % Available power [W] in camera FOV
                            app.beam_profile=app.exp_beam_profile;
                            beam_profile_ok=CheckBeamProfile(app);
                            app.LaserProfileLabel.Text=['Exp Laser Profile: ',app.Laserpar.beam_profile_file];
                            app.Laserpar.mode='Exp Profile'; % Set the mode to Exp Profile
                        else
                            app.beam_profile=[];
                            app.Laserpar.mode='Load Profile';
                            beam_profile_ok=0;
                        end

                    otherwise
                        error('Error, laser shape not recognized !')
                end
                
                if beam_profile_ok==1
                    %Calculate power density [W/cm²] at center
                    %app.raster in nm => 1e+7 to go to cm ; duration in ms => 1e+3 to go to seconds
                    min_beam_profile=min(app.beam_profile(:));
                    max_beam_profile=max(app.beam_profile(:));
                    beam_power_density=max_beam_profile/((app.raster*1e-7)^2);

                    app.Laserpar.beam_profile=app.beam_profile; % Update the beam profile
                    app.Laserpar.max_beam_profile=max_beam_profile;  % Update the beam power density
                    app.Powerdensityatcenterat100WcmEditField.Value=num2str(beam_power_density,'%.2f');

                    %Display the laser beam profile
                    if  ~strcmp(app.Laserpar.mode,'Flat')==1
                        app.clims = [min_beam_profile, max_beam_profile];
                    else
                        app.clims = [0.8*min_beam_profile, 1.2*max_beam_profile];
                    end
                    if app.Laserpar.frap_mode==1 && ~isempty(app.Laserpar.mask_pattern)
                        imagesc(app.ImageAxes,double(app.Laserpar.mask_pattern).*app.beam_profile,app.clims);
                    else
                        imagesc(app.ImageAxes,app.beam_profile,app.clims);
                    end
                else
                    app.Laserpar.beam_profile=[]; % Update the beam profile
                    app.Laserpar.max_beam_profile=[];  % Update the beam power density
                    imagesc(app.ImageAxes,app.beam_profile); % Display empty profile
                    app.LaserProfileLabel.Text='No Valid Laser Profile';
                end
            else
                app.Powerdensityatcenterat100WcmEditField.Value=num2str(0,'%.2f');
                app.Laserpar.beam_profile=zeros(m,n);
                app.Laserpar.max_beam_profile=0;  % Update the beam power density
                imagesc(app.ImageAxes,app.beam_profile);
            end
        end
        
        function ClearImage(app)
            MyData = findobj(app.ImageAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
        
        
        function beam_profile_ok=CheckBeamProfile(app)
            % See if the pattern can be accepted: size needs to be compatible with detector size

            [~,n,m,~]=check_im_size(app.beam_profile, 1);
           
            % Check if image size has been defined and if yes look whether there is a change in image dimension
            n0=app.image_size(1); m0=app.image_size(2);
            DimChange=~isempty(n0) && (n~=n0 || m~=m0);

            if DimChange==1
                app.beam_profile=imresize(app.beam_profile,[n0 m0]);
                if ~isempty(app.exp_beam_profile)
                    app.exp_beam_profile=imresize(app.exp_beam_profile,[n0 m0]);
                end
                answer = questdlg('Pattern does not have the same size as detector size ... Ok to resize ?', ...
                    'SMIS Question', ...
                    'Yes','No','Yes');
                switch answer
                    case 'Yes'
                        beam_profile_ok=1;
                    case 'No'
                        beam_profile_ok=0;
                        app.beam_profile=[];
                        app.Laserpar.mode='Load Profile';
                end
            else % We are OK
                beam_profile_ok=1;
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selected_laser)
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.smis_gui_parameters=mainapp.smis_gui_parameters;
            
            % Grab the parameters from input
            app.n_images=app.smis_gui_parameters.n_images;
            app.addtime=app.smis_gui_parameters.addtime;
            app.simul_3D=app.smis_gui_parameters.simul_3D;
            app.raster=app.smis_gui_parameters.raster;
            app.critical_angle=180/pi*asin(app.smis_gui_parameters.obj_and_psf.obj_immersion_sample/app.smis_gui_parameters.obj_and_psf.obj_immersion_indice);
            
            app.selected_laser=selected_laser;
            if ~isempty(app.smis_gui_parameters.image_size.n)
                app.Powerdensityatcenterat100WcmEditField.Visible='on';
                app.Powerdensityatcenterat100WcmEditFieldLabel.Visible='on';
                app.image_size=[app.smis_gui_parameters.image_size.n,app.smis_gui_parameters.image_size.m,app.smis_gui_parameters.image_size.nz];
            else
                app.Powerdensityatcenterat100WcmEditField.Visible='off';
                app.Powerdensityatcenterat100WcmEditFieldLabel.Visible='off';
            end
            input_laser_par=app.smis_gui_parameters.lasers;
            
            % Initialize current Laserpar
            app.Laserpar=input_laser_par(selected_laser);
            
            % Initialize OtherLaserpar
            app.OtherLaserpar=input_laser_par;
            app.OtherLaserpar(selected_laser)=[];
            
            % Define item data for sequence drop-down menu
            app.SequenceDropDown.ItemsData=1:9;
            
            % Get number of lasers
            app.n_lasers=size(input_laser_par,2);
            
            app.FRAPMaskDirectoryEditField.Value=input_laser_par.mask_dir;
            app.FRAPMaskFileEditField.Value=input_laser_par.mask_file;
            % Get FRAP pattern (in case of FRAP)
            if ~isempty(app.Laserpar.mask_pattern)
                app.FrapPattern=app.Laserpar.mask_pattern;
            end

            % Get beam profile (in case of experimental beam profile)
            if strcmp(app.Laserpar.mode,'Exp Profile')==1
                app.exp_beam_profile=app.Laserpar.beam_profile;
            end
            
            % Handle visibility of copy laser option
            if app.n_lasers<2
                app.CopySetupFromOtherLaserDropDown.Visible = 'off';
                app.CopySetupFromOtherLaserDropDownLabel.Visible = 'off';
            else
                other_laser_names={input_laser_par.name};
                other_laser_names(selected_laser)=[];
                other_laser_names=horzcat('Choose',other_laser_names);
                app.CopySetupFromOtherLaserDropDown.Items=other_laser_names;
            end
            
            % Handle FRAP pattern overlay with virtual sample
            fluorophore_names={app.smis_gui_parameters.Fluorophores.name};
            virtual_sample_names=horzcat('Choose',fluorophore_names);
            app.OverlayFRAPpatternontovirtualsampleDropDown.Items=virtual_sample_names;
            app.OverlayFRAPpatternontovirtualsampleDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+1 ;
            app.DrawFRAPpatternontovirtualsampleDropDown.Items=virtual_sample_names;
            app.DrawFRAPpatternontovirtualsampleDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+1 ;
            
            % Setup ImageAxes
            axis(app.ImageAxes, 'image');
            colormap(app.ImageAxes,'gray')
            
            % Update UI with input values
            UpdateLaserSetup(app)
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Handle incomplete FRAP definition
            if isempty(app.Laserpar.mask_size)
                app.Laserpar.frap_mode=0;
            end
            
            if app.Laserpar.tirf==1 && isinf(app.dnmEditField.Value)
                mydlg=warndlg('TIRF at exactly the critical angle produces infinite characteristic penetration depth ! Increase TIRF angle !');
                waitfor(mydlg)
            else
                % Call main app's public function
                updateLaserpar(app.CallingApp, app.Laserpar, app.selected_laser)
                % Delete the dialog box
                delete(app.DrawFRAPPattern_App)
                delete(app.CheckFRAPPattern_App)
                delete(app)
            end
        end

        % Value changed function: PowermWEditField
        function PowermWEditFieldValueChanged(app, event)
            value = app.PowermWEditField.Value;
            app.Laserpar.power=value;
            
            UpdateLaserSetup(app);
        end

        % Callback function
        function SequenceEditFieldValueChanged(app, event)
            value = app.SequenceEditField.Value;
            app.Laserpar.obj_immersion_indice=value;
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            value = app.WavelengthnmEditField.Value;
            app.Laserpar.wavelength=value;
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
        end

        % Value changed function: LaserONDuringFramesSwitch
        function LaserONDuringFramesSwitchValueChanged(app, event)
            value = app.LaserONDuringFramesSwitch.Value;
            app.Laserpar.on_during_frametime=on2one(value, 'Off', 'On');
        end

        % Value changed function: LaserNameEditField
        function LaserNameEditFieldValueChanged(app, event)
            value = app.LaserNameEditField.Value;
            if ~isnan(str2double(value(1)))
                disp('Laser name must start with a letter ! ; Adding "Laser" at the beginning of the name')
                value=strcat('Laser',value);
                app.LaserNameEditField.Value=value;
            end
            app.Laserpar.name=value;
        end

        % Value changed function: FWHMumEditField
        function FWHMumEditFieldValueChanged(app, event)
            value = app.FWHMumEditField.Value;
            app.Laserpar.fwhm=value;
            UpdateLaserSetup(app);
        end

        % Value changed function: LaserONBetweenFramesSwitch
        function LaserONBetweenFramesSwitchValueChanged(app, event)
            value = app.LaserONBetweenFramesSwitch.Value;
            if strcmp(value,'On')==1 && app.addtime==0
                mydlg=warndlg('Addtime between frametimes is currently set to zero ! ');
                waitfor(mydlg)
                app.Laserpar.on_during_addtime=0;
                app.LaserONBetweenFramesSwitch.Value=event.PreviousValue;
            else
                app.Laserpar.on_during_addtime=on2one(value, 'Off', 'On');
            end
        end

        % Value changed function: PolarizationSwitch
        function PolarizationSwitchValueChanged(app, event)
            value = app.PolarizationSwitch.Value;
            app.Laserpar.polarization=on2one(value, 'Circular', 'Linear');
            
            if app.Laserpar.polarization==1
                app.PhiAngleEditField.Visible ='on';
                app.PhiAngleLabel.Visible ='on';
            else
                app.PhiAngleEditField.Visible='off';
                app.PhiAngleLabel.Visible='off';
            end
            
        end

        % Value changed function: TIRFHILOModeSwitch
        function TIRFHILOModeSwitchValueChanged(app, event)
            value = app.TIRFHILOModeSwitch.Value;
            app.Laserpar.tirf=on2one(value, 'Off', 'On');
            
            % Update UI with input values
            UpdateLaserSetup(app)
            
        end

        % Value changed function: FRAPModeSwitch
        function FRAPModeSwitchValueChanged(app, event)
            value = app.FRAPModeSwitch.Value;
            app.Laserpar.frap_mode=on2one(value, 'Off', 'On');
            
            if app.Laserpar.frap_mode==1
                app.LoadFRAPpatternButton.Visible='on';
            else
                app.LoadFRAPpatternButton.Visible='off';
            end
            
            if app.Laserpar.frap_mode==1 && ~isempty(app.Laserpar.mask_file)
                app.FRAPMaskFileEditField.Visible ='on';
                app.FRAPMaskFileEditFieldLabel.Visible ='on';
                app.FRAPMaskDirectoryEditField.Visible ='on';
                app.FRAPMaskDirectoryEditFieldLabel.Visible ='on';
                app.OverlayFRAPpatternontovirtualsampleDropDown.Visible ='on';
                app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible ='on';
            else
                app.FRAPMaskFileEditField.Visible ='off';
                app.FRAPMaskFileEditFieldLabel.Visible ='off';
                app.FRAPMaskDirectoryEditField.Visible ='off';
                app.FRAPMaskDirectoryEditFieldLabel.Visible ='off';
                app.OverlayFRAPpatternontovirtualsampleDropDown.Visible ='off';
                app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible ='off';
            end
            UpdateLaserSetup(app);
        end

        % Value changed function: SequenceDropDown
        function SequenceDropDownValueChanged(app, event)
            value = app.SequenceDropDown.Value-1;
            
            switch value
                case 1
                    app.Laserpar.sequence=100*ones(1,app.n_images);
                case 2
                    % Disable Plot Options button while dialog is open
                    app.SequenceDropDown.Enable='off';
                    % Get the sequence
                    app.Ramp_App = LaserRampProfileEditor(app, app.n_images,app.Laserpar.RampPar);
                case 3
                    % Disable Plot Options button while dialog is open
                    app.SequenceDropDown.Enable='off';
                    % Get the sequence
                    app.OnSelected_App = LaserOnSelectedEditor(app, app.n_images,app.Laserpar.OnSelectedPar);
                case 4
                    % Disable Plot Options button while dialog is open
                    app.SequenceDropDown.Enable='off';
                    % Get the sequence
                    app.OffSelected_App = LaserOffSelectedEditor(app, app.n_images,app.Laserpar.OffSelectedPar);
                case 5
                    % Disable Plot Options button while dialog is open
                    app.SequenceDropDown.Enable='off';
                    % Get the sequence
                    app.Fermi_App = LaserFermiProfileEditor(app, app.n_images,app.Laserpar.FermiPar);
                case 6
                    sequence=100*ones(1,app.n_images);
                    sequence(2:2:end)=0;
                    app.Laserpar.sequence=sequence;
                case 7
                    sequence=100*ones(1,app.n_images);
                    sequence(1:2:end)=0;
                    app.Laserpar.sequence=sequence;
                case 8
                    % Disable Plot Options button while dialog is open
                    app.SequenceDropDown.Enable='off';
                    % Get the sequence
                    app.Custom_App = LaserCustomProfileEditor(app, app.n_images,app.Laserpar.CustomPar);
            end
            
            loc_c=wavelength2rgb(-app.Laserpar.wavelength);
            scatter(app.UIAxes, 1:app.n_images,app.Laserpar.sequence/100*app.Laserpar.power,[],loc_c,'filled')
            
            % Reset Sequence menu to first position
            app.SequenceDropDown.Value = 1;
            
        end

        % Value changed function: CopySetupFromOtherLaserDropDown
        function CopySetupFromOtherLaserDropDownValueChanged(app, event)
            value = app.CopySetupFromOtherLaserDropDown.Value;
            OtherLaserNames={app.OtherLaserpar.name}; % Makes this a cell array
            sel=find(strcmp(OtherLaserNames,value)==1,1);
            app.Laserpar=app.OtherLaserpar(sel);
            
            % Update UI with input values
            UpdateLaserSetup(app);
            
            % Reset menu to first position
            app.CopySetupFromOtherLaserDropDown.Value = 'Choose';
        end

        % Callback function
        function ProfileSwitchValueChanged(app, event)
            % value = app.ProfileSwitch.Value;
            % app.Laserpar.mode=value;
            % 
            % % Update UI with input values
            % UpdateLaserSetup(app)
        end

        % Value changed function: PhiAngleEditField
        function PhiAngleEditFieldValueChanged(app, event)
            value = app.PhiAngleEditField.Value;
            app.Laserpar.phi=value;
        end

        % Button pushed function: LoadFRAPpatternButton
        function LoadFRAPpatternButtonPushed(app, event)
            %A FRAP mask cannot be loaded without an image pattern for compatibility with image size
            if isempty(app.image_size)
                errordlg('Image pattern must have been loaded first ! ','Error');
                app.Laserpar.frap_mode=0;
                app.FRAPModeSwitch.Value='Off';
                app.LoadFRAPpatternButton.Visible='off';
                app.FRAPMaskFileEditField.Visible='off';
                app.FRAPMaskFileEditFieldLabel.Visible='off';
                app.FRAPMaskDirectoryEditField.Visible='off';
                app.FRAPMaskDirectoryEditFieldLabel.Visible='off';
                app.OverlayFRAPpatternontovirtualsampleDropDown.Visible='off';
                app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible='off';
            else
                if exist(app.Laserpar.mask_dir,'dir')
                    cd(app.Laserpar.mask_dir);
                end
                [MyFile,MyPath] = uigetfile('*.tif*','Select a FRAP mask file');
                
                if ~isequal(MyFile,0)
                    % Read the pattern
                    app.FrapPattern=imread(fullfile(MyPath,MyFile));
                    % Normalize to 1
                    app.FrapPattern=app.FrapPattern/max(app.FrapPattern(:));
                    [~,n,m,~]=check_im_size(app.FrapPattern, 1);
                    
                    % See if the FRAP mask can be accepted: size needs to be compatible with eventual already loaded patterns
                    
                    % Check if image size has been defined and if yes look whether there is a change in image dimension
                    n0=app.image_size(1); m0=app.image_size(2);
                    DimChange=~isempty(n0) && (n~=n0 || m~=m0);
                    
                    % Check that the FRAP pattern contains only 1 value
                    N_FRAP_val=numel(unique(app.FrapPattern));
                    
                    if DimChange==1

                        app.FrapPattern=imresize(app.FrapPattern,[n0 m0]);
                        app.FrapPattern=round(app.FrapPattern);  % Ensure only logical values
                        answer = questdlg('FRAP pattern does not have the same size as detector size ... Ok to resize ?', ...
                            'SMIS Question', ...
                            'Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                FRAP_OK=1;
                            case 'No'
                                FRAP_OK=0;
                        end                        
                    elseif N_FRAP_val~=2
                        mydlg=warndlg('FRAP mask must be properly segmented, ie must contain 2 pixel values, 0 and > 0 ! ','Warning');
                        waitfor(mydlg)
                        FRAP_OK=0;
                    else
                        FRAP_OK=1;
                    end
                    
                    if FRAP_OK==0
                        app.Laserpar.frap_mode=0;
                        app.FRAPModeSwitch.Value='Off';
                        app.LoadFRAPpatternButton.Visible='off';
                        app.FRAPMaskFileEditField.Visible='off';
                        app.FRAPMaskFileEditFieldLabel.Visible='off';
                        app.FRAPMaskDirectoryEditField.Visible='off';
                        app.FRAPMaskDirectoryEditFieldLabel.Visible='off';
                        app.OverlayFRAPpatternontovirtualsampleDropDown.Visible='off';
                        app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible='off';
                    else % We are okay
                        app.FRAPMaskFileEditField.Visible ='on';
                        app.FRAPMaskFileEditFieldLabel.Visible ='on';
                        app.FRAPMaskDirectoryEditField.Visible ='on';
                        app.FRAPMaskDirectoryEditFieldLabel.Visible ='on';
                        app.OverlayFRAPpatternontovirtualsampleDropDown.Visible ='on';
                        app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Visible ='on';
                        app.FRAPMaskDirectoryEditField.Value=MyPath;
                        app.FRAPMaskFileEditField.Value=MyFile;
                        app.Laserpar.mask_file=MyFile;
                        app.Laserpar.mask_dir=MyPath;
                        app.Laserpar.mask_pattern=app.FrapPattern;
                        app.Laserpar.mask_size=[n,m];
                        UpdateLaserSetup(app);
                    end
                end
            end
        end

        % Value changed function: TIRFAngleEditField
        function TIRFAngleEditFieldValueChanged(app, event)
            value = app.TIRFAngleEditField.Value;
            app.Laserpar.tirf_angle=value;
            UpdateLaserSetup(app);
            
        end

        % Value changed function: 
        % OverlayFRAPpatternontovirtualsampleDropDown
        function OverlayFRAPpatternontovirtualsampleDropDownValueChanged(app, event)
            value = app.OverlayFRAPpatternontovirtualsampleDropDown.Value;
            
            %Load the FRAP pattern if needed
            if isempty(app.FrapPattern)
                app.FrapPattern=imread(fullfile(app.Laserpar.mask_dir,app.Laserpar.mask_file));
                % Normalize to 1
                app.FrapPattern=app.FrapPattern/max(app.FrapPattern(:));
            end
            
            selected_fluorophore=value-1;

            if ~isempty(app.smis_gui_parameters.in_images_dir{selected_fluorophore})
                app.CheckFRAPPattern_App=CheckFRAPPattern(app,app.smis_gui_parameters.in_images{selected_fluorophore},app.FrapPattern, app.smis_gui_parameters.binning);
            else

                MyPath=app.smis_gui_parameters.in_images_dir{selected_fluorophore};
                MyFile=app.smis_gui_parameters.in_images_names{selected_fluorophore};

                if isfile(fullfile(cellstr(MyPath), cellstr(MyFile)))
                    % Read the pattern
                    [Pattern,~,~,~,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                    Pattern=squeeze(Pattern);
                    app.CheckFRAPPattern_App=CheckFRAPPattern(app,Pattern,app.FrapPattern, app.smis_gui_parameters.binning);
                else
                    mydlg=warndlg(['Image pattern for fluorophore: ',app.Fluorophorepar.name, ' has not been loaded !'],'Warning');
                    waitfor(mydlg)
                end
            end
            
            % Reset menu
            app.OverlayFRAPpatternontovirtualsampleDropDown.Value=1;
            
        end

        % Value changed function: Powerdensityatcenterat100WcmEditField
        function Powerdensityatcenterat100WcmEditFieldValueChanged(app, event)
            new_power_density = str2double(app.Powerdensityatcenterat100WcmEditField.Value);
            old_power_density=str2double(event.PreviousValue);
            
            if old_power_density==0 % Use a trick here to handle the case where zero power was specified
                app.Laserpar.power=1;
                UpdateLaserSetup(app);
                old_power_density=str2double(app.Powerdensityatcenterat100WcmEditField.Value);
            end
            
            s=new_power_density/old_power_density;
            app.Laserpar.power=s*app.PowermWEditField.Value;
            app.PowermWEditField.Value=app.Laserpar.power;
            UpdateLaserSetup(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.DrawFRAPPattern_App)
            delete(app.CheckFRAPPattern_App)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: dnmEditField
        function dnmEditFieldValueChanged(app, event)
            value = app.dnmEditField.Value;
            
            % Calculate the angle
            lambda=app.WavelengthnmEditField.Value;
            sample_indice=app.smis_gui_parameters.obj_and_psf.obj_immersion_sample;
            immersion_indice=app.smis_gui_parameters.obj_and_psf.obj_immersion_indice;
            % Set a limit between TIRF and HILO
            hilo_tirf_limit=300; % [nm]
            if value<=hilo_tirf_limit % TIRF mode
                theta=180/pi*asin(sqrt((lambda/(4*pi)/value)^2+sample_indice^2)/immersion_indice); % in [deg]
                app.TIRFModeLabel.Text='(TIRF Mode)';
            else   
                theta_sample=atan(1000*app.Laserpar.fwhm/value); % in [rad]
                theta=180/pi*asin(sample_indice/immersion_indice*sin(theta_sample));
                app.TIRFModeLabel.Text='(HILO Mode)';
            end
            app.TIRFAngleEditField.Value=theta;
            app.Laserpar.tirf_angle=theta;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DrawFRAPPattern_App)
            delete(app.CheckFRAPPattern_App)
            Reenable(app.CallingApp)
            delete(app)           
        end

        % Value changed function: DrawFRAPpatternontovirtualsampleDropDown
        function DrawFRAPpatternontovirtualsampleDropDownValueChanged(app, event)
            app.DrawFRAPpatternontovirtualsampleDropDown.Enable = 'off';
           
            value = app.DrawFRAPpatternontovirtualsampleDropDown.Value;
            
            selected_fluorophore=value-1;

            if ~isempty(app.smis_gui_parameters.in_images{selected_fluorophore})
                app.DrawFRAPPattern_App=DrawFRAPPattern(app,app.smis_gui_parameters.in_images{selected_fluorophore});
            else
                mydlg=warndlg(['Virtual sample for fluorophore: ',app.Fluorophorepar.name, ' has not been loaded !'],'Warning'); 
                waitfor(mydlg)
            end

            % Reset Sequence menu to first position
            app.DrawFRAPpatternontovirtualsampleDropDown.Value = 1;

        end

        % Value changed function: DrawROISwitch
        function DrawROISwitchValueChanged(app, event)
            UpdateLaserSetup(app);
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            app.Laserpar.mode= app.ProfileDropDown.Value;

            % Reset Sequence menu to first position
            app.ProfileDropDown.Value = 'Choose';

            UpdateLaserSetup(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Laser Sequence')
            xlabel(app.UIAxes, 'Frame #')
            ylabel(app.UIAxes, 'Laser power')
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.PowermWEditFieldLabel = uilabel(app.UIFigure);
            app.PowermWEditFieldLabel.Text = 'Power [mW]';
            app.PowermWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowermWEditField.Limits = [0 Inf];
            app.PowermWEditField.ValueDisplayFormat = '%5.2f';
            app.PowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PowermWEditFieldValueChanged, true);
            app.PowermWEditField.Tooltip = {'Power of the laser at sample position. The resulting power density at the center of the laser beam is shown when both power and size of the laser are set, and when a virtual sample pattern has been loaded.'};
            app.PowermWEditField.Value = 10;
            app.LaserSetupLabel = uilabel(app.UIFigure);
            app.LaserSetupLabel.Text = 'Laser Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main SMIS menu'};
            app.OKButton.Text = 'OK';
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength [nm]';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthnmEditField.Limits = [200 1000];
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Tooltip = {'Wavelength of the laser'};
            app.WavelengthnmEditField.Value = 488;
            app.LaserONDuringFramesSwitchLabel = uilabel(app.UIFigure);
            app.LaserONDuringFramesSwitchLabel.Text = 'Laser ON During Frames';
            app.LaserONDuringFramesSwitch = uiswitch(app.UIFigure, 'slider');
            app.LaserONDuringFramesSwitch.ValueChangedFcn = createCallbackFcn(app, @LaserONDuringFramesSwitchValueChanged, true);
            app.LaserONDuringFramesSwitch.Tooltip = {'Use this switch to decide whether the laser is on during frame acquisition'};
            app.LaserONDuringFramesSwitch.Value = 'On';
            app.LaserNameEditFieldLabel = uilabel(app.UIFigure);
            app.LaserNameEditFieldLabel.Text = 'Laser Name';
            app.LaserNameEditField = uieditfield(app.UIFigure, 'text');
            app.LaserNameEditField.ValueChangedFcn = createCallbackFcn(app, @LaserNameEditFieldValueChanged, true);
            app.LaserNameEditField.Tooltip = {'Name of the laser'};
            app.LaserONBetweenFramesSwitchLabel = uilabel(app.UIFigure);
            app.LaserONBetweenFramesSwitchLabel.Text = 'Laser ON Between Frames';
            app.LaserONBetweenFramesSwitch = uiswitch(app.UIFigure, 'slider');
            app.LaserONBetweenFramesSwitch.ValueChangedFcn = createCallbackFcn(app, @LaserONBetweenFramesSwitchValueChanged, true);
            app.LaserONBetweenFramesSwitch.Tooltip = {'Use this switch to decide whether the laser is on between frame acquisitions. This is only possible if a time between frame greater than zero is set in the main SMIS window.'};
            app.LaserONBetweenFramesSwitch.Value = 'On';
            app.TIRFHILOModeSwitchLabel = uilabel(app.UIFigure);
            app.TIRFHILOModeSwitchLabel.Text = 'TIRF/HILO Mode';
            app.TIRFHILOModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.TIRFHILOModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TIRFHILOModeSwitchValueChanged, true);
            app.TIRFHILOModeSwitch.Tooltip = {'Use this switch to apply TIRF or HILO illumination in 3D mode. Use this switch to apply TIRF or HILO illumination in 3D mode. The critical angle corresponding to the objective specifications is then displayed. '};
            app.TIRFAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TIRFAngleEditFieldLabel.Text = 'TIRF Angle [°]';
            app.TIRFAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TIRFAngleEditField.Limits = [-1 Inf];
            app.TIRFAngleEditField.ValueDisplayFormat = '%8.4g';
            app.TIRFAngleEditField.ValueChangedFcn = createCallbackFcn(app, @TIRFAngleEditFieldValueChanged, true);
            app.TIRFAngleEditField.Tooltip = {'Changing this angle to a value lower than the critical angle will allow to switch to HILO mode.'};
            app.TIRFAngleEditField.Value = -1;
            app.FRAPModeSwitchLabel = uilabel(app.UIFigure);
            app.FRAPModeSwitchLabel.Text = 'FRAP Mode';
            app.FRAPModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.FRAPModeSwitch.ValueChangedFcn = createCallbackFcn(app, @FRAPModeSwitchValueChanged, true);
            app.FRAPModeSwitch.Tooltip = {'Use this switch to use the laser in FRAP mode or for pulse-chase, that is the laser will only be applied in a restricted field of view defined by the FRAP pattern to be loaded. '};
            app.PolarizationSwitchLabel = uilabel(app.UIFigure);
            app.PolarizationSwitchLabel.Text = 'Polarization';
            app.PolarizationSwitch = uiswitch(app.UIFigure, 'slider');
            app.PolarizationSwitch.Items = {'Circular', 'Linear'};
            app.PolarizationSwitch.ValueChangedFcn = createCallbackFcn(app, @PolarizationSwitchValueChanged, true);
            app.PolarizationSwitch.Tooltip = {'The laser can have circular or linear polarization. If linear polarization, the angle of the laser polarization relative to the X axis of the focal plane should be defined. Laser polarization is only relevant when the option of anisotropic fluorophore dipole orientation is chosen for at least one fluorophore.'};
            app.PolarizationSwitch.Value = 'Linear';
            app.PhiAngleLabel = uilabel(app.UIFigure);
            app.PhiAngleLabel.Visible = 'off';
            app.PhiAngleLabel.Text = 'Phi Angle [°]';
            app.PhiAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiAngleEditField.Limits = [0 Inf];
            app.PhiAngleEditField.ValueChangedFcn = createCallbackFcn(app, @PhiAngleEditFieldValueChanged, true);
            app.PhiAngleEditField.Visible = 'off';
            app.FRAPMaskFileEditFieldLabel = uilabel(app.UIFigure);
            app.FRAPMaskFileEditFieldLabel.Text = 'FRAP Mask File';
            app.FRAPMaskFileEditField = uieditfield(app.UIFigure, 'text');
            app.FRAPMaskFileEditField.Editable = 'off';
            app.FRAPMaskFileEditField.Tooltip = {'The used FRAP mask file and directory are displayed (but cannot be modified) here.'};
            app.CopySetupFromOtherLaserDropDownLabel = uilabel(app.UIFigure);
            app.CopySetupFromOtherLaserDropDownLabel.Text = 'Copy Setup From Other Laser';
            app.CopySetupFromOtherLaserDropDown = uidropdown(app.UIFigure);
            app.CopySetupFromOtherLaserDropDown.ValueChangedFcn = createCallbackFcn(app, @CopySetupFromOtherLaserDropDownValueChanged, true);
            app.CopySetupFromOtherLaserDropDown.Tooltip = {'Use this menu to copy all parameters from another defined laser to the current laser. This is in particular useful when you want to delete some lasers and only keep some that are at the end of the list: in that case copy the setting of the lasers you want to keep to those of the first lasers in the list.'};
            app.SequenceDropDownLabel = uilabel(app.UIFigure);
            app.SequenceDropDownLabel.Text = 'Sequence';
            app.SequenceDropDown = uidropdown(app.UIFigure);
            app.SequenceDropDown.Items = {'Choose', 'Constant at 100%', 'Ramp', 'On during selected Frames', 'Off during selected Frames', 'Fermi Profile', 'On during odd frames', 'On during even frames', 'Custom Profile'};
            app.SequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @SequenceDropDownValueChanged, true);
            app.SequenceDropDown.Tooltip = {'Use this menu to define the laser sequence scheme along data acquisition. The currently used laser sequence is shown on the plot on the lower right of the window.'};
            app.SequenceDropDown.Value = 'Choose';
            app.FWHMumEditFieldLabel = uilabel(app.UIFigure);
            app.FWHMumEditFieldLabel.Text = 'FWHM [um]';
            app.FWHMumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMumEditField.Limits = [0 Inf];
            app.FWHMumEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMumEditFieldValueChanged, true);
            app.FWHMumEditField.Tooltip = {'Size of the laser at sample position, either full width at half maximum in case of a Gaussian beam, or size of the laser if flat beam. The resulting power density at the center of the laser beam is shown when both power and size of the laser are set, and when a virtual sample pattern has been loaded.'};
            app.FWHMumEditField.Value = 1.33;
            app.Powerdensityatcenterat100WcmEditFieldLabel = uilabel(app.UIFigure);
            app.Powerdensityatcenterat100WcmEditFieldLabel.Text = 'Power density at center at 100 % [W/cm²]';
            app.Powerdensityatcenterat100WcmEditField = uieditfield(app.UIFigure, 'text');
            app.Powerdensityatcenterat100WcmEditField.ValueChangedFcn = createCallbackFcn(app, @Powerdensityatcenterat100WcmEditFieldValueChanged, true);
            app.LaserProfileLabel = uilabel(app.UIFigure);
            app.LaserProfileLabel.Tooltip = {'Note that the laser beam profile has the same dimensions as the camera frames dimensions, which are defined by the virtual sample that has been loaded divided by the binning parameter set in the main SMIS window'};
            app.LaserProfileLabel.Text = 'Laser Profile';
            app.LoadFRAPpatternButton = uibutton(app.UIFigure, 'push');
            app.LoadFRAPpatternButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFRAPpatternButtonPushed, true);
            app.LoadFRAPpatternButton.Tooltip = {'You can only load a FRAP pattern of the same overall dimension than the camera dimension. '};
            app.LoadFRAPpatternButton.Text = 'Load FRAP pattern';
            app.FRAPMaskDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.FRAPMaskDirectoryEditFieldLabel.Text = 'FRAP Mask Directory';
            app.FRAPMaskDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.FRAPMaskDirectoryEditField.Editable = 'off';
            app.CriticalangleLabel = uilabel(app.UIFigure);
            app.CriticalangleLabel.Visible = 'off';
            app.CriticalangleLabel.Text = 'Critical angle ';
            app.TIRFModeLabel = uilabel(app.UIFigure);
            app.TIRFModeLabel.Text = '(TIRF Mode)';
            app.LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel = uilabel(app.UIFigure);
            app.LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel.Tooltip = {''};
            app.LoadasamplepatterntodefineimagesizeanddisplaylaserprofileLabel.Text = 'Load a sample pattern to define image size and display laser profile !';
            app.OverlayFRAPpatternontovirtualsampleDropDownLabel = uilabel(app.UIFigure);
            app.OverlayFRAPpatternontovirtualsampleDropDownLabel.Text = 'Overlay FRAP pattern onto virtual sample';
            app.OverlayFRAPpatternontovirtualsampleDropDown = uidropdown(app.UIFigure);
            app.OverlayFRAPpatternontovirtualsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @OverlayFRAPpatternontovirtualsampleDropDownValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main SMIS menu'};
            app.CancelButton.Text = 'Cancel';
            app.dnmEditFieldLabel = uilabel(app.UIFigure);
            app.dnmEditFieldLabel.Text = 'd [nm]';
            app.dnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.dnmEditField.Limits = [-1 Inf];
            app.dnmEditField.ValueDisplayFormat = '%.0f';
            app.dnmEditField.ValueChangedFcn = createCallbackFcn(app, @dnmEditFieldValueChanged, true);
            app.dnmEditField.Tooltip = {'Characteristic depth In TIRF mode'};
            app.dnmEditField.Value = -1;
            app.DrawROISwitchLabel = uilabel(app.UIFigure);
            app.DrawROISwitchLabel.Text = 'Draw ROI';
            app.DrawROISwitch = uiswitch(app.UIFigure, 'slider');
            app.DrawROISwitch.ValueChangedFcn = createCallbackFcn(app, @DrawROISwitchValueChanged, true);
            app.DrawFRAPpatternontovirtualsampleDropDownLabel = uilabel(app.UIFigure);
            app.DrawFRAPpatternontovirtualsampleDropDownLabel.Text = 'Draw FRAP pattern onto virtual sample';
            app.DrawFRAPpatternontovirtualsampleDropDown = uidropdown(app.UIFigure);
            app.DrawFRAPpatternontovirtualsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @DrawFRAPpatternontovirtualsampleDropDownValueChanged, true);
            app.BeamprofileLabel = uilabel(app.UIFigure);
            app.BeamprofileLabel.Text = 'Beam profile';
            app.ProfileDropDown = uidropdown(app.UIFigure);
            app.ProfileDropDown.Items = {'Choose', 'Gaussian', 'Flat', 'Exp Profile', 'Load Profile'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Tooltip = {'Use this menu to define the laser profile. You can choose between a Gaussian profile, a flat profile, or a custom profile such as an experimental laser beam profile or an irradiance map. The custom profile should preferably have the same size as the detector, but if not SMIS will propose to resize.. '};
            app.ProfileDropDown.Value = 'Choose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaserSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end