classdef ObjectivePSF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SampleheightatcoverslippositionnmTIRFEditFieldLabel  matlab.ui.control.Label
        SampleheightatcoverslippositionnmTIRFEditField  matlab.ui.control.NumericEditField
        OnChannel1onlyCheckBox          matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        Y4thordercorrectionEditField    matlab.ui.control.NumericEditField
        Y4thordercorrectionEditFieldLabel  matlab.ui.control.Label
        X4thordercorrectionEditField    matlab.ui.control.NumericEditField
        X4thordercorrectionEditFieldLabel  matlab.ui.control.Label
        NumberofslicesofPSFinZdimensionEditField  matlab.ui.control.NumericEditField
        NumberofslicesofPSFinZdimensionEditFieldLabel  matlab.ui.control.Label
        PositionfocalplaneatcoverslipforTIRFmodeButton  matlab.ui.control.Button
        ShiftofsamplecenterrelativetofocalplanenmEditField  matlab.ui.control.NumericEditField
        ShiftofsamplecenterrelativetofocalplanenmEditFieldLabel  matlab.ui.control.Label
        ObjectiveDepthoffocusnmEditField  matlab.ui.control.NumericEditField
        ObjectiveDepthoffocusnmEditFieldLabel  matlab.ui.control.Label
        AstigmatisminYdimensionEditField  matlab.ui.control.NumericEditField
        AstigmatisminYdimensionEditFieldLabel  matlab.ui.control.Label
        AstigmatisminXdimensionEditField  matlab.ui.control.NumericEditField
        AstigmatisminXdimensionEditFieldLabel  matlab.ui.control.Label
        Astigmatismfor3DSMLMSwitch      matlab.ui.control.Switch
        Astigmatismfor3DSMLMSwitchLabel  matlab.ui.control.Label
        MicroscopeTransmissionEfficiencyEditField  matlab.ui.control.NumericEditField
        MicroscopeTransmissionEfficiencyEditFieldLabel  matlab.ui.control.Label
        AddtionnalAttenuationEditField  matlab.ui.control.NumericEditField
        AddtionnalAttenuationEditFieldLabel  matlab.ui.control.Label
        CalculateObjTransmissionEffAutomaticallySwitch  matlab.ui.control.Switch
        CalculateObjTransmissionEffAutomaticallySwitchLabel  matlab.ui.control.Label
        ObjectiveSampleIndiceEditField  matlab.ui.control.NumericEditField
        ObjectiveSampleIndiceEditFieldLabel  matlab.ui.control.Label
        ObjectiveImmersionIndiceEditField  matlab.ui.control.NumericEditField
        ObjectiveImmersionIndiceEditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        ObjectiveandPSFLabel            matlab.ui.control.Label
        ObjectiveNumericalApertureEditField  matlab.ui.control.NumericEditField
        ObjectiveNumericalApertureEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        ObjectivePSFpar=[]; % Initialize the parameters
        simul_3D=[];
        raster=[];
        nz=[];
        two_channel=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize ObjectivePSFpar
            app.ObjectivePSFpar=mainapp.smis_gui_parameters.obj_and_psf;
            
            % Initialize 3D option
            app.simul_3D=mainapp.smis_gui_parameters.simul_3D;
            % Initialize raster size for 3D option
            app.raster=mainapp.smis_gui_parameters.raster;
            % Initialize raster size for 3D option
            app.nz=mainapp.smis_gui_parameters.image_size.nz;
            % Initialize two-channel state
            app.two_channel=mainapp.smis_gui_parameters.TwoChannel.state;
            
            % Update UI with input values
            app.ObjectiveNumericalApertureEditField.Value = app.ObjectivePSFpar.obj_na;
            app.ObjectiveImmersionIndiceEditField.Value = app.ObjectivePSFpar.obj_immersion_indice;
            app.ObjectiveSampleIndiceEditField.Value = app.ObjectivePSFpar.obj_immersion_sample;
            app.AddtionnalAttenuationEditField.Value = app.ObjectivePSFpar.obj_mic_transmission;
            app.MicroscopeTransmissionEfficiencyEditField.Value = app.ObjectivePSFpar.obj_transmission_eff;
            
            app.ObjectiveDepthoffocusnmEditField.Value = app.ObjectivePSFpar.obj_depth_of_focus;
            app.NumberofslicesofPSFinZdimensionEditField.Value = app.ObjectivePSFpar.psf_n_zslices;
            app.ShiftofsamplecenterrelativetofocalplanenmEditField.Value = app.ObjectivePSFpar.sample_zcenter;
            app.SampleheightatcoverslippositionnmTIRFEditField.Value = app.ObjectivePSFpar.sample_z_coverslip;
            
            if app.simul_3D==1
                app.ObjectiveDepthoffocusnmEditField.Visible='on';
                app.ObjectiveDepthoffocusnmEditFieldLabel.Visible='on';
                app.NumberofslicesofPSFinZdimensionEditField.Visible='on';
                app.NumberofslicesofPSFinZdimensionEditFieldLabel.Visible='on';
                app.ShiftofsamplecenterrelativetofocalplanenmEditField.Visible='on';
                app.ShiftofsamplecenterrelativetofocalplanenmEditFieldLabel.Visible='on';
                app.SampleheightatcoverslippositionnmTIRFEditField.Visible='on';
                app.SampleheightatcoverslippositionnmTIRFEditFieldLabel.Visible='on';
                
                if app.ObjectivePSFpar.psf_astigmatism_on==1
                    app.Astigmatismfor3DSMLMSwitch.Visible='on';
                    app.Astigmatismfor3DSMLMSwitchLabel.Visible='on';
                    app.AstigmatisminXdimensionEditField.Visible='on';
                    app.AstigmatisminXdimensionEditFieldLabel.Visible='on';
                    app.AstigmatisminYdimensionEditField.Visible='on';
                    app.AstigmatisminYdimensionEditFieldLabel.Visible='on';
                    app.X4thordercorrectionEditField.Visible='on';
                    app.X4thordercorrectionEditFieldLabel.Visible='on';
                    app.Y4thordercorrectionEditField.Visible='on';
                    app.Y4thordercorrectionEditFieldLabel.Visible='on';
                    app.Astigmatismfor3DSMLMSwitch.Value='On';
                    app.AstigmatisminXdimensionEditField.Value = app.ObjectivePSFpar.psf_astigmatism_x(1);
                    app.X4thordercorrectionEditField.Value = app.ObjectivePSFpar.psf_astigmatism_x(2);
                    app.AstigmatisminYdimensionEditField.Value = app.ObjectivePSFpar.psf_astigmatism_y(1);
                    app.Y4thordercorrectionEditField.Value = app.ObjectivePSFpar.psf_astigmatism_y(2);
                    
                    if app.two_channel==1
                        app.OnChannel1onlyCheckBox.Visible='on';
                        app.OnChannel1onlyCheckBox.Value = app.ObjectivePSFpar.psf_ch1_only;
                    else
                        app.OnChannel1onlyCheckBox.Visible='off';
                    end

                else
                    app.Astigmatismfor3DSMLMSwitch.Value='Off';
                    app.AstigmatisminXdimensionEditField.Visible='off';
                    app.AstigmatisminXdimensionEditFieldLabel.Visible='off';
                    app.AstigmatisminYdimensionEditField.Visible='off';
                    app.AstigmatisminYdimensionEditFieldLabel.Visible='off';
                    app.X4thordercorrectionEditField.Visible='off';
                    app.X4thordercorrectionEditFieldLabel.Visible='off';
                    app.Y4thordercorrectionEditField.Visible='off';
                    app.Y4thordercorrectionEditFieldLabel.Visible='off';

                    app.OnChannel1onlyCheckBox.Visible='off';
                end
            else
                app.ObjectiveDepthoffocusnmEditField.Visible='off';
                app.ObjectiveDepthoffocusnmEditFieldLabel.Visible='off';
                app.NumberofslicesofPSFinZdimensionEditField.Visible='off';
                app.NumberofslicesofPSFinZdimensionEditFieldLabel.Visible='off';
                app.ShiftofsamplecenterrelativetofocalplanenmEditField.Visible='off';
                app.ShiftofsamplecenterrelativetofocalplanenmEditFieldLabel.Visible='off';
                app.SampleheightatcoverslippositionnmTIRFEditField.Visible='off';
                app.SampleheightatcoverslippositionnmTIRFEditFieldLabel.Visible='off';
                app.PositionfocalplaneatcoverslipforTIRFmodeButton.Visible='off';
                app.Astigmatismfor3DSMLMSwitch.Visible='off';
                app.Astigmatismfor3DSMLMSwitchLabel.Visible='off';
                app.AstigmatisminXdimensionEditField.Visible='off';
                app.AstigmatisminXdimensionEditFieldLabel.Visible='off';
                app.AstigmatisminYdimensionEditField.Visible='off';
                app.AstigmatisminYdimensionEditFieldLabel.Visible='off';
                app.X4thordercorrectionEditField.Visible='off';
                app.X4thordercorrectionEditFieldLabel.Visible='off';
                app.Y4thordercorrectionEditField.Visible='off';
                app.Y4thordercorrectionEditFieldLabel.Visible='off';
                app.OnChannel1onlyCheckBox.Visible='off';
            end
            
            if app.ObjectivePSFpar.obj_eff_from_opening_angle==1
                app.CalculateObjTransmissionEffAutomaticallySwitch.Value = 'On';
                app.MicroscopeTransmissionEfficiencyEditField.Enable ='off';
                app.MicroscopeTransmissionEfficiencyEditFieldLabel.Enable ='off';
                app.AddtionnalAttenuationEditField.Enable ='on';
                app.AddtionnalAttenuationEditFieldLabel.Enable ='on';
            else
                app.CalculateObjTransmissionEffAutomaticallySwitch.Value = 'Off';
                app.MicroscopeTransmissionEfficiencyEditField.Enable='on';
                app.MicroscopeTransmissionEfficiencyEditFieldLabel.Enable ='on';
                app.AddtionnalAttenuationEditField.Enable ='off';
                app.AddtionnalAttenuationEditFieldLabel.Enable ='off';
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateObjectivePSF(app.CallingApp, app.ObjectivePSFpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: ObjectiveNumericalApertureEditField
        function ObjectiveNumericalApertureEditFieldValueChanged(app, event)
            value = app.ObjectiveNumericalApertureEditField.Value;
            app.ObjectivePSFpar.obj_na=value;
        end

        % Value changed function: ObjectiveImmersionIndiceEditField
        function ObjectiveImmersionIndiceEditFieldValueChanged(app, event)
            value = app.ObjectiveImmersionIndiceEditField.Value;
            app.ObjectivePSFpar.obj_immersion_indice=value;
        end

        % Value changed function: ObjectiveSampleIndiceEditField
        function ObjectiveSampleIndiceEditFieldValueChanged(app, event)
            value = app.ObjectiveSampleIndiceEditField.Value;
            app.ObjectivePSFpar.obj_immersion_sample=value;
        end

        % Value changed function: 
        % CalculateObjTransmissionEffAutomaticallySwitch
        function CalculateObjTransmissionEffAutomaticallySwitchValueChanged(app, event)
            value = app.CalculateObjTransmissionEffAutomaticallySwitch.Value;
            
            if strcmp(value,'On')
                app.MicroscopeTransmissionEfficiencyEditField.Enable ='off';
                app.MicroscopeTransmissionEfficiencyEditFieldLabel.Enable ='off';
                app.AddtionnalAttenuationEditField.Enable ='on';
                app.AddtionnalAttenuationEditFieldLabel.Enable ='on';
                app.ObjectivePSFpar.obj_eff_from_opening_angle=1;
            else
                app.MicroscopeTransmissionEfficiencyEditField.Enable='on';
                app.MicroscopeTransmissionEfficiencyEditFieldLabel.Enable ='on';
                app.AddtionnalAttenuationEditField.Enable ='off';
                app.AddtionnalAttenuationEditFieldLabel.Enable ='off';
                app.ObjectivePSFpar.obj_eff_from_opening_angle=0;
            end
        end

        % Value changed function: MicroscopeTransmissionEfficiencyEditField
        function MicroscopeTransmissionEfficiencyEditFieldValueChanged(app, event)
            value = app.MicroscopeTransmissionEfficiencyEditField.Value;
            app.ObjectivePSFpar.obj_transmission_eff=value;
        end

        % Value changed function: AddtionnalAttenuationEditField
        function AddtionnalAttenuationEditFieldValueChanged(app, event)
            value = app.AddtionnalAttenuationEditField.Value;
            app.ObjectivePSFpar.obj_mic_transmission=value;
        end

        % Value changed function: ObjectiveDepthoffocusnmEditField
        function ObjectiveDepthoffocusnmEditFieldValueChanged(app, event)
            value = app.ObjectiveDepthoffocusnmEditField.Value;
            app.ObjectivePSFpar.obj_depth_of_focus=value;
        end

        % Value changed function: NumberofslicesofPSFinZdimensionEditField
        function NumberofslicesofPSFinZdimensionEditFieldValueChanged(app, event)
            value = app.NumberofslicesofPSFinZdimensionEditField.Value;
            if (mod(app.nz,2)==0 && mod(value,2)==0) || (mod(app.nz,2)==1 && mod(value,2)==1)
                value=value+1;
                MyDlg=warndlg('Number of slices updated for compatibility with sample z-size','Warning');
                waitfor(MyDlg);
            end
            app.ObjectivePSFpar.psf_n_zslices=value;
            app.NumberofslicesofPSFinZdimensionEditField.Value=value;
        end

        % Value changed function: 
        % ShiftofsamplecenterrelativetofocalplanenmEditField
        function ShiftofsamplecenterrelativetofocalplanenmEditFieldValueChanged(app, event)
            value = app.ShiftofsamplecenterrelativetofocalplanenmEditField.Value;
            app.ObjectivePSFpar.sample_zcenter=value;
        end

        % Value changed function: AstigmatisminXdimensionEditField
        function AstigmatisminXdimensionEditFieldValueChanged(app, event)
            value = app.AstigmatisminXdimensionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_x(1)=value;
            app.ObjectivePSFpar.psf_astigmatism_x_save(1)=value;
        end

        % Callback function
        function X4thordercorrectionEditFieldValueChanged(app, event)
            value = app.X4thordercorrectionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_x(2)=value;
            app.ObjectivePSFpar.psf_astigmatism_x_save(2)=value;
        end

        % Value changed function: AstigmatisminYdimensionEditField
        function AstigmatisminYdimensionEditFieldValueChanged(app, event)
            value = app.AstigmatisminYdimensionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_y(1)=value;
            app.ObjectivePSFpar.psf_astigmatism_y_save(1)=value;
        end

        % Callback function
        function Y4thordercorrectionEditFieldValueChanged(app, event)
            value = app.Y4thordercorrectionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_y(2)=value;
            app.ObjectivePSFpar.psf_astigmatism_y_save(2)=value;
        end

        % Button pushed function: 
        % PositionfocalplaneatcoverslipforTIRFmodeButton
        function PositionfocalplaneatcoverslipforTIRFmodeButtonPushed(app, event)
            if isempty(app.nz)
                warndlg('3D virtual sample has not been loaded ! Sample cannot be positioned !','Warning');
            else
                image_height=app.raster*app.nz; % in [nm]
                app.ObjectivePSFpar.sample_zcenter=image_height/2-app.ObjectivePSFpar.sample_z_coverslip;
                app.ShiftofsamplecenterrelativetofocalplanenmEditField.Value=app.ObjectivePSFpar.sample_zcenter;
            end
        end

        % Value changed function: Astigmatismfor3DSMLMSwitch
        function Astigmatismfor3DSMLMSwitchValueChanged(app, event)
            value = app.Astigmatismfor3DSMLMSwitch.Value;
            if strcmp(value,'On')==1
                app.ObjectivePSFpar.psf_astigmatism_on=1;
                app.ObjectivePSFpar.psf_astigmatism_x=app.ObjectivePSFpar.psf_astigmatism_x_save;
                app.ObjectivePSFpar.psf_astigmatism_y=app.ObjectivePSFpar.psf_astigmatism_y_save;
                
                app.X4thordercorrectionEditField.Visible='on';
                app.X4thordercorrectionEditFieldLabel.Visible='on';
                app.Y4thordercorrectionEditField.Visible='on';
                app.Y4thordercorrectionEditFieldLabel.Visible='on';
                app.AstigmatisminXdimensionEditField.Visible='on';
                app.AstigmatisminXdimensionEditFieldLabel.Visible='on';
                app.AstigmatisminYdimensionEditField.Visible='on';
                app.AstigmatisminYdimensionEditFieldLabel.Visible='on';
                
                app.AstigmatisminXdimensionEditField.Value=app.ObjectivePSFpar.psf_astigmatism_x(1);
                app.X4thordercorrectionEditField.Value=app.ObjectivePSFpar.psf_astigmatism_x(2);
                app.AstigmatisminYdimensionEditField.Value=app.ObjectivePSFpar.psf_astigmatism_y(1);
                app.Y4thordercorrectionEditField.Value=app.ObjectivePSFpar.psf_astigmatism_y(2);
                
                if app.two_channel==1
                    app.OnChannel1onlyCheckBox.Visible='on';
                    app.OnChannel1onlyCheckBox.Value = app.ObjectivePSFpar.psf_ch1_only;
                else
                    app.OnChannel1onlyCheckBox.Visible='off';
                end
            else
                app.ObjectivePSFpar.psf_astigmatism_on=0;
                
                app.ObjectivePSFpar.psf_astigmatism_x=[0,0];
                app.ObjectivePSFpar.psf_astigmatism_y=[0,0];
                
                app.X4thordercorrectionEditField.Visible='off';
                app.X4thordercorrectionEditFieldLabel.Visible='off';
                app.Y4thordercorrectionEditField.Visible='off';
                app.Y4thordercorrectionEditFieldLabel.Visible='off';
                app.AstigmatisminXdimensionEditField.Visible='off';
                app.AstigmatisminXdimensionEditFieldLabel.Visible='off';
                app.AstigmatisminYdimensionEditField.Visible='off';
                app.AstigmatisminYdimensionEditFieldLabel.Visible='off';
                
                app.OnChannel1onlyCheckBox.Visible='off';

            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: X4thordercorrectionEditField
        function X4thordercorrectionEditFieldValueChanged2(app, event)
            value = app.X4thordercorrectionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_x(2)=value;
            app.ObjectivePSFpar.psf_astigmatism_x_save(2)=value;
        end

        % Value changed function: Y4thordercorrectionEditField
        function Y4thordercorrectionEditFieldValueChanged2(app, event)
            value = app.Y4thordercorrectionEditField.Value;
            app.ObjectivePSFpar.psf_astigmatism_y(2)=value;
            app.ObjectivePSFpar.psf_astigmatism_y_save(2)=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: OnChannel1onlyCheckBox
        function OnChannel1onlyCheckBoxValueChanged(app, event)
            value = app.OnChannel1onlyCheckBox.Value;
            app.ObjectivePSFpar.psf_ch1_only=value;
        end

        % Value changed function: 
        % SampleheightatcoverslippositionnmTIRFEditField
        function SampleheightatcoverslippositionnmTIRFEditFieldValueChanged(app, event)
            value = app.SampleheightatcoverslippositionnmTIRFEditField.Value;
            app.ObjectivePSFpar.sample_z_coverslip=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ObjectiveNumericalApertureEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveNumericalApertureEditFieldLabel.Text = 'Objective Numerical Aperture';
            app.ObjectiveNumericalApertureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveNumericalApertureEditField.Limits = [0 Inf];
            app.ObjectiveNumericalApertureEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveNumericalApertureEditFieldValueChanged, true);
            app.ObjectiveNumericalApertureEditField.Tooltip = {'Define here the numerical aperture of the objective'};
            app.ObjectiveNumericalApertureEditField.Value = 1.49;
            app.ObjectiveandPSFLabel = uilabel(app.UIFigure);
            app.ObjectiveandPSFLabel.Text = 'Objective and PSF';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main menu'};
            app.OKButton.Text = 'OK';
            app.ObjectiveImmersionIndiceEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveImmersionIndiceEditFieldLabel.Text = 'Objective Immersion Indice';
            app.ObjectiveImmersionIndiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveImmersionIndiceEditField.Limits = [0 Inf];
            app.ObjectiveImmersionIndiceEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveImmersionIndiceEditFieldValueChanged, true);
            app.ObjectiveImmersionIndiceEditField.Tooltip = {'Define here the refraction index of the immersion medium of the objective'};
            app.ObjectiveImmersionIndiceEditField.Value = 1.515;
            app.ObjectiveSampleIndiceEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveSampleIndiceEditFieldLabel.Text = 'Objective Sample Indice';
            app.ObjectiveSampleIndiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveSampleIndiceEditField.Limits = [0 Inf];
            app.ObjectiveSampleIndiceEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveSampleIndiceEditFieldValueChanged, true);
            app.ObjectiveSampleIndiceEditField.Tooltip = {'Define here the refraction index of the sample'};
            app.ObjectiveSampleIndiceEditField.Value = 1.33;
            app.CalculateObjTransmissionEffAutomaticallySwitchLabel = uilabel(app.UIFigure);
            app.CalculateObjTransmissionEffAutomaticallySwitchLabel.Text = 'Calculate Obj Transmission Eff Automatically';
            app.CalculateObjTransmissionEffAutomaticallySwitch = uiswitch(app.UIFigure, 'slider');
            app.CalculateObjTransmissionEffAutomaticallySwitch.ValueChangedFcn = createCallbackFcn(app, @CalculateObjTransmissionEffAutomaticallySwitchValueChanged, true);
            app.CalculateObjTransmissionEffAutomaticallySwitch.Tooltip = {'Set this switch to On to automatically calculate the transmission efficiency of the objective based on the formula of Fourkas, 2001, Opt.Letters. If the switch is set to Off enter the overall transmission coefficient manually.'};
            app.CalculateObjTransmissionEffAutomaticallySwitch.Value = 'On';
            app.AddtionnalAttenuationEditFieldLabel = uilabel(app.UIFigure);
            app.AddtionnalAttenuationEditFieldLabel.Text = 'Addtionnal Attenuation';
            app.AddtionnalAttenuationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddtionnalAttenuationEditField.Limits = [0 1];
            app.AddtionnalAttenuationEditField.ValueChangedFcn = createCallbackFcn(app, @AddtionnalAttenuationEditFieldValueChanged, true);
            app.AddtionnalAttenuationEditField.Tooltip = {'Additional attenuation that takes into account the additional losses due to filters, etc. The total microscope transmission efficiency will be the automatically calculated objerctive transmission efficiency multiplied by the additional attenuation'};
            app.AddtionnalAttenuationEditField.Value = 0.8;
            app.MicroscopeTransmissionEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.MicroscopeTransmissionEfficiencyEditFieldLabel.Enable = 'off';
            app.MicroscopeTransmissionEfficiencyEditFieldLabel.Text = 'Microscope Transmission Efficiency ';
            app.MicroscopeTransmissionEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MicroscopeTransmissionEfficiencyEditField.Limits = [0 1];
            app.MicroscopeTransmissionEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @MicroscopeTransmissionEfficiencyEditFieldValueChanged, true);
            app.MicroscopeTransmissionEfficiencyEditField.Enable = 'off';
            app.MicroscopeTransmissionEfficiencyEditField.Tooltip = {'Overall transmission efficiency of the microscope'};
            app.MicroscopeTransmissionEfficiencyEditField.Value = 0.2;
            app.Astigmatismfor3DSMLMSwitchLabel = uilabel(app.UIFigure);
            app.Astigmatismfor3DSMLMSwitchLabel.Text = 'Astigmatism for 3D SMLM';
            app.Astigmatismfor3DSMLMSwitch = uiswitch(app.UIFigure, 'slider');
            app.Astigmatismfor3DSMLMSwitch.ValueChangedFcn = createCallbackFcn(app, @Astigmatismfor3DSMLMSwitchValueChanged, true);
            app.Astigmatismfor3DSMLMSwitch.Tooltip = {'Use this switch to perform 3D imaging via astigmatism'};
            app.AstigmatisminXdimensionEditFieldLabel = uilabel(app.UIFigure);
            app.AstigmatisminXdimensionEditFieldLabel.Tooltip = {'Define the extent of astigmatism in the X dimension'};
            app.AstigmatisminXdimensionEditFieldLabel.Text = 'Astigmatism in X-dimension';
            app.AstigmatisminXdimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AstigmatisminXdimensionEditField.ValueChangedFcn = createCallbackFcn(app, @AstigmatisminXdimensionEditFieldValueChanged, true);
            app.AstigmatisminXdimensionEditField.Tooltip = {''};
            app.AstigmatisminYdimensionEditFieldLabel = uilabel(app.UIFigure);
            app.AstigmatisminYdimensionEditFieldLabel.Tooltip = {'Define the extent of astigmatism in the Y dimension'};
            app.AstigmatisminYdimensionEditFieldLabel.Text = 'Astigmatism in Y-dimension';
            app.AstigmatisminYdimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AstigmatisminYdimensionEditField.ValueChangedFcn = createCallbackFcn(app, @AstigmatisminYdimensionEditFieldValueChanged, true);
            app.AstigmatisminYdimensionEditField.Tooltip = {''};
            app.ObjectiveDepthoffocusnmEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveDepthoffocusnmEditFieldLabel.Text = 'Objective Depth of focus [nm]';
            app.ObjectiveDepthoffocusnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveDepthoffocusnmEditField.Limits = [1 Inf];
            app.ObjectiveDepthoffocusnmEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDepthoffocusnmEditFieldValueChanged, true);
            app.ObjectiveDepthoffocusnmEditField.Tooltip = {'Define here the objective depth of focus'};
            app.ObjectiveDepthoffocusnmEditField.Value = 500;
            app.ShiftofsamplecenterrelativetofocalplanenmEditFieldLabel = uilabel(app.UIFigure);
            app.ShiftofsamplecenterrelativetofocalplanenmEditFieldLabel.Text = 'Shift of sample center relative to focal plane [nm]';
            app.ShiftofsamplecenterrelativetofocalplanenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShiftofsamplecenterrelativetofocalplanenmEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftofsamplecenterrelativetofocalplanenmEditFieldValueChanged, true);
            app.ShiftofsamplecenterrelativetofocalplanenmEditField.Tooltip = {'Positioning of the sample relative to the focal plane of the objective. If set to zero, the center of the sample in the Z dimension is put at the focal plane.'};
            app.PositionfocalplaneatcoverslipforTIRFmodeButton = uibutton(app.UIFigure, 'push');
            app.PositionfocalplaneatcoverslipforTIRFmodeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionfocalplaneatcoverslipforTIRFmodeButtonPushed, true);
            app.PositionfocalplaneatcoverslipforTIRFmodeButton.Tooltip = {'Click here to set the focal plane at the bottom of the sample so that its bottom surface is in focus, which is what is wanted for TIRF imaging notably.'};
            app.PositionfocalplaneatcoverslipforTIRFmodeButton.Text = 'Position focal plane at coverslip (for TIRF mode)';
            app.NumberofslicesofPSFinZdimensionEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofslicesofPSFinZdimensionEditFieldLabel.Text = 'Number of slices of PSF in Z-dimension';
            app.NumberofslicesofPSFinZdimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofslicesofPSFinZdimensionEditField.Limits = [4 256];
            app.NumberofslicesofPSFinZdimensionEditField.ValueDisplayFormat = '%11d';
            app.NumberofslicesofPSFinZdimensionEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofslicesofPSFinZdimensionEditFieldValueChanged, true);
            app.NumberofslicesofPSFinZdimensionEditField.Tooltip = {'The number of slices of the PSF defines the resolution of the point spread function along the optical axis. The higher the number, the more accurate but the more computing time. The number of slices should be odd if z sample size is even an vice-versa.'};
            app.NumberofslicesofPSFinZdimensionEditField.Value = 32;
            app.X4thordercorrectionEditFieldLabel = uilabel(app.UIFigure);
            app.X4thordercorrectionEditFieldLabel.Tooltip = {'Define the fourth order correction for the astigmatism in the X dimension'};
            app.X4thordercorrectionEditFieldLabel.Text = 'X 4th order correction';
            app.X4thordercorrectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.X4thordercorrectionEditField.ValueChangedFcn = createCallbackFcn(app, @X4thordercorrectionEditFieldValueChanged2, true);
            app.X4thordercorrectionEditField.Tooltip = {''};
            app.Y4thordercorrectionEditFieldLabel = uilabel(app.UIFigure);
            app.Y4thordercorrectionEditFieldLabel.Tooltip = {'Define the fourth order correction for the astigmatism in the Y dimension'};
            app.Y4thordercorrectionEditFieldLabel.Text = 'Y 4th order correction';
            app.Y4thordercorrectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y4thordercorrectionEditField.ValueChangedFcn = createCallbackFcn(app, @Y4thordercorrectionEditFieldValueChanged2, true);
            app.Y4thordercorrectionEditField.Tooltip = {''};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main menu'};
            app.CancelButton.Text = 'Cancel';
            app.OnChannel1onlyCheckBox = uicheckbox(app.UIFigure);
            app.OnChannel1onlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnChannel1onlyCheckBoxValueChanged, true);
            app.OnChannel1onlyCheckBox.Tooltip = {'Tick this box if astigmatism is to be applied only on Channel 1'};
            app.OnChannel1onlyCheckBox.Text = 'On Channel 1 only';
            app.SampleheightatcoverslippositionnmTIRFEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleheightatcoverslippositionnmTIRFEditField.Limits = [0 Inf];
            app.SampleheightatcoverslippositionnmTIRFEditField.ValueChangedFcn = createCallbackFcn(app, @SampleheightatcoverslippositionnmTIRFEditFieldValueChanged, true);
            app.SampleheightatcoverslippositionnmTIRFEditField.Tooltip = {'Position of the sample considered to be at coverslip for maximum excitation in TIRF mode. If set to 0, coverslip position is assumed to be at the z=0 position of the virtural sample, which is a 3D box. The sample is generally inside this 3D box with its lower layer at z>0.'};
            app.SampleheightatcoverslippositionnmTIRFEditFieldLabel = uilabel(app.UIFigure);
            app.SampleheightatcoverslippositionnmTIRFEditFieldLabel.Text = 'Sample height at coverslip position [nm] (TIRF)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectivePSF(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end