classdef OverlayRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ContrastSlider                  matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ChoosetrackButton               matlab.ui.control.Button
        ColormodeSwitch                 matlab.ui.control.Switch
        ColormodeSwitchLabel            matlab.ui.control.Label
        OverlaymodeSwitch               matlab.ui.control.Switch
        OverlaymodeSwitchLabel          matlab.ui.control.Label
        AreaborderpixelsEditField       matlab.ui.control.NumericEditField
        AreaborderpixelsEditFieldLabel  matlab.ui.control.Label
        FractionoftrackstoplotSlider    matlab.ui.control.Slider
        FractionoftrackstoplotLabel     matlab.ui.control.Label
        MaxTrackLengthFramesEditField   matlab.ui.control.NumericEditField
        MaxTrackLengthFramesEditFieldLabel  matlab.ui.control.Label
        MinTrackLengthFramesEditField   matlab.ui.control.NumericEditField
        MinTrackLengthFramesEditFieldLabel  matlab.ui.control.Label
        StackfilenameEditField          matlab.ui.control.EditField
        StackfilenameEditFieldLabel     matlab.ui.control.Label
        LoaddatasetButton               matlab.ui.control.Button
        FrametoshowSpinner              matlab.ui.control.Spinner
        FrametoshowSpinnerLabel         matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        OverlayTracksonRawDataLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        im_par=[]; % Imaging parameters
    end
    
    properties (Access = public)
        par=[]; % Parameters
    end
    
    methods (Access = private)
        
        function PlotTracks(app)
            
            figure(app.par.Fig1);
            
            hold on;
            axis image
            cla
            
            track_range=[app.MinTrackLengthFramesEditField.Value,app.MaxTrackLengthFramesEditField.Value] ; %track range to analyse
            
            plot_tracks=app.FractionoftrackstoplotSlider.Value; % plot X% of the selected tracks
            
            app.par.GT_tracks=app.par.selected_tracks;
            
            GT_tl=[app.par.GT_tracks.tracklength];
            app.par.GT_tracks=app.par.GT_tracks(GT_tl >= track_range(1) & GT_tl <= track_range(2));
            
            GT_ntracks_selected=length(app.par.GT_tracks);
            
            selected=zeros(1,GT_ntracks_selected);
            
            inc=round(100/plot_tracks);
            start_k=ceil(rand*inc);
            
            for k=start_k:inc:GT_ntracks_selected
                
                clc; disp(['Tracks [%] : ',num2str(100*k/GT_ntracks_selected)]);
                
                GT_t=app.par.GT_tracks(k); % GT tracks to be analyzed
                
                ColorVector = rand(1,3);
                plot([GT_t.x], [GT_t.y], '--+', 'Color', ColorVector, 'LineWidth',2);
                selected(k)=1;
            end
            
            app.par.GT_tracks(~selected)=[];
            
            if ~isempty(app.par.GT_tracks)
                %Converting tracks to localizations (Prepare for OverlayTrack)
                raster=app.im_par.raster; % [nm]
                app.par.GT_locs=GT_tracks_to_localizations(app.par.GT_tracks,raster);
            else
                clf(app.par.Fig1);
                app.par.MyDlg=warndlg('No tracks selected ! Increased fraction of selected tracks !','Warning');
                waitfor(app.par.MyDlg);
            end
            
            hold off
            
        end
        
        function OverlayTrack(app)
            
            if ~isempty(app.par.GT_tracks)
                
                raster=app.im_par.raster; % [nm]
                
                figure(app.par.Fig2);
                colormap('gray');
                
                
                app.par.ColorVector= rand(100,3);
                
                X_all=[app.par.GT_locs.x]/raster; %
                Y_all=[app.par.GT_locs.y]/raster;


                disp('Select spot on track image ...');
                MyDlg=msgbox('Select spot on track image ...','');
                figure(app.par.Fig1)
                [x,y] = ginput(1);
                delete(MyDlg)
                
                %select closest track
                d2=(x-X_all).^2+(y-Y_all).^2;
                k=find(d2==min(d2),1);
                GT_t=app.par.GT_tracks(app.par.GT_locs(k).tn); % GT tracks to be analyzed
                
                app.par.GT_x=[GT_t.x];
                app.par.GT_y=[GT_t.y];
                app.par.GT_f=[GT_t.frames];
                
                disp(['Track frame range: from: ', num2str(min(app.par.GT_f)), ' to: ', num2str(max(app.par.GT_f))]);


                %Show the frame
                app.FrametoshowSpinner.Value=app.par.GT_f(1);
                
                PlotFrame(app)
                
            else
                MyDlg=warndlg('No tracks selected ! Increased fraction of selected tracks !','Warning');
                waitfor(MyDlg);
            end
            
        end
        
        function PlotFrame(app)
            
            if ~isempty(app.par.GT_tracks)
                
                area_size=app.AreaborderpixelsEditField.Value*2; % area size for display
                s=size(app.par.stack);
                app.par.min_x=max(1,fix(min(app.par.GT_x)-area_size/2)); app.par.min_y=max(1,fix(min(app.par.GT_y)-area_size/2));
                app.par.max_x=min(s(2),ceil(max(app.par.GT_x)+area_size/2)); app.par.max_y=min(s(1),ceil(max(app.par.GT_y)+area_size/2));
                
                figure(app.par.Fig2)
                
                if strcmp(app.ColormodeSwitch.Value,'Gradient')==true
                    color_track_mode=0; % 0: Color gradient per track
                else
                    color_track_mode=1; % 1: One color per track
                end
                
                if strcmp(app.OverlaymodeSwitch.Value,'Full')==true
                    overlay_full_track=1; % 1: Full track overlaid on image
                else
                    overlay_full_track=0; % 0: Only track until current frame is shown
                end
                
                contrast_factor=max([app.ContrastSlider.Value/100,0.1]); % to modulate contrast
                
                frame_to_show=app.FrametoshowSpinner.Value;
                
                area=app.par.stack(app.par.min_y:app.par.max_y,app.par.min_x:app.par.max_x, frame_to_show);
                
                %area_r=imrotate(area,90); % to match track display on figure 1
                area_r=flip(area);
                
                clims=2*contrast_factor*[0.5*min(area_r(:)) 1.5*max(area_r(:))];
                
                imagesc(area_r,clims); axis image;
                
                %show the track(s)
                hold on
                
                if overlay_full_track==1
                    X=app.par.GT_x-app.par.min_x+1; Y=app.par.GT_y-app.par.min_y+1;
                else
                    X=app.par.GT_x(app.par.GT_f<=frame_to_show)-app.par.min_x+1; Y=app.par.GT_y(app.par.GT_f<=frame_to_show)-app.par.min_y+1;
                end
                
                % Xr=Y; Yr=size(area,2)+1-X; %rotate coordinates to match figure 1
                Yr=size(area,1)+1-Y; Xr=X; %flip coordinates to match figure 1
                
                if color_track_mode==1
                    ColorVector = app.par.ColorVector(end,:);
                    plot(Xr, Yr, '--+', 'Color', ColorVector, 'LineWidth',2);
                elseif color_track_mode==0 %  color gradient per track
                    ColorVector = ColorCoding(length(X));
                    for n = 1 : length(X)-1
                        plot([Xr(n),Xr(n+1)], [Yr(n),Yr(n+1)], '--+', 'Color', ColorVector(n, :), 'LineWidth',2);
                    end
                    plot(Xr(end), Yr(end), '+', 'Color', ColorVector(end, :), 'LineWidth',2);
                end
                
                hold off
                
                xlabel('X [Pixels]')
                ylabel('Y [Pixels]')
                set(gca,'FontSize',14);
                title(['Frame number: ',num2str(frame_to_show)]);
                
                clc % Clear screen
                
            else
                MyDlg=warndlg('No tracks selected ! Increased fraction of selected tracks !','Warning');
                waitfor(MyDlg);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.par=mainapp.par;
            app.im_par=mainapp.im_par;
            
            if app.par.stack_loaded==1
                app.StackfilenameEditField.Value=app.par.stack_filename;
                app.ChoosetrackButton.Enable='on';
            else
                app.par.stack_filename=[];
                app.par.stack=[];
                app.ChoosetrackButton.Enable='off';
            end
            
            %Make sure at least 1 track is selected
            if numel(app.par.selected_tracks)>0
                if numel(app.par.selected_tracks)*0.01*app.FractionoftrackstoplotSlider.Value<1
                    app.FractionoftrackstoplotSlider.Value=100*max([0.01*app.FractionoftrackstoplotSlider.Value,1/numel(app.par.selected_tracks)]);
                    disp(['Increasing fraction of plotted tracks to ',app.FractionoftrackstoplotSlider.Value,' %'])
                end
            end
            
            if app.im_par.n_images>1
                app.FrametoshowSpinner.Limits=[1,app.im_par.n_images];
            else
                app.FrametoshowSpinner.Visible='off';
                app.FrametoshowSpinnerLabel.Visible='off';
            end
            
            app.par.Fig1=figure;
            set(gcf, 'WindowStyle', 'docked')
            clf;
            set(gcf, 'color', [1 1 1]);
            set(gcf, 'name', 'Tracks');
            
            app.par.Fig2=figure;
            set(gcf, 'WindowStyle', 'docked')
            clf;
            set(gcf, 'color', [1 1 1]);
            set(gcf, 'name', 'Overlay');
            
            PlotTracks(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Close the figures
            if ishandle(app.par.Fig1)
                close(app.par.Fig1);
            end
            if ishandle(app.par.Fig2)
                close(app.par.Fig2);
            end
            
            % Call main app's public function
            stackpar.stack_loaded=app.par.stack_loaded;
            stackpar.stack_filename=app.par.stack_filename;
            stackpar.stack=app.par.stack;
            UpdateStack(app.CallingApp, stackpar)
            % Delete the dialog box
            if isfield(app.par,'MyDlg')
                delete(app.par.MyDlg);
            end
            delete(app)
        end

        % Value changed function: FrametoshowSpinner
        function FrametoshowSpinnerValueChanged(app, event)
            PlotFrame(app)
        end

        % Button pushed function: LoaddatasetButton
        function LoaddatasetButtonPushed(app, event)
            cd(app.par.datadir)
            
            [file,path] = uigetfile('*.tif','Select .tif stack');
            if isequal(file,0)
                disp('User selected Cancel');
                figure(app.UIFigure); % Get the GUI in the front
            else
                app.par.datadir=path;
                app.par.datafile=file;
                [app.par.stack, app.par.n_frames]=read_SMIS_MultipageTiff(fullfile(path,file));
                
                app.StackfilenameEditField.Value=file;
                
                app.par.stack_loaded=1;
                app.par.stack_filename=file;
                app.ChoosetrackButton.Enable='on';
            end
            
            OverlayTrack(app)
        end

        % Button pushed function: ChoosetrackButton
        function ChoosetrackButtonPushed(app, event)
            OverlayTrack(app)
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            PlotFrame(app)
        end

        % Value changed function: OverlaymodeSwitch
        function OverlaymodeSwitchValueChanged(app, event)
            PlotFrame(app)
        end

        % Value changed function: ColormodeSwitch
        function ColormodeSwitchValueChanged(app, event)
            PlotFrame(app)
        end

        % Value changed function: FractionoftrackstoplotSlider
        function FractionoftrackstoplotSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: AreaborderpixelsEditField
        function AreaborderpixelsEditFieldValueChanged(app, event)
            PlotFrame(app)
        end

        % Value changed function: MinTrackLengthFramesEditField
        function MinTrackLengthFramesEditFieldValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: MaxTrackLengthFramesEditField
        function MaxTrackLengthFramesEditFieldValueChanged(app, event)
            PlotTracks(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OverlayTracksonRawDataLabel = uilabel(app.UIFigure);
            app.OverlayTracksonRawDataLabel.Text = 'Overlay Tracks on Raw Data';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Returns to the main menu'};
            app.OKButton.Text = 'OK';
            app.FrametoshowSpinnerLabel = uilabel(app.UIFigure);
            app.FrametoshowSpinnerLabel.Text = 'Frame to show';
            app.FrametoshowSpinner = uispinner(app.UIFigure);
            app.FrametoshowSpinner.Limits = [1 Inf];
            app.FrametoshowSpinner.RoundFractionalValues = 'on';
            app.FrametoshowSpinner.ValueDisplayFormat = '%.0f';
            app.FrametoshowSpinner.ValueChangedFcn = createCallbackFcn(app, @FrametoshowSpinnerValueChanged, true);
            app.FrametoshowSpinner.Tooltip = {'Scroll between frames to look at matching between fluorescent spots and ground truth localization'};
            app.FrametoshowSpinner.Value = 1;
            app.LoaddatasetButton = uibutton(app.UIFigure, 'push');
            app.LoaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatasetButtonPushed, true);
            app.LoaddatasetButton.Tooltip = {'Load a SMIS generated .tif SPT data stack'};
            app.LoaddatasetButton.Text = 'Load data set';
            app.StackfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.StackfilenameEditFieldLabel.Text = 'Stack filename';
            app.StackfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.StackfilenameEditField.Editable = 'off';
            app.StackfilenameEditField.Tooltip = {'Currently loaded stack'};
            app.MinTrackLengthFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MinTrackLengthFramesEditFieldLabel.Text = 'Min Track Length [Frames]';
            app.MinTrackLengthFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinTrackLengthFramesEditField.Limits = [1 Inf];
            app.MinTrackLengthFramesEditField.ValueDisplayFormat = '%.0f';
            app.MinTrackLengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthFramesEditFieldValueChanged, true);
            app.MinTrackLengthFramesEditField.Tooltip = {'Choose the minimum track length for track selection'};
            app.MinTrackLengthFramesEditField.Value = 1;
            app.MaxTrackLengthFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxTrackLengthFramesEditFieldLabel.Text = 'Max Track Length [Frames]';
            app.MaxTrackLengthFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxTrackLengthFramesEditField.Limits = [2 Inf];
            app.MaxTrackLengthFramesEditField.ValueDisplayFormat = '%.0f';
            app.MaxTrackLengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrackLengthFramesEditFieldValueChanged, true);
            app.MaxTrackLengthFramesEditField.Tooltip = {'Choose the maximum track length for track selection'};
            app.MaxTrackLengthFramesEditField.Value = Inf;
            app.FractionoftrackstoplotLabel = uilabel(app.UIFigure);
            app.FractionoftrackstoplotLabel.Text = 'Fraction of tracks to plot [%]';
            app.FractionoftrackstoplotSlider = uislider(app.UIFigure);
            app.FractionoftrackstoplotSlider.ValueChangedFcn = createCallbackFcn(app, @FractionoftrackstoplotSliderValueChanged, true);
            app.FractionoftrackstoplotSlider.Tooltip = {'Use this to adjust the number of tracks displayed on the Matlab figure'};
            app.FractionoftrackstoplotSlider.Value = 10;
            app.AreaborderpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.AreaborderpixelsEditFieldLabel.Text = 'Area border [pixels]';
            app.AreaborderpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaborderpixelsEditField.Limits = [1 Inf];
            app.AreaborderpixelsEditField.ValueDisplayFormat = '%.0f';
            app.AreaborderpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @AreaborderpixelsEditFieldValueChanged, true);
            app.AreaborderpixelsEditField.Tooltip = {'Adjust the size of the displayed area of current frame'};
            app.AreaborderpixelsEditField.Value = 10;
            app.OverlaymodeSwitchLabel = uilabel(app.UIFigure);
            app.OverlaymodeSwitchLabel.Text = 'Overlay mode';
            app.OverlaymodeSwitch = uiswitch(app.UIFigure, 'slider');
            app.OverlaymodeSwitch.Items = {'Full', 'Until current frame'};
            app.OverlaymodeSwitch.ValueChangedFcn = createCallbackFcn(app, @OverlaymodeSwitchValueChanged, true);
            app.OverlaymodeSwitch.Tooltip = {'Display either the full current track or only part of the track up to the currently displayed frame'};
            app.OverlaymodeSwitch.Value = 'Full';
            app.ColormodeSwitchLabel = uilabel(app.UIFigure);
            app.ColormodeSwitchLabel.Text = 'Color mode';
            app.ColormodeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ColormodeSwitch.Items = {'Single color', 'Gradient'};
            app.ColormodeSwitch.ValueChangedFcn = createCallbackFcn(app, @ColormodeSwitchValueChanged, true);
            app.ColormodeSwitch.Tooltip = {'Display the current track either with a single color or with a color gradient from start to end of the track'};
            app.ColormodeSwitch.Value = 'Single color';
            app.ChoosetrackButton = uibutton(app.UIFigure, 'push');
            app.ChoosetrackButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetrackButtonPushed, true);
            app.ChoosetrackButton.Tooltip = {'Choose a new track to inspect'};
            app.ChoosetrackButton.Text = 'Choose track';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Tooltip = {'Adjust the contrast of the fluorescence image'};
            app.ContrastSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OverlayRawData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end