classdef QYSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        ToLabel                         matlab.ui.control.Label
        FromLabel                       matlab.ui.control.Label
        ShowabsexcspectraDropDown       matlab.ui.control.DropDown
        ShowabsexcspectraDropDownLabel  matlab.ui.control.Label
        QTable                          matlab.ui.control.Table
        OKButton                        matlab.ui.control.Button
        PhototransformationsquantumyieldssetupLabel  matlab.ui.control.Label
        UIAxesSpectrum                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        QYpar=[]; % Initialize the parameters
        n_states=[]; % Initialize variable
        photoactive_states=[]; % Initialize variable
        Fluorophorepar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Fluorophorepar, App_Position)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.Fluorophorepar=Fluorophorepar;
            app.n_states=Fluorophorepar.Photophysics.n_states; % Number of photophysical states
            app.photoactive_states=Fluorophorepar.Photophysics.photoactive_states; % Ids of photoactive states
            n_photoactive_states=numel(Fluorophorepar.Photophysics.photoactive_states); % Number of photoactive states
            
            % Load name of fluorophore
            app.PhototransformationsquantumyieldssetupLabel.Text=['Phototransformation''s quantum yields set up: ',Fluorophorepar.name];
            
            % Load phototransformation quantum yield parameters
            app.QYpar=Fluorophorepar.Photophysics.trans_q;
            
            app.ShowabsexcspectraDropDown.Items(1)={'Choose'};
            if n_photoactive_states>=1
                app.ShowabsexcspectraDropDown.Items(2:1+n_photoactive_states)=Fluorophorepar.Photophysics.state_names(Fluorophorepar.Photophysics.photoactive_states);
            end
            
            % Initialize QYpar
            if ~isempty(app.QYpar)
                % Setup Table Position size
                QtableWidth=min([100+100*app.n_states,app.UIFigure.Position(3)-100]);
                QtableHeight=min([30+20*n_photoactive_states,app.UIFigure.Position(4)-350]);
                QtableBottomPos=max([480-20*n_photoactive_states,240]);
                QtableLeftPos=70;

                app.QTable.Position=[QtableLeftPos,QtableBottomPos,QtableWidth,QtableHeight]; % Adjust position

                %Center position of the From and To labels
                app.ToLabel.Position(1)=app.QTable.Position(1)+app.QTable.Position(3)/2;
                app.FromLabel.Position(2)=app.QTable.Position(2)+app.QTable.Position(4)/2;
                % Show the names of the fluorescent states
                app.QTable.ColumnName=Fluorophorepar.Photophysics.state_names;
                app.QTable.RowName=app.QTable.ColumnName(app.photoactive_states);
                % Fill the data
                app.QTable.Data=app.QYpar(app.photoactive_states,:);
                MyColumnFormat=cell(1,numel(app.QTable.ColumnName));
                [MyColumnFormat{:}]=deal('shortG');
                app.QTable.ColumnFormat = MyColumnFormat;
            end
            
            % Eventually resize the app according to previous user action
            if ~isempty(App_Position) && strcmp(app.Fluorophorepar.name, App_Position.Name)==1
                app.UIFigure.Position = App_Position.UIFigure;
                app.QTable.Position = App_Position.QTable;
                app.FromLabel.Position=App_Position.FromLabel;
                app.ToLabel.Position=App_Position.ToLabel;
                %Center position of the From and To labels
                app.ToLabel.Position(1)=app.QTable.Position(1)+app.QTable.Position(3)/2;
                app.FromLabel.Position(2)=app.QTable.Position(2)+app.QTable.Position(4)/2;
                app.PhototransformationsquantumyieldssetupLabel.Position=App_Position.PhototransformationsquantumyieldssetupLabel;
            end
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            App_Position.Name=app.Fluorophorepar.name;
            App_Position.UIFigure=app.UIFigure.Position;
            App_Position.QTable=app.QTable.Position;
            App_Position.FromLabel=app.FromLabel.Position;
            App_Position.ToLabel=app.ToLabel.Position;
            App_Position.PhototransformationsquantumyieldssetupLabel=app.PhototransformationsquantumyieldssetupLabel.Position;
            % Call main app's public function
            
            updateQY(app.CallingApp, app.QYpar, App_Position)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: ShowabsexcspectraDropDown
        function ShowabsexcspectraDropDownValueChanged(app, event)
            value = app.ShowabsexcspectraDropDown.Value;
            w=find(strcmp(app.Fluorophorepar.Photophysics.state_names(app.Fluorophorepar.Photophysics.photoactive_states),value)==1,1);
            state_id=app.Fluorophorepar.Photophysics.photoactive_states(w);
            if ismember(state_id,app.Fluorophorepar.Photophysics.fluorescent_states) %Fluorescent state was selected
                app.UIAxesSpectrum.Title.String='Excitation spectrum';
                spectrum=app.Fluorophorepar.Photophysics.exc_spectra(state_id==app.Fluorophorepar.Photophysics.fluorescent_states);
                X=spectrum.s(:,1);
                w_max=find(abs(X-spectrum.l)==min(abs(X-spectrum.l)),1);
                Y=spectrum.s(:,2)*spectrum.eps/spectrum.s(w_max,2);
                plot(app.UIAxesSpectrum,X,Y);
            elseif ismember(state_id,setdiff(app.Fluorophorepar.Photophysics.photoactive_states,app.Fluorophorepar.Photophysics.fluorescent_states)) %Photoactive dark state was selected
                app.UIAxesSpectrum.Title.String='Excitation spectrum';
                spectrum=app.Fluorophorepar.Photophysics.dark_spectra(state_id==setdiff(app.Fluorophorepar.Photophysics.photoactive_states,app.Fluorophorepar.Photophysics.fluorescent_states));
                X=spectrum.s(:,1);
                w_max=find(abs(X-spectrum.l)==min(abs(X-spectrum.l)),1);
                Y=spectrum.s(:,2)*spectrum.eps/spectrum.s(w_max,2);
                plot(app.UIAxesSpectrum,X,Y);
            end
        end

        % Cell edit callback: QTable
        function QTableCellEdit(app, event)
            newData = event.NewData;
            
            starting_state = app.Fluorophorepar.Photophysics.photoactive_states(event.Indices(1));
            ending_state = event.Indices(2);
            
            valid_phototransformation=1; % Initialize
            %Issue warnings when transformation is not possible
            if newData>1
                warndlg('Quantum yields must be < 1 !','Warning');
                valid_phototransformation=0;
            end
            
            if newData<0
                warndlg('Quantum yields must be > 0 !','Warning');
                valid_phototransformation=0;
            end
            
            if starting_state>=app.Fluorophorepar.Photophysics.converted_state && ending_state<app.Fluorophorepar.Photophysics.converted_state
                warndlg('Transformation from photoconverted state back to non-photoconverted state is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if app.Fluorophorepar.Photophysics.state_ids(starting_state)==app.Fluorophorepar.Photophysics.state_ids(ending_state)
                warndlg('Transformation between identical or rapidly exchanging states is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if valid_phototransformation==1
                app.QYpar(app.photoactive_states,:) = app.QTable.DisplayData;
            else
                app.QTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)    
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesSpectrum = uiaxes(app.UIFigure);
            title(app.UIAxesSpectrum, 'Absorption Spectrum')
            xlabel(app.UIAxesSpectrum, 'Wavelength [nm]')
            ylabel(app.UIAxesSpectrum, 'Extinction Coefficient [M-1cm-1]')
            app.PhototransformationsquantumyieldssetupLabel = uilabel(app.UIFigure);
            app.PhototransformationsquantumyieldssetupLabel.Text = 'Phototransformation''s quantum yields set up:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QTable = uitable(app.UIFigure);
            app.QTable.ColumnName = {'Fluo State Name'};
            app.QTable.RowName = {'State name'};
            app.QTable.ColumnEditable = true;
            app.QTable.CellEditCallback = createCallbackFcn(app, @QTableCellEdit, true);
            app.ShowabsexcspectraDropDownLabel = uilabel(app.UIFigure);
            app.ShowabsexcspectraDropDownLabel.Text = 'Show abs/exc spectra';
            app.ShowabsexcspectraDropDown = uidropdown(app.UIFigure);
            app.ShowabsexcspectraDropDown.Items = {'State 1', 'State 2', ''};
            app.ShowabsexcspectraDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowabsexcspectraDropDownValueChanged, true);
            app.ShowabsexcspectraDropDown.Value = 'State 1';
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QYSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end