classdef QYSetupSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CancelButton  matlab.ui.control.Button
        ToLabel       matlab.ui.control.Label
        FromLabel     matlab.ui.control.Label
        QTable        matlab.ui.control.Table
        OKButton      matlab.ui.control.Button
        PhototransformationsquantumyieldssetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        QYpar=[]; % Initialize the parameters
        n_states=[]; % Initialize variable
        photoactive_states=[]; % Initialize variable
        Fluorophorepar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Fluorophorepar)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.Fluorophorepar=Fluorophorepar;
            app.n_states=Fluorophorepar.Photophysics.n_states; % Number of photophysical states
            app.photoactive_states=Fluorophorepar.Photophysics.photoactive_states; % Ids of photoactive states
            n_photoactive_states=numel(Fluorophorepar.Photophysics.photoactive_states); % Number of photoactive states
            
            % Load name of fluorophore
            app.PhototransformationsquantumyieldssetupLabel.Text=['Phototransformation''s quantum yields set up: ',Fluorophorepar.name];
            
            % Load phototransformation quantum yield parameters
            app.QYpar=Fluorophorepar.Photophysics.trans_q;
            
            % Initialize QYpar
            if ~isempty(app.QYpar)
                % Setup Table Position size
                QtableWidth=min([100+100*app.n_states,app.UIFigure.Position(3)-100]);
                QtableHeight=min([30+20*n_photoactive_states,app.UIFigure.Position(4)-210]);
                QtableBottomPos=max([480-20*n_photoactive_states,100]);
                QtableLeftPos=70;

                app.QTable.Position=[QtableLeftPos,QtableBottomPos,QtableWidth,QtableHeight]; % Adjust position

                %Center position of the From and To labels
                app.ToLabel.Position(1)=app.QTable.Position(1)+app.QTable.Position(3)/2;
                app.FromLabel.Position(2)=app.QTable.Position(2)+app.QTable.Position(4)/2;
                % Show the names of the fluorescent states
                app.QTable.ColumnName=Fluorophorepar.Photophysics.state_names;
                app.QTable.RowName=app.QTable.ColumnName(app.photoactive_states);
                % Fill the data
                app.QTable.Data=app.QYpar(app.photoactive_states,:);
                MyColumnFormat=cell(1,numel(app.QTable.ColumnName));
                [MyColumnFormat{:}]=deal('shortG');
                app.QTable.ColumnFormat = MyColumnFormat;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateQYSimple(app.CallingApp, app.QYpar)
           
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: QTable
        function QTableCellEdit(app, event)
            newData = event.NewData;
            
            starting_state = app.Fluorophorepar.Photophysics.photoactive_states(event.Indices(1));
            ending_state = event.Indices(2);
            
            valid_phototransformation=1; % Initialize
            %Issue warnings when transformation is not possible
            if newData>1
                warndlg('Quantum yields must be < 1 !','Warning');
                valid_phototransformation=0;
            end
            
            if newData<0
                warndlg('Quantum yields must be > 0 !','Warning');
                valid_phototransformation=0;
            end
            
            if starting_state>=app.Fluorophorepar.Photophysics.converted_state && ending_state<app.Fluorophorepar.Photophysics.converted_state
                warndlg('Transformation from photoconverted state back to non-photoconverted state is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if app.Fluorophorepar.Photophysics.state_ids(starting_state)==app.Fluorophorepar.Photophysics.state_ids(ending_state)
                warndlg('Transformation between identical or rapidly exchanging states is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if valid_phototransformation==1
                app.QYpar(app.photoactive_states,:) = app.QTable.DisplayData;
            else
                app.QTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PhototransformationsquantumyieldssetupLabel = uilabel(app.UIFigure);
            app.PhototransformationsquantumyieldssetupLabel.Text = 'Phototransformation''s quantum yields set up:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QTable = uitable(app.UIFigure);
            app.QTable.ColumnName = {'Fluo State Name'};
            app.QTable.RowName = {'pKa '; 'Hill'};
            app.QTable.ColumnEditable = true;
            app.QTable.CellEditCallback = createCallbackFcn(app, @QTableCellEdit, true);
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QYSetupSimple(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end