classdef SMIS_vsn2_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowsampleButton                matlab.ui.control.Button
        CreateFluorophoresButton        matlab.ui.control.Button
        UseparallelcomputingCheckBox    matlab.ui.control.CheckBox
        CreateVirtualSamplesButton      matlab.ui.control.Button
        AnalyseResultsButton            matlab.ui.control.Button
        LaunchensemblesimulationButton  matlab.ui.control.Button
        SMISProgressGauge               matlab.ui.control.LinearGauge
        SMISProgressGaugeLabel          matlab.ui.control.Label
        SMISquickhelpButton             matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        STOPCheckBox                    matlab.ui.control.CheckBox
        ForceOverwriteCheckBox          matlab.ui.control.CheckBox
        LowerSlider                     matlab.ui.control.Slider
        LowerSliderLabel                matlab.ui.control.Label
        UpperSlider                     matlab.ui.control.Slider
        UpperSliderLabel                matlab.ui.control.Label
        SavesimulationparametersButton  matlab.ui.control.Button
        DisplayrangeparametersButton    matlab.ui.control.Button
        OthergeneralparametersButton    matlab.ui.control.Button
        AutodisplayrangeSwitch          matlab.ui.control.Switch
        AutodisplayrangeSwitchLabel     matlab.ui.control.Label
        SetuppairingButton              matlab.ui.control.Button
        FluorophorepairingforcolocalizationorFRETSwitch  matlab.ui.control.Switch
        FluorophorepairingforcolocalizationorFRETSwitchLabel  matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        sptPALMoptionsButton            matlab.ui.control.Button
        SetupdiffusionanddirectedmotionDropDown  matlab.ui.control.DropDown
        SetupdiffusionanddirectedmotionDropDownLabel  matlab.ui.control.Label
        ShowPSFButton                   matlab.ui.control.Button
        qPALMSwitch                     matlab.ui.control.Switch
        qPALMSwitchLabel                matlab.ui.control.Label
        sptPALMSwitch                   matlab.ui.control.Switch
        sptPALMSwitchLabel              matlab.ui.control.Label
        FrameSizeXEditField             matlab.ui.control.NumericEditField
        FrameSizeXEditFieldLabel        matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        VirtualSampleLabel              matlab.ui.control.Label
        DefaultSMISDirectoriesButton    matlab.ui.control.Button
        ShowlasersButton                matlab.ui.control.Button
        ChooseButton                    matlab.ui.control.Button
        LoadvirtualsampleDropDown       matlab.ui.control.DropDown
        LoadvirtualsampleDropDownLabel  matlab.ui.control.Label
        EMCCDCameraButton               matlab.ui.control.Button
        FRETSwitch                      matlab.ui.control.Switch
        FRETSwitchLabel                 matlab.ui.control.Label
        OutputStackFilenameEditField    matlab.ui.control.EditField
        OutputStackFilenameEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryEditField        matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        NumberofframesEditField         matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel    matlab.ui.control.Label
        ObjectiveandPSFButton           matlab.ui.control.Button
        LaunchsimulationButton          matlab.ui.control.Button
        SMISSimulationTitleEditField    matlab.ui.control.EditField
        SMISSimulationTitleEditFieldLabel  matlab.ui.control.Label
        LoadsimulationparametersButton  matlab.ui.control.Button
        EmissionFiltersandAquisitionChannelsOptionsButton  matlab.ui.control.Button
        NumberofacquisitionchannelsSwitch  matlab.ui.control.Switch
        NumberofacquisitionchannelsSwitchLabel  matlab.ui.control.Label
        FluorescencesamplingrateButton  matlab.ui.control.Button
        ShowphotophysicsstatisticsatendofsimulationSwitch  matlab.ui.control.Switch
        ShowphotophysicsstatisticsatendofsimulationSwitchLabel  matlab.ui.control.Label
        ShowdataduringacquisitionSwitch  matlab.ui.control.Switch
        ShowdataduringacquisitionSwitchLabel  matlab.ui.control.Label
        BinningfactorEditField          matlab.ui.control.NumericEditField
        BinningfactorEditFieldLabel     matlab.ui.control.Label
        TimebetweenframesmsEditField    matlab.ui.control.NumericEditField
        TimebetweenframesmsEditFieldLabel  matlab.ui.control.Label
        FrametimemsEditField            matlab.ui.control.NumericEditField
        FrametimemsEditFieldLabel       matlab.ui.control.Label
        RastersizenmEditField           matlab.ui.control.NumericEditField
        RastersizenmEditFieldLabel      matlab.ui.control.Label
        EditDriftButton                 matlab.ui.control.Button
        DSwitch                         matlab.ui.control.Switch
        DSwitchLabel                    matlab.ui.control.Label
        SampledriftSwitch               matlab.ui.control.Switch
        SampledriftSwitchLabel          matlab.ui.control.Label
        ShowfluorophorespectraButton    matlab.ui.control.Button
        SetupLaserDropDown              matlab.ui.control.DropDown
        SetupLaserDropDownLabel         matlab.ui.control.Label
        FluorescencebackgroundButton    matlab.ui.control.Button
        ShowvirtualsampleDropDown       matlab.ui.control.DropDown
        ShowvirtualsampleDropDownLabel  matlab.ui.control.Label
        SetupFluorophoreDropDown        matlab.ui.control.DropDown
        SetupFluorophoreDropDownLabel   matlab.ui.control.Label
        NumberoflasersEditField         matlab.ui.control.NumericEditField
        NumberoflasersEditFieldLabel    matlab.ui.control.Label
        NumberoffluorophoresEditField   matlab.ui.control.NumericEditField
        NumberoffluorophoresEditFieldLabel  matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DefaultDirs_App  % Default directories
        ObjectivePSF_App            % Objective and PSF App
        LaserSetup_App             % Lasers setup App
        FluorophoreSetup_App             % Fluorophores setup App
        FluorophorePairing_App            % Fluorophore Pairing setup App
        DiffusionSetup_App             % Diffusion setup App
        EMCCD_App             % EMCCD setup App
        Drift_App             % Drift setup App
        sptPALMOptions_App % SptPALM options application
        BG_App             % Fluorescence Background setup App
        FiltersAndChannels_app             % Emission filter and dual channel parameters
        GeneralPar_App  % General parameters setup App
        DisplayPar_App  % Display Parameters App
        SamplingPar_App % Sampling Parameters App
        SMIS_Logo % SMIS Logo Image
        AnalyzeResults_App  % Analyze Results App
        CurrentFluorophore % Current fluorophore
        CreatePattern_App % Create Pattern App
        PSF_App             % PSF display App
    end
    
    properties (Access = public)
        smis_gui_parameters = [] % Structure with all parameters
    end
    
    methods (Access = public)
        
        function updateSMIS(app) % Update all fields of the current SMIS application
            % Make the nz field Eventually visible
            if app.smis_gui_parameters.simul_3D==1
                app.ZEditField.Visible='on';
                app.ZEditFieldLabel.Visible='on';
            else
                app.ZEditField.Visible='off';
                app.ZEditFieldLabel.Visible='off';
            end
            
            % Update n,m,nz
            n=app.smis_gui_parameters.image_size.n;
            m=app.smis_gui_parameters.image_size.m;
            nz=app.smis_gui_parameters.image_size.nz;
            if ~isempty(n)
                app.FrameSizeXEditField.Value= n;
                app.YEditField.Value= m;
                app.ZEditField.Value= nz;
            end
            
            % Check for compatibility with laser beam profile size
            if ~isempty(n)
                for i=1:app.smis_gui_parameters.n_lasers
                    if ~isempty(app.smis_gui_parameters.lasers(i).beam_profile) 
                        if strcmp(app.smis_gui_parameters.lasers(i).mode,'Exp Profile')==1
                            if size(app.smis_gui_parameters.lasers(i).beam_profile,1)~=n || size(app.smis_gui_parameters.lasers(i).beam_profile,2)~=m
                                answer = questdlg(['Experimental laser beam profile needs to be resized (',app.smis_gui_parameters.lasers(i).name,')... Ok ?'], ...
                                    'SMIS Question', ...
                                    'Yes','No','Yes');
                                switch answer
                                    case 'Yes'
                                        app.smis_gui_parameters.lasers(i).beam_profile=imresize(app.smis_gui_parameters.lasers(i).beam_profile,[n m]);
                                        disp(['Resizing laser beam profile: ',app.smis_gui_parameters.lasers(i).name]), ...
                                    case 'No'
                                        MyDlg=warndlg(['Detector dimensions changed, laser pattern will be removed for laser: ',app.smis_gui_parameters.lasers(i).name, ' !'],'Warning');
                                        waitfor(MyDlg)
                                        app.smis_gui_parameters.lasers(i).beam_profile=[];
                                        app.smis_gui_parameters.lasers(i).mode='Gaussian';
                                end
                            end
                        else % Resize anyway for Gaussian or flat profiles
                            app.smis_gui_parameters.lasers(i).beam_profile=imresize(app.smis_gui_parameters.lasers(i).beam_profile,[n m]);
                            disp(['Resizing laser beam profile: ',app.smis_gui_parameters.lasers(i).name]), ...
                        end
                    end
                end

            end

            % Check for compatibility with FRAP  image size
            if ~isempty(n) && any([app.smis_gui_parameters.lasers.frap_mode])
                for i=1:app.smis_gui_parameters.n_lasers
                    if app.smis_gui_parameters.lasers(i).frap_mode==1
                        if app.smis_gui_parameters.lasers(i).mask_size(1)~=n || app.smis_gui_parameters.lasers(i).mask_size(2)~=m
                            answer = questdlg(['FRAP mask needs to be resized (',app.smis_gui_parameters.lasers(i).name,')... Ok ?'], ...
                                'SMIS Question', ...
                                'Yes','No','Yes');
                            switch answer
                                case 'Yes'
                                    if ~isempty(app.smis_gui_parameters.lasers(i).mask_pattern)
                                    app.smis_gui_parameters.lasers(i).mask_pattern=imresize(app.smis_gui_parameters.lasers(i).mask_pattern,[n m]);
                                    app.smis_gui_parameters.lasers(i).mask_pattern=round(app.smis_gui_parameters.lasers(i).mask_pattern); % Ensure only logical values
                                    app.smis_gui_parameters.lasers(i).mask_size=[n m];
                                    else
                                        mydlg=warndlg('FRAP mask profile not in memory (old SMIS vsn), please reload in Laser menu !','Warning');
                                        waitfor(mydlg);
                                    end
                                case 'No'
                                    mydlg=warndlg('Image dimensions changed, FRAP mask file will be removed !','Warning');
                                    waitfor(mydlg);
                                    app.smis_gui_parameters.lasers(i).frap_mode=0;
                                    app.smis_gui_parameters.lasers(i).mask_size=[];
                                    app.smis_gui_parameters.lasers(i).mask_pattern=[];
                                    app.smis_gui_parameters.lasers(i).mask_dir='';
                                    app.smis_gui_parameters.lasers(i).mask_file='';
                            end                         
                        end
                    end
                end
                
            end
        end
        
        
        function updateDefaultDirs(app, DefaultDirspar)
            % Store inputs as properties
            app.smis_gui_parameters.DefaultDirs = DefaultDirspar;
            
            % Re-enable the Plot Options button
            app.DefaultSMISDirectoriesButton.Enable = 'on';
        end
        
        function updateObjectivePSF(app, ObjectivePSFpar)
            % Store inputs as properties
            app.smis_gui_parameters.obj_and_psf = ObjectivePSFpar;
            
            % Re-enable the Plot Options button
            app.ObjectiveandPSFButton.Enable = 'on';
        end
        
        function updateLaserpar(app, Laserpar, selected_laser)
            % Store inputs as properties
            % app.smis_gui_parameters.lasers(selected_laser).mask_pattern=[]; % For compatibility with SMIS vsn < 2.3
            % app.smis_gui_parameters.lasers(selected_laser).beam_profile=[]; % For compatibility with SMIS vsn < 2.3
            app.smis_gui_parameters.lasers(selected_laser) = Laserpar;
            
            % Re-enable the Plot Options button
            app.SetupLaserDropDown.Enable = 'on';
            
            % Possibly update Laser name
            app.SetupLaserDropDown.Items(selected_laser+1) = {Laserpar.name};
            
            % Reset Laser menu to first position
            app.SetupLaserDropDown.Value = 1;
        end
        
        function updateEMCCD(app, EMCCDpar)
            % Store inputs as properties
            app.smis_gui_parameters.EMCCD = EMCCDpar;
            
            % Re-enable the Plot Options button
            app.EMCCDCameraButton.Enable = 'on';
        end
        
        function updateGeneralPar(app, Generalpar)
            % Store inputs as properties
            app.smis_gui_parameters.General = Generalpar;
            
            % Re-enable the Plot Options button
            app.OthergeneralparametersButton.Enable = 'on';
        end
        
        function updateDisplayPar(app, Displaypar)
            % Store inputs as properties
            app.smis_gui_parameters.ImageDisplay = Displaypar;
            
            % Re-enable the Plot Options button
            app.DisplayrangeparametersButton.Enable = 'on';
        end
        
        function updateSamplingPar(app, Samplingpar)
            % Store inputs as properties
            app.smis_gui_parameters.Sampling = Samplingpar;
            
            % Re-enable the Plot Options button
            app.FluorescencesamplingrateButton.Enable = 'on';
        end
        
        function updateDrift(app, Driftpar)
            % Store inputs as properties
            app.smis_gui_parameters.drift = Driftpar;
            
            % Re-enable the Plot Options button
            app.EditDriftButton.Enable = 'on';
        end
        
        function updatesptPALMOptions(app, sptPALMOptionspar)
            % Store inputs as properties
            app.smis_gui_parameters.sptPALMOptions = sptPALMOptionspar;
            
            % Re-enable the Plot Options button
            app.sptPALMoptionsButton.Enable = 'on';
        end
        
        function updateBG(app, BGpar)
            % Store inputs as properties
            app.smis_gui_parameters.BG = BGpar;
            
            % Re-enable the Plot Options button
            app.FluorescencebackgroundButton.Enable = 'on';
        end
        
        function updateTwoChannels(app, TwoChannelpar)
            % Store inputs as properties
            app.smis_gui_parameters.TwoChannel = TwoChannelpar;
            
            % Re-enable the Plot Options button
            app.EmissionFiltersandAquisitionChannelsOptionsButton.Enable = 'on';
        end
        
        function updateFluorophorepar(app, Fluorophorepar, selected_fluorophore, DefaultDir)
            % Store inputs as properties
            app.smis_gui_parameters.Fluorophores(selected_fluorophore) = Fluorophorepar;
            
            % Store Default dir
            app.smis_gui_parameters.DefaultDirs.Fluorophore_dir = DefaultDir;

            % Re-enable the Plot Options button
            app.SetupFluorophoreDropDown.Enable = 'on';
            
            % Possibly update Fluorophore name
            app.SetupFluorophoreDropDown.Items(selected_fluorophore+1) = {Fluorophorepar.name};
            app.SetupFluorophoreDropDown.Items(selected_fluorophore+1) = {Fluorophorepar.name};
            
            %Update the sample pattern drop-down menus
            app.LoadvirtualsampleDropDown.Items(selected_fluorophore+1) = {Fluorophorepar.name};
            app.ShowvirtualsampleDropDown.Items(selected_fluorophore+1) = {Fluorophorepar.name};
            
            %Update the displayed pattern
            if app.CurrentFluorophore==selected_fluorophore
                CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
                app.VirtualSampleLabel.Text = {['Virtual sample for fluorophore: ', CurrentFluorophoreName],['(',app.smis_gui_parameters.in_images_names{app.CurrentFluorophore},')']};
            end
            
            %Update the spt drop-down menu
            app.SetupdiffusionanddirectedmotionDropDown.Items(selected_fluorophore+1) = {Fluorophorepar.name};
            
            % Reset  menu to first position
            app.SetupFluorophoreDropDown.Value = 1;
        end
        
        function updateFluorophorePairingpar(app, FluorophorePairingpar)
            % Store inputs as properties
            app.smis_gui_parameters.Fluorophore_Pairing = FluorophorePairingpar;
            
            % Re-enable the button
            app.SetuppairingButton.Enable = 'on';
        end
        
        function updateDiffusionpar(app, Fluorophorepar, selected_fluorophore)
            % Store inputs as properties
            app.smis_gui_parameters.Fluorophores(selected_fluorophore) = Fluorophorepar;
            
            % Re-enable the Plot Options button
            app.SetupdiffusionanddirectedmotionDropDown.Enable = 'on';
            
            % Reset  menu to first position
            app.SetupdiffusionanddirectedmotionDropDown.Value = 1;
        end
        
        function Reenable(app)
            app.DefaultSMISDirectoriesButton.Enable = 'on';
            app.ObjectiveandPSFButton.Enable = 'on';
            
            app.SetupLaserDropDown.Enable = 'on';
            app.SetupLaserDropDown.Value = 1;
            
            app.EMCCDCameraButton.Enable = 'on';
            app.OthergeneralparametersButton.Enable = 'on';
            app.DisplayrangeparametersButton.Enable = 'on';
            app.FluorescencesamplingrateButton.Enable = 'on';
            app.EditDriftButton.Enable = 'on';
            app.sptPALMoptionsButton.Enable = 'on';
            app.FluorescencebackgroundButton.Enable = 'on';
            app.EmissionFiltersandAquisitionChannelsOptionsButton.Enable = 'on';
            
            app.SetupFluorophoreDropDown.Enable = 'on';
            app.SetupFluorophoreDropDown.Value = 1;
            
            app.SetuppairingButton.Enable = 'on';
            
            app.SetupdiffusionanddirectedmotionDropDown.Enable = 'on';
            app.SetupdiffusionanddirectedmotionDropDown.Value = 1;
            app.AnalyseResultsButton.Enable = 'on';
            app.CreateFluorophoresButton.Enable = 'on';
        end
    end
    
    methods (Access = private)
        function DisplayImage(app, Pattern, MinDisp, MaxDisp)
            app.ImageAxes.Visible = 'on';
            if app.smis_gui_parameters.simul_3D==0 % 2D case
                app.UpperSlider.Visible='on';
                app.UpperSliderLabel.Visible='on';
                app.LowerSlider.Visible='on';
                app.LowerSliderLabel.Visible='on';
                view(app.ImageAxes, 2);
                colormap(app.ImageAxes,'gray')
                
                app.ImageAxes.YLimMode="auto"; % Reset the XLim/YLim for image to be recentered on axis
                app.ImageAxes.XLimMode="auto";
                
                if MinDisp<MaxDisp
                    clims = [MinDisp, MaxDisp];
                    imagesc(app.ImageAxes,Pattern,clims);
                else
                    imagesc(app.ImageAxes,Pattern);
                end
            else % 3D case
                app.UpperSlider.Visible='off';
                app.UpperSliderLabel.Visible='off';
                app.LowerSlider.Visible='off';
                app.LowerSliderLabel.Visible='off';
                view(app.ImageAxes, 3);
                colormap(app.ImageAxes,'jet')
                
                if app.CurrentFluorophore<=app.smis_gui_parameters.n_fluorophores
                    sp_ids=app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids;
                    n_sp=app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).n_sp;
                else
                    sp_ids=app.smis_gui_parameters.pattern_ids(1).sp_ids; % Take the first fluorophore
                    n_sp=app.smis_gui_parameters.pattern_ids(1).n_sp;
                end
                
                %Define the colors
                RGB=zeros(3,max([6,n_sp]));
                RGB(:,1)=[0,1,0]; % Green
                RGB(:,2)=[1,0,0]; % Red
                RGB(:,3)=[0,0,1]; % Blue
                RGB(:,4)=[1,1,0]; % Yellow
                RGB(:,5)=[1,0,1]; % Magenta
                RGB(:,6)=[0,1,1]; % Cyan
                if n_sp>6
                    RGB(:,7:end)=rand(3,n_sp-6); % Random color
                end
                
                %Draw the different sub_patterns
                hold(app.ImageAxes,'on')
                sp_ids=sp_ids(sp_ids>0);
                for k=1:numel(sp_ids)
                    w=find(Pattern==sp_ids(k));
                    [x,y,z]=ind2sub(size(Pattern),w);
                    scatter3(app.ImageAxes,x,y,z,2,'MarkerEdgeColor',RGB(:,k),'MarkerFaceColor',RGB(:,k))
                end
                hold(app.ImageAxes,'off')
                xlim(app.ImageAxes,[1,app.smis_gui_parameters.binning*app.smis_gui_parameters.image_size.n]);
                ylim(app.ImageAxes,[1,app.smis_gui_parameters.binning*app.smis_gui_parameters.image_size.m]);
                zlim(app.ImageAxes,[1,app.smis_gui_parameters.binning*app.smis_gui_parameters.image_size.nz]);
                
                
                % vol3d('cdata', Pattern, 'Parent',app.ImageAxes);
                alphamap(app.ImageAxes, 'default')
            end
        end
        
        function ClearImage(app)
            MyData = findobj(app.ImageAxes, '-not', 'Type', 'Axes');
            if ~isempty(MyData)
                delete(MyData);
            end
        end
        
        function IsOK = check_dir(~,MyDir)
            IsOK=1;
            if ispc
                backlash_pos=strfind(MyDir,'\');
            else
                backlash_pos=strfind(MyDir,'/');
            end
            if isempty(backlash_pos)
                MyDlg=warndlg('This does not look like a directory path','Warning');
                waitfor(MyDlg)
                IsOK=0;
            else
                subdir=MyDir(1:backlash_pos(end-1));
                if ~exist(subdir,'dir') % Check if the directory name makes sense
                    fig = uifigure;
                    fig.Position = [800 600 400 160];
                    MySel = uiconfirm(fig,'Do you really want to create this strange looking directory name ?','','Icon','warning');
                    close(fig)
                    if strcmp(MySel,'OK') ~= 1
                        IsOK=0;
                    end
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Path where the simulation software is located
            SMIS_GUI_file=mfilename('fullpath');
            if ispc
                backlash_pos=strfind(SMIS_GUI_file,'\');
            else
                backlash_pos=strfind(SMIS_GUI_file,'/');
            end
            SMIS_folder=SMIS_GUI_file(1:backlash_pos(end-1));
            if ~isfolder(SMIS_folder)
                SMIS_folder=uigetdir(pwd,'Select directory were the SMIS simulation is located');
            end

            if ~isdeployed % Take this off for standalone version
                % Add path to SMIS folder
                addpath(genpath(SMIS_folder));
                % Check if parallel computing toolbox is available
                if  ~any(any(contains(struct2cell(ver), 'Parallel Computing Toolbox')))
                    app.UseparallelcomputingCheckBox.Enable='off';
                    disp('Parallel computing not available on this computer !')
                    app.smis_gui_parameters.General.use_parallel_computing=-1; % Disable if no PCT available
                end
            end

            % Load all parameters from prepare_smis_gui_parameters.m
            app.smis_gui_parameters = prepare_smis_gui_parameters(SMIS_folder); % Description
           
            % Handle default directories
            app.smis_gui_parameters.DefaultDirs.SMIS_GUI_Dir=[SMIS_folder,'GUI']; % This directory should never be changed
            app.smis_gui_parameters.DefaultDirs.SMIS_Image_Dir=[SMIS_folder,'IMAGE_LIBRARY']; % This directory should never be changed
            
            
            %Change the default directories if they cannot be found
            DefaultDirs=app.smis_gui_parameters.DefaultDirs;
            
            if ~exist(DefaultDirs.SMIS_dir,'dir') % Output directory for simulations
                app.smis_gui_parameters.DefaultDirs.SMIS_dir='';
            end
            
            app.smis_gui_parameters.outfiledir=app.smis_gui_parameters.DefaultDirs.SMIS_dir; % To start with assign output directory for simulation data to the directory where simulation parameters are generally stored.
            app.OutputDirectoryEditField.Value=app.smis_gui_parameters.DefaultDirs.SMIS_dir; % Initialize output directory
            
            if ~exist(DefaultDirs.SMIS_Simulations_dir,'dir') % Output directory for simulations
                app.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir=[SMIS_folder,'SMIS_EXAMPLES'];
            end
            
            if ~exist(DefaultDirs.Pattern_dir,'dir') % Output directory for simulations
                app.smis_gui_parameters.DefaultDirs.Pattern_dir=[SMIS_folder,'PATTERNS'];
            end
            
            if ~exist(DefaultDirs.Fluorophore_dir,'dir') % Output directory for simulations
                app.smis_gui_parameters.DefaultDirs.Fluorophore_dir=[SMIS_folder,'FLUOROPHORES'];
            end
            
            % Load the SMIS Logo
            app.SMIS_Logo=imread(fullfile(app.smis_gui_parameters.DefaultDirs.SMIS_GUI_Dir,'SMIS_LOGO.png'));
            app.Image.ImageSource=fullfile(app.smis_gui_parameters.DefaultDirs.SMIS_GUI_Dir,'SMIS_LOGO.png');
            
            % Set ItemsData as numbers in drop-down menus (cannot be done properly in the design view)
            app.SetupLaserDropDown.ItemsData=1:app.smis_gui_parameters.n_lasers+1 ;
            
            app.SetupFluorophoreDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+1 ;
            app.SetupFluorophoreDropDown.Items(1) = {'Choose'};
            app.SetupFluorophoreDropDown.Items(2:app.smis_gui_parameters.n_fluorophores+1) = {app.smis_gui_parameters.Fluorophores.name};
            
            app.SetupdiffusionanddirectedmotionDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+1 ;
            app.SetupdiffusionanddirectedmotionDropDown.Items(1) = {'Choose'};
            app.SetupdiffusionanddirectedmotionDropDown.Items(2:app.smis_gui_parameters.n_fluorophores+1) = {app.smis_gui_parameters.Fluorophores.name};
            
            app.LoadvirtualsampleDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+2 ;
            app.LoadvirtualsampleDropDown.Items(1) = {'Choose'};
            app.LoadvirtualsampleDropDown.Items(2:app.smis_gui_parameters.n_fluorophores+1) = {app.smis_gui_parameters.Fluorophores.name};
            app.LoadvirtualsampleDropDown.Items(end) = {'All'};
            
            app.ShowvirtualsampleDropDown.ItemsData=1:app.smis_gui_parameters.n_fluorophores+1 ;
            app.ShowvirtualsampleDropDown.Items(1) = {'Choose'};
            app.ShowvirtualsampleDropDown.Items(2:app.smis_gui_parameters.n_fluorophores+1) = {app.smis_gui_parameters.Fluorophores.name};
            
            app.SMISSimulationTitleEditField.Value=app.smis_gui_parameters.smis_title; % Initialize simulation name
            
            % Initialize image axis
            app.ImageAxes.Visible = 'off';
            
            app.UpperSlider.Visible='off';
            app.UpperSliderLabel.Visible='off';
            app.LowerSlider.Visible='off';
            app.LowerSliderLabel.Visible='off';
            
            axis(app.ImageAxes, 'image');
            app.ImageAxes.XLabel.String = 'Pixels [X dim]';
            app.ImageAxes.YLabel.String = 'Pixels [Y dim]';
            app.ImageAxes.ZLabel.String = 'Pixels [Z dim]';
            
            app.EditDriftButton.Visible='off';
          
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Delete all apps
            delete(app.AnalyzeResults_App)
            delete(app.ObjectivePSF_App)
            delete(app.LaserSetup_App)
            delete(app.FluorophoreSetup_App)
            delete(app.DiffusionSetup_App)
            delete(app.DefaultDirs_App)
            delete(app.EMCCD_App)
            delete(app.Drift_App)
            delete(app.BG_App)
            delete(app.FiltersAndChannels_app)
            delete(app.PSF_App)
            delete(app)
        end

        % Value changed function: DSwitch
        function DSwitchValueChanged(app, event)
            value = app.DSwitch.Value;
            app.smis_gui_parameters.simul_3D=strcmp(value,'On');
            
            updateSMIS(app);
            
            app.ImageAxes.Visible = 'off';
            app.UpperSlider.Visible='off';
            app.UpperSliderLabel.Visible='off';
            app.LowerSlider.Visible='off';
            app.LowerSliderLabel.Visible='off';
            
            app.VirtualSampleLabel.Text = '';
            
            % Remove the loaded patterns
            [app.smis_gui_parameters.pattern_ids(:).n_sp]=deal([]);
            [app.smis_gui_parameters.pattern_ids(:).sp_ids]=deal([]);
            app.smis_gui_parameters.in_images_dir(:)=cellstr('');
            app.smis_gui_parameters.in_images_names(:)=cellstr('');
            app.smis_gui_parameters.in_images(:)={[]};

            app.smis_gui_parameters.image_size.n=[];
            app.smis_gui_parameters.image_size.m=[];
            app.smis_gui_parameters.image_size.nz=[];
            ClearImage(app);
        end

        % Button pushed function: LaunchsimulationButton
        function LaunchsimulationButtonPushed(app, event)
            if exist(app.smis_gui_parameters.outfiledir,'dir')
                app.STOPCheckBox.Value=0;
                app.STOPCheckBox.Visible='on';
                app.SMISProgressGauge.Visible='on';
                app.SMISProgressGaugeLabel.Visible='on';
                app.SMISProgressGauge.BackgroundColor=[0,1,0];
                app.SMISProgressGauge.Value=0;
                
                smis_ok=smis_vsn2_3_main(app, app.smis_gui_parameters);
                if smis_ok==1
                    msgbox('SMIS simulation done !','Success','custom',app.SMIS_Logo,[]);
                elseif smis_ok==2
                    msgbox('SMIS simulation interrupted !','Warning','custom',app.SMIS_Logo,[]);
                elseif smis_ok==3
                    % do nothing if simulation canceled from beginning !
                else
                    msgbox('SMIS simulation error ! Check faulty parameter !','Warning','custom',app.SMIS_Logo,[]);
                end
                app.STOPCheckBox.Visible='off';
                app.SMISProgressGauge.Visible='off';
                app.SMISProgressGaugeLabel.Visible='off';
                app.LaunchsimulationButton.Enable='on';
            else
                MyDlg=warndlg('The output directory for the simulation data does not exist !','Warning');
                waitfor(MyDlg)
            end
        end

        % Value changed function: FRETSwitch
        function FRETSwitchValueChanged(app, event)
            value = app.FRETSwitch.Value;
            if strcmp(value,'On')==1
                FRET_ok=1;
                if strcmp(app.qPALMSwitch.Value,'On')==1
                    MyDlg=warndlg('Cannot run FRET when the qPALM option is on !','Warning');
                    waitfor(MyDlg)
                    FRET_ok=0;
                end
                if strcmp(app.sptPALMSwitch.Value,'On')==1
                    MyDlg=warndlg('Cannot run FRET when the sptPALM option is on !','Warning');
                    waitfor(MyDlg)
                    FRET_ok=0;
                end
                if app.smis_gui_parameters.Fluorophore_Pairing.state==0
                    MyDlg=warndlg('Cannot run FRET when fluorophore pairing is not set !','Warning');
                    waitfor(MyDlg)
                    FRET_ok=0;
                end
                if isempty(app.smis_gui_parameters.Fluorophore_Pairing.pairs)
                    MyDlg=warndlg('Cannot run FRET until fluorophore pairs have been defined !','Warning');
                    waitfor(MyDlg)
                    FRET_ok=0;
                end

                for i=1:app.smis_gui_parameters.n_fluorophores
                    if app.smis_gui_parameters.Fluorophores(i).Linkage.set==1
                        MyDlg=warndlg(['Cannot run FRET with fluorophore linkage errors (', app.smis_gui_parameters.Fluorophores(i).name,') !'],'Warning');
                        waitfor(MyDlg)
                        FRET_ok=0;
                    end
                end
                
            else
                FRET_ok=0;
            end
            
            if FRET_ok==1
                app.smis_gui_parameters.fret_on=1;
            else
                app.smis_gui_parameters.fret_on=0;
                app.FRETSwitch.Value='Off';
            end
        end

        % Button pushed function: LoadsimulationparametersButton
        function LoadsimulationparametersButtonPushed(app, event)
            app.LoadsimulationparametersButton.Enable='off';
            
            if exist(app.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir,'dir')
                cd(app.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir);
            end
            [file,path] = uigetfile('*_SMIS_par.mat','Select a SMIS simulation input file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file),'smis_par');
                if ~exist('smis_par',"var")
                    msgbox('Invalid Matlab SMIS Parameter file !','icon','warn')
                else
                    disp('Loading simulation ...')
                    
                    % Do NOT update the Default Directories except SMIS_Simulations_dir
                    smis_par.DefaultDirs=app.smis_gui_parameters.DefaultDirs; % Take the existing Default Directories
                    smis_par.DefaultDirs.SMIS_Simulations_dir=path; % Update the SMIS_Simulations_dir


                    % Ensure compatibility with older SMIS versions
                    app.smis_gui_parameters=check_smis_versions(smis_par); % Then copy all parameters


                    %Fill all values
                    app.SMISSimulationTitleEditField.Value=app.smis_gui_parameters.smis_title;
                    
                    %3D option
                    if app.smis_gui_parameters.simul_3D==1
                        app.DSwitch.Value='On';
                        app.ZEditField.Visible='on';
                        app.ZEditFieldLabel.Visible='on';
                    else
                        app.DSwitch.Value='Off';
                        app.ZEditField.Visible='off';
                        app.ZEditFieldLabel.Visible='off';
                    end
                    
                    %Number of fluorophores
                    n_fluorophores=app.smis_gui_parameters.n_fluorophores;
                    app.NumberoffluorophoresEditField.Value=n_fluorophores;
                    
                    %Names of fluorophores
                    fluorophore_names = cell(1, n_fluorophores+2);
                    fluorophore_names(1) = {'Choose'};
                    for i=2:n_fluorophores+1 % Get the previous names
                        fluorophore_names(i) = {app.smis_gui_parameters.Fluorophores(i-1).name};
                    end
                    fluorophore_names(end) = {'All'};
                    
                    %Update the sample pattern drop-down menu
                    app.LoadvirtualsampleDropDown.Items=fluorophore_names;
                    app.LoadvirtualsampleDropDown.ItemsData=1:n_fluorophores+2;
                    app.ShowvirtualsampleDropDown.Items=fluorophore_names(1:n_fluorophores+1);
                    app.ShowvirtualsampleDropDown.ItemsData=1:n_fluorophores+1;
                    
                    %Update the fluorophore photophysics drop-down menu
                    app.SetupFluorophoreDropDown.Items=fluorophore_names(1:n_fluorophores+1);
                    app.SetupFluorophoreDropDown.ItemsData=1:n_fluorophores+1;
                    
                    %Update the diffusion and directed motion drop-down menu
                    app.SetupdiffusionanddirectedmotionDropDown.Items=fluorophore_names(1:n_fluorophores+1);
                    app.SetupdiffusionanddirectedmotionDropDown.ItemsData=1:n_fluorophores+1;
                    
                    %FRET option
                    if app.smis_gui_parameters.fret_on==1
                        app.FRETSwitch.Value='On';
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='on';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='on';
                    else
                        app.FRETSwitch.Value='Off';
                    end
                    
                    % Fluorophore pairing option
                    if app.smis_gui_parameters.Fluorophore_Pairing.state==1 && n_fluorophores>1
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Value='On';
                        app.SetuppairingButton.Visible='on';
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='on';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='on';
                    elseif n_fluorophores>1
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Value='Off';
                        app.SetuppairingButton.Visible='off';
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='on';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='on';
                    else
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Value='Off';
                        app.SetuppairingButton.Visible='off';
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='off';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='off';
                    end
                    
                    %SptPALM option
                    if app.smis_gui_parameters.add_diffusion==1
                        app.sptPALMSwitch.Value='On';
                        app.SetupdiffusionanddirectedmotionDropDown.Visible='on';
                        app.SetupdiffusionanddirectedmotionDropDownLabel.Visible='on';
                        app.sptPALMoptionsButton.Visible='on';
                    else
                        app.sptPALMSwitch.Value='Off';
                        app.SetupdiffusionanddirectedmotionDropDown.Visible='off';
                        app.SetupdiffusionanddirectedmotionDropDownLabel.Visible='off';
                        app.sptPALMoptionsButton.Visible='off';
                    end
                    
                    %QPALM option
                    if app.smis_gui_parameters.distribute_in_clusters==1
                        app.qPALMSwitch.Value='On';
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='off';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='off';
                    else
                        app.qPALMSwitch.Value='Off';
                    end
                    
                    
                    % Number of lasers
                    n_lasers=app.smis_gui_parameters.n_lasers;
                    app.NumberoflasersEditField.Value=n_lasers;
                    
                    % Names of lasers
                    app.SetupLaserDropDown.ItemsData=1:n_lasers+1;
                    laser_names = cell(1, n_lasers+1);
                    laser_names(1) = {'Choose'};
                    for i=2:n_lasers+1
                        laser_names(i) = {app.smis_gui_parameters.lasers(i-1).name};
                    end

                    app.SetupLaserDropDown.Items=laser_names;
                    
                    % Binning factor
                    app.BinningfactorEditField.Value=app.smis_gui_parameters.binning;
                    
                    %Frame size
                    app.FrameSizeXEditField.Value=app.smis_gui_parameters.image_size.n;
                    app.YEditField.Value=app.smis_gui_parameters.image_size.m;
                    app.ZEditField.Value=app.smis_gui_parameters.image_size.nz;
                    
                    %Number of frames
                    app.NumberofframesEditField.Value=app.smis_gui_parameters.n_images;
                    %Raster size
                    app.RastersizenmEditField.Value=app.smis_gui_parameters.raster;
                    %Frametime
                    app.FrametimemsEditField.Value=app.smis_gui_parameters.frametime;
                    %Time between frames
                    app.TimebetweenframesmsEditField.Value=app.smis_gui_parameters.addtime;
                    
                    % Drift option
                    if app.smis_gui_parameters.drift.state==1
                        app.SampledriftSwitch.Value='On';
                        app.EditDriftButton.Visible='on';
                    else
                        app.SampledriftSwitch.Value='Off';
                        app.EditDriftButton.Visible='off';
                    end
                    
                    %Number of acquisition channels
                    app.NumberofacquisitionchannelsSwitch.Value=num2str(app.smis_gui_parameters.TwoChannel.state+1);
                    
                    %Show data during acquisition
                    if app.smis_gui_parameters.show_data_aquisition==1
                        app.ShowdataduringacquisitionSwitch.Value='On';
                        app.AutodisplayrangeSwitch.Visible='on';
                        app.AutodisplayrangeSwitchLabel.Visible='on';
                        
                        % Create a figure if necessary
                        create_figure=1;
                        % The following code ensures that the right figure is addressed
                        % Indeed, it might be that the figure has been created before, then deleted, and the same figure number has been re-created for another purpose and is still open
                        
                        % Take a look at the current figures open
                        figHandles = findobj('Type', 'figure'); % Get the array of all figures open
                        n_fig=size(figHandles,1);
                        for k=1:n_fig
                            if strcmp(figHandles(k).Name,'SMIS Data Aquisition')==1 % Our figure already exists
                                create_figure=0;
                                emccd_figure_number=figHandles(k).Number;
                            end
                        end
                        
                        % create a figure if necessary
                        if create_figure==1
                            emccd_figure=figure;
                            app.smis_gui_parameters.emccd_figure_number=emccd_figure.Number;
                            scrsz=get(0,'ScreenSize');
                            set(emccd_figure,'Position',[10 scrsz(4)/10 scrsz(3)*3/4 scrsz(4)*1/2])
                            set(emccd_figure,'color','white');
                            set(emccd_figure,'Name','SMIS Data Aquisition');
                            set(emccd_figure,'NumberTitle','off');
                        else
                            figure(emccd_figure_number);
                            clf;
                        end
                    else
                        app.ShowdataduringacquisitionSwitch.Value='Off';
                        app.AutodisplayrangeSwitch.Visible='off';
                        app.AutodisplayrangeSwitchLabel.Visible='off';
                    end
                    
                    % Show photophysics at end of simulation
                    if app.smis_gui_parameters.show_photophysics==1
                        app.ShowphotophysicsstatisticsatendofsimulationSwitch.Value='On';
                    else
                        app.ShowphotophysicsstatisticsatendofsimulationSwitch.Value='Off';
                    end
                    
                    % Show output directory and stack file name
                    if exist(app.smis_gui_parameters.outfiledir,'dir')
                        app.OutputDirectoryEditField.Value=app.smis_gui_parameters.outfiledir;
                    else
                        app.OutputDirectoryEditField.Value='';
                    end
                    app.OutputStackFilenameEditField.Value=app.smis_gui_parameters.outfilename;
                    
                    % Handle force overwrite
                    app.ForceOverwriteCheckBox.Value=app.smis_gui_parameters.force_overwrite;

                    % Handle parallel computing
                    if  strcmp(app.UseparallelcomputingCheckBox.Enable,'off')==1
                        app.smis_gui_parameters.General.use_parallel_computing=-1; % Disable if no PCT available
                    end
                    app.UseparallelcomputingCheckBox.Value=max([app.smis_gui_parameters.General.use_parallel_computing,0]);
                    
                    % Clear displayed data
                    ClearImage(app);
                    
                    % Show the pattern for the first fluorophore
                    app.CurrentFluorophore=1;


                    % Check that the virtual sample is present for all fluorophores, if not check that sample image directory exist, if not replace it by the default directory 
                    for i=1:n_fluorophores
                        if isempty(app.smis_gui_parameters.in_images{i})
                            if ~exist(app.smis_gui_parameters.in_images_dir{i},'dir')
                                if app.smis_gui_parameters.simul_3D==0
                                    if app.smis_gui_parameters.distribute_in_clusters==0 % Not qPALM mode
                                        app.smis_gui_parameters.in_images_dir(i)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D')};
                                    else % qPALM mode
                                        app.smis_gui_parameters.in_images_dir(i)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D','QPALM')};
                                    end
                                else
                                    if app.smis_gui_parameters.distribute_in_clusters==0 % Not qPALM mode
                                        app.smis_gui_parameters.in_images_dir(i)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D')};
                                    else % qPALM mode
                                        app.smis_gui_parameters.in_images_dir(i)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D','QPALM')};
                                    end
                                end
                            end

                            % Check that the sample virtual image patterns exist
                            MyPath=app.smis_gui_parameters.in_images_dir{i};
                            MyFile=app.smis_gui_parameters.in_images_names{i};

                            FluorophoreName=app.smis_gui_parameters.Fluorophores(i).name;

                            % Load the patterns
                            if exist(fullfile(MyPath,MyFile),'file')
                                [Pattern,~,~,~,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                                app.smis_gui_parameters.in_images{i}=squeeze(Pattern);
                            else % If not, issue a warning
                                MyDlg=warndlg(['Image pattern: ', fullfile(MyPath,MyFile), ' for fluorophore: ', FluorophoreName,' has not been found on the disk !'],'warning');
                                waitfor(MyDlg)
                            end
                        end
                    end

                    % Display the pattern for the 1st fluorophore if it is found
                    if ~isempty(app.smis_gui_parameters.in_images{app.CurrentFluorophore})
                        % Display the pattern
                        app.VirtualSampleLabel.Visible = 'on';
                        app.ImageAxes.Visible = 'on';
                        CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;

                        app.VirtualSampleLabel.Text = {['Virtual sample for fluorophore: ', CurrentFluorophoreName],['(',app.smis_gui_parameters.in_images_names{app.CurrentFluorophore},')']};

                        MinDisp=min(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);
                        MaxDisp=max(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);

                        DisplayImage(app, app.smis_gui_parameters.in_images{app.CurrentFluorophore}, MinDisp, MaxDisp)
                    else
                        app.VirtualSampleLabel.Visible = 'off';
                        app.ImageAxes.Visible = 'off';
                        app.UpperSlider.Visible='off';
                        app.UpperSliderLabel.Visible='off';
                        app.LowerSlider.Visible='off';
                        app.LowerSliderLabel.Visible='off';
                    end
                end
            end
            figure(app.UIFigure); % Get the GUI in the front
            app.LoadsimulationparametersButton.Enable='on';
        end

        % Value changed function: OutputStackFilenameEditField
        function OutputStackFilenameEditFieldValueChanged(app, event)
            value = app.OutputStackFilenameEditField.Value;
            app.smis_gui_parameters.outfilename=value;
        end

        % Value changed function: OutputDirectoryEditField
        function OutputDirectoryEditFieldValueChanged(app, event)
            value = app.OutputDirectoryEditField.Value;
            
            if ~exist(value,'dir')
                if check_dir(app,value)==1
                    disp('Creating directory ...')
                    mkdir(value)
                    app.smis_gui_parameters.outfiledir=value;
                else
                    app.smis_gui_parameters.outfiledir=event.PreviousValue;
                    app.OutputDirectoryEditField.Value=event.PreviousValue;
                end
                
            else % Accept in any case
                app.smis_gui_parameters.outfiledir=value;
            end
        end

        % Value changed function: SMISSimulationTitleEditField
        function SMISSimulationTitleEditFieldValueChanged(app, event)
            value = app.SMISSimulationTitleEditField.Value;
            app.smis_gui_parameters.smis_title=value;
        end

        % Value changed function: RastersizenmEditField
        function RastersizenmEditFieldValueChanged(app, event)
            value = app.RastersizenmEditField.Value;
            app.smis_gui_parameters.raster=value;
        end

        % Value changed function: BinningfactorEditField
        function BinningfactorEditFieldValueChanged(app, event)
            value = app.BinningfactorEditField.Value;
            app.smis_gui_parameters.binning=value;

            % Update n,m,nz
            % Check new values of n,m,z if virtual samples have been loaded
            if ~isempty(app.smis_gui_parameters.image_size.n) 
                is_ok=1;
                % Check eventual cropping, start with the first fluorophore
                app.CurrentFluorophore=1;

                if isempty(app.smis_gui_parameters.in_images{1})
                    if ~exist(app.smis_gui_parameters.in_images_dir{1},'dir')
                        if app.smis_gui_parameters.simul_3D==0
                            if app.smis_gui_parameters.distribute_in_clusters==0 % Not qPALM mode
                                app.smis_gui_parameters.in_images_dir(1)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D')};
                            else % qPALM mode
                                app.smis_gui_parameters.in_images_dir(1)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D','QPALM')};
                            end
                        else
                            if app.smis_gui_parameters.distribute_in_clusters==0 % Not qPALM mode
                                app.smis_gui_parameters.in_images_dir(1)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D')};
                            else % qPALM mode
                                app.smis_gui_parameters.in_images_dir(1)={fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D','QPALM')};
                            end
                        end
                    end

                    % Check that the sample virtual image patterns exist
                    MyPath=app.smis_gui_parameters.in_images_dir{1};
                    MyFile=app.smis_gui_parameters.in_images_names{1};
                    if exist(fullfile(MyPath,MyFile),'file')==2
                        % Read the pattern and update n, m, nz
                        [app.smis_gui_parameters.in_images{1},n,m,nz,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                    else
                        MyDlg=warndlg(['Virtual sample not found for fluorophore ',app.smis_gui_parameters.Fluorophores(1).name],'Error');
                        waitfor(MyDlg)
                    end
                else % General case for SMIS vsn > 2.2
                    [app.smis_gui_parameters.in_images{1},n,m,nz]=check_im_size(app.smis_gui_parameters.in_images{1},app.smis_gui_parameters.binning);
                end
              
               
                % Check consistency with other virtual samples
                if app.smis_gui_parameters.n_fluorophores>1
                    for i=1:app.smis_gui_parameters.n_fluorophores
                        if i~=app.CurrentFluorophore
                            if isempty(app.smis_gui_parameters.in_images{i})
                                MyPath=app.smis_gui_parameters.in_images_dir{i};
                                MyFile=app.smis_gui_parameters.in_images_names{i};

                                if exist(fullfile(MyPath,MyFile),'file')==2
                                    % Read the pattern and update n, m, nz
                                    [app.smis_gui_parameters.in_images{i},n2,m2,nz2,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                                else
                                    MyDlg=warndlg(['Virtual sample not found for fluorophore ',app.smis_gui_parameters.Fluorophores(i).name],'Error');
                                    waitfor(MyDlg)
                                end
                            else % General case for SMIS vsn > 2.2
                                [app.smis_gui_parameters.in_images{i},n2,m2,nz2]=check_im_size(app.smis_gui_parameters.in_images{i},app.smis_gui_parameters.binning);
                            end

                            if n2~=n || m2~=m || nz2~=nz
                                FluorophoreName=app.smis_gui_parameters.Fluorophores(i).name;
                                CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
                                disp(['Virtual sample size for fluorophore ',CurrentFluorophoreName,' is: ', num2str([n,m,nz])]);
                                disp(['Virtual sample size for fluorophore ',FluorophoreName,' is: ', num2str([n2,m2,nz2])]);
                                MyDlg=warndlg('Incompatible image size: check virtual samples images !','Error');
                                waitfor(MyDlg)
                                app.smis_gui_parameters.binning=event.PreviousValue;
                                app.BinningfactorEditField.Value=app.smis_gui_parameters.binning;
                                is_ok=0;
                                break
                            end
                        end
                    end
                end
                
                if is_ok==1
                    app.smis_gui_parameters.image_size.n=n;
                    app.smis_gui_parameters.image_size.m=m;
                    if app.smis_gui_parameters.simul_3D
                        app.smis_gui_parameters.image_size.nz=nz;
                    end
                end
            end
                
            % Update the fields
            updateSMIS(app)
        end

        % Value changed function: TimebetweenframesmsEditField
        function TimebetweenframesmsEditFieldValueChanged(app, event)
            value = app.TimebetweenframesmsEditField.Value;
            app.smis_gui_parameters.addtime=value;
            if any([app.smis_gui_parameters.lasers.on_during_addtime]==1) && value==0
                MyDlg=warndlg('Lasers on during addtime will be switched to on during frametime !', 'warning');
                waitfor(MyDlg)
                [app.smis_gui_parameters.lasers.on_during_addtime]=deal(0);
                [app.smis_gui_parameters.lasers.on_during_frametime]=deal(1);
            end
        end

        % Button pushed function: ObjectiveandPSFButton
        function ObjectiveandPSFButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ObjectiveandPSFButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.ObjectivePSF_App = ObjectivePSF(app);
        end

        % Value changed function: NumberoflasersEditField
        function NumberoflasersEditFieldValueChanged(app, event)
            value = app.NumberoflasersEditField.Value;
            app.smis_gui_parameters.n_lasers=value;
            
            laser_names=app.SetupLaserDropDown.Items;
            
            laser_names(1) = {'Choose'};
            for k=2:event.PreviousValue+1
                laser_names(k) = {app.smis_gui_parameters.lasers(k-1).name};
            end
            app.SetupLaserDropDown.Items=laser_names;
            
            %Adjust the # of lasers and associated fields
            if event.Value>event.PreviousValue
                app.smis_gui_parameters.lasers(event.PreviousValue+1:event.Value)=app.smis_gui_parameters.lasers(event.PreviousValue);
                laser_names = cell(1, value+1);
                laser_names(1:event.PreviousValue+1)=app.SetupLaserDropDown.Items;
                for k=(event.PreviousValue+1):event.Value
                    laser_names(k+1) = {['Laser ',num2str(k)]};
                    app.smis_gui_parameters.lasers(k).name=laser_names{k+1};
                end
                app.smis_gui_parameters.lasers(event.Value+1:end)=[];
                app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1(event.PreviousValue+1:event.Value)=app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1(event.PreviousValue);
                app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2(event.PreviousValue+1:event.Value)=app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2(event.PreviousValue);
                app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1(event.PreviousValue+1:event.Value)=app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1(event.PreviousValue);
                app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2(event.PreviousValue+1:event.Value)=app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2(event.PreviousValue);
                app.SetupLaserDropDown.Items=laser_names;
            elseif event.Value<event.PreviousValue
                app.SetupLaserDropDown.Items(event.Value+2:end)=[];
                app.smis_gui_parameters.lasers(event.Value+1:end)=[];
                app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1(event.Value+1:end)=[];
                app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2(event.Value+1:end)=[];
                app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1(event.Value+1:end)=[];
                app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2(event.Value+1:end)=[];
            end
            
            % Normalize laser_sensitivities
            app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1=app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1/sum(app.smis_gui_parameters.BG.bg_laser_sensitivity_ch1);
            app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2=app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2/sum(app.smis_gui_parameters.BG.bg_laser_sensitivity_ch2);
            app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1=app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1/sum(app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch1);
            app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2=app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2/sum(app.smis_gui_parameters.BG.textured_bg_laser_sensitivity_ch2);
            
            app.SetupLaserDropDown.ItemsData=1:value+1;
        end

        % Button pushed function: EMCCDCameraButton
        function EMCCDCameraButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.EMCCDCameraButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.EMCCD_App = EMCCD(app, app.smis_gui_parameters.EMCCD);
        end

        % Value changed function: SetupLaserDropDown
        function SetupLaserDropDownValueChanged(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupLaserDropDown.Enable='off';
            
            selected_laser = app.SetupLaserDropDown.Value-1;
            
            % Open the options dialog and pass inputs
            app.LaserSetup_App = LaserSetup(app, selected_laser);
        end

        % Button pushed function: FluorescencebackgroundButton
        function FluorescencebackgroundButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.FluorescencebackgroundButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.BG_App = BackgroundFluorescence(app);
        end

        % Value changed function: NumberofacquisitionchannelsSwitch
        function NumberofacquisitionchannelsSwitchValueChanged(app, event)
            value = app.NumberofacquisitionchannelsSwitch.Value;
            app.smis_gui_parameters.TwoChannel.state=str2double(value)-1;
        end

        % Value changed function: NumberofframesEditField
        function NumberofframesEditFieldValueChanged(app, event)
            value = app.NumberofframesEditField.Value;
            app.smis_gui_parameters.n_images=value;
            
            %The laser sequence must be reset
            for k=1:app.smis_gui_parameters.n_lasers
                if value>event.PreviousValue % Append
                    if numel(unique(app.smis_gui_parameters.lasers(k).sequence))~=1
                        MyDlg=warndlg(['Laser sequence for ',app.smis_gui_parameters.lasers(k).name,' will be reset !']);
                        waitfor(MyDlg)
                    end
                    app.smis_gui_parameters.lasers(k).sequence=100*ones(1,value);
                else % Just cut
                    app.smis_gui_parameters.lasers(k).sequence=app.smis_gui_parameters.lasers(k).sequence(1:value);
                end
            end

            %The drift sequence must be updated
            if app.smis_gui_parameters.drift.state==1
                if value>event.PreviousValue % Append
                    MyDlg=warndlg('Drift must be recalculated !');
                    waitfor(MyDlg)
                    
                    app.smis_gui_parameters.drift.dx=[];
                    app.smis_gui_parameters.drift.dy=[];
                    app.smis_gui_parameters.drift.dz=[];
                    app.smis_gui_parameters.drift.dtheta=[];
                    app.smis_gui_parameters.drift.state=0;
                else % Just cut
                    app.smis_gui_parameters.drift.dx=app.smis_gui_parameters.drift.dx(1:value);
                    app.smis_gui_parameters.drift.dy=app.smis_gui_parameters.drift.dy(1:value);
                    if ~isempty(app.smis_gui_parameters.drift.dz)
                        app.smis_gui_parameters.drift.dz=app.smis_gui_parameters.drift.dz(1:value);
                    end
                    if ~isempty(app.smis_gui_parameters.drift.dtheta)
                        app.smis_gui_parameters.drift.dtheta=app.smis_gui_parameters.drift.dtheta(1:value);
                    end
                    
                end
            end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            MyDir = uigetdir(app.smis_gui_parameters.outfiledir,'Select a directory where the SMIS simulation output files will be stored');
            if isequal(MyDir,0)
                disp('User selected Cancel');
            else
                app.smis_gui_parameters.outfiledir=MyDir;
                app.OutputDirectoryEditField.Value=app.smis_gui_parameters.outfiledir;
            end
            figure(app.UIFigure)
        end

        % Value changed function: LoadvirtualsampleDropDown
        function LoadvirtualsampleDropDownValueChanged(app, event)
            value = app.LoadvirtualsampleDropDown.Value;
            app.CurrentFluorophore = value-1;
            if app.CurrentFluorophore<=app.smis_gui_parameters.n_fluorophores
                CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
            else % In this case load the same pattern for all fluorophores fluorophores
                CurrentFluorophoreName=[];
            end
            
            
            if app.smis_gui_parameters.simul_3D==0 % 2D case
                if app.smis_gui_parameters.distribute_in_clusters~=1 && exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D'),'dir')
                    cd(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D'));
                elseif  app.smis_gui_parameters.distribute_in_clusters==1 && exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D','QPALM'),'dir') % Qpalm option
                    cd(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D','QPALM'));
                elseif exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir),'dir')
                    cd(app.smis_gui_parameters.DefaultDirs.Pattern_dir);
                end
                [MyFile,MyPath] = uigetfile({'*.tif';'*.tiff';'*.csv'},['Select a TIF or CSV Virtual sample for Fluorophore: ', CurrentFluorophoreName] );
            else % 3D case
                if app.smis_gui_parameters.distribute_in_clusters~=1 && exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D'),'dir')
                    cd(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D'));
                elseif  app.smis_gui_parameters.distribute_in_clusters==1 && exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D','QPALM'),'dir') % Qpalm option
                    cd(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir,'3D','QPALM'));
                elseif exist(fullfile(app.smis_gui_parameters.DefaultDirs.Pattern_dir),'dir')
                    cd(app.smis_gui_parameters.DefaultDirs.Pattern_dir);
                end
                [MyFile,MyPath] = uigetfile({'*.mat';'*.tif';'*.tiff';'*.csv'},['Select a MATLAB, TIF or CSV 3D Pattern For Fluorophore:', CurrentFluorophoreName] );
            end
           
            if ~isequal(MyFile,0)
                %Update the path to Pattern library
                app.smis_gui_parameters.DefaultDirs.Pattern_dir=MyPath; % Update the SMIS_Simulations_dir


                % Read the pattern
                [Pattern,n,m,nz,n_sp] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                Pattern=squeeze(Pattern);
                
                % See if the pattern can be accepted: size needs to be compatible with eventual already loaded patterns
                MySel='OK'; % Initialize my selection
                % Look whether there is a change in image dimension
                DimChange=app.smis_gui_parameters.n_fluorophores>1 && app.CurrentFluorophore<=app.smis_gui_parameters.n_fluorophores && ~isempty(app.smis_gui_parameters.image_size.n) && ...
                    (app.smis_gui_parameters.image_size.n~=n || app.smis_gui_parameters.image_size.m~=m || app.smis_gui_parameters.image_size.nz~=nz);
                if DimChange==1
                    % Display a dialog box saying that pattern will be reset for all other fluorophores !
                    fig = uifigure;
                    fig.Position = [800 600 400 160];
                    MySel = uiconfirm(fig,'Image size changed ! Okay to reset all previously loaded patterns ?','','Icon','warning');
                    close(fig)
                end % We are okay
                if strcmp(MySel,'OK') == 1
                    app.smis_gui_parameters.image_size.n=n;
                    app.smis_gui_parameters.image_size.m=m;
                    app.smis_gui_parameters.image_size.nz=nz;
                    % Treat the case with all fluorophores
                    if app.CurrentFluorophore>app.smis_gui_parameters.n_fluorophores 
                        app.smis_gui_parameters.in_images_dir(:)=cellstr(MyPath);
                        app.smis_gui_parameters.in_images_names(:)=cellstr(MyFile);
                        app.smis_gui_parameters.in_images(:)={Pattern};

                        [app.smis_gui_parameters.pattern_ids(:).n_sp]=deal(n_sp);
                        [app.smis_gui_parameters.pattern_ids(:).sp_ids]=deal(unique(Pattern));
                    else % Treat the case with a specific fluorophore
                        if DimChange
                            [app.smis_gui_parameters.pattern_ids(:).n_sp]=deal([]);
                            [app.smis_gui_parameters.pattern_ids(:).sp_ids]=deal([]);
                            app.smis_gui_parameters.in_images_dir(:)=cellstr('');
                            app.smis_gui_parameters.in_images_names(:)=cellstr('');
                            app.smis_gui_parameters.in_images(:)={[]};
                        end
                        app.smis_gui_parameters.in_images_dir{app.CurrentFluorophore}=MyPath;
                        app.smis_gui_parameters.in_images_names{app.CurrentFluorophore}=MyFile;
                        app.smis_gui_parameters.in_images{app.CurrentFluorophore}=Pattern;
                        app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).n_sp=n_sp;
                        app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids=unique(Pattern);
                    end
                    
                    
                    % Display the pattern
                    
                    if app.CurrentFluorophore>app.smis_gui_parameters.n_fluorophores % Treat the case with all fluorophores
                        app.VirtualSampleLabel.Text = {'Virtual sample for all fluorophores ',['(',app.smis_gui_parameters.in_images_names{1},')']};
                        MinDisp=min(app.smis_gui_parameters.pattern_ids(1).sp_ids);
                        MaxDisp=max(app.smis_gui_parameters.pattern_ids(1).sp_ids);
                    else
                        CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
                        app.VirtualSampleLabel.Text = {['Virtual sample for fluorophore: ', CurrentFluorophoreName],['(',app.smis_gui_parameters.in_images_names{app.CurrentFluorophore},')']};
                        MinDisp=min(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);
                        MaxDisp=max(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);
                    end
                    
                    % Set the sliders
                    app.UpperSlider.Value=50;
                    app.LowerSlider.Value=0;
                    
                    % Clear displayed data
                    ClearImage(app);
                    % Display the new image
                    DisplayImage(app, Pattern, MinDisp, MaxDisp)
                    
                    % If qPALM option is not , remove it
                    if strcmp(app.qPALMSwitch.Value,'On')==1
                        MyDlg=warndlg('Resetting qPALM option when changing patterns !','Warning');
                        waitfor(MyDlg)
                        app.qPALMSwitch.Value='Off';
                        app.smis_gui_parameters.distribute_in_clusters=0;
                    end
                end
            end
            
            % Reset  menu to first position
            app.LoadvirtualsampleDropDown.Value = 1;
            
            % Update the fields
            updateSMIS(app);
            
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: NumberoffluorophoresEditField
        function NumberoffluorophoresEditFieldValueChanged(app, event)
            n_fluorophores = event.Value;
            old_n_fluorophores = event.PreviousValue;
            %Update number of fluorophores
            app.smis_gui_parameters.n_fluorophores=n_fluorophores;
            
            %Check the names
            fluorophore_names = cell(1, n_fluorophores+2);
            fluorophore_names(1) = {'Choose'};
            if n_fluorophores>old_n_fluorophores % Number of fluorophores was increased
                for i=2:old_n_fluorophores+1 % Get the previous names
                    fluorophore_names(i) = {app.smis_gui_parameters.Fluorophores(i-1).name};
                end
                
                for i=old_n_fluorophores+2:n_fluorophores+1 % Add new names
                    fluorophore_names(i) = {['Fluorophore ',num2str(i-1)]};
                end
            elseif n_fluorophores<old_n_fluorophores % Number of fluorophores was decreased
                for i=2:n_fluorophores+1 % Get the previous names
                    fluorophore_names(i) = {app.smis_gui_parameters.Fluorophores(i-1).name};
                end
                %Check the pairing state
                if app.smis_gui_parameters.Fluorophore_Pairing.state==1
                    n_left_pairs=fix(n_fluorophores/2); % # of pairs possibly left
                    if size(app.smis_gui_parameters.Fluorophore_Pairing.pairs,1)>n_left_pairs
                        app.smis_gui_parameters.Fluorophore_Pairing.pairs=app.smis_gui_parameters.Fluorophore_Pairing.pairs(1:n_left_pairs,:);
                        app.smis_gui_parameters.Fluorophore_Pairing.d1d2_dist=app.smis_gui_parameters.Fluorophore_Pairing.d1d2_dist(1:n_left_pairs,1);
                        app.smis_gui_parameters.Fluorophore_Pairing.d1d2_dist_sig=app.smis_gui_parameters.Fluorophore_Pairing.d1d2_dist_sig(1:n_left_pairs,1);
                        app.smis_gui_parameters.Fluorophore_Pairing.d2_constrained=app.smis_gui_parameters.Fluorophore_Pairing.d2_constrained(1:n_left_pairs,1);
                    end
                end
            end
            
            fluorophore_names(end) = {'All'};
            
            % Disable pairing option if n_fluorophores=1     

            if n_fluorophores==1
                app.smis_gui_parameters.Fluorophore_Pairing.state=0;
                app.FluorophorepairingforcolocalizationorFRETSwitch.Value='Off';
                app.SetuppairingButton.Visible='off';
                app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='off';
                app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='off';
            else
                if strcmp(app.qPALMSwitch.Value,'Off')==1
                    app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='on';
                    app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='on';
                    if app.smis_gui_parameters.Fluorophore_Pairing.state==1
                        app.SetuppairingButton.Visible='on';
                    else
                        app.SetuppairingButton.Visible='off';
                    end
                end
            end
            
            %Update the sample pattern drop-down menu
            app.LoadvirtualsampleDropDown.Items=fluorophore_names;
            app.LoadvirtualsampleDropDown.ItemsData=1:n_fluorophores+2;
            app.ShowvirtualsampleDropDown.Items=fluorophore_names(1:n_fluorophores+1);
            app.ShowvirtualsampleDropDown.ItemsData=1:n_fluorophores+1;
            
            %Update the fluorophore photophysics drop-down menu
            app.SetupFluorophoreDropDown.Items=fluorophore_names(1:n_fluorophores+1);
            app.SetupFluorophoreDropDown.ItemsData=1:n_fluorophores+1;
            
            %Update the diffusion and directed motion drop-down menu
            app.SetupdiffusionanddirectedmotionDropDown.Items=fluorophore_names(1:n_fluorophores+1);
            app.SetupdiffusionanddirectedmotionDropDown.ItemsData=1:n_fluorophores+1;
            
            %Adjust the # of Patterns and the definition of smis_gui_parameters
            if n_fluorophores>old_n_fluorophores % Number of fluorophores was increased
                current_in_images_dir=app.smis_gui_parameters.in_images_dir;
                app.smis_gui_parameters.in_images_dir = repmat({''},n_fluorophores,1);
                current_in_images_names=app.smis_gui_parameters.in_images_names;
                app.smis_gui_parameters.in_images_names = repmat({''},n_fluorophores,1);
                
                app.smis_gui_parameters.in_images_dir(1:old_n_fluorophores,1)=cellstr(current_in_images_dir);
                app.smis_gui_parameters.in_images_dir(old_n_fluorophores+1:n_fluorophores,1)=cellstr(current_in_images_dir{end});
                app.smis_gui_parameters.in_images_names(1:old_n_fluorophores,1)=cellstr(current_in_images_names);
                app.smis_gui_parameters.in_images_names(old_n_fluorophores+1:n_fluorophores,1)=cellstr(current_in_images_names{end});
                % Update the virtual sample itself
                if isfield(app.smis_gui_parameters, 'in_images')
                    app.smis_gui_parameters.in_images(old_n_fluorophores+1:n_fluorophores)=app.smis_gui_parameters.in_images(end);
                end

                
                sp_ids=app.smis_gui_parameters.pattern_ids(end).sp_ids;
                [app.smis_gui_parameters.pattern_ids(old_n_fluorophores+1:n_fluorophores,1).n_sp]=deal(app.smis_gui_parameters.pattern_ids(end).n_sp);
                [app.smis_gui_parameters.pattern_ids(old_n_fluorophores+1:n_fluorophores,1).sp_ids]=deal(sp_ids);
                
                % Copy the new fluorophores from the fluorophore at the end
                % of the previous list but keep the names to avoid confusion
                app.smis_gui_parameters.Fluorophores(old_n_fluorophores+1:n_fluorophores)=app.smis_gui_parameters.Fluorophores(old_n_fluorophores);
                for i=old_n_fluorophores+1:n_fluorophores
                    app.smis_gui_parameters.Fluorophores(i).name=fluorophore_names{i+1};
                end
                
                % Update app.smis_gui_parameters.n_clusters if qPALM is on
                if strcmp(app.qPALMSwitch.Value,'On')==1 % Addedto correct bug 21/03/2024
                    app.smis_gui_parameters.n_clusters(old_n_fluorophores+1:n_fluorophores)=app.smis_gui_parameters.n_clusters(end);
                end

                
            elseif n_fluorophores<old_n_fluorophores % Number of fluorophores was decreased
                app.smis_gui_parameters.in_images_dir(n_fluorophores+1:end)=[];
                app.smis_gui_parameters.in_images_names(n_fluorophores+1:end)=[];
                % Update the virtual sample itself
                if isfield(app.smis_gui_parameters, 'in_images')
                    app.smis_gui_parameters.in_images(n_fluorophores+1:end)=[];
                end

                app.smis_gui_parameters.Fluorophores(n_fluorophores+1:end)=[];
                app.smis_gui_parameters.pattern_ids(n_fluorophores+1:end)=[];
                % Update app.smis_gui_parameters.n_clusters if qPALM is on
                if strcmp(app.qPALMSwitch.Value,'On')==1 % Addedto correct bug 21/03/2024
                    app.smis_gui_parameters.n_clusters(n_fluorophores+1:end)=[];
                end
            end
            
        end

        % Value changed function: ShowvirtualsampleDropDown
        function ShowvirtualsampleDropDownValueChanged(app, event)
            
            value = app.ShowvirtualsampleDropDown.Value;
            app.CurrentFluorophore = value-1;

            % Clear displayed data
            ClearImage(app);

            if isempty(app.smis_gui_parameters.in_images{app.CurrentFluorophore})
                MyPath=app.smis_gui_parameters.in_images_dir{app.CurrentFluorophore};
                MyFile=app.smis_gui_parameters.in_images_names{app.CurrentFluorophore};
               
                if exist(fullfile(MyPath,MyFile),'file')
                    % Read the pattern
                    [Pattern,~,~,~,~,] = read_patterns(cellstr(MyPath), cellstr(MyFile), app.smis_gui_parameters, app.smis_gui_parameters.simul_3D);
                    Pattern=squeeze(Pattern);
                else
                    Pattern=[];
                    app.ImageAxes.Visible = 'off';
                    app.UpperSlider.Visible='off';
                    app.UpperSliderLabel.Visible='off';
                    app.LowerSlider.Visible='off';
                    app.LowerSliderLabel.Visible='off';

                    app.VirtualSampleLabel.Text = '';
                    CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
                    MyDlg=warndlg(['Virtual sample for fluorophore: ',CurrentFluorophoreName, ' has not been loaded !'],'Warning');
                    waitfor(MyDlg)
                end
            else
                Pattern=app.smis_gui_parameters.in_images{app.CurrentFluorophore};
            end

            if ~isempty(Pattern)
                % Display the pattern
                app.ImageAxes.Visible = 'on';
                CurrentFluorophoreName=app.smis_gui_parameters.Fluorophores(app.CurrentFluorophore).name;
                app.VirtualSampleLabel.Text = {['Virtual sample for fluorophore: ', CurrentFluorophoreName],['(',app.smis_gui_parameters.in_images_names{app.CurrentFluorophore},')']};

                MinDisp=min(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);
                MaxDisp=max(app.smis_gui_parameters.pattern_ids(app.CurrentFluorophore).sp_ids);

                DisplayImage(app, Pattern, MinDisp, MaxDisp)
            end
            
            % Reset  menu to first position
            app.ShowvirtualsampleDropDown.Value = 1;
        end

        % Button pushed function: 
        % EmissionFiltersandAquisitionChannelsOptionsButton
        function EmissionFiltersandAquisitionChannelsOptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.EmissionFiltersandAquisitionChannelsOptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.FiltersAndChannels_app = FiltersAndChannels(app, app.smis_gui_parameters);
        end

        % Button pushed function: DefaultSMISDirectoriesButton
        function DefaultSMISDirectoriesButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.DefaultSMISDirectoriesButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.DefaultDirs_App = DefaultDirectories(app, app.smis_gui_parameters.DefaultDirs);
        end

        % Value changed function: SetupFluorophoreDropDown
        function SetupFluorophoreDropDownValueChanged(app, event)
            % Disable Plot Options button while dialog is open
            app.SetupFluorophoreDropDown.Enable = 'off';
            
            selected_fluorophore = app.SetupFluorophoreDropDown.Value-1;
            
            % Open the options dialog and pass inputs
            app.FluorophoreSetup_App = FluorophoreSetup(app, selected_fluorophore);
        end

        % Value changed function: sptPALMSwitch
        function sptPALMSwitchValueChanged(app, event)
            value = app.sptPALMSwitch.Value;
            
            if strcmp(app.qPALMSwitch.Value,'On')==1
                MyDlg=warndlg('Cannot run sptPALM when the qPALM option is on !','Warning');
                waitfor(MyDlg)
                app.sptPALMSwitch.Value='Off';
                value=app.sptPALMSwitch.Value;
            end
            if strcmp(app.FRETSwitch.Value,'On')==1
                MyDlg=warndlg('Cannot run sptPALM when the FRET option is on !','Warning');
                waitfor(MyDlg)
                app.sptPALMSwitch.Value='Off';
                value=app.sptPALMSwitch.Value;
            end

            for i=1:app.smis_gui_parameters.n_fluorophores
                if app.smis_gui_parameters.Fluorophores(i).Linkage.fixed==1
                    MyDlg=warndlg(['Cannot run sptPALM with fixed fluorophore linkage errors: ', app.smis_gui_parameters.Fluorophores(i).name,' !'],'Warning');
                    waitfor(MyDlg)
                    app.sptPALMSwitch.Value='Off';
                    value=app.sptPALMSwitch.Value;
                end
            end

            if strcmp(value,'On')==1
                app.smis_gui_parameters.add_diffusion=1;
                app.SetupdiffusionanddirectedmotionDropDown.Visible='on';
                app.SetupdiffusionanddirectedmotionDropDownLabel.Visible='on';
                app.sptPALMoptionsButton.Visible='on';
            else
                app.smis_gui_parameters.add_diffusion=0;
                app.SetupdiffusionanddirectedmotionDropDown.Visible='off';
                app.SetupdiffusionanddirectedmotionDropDownLabel.Visible='off';
                app.sptPALMoptionsButton.Visible='off';
            end
        end

        % Value changed function: SetupdiffusionanddirectedmotionDropDown
        function SetupdiffusionanddirectedmotionDropDownValueChanged(app, event)
            
            % Disable Plot Options button while dialog is open
            app.SetupdiffusionanddirectedmotionDropDown.Enable = 'off';
            
            selected_fluorophore = app.SetupdiffusionanddirectedmotionDropDown.Value-1;
            
            % Open the options dialog and pass inputs
            app.DiffusionSetup_App = DiffusionSetup(app, selected_fluorophore);
            
            
        end

        % Button pushed function: sptPALMoptionsButton
        function sptPALMoptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.sptPALMoptionsButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.sptPALMOptions_App = sptPALMOptions(app);
        end

        % Value changed function: qPALMSwitch
        function qPALMSwitchValueChanged(app, event)
            value = app.qPALMSwitch.Value;
            
            if strcmp(value,'On')==1
                qPALM_ok=1;
                
                if strcmp(app.sptPALMSwitch.Value,'On')==1
                    MyDlg=warndlg('Cannot run qPALM when the sptPALM option is on !','Warning');
                    waitfor(MyDlg)
                    qPALM_ok=0;
                end
                
                if strcmp(app.FRETSwitch.Value,'On')==1
                    MyDlg=warndlg('Cannot run qPALM when the FRET option is on !','Warning');
                    waitfor(MyDlg)
                    qPALM_ok=0;
                end
                
                if app.smis_gui_parameters.Fluorophore_Pairing.state==1
                    MyDlg=warndlg('Cannot run qPALM when fluorophore pairing option is on !','Warning');
                    waitfor(MyDlg)
                    qPALM_ok=0;
                end
                
                for i=1:app.smis_gui_parameters.n_fluorophores
                    if isempty(app.smis_gui_parameters.pattern_ids(i).n_sp)
                        MyDlg=warndlg(['You first need to load a pattern appropriate for qPALM for for fluorophore ',app.smis_gui_parameters.Fluorophores(i).name,' !'],'Warning');
                        waitfor(MyDlg)
                        qPALM_ok=0;
                    end
                    
                    sp_ids=app.smis_gui_parameters.pattern_ids(i).sp_ids;
                    
                    if app.smis_gui_parameters.pattern_ids(i).n_sp<20
                        MyDlg=warndlg(['It looks like the loaded pattern for fluorophore ',app.smis_gui_parameters.Fluorophores(i).name,' is not appropriate for qPALM: less than 20 subpattern IDs !'],'Warning');
                        waitfor(MyDlg)
                        qPALM_ok=0;
                    end

                    if app.smis_gui_parameters.Fluorophores(i).Linkage.pattern_control==1
                        MyDlg=warndlg('Cannot run qPALM with fluorophore linkage in controlled pattern mode !','Warning');
                        waitfor(MyDlg)
                        qPALM_ok=0;
                    end
                    
                    if ~isequal(sp_ids',0:max(sp_ids))
                        MyDlg=warndlg(['It looks like the loaded pattern for fluorophore ',app.smis_gui_parameters.Fluorophores(i).name,' is not appropriate for qPALM: subpattern IDs do not form a continuous series 1,2,3 ... !'],'Warning');
                        waitfor(MyDlg)
                        qPALM_ok=0;
                    end
                    
                    if qPALM_ok==1
                        app.smis_gui_parameters.distribute_in_clusters=1;
                        app.smis_gui_parameters.n_clusters=[app.smis_gui_parameters.pattern_ids.n_sp]-1; % Only take the non-zero subpattens
                        app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='off';
                        app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='off';
                        app.sptPALMoptionsButton.Visible='off';
                    else
                        app.qPALMSwitch.Value='Off';
                        app.smis_gui_parameters.distribute_in_clusters=0;
                    end
                end
            else
                app.smis_gui_parameters.distribute_in_clusters=0;
                if app.smis_gui_parameters.n_fluorophores > 1
                    app.FluorophorepairingforcolocalizationorFRETSwitch.Visible='on';
                    app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible='on';
                end
            end
        end

        % Value changed function: ShowdataduringacquisitionSwitch
        function ShowdataduringacquisitionSwitchValueChanged(app, event)
            value = app.ShowdataduringacquisitionSwitch.Value;
            if strcmp(value,'On')==1
                % Create a figure if necessary
                create_figure=1;
                % The following code ensures that the right figure is addressed
                % Indeed, it might be that the figure has been created before, then deleted, and the same figure number has been re-created for another purpose and is still open
                
                % Take a look at the current figures open
                figHandles = findobj('Type', 'figure'); % Get the array of all figures open
                n_fig=size(figHandles,1);
                for k=1:n_fig
                    if strcmp(figHandles(k).Name,'SMIS Data Aquisition')==1 % Our figure already exists
                        create_figure=0;
                        emccd_figure_number=figHandles(k).Number;
                    end
                end
                
                % create a figure if necessary
                if create_figure==1
                    emccd_figure=figure;
                    app.smis_gui_parameters.emccd_figure_number=emccd_figure.Number;
                    scrsz=get(0,'ScreenSize');
                    set(emccd_figure,'Position',[10 scrsz(4)/10 scrsz(3)*3/4 scrsz(4)*1/2])
                    set(emccd_figure,'color','white');
                    set(emccd_figure,'Name','SMIS Data Aquisition');
                    set(emccd_figure,'NumberTitle','off');
                else
                    figure(emccd_figure_number);
                    clf;
                end
                app.smis_gui_parameters.show_experiment=1;
                app.smis_gui_parameters.show_data_aquisition=1;
                app.AutodisplayrangeSwitch.Visible='on';
                app.AutodisplayrangeSwitchLabel.Visible='on';
                if strcmp(app.AutodisplayrangeSwitch.Value,'Off')==1
                    app.DisplayrangeparametersButton.Visible='on';
                end
            else
                if ~isempty(app.smis_gui_parameters.emccd_figure_number) && ishandle(app.smis_gui_parameters.emccd_figure_number)~=0
                    close(app.smis_gui_parameters.emccd_figure_number);
                end
                app.smis_gui_parameters.show_experiment=0;
                app.smis_gui_parameters.show_data_aquisition=0;
                app.AutodisplayrangeSwitch.Visible='off';
                app.AutodisplayrangeSwitchLabel.Visible='off';
                app.DisplayrangeparametersButton.Visible='off';
            end
        end

        % Value changed function: 
        % ShowphotophysicsstatisticsatendofsimulationSwitch
        function ShowphotophysicsstatisticsatendofsimulationSwitchValueChanged(app, event)
            value = app.ShowphotophysicsstatisticsatendofsimulationSwitch.Value;
            if strcmp(value,'On')==1
                
                % Create a figure if necessary
                create_figure=1;
                % The following code ensures that the right figure is addressed
                % Indeed, it might be that the figure has been created before, then deleted, and the same figure number has been re-created for another purpose and is still open
                
                % Take a look at the current figures open
                figHandles = findobj('Type', 'figure'); % Get the array of all figures open
                n_fig=size(figHandles,1);
                for k=1:n_fig
                    if strcmp(figHandles(k).Name,'Photophysics Summary')==1 % Our figure already exists
                        create_figure=0;
                        photophysics_figure_number=figHandles(k).Number;
                    end
                end
                
                % create a figure if necessary
                if create_figure==1
                    photophysics_figure=figure;
                    app.smis_gui_parameters.photophysics_figure_number=photophysics_figure.Number;
                    scrsz=get(0,'ScreenSize');
                    set(photophysics_figure,'Position',[10 scrsz(4)/10 scrsz(3)*3/5 scrsz(4)*3/5])
                    set(photophysics_figure,'color','white');
                    set(photophysics_figure,'Name','Photophysics Summary');
                    set(photophysics_figure,'NumberTitle','off');
                else
                    figure(photophysics_figure_number);
                    clf;
                end
                app.smis_gui_parameters.show_experiment=1;
                app.smis_gui_parameters.show_photophysics=1;
            else
                app.smis_gui_parameters.show_experiment=0;
                app.smis_gui_parameters.show_photophysics=0;
            end
        end

        % Button pushed function: ShowlasersButton
        function ShowlasersButtonPushed(app, event)
            app.smis_gui_parameters.lasers_figure_number=DisplayLasers(app.smis_gui_parameters);
        end

        % Button pushed function: ShowPSFButton
        function ShowPSFButtonPushed(app, event)
            app.PSF_App = DisplayPSF(app);
        end

        % Value changed function: SampledriftSwitch
        function SampledriftSwitchValueChanged(app, event)
            value = app.SampledriftSwitch.Value;
            if strcmp(value,'On')
                app.smis_gui_parameters.drift.state=1;
                app.EditDriftButton.Visible='on';
            else
                app.smis_gui_parameters.drift.state=0;
                app.EditDriftButton.Visible='off';
            end
        end

        % Button pushed function: EditDriftButton
        function EditDriftButtonPushed(app, event)
            
            % Disable Plot Options button while dialog is open
            app.EditDriftButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.Drift_App = Drift(app);
        end

        % Button pushed function: ShowfluorophorespectraButton
        function ShowfluorophorespectraButtonPushed(app, event)
            app.smis_gui_parameters.spectra_figure_number=DisplaySpectra(app.smis_gui_parameters);
        end

        % Button pushed function: SetuppairingButton
        function SetuppairingButtonPushed(app, event)
            app.FluorophorePairing_App = FluorophorePairing(app);
            % Disable the button
            app.SetuppairingButton.Enable = 'off';
        end

        % Value changed function: 
        % FluorophorepairingforcolocalizationorFRETSwitch
        function FluorophorepairingforcolocalizationorFRETSwitchValueChanged(app, event)
            value = app.FluorophorepairingforcolocalizationorFRETSwitch.Value;
            if strcmp(value,'On')==1
                app.smis_gui_parameters.Fluorophore_Pairing.state=1;
                app.SetuppairingButton.Visible='on';
            else
                app.smis_gui_parameters.Fluorophore_Pairing.state=0;
                app.SetuppairingButton.Visible='off';
            end
        end

        % Button pushed function: OthergeneralparametersButton
        function OthergeneralparametersButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OthergeneralparametersButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.GeneralPar_App = GeneralPar(app);
        end

        % Value changed function: AutodisplayrangeSwitch
        function AutodisplayrangeSwitchValueChanged(app, event)
            value = app.AutodisplayrangeSwitch.Value;
            if strcmp(value,'On')
                app.smis_gui_parameters.ImageDisplay.auto_display_range=1;
                app.DisplayrangeparametersButton.Visible=0;
            else
                app.smis_gui_parameters.ImageDisplay.auto_display_range=0;
                app.DisplayrangeparametersButton.Visible=1;
            end
        end

        % Button pushed function: DisplayrangeparametersButton
        function DisplayrangeparametersButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.DisplayrangeparametersButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.DisplayPar_App = DisplayPar(app);
        end

        % Button pushed function: FluorescencesamplingrateButton
        function FluorescencesamplingrateButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.FluorescencesamplingrateButton.Enable='off';
            
            % Open the options dialog and pass inputs
            app.SamplingPar_App = SamplingPar(app);
        end

        % Button pushed function: SavesimulationparametersButton
        function SavesimulationparametersButtonPushed(app, event)
            path=app.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir;
            SMIS_par_file=[deblank(app.smis_gui_parameters.smis_title),'_SMIS_par.mat'];
            
            cd(path);
            [SMIS_par_file,SMIS_par_path] = uiputfile('*.mat','SMIS File Selection',SMIS_par_file);
            if ~isnumeric(SMIS_par_path) || ~isnumeric(SMIS_par_file)
                app.smis_gui_parameters.DefaultDirs.SMIS_Simulations_dir=SMIS_par_path;
                smis_par=app.smis_gui_parameters;
                save(fullfile(SMIS_par_path,SMIS_par_file),'smis_par');
            end
            
            figure(app.UIFigure); % Get the GUI in the front
        end

        % Value changed function: UpperSlider
        function UpperSliderValueChanged(app, event)
            value = app.UpperSlider.Value;
            
            if value>app.LowerSlider.Value
                Range=app.ImageAxes.CLim(2)*100/event.PreviousValue;
                MinDisp=app.ImageAxes.CLim(1);
                MaxDisp=Range*value/100;
                disp(['New limits: ',num2str([MinDisp MaxDisp])]);
                app.ImageAxes.CLim=[MinDisp MaxDisp];
            else
                MyDlg=warndlg('Upper display range must be higher than lower display range !','Warning');
                waitfor(MyDlg)
            end
            
            
        end

        % Value changed function: LowerSlider
        function LowerSliderValueChanged(app, event)
            value = app.LowerSlider.Value;
            
            
            if value<app.UpperSlider.Value
                Range=app.ImageAxes.CLim(2)*100/app.UpperSlider.Value;
                MinDisp=Range*value/100;
                MaxDisp=app.ImageAxes.CLim(2);
                disp(['New limits: ',num2str([MinDisp MaxDisp])]);
                app.ImageAxes.CLim=[MinDisp MaxDisp];
            else
                MyDlg=warndlg('Upper display range must be higher than lower display range !','Warning');
                waitfor(MyDlg)
            end
            
        end

        % Value changed function: ForceOverwriteCheckBox
        function ForceOverwriteCheckBoxValueChanged(app, event)
            app.smis_gui_parameters.force_overwrite = app.ForceOverwriteCheckBox.Value;
        end

        % Button pushed function: SMISquickhelpButton
        function SMISquickhelpButtonPushed(app, event)
            % Open the quick help
            QuickHelp(app);
        end

        % Value changed function: FrametimemsEditField
        function FrametimemsEditFieldValueChanged(app, event)
            value = app.FrametimemsEditField.Value;
            app.smis_gui_parameters.frametime=value;
        end

        % Button pushed function: LaunchensemblesimulationButton
        function LaunchensemblesimulationButtonPushed(app, event)
            if exist(app.smis_gui_parameters.outfiledir,'dir')
                app.STOPCheckBox.Value=0;
                app.STOPCheckBox.Visible='on';
                app.SMISProgressGauge.Visible='on';
                app.SMISProgressGaugeLabel.Visible='on';
                app.SMISProgressGauge.BackgroundColor=[1,1,0];
                app.SMISProgressGauge.Value=0;
                smis_ok=smis_vsn2_3_ensemble_main(app, app.smis_gui_parameters);
                if smis_ok==1
                    msgbox('SMIS simulation done !','Success','custom',app.SMIS_Logo,[]);
                elseif smis_ok==2
                    msgbox('SMIS simulation interrupted !','Warning','custom',app.SMIS_Logo,[]);
                elseif smis_ok==3
                    % do nothing if simulation canceled from beginning !
                else
                    msgbox('SMIS simulation error ! Check faulty parameter !','Warning','custom',app.SMIS_Logo,[]);
                end
                app.STOPCheckBox.Visible='off';
                app.SMISProgressGauge.Visible='off';
                app.SMISProgressGaugeLabel.Visible='off';
                app.LaunchsimulationButton.Enable='on';
            else
                MyDlg=warndlg('The output directory for the simulation data does not exist !','Warning');
            end
        end

        % Button pushed function: AnalyseResultsButton
        function AnalyseResultsButtonPushed(app, event)
            
            % Open the options dialog and pass inputs
            app.AnalyzeResults_App = AnalyzeResults(app);
        end

        % Value changed function: UseparallelcomputingCheckBox
        function UseparallelcomputingCheckBoxValueChanged(app, event)
            value = app.UseparallelcomputingCheckBox.Value;
            if value==true
                app.smis_gui_parameters.General.use_parallel_computing=1;
                n_cores=feature('NumCores');
                pc=parcluster;
                pc.NumWorkers=max((n_cores<9)*n_cores,ceil(n_cores*0.8)); % Set # of workers depending on used machine
                disp(['Number of workers set to: ',num2str(pc.NumWorkers)])
                ps = parallel.Settings;
                ps.Pool.AutoCreate=false; % Do not automatically start the PCT
            else
                app.smis_gui_parameters.General.use_parallel_computing=0;
            end
        end

        % Button pushed function: CreateVirtualSamplesButton
        function CreateVirtualSamplesButtonPushed(app, event)
              app.CreatePattern_App = CreatePattern(app);
        end

        % Button pushed function: CreateFluorophoresButton
        function CreateFluorophoresButtonPushed(app, event)
             DefineNewFluorophore(app);
             app.CreateFluorophoresButton.Enable='off';
        end

        % Button pushed function: ShowsampleButton
        function ShowsampleButtonPushed(app, event)
           
            if ~isempty(app.smis_gui_parameters.in_images{app.CurrentFluorophore})                 
                DisplayVirtualSample(app,app.CurrentFluorophore);
                % DisplaySample(app.smis_gui_parameters,app.CurrentFluorophore);
            else
                MyDlg=warndlg(['Image pattern for fluorophore: ',app.Fluorophorepar.name, ' has not been loaded !'],'Warning');
                waitfor(MyDlg);
            end


            % app.smis_gui_parameters.sample_figure_number=DisplaySample(app.smis_gui_parameters, app.CurrentFluorophore);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SMIS VSN 2.3.7';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.Visible = 'off';
            app.NumberoffluorophoresEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoffluorophoresEditFieldLabel.Text = 'Number of fluorophores';
            app.NumberoffluorophoresEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoffluorophoresEditField.Limits = [0 Inf];
            app.NumberoffluorophoresEditField.RoundFractionalValues = 'on';
            app.NumberoffluorophoresEditField.ValueDisplayFormat = '%.0f';
            app.NumberoffluorophoresEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoffluorophoresEditFieldValueChanged, true);
            app.NumberoffluorophoresEditField.Tooltip = {'Define here the number of different fluorophores used for your (possibly multicolor) SMIS simulation. For example, input 3 if you want to use three different fluorophores such as mEos4b, Alexa647, and beads.  '};
            app.NumberoffluorophoresEditField.Value = 1;
            app.NumberoflasersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflasersEditFieldLabel.Text = 'Number of lasers';
            app.NumberoflasersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflasersEditField.Limits = [1 100];
            app.NumberoflasersEditField.RoundFractionalValues = 'on';
            app.NumberoflasersEditField.ValueDisplayFormat = '%.0f';
            app.NumberoflasersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflasersEditFieldValueChanged, true);
            app.NumberoflasersEditField.Tooltip = {'Choose here the number of lasers that will be necessary for your SMIS simulation'};
            app.NumberoflasersEditField.Value = 2;
            app.SetupFluorophoreDropDownLabel = uilabel(app.UIFigure);
            app.SetupFluorophoreDropDownLabel.Text = 'Setup Fluorophore';
            app.SetupFluorophoreDropDown = uidropdown(app.UIFigure);
            app.SetupFluorophoreDropDown.Items = {'Choose', 'Fluorophore 1'};
            app.SetupFluorophoreDropDown.ItemsData = {'1', '2'};
            app.SetupFluorophoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupFluorophoreDropDownValueChanged, true);
            app.SetupFluorophoreDropDown.Tooltip = {'Choose the fluorophore you want to set up. This will open a menu where you can load a fluorophore definition file, and adjust all parameters concerning this fluorophore. It is advisable to load a virtual sample corresponding to the chosen fluorophore beforehand.'};
            app.SetupFluorophoreDropDown.Value = '1';
            app.ShowvirtualsampleDropDownLabel = uilabel(app.UIFigure);
            app.ShowvirtualsampleDropDownLabel.Text = 'Show virtual sample';
            app.ShowvirtualsampleDropDown = uidropdown(app.UIFigure);
            app.ShowvirtualsampleDropDown.Items = {'Choose', 'Fluorophore 1', 'All'};
            app.ShowvirtualsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowvirtualsampleDropDownValueChanged, true);
            app.ShowvirtualsampleDropDown.Tooltip = {'Show a previously loaded virtual sample associated to a particular fluorophore.'};
            app.ShowvirtualsampleDropDown.Value = 'Choose';
            app.FluorescencebackgroundButton = uibutton(app.UIFigure, 'push');
            app.FluorescencebackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @FluorescencebackgroundButtonPushed, true);
            app.FluorescencebackgroundButton.Tooltip = {'Click this button to define fluorescence background '};
            app.FluorescencebackgroundButton.Text = 'Fluorescence background ';
            app.SetupLaserDropDownLabel = uilabel(app.UIFigure);
            app.SetupLaserDropDownLabel.Text = 'Setup  Laser';
            app.SetupLaserDropDown = uidropdown(app.UIFigure);
            app.SetupLaserDropDown.Items = {'Choose ', 'Laser 1', 'Laser 2'};
            app.SetupLaserDropDown.ItemsData = {'1', '2', '3', ''};
            app.SetupLaserDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupLaserDropDownValueChanged, true);
            app.SetupLaserDropDown.Tooltip = {'Use this menu to define the properties of each laser.'};
            app.SetupLaserDropDown.Value = '1';
            app.ShowfluorophorespectraButton = uibutton(app.UIFigure, 'push');
            app.ShowfluorophorespectraButton.ButtonPushedFcn = createCallbackFcn(app, @ShowfluorophorespectraButtonPushed, true);
            app.ShowfluorophorespectraButton.Tooltip = {'Click this button to show Absorption/Excitation and fluorescence emission spectra of the fluorophores '};
            app.ShowfluorophorespectraButton.Text = 'Show fluorophore spectra';
            app.SampledriftSwitchLabel = uilabel(app.UIFigure);
            app.SampledriftSwitchLabel.Text = 'Sample drift';
            app.SampledriftSwitch = uiswitch(app.UIFigure, 'slider');
            app.SampledriftSwitch.ValueChangedFcn = createCallbackFcn(app, @SampledriftSwitchValueChanged, true);
            app.SampledriftSwitch.Tooltip = {'Use this switch to enable drifting of the sample during data collection'};
            app.DSwitchLabel = uilabel(app.UIFigure);
            app.DSwitchLabel.Text = '3D';
            app.DSwitch = uiswitch(app.UIFigure, 'slider');
            app.DSwitch.ValueChangedFcn = createCallbackFcn(app, @DSwitchValueChanged, true);
            app.DSwitch.Tooltip = {'Toggle between 2D and 3D simulations. When this toggle is pressed, this will erase any previously loaded virtual samples.'};
            app.EditDriftButton = uibutton(app.UIFigure, 'push');
            app.EditDriftButton.ButtonPushedFcn = createCallbackFcn(app, @EditDriftButtonPushed, true);
            app.EditDriftButton.Tooltip = {'Press this button to define the drift parameters'};
            app.EditDriftButton.Text = 'Edit Drift';
            app.RastersizenmEditFieldLabel = uilabel(app.UIFigure);
            app.RastersizenmEditFieldLabel.Text = 'Raster size [nm]';
            app.RastersizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RastersizenmEditField.Limits = [1 Inf];
            app.RastersizenmEditField.ValueChangedFcn = createCallbackFcn(app, @RastersizenmEditFieldValueChanged, true);
            app.RastersizenmEditField.Tooltip = {'Pixel size of the recorded fluorescence images'};
            app.RastersizenmEditField.Value = 100;
            app.FrametimemsEditFieldLabel = uilabel(app.UIFigure);
            app.FrametimemsEditFieldLabel.Text = 'Frametime [ms]';
            app.FrametimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrametimemsEditField.Limits = [0.001 Inf];
            app.FrametimemsEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimemsEditFieldValueChanged, true);
            app.FrametimemsEditField.Tooltip = {'EMCCD exposure time for each frame'};
            app.FrametimemsEditField.Value = 50;
            app.TimebetweenframesmsEditFieldLabel = uilabel(app.UIFigure);
            app.TimebetweenframesmsEditFieldLabel.Text = 'Time between frames [ms]';
            app.TimebetweenframesmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimebetweenframesmsEditField.Limits = [0 Inf];
            app.TimebetweenframesmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimebetweenframesmsEditFieldValueChanged, true);
            app.TimebetweenframesmsEditField.Tooltip = {'Additional time between EMCCD exposures, during which laser illumination can be applied (e.g. 405 nm light) and thermal relaxation may occur.'};
            app.BinningfactorEditFieldLabel = uilabel(app.UIFigure);
            app.BinningfactorEditFieldLabel.Text = 'Binning factor';
            app.BinningfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinningfactorEditField.Limits = [1 Inf];
            app.BinningfactorEditField.RoundFractionalValues = 'on';
            app.BinningfactorEditField.ValueDisplayFormat = '%.0f';
            app.BinningfactorEditField.ValueChangedFcn = createCallbackFcn(app, @BinningfactorEditFieldValueChanged, true);
            app.BinningfactorEditField.Tooltip = {'The binning factor is the ratio between the size of the virtual sample patterns loaded and the size of the recorded fluorescence image on the detector. For a defined virtual sample, increasing the binning factor will produce smaller images, corresponding also to a smaller field of view.'};
            app.BinningfactorEditField.Value = 4;
            app.ShowdataduringacquisitionSwitchLabel = uilabel(app.UIFigure);
            app.ShowdataduringacquisitionSwitchLabel.Text = 'Show data during acquisition ';
            app.ShowdataduringacquisitionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowdataduringacquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowdataduringacquisitionSwitchValueChanged, true);
            app.ShowdataduringacquisitionSwitch.Tooltip = {'Use this switch to display recorded frames along data acquisition. This will open an empty Matlab window until the simulation is launched.'};
            app.ShowphotophysicsstatisticsatendofsimulationSwitchLabel = uilabel(app.UIFigure);
            app.ShowphotophysicsstatisticsatendofsimulationSwitchLabel.Text = 'Show photophysics statistics at end of simulation  ';
            app.ShowphotophysicsstatisticsatendofsimulationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowphotophysicsstatisticsatendofsimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowphotophysicsstatisticsatendofsimulationSwitchValueChanged, true);
            app.ShowphotophysicsstatisticsatendofsimulationSwitch.Tooltip = {'Use this switch to show a summary of the fluorophore photophysics at the end of the SMIS simulation (progress of activation, bleaching and blinking)'};
            app.FluorescencesamplingrateButton = uibutton(app.UIFigure, 'push');
            app.FluorescencesamplingrateButton.ButtonPushedFcn = createCallbackFcn(app, @FluorescencesamplingrateButtonPushed, true);
            app.FluorescencesamplingrateButton.Tooltip = {'Click this button to define the sampling rate (time resolution) of the fluorescence traces that will be calculated by SMIS. A high sampling rate will slow down simulations. A low sampling rate will not be able to catch rapid photo transformations, and in that case SMIS will automatically raise the sampling rate.'};
            app.FluorescencesamplingrateButton.Text = 'Fluorescence sampling rate ';
            app.NumberofacquisitionchannelsSwitchLabel = uilabel(app.UIFigure);
            app.NumberofacquisitionchannelsSwitchLabel.Text = 'Number of acquisition channels';
            app.NumberofacquisitionchannelsSwitch = uiswitch(app.UIFigure, 'slider');
            app.NumberofacquisitionchannelsSwitch.Items = {'1', '2'};
            app.NumberofacquisitionchannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NumberofacquisitionchannelsSwitchValueChanged, true);
            app.NumberofacquisitionchannelsSwitch.Tooltip = {'Define here the number of acquisition channels of the microscope. No more than two channels are presently possible.'};
            app.NumberofacquisitionchannelsSwitch.Value = '1';
            app.EmissionFiltersandAquisitionChannelsOptionsButton = uibutton(app.UIFigure, 'push');
            app.EmissionFiltersandAquisitionChannelsOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @EmissionFiltersandAquisitionChannelsOptionsButtonPushed, true);
            app.EmissionFiltersandAquisitionChannelsOptionsButton.Tooltip = {'Click this button to define the emission filters and possible defocus or deformation of the second channel relative to the first (when two channels are used)'};
            app.EmissionFiltersandAquisitionChannelsOptionsButton.Text = 'Emission Filters and Aquisition Channels Options';
            app.LoadsimulationparametersButton = uibutton(app.UIFigure, 'push');
            app.LoadsimulationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsimulationparametersButtonPushed, true);
            app.LoadsimulationparametersButton.Tooltip = {'Click here to load a previously saved simulation: all parameters for the simulation will be loaded to the SMIS GUI.'};
            app.LoadsimulationparametersButton.Text = 'Load simulation parameters';
            app.SMISSimulationTitleEditFieldLabel = uilabel(app.UIFigure);
            app.SMISSimulationTitleEditFieldLabel.Text = 'SMIS Simulation Title';
            app.SMISSimulationTitleEditField = uieditfield(app.UIFigure, 'text');
            app.SMISSimulationTitleEditField.ValueChangedFcn = createCallbackFcn(app, @SMISSimulationTitleEditFieldValueChanged, true);
            app.SMISSimulationTitleEditField.Tooltip = {'Input here the name of your simulation. This name will be used as the file name of the simulation parameter output when you click on "Save simulation parameters".'};
            app.LaunchsimulationButton = uibutton(app.UIFigure, 'push');
            app.LaunchsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchsimulationButtonPushed, true);
            app.LaunchsimulationButton.Tooltip = {'Launch the SMIS simulation. Check proper running by inspecting the main Matlab window.'};
            app.LaunchsimulationButton.Text = 'Launch simulation';
            app.ObjectiveandPSFButton = uibutton(app.UIFigure, 'push');
            app.ObjectiveandPSFButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectiveandPSFButtonPushed, true);
            app.ObjectiveandPSFButton.Tooltip = {'Press this button to define the characteristics of the objective (e.g. numerical aperture, refractive index), the microscope detection efficiency, and the parameters of the cylindrical lens for the 3D astigmatism method (in 3D mode)'};
            app.ObjectiveandPSFButton.Text = 'Objective and PSF';
            app.NumberofframesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.RoundFractionalValues = 'on';
            app.NumberofframesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofframesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofframesEditFieldValueChanged, true);
            app.NumberofframesEditField.Tooltip = {'Number of frames in the SMIS simulation. If all fluorophores get bleached before the specified number of frames is reached, the SMIS simulation will stop.'};
            app.NumberofframesEditField.Value = 10;
            app.OutputDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.OutputDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoryEditFieldValueChanged, true);
            app.OutputDirectoryEditField.Tooltip = {'Output directory where the SMIS simulation data will be stored. Can be manually changed by typing in.'};
            app.OutputStackFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputStackFilenameEditFieldLabel.Text = 'Output Stack Filename';
            app.OutputStackFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputStackFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputStackFilenameEditFieldValueChanged, true);
            app.OutputStackFilenameEditField.Tooltip = {'Enter here the name of the SMIS simulation data file (no extension, various files will be produced: image stack (.tif), laser profiles (.tif), diary file (.txt), properties and time traces of each fluorophore (.mat)'};
            app.OutputStackFilenameEditField.Value = 'SMIS_1';
            app.FRETSwitchLabel = uilabel(app.UIFigure);
            app.FRETSwitchLabel.Text = 'FRET';
            app.FRETSwitch = uiswitch(app.UIFigure, 'slider');
            app.FRETSwitch.ValueChangedFcn = createCallbackFcn(app, @FRETSwitchValueChanged, true);
            app.FRETSwitch.Tooltip = {'Switch button to enable FRET between pairs of fluorophores'};
            app.EMCCDCameraButton = uibutton(app.UIFigure, 'push');
            app.EMCCDCameraButton.ButtonPushedFcn = createCallbackFcn(app, @EMCCDCameraButtonPushed, true);
            app.EMCCDCameraButton.Tooltip = {'Click this button to define the parameters of the EMCCD camera (e.g. EMCCD gain).'};
            app.EMCCDCameraButton.Text = 'EMCCD Camera ';
            app.LoadvirtualsampleDropDownLabel = uilabel(app.UIFigure);
            app.LoadvirtualsampleDropDownLabel.Text = 'Load virtual sample';
            app.LoadvirtualsampleDropDown = uidropdown(app.UIFigure);
            app.LoadvirtualsampleDropDown.Items = {'Choose', 'Fluorophore 1', 'All'};
            app.LoadvirtualsampleDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadvirtualsampleDropDownValueChanged, true);
            app.LoadvirtualsampleDropDown.Tooltip = {'Load a virtual sample for the chosen fluorophore. For 2D simulations virtual samples are 2D .tif images or .cs fluorophore coordinate files.  Tif images can be created from scratch or by segmenting an experimental image, typically using ImageJ, Python or Matlab. Csv files must contain the x and y coordinates of fluorophores in [nm] and possibly their pattern ids (integers). The column names of the csv file must be ‘x’, ‘y’, (and ‘z’ for 3D), and optionally ‘ids’.  For 3D simulations, in addition to .tif or .csv, virtual samples can also be 3D arrays that are created with Matlab (.mat). If you choose "all", the same virtual sample will be used for all loaded fluorophores.'};
            app.LoadvirtualsampleDropDown.Value = 'Choose';
            app.ChooseButton = uibutton(app.UIFigure, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Tooltip = {'Click this button to choose the output directory where the SMIS simulation data will be stored'};
            app.ChooseButton.Text = 'Choose';
            app.ShowlasersButton = uibutton(app.UIFigure, 'push');
            app.ShowlasersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowlasersButtonPushed, true);
            app.ShowlasersButton.Tooltip = {'Click this button to show a summary of the defined lasers'};
            app.ShowlasersButton.Text = 'Show lasers';
            app.DefaultSMISDirectoriesButton = uibutton(app.UIFigure, 'push');
            app.DefaultSMISDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSMISDirectoriesButtonPushed, true);
            app.DefaultSMISDirectoriesButton.Tooltip = {'Click here to define the default location of directories containing: the virtual sample patterns, the fluorophore library, the simulation''s output. '};
            app.DefaultSMISDirectoriesButton.Text = 'Default SMIS Directories';
            app.VirtualSampleLabel = uilabel(app.UIFigure);
            app.VirtualSampleLabel.Text = '';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'This shows the actual size of the recorded image in Y'};
            app.FrameSizeXEditFieldLabel = uilabel(app.UIFigure);
            app.FrameSizeXEditFieldLabel.Text = 'Frame Size X';
            app.FrameSizeXEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameSizeXEditField.Editable = 'off';
            app.FrameSizeXEditField.Tooltip = {'This shows the actual size of the recorded image in X'};
            app.sptPALMSwitchLabel = uilabel(app.UIFigure);
            app.sptPALMSwitchLabel.Text = 'sptPALM';
            app.sptPALMSwitch = uiswitch(app.UIFigure, 'slider');
            app.sptPALMSwitch.ValueChangedFcn = createCallbackFcn(app, @sptPALMSwitchValueChanged, true);
            app.sptPALMSwitch.Tooltip = {'Switch button to enable single-particle-tracking PALM'};
            app.qPALMSwitchLabel = uilabel(app.UIFigure);
            app.qPALMSwitchLabel.Text = 'qPALM';
            app.qPALMSwitch = uiswitch(app.UIFigure, 'slider');
            app.qPALMSwitch.ValueChangedFcn = createCallbackFcn(app, @qPALMSwitchValueChanged, true);
            app.qPALMSwitch.Tooltip = {'Switch button to enable quantitative PALM (molecular accounting or stoichiometry retrieval)'};
            app.ShowPSFButton = uibutton(app.UIFigure, 'push');
            app.ShowPSFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPSFButtonPushed, true);
            app.ShowPSFButton.Tooltip = {'Click this button to show the point spread function corresponding to each fluorophore in all their fluorescent states for each channel'};
            app.ShowPSFButton.Text = 'Show PSF';
            app.SetupdiffusionanddirectedmotionDropDownLabel = uilabel(app.UIFigure);
            app.SetupdiffusionanddirectedmotionDropDownLabel.Visible = 'off';
            app.SetupdiffusionanddirectedmotionDropDownLabel.Text = 'Setup diffusion and directed motion';
            app.SetupdiffusionanddirectedmotionDropDown = uidropdown(app.UIFigure);
            app.SetupdiffusionanddirectedmotionDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupdiffusionanddirectedmotionDropDownValueChanged, true);
            app.SetupdiffusionanddirectedmotionDropDown.Visible = 'off';
            app.sptPALMoptionsButton = uibutton(app.UIFigure, 'push');
            app.sptPALMoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @sptPALMoptionsButtonPushed, true);
            app.sptPALMoptionsButton.Visible = 'off';
            app.sptPALMoptionsButton.Text = 'sptPALM options';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Visible = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Visible = 'off';
            app.ZEditField.Tooltip = {'This shows the actual size of the recorded image in Z'};
            app.FluorophorepairingforcolocalizationorFRETSwitchLabel = uilabel(app.UIFigure);
            app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Visible = 'off';
            app.FluorophorepairingforcolocalizationorFRETSwitchLabel.Text = 'Fluorophore pairing for colocalization or FRET';
            app.FluorophorepairingforcolocalizationorFRETSwitch = uiswitch(app.UIFigure, 'slider');
            app.FluorophorepairingforcolocalizationorFRETSwitch.ValueChangedFcn = createCallbackFcn(app, @FluorophorepairingforcolocalizationorFRETSwitchValueChanged, true);
            app.FluorophorepairingforcolocalizationorFRETSwitch.Visible = 'off';
            app.FluorophorepairingforcolocalizationorFRETSwitch.Tooltip = {'Use this switch to define pairs of fluorophores that are to be localized close to each other for colocalization or FRET studies'};
            app.SetuppairingButton = uibutton(app.UIFigure, 'push');
            app.SetuppairingButton.ButtonPushedFcn = createCallbackFcn(app, @SetuppairingButtonPushed, true);
            app.SetuppairingButton.Visible = 'off';
            app.SetuppairingButton.Text = 'Setup pairing';
            app.AutodisplayrangeSwitchLabel = uilabel(app.UIFigure);
            app.AutodisplayrangeSwitchLabel.Visible = 'off';
            app.AutodisplayrangeSwitchLabel.Text = 'Auto display range';
            app.AutodisplayrangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutodisplayrangeSwitch.ValueChangedFcn = createCallbackFcn(app, @AutodisplayrangeSwitchValueChanged, true);
            app.AutodisplayrangeSwitch.Visible = 'off';
            app.AutodisplayrangeSwitch.Tooltip = {'Toggle switch for automatic or manual adjustment of image contrast '};
            app.AutodisplayrangeSwitch.Value = 'On';
            app.OthergeneralparametersButton = uibutton(app.UIFigure, 'push');
            app.OthergeneralparametersButton.ButtonPushedFcn = createCallbackFcn(app, @OthergeneralparametersButtonPushed, true);
            app.OthergeneralparametersButton.Tooltip = {'Press this button to set unusual options for SMIS'};
            app.OthergeneralparametersButton.Text = 'Other general parameters';
            app.DisplayrangeparametersButton = uibutton(app.UIFigure, 'push');
            app.DisplayrangeparametersButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayrangeparametersButtonPushed, true);
            app.DisplayrangeparametersButton.Visible = 'off';
            app.DisplayrangeparametersButton.Tooltip = {'Click this button if manual display range is chosen to set up the lower and upper image display range for contrast adjustment.'};
            app.DisplayrangeparametersButton.Text = 'Display range parameters';
            app.SavesimulationparametersButton = uibutton(app.UIFigure, 'push');
            app.SavesimulationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SavesimulationparametersButtonPushed, true);
            app.SavesimulationparametersButton.Tooltip = {'Save all simulation parameters set in the SMIS GUI (Matlab .mat format). The proposed name of the Matlab file will be that of the SMIS Simulation Title specified at the top of the SMIS GUI window.'};
            app.SavesimulationparametersButton.Text = 'Save simulation parameters';
            app.UpperSliderLabel = uilabel(app.UIFigure);
            app.UpperSliderLabel.Visible = 'off';
            app.UpperSliderLabel.Text = 'Upper';
            app.UpperSlider = uislider(app.UIFigure);
            app.UpperSlider.MajorTicks = [0 100];
            app.UpperSlider.MajorTickLabels = {'Min', 'Max'};
            app.UpperSlider.ValueChangedFcn = createCallbackFcn(app, @UpperSliderValueChanged, true);
            app.UpperSlider.Visible = 'off';
            app.UpperSlider.Tooltip = {'Upper limit for image display, use this to change the contrast.'};
            app.UpperSlider.Value = 100;
            app.LowerSliderLabel = uilabel(app.UIFigure);
            app.LowerSliderLabel.Visible = 'off';
            app.LowerSliderLabel.Text = 'Lower';
            app.LowerSlider = uislider(app.UIFigure);
            app.LowerSlider.MajorTicks = [0 100];
            app.LowerSlider.MajorTickLabels = {'Min', 'Max'};
            app.LowerSlider.ValueChangedFcn = createCallbackFcn(app, @LowerSliderValueChanged, true);
            app.LowerSlider.Visible = 'off';
            app.LowerSlider.Tooltip = {'Lower limit for image display, use this to change the contrast.'};
            app.ForceOverwriteCheckBox = uicheckbox(app.UIFigure);
            app.ForceOverwriteCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceOverwriteCheckBoxValueChanged, true);
            app.ForceOverwriteCheckBox.Tooltip = {'Tick this to force overwriting of previously saved simulation data files.'};
            app.ForceOverwriteCheckBox.Text = 'Force Overwrite';
            app.STOPCheckBox = uicheckbox(app.UIFigure);
            app.STOPCheckBox.Visible = 'off';
            app.STOPCheckBox.Tooltip = {'Tick to stop the simulation: the simulation will take more or less time to stop depending on the system and clocking of your computer.'};
            app.STOPCheckBox.Text = 'STOP';
            app.Image = uiimage(app.UIFigure);
            app.SMISquickhelpButton = uibutton(app.UIFigure, 'push');
            app.SMISquickhelpButton.ButtonPushedFcn = createCallbackFcn(app, @SMISquickhelpButtonPushed, true);
            app.SMISquickhelpButton.Text = 'SMIS quick help';
            app.SMISProgressGaugeLabel = uilabel(app.UIFigure);
            app.SMISProgressGaugeLabel.Visible = 'off';
            app.SMISProgressGaugeLabel.Text = 'SMIS Progress';
            app.SMISProgressGauge = uigauge(app.UIFigure, 'linear');
            app.SMISProgressGauge.Visible = 'off';
            app.SMISProgressGauge.Tooltip = {'Shows progression of SMIS data collection'};
            app.LaunchensemblesimulationButton = uibutton(app.UIFigure, 'push');
            app.LaunchensemblesimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchensemblesimulationButtonPushed, true);
            app.LaunchensemblesimulationButton.Tooltip = {'Launch the SMIS ensemble simulation. Check proper running by inspecting the main Matlab window.'};
            app.LaunchensemblesimulationButton.Text = 'Launch ensemble simulation';
            app.AnalyseResultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyseResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseResultsButtonPushed, true);
            app.AnalyseResultsButton.Tooltip = {'Click this button to analyze previous SMIS simulations'};
            app.AnalyseResultsButton.Text = 'Analyse Results';
            app.CreateVirtualSamplesButton = uibutton(app.UIFigure, 'push');
            app.CreateVirtualSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateVirtualSamplesButtonPushed, true);
            app.CreateVirtualSamplesButton.Tooltip = {'Click this button to launch a virtual sample creation tool'};
            app.CreateVirtualSamplesButton.Text = 'Create Virtual Samples';
            app.UseparallelcomputingCheckBox = uicheckbox(app.UIFigure);
            app.UseparallelcomputingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseparallelcomputingCheckBoxValueChanged, true);
            app.UseparallelcomputingCheckBox.Tooltip = {'Tick this to use parallel computing. This is generally useful when you have long simulations to run with many molecules per fluorophore. If you are running quick simulations with few molecules, there will be a strong time penalty in using parallel computing'};
            app.UseparallelcomputingCheckBox.Text = 'Use parallel computing';
            app.CreateFluorophoresButton = uibutton(app.UIFigure, 'push');
            app.CreateFluorophoresButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFluorophoresButtonPushed, true);
            app.CreateFluorophoresButton.Tooltip = {'Click this button to launch a virtual sample creation tool'};
            app.CreateFluorophoresButton.Text = 'Create Fluorophores';
            app.ShowsampleButton = uibutton(app.UIFigure, 'push');
            app.ShowsampleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsampleButtonPushed, true);
            app.ShowsampleButton.Tooltip = {'Click this button to show the point spread function corresponding to each fluorophore in all their fluorescent states for each channel'};
            app.ShowsampleButton.Text = 'Show sample';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMIS_vsn2_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end