classdef SamplingPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MinimumsamplepointsperframetimeoraddtimeEditField  matlab.ui.control.NumericEditField
        MinimumsamplepointsperframetimeoraddtimeEditFieldLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        SamplingratebetweenframesHzEditField  matlab.ui.control.NumericEditField
        SamplingratebetweenframesHzEditFieldLabel  matlab.ui.control.Label
        OptimizesamplingrateSwitch    matlab.ui.control.Switch
        OptimizesamplingrateSwitchLabel  matlab.ui.control.Label
        SamplingrateduringframetimeHzEditField  matlab.ui.control.NumericEditField
        SamplingrateduringframetimeHzEditFieldLabel  matlab.ui.control.Label
        MinimumoversamplingEditField  matlab.ui.control.NumericEditField
        MinimumoversamplingEditFieldLabel  matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        FluorescencesamplingparametersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Samplingpar=[]; % Initialize the parameters
        addtime=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize Samplingpar
            app.Samplingpar=mainapp.smis_gui_parameters.Sampling;
            app.addtime=mainapp.smis_gui_parameters.addtime;
            
            app.SamplingrateduringframetimeHzEditField.Value=app.Samplingpar.sampling_rate(1);
            if numel(app.Samplingpar.sampling_rate)==2
                app.SamplingratebetweenframesHzEditField.Value=app.Samplingpar.sampling_rate(2);
            else
                app.SamplingratebetweenframesHzEditField.Value=app.Samplingpar.sampling_rate(1);
            end
            
            app.MinimumoversamplingEditField.Value=app.Samplingpar.minimum_oversampling;
            app.MinimumsamplepointsperframetimeoraddtimeEditField.Value=app.Samplingpar.min_sampling_points;
            % Update UI with input values
            if app.Samplingpar.optimize_sampling_rate==1
                app.OptimizesamplingrateSwitch.Value='On';
                app.SamplingrateduringframetimeHzEditField.Visible='off';
                app.SamplingrateduringframetimeHzEditFieldLabel.Visible='off';
                app.SamplingratebetweenframesHzEditField.Visible='off';
                app.SamplingratebetweenframesHzEditFieldLabel.Visible='off';
%                 app.MinimumoversamplingEditField.Visible='on';
%                 app.MinimumoversamplingEditFieldLabel.Visible='on';
            else
                app.OptimizesamplingrateSwitch.Value='Off';
                app.SamplingrateduringframetimeHzEditField.Visible='on';
                app.SamplingrateduringframetimeHzEditFieldLabel.Visible='on';
                if app.addtime>0
                    app.SamplingratebetweenframesHzEditField.Visible='on';
                    app.SamplingratebetweenframesHzEditFieldLabel.Visible='on';
                else
                    app.SamplingratebetweenframesHzEditField.Visible='off';
                    app.SamplingratebetweenframesHzEditFieldLabel.Visible='off';
                end
%                 app.MinimumoversamplingEditField.Visible='off';
%                 app.MinimumoversamplingEditFieldLabel.Visible='off';
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateSamplingPar(app.CallingApp, app.Samplingpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: OptimizesamplingrateSwitch
        function OptimizesamplingrateSwitchValueChanged(app, event)
            value = app.OptimizesamplingrateSwitch.Value;
            if strcmp(value,'On')
                app.SamplingrateduringframetimeHzEditField.Visible='off';
                app.SamplingrateduringframetimeHzEditFieldLabel.Visible='off';
                app.SamplingratebetweenframesHzEditField.Visible='off';
                app.SamplingratebetweenframesHzEditFieldLabel.Visible='off';
%                 app.MinimumoversamplingEditField.Visible='on';
%                 app.MinimumoversamplingEditFieldLabel.Visible='on';
                app.Samplingpar.optimize_sampling_rate=1;
            else
                app.SamplingrateduringframetimeHzEditField.Visible='on';
                app.SamplingrateduringframetimeHzEditFieldLabel.Visible='on';
                if app.addtime>0
                    app.SamplingratebetweenframesHzEditField.Visible='on';
                    app.SamplingratebetweenframesHzEditFieldLabel.Visible='on';
                else
                    app.SamplingratebetweenframesHzEditField.Visible='off';
                    app.SamplingratebetweenframesHzEditFieldLabel.Visible='off';
                end
%                 app.MinimumoversamplingEditField.Visible='off';
%                 app.MinimumoversamplingEditFieldLabel.Visible='off';
                app.Samplingpar.optimize_sampling_rate=0;
            end
        end

        % Value changed function: MinimumoversamplingEditField
        function MinimumoversamplingEditFieldValueChanged(app, event)
            app.Samplingpar.minimum_oversampling = app.MinimumoversamplingEditField.Value;
        end

        % Value changed function: SamplingratebetweenframesHzEditField
        function SamplingratebetweenframesHzEditFieldValueChanged(app, event)
            app.Samplingpar.sampling_rate(2) = app.SamplingratebetweenframesHzEditField.Value;
        end

        % Value changed function: SamplingrateduringframetimeHzEditField
        function SamplingrateduringframetimeHzEditFieldValueChanged(app, event)
            app.Samplingpar.sampling_rate(1) = app.SamplingrateduringframetimeHzEditField.Value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: 
        % MinimumsamplepointsperframetimeoraddtimeEditField
        function MinimumsamplepointsperframetimeoraddtimeEditFieldValueChanged(app, event)
            app.Samplingpar.min_sampling_points = app.MinimumsamplepointsperframetimeoraddtimeEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FluorescencesamplingparametersLabel = uilabel(app.UIFigure);
            app.FluorescencesamplingparametersLabel.Text = 'Fluorescence sampling parameters';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Returns to main menu'};
            app.OKButton.Text = 'OK';
            app.MinimumoversamplingEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumoversamplingEditFieldLabel.Text = 'Minimum oversampling';
            app.MinimumoversamplingEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumoversamplingEditField.Limits = [1 10000];
            app.MinimumoversamplingEditField.ValueDisplayFormat = '%.0f';
            app.MinimumoversamplingEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumoversamplingEditFieldValueChanged, true);
            app.MinimumoversamplingEditField.Tooltip = {'Minimum oversampling of fluorescence photophysical state relative to the maximum transformation rate experienced by the fluorophores'};
            app.MinimumoversamplingEditField.Value = 1;
            app.SamplingrateduringframetimeHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateduringframetimeHzEditFieldLabel.Text = 'Sampling rate during frametime [Hz] ';
            app.SamplingrateduringframetimeHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateduringframetimeHzEditField.Limits = [0 10000000];
            app.SamplingrateduringframetimeHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingrateduringframetimeHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateduringframetimeHzEditFieldValueChanged, true);
            app.SamplingrateduringframetimeHzEditField.Tooltip = {'If Optimize sampling rate is set to Off, input sampling rate during frame time to be used at the start of the simulation. This input sampling rate might be raised if imposed by the calculated photophysical transformation rates.'};
            app.SamplingrateduringframetimeHzEditField.Value = 50000;
            app.OptimizesamplingrateSwitchLabel = uilabel(app.UIFigure);
            app.OptimizesamplingrateSwitchLabel.Text = 'Optimize sampling rate';
            app.OptimizesamplingrateSwitch = uiswitch(app.UIFigure, 'slider');
            app.OptimizesamplingrateSwitch.ValueChangedFcn = createCallbackFcn(app, @OptimizesamplingrateSwitchValueChanged, true);
            app.OptimizesamplingrateSwitch.Tooltip = {'Set this to On if you want the sampling rate to be optimized to match the specified minimum oversampling and minimum sample points per frametime or addtime'};
            app.SamplingratebetweenframesHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingratebetweenframesHzEditFieldLabel.Text = 'Sampling rate between frames [Hz] ';
            app.SamplingratebetweenframesHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingratebetweenframesHzEditField.Limits = [0 10000000];
            app.SamplingratebetweenframesHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingratebetweenframesHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingratebetweenframesHzEditFieldValueChanged, true);
            app.SamplingratebetweenframesHzEditField.Tooltip = {'If Optimize sampling rate is set to Off, input sampling rate during addtime to be used at the start of the simulation. This input sampling rate might be raised if imposed by the calculated photophysical transformation rates.'};
            app.SamplingratebetweenframesHzEditField.Value = 50000;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MinimumsamplepointsperframetimeoraddtimeEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumsamplepointsperframetimeoraddtimeEditFieldLabel.Text = 'Minimum sample points per frametime or addtime';
            app.MinimumsamplepointsperframetimeoraddtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumsamplepointsperframetimeoraddtimeEditField.Limits = [1 10000];
            app.MinimumsamplepointsperframetimeoraddtimeEditField.ValueDisplayFormat = '%.0f';
            app.MinimumsamplepointsperframetimeoraddtimeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumsamplepointsperframetimeoraddtimeEditFieldValueChanged, true);
            app.MinimumsamplepointsperframetimeoraddtimeEditField.Tooltip = {'This is the minimum number of photophysical state points that will be calculated during frametime and addtime'};
            app.MinimumsamplepointsperframetimeoraddtimeEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SamplingPar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end