classdef Show_Diffusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExtractsegmentsCheckBox         matlab.ui.control.CheckBox
        ExtractsubtracksCheckBox        matlab.ui.control.CheckBox
        ChannelSwitch                   matlab.ui.control.Switch
        ChannelSwitchLabel              matlab.ui.control.Label
        MSDsFitLabel                    matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        DumsEditField                   matlab.ui.control.NumericEditField
        DumsEditFieldLabel              matlab.ui.control.Label
        WorkinSwitch                    matlab.ui.control.Switch
        WorkinSwitchLabel               matlab.ui.control.Label
        FitDropDown                     matlab.ui.control.DropDown
        FitDropDownLabel                matlab.ui.control.Label
        CompositefinalFigureSwitch      matlab.ui.control.Switch
        CompositefinalFigureSwitchLabel  matlab.ui.control.Label
        TracklengthsSwitch              matlab.ui.control.Switch
        TracklengthsSwitchLabel         matlab.ui.control.Label
        TotalframetimesEditField        matlab.ui.control.NumericEditField
        TotalframetimesEditFieldLabel   matlab.ui.control.Label
        MeanpropertyEditField           matlab.ui.control.NumericEditField
        MeanpropertyEditFieldLabel      matlab.ui.control.Label
        MeantracklengthEditField        matlab.ui.control.NumericEditField
        MeantracklengthEditFieldLabel   matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        NumberofselectedEditField       matlab.ui.control.NumericEditField
        NumberofselectedEditFieldLabel  matlab.ui.control.Label
        XLogScaleSwitch                 matlab.ui.control.Switch
        XLogScaleSwitchLabel            matlab.ui.control.Label
        YLogScaleSwitch                 matlab.ui.control.Switch
        YLogScaleSwitchLabel            matlab.ui.control.Label
        YLimitSlider                    matlab.ui.control.Slider
        YLimitSliderLabel               matlab.ui.control.Label
        XLimitSlider                    matlab.ui.control.Slider
        XLimitSliderLabel               matlab.ui.control.Label
        HistogramBinsEditField          matlab.ui.control.NumericEditField
        HistogramBinsEditFieldLabel     matlab.ui.control.Label
        UseSwitch                       matlab.ui.control.Switch
        UseSwitchLabel                  matlab.ui.control.Label
        MaxlagforMSDsFramesEditField    matlab.ui.control.NumericEditField
        MaxlagforMSDsFramesEditFieldLabel  matlab.ui.control.Label
        FOVsafetymarginEditField        matlab.ui.control.NumericEditField
        FOVsafetymarginEditFieldLabel   matlab.ui.control.Label
        MinimumphotonsfordetectionEditField  matlab.ui.control.NumericEditField
        MinimumphotonsfordetectionEditFieldLabel  matlab.ui.control.Label
        PlotButtonGroup                 matlab.ui.container.ButtonGroup
        DfomJDsButton                   matlab.ui.control.ToggleButton
        DfomMSDsButton                  matlab.ui.control.ToggleButton
        StateOriginButton               matlab.ui.control.ToggleButton
        OfftimesButton                  matlab.ui.control.ToggleButton
        MSDButton                       matlab.ui.control.ToggleButton
        JDButton                        matlab.ui.control.ToggleButton
        MJDButton                       matlab.ui.control.ToggleButton
        BlinkinggapFramesEditField      matlab.ui.control.NumericEditField
        BlinkinggapFramesEditFieldLabel  matlab.ui.control.Label
        MaxTrackLengthFramesEditField   matlab.ui.control.NumericEditField
        MaxTrackLengthFramesEditFieldLabel  matlab.ui.control.Label
        MinTrackLengthFramesEditField   matlab.ui.control.NumericEditField
        MinTrackLengthFramesEditFieldLabel  matlab.ui.control.Label
        ShowSingleTracksButton          matlab.ui.control.Button
        DTable                          matlab.ui.control.Table
        MakefinalfiguresButton          matlab.ui.control.Button
        LineWidthSlider                 matlab.ui.control.Slider
        LineWidthSliderLabel            matlab.ui.control.Label
        AxisWidthSlider                 matlab.ui.control.Slider
        AxisWidthSliderLabel            matlab.ui.control.Label
        FontSizeSlider                  matlab.ui.control.Slider
        FontSizeSliderLabel             matlab.ui.control.Label
        XaxisSwitch                     matlab.ui.control.Switch
        XaxisSwitchLabel                matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        AnalysisofdiffusiontracksLabel  matlab.ui.control.Label
        PropertyAxes                    matlab.ui.control.UIAxes
        TrackLengthAxis                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        tracks=[]; % The tracks
        subtracks=[]; % The subtracks
        segments=[]; % The segments extracted from the tracks
        subsegments=[]; % The segments extracted from the subtracks
        saved_data=[]; % Data in memory to avoid repeating calculations
    end
    
    properties (Access = public)
        par=struct; % General parameters
        sm_par=[]; % The sm_par
        im_par=[]; % The im_par
        sm=[]; % The SM
        current_state=[]; % Current diffusion states to treat
        ShowDiffTracesApp=[] % Call to ShowDiffTracesApp
    end
    
    methods (Access = private)
        
        function PlotTracks(app)
            
            % Plot the track length
            PlotTrackLength(app)
            
            
            % Plot Other information according to PlotButtonGroup
            PlotProperty(app)
            
        end
        
        
        function GetTracks(app)
            track_par.min_phot=app.par.min_phot;
            track_par.blinking_gap=app.par.blinking_gap;
            track_par.im_par=app.im_par;
            track_par.safety_margin=app.par.safety_margin;
            track_par.channel=app.par.channel;
            track_par.ph_lim=app.par.ph_lim;
            track_par.simul_3D=app.im_par.simul_3D;
            
            track_par.n_diff_states=numel(app.sm_par.D);
            
            if app.ExtractsubtracksCheckBox.Value==0 % Do not extract subtracks
                track_par.Extractsubtracks=0;
                app.UseSwitch.Visible='off';
                app.UseSwitchLabel.Visible='off';
                [app.tracks, ~, ~]=extract_SMIS_GT_tracks(app.sm, track_par);
                
                % Figure out the full track length including off times
                MyData = arrayfun(@(s)s.frames(1), app.tracks,'UniformOutput',false);
                StartFrames=[MyData{:}]; % All the starting frames
                MyData = arrayfun(@(s)s.frames(end), app.tracks,'UniformOutput',false);
                EndFrames=[MyData{:}]; % All the ending frames
                TotTracklength=EndFrames-StartFrames+1;
                newVals = num2cell(TotTracklength);
                [app.tracks.tot_tracklength]=newVals{:};
                
                if app.ExtractsegmentsCheckBox.Value==1 && track_par.n_diff_states>1 % Extract segments
                    disp('Extracting segments for tracks ...')
                    track_par.title='tracks';
                    app.segments=extract_SMIS_segments(app.sm,app.tracks,track_par);
                    
                    % Figure out the full segment length including off times
                    MyData = arrayfun(@(s)s.frames(1), app.segments,'UniformOutput',false);
                    StartFrames=[MyData{:}]; % All the starting frames
                    MyData = arrayfun(@(s)s.frames(end), app.segments,'UniformOutput',false);
                    EndFrames=[MyData{:}]; % All the ending frames
                    TotTracklength=EndFrames-StartFrames+1;
                    newVals = num2cell(TotTracklength);
                    [app.segments.tot_tracklength]=newVals{:};
                else
                    app.segments=[];
                end
            else % Extract subtracks
                track_par.Extractsubtracks=1;
                app.UseSwitch.Visible='on';
                app.UseSwitchLabel.Visible='on';
                [app.tracks, app.subtracks, ~]=extract_SMIS_GT_tracks(app.sm, track_par);
                
                % Figure out the full track length including off times
                MyData = arrayfun(@(s)s.frames(1), app.tracks,'UniformOutput',false);
                StartFrames=[MyData{:}]; % All the starting frames
                MyData = arrayfun(@(s)s.frames(end), app.tracks,'UniformOutput',false);
                EndFrames=[MyData{:}]; % All the ending frames
                TotTracklength=EndFrames-StartFrames+1;
                newVals = num2cell(TotTracklength);
                [app.tracks.tot_tracklength]=newVals{:};
                
                % Figure out the full subtrack length including off times
                MyData = arrayfun(@(s)s.tracklength_with_blink, app.subtracks,'UniformOutput',false);
                TotTracklength=[MyData{:}]; % All the starting frames
                newVals = num2cell(TotTracklength);
                [app.subtracks.tot_tracklength]=newVals{:};
                
                if app.ExtractsegmentsCheckBox.Value==1 && track_par.n_diff_states>1 % Extract segments and subsegments
                    disp('Extracting segments for tracks ...')
                    track_par.title='tracks';
                    app.segments=extract_SMIS_segments(app.sm, app.tracks,track_par);
                    
                    % Figure out the full segment length including off times
                    MyData = arrayfun(@(s)s.frames(1), app.segments,'UniformOutput',false);
                    StartFrames=[MyData{:}]; % All the starting frames
                    MyData = arrayfun(@(s)s.frames(end), app.segments,'UniformOutput',false);
                    EndFrames=[MyData{:}]; % All the ending frames
                    TotTracklength=EndFrames-StartFrames+1;
                    newVals = num2cell(TotTracklength);
                    [app.segments.tot_tracklength]=newVals{:};
                    
                    disp('Extracting segments for subtracks ...')
                    track_par.title='subtracks';
                    app.subsegments=extract_SMIS_segments(app.sm, app.subtracks,track_par);
                    
                    % Figure out the full segment length including off times
                    MyData = arrayfun(@(s)s.frames(1), app.subsegments,'UniformOutput',false);
                    StartFrames=[MyData{:}]; % All the starting frames
                    MyData = arrayfun(@(s)s.frames(end), app.subsegments,'UniformOutput',false);
                    EndFrames=[MyData{:}]; % All the ending frames
                    TotTracklength=EndFrames-StartFrames+1;
                    newVals = num2cell(TotTracklength);
                    [app.subsegments.tot_tracklength]=newVals{:};
                else
                    app.subsegments=[];
                end
            end


            disp('Done !')
        end
        
        function AnalyzeTracks(app)
            
            % Get which state to analyze:
            tr_type=app.current_state;
            
            switch tr_type
                case 1 % The tracks or subtracks
                    if app.par.use_tracks==1
                        app.par.selected_tracks=app.tracks;
                        app.par.track_string='tracks';
                    else
                        app.par.selected_tracks=app.subtracks;
                        app.par.track_string='subtracks';
                    end
                    app.par.plot_origin=0;
                    app.par.use_tracklength_limits=1;
                case app.par.n_diff_states+2 % All the segments
                    if app.par.use_tracks==1
                        app.par.selected_tracks=app.segments;
                        app.par.track_string='all segments';
                    else
                        app.par.selected_tracks=app.subsegments;
                        app.par.track_string='all subsegments';
                    end
                    app.par.plot_origin=1;
                    app.par.use_tracklength_limits=0;
                otherwise % Individual segments
                    if app.par.use_tracks==1
                        if ~isempty(app.segments)
                            app.par.selected_tracks=app.segments([app.segments.diff_state_id]==tr_type-1);
                        else
                            app.par.selected_tracks=[];
                        end
                        app.par.track_string=['segments for Diffusion state ',num2str(tr_type-1)];
                    else
                        if ~isempty(app.subsegments)
                            app.par.selected_tracks=app.subsegments([app.subsegments.diff_state_id]==tr_type-1);
                        else
                            app.par.selected_tracks=[];
                        end
                        app.par.track_string=['subsegments for Diffusion state ',num2str(tr_type-1)];
                    end
                    app.par.plot_origin=1;
                    app.par.use_tracklength_limits=0;
            end
            
            if app.saved_data(app.current_state).ready==0 && ~isempty(app.par.selected_tracks) % Do these calculations if the data for the current state have not been calculated yet
                % Select based on tracklength limits if tracks of subtracks
                if app.par.use_tracklength_limits==1
                    app.par.selected_tracks=app.par.selected_tracks([app.par.selected_tracks.tot_tracklength]>=app.par.min_tracklength & [app.par.selected_tracks.tot_tracklength]<=app.par.max_tracklength);
                elseif app.par.use_tracks==1 % Use segments with tracklength limits from the tracks
                    selected_ids=find([app.tracks.tot_tracklength]>=app.par.min_tracklength & [app.tracks.tot_tracklength]<=app.par.max_tracklength);
                    app.par.selected_tracks=app.par.selected_tracks(ismember([app.par.selected_tracks.tr_id],selected_ids));
                else % Use subsegments with tracklength limits from the subtracks
                    selected_ids=find([app.subtracks.tot_tracklength]>=app.par.min_tracklength & [app.subtracks.tot_tracklength]<=app.par.max_tracklength);
                    app.par.selected_tracks=app.par.selected_tracks(ismember([app.par.selected_tracks.tr_id],selected_ids));
                end
                
            elseif app.saved_data(app.current_state).ready==1
                app.par.selected_tracks=app.saved_data(app.current_state).selected_tracks; % Take the already saved selected tracks
            end
            
            % Reduce app.par.track_string to fit in title space
            SegStrFound=strfind(app.par.track_string,'segments');
            if SegStrFound~=0
                MyStr=['Number of selected ',app.par.track_string(1:SegStrFound+7)];
            else
                MyStr=['Number of selected ',app.par.track_string];
            end
            app.NumberofselectedEditFieldLabel.Text=MyStr;
            app.NumberofselectedEditField.Value=numel(app.par.selected_tracks);
            
            if app.saved_data(app.current_state).ready==0 % Do these calculations if the data for the current state have not been calculated yet
                if ~isempty(app.par.selected_tracks)
                    %Get MSD's, MJDs and JD from the tracks
                    [D.MSD,D.LAGS, D.JD, D.MJD, D.STATS] = Get_SMIS_MSD_JD(app.par.selected_tracks,app.par.totframetime,app.par.max_lag,app.par.raster,app.par.mode_3D);
                    D.Dapp=[]; % Leave the calculation of apparent diffusion coefficients from MSDs for later on
                    D.DappJD=[]; % Leave the calculation of apparent diffusion coefficients from JDs for later on
                else
                    D=[];
                end
            else
                D=app.saved_data(app.current_state).D;
            end
            
            % Gather all the results
            app.par.D=D;
            
            
            % And save parameters if necessary
            if app.saved_data(app.current_state).ready==0
                app.saved_data(app.current_state).selected_tracks=app.par.selected_tracks;
                app.saved_data(app.current_state).D=app.par.D;
                app.saved_data(app.current_state).MSD_fit_ready=0; % Reset the fitting parameters
                app.saved_data(app.current_state).D_fit_ready=0; % Reset the fitting parameters
            end
            
            app.saved_data(app.current_state).ready=1; % Now the data for the current state are ready
            
        end
        
        function SetupDTable(app)
            app.DTable.Visible=1;
            
            app.DTable.Position=[30,610,500,min(190,30+22*(app.par.n_diff_states+1))]; % Adjust position
            
            % Show the names of the fluorescent states
            app.DTable.RowName=[{'Tracks'}];
            if app.par.n_diff_states>1 % Case where there are different diffusion states
                for k=1:app.par.n_diff_states
                    if app.im_par.simul_3D==0
                        D_state_name=['Diffusion state ',num2str(k),': D = ',num2str(app.sm_par.D(k)),' µm²/s'];
                    else
                        D_state_name=['Diffusion state ',num2str(k),': D = ',num2str(app.sm_par.D(k)),' µm',char(179),'/s'];
                    end
                    app.DTable.RowName=[app.DTable.RowName;{D_state_name}];
                end
                app.DTable.RowName=[app.DTable.RowName;{'All segments'}];
            end
            
            app.DTable.ColumnName=[{'Show'};{'Plot final'}];
            
            n_rows=numel(app.DTable.RowName);
            
            % Initialize the plotting data
            StatesShow=false(n_rows,1);
            StatesShow(1)=true; % Select the first state
            app.current_state=1; % And set this to the current diffusion state to treat
            
            StatesPlotFinal=false(n_rows,1);
            
            app.par.DTableData = table(StatesShow,StatesPlotFinal,'VariableNames',app.DTable.ColumnName);
            app.DTable.Data=app.par.DTableData;
        end
        
        
        function PlotProperty(app)
            
            
            if isempty(app.par.D)
                cla(app.PropertyAxes);
                xlabel(app.PropertyAxes,'');
                ylabel(app.PropertyAxes,'');
                app.PropertyAxes.Title.String='';
                % Hide the main property field
                app.MeanpropertyEditField.Visible='off';
                app.MeanpropertyEditFieldLabel.Visible='off';
                DisableFitting(app)
                
            else     % Proceed
                
                % Show the main property field
                app.MeanpropertyEditField.Visible='on';
                app.MeanpropertyEditFieldLabel.Visible='on';
                
                % Look at what to plot
                switch app.par.PlotWhat
                    case 'MJD'
                        app.par.MyDataToPlot=app.par.D.MJD;
                        app.par.PropertyLabel='MJDs for: ';
                        app.par.MyXLabel='Distance [um]';
                        % Get the mean value
                        app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                        app.MeanpropertyEditFieldLabel.Text='Mean MJD [um]';
                        DisableFitting(app)
                        
                        PlotMyData(app)
                        
                    case 'JD'
                        app.par.MyDataToPlot=app.par.D.JD;
                        app.par.PropertyLabel='JDs for: ';
                        app.par.MyXLabel='Distance [um]';
                        app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                        app.MeanpropertyEditFieldLabel.Text='Mean JD [um]';
                        DisableFitting(app)
                        
                        PlotMyData(app)
                        
                    case 'MSD'
                        app.par.PropertyLabel='MSDs for: ';
                        if strcmp(app.XaxisSwitch.Value,"Time")==1
                            app.par.MyXLabel='Lag [s]';
                        else
                            app.par.MyXLabel='Lag [frames]';
                        end
                        app.MeanpropertyEditField.Visible='off';
                        app.MeanpropertyEditFieldLabel.Visible='off';
                        DisableFitting(app)
                        
                        %Enable fitting
                        app.FitDropDown.Visible='on';
                        app.FitDropDownLabel.Visible='on';
                        app.FitDropDown.Items=app.par.FitDropDownItems(1:4);
                        app.FitDropDown.ItemsData=1:4;
                        
                        PlotMSDs(app)
                        
                    case 'Offtimes'
                        app.par.MyDataToPlot=[app.par.selected_tracks.off_times];
                        app.par.PropertyLabel='Offtimes for: ';
                        if strcmp(app.XaxisSwitch.Value,"Time")==1
                            app.par.MyXLabel='Off times [s]';
                            app.par.MyDataToPlot=app.par.MyDataToPlot*app.par.totframetime;
                            if ~isempty(app.par.MyDataToPlot)
                                app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                                app.MeanpropertyEditFieldLabel.Text='Mean Offtime [s]';
                            else
                                app.MeanpropertyEditField.Visible='off';
                                app.MeanpropertyEditFieldLabel.Visible='off';
                            end
                        else
                            app.par.MyXLabel='Off times [frames]';
                            if ~isempty(app.par.MyDataToPlot)
                                app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                                app.MeanpropertyEditFieldLabel.Text='Mean Offtime [frames]';
                            else
                                app.MeanpropertyEditField.Visible='off';
                                app.MeanpropertyEditFieldLabel.Visible='off';
                            end
                        end
                        
                        DisableFitting(app)
                        
                        PlotMyData(app)
                        
                    case 'State Origin'
                        if app.par.plot_origin==1
                            app.par.MyDataToPlot=[app.par.selected_tracks.diff_state_origin];
                            app.par.PropertyLabel='State origin for: ';
                            
                            DisableFitting(app)
                            
                            PlotOrigin(app)
                            
                        else
                            cla(app.PropertyAxes);
                            xlabel(app.PropertyAxes,'');
                            ylabel(app.PropertyAxes,'');
                            app.PropertyAxes.Title.String='';
                        end
                        
                    case 'D fom MSDs'
                        
                        if isempty(app.saved_data(app.current_state).D.Dapp) % Calculate the diffusion coefficients if they are not already calculated
                            GetDiffusionCoeff(app);
                        end
                        
                        app.par.MyDataToPlot=app.saved_data(app.current_state).D.Dapp;
                        app.par.PropertyLabel='Apparent Diff Coeff (from MSDs) for: ';
                        
                        if app.par.mode_3D==0
                            app.par.MyXLabel='D [µm²/s]';
                            % Get the mean value
                            app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                            app.MeanpropertyEditFieldLabel.Text='Mean D [µm²/s]';
                        else
                            app.par.MyXLabel='D [µm^3/s]';
                            % Get the mean value
                            app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                            app.MeanpropertyEditFieldLabel.Text='Mean D [µm^3/s]';
                        end
                        
                        DisableFitting(app)
                        if app.saved_data(app.current_state).D_fit_ready==1
                            app.par.FitFieldsMode=2; % Display MSD fit field
                            EnableFitFields(app)
                        end
                        
                        %Enable D histogram fitting
                        app.FitDropDown.Visible='on';
                        app.FitDropDownLabel.Visible='on';
                        app.FitDropDown.Items=app.par.FitDropDownItems([1,5:8]);
                        app.FitDropDown.ItemsData=[1,5:8];
                        
                        app.par.FitFieldsMode=2; % Display D fit fields from MSDs
                        PlotMyData(app)
                        
                    case 'D fom JDs'
                        
                        if app.MinTrackLengthFramesEditField.Value<3
                            MyDlg=warndlg('Set minimum tracklength to at least 3 !');
                            waitfor(MyDlg)
                            return
                        end
                        
                        if isempty(app.saved_data(app.current_state).D.DappJD) % Calculate the diffusion coefficients if they are not already calculated
                            GetDiffusionCoeffFromJD2(app);
                        end
                        
                        app.par.MyDataToPlot=app.saved_data(app.current_state).D.DappJD;
                        app.par.PropertyLabel='Apparent Diff Coeff (from JDs) for: ';
                        
                        if app.par.mode_3D==0
                            app.par.MyXLabel='D [µm²/s]';
                            % Get the mean value
                            app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                            app.MeanpropertyEditFieldLabel.Text='Mean D [µm²/s]';
                        else
                            app.par.MyXLabel='D [µm^3/s]';
                            % Get the mean value
                            app.MeanpropertyEditField.Value=mean(app.par.MyDataToPlot);
                            app.MeanpropertyEditFieldLabel.Text='Mean D [µm^3/s]';
                        end
                        
                        DisableFitting(app)
                        if app.saved_data(app.current_state).D_fit_JDs_ready==1
                            app.par.FitFieldsMode=3; % Display MSD fit field
                            EnableFitFields(app)
                        end
                        
                        %Enable D histogram fitting
                        app.FitDropDown.Visible='on';
                        app.FitDropDownLabel.Visible='on';
                        app.FitDropDown.Items=app.par.FitDropDownItems([1,9]);
                        app.FitDropDown.ItemsData=[1,9];
                        
                        app.par.FitFieldsMode=3; % Display D fit fields from JDs
                        PlotMyData(app)
                end
                
            end
        end
        
        function PlotTrackLength(app)
            
            % Define the type of data to plot: track,substrack or segment length
            MyTracks=app.par.selected_tracks;
            
            if isempty(MyTracks)
                MyDialog=warndlg('There is no data to plot ! ','Warning');
                waitfor(MyDialog)
                cla(app.TrackLengthAxis);
                xlabel(app.TrackLengthAxis,'');
                ylabel(app.TrackLengthAxis,'');
                app.TrackLengthAxis.Title.String='';
                app.MeantracklengthEditField.Visible='off';
                app.MeantracklengthEditFieldLabel.Visible='off';
            else     % Proceed
                
                % Select whether internal or external figure
                if app.par.FinalFigureMode==true
                    if app.par.FinalFigure.composite==true
                        figure(app.par.MyFigure)
                        subplot(app.par.FinalFigure.n_cols,app.par.FinalFigure.n_rows,app.par.FinalFigure.pos);
                        MyAxis=gca;
                        app.par.FinalFigure.pos=app.par.FinalFigure.pos+1; % Prepare for next plot
                    else
                        app.par.MyFigure=figure;
                        set(app.par.MyFigure,'color','w');
                        MyAxis=gca;
                    end
                else
                    MyAxis=app.TrackLengthAxis;
                end
                
                % Plot histogram of track length
                if strcmp(app.TracklengthsSwitch.Value,'With Offtimes')==1
                    app.par.MyDataToPlot=[MyTracks.tot_tracklength];
                else
                    app.par.MyDataToPlot=[MyTracks.tracklength];
                end
                
                % Change to frames units if required
                if strcmp(app.XaxisSwitch.Value,"Time")==1
                    app.par.MyDataToPlot=app.par.MyDataToPlot*app.par.totframetime;
                    app.MeantracklengthEditFieldLabel.Text='Mean tracklength [s]';
                else
                    app.MeantracklengthEditFieldLabel.Text='Mean tracklength [frames]';
                end
                
                % Display the mean track length
                app.MeantracklengthEditField.Visible='on';
                app.MeantracklengthEditFieldLabel.Visible='on';
                app.MeantracklengthEditField.Value=mean([app.par.MyDataToPlot]);
                
                % Get the histogram
                h=GetHistogram(app, MyAxis);
                
                h.LineWidth=app.LineWidthSlider.Value;
                h.FaceColor='Cyan';
                h.EdgeColor='Blue';
                
                if strcmp(app.XaxisSwitch.Value,"Time")==1
                    xlabel(MyAxis,'Length [s]');
                else
                    xlabel(MyAxis,'Length [frames]');
                end
                
                ylabel(MyAxis,'Fraction')
                
                if strcmp(app.XLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'XScale', 'log')
                else
                    set(MyAxis, 'XScale', 'linear')
                end
                
                if strcmp(app.YLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'YScale', 'log')
                else
                    set(MyAxis, 'YScale', 'linear')
                end
                
                ylim(MyAxis,[0 max(h.Values)*1.1*app.YLimitSlider.Value/100]);
                
                set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
                set(MyAxis,'FontWeight','bold');
                set(MyAxis,'FontSize',app.FontSizeSlider.Value);
                
                MyTitle=app.par.track_string;
                MyTitle=['Tracklength for: ',upper(MyTitle(1)),MyTitle(2:end)]; % Make the first letter upper case
                MyAxis.Title.String=MyTitle;
                
            end
        end
        
        function PlotOrigin(app)
            
            % Select whether internal or external figure
            if app.par.FinalFigureMode==true
                if app.par.FinalFigure.composite==true
                    figure(app.par.MyFigure)
                    subplot(app.par.FinalFigure.n_cols,app.par.FinalFigure.n_rows,app.par.FinalFigure.pos);
                    MyAxis=gca;
                    app.par.FinalFigure.pos=app.par.FinalFigure.pos+1; % Prepare for next plot
                else
                    app.par.MyFigure=figure;
                    set(app.par.MyFigure,'color','w');
                    MyAxis=gca;
                end
            else
                MyAxis=app.PropertyAxes;
            end
            
            
            % Hide the main property field
            app.MeanpropertyEditField.Visible='off';
            app.MeanpropertyEditFieldLabel.Visible='off';
            
            if any(isnan(app.par.MyDataToPlot))
                originally_on=1;
                app.par.MyDataToPlot(isnan(app.par.MyDataToPlot))=0; % Set those molecules initially in the state to an origin of 0
                nbins=app.par.n_diff_states+1;
                MyEdges=-0.5+0:nbins;
            else
                originally_on=0; % There is no molecule initially in that state
                nbins=app.par.n_diff_states;
                MyEdges=0.5+0:nbins;
            end
            
            h=histogram(MyAxis,app.par.MyDataToPlot,nbins,'BinEdges',MyEdges,'Normalization','probability','FaceColor',[0.5,0.5,0.5]);
            h.LineWidth=app.LineWidthSlider.Value;
            h.FaceColor='Green';
            h.EdgeColor=[0 0.8 0];
            
            xlabel(MyAxis,'') % No need for a label here
            ylabel(MyAxis,'Fraction')
            
            xlim(MyAxis,[min(MyEdges), max(MyEdges)]);
            ylim(MyAxis,[0 app.YLimitSlider.Value/100]);
            
            set(MyAxis,'XTickLabelRotation',30);
            DiffStatesNames=cell(app.par.n_diff_states,1);
            for k=1:app.par.n_diff_states
                DiffStatesNames(k)={['Diff state ',num2str(k)]};
            end
            
            if originally_on==1
                set(MyAxis, 'XTick',0:app.par.n_diff_states,'XTickLabel',[{'Present at start'};DiffStatesNames]);
            else
                set(MyAxis, 'XTick',1:app.par.n_diff_states,'XTickLabel',DiffStatesNames);
            end
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider.Value);
            
            MyTitle=app.par.track_string;
            MyTitle=[app.par.PropertyLabel,upper(MyTitle(1)),MyTitle(2:end)]; % Make the first letter upper case
            MyAxis.Title.String=MyTitle;
            
        end
        
        function PlotMyData(app)
            
            if ~isempty(app.par.MyDataToPlot)
                
                % Select whether internal or external figure
                if app.par.FinalFigureMode==true
                    if app.par.FinalFigure.composite==true
                        figure(app.par.MyFigure)
                        subplot(app.par.FinalFigure.n_cols,app.par.FinalFigure.n_rows,app.par.FinalFigure.pos);
                        MyAxis=gca;
                        app.par.FinalFigure.pos=app.par.FinalFigure.pos+1; % Prepare for next plot
                    else
                        app.par.MyFigure=figure;
                        set(app.par.MyFigure,'color','w');
                        MyAxis=gca;
                    end
                else
                    MyAxis=app.PropertyAxes;
                end


                set(MyAxis, 'XTickMode', 'auto', 'XTickLabelMode', 'auto') % Important if state origin has been plotted before
                
                % This is only if diffusion histogram data were fitted
                if app.saved_data(app.current_state).D_fit_ready==1 && app.par.FitFieldsMode==2
                    if app.saved_data(app.current_state).D_fit_par.UseLog==1
                        if app.par.mode_3D==0
                            app.par.MyXLabel='log D [µm²/s]';
                        else
                            app.par.MyXLabel='log D [µm^3/s]';
                        end
                    end
                end
                
                
                % Get the histogram
                h=GetHistogram(app, MyAxis);
                
                % Eventually add fitted data
                if app.saved_data(app.current_state).D_fit_ready==1 && app.par.FitFieldsMode==2
                    hold(MyAxis,'on')
                    Yfit=app.saved_data(app.current_state).D_fit_par.Yfit/100*app.saved_data(app.current_state).D_fit_par.n_bins/h.NumBins; % We have to normalize by the ratio of used number of bins used in the fitting routine.
                    plot(MyAxis,app.saved_data(app.current_state).D_fit_par.Xfit,Yfit , 'Color', [1 0 0], 'LineStyle', '--', 'LineWidth', max([1,app.LineWidthSlider.Value-1]));
                    hold(MyAxis,'off')
                    
                    EnableFitFields(app)
                end
                
                if app.saved_data(app.current_state).D_fit_JDs_ready==1 && app.par.FitFieldsMode==3
                    hold(MyAxis,'on')
                    Yfit=app.saved_data(app.current_state).D_fit_JDs_par.Yfit/100*app.saved_data(app.current_state).D_fit_JDs_par.n_bins/h.NumBins; % We have to normalize by the ratio of used number of bins used in the fitting routine.
                    plot(MyAxis,app.saved_data(app.current_state).D_fit_JDs_par.Xfit,Yfit , 'Color', [1 0 0], 'LineStyle', '--', 'LineWidth', max([1,app.LineWidthSlider.Value-1]));
                    hold(MyAxis,'off')
                    
                    EnableFitFields(app)
                end
                
                
                h.LineWidth=app.LineWidthSlider.Value;
                h.FaceColor=[0,0.6,0]; % Light green
                h.EdgeColor='Green';
                
                xlabel(MyAxis,app.par.MyXLabel);
                
                ylabel(MyAxis,'Fraction')
                
                if strcmp(app.XLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'XScale', 'log')
                else
                    set(MyAxis, 'XScale', 'linear')
                end
                
                if strcmp(app.YLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'YScale', 'log')
                else
                    set(MyAxis, 'YScale', 'linear')
                end
                
                ylim(MyAxis,[0 max(h.Values)*1.1*app.YLimitSlider.Value/100]);
                
                set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
                set(MyAxis,'FontWeight','bold');
                set(MyAxis,'FontSize',app.FontSizeSlider.Value);
                
                MyTitle=app.par.track_string;
                MyTitle=[app.par.PropertyLabel,upper(MyTitle(1)),MyTitle(2:end)]; % Make the first letter upper case
                MyAxis.Title.String=MyTitle;
                
            else
                MyDialog=warndlg('There is no data to plot ! ','Warning');
                waitfor(MyDialog)
                cla(app.PropertyAxes);
                xlabel(app.PropertyAxes,'');
                ylabel(app.PropertyAxes,'');
                app.PropertyAxes.Title.String='';
                % Hide the main property field
                app.MeanpropertyEditField.Visible='off';
                app.MeanpropertyEditFieldLabel.Visible='off';
            end
        end
        
        function PlotMSDs(app)
            
            if ~isempty(app.par.D.LAGS)
                
                % Select whether internal or external figure
                if app.par.FinalFigureMode==true
                    if app.par.FinalFigure.composite==true
                        figure(app.par.MyFigure)
                        subplot(app.par.FinalFigure.n_cols,app.par.FinalFigure.n_rows,app.par.FinalFigure.pos);
                        MyAxis=gca;
                        app.par.FinalFigure.pos=app.par.FinalFigure.pos+1; % Prepare for next plot
                    else
                        app.par.MyFigure=figure;
                        set(app.par.MyFigure,'color','w');
                        MyAxis=gca;
                    end
                else
                    MyAxis=app.PropertyAxes;
                end
                
                
                set(MyAxis, 'XTickMode', 'auto', 'XTickLabelMode', 'auto') % Important if state origin has been plotted before
                
                MSDsLags=app.par.D.LAGS;
                MSDsMean=app.par.D.STATS.msd_array_mean;
                MSDsStd=app.par.D.STATS.msd_array_std;
                
                % Overlay fit if requested
                if  app.saved_data(app.current_state).MSD_fit_ready==1
                    
                    app.par.FitFieldsMode=1; % Display MSD fit fields
                    EnableFitFields(app)
                    D=app.saved_data(app.current_state).MSD_fit_par.D;
                    if ~isempty(app.saved_data(app.current_state).MSD_fit_par.alpha)
                        alpha=app.saved_data(app.current_state).MSD_fit_par.alpha;
                    else
                        alpha=1;
                    end
                    if ~isempty(app.saved_data(app.current_state).MSD_fit_par.offset)
                        offset=app.saved_data(app.current_state).MSD_fit_par.offset;
                    else
                        offset=0;
                    end
                    app.par.MSD_fit_par.FittedMSDs=2*app.par.fitdim*D*MSDsLags.^alpha+offset; % Calculate the fitted MSDs
                else
                    DisableFitFields(app)
                end
                
                
                if strcmp(app.XaxisSwitch.Value,"Frames")==1
                    MSDsLags=MSDsLags/app.par.totframetime;
                end
                
                % Do the plot
                errorbar(MyAxis,MSDsLags, MSDsMean, MSDsStd, '-s', 'Color', [1 0.5 0], 'LineWidth', app.LineWidthSlider.Value);
                if  app.saved_data(app.current_state).MSD_fit_ready==1 % Overlay the fit
                    hold(MyAxis,'on')
                    plot(MyAxis,MSDsLags, app.par.MSD_fit_par.FittedMSDs, 'Color', [1 0 0], 'LineStyle', '--', 'LineWidth', max([1,app.LineWidthSlider.Value-1]));
                    hold(MyAxis,'off')
                end
                
                xlabel(MyAxis,app.par.MyXLabel);
                
                XWindowMin=min(MSDsLags)-app.par.totframetime/5;
                XWindowMax=max(MSDsLags)+app.par.totframetime/5;
                
                if XWindowMax>0
                    xlim(MyAxis,[XWindowMin,XWindowMin+(XWindowMax-XWindowMin)*app.XLimitSlider.Value/100]);
                end
                
                if app.par.mode_3D==0
                    ylabel(MyAxis,'MSDs [µm²]')
                else
                    ylabel(MyAxis,'MSDs [µm^3]')
                end
                
                
                if strcmp(app.XLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'XScale', 'log')
                else
                    set(MyAxis, 'XScale', 'linear')
                end
                
                if strcmp(app.YLogScaleSwitch.Value,'On')==1
                    set(MyAxis, 'YScale', 'log')
                else
                    set(MyAxis, 'YScale', 'linear')
                end
                
                YWindowMin=min(MSDsMean-MSDsStd)-min(MSDsStd);
                YWindowMax=max(MSDsMean+MSDsStd)+min(MSDsStd);
                if YWindowMax>0
                    ylim(MyAxis,[YWindowMin,YWindowMin+(YWindowMax-YWindowMin)*app.YLimitSlider.Value/100]);
                end
                set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
                set(MyAxis,'FontWeight','bold');
                set(MyAxis,'FontSize',app.FontSizeSlider.Value);
                
                MyTitle=app.par.track_string;
                MyTitle=[app.par.PropertyLabel,upper(MyTitle(1)),MyTitle(2:end)]; % Make the first letter upper case
                MyAxis.Title.String=MyTitle;
                
            else
                MyDialog=warndlg('There is no data to plot ! ','Warning');
                waitfor(MyDialog)
                cla(app.PropertyAxes);
                xlabel(app.PropertyAxes,'');
                ylabel(app.PropertyAxes,'');
                app.PropertyAxes.Title.String='';
                % Hide the main property field
                app.MeanpropertyEditField.Visible='off';
                app.MeanpropertyEditFieldLabel.Visible='off';
            end
        end
        
        function GetDiffusionCoeff(app)
            
            n_tr=numel(app.par.selected_tracks); % Number of selected tracks
            
            Dapp=nan(1,n_tr); % Define the apparent diffusion coefficient array
            
            max_lag=app.MaxlagforMSDsFramesEditField.Value;
            
            MyWaitbar = waitbar(0,'Calculating D''s ...');
            
            
            % Go through each track
            for i=1:n_tr
                if i/100==fix(i/100)
                    waitbar(i/n_tr,MyWaitbar)
                    clc; disp(['Tracks [%]: ',num2str(100*i/n_tr)]);
                end
                
                %Get the lags and the MSD's for current track
                LAGS=app.par.D.MSD(app.par.D.MSD(:,1)==i,2);
                if max(LAGS)>=max_lag % then do the calculation
                    MSDLags=unique(LAGS);
                    MSDLags(MSDLags>max_lag)=[];
                    n_lags=numel(MSDLags); % Number of effectively used lags
                    
                    MSDs=app.par.D.MSD(app.par.D.MSD(:,1)==i,3); % Get the MSD's
                    
                    % Calculate the mean and standard deviation of MSD's for each lag
                    MSDMeans=zeros(1,n_lags);
                    MSDStds=zeros(1,n_lags);
                    
                    for k=1:n_lags
                        w=LAGS==MSDLags(k);
                        MSDMeans(k)=mean(MSDs(w));
                        MSDStds(k)=std(MSDs(w))/sqrt(length(MSDs(w))); % standard error of the mean
                    end
                    
                    MSDLags=MSDLags*app.par.totframetime; % Converts the lags in [s]
                    
                    XToFit=MSDLags;
                    YToFit=MSDMeans';
                    
                    Weight=1./MSDStds';
                    if ~isinf(min(Weight))
                        Weight(isinf(Weight))=0.1*min(Weight(~isinf(Weight))); % trick to avoid +inf Weights
                    else
                        Weight(:)=1; % Suppress weights in that case
                    end
                    
                    if numel(XToFit)>1 % Can only fit if more than 1 point 
                        [fitobject,~] = fit(XToFit, YToFit, 'Poly1', 'Weights', Weight, 'Upper', [Inf, min(YToFit)]);
                        if fitobject.p1>0
                            Dapp(i) = fitobject.p1/(2*app.par.fitdim); % Return the coefficient of diffusion
                        end
                    end
                end
            end
            
            close(MyWaitbar)
            
            Dapp(isnan(Dapp))=[]; % Suppress the non-successfully fitted diffusion coefficients
            
            n_ok=numel(Dapp);
            disp(['Number of successfully fitted diffusion coefficients : ',num2str(n_ok),' ie ',num2str(100*n_ok/n_tr),'%']);
            
            
            app.saved_data(app.current_state).D.Dapp=Dapp;
        end
        
        
        function GetDiffusionCoeffFromJD2(app)
            
            %First we need to split the tracks into short continuous tracks
            % The length of these short tracks are set by the minimum track length edit field
            short_tracks=SplitTracks(app.par.selected_tracks,app.MinTrackLengthFramesEditField.Value,app.im_par.simul_3D);
            
            %Then we need to get the JDs from these short tracks. We will calculate them from the MSD's
            short_tracks_MSDs = Get_SMIS_MSD_JD(short_tracks,app.par.totframetime,1,app.par.raster,app.par.mode_3D);
            
            n_tr=numel(short_tracks); % Number of selected tracks
            
            DappJD=nan(1,n_tr); % Define the apparent diffusion coefficient array
            
            MyWaitbar = waitbar(0,'Calculating D''s ...');
            
            % Go through each track
            for i=1:n_tr
                if i/100==fix(i/100)
                    waitbar(i/n_tr,MyWaitbar)
                    clc; disp(['Tracks [%]: ',num2str(100*i/n_tr)]);
                end
                
                %Get the lags for current track
                LAGS=short_tracks_MSDs(short_tracks_MSDs(:,1)==i,2);
                
                %Get the MSDs for current track
                MSDs=short_tracks_MSDs(short_tracks_MSDs(:,1)==i,3); % Get the MSD's
                
                %Get the MSDs with lag=1 for current track
                w=LAGS==1;
                JD2Mean=mean(MSDs(w));
                
                %Extract the apparent diff coeff
                DappJD(i) = JD2Mean/(2*app.par.fitdim*app.par.totframetime);
                
            end
            
            close(MyWaitbar)
            
            DappJD(isnan(DappJD))=[]; % Suppress the non-successfully fitted diffusion coefficients
            
            n_ok=numel(DappJD);
            disp(['Number of successfully fitted diffusion coefficients : ',num2str(n_ok),' ie ',num2str(100*n_ok/n_tr),'%']);
            
            
            app.saved_data(app.current_state).D.DappJD=DappJD;
            app.saved_data(app.current_state).D.DappJD_used_tracklength=app.MinTrackLengthFramesEditField.Value;
        end
        
        function DisableFitting(app)
            
            app.FitDropDown.Visible='off';
            app.FitDropDownLabel.Visible='off';
            DisableFitFields(app)
            
        end
        
        function h=GetHistogram(app, MyAxis)
            
            Data=app.par.MyDataToPlot;
            
            % Handle log data if necessary
            if app.saved_data(app.current_state).D_fit_ready==1 && app.par.FitFieldsMode==2
                if app.saved_data(app.current_state).D_fit_par.UseLog==1
                    Data=log10(Data);
                end
            end
            
            WindowMax=max(Data);
            WindowMin=min(Data);
            if isempty(WindowMax)
                WindowMin=0; % Arbitrary number
                WindowMax=1; % Arbitrary number
            end
            if WindowMax==WindowMin && WindowMax~=0
                WindowMin=WindowMin*0.95;
                WindowMax=WindowMax*1.05;
            end
            if WindowMax==WindowMin && WindowMax==0
                WindowMin=0;
                WindowMax=1;
            end
            
            n_bins=app.HistogramBinsEditField.Value;
            
            MyStep=(WindowMax-WindowMin)/n_bins;
            MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram
            MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
            MyEdges(end)=MyEdges(end)+MyStep*1e-6;
            
            h=histogram(MyAxis, Data,'BinEdges',MyEdges,'Normalization','probability');
            
            % Possibly reduce the number of bins but not too much
            while nnz(h.Values) <= 0.7*h.NumBins && n_bins> app.HistogramBinsEditField.Value/2
                n_bins=fix(n_bins*0.8);
                MyStep=(WindowMax-WindowMin)/n_bins;
                MyEdges=WindowMin+(0:n_bins)*MyStep; % Produce x_values for histogram
                MyEdges(1)=MyEdges(1)-MyStep*1e-6; % Guaranty that no data fall on one of the edge
                MyEdges(end)=MyEdges(end)+MyStep*1e-6;
                
                h=histogram(MyAxis, Data,'BinEdges',MyEdges,'Normalization','probability');
            end
            
            xlim(MyAxis,[WindowMin,WindowMin+(WindowMax-WindowMin)*app.XLimitSlider.Value/100]);
            
            
            if n_bins<app.HistogramBinsEditField.Value
                % MyDialog=warndlg(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]  ,'Warning');
                % waitfor(MyDialog)
                disp(['Number of histogram bins looks too high ! Reducing it to: ',num2str(h.NumBins)]);
            end
        end
        
        function EnableFitFields(app)
            
            if app.par.FitFieldsMode==1 % MSD fit mode
                
                app.MSDsFitLabel.Visible='on';
                
                app.MSDsFitLabel.Text='MSDs Fit:';
                if app.par.fitdim==2
                    app.DumsEditFieldLabel.Text='D [µm²]';
                else
                    app.DumsEditFieldLabel.Text=['D [µm',char(179),']'];
                end
                app.AlphaEditFieldLabel.Text='Alpha';
                app.OffsetEditFieldLabel.Text='Offset';
                
                app.DumsEditField.Visible='on';
                app.DumsEditFieldLabel.Visible='on';
                app.DumsEditField.Value=app.saved_data(app.current_state).MSD_fit_par.D;
                
                if ~isempty(app.saved_data(app.current_state).MSD_fit_par.offset)
                    app.OffsetEditField.Visible='on';
                    app.OffsetEditFieldLabel.Visible='on';
                    app.OffsetEditField.Value=app.saved_data(app.current_state).MSD_fit_par.offset;
                else
                    app.OffsetEditField.Visible='off';
                    app.OffsetEditFieldLabel.Visible='off';
                end
                
                if ~isempty(app.saved_data(app.current_state).MSD_fit_par.alpha)
                    app.AlphaEditField.Visible='on';
                    app.AlphaEditFieldLabel.Visible='on';
                    app.AlphaEditField.Value=app.saved_data(app.current_state).MSD_fit_par.alpha;
                else
                    app.AlphaEditField.Visible='off';
                    app.AlphaEditFieldLabel.Visible='off';
                end
                
                
            elseif app.par.FitFieldsMode==2 % D fit from MSDs mode
                app.MSDsFitLabel.Visible='on';
                app.MSDsFitLabel.Text='D Histogram Fit (MSDs):';
                
                app.DumsEditField.Visible='on';
                app.DumsEditFieldLabel.Visible='on';
                if app.par.fitdim==2
                    app.DumsEditFieldLabel.Text='D1 [µm²]';
                else
                    app.DumsEditFieldLabel.Text=['D1 [µm',char(179),']'];
                end
                
                app.DumsEditField.Value=app.saved_data(app.current_state).D_fit_par.Fitted_D(1);
                
                if app.saved_data(app.current_state).D_fit_par.NbrFit>1
                    app.AlphaEditField.Visible='on';
                    app.AlphaEditFieldLabel.Visible='on';
                    if app.par.fitdim==2
                        app.AlphaEditFieldLabel.Text='D2 [µm²]';
                    else
                        app.AlphaEditFieldLabel.Text=['D2 [µm',char(179),']'];
                    end
                    app.AlphaEditField.Value=app.saved_data(app.current_state).D_fit_par.Fitted_D(2);
                end
                
                if app.saved_data(app.current_state).D_fit_par.NbrFit>2
                    app.OffsetEditField.Visible='on';
                    app.OffsetEditFieldLabel.Visible='on';
                    if app.par.fitdim==2
                        app.OffsetEditFieldLabel.Text='D3 [µm²]';
                    else
                        app.OffsetEditFieldLabel.Text=['D3 [µm',char(179),']'];
                    end
                    app.OffsetEditField.Value=app.saved_data(app.current_state).D_fit_par.Fitted_D(3);
                end
                
            elseif app.par.FitFieldsMode==3 % D fit from JDs mode
                app.MSDsFitLabel.Visible='on';
                app.MSDsFitLabel.Text='D Histogram Fit (JDs):';
                
                app.DumsEditField.Visible='on';
                app.DumsEditFieldLabel.Visible='on';
                if app.par.fitdim==2
                    app.DumsEditFieldLabel.Text='D1 [µm²]';
                else
                    app.DumsEditFieldLabel.Text=['D1 [µm',char(179),']'];
                end
                
                app.DumsEditField.Value=app.saved_data(app.current_state).D_fit_JDs_par.Fitted_D(1);
                
                if app.saved_data(app.current_state).D_fit_JDs_par.NbrFit>1
                    app.AlphaEditField.Visible='on';
                    app.AlphaEditFieldLabel.Visible='on';
                    if app.par.fitdim==2
                        app.AlphaEditFieldLabel.Text='D2 [µm²]';
                    else
                        app.AlphaEditFieldLabel.Text=['D2 [µm',char(179),']'];
                    end
                    app.AlphaEditField.Value=app.saved_data(app.current_state).D_fit_JDs_par.Fitted_D(2);
                end
                
                if app.saved_data(app.current_state).D_fit_JDs_par.NbrFit>2
                    app.OffsetEditField.Visible='on';
                    app.OffsetEditFieldLabel.Visible='on';
                    if app.par.fitdim==2
                        app.OffsetEditFieldLabel.Text='D3 [µm²]';
                    else
                        app.OffsetEditFieldLabel.Text=['D3 [µm',char(179),']'];
                    end
                    app.OffsetEditField.Value=app.saved_data(app.current_state).D_fit_JDs_par.Fitted_D(3);
                end
            end
        end
        
        function DisableFitFields(app)
            
            app.MSDsFitLabel.Visible='off';
            
            app.DumsEditField.Visible='off';
            app.DumsEditFieldLabel.Visible='off';
            app.AlphaEditField.Visible='off';
            app.AlphaEditFieldLabel.Visible='off';
            app.OffsetEditField.Visible='off';
            app.OffsetEditFieldLabel.Visible='off';
            
        end
        
        function InitSavedData(app)
            
            [app.saved_data(1:app.par.n_diff_states+2).ready]=deal(0); % Initialize the saved data
            [app.saved_data(1:app.par.n_diff_states+2).MSD_fit_ready]=deal(0);
            [app.saved_data(1:app.par.n_diff_states+2).D_fit_ready]=deal(0); % D fit from D histograms from MSDs fitting
            [app.saved_data(1:app.par.n_diff_states+2).D_fit_JDs_ready]=deal(0); % D fit from D histograms from JDs square fitting
            [app.saved_data(1:app.par.n_diff_states+2).D]=deal([]);
            [app.saved_data(1:app.par.n_diff_states+2).selected_tracks]=deal([]);
            [app.saved_data(1:app.par.n_diff_states+2).MSD_fit_par]=deal([]);
            [app.saved_data(1:app.par.n_diff_states+2).D_fit_par]=deal([]);
            [app.saved_data(1:app.par.n_diff_states+2).D_fit_JDs_par]=deal([]);
            
        end
        
        function [r,fit_ok]=Fit_MSD(app)
            
            
            switch app.par.fit_MSD_mode
                case 1 % Fit Average MSDs, alpha=1, k=0
                    
                    fitpar.C=[1,1]; % Define the constraints
                    fitpar.d=app.par.fitdim;
                    
                    % Do the fit
                    r=fit_SMIS_MSDMeans(app.par.D.LAGS,app.par.D.STATS.msd_array_mean,app.par.D.STATS.msd_array_std,fitpar);
                    
                    if ~isempty(r)
                        r.alpha=[];
                        r.offset=[];
                        fit_ok=1;
                    else
                        r=[];
                        fit_ok=0;
                    end
                    
                case 2 % Fit Average MSDs, alpha=free, k=0
                    
                    fitpar.C=[0,1]; % Define the constraints
                    fitpar.d=app.par.fitdim;
                    
                    % Do the fit
                    r=fit_SMIS_MSDMeans(app.par.D.LAGS,app.par.D.STATS.msd_array_mean,app.par.D.STATS.msd_array_std,fitpar);
                    
                    if ~isempty(r)
                        r.offset=[];
                        fit_ok=1;
                    else
                        r=[];
                        fit_ok=0;
                    end
                    
                case 3 % Fit Average MSDs, alpha=free, k=free
                    
                    fitpar.C=[0,0]; % Define the constraints
                    fitpar.d=app.par.fitdim;
                    
                    % Do the fit
                    r=fit_SMIS_MSDMeans(app.par.D.LAGS,app.par.D.STATS.msd_array_mean,app.par.D.STATS.msd_array_std,fitpar);
                    
                    if ~isempty(r)
                        fit_ok=1;
                    else
                        r=[];
                        fit_ok=0;
                    end
            end
            
        end
    end
    
    methods (Access = public)
        
        function KeepStack(app, stackpar)
            
            % Store inputs as properties
            app.par.stack_loaded = stackpar.stack_loaded;
            app.par.stack_filename = stackpar.stack_filename;
            app.par.stack = stackpar.stack;
            
            % Re-enable the button
            app.ShowSingleTracksButton.Enable = 'on';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.sm=mainapp.sm;
            app.im_par=mainapp.im_par;
            app.sm_par=mainapp.sm_par(mainapp.fluo_id);
            
            app.par.n_images=mainapp.im_par.n_images; % Number of images in data set
            app.par.totframetime=(mainapp.im_par.frametime+mainapp.im_par.addtime)*1e-3; % Total frame time in [s]
            app.TotalframetimesEditField.Value=app.par.totframetime;
            app.par.raster=app.im_par.raster*1e-3; % Raster size in microns
            
            if app.im_par.two_channel==0
                app.ChannelSwitch.Visible='off';
                app.ChannelSwitchLabel.Visible='off';
            end
            app.par.channel=1; % Use first channel to start with
            
            app.par.ph_lim=app.MinimumphotonsfordetectionEditField.Value;
            
            
            app.par.n_diff_states=numel(app.sm_par.D);
            
            app.par.blinking_gap = app.BlinkinggapFramesEditField.Value;
            app.par.min_phot = app.MinimumphotonsfordetectionEditField.Value;
            app.par.safety_margin  = app.FOVsafetymarginEditField.Value;
            app.par.min_tracklength= app.MinTrackLengthFramesEditField.Value;
            app.par.max_tracklength= app.MaxTrackLengthFramesEditField.Value;
            app.par.max_lag  = app.MaxlagforMSDsFramesEditField.Value;
            app.par.mode_3D= 0;
            app.par.PlotWhat=app.PlotButtonGroup.SelectedObject.Text;
            app.par.FinalFigureMode=0;
            app.par.D=[];
            
            app.par.stack_loaded=0;
            app.par.stack_filename=[];
            app.par.stack=[];
            
            app.FitDropDown.ItemsData=1:numel(app.FitDropDown.Items);
            app.par.FitDropDownItems=app.FitDropDown.Items; % Save the menu items
            app.par.fitdim=2;
            
            if app.im_par.simul_3D==0 % The Calculation switch should only be visible in 3D mode
                app.WorkinSwitch.Visible='off';
                app.WorkinSwitchLabel.Visible='off';
            end
            
            app.MinTrackLengthFramesEditField.Limits(2)=app.MaxTrackLengthFramesEditField.Value;
            app.MaxTrackLengthFramesEditField.Limits(1)=app.MinTrackLengthFramesEditField.Value;
            
            %Disable fitting
            app.FitDropDown.Visible='off';
            app.FitDropDownLabel.Visible='off';
            DisableFitFields(app)
            
            %Disable subtracks selection
            app.UseSwitch.Visible='off';
            app.UseSwitchLabel.Visible='off';
            
            app.par.datadir=mainapp.datadir;
            app.par.datafile=mainapp.datafile;
            
            app.par.use_tracks=1; % At the beginning, use tracks instead of subtracks
            
            % Setup Table
            SetupDTable(app);
            
            % Get the tracks
            GetTracks(app);
            InitSavedData(app); % Initialize the saved data
            
            % Analyze the tracks
            AnalyzeTracks(app);
            
            % Plot first SM
            PlotTracks(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            if ~isempty(app.ShowDiffTracesApp)
                if isvalid(app.ShowDiffTracesApp)
                    if ~isempty(app.ShowDiffTracesApp.OverlayRawDataApp)
                        if isvalid(app.ShowDiffTracesApp.OverlayRawDataApp)
                            delete(app.ShowDiffTracesApp.OverlayRawDataApp)
                        end
                    end
                    delete(app.ShowDiffTracesApp)
                end
            end
            close all
            delete(app)
        end

        % Value changed function: FontSizeSlider
        function FontSizeSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: AxisWidthSlider
        function AxisWidthSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: LineWidthSlider
        function LineWidthSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Callback function
        function YaxisSwitchValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: XaxisSwitch
        function XaxisSwitchValueChanged(app, event)
            PlotTracks(app)
        end

        % Button pushed function: MakefinalfiguresButton
        function MakefinalfiguresButtonPushed(app, event)
            
            close all; % Close all figures
            app.par.FinalFigureMode=true; % Switch to external figure mode
            StatesToPlot=find(app.DTable.Data{:,2}==true); % States to be plotted
            % Case of individual figures
            if strcmp(app.CompositefinalFigureSwitch.Value,'Off')==1
                app.par.FinalFigure.composite=0;
                if ~isempty(StatesToPlot)
                    for k=1:numel(StatesToPlot)
                        app.current_state=StatesToPlot(k);
                        AnalyzeTracks(app)
                        PlotTracks(app)
                    end
                end
            else % Case of composite figure
                app.par.FinalFigure.composite=1;
                if ~isempty(StatesToPlot)
                    n_sp=2*numel(StatesToPlot); % # of subplots
                    % Figure out the arrangment of subplots
                    subplot_n_cols=ceil(sqrt(n_sp));
                    subplot_n_rows=fix(sqrt(n_sp));
                    if subplot_n_cols*subplot_n_rows < n_sp
                        subplot_n_rows=subplot_n_rows+1;
                    end
                    app.par.FinalFigure.n_cols=subplot_n_cols;
                    app.par.FinalFigure.n_rows=subplot_n_rows;
                    app.par.FinalFigure.pos=1; % Initialize position within composite figure
                    %Define figure
                    FigTitle=['Diffusion Analysis: ',app.sm_par.fluorophore_name];
                    app.par.MyFigure=figure();
                    set(gcf,'Name',FigTitle)
                    set(app.par.MyFigure,'color','w');
                    % Go on with plotting
                    for k=1:numel(StatesToPlot)
                        app.current_state=StatesToPlot(k);
                        AnalyzeTracks(app)
                        PlotTracks(app)
                    end
                end
            end
            app.par.FinalFigureMode=false; % Switch back to internal figure mode
            
        end

        % Value changed function: BlinkinggapFramesEditField
        function BlinkinggapFramesEditFieldValueChanged(app, event)
            app.par.blinking_gap = app.BlinkinggapFramesEditField.Value;
            
            if app.ExtractsubtracksCheckBox.Value==1
                GetTracks(app);
                InitSavedData(app); % Re-initialize the saved data
                AnalyzeTracks(app);
                PlotTracks(app);
            end
        end

        % Value changed function: MinimumphotonsfordetectionEditField
        function MinimumphotonsfordetectionEditFieldValueChanged(app, event)
            app.par.min_phot = app.MinimumphotonsfordetectionEditField.Value;
            
            GetTracks(app);
            InitSavedData(app); % Re-initialize the saved data
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: FOVsafetymarginEditField
        function FOVsafetymarginEditFieldValueChanged(app, event)
            app.par.safety_margin  = app.FOVsafetymarginEditField.Value;
            
            GetTracks(app);
            InitSavedData(app); % Re-initialize the saved data
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: MinTrackLengthFramesEditField
        function MinTrackLengthFramesEditFieldValueChanged(app, event)
            app.par.min_tracklength = app.MinTrackLengthFramesEditField.Value;
            app.MaxTrackLengthFramesEditField.Limits(1)=app.MinTrackLengthFramesEditField.Value;
            
            %Reset the saved data for all diffusion states
            InitSavedData(app)
            
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: MaxTrackLengthFramesEditField
        function MaxTrackLengthFramesEditFieldValueChanged(app, event)
            app.par.max_tracklength  = app.MaxTrackLengthFramesEditField.Value;
            app.MinTrackLengthFramesEditField.Limits(2)=app.MaxTrackLengthFramesEditField.Value;
            
            %Reset the saved data for all diffusion states
            InitSavedData(app)
            
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: MaxlagforMSDsFramesEditField
        function MaxlagforMSDsFramesEditFieldValueChanged(app, event)
            app.par.max_lag  = app.MaxlagforMSDsFramesEditField.Value;
            
            %Reset the saved data for all diffusion states
            InitSavedData(app)
            
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: UseSwitch
        function UseSwitchValueChanged(app, event)
            if strcmp(app.UseSwitch.Value,'Tracks')==1
                app.par.use_tracks=1;
                app.DTable.RowName{1}='Tracks';
            else
                app.par.use_tracks=0;
                app.DTable.RowName{1}='Subtracks';
            end
            
            %Reset the saved data for all diffusion states
            InitSavedData(app)
            
            AnalyzeTracks(app);
            PlotTracks(app)
        end

        % Value changed function: YLimitSlider
        function YLimitSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: XLimitSlider
        function XLimitSliderValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: YLogScaleSwitch
        function YLogScaleSwitchValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: XLogScaleSwitch
        function XLogScaleSwitchValueChanged(app, event)
            PlotTracks(app)
        end

        % Cell selection callback: DTable
        function DTableCellSelection(app, event)
            
            if size(event.Indices,1)==1
                state_id = event.Indices(1);
                action_id = event.Indices(2);
            else
                state_id = event.Indices(:,1);
                action_id = event.Indices(1,2);
            end
            
            switch action_id
                case 1 % Show a state
                    if numel(state_id)==1 % Only do something if a particular state has been clicked
                        app.DTable.Data{:,1}=false;
                        app.DTable.Data{state_id,1}=true;
                        
                        % Set the current diffusion state to treat
                        app.current_state=state_id;
                        
                        % Analyze the tracks
                        AnalyzeTracks(app);
                        
                        % And plot the data
                        PlotTracks(app)
                        
                    end
                    
                case 2 % Select for final plot
                    if numel(state_id)==1
                        app.DTable.Data{state_id,action_id}=~app.DTable.Data{state_id,action_id};
                    else
                        app.DTable.Data{:,action_id}=true; % Select everything in that case
                    end
                    
            end
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotButtonGroup.SelectedObject;
            app.par.PlotWhat=selectedButton.Text;
            
            if strcmp(app.par.PlotWhat,'State Origin')==1 && app.par.plot_origin==0
                MyDialog=warndlg('Only valid in case of studying segments !','Warning');
                waitfor(MyDialog)
            else
                
                PlotProperty(app);
            end
        end

        % Value changed function: HistogramBinsEditField
        function HistogramBinsEditFieldValueChanged(app, event)
            PlotTracks(app)
        end

        % Value changed function: TracklengthsSwitch
        function TracklengthsSwitchValueChanged(app, event)
            AnalyzeTracks(app);
            PlotTracks(app)
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            SuggestedFile=[app.par.datafile(1:end-4),'_',app.sm_par.fluorophore_name,'_Diffusion_Analysis.mat'];
            
            cd(app.par.datadir);
            [MyFile,MyDir] = uiputfile('*.mat','SMIS File Selection',SuggestedFile);
            if ~isnumeric(MyDir) || ~isnumeric(MyFile)
                par=app.par; %#ok<ADPROPLC>
                im_par=app.im_par; %#ok<ADPROPLC>
                sm_par=app.sm_par; %#ok<ADPROPLC>
                tracks=app.tracks; %#ok<ADPROPLC>
                subtracks=app.subtracks; %#ok<ADPROPLC>
                segments=app.segments; %#ok<ADPROPLC>
                subsegments=app.subsegments; %#ok<ADPROPLC>
                save(fullfile(MyDir,MyFile),'par','im_par','sm_par','tracks','subtracks','segments','subsegments');
                disp('Good, diffusion they are saved !')
            end
        end

        % Button pushed function: ShowSingleTracksButton
        function ShowSingleTracksButtonPushed(app, event)
            app.ShowSingleTracksButton.Enable='off';
            app.ShowDiffTracesApp=Show_Diffusion_Traces(app);
        end

        % Value changed function: FitDropDown
        function FitDropDownValueChanged(app, event)
            value = app.FitDropDown.Value;
            
            if isempty(app.par.D)
                MyDialog=warndlg('There is no data to plot ! ','Warning');
                waitfor(MyDialog)
            end
            
            app.FitDropDown.Value=1;
            
            switch value
                case 2 % Fit Average MSDs, alpha=1, k=0
                    app.par.fit_MSD=1; % Enable fitting
                    app.par.fit_MSD_mode=1; % Define the mode
                    
                case 3 % Fit Average MSDs, alpha=free, k=0
                    app.par.fit_MSD=1; % Enable fitting
                    app.par.fit_MSD_mode=2; % Define the mode
                    
                case 4 % Fit Average MSDs, alpha=free, k=free
                    app.par.fit_MSD=1; % Enable fitting
                    app.par.fit_MSD_mode=3; % Define the mode
                    
                case 5 % Fit D Histogram, Gaussian linear
                    
                    DiffusionDisplayAndFitMethod=1; % 1,2: Linear; 3,4: Log10; 1,3: Gaussian fit; 2,4: Lorentzian fit
                    
                case 6 % Fit D Histogram, Lorentzian linear
                    
                    DiffusionDisplayAndFitMethod=2; % 1,2: Linear; 3,4: Log10; 1,3: Gaussian fit; 2,4: Lorentzian fit
                    
                case 7 % Fit D Histogram, Gaussian log
                    
                    DiffusionDisplayAndFitMethod=3; % 1,2: Linear; 3,4: Log10; 1,3: Gaussian fit; 2,4: Lorentzian fit
                    
                case 8 % Fit D Histogram, Lorentzian log
                    
                    DiffusionDisplayAndFitMethod=4; % 1,2: Linear; 3,4: Log10; 1,3: Gaussian fit; 2,4: Lorentzian fit
                    
            end
            
            if value>=2 && value <=4 % Case of MSD fitting
                [r,fit_ok]=Fit_MSD(app);
                
                if fit_ok==1
                    
                    app.par.MSD_fit_par.D=r.D;
                    app.par.MSD_fit_par.alpha=r.alpha;
                    app.par.MSD_fit_par.offset=r.offset;
                    
                    % Save the fitting data
                    app.saved_data(app.current_state).MSD_fit_par.D=app.par.MSD_fit_par.D;
                    app.saved_data(app.current_state).MSD_fit_par.alpha=app.par.MSD_fit_par.alpha;
                    app.saved_data(app.current_state).MSD_fit_par.offset=app.par.MSD_fit_par.offset;
                    
                    app.saved_data(app.current_state).MSD_fit_ready=1; % Okay, we can save the fit
                else
                    app.saved_data(app.current_state).MSD_fit_ready=0; % Too bad, the fit will need to be repeated
                end
                
                PlotMSDs(app)
                
            end
            
            if value>=5 && value <=8 % Case of D hist fitting (from MSDs)
                
                
                [NbrFit, Fitted_D, ~, Xfit, Yfit, n_bins, fit_ok]=Fit_D_His_SMIS(app.saved_data(app.current_state).D.Dapp,DiffusionDisplayAndFitMethod);
                
                % Plot results if fit is successful
                if strcmp(fit_ok,'Yes')
                    
                    app.par.D_fit_par.NbrFit=NbrFit;
                    app.par.D_fit_par.Fitted_D=Fitted_D;
                    
                    app.par.D_fit_par.Xfit=Xfit;
                    app.par.D_fit_par.Yfit=Yfit;
                    app.par.D_fit_par.n_bins=n_bins;
                    
                    if value==7 ||value==8
                        app.par.D_fit_par.UseLog=1;
                    else
                        app.par.D_fit_par.UseLog=0;
                    end
                    
                    % Save the fitting data
                    app.saved_data(app.current_state).D_fit_par.NbrFit=app.par.D_fit_par.NbrFit;
                    app.saved_data(app.current_state).D_fit_par.Fitted_D=app.par.D_fit_par.Fitted_D;
                    app.saved_data(app.current_state).D_fit_par.Xfit=app.par.D_fit_par.Xfit;
                    app.saved_data(app.current_state).D_fit_par.Yfit=app.par.D_fit_par.Yfit;
                    app.saved_data(app.current_state).D_fit_par.n_bins=app.par.D_fit_par.n_bins;
                    app.saved_data(app.current_state).D_fit_par.UseLog=app.par.D_fit_par.UseLog;
                    
                    app.saved_data(app.current_state).D_fit_ready=1; % Okay, we can save the fit
                    
                else
                    app.saved_data(app.current_state).D_fit_ready=0; % Too bad, the fit will need to be repeated
                end
                
                app.par.FitFieldsMode=2; % Display D fit fields from MSDs
                
                PlotMyData(app)
                
            end
            
            if value==9 % Case of D hist fitting (from JDs)
                
                
                [NbrFit, Fitted_D, ~, Xfit, Yfit, n_bins, fit_ok]=Fit_D_His_JDs_SMIS(app.saved_data(app.current_state).D.DappJD,app.saved_data(app.current_state).D.DappJD_used_tracklength);
                
                % Plot results if fit is successful
                if strcmp(fit_ok,'Yes')
                    
                    app.par.D_fit_JDs_par.NbrFit=NbrFit;
                    app.par.D_fit_JDs_par.Fitted_D=Fitted_D;
                    
                    app.par.D_fit_JDs_par.Xfit=Xfit;
                    app.par.D_fit_JDs_par.Yfit=Yfit;
                    app.par.D_fit_JDs_par.n_bins=n_bins;
                    
                    % Save the fitting data
                    app.saved_data(app.current_state).D_fit_JDs_par.NbrFit=app.par.D_fit_JDs_par.NbrFit;
                    app.saved_data(app.current_state).D_fit_JDs_par.Fitted_D=app.par.D_fit_JDs_par.Fitted_D;
                    app.saved_data(app.current_state).D_fit_JDs_par.Xfit=app.par.D_fit_JDs_par.Xfit;
                    app.saved_data(app.current_state).D_fit_JDs_par.Yfit=app.par.D_fit_JDs_par.Yfit;
                    app.saved_data(app.current_state).D_fit_JDs_par.n_bins=app.par.D_fit_JDs_par.n_bins;
                    app.saved_data(app.current_state).D_fit_JDs_ready=1; % Okay, we can save the fit
                    
                else
                    app.saved_data(app.current_state).D_fit_JDs_ready=0; % Too bad, the fit will need to be repeated
                end
                
                app.par.FitFieldsMode=3; % Display D fit fields from JDs
                
                PlotMyData(app)
                
            end
            
            
        end

        % Value changed function: WorkinSwitch
        function WorkinSwitchValueChanged(app, event)
            value = app.WorkinSwitch.Value;
            if strcmp(value,'2D')==true
                app.par.mode_3D=0;
                app.par.fitdim=2;
            else
                app.par.mode_3D=1;
                app.par.fitdim=3;
            end
            
            InitSavedData(app); % Reset everything
            
            AnalyzeTracks(app);
            PlotTracks(app);
            
        end

        % Value changed function: ChannelSwitch
        function ChannelSwitchValueChanged(app, event)
            app.par.channel = app.ChannelSwitch.Value;
            
            GetTracks(app);
            InitSavedData(app); % Re-initialize the saved data
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: ExtractsubtracksCheckBox
        function ExtractsubtracksCheckBoxValueChanged(app, event)
            value = app.ExtractsubtracksCheckBox.Value;
            
            if value==0
                app.par.use_tracks=1;
                app.DTable.RowName{1}='Tracks';
                app.UseSwitch.Value='Tracks';
                app.subtracks=[]; % Remove old subtracks
                app.subsegments=[]; % Remove old subsegments if they exist
                app.UseSwitch.Visible='off';
                app.UseSwitchLabel.Visible='off';
            else
                GetTracks(app); % Get the subtracks
                app.UseSwitch.Visible='on';
                app.UseSwitchLabel.Visible='on';
            end
            AnalyzeTracks(app);
            PlotTracks(app);
        end

        % Value changed function: ExtractsegmentsCheckBox
        function ExtractsegmentsCheckBoxValueChanged(app, event)
            value = app.ExtractsegmentsCheckBox.Value;
            
            if value==0
                app.segments=[]; % Remove old segments
                app.subsegments=[]; % Remove old subsegments if they exist
                app.current_state=1; % Reset current state to look at
                
                % Remove existing segments but not the tracks
                [app.saved_data(2:app.par.n_diff_states+2).ready]=deal(0); % Initialize the saved data
                [app.saved_data(2:app.par.n_diff_states+2).MSD_fit_ready]=deal(0);
                [app.saved_data(2:app.par.n_diff_states+2).D_fit_ready]=deal(0); % D fit from D histograms from MSDs fitting
                [app.saved_data(2:app.par.n_diff_states+2).D_fit_JDs_ready]=deal(0); % D fit from D histograms from JDs square fitting
                [app.saved_data(2:app.par.n_diff_states+2).D]=deal([]);
                [app.saved_data(2:app.par.n_diff_states+2).selected_tracks]=deal([]);
                [app.saved_data(2:app.par.n_diff_states+2).MSD_fit_par]=deal([]);
                [app.saved_data(2:app.par.n_diff_states+2).D_fit_par]=deal([]);
                [app.saved_data(2:app.par.n_diff_states+2).D_fit_JDs_par]=deal([]);
                
                app.DTable.Data{:,1}=false;
                app.DTable.Data{1,1}=true;
            else
                GetTracks(app); % Get the segments and subsegments
            end
            
            AnalyzeTracks(app);
            PlotTracks(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrackLengthAxis = uiaxes(app.UIFigure);
            title(app.TrackLengthAxis, 'Diffusion State Lifetime')
            xlabel(app.TrackLengthAxis, 'Time [s]')
            ylabel(app.TrackLengthAxis, 'PhotoPhysical State')
            app.TrackLengthAxis.XLim = [200 800];
            app.TrackLengthAxis.YLim = [0 100];
            app.PropertyAxes = uiaxes(app.UIFigure);
            title(app.PropertyAxes, 'Mean Jump Distance Histogram')
            xlabel(app.PropertyAxes, 'X')
            ylabel(app.PropertyAxes, 'Y')
            zlabel(app.PropertyAxes, 'Z')
            app.AnalysisofdiffusiontracksLabel = uilabel(app.UIFigure);
            app.AnalysisofdiffusiontracksLabel.Text = 'Analysis of diffusion tracks';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main menu and close all downstream analysis windows'};
            app.OKButton.Text = 'OK';
            app.XaxisSwitchLabel = uilabel(app.UIFigure);
            app.XaxisSwitchLabel.Text = 'X-axis';
            app.XaxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.XaxisSwitch.Items = {'Frames', 'Time'};
            app.XaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @XaxisSwitchValueChanged, true);
            app.XaxisSwitch.Tooltip = {'Use this toggle to switch between frames and time'};
            app.XaxisSwitch.Value = 'Frames';
            app.FontSizeSliderLabel = uilabel(app.UIFigure);
            app.FontSizeSliderLabel.Text = 'Font Size';
            app.FontSizeSlider = uislider(app.UIFigure);
            app.FontSizeSlider.Limits = [8 20];
            app.FontSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeSliderValueChanged, true);
            app.FontSizeSlider.MinorTicks = [];
            app.FontSizeSlider.Tooltip = {'Use this slider to adjust font size'};
            app.FontSizeSlider.Value = 14;
            app.AxisWidthSliderLabel = uilabel(app.UIFigure);
            app.AxisWidthSliderLabel.Text = 'Axis Width';
            app.AxisWidthSlider = uislider(app.UIFigure);
            app.AxisWidthSlider.Limits = [1 8];
            app.AxisWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxisWidthSliderValueChanged, true);
            app.AxisWidthSlider.MinorTicks = [];
            app.AxisWidthSlider.Tooltip = {'Use this slider to adjust axis width'};
            app.AxisWidthSlider.Value = 3;
            app.LineWidthSliderLabel = uilabel(app.UIFigure);
            app.LineWidthSliderLabel.Text = 'Line Width';
            app.LineWidthSlider = uislider(app.UIFigure);
            app.LineWidthSlider.Limits = [1 8];
            app.LineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @LineWidthSliderValueChanged, true);
            app.LineWidthSlider.MinorTicks = [];
            app.LineWidthSlider.Tooltip = {'Use this slider to adjust line width'};
            app.LineWidthSlider.Value = 3;
            app.MakefinalfiguresButton = uibutton(app.UIFigure, 'push');
            app.MakefinalfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @MakefinalfiguresButtonPushed, true);
            app.MakefinalfiguresButton.Tooltip = {'Click this button to produce figures, based on data selected in the Plot Final column of the table,  that can be saved on disk.'};
            app.MakefinalfiguresButton.Text = 'Make final figures';
            app.DTable = uitable(app.UIFigure);
            app.DTable.ColumnName = {'Show'; 'Final Plot'};
            app.DTable.RowName = {};
            app.DTable.CellSelectionCallback = createCallbackFcn(app, @DTableCellSelection, true);
            app.DTable.Tooltip = {'Table showing the different diffusion states in the SMIS simulation. Select tracks or diffusion state to be analyzed or to produce final plot. To analyze subtracks (in between blinking gaps larger than the specified blinking gap), click first on "Extract subtracks", and toggle to "Use Subtracks". To analyze diffusion states, first click on "Extract segments" '};
            app.ShowSingleTracksButton = uibutton(app.UIFigure, 'push');
            app.ShowSingleTracksButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSingleTracksButtonPushed, true);
            app.ShowSingleTracksButton.Tooltip = {'Click on this button to examine single tracks'};
            app.ShowSingleTracksButton.Text = 'Show Single Tracks';
            app.MinTrackLengthFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MinTrackLengthFramesEditFieldLabel.Text = 'Min Track Length [Frames]';
            app.MinTrackLengthFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinTrackLengthFramesEditField.Limits = [1 Inf];
            app.MinTrackLengthFramesEditField.ValueDisplayFormat = '%.0f';
            app.MinTrackLengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthFramesEditFieldValueChanged, true);
            app.MinTrackLengthFramesEditField.Tooltip = {'Minimum tracklength to consider, for tracklength display but also for calculation of mean jump distances (MJDs), or other parameters. Importantly this value is used as the reference tracklength for calculating diffusion coefficients from JDs (ie D''s will be calculated from subtracks of length =  this value)'};
            app.MinTrackLengthFramesEditField.Value = 4;
            app.MaxTrackLengthFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxTrackLengthFramesEditFieldLabel.Text = 'Max Track Length [Frames]';
            app.MaxTrackLengthFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxTrackLengthFramesEditField.Limits = [2 Inf];
            app.MaxTrackLengthFramesEditField.ValueDisplayFormat = '%.0f';
            app.MaxTrackLengthFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTrackLengthFramesEditFieldValueChanged, true);
            app.MaxTrackLengthFramesEditField.Tooltip = {'Maximum tracklength to consider, for tracklength display but also for calculation of mean jump distances (MJDs), or other parameters.'};
            app.MaxTrackLengthFramesEditField.Value = Inf;
            app.BlinkinggapFramesEditFieldLabel = uilabel(app.UIFigure);
            app.BlinkinggapFramesEditFieldLabel.Text = 'Blinking gap [Frames] ';
            app.BlinkinggapFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlinkinggapFramesEditField.Limits = [0 Inf];
            app.BlinkinggapFramesEditField.ValueDisplayFormat = '%.0f';
            app.BlinkinggapFramesEditField.ValueChangedFcn = createCallbackFcn(app, @BlinkinggapFramesEditFieldValueChanged, true);
            app.BlinkinggapFramesEditField.Tooltip = {'Blinking gap to separate subtracks from tracks. If blinking gap = inf, the subtracks will equal the tracks; if blinking gap = 0, subtracks will contain no blinking. '};
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Tooltip = {'Analysis for tracks or subtracks or segments determined by used input parameters (minimum and maximum tracklength, blinking gap, etc ...)'; ''; 'MJD: display histogram of the mean jump distances '; 'JD: display histogram of the jump distances '; 'MSD: average mean square displacement'; 'offtimes: display histogram off offtimes'; 'state origin: only valid for segments: display histogram of previous diffusion state for segments of specified diffusion state, when exchange kinetics is present'; 'D from MSD''s: get apparent diffusion coefficient histogram from individual MSD plots: fitting can then be attempted using the Fit menu'; 'D from JD''s: get apparent diffusion coefficient histogram from individual MJDs extracted from bits of tracks of length specified by the min track length: fitting can then be attempted using the Fit menu'; ''};
            app.PlotButtonGroup.Title = 'Plot';
            app.MJDButton = uitogglebutton(app.PlotButtonGroup);
            app.MJDButton.Text = 'MJD';
            app.MJDButton.Value = true;
            app.JDButton = uitogglebutton(app.PlotButtonGroup);
            app.JDButton.Text = 'JD';
            app.MSDButton = uitogglebutton(app.PlotButtonGroup);
            app.MSDButton.Text = 'MSD';
            app.OfftimesButton = uitogglebutton(app.PlotButtonGroup);
            app.OfftimesButton.Text = 'Offtimes';
            app.StateOriginButton = uitogglebutton(app.PlotButtonGroup);
            app.StateOriginButton.Text = 'State Origin';
            app.DfomMSDsButton = uitogglebutton(app.PlotButtonGroup);
            app.DfomMSDsButton.Text = 'D fom MSDs';
            app.DfomJDsButton = uitogglebutton(app.PlotButtonGroup);
            app.DfomJDsButton.Text = 'D fom JDs';
            app.MinimumphotonsfordetectionEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumphotonsfordetectionEditFieldLabel.Text = 'Minimum photons for detection';
            app.MinimumphotonsfordetectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumphotonsfordetectionEditField.Limits = [0 Inf];
            app.MinimumphotonsfordetectionEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumphotonsfordetectionEditFieldValueChanged, true);
            app.MinimumphotonsfordetectionEditField.Tooltip = {'Minimum number of photons necessary to consider a localization as detectable. Localizations with lower numbers of detected photons will be considered as nondetected (ie as blink)'};
            app.MinimumphotonsfordetectionEditField.Value = 5;
            app.FOVsafetymarginEditFieldLabel = uilabel(app.UIFigure);
            app.FOVsafetymarginEditFieldLabel.Text = 'FOV safety margin';
            app.FOVsafetymarginEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVsafetymarginEditField.ValueDisplayFormat = '%.0f';
            app.FOVsafetymarginEditField.ValueChangedFcn = createCallbackFcn(app, @FOVsafetymarginEditFieldValueChanged, true);
            app.FOVsafetymarginEditField.Tooltip = {'Field-of-view safety margin: molecules going near the border of the field-of-view by this number of pixel will be considered as transiently lost (ie producing blinks). Set to -inf to eventually prevent this'};
            app.MaxlagforMSDsFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlagforMSDsFramesEditFieldLabel.Text = 'Max lag for MSDs [Frames]';
            app.MaxlagforMSDsFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlagforMSDsFramesEditField.Limits = [1 1000];
            app.MaxlagforMSDsFramesEditField.ValueDisplayFormat = '%.0f';
            app.MaxlagforMSDsFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlagforMSDsFramesEditFieldValueChanged, true);
            app.MaxlagforMSDsFramesEditField.Tooltip = {'Maximum lagtime for fitting diffusion coefficients from mean square displacement plots.'};
            app.MaxlagforMSDsFramesEditField.Value = 4;
            app.UseSwitchLabel = uilabel(app.UIFigure);
            app.UseSwitchLabel.Text = 'Use';
            app.UseSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseSwitch.Items = {'Tracks', 'Subtracks'};
            app.UseSwitch.ValueChangedFcn = createCallbackFcn(app, @UseSwitchValueChanged, true);
            app.UseSwitch.Tooltip = {'Use this switch to toggle between displaying tracks or subtracks'};
            app.UseSwitch.Value = 'Tracks';
            app.HistogramBinsEditFieldLabel = uilabel(app.UIFigure);
            app.HistogramBinsEditFieldLabel.Text = 'Histogram Bins';
            app.HistogramBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HistogramBinsEditField.Limits = [2 10000];
            app.HistogramBinsEditField.ValueDisplayFormat = '%.0f';
            app.HistogramBinsEditField.ValueChangedFcn = createCallbackFcn(app, @HistogramBinsEditFieldValueChanged, true);
            app.HistogramBinsEditField.Tooltip = {'Change number of bins in histograms'};
            app.HistogramBinsEditField.Value = 25;
            app.XLimitSliderLabel = uilabel(app.UIFigure);
            app.XLimitSliderLabel.Text = 'X Limit [%]';
            app.XLimitSlider = uislider(app.UIFigure);
            app.XLimitSlider.MajorTicks = [0 20 40 60 80 100];
            app.XLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XLimitSliderValueChanged, true);
            app.XLimitSlider.Tooltip = {'Use this slider to change the X limits of the plots'};
            app.XLimitSlider.Value = 100;
            app.YLimitSliderLabel = uilabel(app.UIFigure);
            app.YLimitSliderLabel.Text = 'Y Limit [%]';
            app.YLimitSlider = uislider(app.UIFigure);
            app.YLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YLimitSliderValueChanged, true);
            app.YLimitSlider.Tooltip = {'Use this slider to change the Y limits of the plots'};
            app.YLimitSlider.Value = 100;
            app.YLogScaleSwitchLabel = uilabel(app.UIFigure);
            app.YLogScaleSwitchLabel.Text = 'Y Log Scale';
            app.YLogScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.YLogScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @YLogScaleSwitchValueChanged, true);
            app.YLogScaleSwitch.Tooltip = {'Switch to log scale on Y-axis'};
            app.XLogScaleSwitchLabel = uilabel(app.UIFigure);
            app.XLogScaleSwitchLabel.Text = 'X Log Scale';
            app.XLogScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.XLogScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @XLogScaleSwitchValueChanged, true);
            app.XLogScaleSwitch.Tooltip = {'Switch to log scale on X-axis'};
            app.NumberofselectedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofselectedEditFieldLabel.Text = 'Number of selected ';
            app.NumberofselectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofselectedEditField.Limits = [0 Inf];
            app.NumberofselectedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofselectedEditField.Editable = 'off';
            app.NumberofselectedEditField.Value = 1;
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Tooltip = {'Returns to the main menu'};
            app.SavedataButton.Text = 'Save data';
            app.MeantracklengthEditFieldLabel = uilabel(app.UIFigure);
            app.MeantracklengthEditFieldLabel.Text = 'Mean tracklength';
            app.MeantracklengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeantracklengthEditField.Limits = [0 Inf];
            app.MeantracklengthEditField.Editable = 'off';
            app.MeantracklengthEditField.Value = 1;
            app.MeanpropertyEditFieldLabel = uilabel(app.UIFigure);
            app.MeanpropertyEditFieldLabel.Text = 'Mean property';
            app.MeanpropertyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanpropertyEditField.Limits = [0 Inf];
            app.MeanpropertyEditField.Editable = 'off';
            app.MeanpropertyEditField.Value = 1;
            app.TotalframetimesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalframetimesEditFieldLabel.Text = 'Total frame time [s]';
            app.TotalframetimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalframetimesEditField.Limits = [0 Inf];
            app.TotalframetimesEditField.Editable = 'off';
            app.TotalframetimesEditField.Tooltip = {'Displays the total frame time (addtime + frametime)'};
            app.TotalframetimesEditField.Value = 1;
            app.TracklengthsSwitchLabel = uilabel(app.UIFigure);
            app.TracklengthsSwitchLabel.Text = 'Tracklengths';
            app.TracklengthsSwitch = uiswitch(app.UIFigure, 'slider');
            app.TracklengthsSwitch.Items = {'With Offtimes', 'Without Offtimes'};
            app.TracklengthsSwitch.ValueChangedFcn = createCallbackFcn(app, @TracklengthsSwitchValueChanged, true);
            app.TracklengthsSwitch.Tooltip = {'Display the tracklengths including offtimes (longer: undetected localization during gaps will be counted) or not (shorter: only detected localizations will be counted).  '};
            app.TracklengthsSwitch.Value = 'With Offtimes';
            app.CompositefinalFigureSwitchLabel = uilabel(app.UIFigure);
            app.CompositefinalFigureSwitchLabel.Text = 'Composite final Figure ';
            app.CompositefinalFigureSwitch = uiswitch(app.UIFigure, 'slider');
            app.CompositefinalFigureSwitch.Tooltip = {'Set to on to produce a composite (single window) output figure recapitulating the data selected in the Plot Final column of the table.'};
            app.FitDropDownLabel = uilabel(app.UIFigure);
            app.FitDropDownLabel.Text = 'Fit ';
            app.FitDropDown = uidropdown(app.UIFigure);
            app.FitDropDown.Items = {'Choose', 'Fit Average MSDs, alpha=1, k=0', 'Fit Average MSDs, alpha=free, k=0', 'Fit Average MSDs, alpha=free, k=free', 'Fit Dcoeff histogram, Linear Gaussian', 'Fit Dcoeff histogram, Linear Lorentzian', 'Fit Dcoeff histogram, Log Gaussian', 'Fit Dcoeff histogram, Log Lorentzian', 'Fit Dcoeff histogram, from JD''s '};
            app.FitDropDown.ValueChangedFcn = createCallbackFcn(app, @FitDropDownValueChanged, true);
            app.FitDropDown.Tooltip = {'Fit apparent diffusion coefficient histograms either extracted from MSD''s or from JDs. 1, 2 or 3 state fitting can be achieved, different options for fitting the MSDs-based histograms are offered.  '};
            app.FitDropDown.Value = 'Choose';
            app.WorkinSwitchLabel = uilabel(app.UIFigure);
            app.WorkinSwitchLabel.Text = 'Work in';
            app.WorkinSwitch = uiswitch(app.UIFigure, 'slider');
            app.WorkinSwitch.Items = {'2D', '3D'};
            app.WorkinSwitch.ValueChangedFcn = createCallbackFcn(app, @WorkinSwitchValueChanged, true);
            app.WorkinSwitch.Tooltip = {'For 3D datasets, calculate the parameters either in 2D (general experimental case, using only X and Y coordinates) or in 3D (using X, Y, and Z coordinates)'};
            app.WorkinSwitch.Value = '2D';
            app.DumsEditFieldLabel = uilabel(app.UIFigure);
            app.DumsEditFieldLabel.Text = 'D [um²/s]';
            app.DumsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DumsEditField.ValueDisplayFormat = '%5.2f';
            app.DumsEditField.Editable = 'off';
            app.DumsEditField.Tooltip = {'Result of fit for the indicated parameter'};
            app.DumsEditField.Value = 1;
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.ValueDisplayFormat = '%5.2f';
            app.AlphaEditField.Editable = 'off';
            app.AlphaEditField.Tooltip = {'Result of fit for the indicated parameter'};
            app.AlphaEditField.Value = 1;
            app.OffsetEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetEditField.ValueDisplayFormat = '%5.4f';
            app.OffsetEditField.Editable = 'off';
            app.OffsetEditField.Tooltip = {'Result of fit for the indicated parameter'};
            app.OffsetEditField.Value = 1;
            app.MSDsFitLabel = uilabel(app.UIFigure);
            app.MSDsFitLabel.Text = 'MSDs Fit';
            app.ChannelSwitchLabel = uilabel(app.UIFigure);
            app.ChannelSwitchLabel.Text = 'Channel';
            app.ChannelSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChannelSwitch.Items = {'1', '2'};
            app.ChannelSwitch.ValueChangedFcn = createCallbackFcn(app, @ChannelSwitchValueChanged, true);
            app.ChannelSwitch.Value = '1';
            app.ExtractsubtracksCheckBox = uicheckbox(app.UIFigure);
            app.ExtractsubtracksCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtractsubtracksCheckBoxValueChanged, true);
            app.ExtractsubtracksCheckBox.Tooltip = {'Click to extract subtracks that correspond to continuous parts of the tracks separated by the specified blinking gap'};
            app.ExtractsubtracksCheckBox.Text = 'Extract subtracks';
            app.ExtractsegmentsCheckBox = uicheckbox(app.UIFigure);
            app.ExtractsegmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtractsegmentsCheckBoxValueChanged, true);
            app.ExtractsegmentsCheckBox.Tooltip = {'Click to extract segments corresponding to single diffusion states within tracks or subtracks. This can take time ...'};
            app.ExtractsegmentsCheckBox.Text = 'Extract segments';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Diffusion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end