classdef Show_Diffusion_Traces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IncludeBlinksSwitch         matlab.ui.control.Switch
        IncludeBlinksSwitchLabel    matlab.ui.control.Label
        OverlayrawdataButton        matlab.ui.control.Button
        YaxisSwitch                 matlab.ui.control.Switch
        YaxisSwitchLabel            matlab.ui.control.Label
        PlotButtonGroup             matlab.ui.container.ButtonGroup
        DiffusionstatetraceButton   matlab.ui.control.ToggleButton
        MSDButton                   matlab.ui.control.ToggleButton
        ColorSwitch                 matlab.ui.control.Switch
        ColorSwitchLabel            matlab.ui.control.Label
        ScaleSwitch                 matlab.ui.control.Switch
        ScaleSwitchLabel            matlab.ui.control.Label
        TracklengthEditField        matlab.ui.control.NumericEditField
        TracklengthEditFieldLabel   matlab.ui.control.Label
        MaxlagFramesEditField       matlab.ui.control.NumericEditField
        MaxlagFramesEditFieldLabel  matlab.ui.control.Label
        FitDSwitch                  matlab.ui.control.Switch
        FitDSwitchLabel             matlab.ui.control.Label
        FittedDumsEditField         matlab.ui.control.NumericEditField
        FittedDumsEditFieldLabel    matlab.ui.control.Label
        MakefigureButton            matlab.ui.control.Button
        TrackSpinner                matlab.ui.control.Spinner
        TrackSpinnerLabel           matlab.ui.control.Label
        YHighLimitSlider            matlab.ui.control.Slider
        YHighLimitSliderLabel       matlab.ui.control.Label
        XHighLimitSlider            matlab.ui.control.Slider
        XHighLimitSliderLabel       matlab.ui.control.Label
        YLowLimitSlider             matlab.ui.control.Slider
        YLowLimitSliderLabel        matlab.ui.control.Label
        XLowLimitSlider             matlab.ui.control.Slider
        XLowLimitSliderLabel        matlab.ui.control.Label
        LineWidthSlider             matlab.ui.control.Slider
        LineWidthSliderLabel        matlab.ui.control.Label
        AxisWidthSlider             matlab.ui.control.Slider
        AxisWidthSliderLabel        matlab.ui.control.Label
        FontSizeSlider              matlab.ui.control.Slider
        FontSizeSliderLabel         matlab.ui.control.Label
        XaxisSwitch                 matlab.ui.control.Switch
        XaxisSwitchLabel            matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        SingleMoleculesDiffusionTracksLabel  matlab.ui.control.Label
        MSDAxis                     matlab.ui.control.UIAxes
        TrackAxis                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        n=[]; % The current track id
        sm=[]; % The single molecules
        sm_par=[]; % SM parameters
    end
    
    properties (Access = public)
        par=[]; % General parameters
        im_par=[]; % Imaging parameters
        OverlayRawDataApp=[]; % Call to OverlayRawDataApp
    end
    
    methods (Access = private)
        
        
        function PlotTrack(app)
            
            if app.par.FinalFigureMode==0
                MyAxis=app.TrackAxis;
            else
                figure('Color','white');
                MyAxis=gca;
            end
            
            cla(MyAxis)
            axis(MyAxis,'image')
            hold(MyAxis,'on')
            
            
            %Get track to plot
            MyTrack=app.par.selected_tracks(app.n);
            
            
            if strcmp(app.IncludeBlinksSwitch.Value,'Yes')==1 % In that case include blinks in track representation
                MySM=app.sm(app.par.selected_tracks(app.n).molecule_id);
                
                minFrame=min(app.par.selected_tracks(app.n).frames);
                maxFrame=max(app.par.selected_tracks(app.n).frames);
                allFrames=MySM.diff_state_trace(1,:);
                w=find(allFrames>=minFrame & allFrames <=maxFrame);
                
                X=(MySM.x_track(w,1)-0.5)/app.im_par.binning+0.5;
                Y=(MySM.y_track(w,1)-0.5)/app.im_par.binning+0.5;
                
                if app.par.mode_3D==1
                    Z=(MySM.z_track(w,1)-0.5)/app.im_par.binning+0.5;
                end
                
                MyTrackLength=MyTrack.tot_tracklength;
                
            else % Do not include blinks in track representation
                Y=[MyTrack.y];
                X=[MyTrack.x];
                if app.par.mode_3D==1
                    Z=[MyTrack.z];
                end
                
                MyTrackLength=MyTrack.tracklength;
                
                
            end
            
            if strcmp(app.XaxisSwitch.Value,'Time')==1
                app.TracklengthEditField.Value=MyTrackLength*app.par.totframetime;
                app.TracklengthEditFieldLabel.Text='Tracklength [s]';
            else
                app.TracklengthEditField.Value=MyTrackLength;
                app.TracklengthEditFieldLabel.Text='Tracklength [frames]';
            end


            if strcmp(app.ScaleSwitch.Value,'Pixels')==0
                X=X*app.im_par.raster*1e-3; % Convert to microns
                Y=Y*app.im_par.raster*1e-3; % Convert to microns
                if app.par.mode_3D==1
                    Z=Z*app.im_par.raster*1e-3; % Convert to microns
                end
            end
            
            if app.par.mode_3D==0
                if strcmp(app.ColorSwitch.Value,'Multi')==1
                    ColorVector = ColorCoding(MyTrackLength);
                    
                    % Using scatterplot is much faster in that case than using plot
                    scatter(MyAxis,X, Y, 2, ColorVector, 'LineWidth',app.LineWidthSlider.Value+2);
                    hold(MyAxis,'on')
                    line(MyAxis,X,Y,'Color',0.5*[1 1 1],'LineStyle','--','LineWidth',max([app.LineWidthSlider.Value-1,1]));
                    hold(MyAxis,'off')
                    
                else
                    ColorVector = rand(1,3);
                    plot(MyAxis,X, Y, '--+', 'Color', ColorVector, 'LineWidth',app.LineWidthSlider.Value);
                end
            else
                view(MyAxis, 3);
                if strcmp(app.ColorSwitch.Value,'Multi')==1
                    ColorVector = ColorCoding(MyTrackLength);
                    scatter3(MyAxis,X, Y, Z, 2, ColorVector, 'LineWidth',app.LineWidthSlider.Value+2);
                    hold(MyAxis,'on')
                    line(MyAxis,X,Y,Z,'Color',0.5*[1 1 1],'LineStyle','--','LineWidth',max([app.LineWidthSlider.Value-1,1]));
                    hold(MyAxis,'off')
                else
                    ColorVector = rand(1,3);
                    plot3(MyAxis,X, Y, Z, '--+', 'Color', ColorVector, 'LineWidth',app.LineWidthSlider.Value);
                end
            end
            
            hold(MyAxis,'off')
            
            if strcmp(app.ScaleSwitch.Value,'Pixels')==1
                xlabel(MyAxis,'X [raster]')
                ylabel(MyAxis,'Y [raster]');
                if app.par.mode_3D==1
                    zlabel(MyAxis,'Z [raster]');
                end
            else
                xlabel(MyAxis,'X [µm]')
                ylabel(MyAxis,'Y [µm]');
                if app.par.mode_3D==1
                    zlabel(MyAxis,'Z [µm]');
                end
            end
            
            set(MyAxis,'FontSize',app.FontSizeSlider.Value);
            set(MyAxis,'FontWeight','b');
            
            XLowLim=min(X)+app.XLowLimitSlider.Value/100*(max(X)-min(X));
            XHighLim=min(X)+app.XHighLimitSlider.Value/100*(max(X)-min(X));
            YLowLim=min(Y)+app.YLowLimitSlider.Value/100*(max(Y)-min(Y));
            YHighLim=min(Y)+app.YHighLimitSlider.Value/100*(max(Y)-min(Y));
            
            if XLowLim==XHighLim
                XLowLim=XLowLim*0.95;
                XHighLim=XHighLim*1.05;
            end
            
            if YLowLim==YHighLim
                YLowLim=YLowLim*0.95;
                YHighLim=YHighLim*1.05;
            end
            
            xlim(MyAxis,[XLowLim XHighLim]);
            ylim(MyAxis,[YLowLim YHighLim]);
            
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
            
        end
        
        
        function PlotMSD(app)
            if ~isempty(app.par.D.LAGS)
                
                % Select whether internal or external figure
                if app.par.FinalFigureMode==0
                    MyAxis=app.MSDAxis;
                else
                    figure('Color','white');
                    MyAxis=gca;
                end
                
                %Get the lags and the MSD's for current track
                LAGS=app.par.D.MSD(app.par.D.MSD(:,1)==app.n,2);
                MSDs=app.par.D.MSD(app.par.D.MSD(:,1)==app.n,3);
                
                
                %Get the mean and standard deviation for each lag for current track
                MSDLags=unique(LAGS);
                max_lag=min([numel(MSDLags),app.MaxlagFramesEditField.Value]);
                MSDLags(MSDLags>max_lag)=[];
                n_lags=numel(MSDLags);
                
                MSDMeans=zeros(1,n_lags);
                MSDStds=zeros(1,n_lags);
                
                for k=1:n_lags
                    w=LAGS==MSDLags(k);
                    MSDMeans(k)=mean(MSDs(w));
                    MSDStds(k)=std(MSDs(w))/sqrt(length(MSDs(w))); % standard error of the mean
                end
                
                if strcmp(app.XaxisSwitch.Value,"Time")==1
                    MSDLags=MSDLags*app.par.totframetime;
                end
                
                % Do the plot
                errorbar(MyAxis,MSDLags, MSDMeans, MSDStds, '-s', 'Color', [1 0.5 0], 'LineWidth', app.LineWidthSlider.Value);
                
                if strcmp(app.XaxisSwitch.Value,'Time')==1
                    xlabel(MyAxis,'Lag [s]');
                else
                    xlabel(MyAxis,'Lag [frames]');
                end
                
                set(MyAxis, 'XTickMode', 'auto', 'XTickLabelMode', 'auto') % Important if state origin has been plotted before
                XWindowMin=min(MSDLags)-app.par.totframetime/5;
                XWindowMax=max(MSDLags)+app.par.totframetime/5;
                
                if XWindowMax>0
                    xlim(MyAxis,[XWindowMin,XWindowMin+(XWindowMax-XWindowMin)*app.XHighLimitSlider.Value/100]);
                end
                
                if app.par.mode_3D==0
                    ylabel(MyAxis,'MSDs [µm²]')
                else
                    ylabel(MyAxis,'MSDs [µm^3]')
                end
                
                title(MyAxis,'Single Molecule MSD','FontWeight','bold');
                
                YWindowMin=min(MSDMeans-MSDStds)-min(MSDStds);
                YWindowMax=max(MSDMeans+MSDStds)+min(MSDStds);
                if YWindowMin==YWindowMax
                    YWindowMin=YWindowMin*0.95;
                    YWindowMax=YWindowMax*1.05;
                end
                if YWindowMax>0
                    ylim(MyAxis,[YWindowMin,YWindowMin+(YWindowMax-YWindowMin)*app.YHighLimitSlider.Value/100]);
                end
                set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
                set(MyAxis,'FontWeight','bold');
                set(MyAxis,'FontSize',app.FontSizeSlider.Value);
                
                %Fit Diffusion coefficient if required
                if strcmp(app.FitDSwitch.Value,'Yes')==true
                    
                    if n_lags>=2 % Only fit if at least 2 lags
                        % Make sure that lags are in seconds
                        if strcmp(app.XaxisSwitch.Value,"Time")~=1
                            XToFit=MSDLags*app.par.totframetime; % If app.XaxisSwitch.Value = "time", Conversion was already made
                        else
                            XToFit=MSDLags;
                        end
                        YToFit=MSDMeans';
                        
                        Weight=1./MSDStds';
                        Weight(isinf(Weight))=0.1*min(Weight(~isinf(Weight))); % trick to avoid +inf Weights
                        
                        [fitobject,~] = fit(XToFit, YToFit, 'Poly1', 'Weights', Weight, 'Upper', [Inf, min(YToFit)]);
                        if fitobject.p1>0
                            Dapp = fitobject.p1/(2*app.par.fitdim); % Return the coefficient of diffusion
                            if app.par.mode_3D==0
                                disp(['Apparent diffusion coefficient [um²/s]: ',num2str(Dapp)]);
                            else
                                disp(['Apparent diffusion coefficient [um',char(179),'/s]: ',num2str(Dapp)]);
                            end
                            app.FittedDumsEditField.Value=Dapp;
                            YFit=fitobject.p1*XToFit+fitobject.p2;
                            hold(MyAxis,'on')
                            plot(MyAxis,MSDLags, YFit, 'Color', [1 0 0], 'LineStyle', '--', 'LineWidth', max([1,app.LineWidthSlider.Value-1]));
                            hold(MyAxis,'off')
                        else
                            disp(['Nonsense (negative) diffusion coefficient !']);
                        end
                    else
                        disp(['Cannot fit diffusion coefficient, not enough lags !']);
                    end
                end
                
            else
                MyDialog=warndlg('There is no data to plot ! ','Warning');
                waitfor(MyDialog)
                cla(app.MSDAxis);
                xlabel(app.MSDAxis,'');
                ylabel(app.MSDAxis,'');
                app.MSDAxis.Title.String='';
            end
        end
        
        function PlotProperty(app)
            
            switch app.par.PlotWhat
                case 'MSD'
                    PlotMSD(app)
                    app.YaxisSwitch.Visible='off';
                    app.YaxisSwitchLabel.Visible='off';
                    
                case 'Diffusion state trace'
                    app.YaxisSwitch.Visible='on';
                    app.YaxisSwitchLabel.Visible='on';
                    PlotDiffusionStateTrace(app)
            end
        end
        
        function PlotDiffusionStateTrace(app)
            
            % Select whether internal or external figure
            if app.par.FinalFigureMode==0
                MyAxis=app.MSDAxis;
            else
                figure('Color','white');
                MyAxis=gca;
            end
            
            
            MySM=app.sm(app.par.selected_tracks(app.n).molecule_id);
            
            if strcmp(app.IncludeBlinksSwitch.Value,'Yes')==1 % In that case include blinks in track representation
                minFrame=min(app.par.selected_tracks(app.n).frames);
                maxFrame=max(app.par.selected_tracks(app.n).frames);
                allFrames=MySM.diff_state_trace(1,:);
                w=find(allFrames>=minFrame & allFrames <=maxFrame);
                
            else % In that case do not include blinks in track representation
                allFrames=MySM.diff_state_trace(1,:);
                [~,w,~]=intersect(allFrames,app.par.selected_tracks(app.n).frames);
                
            end
            
            if strcmp(app.XaxisSwitch.Value,'Time')==1
                X=MySM.diff_state_trace(1,w)*app.par.totframetime;
            else
                X=MySM.diff_state_trace(1,w);
            end
            
            Y=MySM.diff_state_trace(2,w);
            
            
            plot(MyAxis,X,Y,'Color', [1 0.5 0], 'LineWidth', app.LineWidthSlider.Value);
            
            if strcmp(app.XaxisSwitch.Value,'Time')==1
                xlabel(MyAxis,'Time [s]');
            else
                xlabel(MyAxis,'Time [frames]');
            end
            
            ylabel(MyAxis,'SMIS diffusion state')
            
            
            if strcmp(app.YaxisSwitch.Value, 'Names')==1
                set(MyAxis,'YTickLabelRotation',30);
                set(MyAxis, 'YTick',1:numel(app.par.diff_state_names),'YTickLabel',app.par.diff_state_names);
            else
                set(MyAxis, 'YTickMode', 'auto', 'YTickLabelMode', 'auto') % Important if state origin has been plotted before
            end
            
            
            XLowLim=min(X)+app.XLowLimitSlider.Value/100*(max(X)-min(X));
            XHighLim=min(X)+app.XHighLimitSlider.Value/100*(max(X)-min(X));
            YLowLim=min(Y)-1+app.YLowLimitSlider.Value/100*(max(Y)-min(Y)+2);
            YHighLim=min(Y)-1+app.YHighLimitSlider.Value/100*(max(Y)-min(Y)+2);
            
            if XLowLim==XHighLim
                XLowLim=XLowLim*0.95;
                XHighLim=XHighLim*1.05;
            end
            
            if YLowLim==YHighLim
                YLowLim=YLowLim*0.95;
                YHighLim=YHighLim*1.05;
            end
            
            xlim(MyAxis,[XLowLim XHighLim]);
            ylim(MyAxis,[YLowLim YHighLim]);
            
            title(MyAxis,'Single Molecule Diffusion Trace','FontWeight','bold');
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
            set(MyAxis,'FontWeight','bold');
            set(MyAxis,'FontSize',app.FontSizeSlider.Value);
            
        end
    end
    
    methods (Access = public)
        
        function UpdateStack(app, stackpar)
            
            % Store inputs as properties
            app.par.stack_loaded = stackpar.stack_loaded;
            app.par.stack_filename = stackpar.stack_filename;
            app.par.stack = stackpar.stack;
            
            % Re-enable the button
            app.OverlayrawdataButton.Enable = 'on';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.par=mainapp.par;
            app.sm=mainapp.sm;
            app.im_par=mainapp.im_par;
            app.sm_par=mainapp.sm_par;
            
            app.n=1;
            
            if numel(app.par.selected_tracks)>1
                app.TrackSpinner.Limits=[1 numel(app.par.selected_tracks)];
            else
                app.TrackSpinner.Visible='off';
                app.TrackSpinnerLabel.Visible='off';
            end
            
            app.MaxlagFramesEditField.Limits=[1 app.par.max_lag];
            
            %Define the names of the diffusion states
            app.par.diff_state_names=[];
            for k=1:app.par.n_diff_states
                if app.im_par.simul_3D==0
                    D_state_name=['State ',num2str(k),': D = ',num2str(app.sm_par.D(k)),' µm²/s'];
                else
                    D_state_name=['State ',num2str(k),': D = ',num2str(app.sm_par.D(k)),' µm',char(179),'/s'];
                end
                
                app.par.diff_state_names=[app.par.diff_state_names;{D_state_name}];
            end
            
            if mainapp.current_state>1
                app.PlotButtonGroup.Visible='off';
            end
            
            app.par.PlotWhat='MSD';
            
            % Plot first SM
            PlotTrack(app);
            PlotProperty(app);
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            stackpar.stack_loaded=app.par.stack_loaded;
            stackpar.stack_filename=app.par.stack_filename;
            stackpar.stack=app.par.stack;
            KeepStack(app.CallingApp, stackpar)
            
            % Delete the OverlayRawDataApp app
            if ~isempty(app.OverlayRawDataApp)
                if isvalid(app.OverlayRawDataApp)
                    delete(app.OverlayRawDataApp)
                end
            end
            close all
            delete(app)
        end

        % Value changed function: TrackSpinner
        function TrackSpinnerValueChanged(app, event)
            app.n=app.TrackSpinner.Value;
            PlotTrack(app)
            PlotProperty(app)
        end

        % Value changed function: FontSizeSlider
        function FontSizeSliderValueChanged(app, event)
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: AxisWidthSlider
        function AxisWidthSliderValueChanged(app, event)
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: LineWidthSlider
        function LineWidthSliderValueChanged(app, event)
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: XLowLimitSlider
        function XLowLimitSliderValueChanged(app, event)
            event=app.XLowLimitSlider.Value;
            app.XHighLimitSlider.Limits=[event 100];
            if event>=app.XHighLimitSlider.Value
                app.XLowLimitSlider.Value=(1-1e-4)*app.XHighLimitSlider.Value;
            end
            
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: XHighLimitSlider
        function XHighLimitSliderValueChanged(app, event)
            event=app.XHighLimitSlider.Value;
            app.XLowLimitSlider.Limits=[0 event];
            if event<=app.XLowLimitSlider.Value
                app.XHighLimitSlider.Value=(1+1e-4)*app.XLowLimitSlider.Value;
            end
            
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: YLowLimitSlider
        function YLowLimitSliderValueChanged(app, event)
            event=app.YLowLimitSlider.Value;
            app.YHighLimitSlider.Limits=[event 100];
            if event>=app.YHighLimitSlider.Value
                app.YLowLimitSlider.Value=app.YHighLimitSlider.Value-1;
            end
            
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: YHighLimitSlider
        function YHighLimitSliderValueChanged(app, event)
            event=app.YHighLimitSlider.Value;
            app.YLowLimitSlider.Limits=[0 event];
            if event<=app.YLowLimitSlider.Value
                app.YHighLimitSlider.Value=app.YLowLimitSlider.Value+1;
            end
            
            PlotProperty(app)
            PlotTrack(app)
        end

        % Value changed function: XaxisSwitch
        function XaxisSwitchValueChanged(app, event)
            PlotTrack(app)
            PlotProperty(app)
        end

        % Button pushed function: MakefigureButton
        function MakefigureButtonPushed(app, event)
            close all
            app.par.FinalFigureMode=1;
            PlotTrack(app)
            PlotProperty(app)
            app.par.FinalFigureMode=0;
        end

        % Value changed function: ScaleSwitch
        function ScaleSwitchValueChanged(app, event)
            PlotTrack(app)
        end

        % Value changed function: ColorSwitch
        function ColorSwitchValueChanged(app, event)
            PlotTrack(app)
        end

        % Value changed function: MaxlagFramesEditField
        function MaxlagFramesEditFieldValueChanged(app, event)
            PlotMSD(app)
        end

        % Value changed function: FitDSwitch
        function FitDSwitchValueChanged(app, event)
            value = app.FitDSwitch.Value;
            if strcmp(value,'Yes')==true
                app.FittedDumsEditField.Visible='on';
                app.FittedDumsEditFieldLabel.Visible='on';
                app.MaxlagFramesEditField.Visible='on';
                app.MaxlagFramesEditFieldLabel.Visible='on';
            else
                app.FittedDumsEditField.Visible='off';
                app.FittedDumsEditFieldLabel.Visible='off';
                app.MaxlagFramesEditField.Visible='off';
                app.MaxlagFramesEditFieldLabel.Visible='off';
            end
            PlotMSD(app)
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.PlotButtonGroup.SelectedObject;
            app.par.PlotWhat=selectedButton.Text;
            
            PlotProperty(app);
            
        end

        % Value changed function: YaxisSwitch
        function YaxisSwitchValueChanged(app, event)
            PlotProperty(app)
        end

        % Button pushed function: OverlayrawdataButton
        function OverlayrawdataButtonPushed(app, event)
            app.OverlayrawdataButton.Enable = 'off';
            app.OverlayRawDataApp=OverlayRawData(app);
        end

        % Value changed function: IncludeBlinksSwitch
        function IncludeBlinksSwitchValueChanged(app, event)
            PlotTrack(app)
            PlotProperty(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrackAxis = uiaxes(app.UIFigure);
            title(app.TrackAxis, 'Single Molecule Track')
            xlabel(app.TrackAxis, 'Time [s]')
            ylabel(app.TrackAxis, 'PhotoPhysical State')
            app.TrackAxis.XLim = [200 800];
            app.TrackAxis.YLim = [0 100];
            app.MSDAxis = uiaxes(app.UIFigure);
            title(app.MSDAxis, 'MSD')
            xlabel(app.MSDAxis, 'X')
            ylabel(app.MSDAxis, 'Y')
            zlabel(app.MSDAxis, 'Z')
            app.SingleMoleculesDiffusionTracksLabel = uilabel(app.UIFigure);
            app.SingleMoleculesDiffusionTracksLabel.Text = 'Single Molecules DiffusionTracks';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Returns to the main menu'};
            app.OKButton.Text = 'OK';
            app.XaxisSwitchLabel = uilabel(app.UIFigure);
            app.XaxisSwitchLabel.Text = 'X-axis';
            app.XaxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.XaxisSwitch.Items = {'Frames', 'Time'};
            app.XaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @XaxisSwitchValueChanged, true);
            app.XaxisSwitch.Tooltip = {'Toggle between frames and time on X axis'};
            app.XaxisSwitch.Value = 'Frames';
            app.FontSizeSliderLabel = uilabel(app.UIFigure);
            app.FontSizeSliderLabel.Text = 'Font Size';
            app.FontSizeSlider = uislider(app.UIFigure);
            app.FontSizeSlider.Limits = [8 20];
            app.FontSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeSliderValueChanged, true);
            app.FontSizeSlider.MinorTicks = [];
            app.FontSizeSlider.Tooltip = {'Adjust font size'};
            app.FontSizeSlider.Value = 14;
            app.AxisWidthSliderLabel = uilabel(app.UIFigure);
            app.AxisWidthSliderLabel.Text = 'Axis Width';
            app.AxisWidthSlider = uislider(app.UIFigure);
            app.AxisWidthSlider.Limits = [1 8];
            app.AxisWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxisWidthSliderValueChanged, true);
            app.AxisWidthSlider.MinorTicks = [];
            app.AxisWidthSlider.Tooltip = {'Adjust axis width'};
            app.AxisWidthSlider.Value = 3;
            app.LineWidthSliderLabel = uilabel(app.UIFigure);
            app.LineWidthSliderLabel.Text = 'Line Width';
            app.LineWidthSlider = uislider(app.UIFigure);
            app.LineWidthSlider.Limits = [1 8];
            app.LineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @LineWidthSliderValueChanged, true);
            app.LineWidthSlider.MinorTicks = [];
            app.LineWidthSlider.Tooltip = {'Adjust line width'};
            app.LineWidthSlider.Value = 2;
            app.XLowLimitSliderLabel = uilabel(app.UIFigure);
            app.XLowLimitSliderLabel.Text = 'X Low Limit';
            app.XLowLimitSlider = uislider(app.UIFigure);
            app.XLowLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XLowLimitSliderValueChanged, true);
            app.XLowLimitSlider.Tooltip = {'Adjust lower limit of X axis'};
            app.YLowLimitSliderLabel = uilabel(app.UIFigure);
            app.YLowLimitSliderLabel.Text = 'Y Low Limit';
            app.YLowLimitSlider = uislider(app.UIFigure);
            app.YLowLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YLowLimitSliderValueChanged, true);
            app.YLowLimitSlider.Tooltip = {'Adjust lower limit of Y axis'};
            app.XHighLimitSliderLabel = uilabel(app.UIFigure);
            app.XHighLimitSliderLabel.Text = 'X High Limit';
            app.XHighLimitSlider = uislider(app.UIFigure);
            app.XHighLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XHighLimitSliderValueChanged, true);
            app.XHighLimitSlider.Tooltip = {'Adjust higher limit of X axis'};
            app.XHighLimitSlider.Value = 100;
            app.YHighLimitSliderLabel = uilabel(app.UIFigure);
            app.YHighLimitSliderLabel.Text = 'Y High Limit';
            app.YHighLimitSlider = uislider(app.UIFigure);
            app.YHighLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YHighLimitSliderValueChanged, true);
            app.YHighLimitSlider.Tooltip = {'Adjust higher limit of Y axis'};
            app.YHighLimitSlider.Value = 100;
            app.TrackSpinnerLabel = uilabel(app.UIFigure);
            app.TrackSpinnerLabel.Text = 'Track';
            app.TrackSpinner = uispinner(app.UIFigure);
            app.TrackSpinner.Limits = [1 Inf];
            app.TrackSpinner.RoundFractionalValues = 'on';
            app.TrackSpinner.ValueDisplayFormat = '%.0f';
            app.TrackSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackSpinnerValueChanged, true);
            app.TrackSpinner.Tooltip = {'Navigate through the tracks'};
            app.TrackSpinner.Value = 1;
            app.MakefigureButton = uibutton(app.UIFigure, 'push');
            app.MakefigureButton.ButtonPushedFcn = createCallbackFcn(app, @MakefigureButtonPushed, true);
            app.MakefigureButton.Tooltip = {'Make external figure that can be saved on disk'};
            app.MakefigureButton.Text = 'Make figure';
            app.FittedDumsEditFieldLabel = uilabel(app.UIFigure);
            app.FittedDumsEditFieldLabel.Text = 'Fitted D [um²/s]';
            app.FittedDumsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FittedDumsEditField.Limits = [0 Inf];
            app.FittedDumsEditField.Editable = 'off';
            app.FittedDumsEditField.Tooltip = {'Value of the currently fitted diffusion coefficient'};
            app.FittedDumsEditField.Value = 1;
            app.FitDSwitchLabel = uilabel(app.UIFigure);
            app.FitDSwitchLabel.Text = 'Fit D';
            app.FitDSwitch = uiswitch(app.UIFigure, 'slider');
            app.FitDSwitch.Items = {'Yes', 'No'};
            app.FitDSwitch.ValueChangedFcn = createCallbackFcn(app, @FitDSwitchValueChanged, true);
            app.FitDSwitch.Tooltip = {'Fit diffusion coefficient from mean square displacement trace shown in the lower graph panel.'};
            app.FitDSwitch.Value = 'Yes';
            app.MaxlagFramesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlagFramesEditFieldLabel.Text = 'Max lag  [Frames]';
            app.MaxlagFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlagFramesEditField.Limits = [2 1000];
            app.MaxlagFramesEditField.ValueDisplayFormat = '%.0f';
            app.MaxlagFramesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlagFramesEditFieldValueChanged, true);
            app.MaxlagFramesEditField.Tooltip = {'Max lag for fitting diffusion coefficient. The maximum value for the max lag is set from track calculations in the previous menu.'};
            app.MaxlagFramesEditField.Value = 4;
            app.TracklengthEditFieldLabel = uilabel(app.UIFigure);
            app.TracklengthEditFieldLabel.Text = 'Tracklength';
            app.TracklengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TracklengthEditField.Limits = [0 Inf];
            app.TracklengthEditField.Editable = 'off';
            app.TracklengthEditField.Tooltip = {'Shows the tracklength of the current track'};
            app.TracklengthEditField.Value = 1;
            app.ScaleSwitchLabel = uilabel(app.UIFigure);
            app.ScaleSwitchLabel.Text = 'Scale';
            app.ScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.ScaleSwitch.Items = {'Pixels', 'Microns'};
            app.ScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ScaleSwitchValueChanged, true);
            app.ScaleSwitch.Tooltip = {'Toggle between Pixel''s or Micron''s scale'};
            app.ScaleSwitch.Value = 'Pixels';
            app.ColorSwitchLabel = uilabel(app.UIFigure);
            app.ColorSwitchLabel.Text = 'Color';
            app.ColorSwitch = uiswitch(app.UIFigure, 'slider');
            app.ColorSwitch.Items = {'Single', 'Multi'};
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorSwitchValueChanged, true);
            app.ColorSwitch.Tooltip = {'Toggle between single color or multicolor track'};
            app.ColorSwitch.Value = 'Multi';
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Tooltip = {'Choose the content of the lower panel plot: '; '=> Mean square displacement'; '=> Plot of the evolution of the diffusion state along the track (useful in the case of kinetic exchange between several diffusion states)'};
            app.PlotButtonGroup.Title = 'Plot';
            app.MSDButton = uitogglebutton(app.PlotButtonGroup);
            app.MSDButton.Text = 'MSD';
            app.MSDButton.Value = true;
            app.DiffusionstatetraceButton = uitogglebutton(app.PlotButtonGroup);
            app.DiffusionstatetraceButton.Text = 'Diffusion state trace';
            app.YaxisSwitchLabel = uilabel(app.UIFigure);
            app.YaxisSwitchLabel.Text = 'Y-axis';
            app.YaxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.YaxisSwitch.Items = {'Numbers', 'Names'};
            app.YaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @YaxisSwitchValueChanged, true);
            app.YaxisSwitch.Tooltip = {'Toggle between diffusion state number or diffusion values on the Y-axis when the lower graph shows a plot of the diffusion state trace.'};
            app.YaxisSwitch.Value = 'Numbers';
            app.OverlayrawdataButton = uibutton(app.UIFigure, 'push');
            app.OverlayrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayrawdataButtonPushed, true);
            app.OverlayrawdataButton.Tooltip = {'Inspect tracks overlaid on raw ground truth SMIS dataset '};
            app.OverlayrawdataButton.Text = 'Overlay raw data';
            app.IncludeBlinksSwitchLabel = uilabel(app.UIFigure);
            app.IncludeBlinksSwitchLabel.Text = 'Include Blinks';
            app.IncludeBlinksSwitch = uiswitch(app.UIFigure, 'slider');
            app.IncludeBlinksSwitch.Items = {'Yes', 'No'};
            app.IncludeBlinksSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeBlinksSwitchValueChanged, true);
            app.IncludeBlinksSwitch.Tooltip = {'If set to Yes, the displayed track will contain positions where the current molecule is off due to a blink. If set to No, only the positions corresponding to properly localized spots are shown'};
            app.IncludeBlinksSwitch.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Diffusion_Traces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end