classdef Show_Traces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MakefigureButton            matlab.ui.control.Button
        MoleculeSpinner             matlab.ui.control.Spinner
        MoleculeSpinnerLabel        matlab.ui.control.Label
        YHighLimitSlider            matlab.ui.control.Slider
        YHighLimitSliderLabel       matlab.ui.control.Label
        XHighLimitSlider            matlab.ui.control.Slider
        XHighLimitSliderLabel       matlab.ui.control.Label
        YaxisSwitch                 matlab.ui.control.Switch
        YaxisSwitchLabel            matlab.ui.control.Label
        YLowLimitSlider             matlab.ui.control.Slider
        YLowLimitSliderLabel        matlab.ui.control.Label
        XLowLimitSlider             matlab.ui.control.Slider
        XLowLimitSliderLabel        matlab.ui.control.Label
        LineWidthSlider             matlab.ui.control.Slider
        LineWidthSliderLabel        matlab.ui.control.Label
        AxisWidthSlider             matlab.ui.control.Slider
        AxisWidthSliderLabel        matlab.ui.control.Label
        FontSizeSlider              matlab.ui.control.Slider
        FontSizeSliderLabel         matlab.ui.control.Label
        XaxisSwitch                 matlab.ui.control.Switch
        XaxisSwitchLabel            matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        SingleMoleculesTracesLabel  matlab.ui.control.Label
        SMAxis                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        sm=[]; % The SM
        n=[]; % The current SM id
        state_names=[]; % The names of the photophysical states
        totframetime=[]; % The frametime+addtime in [s]
        FinalFigureMode=0; % For making external figure
        n_images=[]; % Total #of images in data set
    end
    
    methods (Access = private)
        
        
        function PlotSM(app)
            t=app.sm(app.n).tot_state_trace(1,:); % Time
            s=app.sm(app.n).tot_state_trace(2,:); % State
            
            if numel(t)==1 % Deal with the case where a molecule does not change state until the end
                t=[t,app.totframetime*app.n_images]; % Add a point at the end of the dataset
                s=[s, s];
            end
            
            
            if app.FinalFigureMode==0
                MyAxis=app.SMAxis;
            else
                figure('Color','white');
                MyAxis=gca;
            end
            
            if strcmp(app.XaxisSwitch.Value,'Frames')==1
                t=t/app.totframetime; % Go to frames in that case
            end
            plot(MyAxis,t,s,'Color',[0,0,1],'LineWidth',app.LineWidthSlider.Value);
            
            if strcmp(app.XaxisSwitch.Value,'Frames')==1
                xlabel(MyAxis,'Frames')
            else
                xlabel(MyAxis,'Time [s]')
            end
            
            ylabel(MyAxis,'Photophysical state','fontsize',app.FontSizeSlider.Value,'fontweight','b');
            
            if strcmp(app.YaxisSwitch.Value, 'Names')==1
                set(MyAxis,'YTickLabelRotation',30);
                set(MyAxis, 'YTick',1:numel(app.state_names),'YTickLabel',app.state_names);
            else
                set(MyAxis,'YTickLabelRotation',0);
                MyLabels=cellstr(num2str([1:numel(app.state_names)]'));
                set(MyAxis, 'YTick',1:numel(app.state_names),'YTickLabel',MyLabels);
            end


            set(MyAxis,'FontSize',app.FontSizeSlider.Value);
            set(MyAxis,'FontWeight','b');
            
            XLowLim=min(t)+app.XLowLimitSlider.Value/100*(max(t)-min(t));
            XHighLim=min(t)+app.XHighLimitSlider.Value/100*(max(t)-min(t));
            YLowLim=min(s)+app.YLowLimitSlider.Value/100*(max(s)-min(s));
            YHighLim=min(s)+app.YHighLimitSlider.Value/100*(max(s)-min(s));
            if YLowLim==YHighLim
                YLowLim=0;
            end
            
            xlim(MyAxis,[XLowLim XHighLim]);
            ylim(MyAxis,[YLowLim YHighLim]);
            
            
            set(MyAxis,'LineWidth',app.AxisWidthSlider.Value);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.sm=mainapp.sm;
            app.n=1; % Go to the first molecule to start with
            app.n_images=mainapp.im_par.n_images;
            app.totframetime=(mainapp.im_par.frametime+mainapp.im_par.addtime)*1e-3; % Total frame time in [s]
            app.state_names=mainapp.sm_par(mainapp.fluo_id).state_names;
            
            if numel(app.sm)>1
                app.MoleculeSpinner.Limits=[1 numel(app.sm)];
            else
                app.MoleculeSpinner.Visible='off';
                app.MoleculeSpinnerLabel.Visible='off';
            end
            
            % Plot first SM
            PlotSM(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: MoleculeSpinner
        function MoleculeSpinnerValueChanged(app, event)
            app.n=app.MoleculeSpinner.Value;
            PlotSM(app)
        end

        % Value changed function: FontSizeSlider
        function FontSizeSliderValueChanged(app, event)
            PlotSM(app)
        end

        % Value changed function: AxisWidthSlider
        function AxisWidthSliderValueChanged(app, event)
            PlotSM(app)
        end

        % Value changed function: LineWidthSlider
        function LineWidthSliderValueChanged(app, event)
            PlotSM(app)
        end

        % Value changed function: XLowLimitSlider
        function XLowLimitSliderValueChanged(app, event)
            event=app.XLowLimitSlider.Value;
            app.XHighLimitSlider.Limits=[event 100];
            if event>=app.XHighLimitSlider.Value
                app.XLowLimitSlider.Value=(1-1e-4)*app.XHighLimitSlider.Value;
            end
            PlotSM(app)
        end

        % Value changed function: XHighLimitSlider
        function XHighLimitSliderValueChanged(app, event)
            event=app.XHighLimitSlider.Value;
            app.XLowLimitSlider.Limits=[0 event];
            if event<=app.XLowLimitSlider.Value
                app.XHighLimitSlider.Value=(1+1e-4)*app.XLowLimitSlider.Value;
            end
            PlotSM(app)
        end

        % Value changed function: YLowLimitSlider
        function YLowLimitSliderValueChanged(app, event)
            event=app.YLowLimitSlider.Value;
            app.YHighLimitSlider.Limits=[event 100];
            if event>=app.YHighLimitSlider.Value
                app.YLowLimitSlider.Value=app.YHighLimitSlider.Value-1;
            end
            PlotSM(app)
        end

        % Value changed function: YHighLimitSlider
        function YHighLimitSliderValueChanged(app, event)
            event=app.YHighLimitSlider.Value;
            app.YLowLimitSlider.Limits=[0 event];
            if event<=app.YLowLimitSlider.Value
                app.YHighLimitSlider.Value=app.YLowLimitSlider.Value+1;
            end
            PlotSM(app)
        end

        % Value changed function: YaxisSwitch
        function YaxisSwitchValueChanged(app, event)
            PlotSM(app)
        end

        % Value changed function: XaxisSwitch
        function XaxisSwitchValueChanged(app, event)
            PlotSM(app)
        end

        % Button pushed function: MakefigureButton
        function MakefigureButtonPushed(app, event)
            close all
            app.FinalFigureMode=1;
            PlotSM(app)
            app.FinalFigureMode=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SMAxis = uiaxes(app.UIFigure);
            title(app.SMAxis, 'Trace')
            xlabel(app.SMAxis, 'Time [s]')
            ylabel(app.SMAxis, 'PhotoPhysical State')
            app.SMAxis.XLim = [200 800];
            app.SMAxis.YLim = [0 100];
            app.SingleMoleculesTracesLabel = uilabel(app.UIFigure);
            app.SingleMoleculesTracesLabel.Text = 'Single Molecules Traces';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Return to the main menu'};
            app.OKButton.Text = 'OK';
            app.XaxisSwitchLabel = uilabel(app.UIFigure);
            app.XaxisSwitchLabel.Text = 'X-axis';
            app.XaxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.XaxisSwitch.Items = {'Frames', 'Time'};
            app.XaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @XaxisSwitchValueChanged, true);
            app.XaxisSwitch.Tooltip = {'Toggle between frames and time'};
            app.XaxisSwitch.Value = 'Frames';
            app.FontSizeSliderLabel = uilabel(app.UIFigure);
            app.FontSizeSliderLabel.Text = 'Font Size';
            app.FontSizeSlider = uislider(app.UIFigure);
            app.FontSizeSlider.Limits = [8 20];
            app.FontSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeSliderValueChanged, true);
            app.FontSizeSlider.MinorTicks = [];
            app.FontSizeSlider.Tooltip = {'Change figure font size'};
            app.FontSizeSlider.Value = 14;
            app.AxisWidthSliderLabel = uilabel(app.UIFigure);
            app.AxisWidthSliderLabel.Text = 'Axis Width';
            app.AxisWidthSlider = uislider(app.UIFigure);
            app.AxisWidthSlider.Limits = [1 8];
            app.AxisWidthSlider.ValueChangedFcn = createCallbackFcn(app, @AxisWidthSliderValueChanged, true);
            app.AxisWidthSlider.MinorTicks = [];
            app.AxisWidthSlider.Tooltip = {'Change figure axis width'};
            app.AxisWidthSlider.Value = 3;
            app.LineWidthSliderLabel = uilabel(app.UIFigure);
            app.LineWidthSliderLabel.Text = 'Line Width';
            app.LineWidthSlider = uislider(app.UIFigure);
            app.LineWidthSlider.Limits = [1 8];
            app.LineWidthSlider.ValueChangedFcn = createCallbackFcn(app, @LineWidthSliderValueChanged, true);
            app.LineWidthSlider.MinorTicks = [];
            app.LineWidthSlider.Tooltip = {'Change figure line width'};
            app.LineWidthSlider.Value = 3;
            app.XLowLimitSliderLabel = uilabel(app.UIFigure);
            app.XLowLimitSliderLabel.Text = 'X Low Limit';
            app.XLowLimitSlider = uislider(app.UIFigure);
            app.XLowLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XLowLimitSliderValueChanged, true);
            app.XLowLimitSlider.Tooltip = {'Change figure X-range low limit'};
            app.YLowLimitSliderLabel = uilabel(app.UIFigure);
            app.YLowLimitSliderLabel.Text = 'Y Low Limit';
            app.YLowLimitSlider = uislider(app.UIFigure);
            app.YLowLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YLowLimitSliderValueChanged, true);
            app.YLowLimitSlider.Tooltip = {'Change figure Y-range low limit'};
            app.YaxisSwitchLabel = uilabel(app.UIFigure);
            app.YaxisSwitchLabel.Text = 'Y-axis';
            app.YaxisSwitch = uiswitch(app.UIFigure, 'slider');
            app.YaxisSwitch.Items = {'Numbers', 'Names'};
            app.YaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @YaxisSwitchValueChanged, true);
            app.YaxisSwitch.Tooltip = {'Toggle between showing state numbers or state names as y-axis labels'};
            app.YaxisSwitch.Value = 'Numbers';
            app.XHighLimitSliderLabel = uilabel(app.UIFigure);
            app.XHighLimitSliderLabel.Text = 'X High Limit';
            app.XHighLimitSlider = uislider(app.UIFigure);
            app.XHighLimitSlider.ValueChangedFcn = createCallbackFcn(app, @XHighLimitSliderValueChanged, true);
            app.XHighLimitSlider.Tooltip = {'Change figure X-range high limit'};
            app.XHighLimitSlider.Value = 100;
            app.YHighLimitSliderLabel = uilabel(app.UIFigure);
            app.YHighLimitSliderLabel.Text = 'Y High Limit';
            app.YHighLimitSlider = uislider(app.UIFigure);
            app.YHighLimitSlider.ValueChangedFcn = createCallbackFcn(app, @YHighLimitSliderValueChanged, true);
            app.YHighLimitSlider.Tooltip = {'Change figure Y-range high limit'};
            app.YHighLimitSlider.Value = 100;
            app.MoleculeSpinnerLabel = uilabel(app.UIFigure);
            app.MoleculeSpinnerLabel.Text = 'Molecule ';
            app.MoleculeSpinner = uispinner(app.UIFigure);
            app.MoleculeSpinner.Limits = [1 Inf];
            app.MoleculeSpinner.RoundFractionalValues = 'on';
            app.MoleculeSpinner.ValueDisplayFormat = '%.0f';
            app.MoleculeSpinner.ValueChangedFcn = createCallbackFcn(app, @MoleculeSpinnerValueChanged, true);
            app.MoleculeSpinner.Tooltip = {'Change molecule id number'};
            app.MoleculeSpinner.Value = 1;
            app.MakefigureButton = uibutton(app.UIFigure, 'push');
            app.MakefigureButton.ButtonPushedFcn = createCallbackFcn(app, @MakefigureButtonPushed, true);
            app.MakefigureButton.Tooltip = {'Make a figure of currently displayed data that can be saved to disk'};
            app.MakefigureButton.Text = 'Make figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Traces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end