classdef kTSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        ToLabel                         matlab.ui.control.Label
        FromLabel                       matlab.ui.control.Label
        kTTable                         matlab.ui.control.Table
        OKButton                        matlab.ui.control.Button
        ThermalexchangeratessetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        kTpar=[]; % Initialize the parameters
        n_states=[]; % Initialize variable
        nonbleached_states=[]; % Initialize variable
        Fluorophorepar=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Fluorophorepar, App_Position)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.Fluorophorepar=Fluorophorepar;
            app.n_states=Fluorophorepar.Photophysics.n_states; % Number of photophysical states
            app.nonbleached_states=setdiff(1:app.n_states,Fluorophorepar.Photophysics.bleached_states); % Ids of non-bleached states
            n_nonbleached_states=numel(app.nonbleached_states); % Number of non-bleached states
            
            % Load name of fluorophore
            app.ThermalexchangeratessetupLabel.Text=['Thermal exchange rates set up [s-1]: ',Fluorophorepar.name];
            
            % Load phototransformation quantum yield parameters
            app.kTpar=Fluorophorepar.Photophysics.trans_k;
            
            % Initialize kTpar
            if ~isempty(app.kTpar)
                % Setup Table Position size
                kTTableWidth=min([100+100*app.n_states,app.UIFigure.Position(3)-100]);
                kTTableHeight=min([30+20*n_nonbleached_states,app.UIFigure.Position(4)-210]);
                kTTableBottomPos=max([350-20*n_nonbleached_states,100]);
                kTTableLeftPos=70;

                app.kTTable.Position=[kTTableLeftPos,kTTableBottomPos,kTTableWidth,kTTableHeight]; % Adjust position

                %Center position of the From and To labels
                app.ToLabel.Position(1)=app.kTTable.Position(1)+app.kTTable.Position(3)/2;
                app.FromLabel.Position(2)=app.kTTable.Position(2)+app.kTTable.Position(4)/2;
                % Show the names of the fluorescent states
                app.kTTable.ColumnName=Fluorophorepar.Photophysics.state_names;
                app.kTTable.RowName=app.kTTable.ColumnName(app.nonbleached_states);
                % Fill the data
                app.kTTable.Data=app.kTpar(app.nonbleached_states,:);
                MyColumnFormat=cell(1,numel(app.kTTable.ColumnName));
                [MyColumnFormat{:}]=deal('shortG');
                app.kTTable.ColumnFormat = MyColumnFormat;
            end
            
            % Eventually resize the app according to previous user action
            if ~isempty(App_Position) && strcmp(app.Fluorophorepar.name, App_Position.Name)==1
                app.UIFigure.Position = App_Position.UIFigure;
                app.kTTable.Position = App_Position.kTtable;
                app.FromLabel.Position=App_Position.FromLabel;
                app.ToLabel.Position=App_Position.ToLabel;
                %Center position of the From and To labels
                app.ToLabel.Position(1)=app.kTTable.Position(1)+app.kTTable.Position(3)/2;
                app.FromLabel.Position(2)=app.kTTable.Position(2)+app.kTTable.Position(4)/2;
                app.ThermalexchangeratessetupLabel.Position=App_Position.ThermalexchangeratessetupLabel;
            end
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            App_Position.Name=app.Fluorophorepar.name;
            App_Position.UIFigure=app.UIFigure.Position;
            App_Position.kTtable=app.kTTable.Position;
            App_Position.FromLabel=app.FromLabel.Position;
            App_Position.ToLabel=app.ToLabel.Position;
            App_Position.ThermalexchangeratessetupLabel=app.ThermalexchangeratessetupLabel.Position;
            % Call main app's public function
            
            updatekT(app.CallingApp, app.kTpar, App_Position)
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: kTTable
        function kTTableCellEdit(app, event)
            newData = event.NewData;
            
            starting_state = app.nonbleached_states(event.Indices(1));
            ending_state = event.Indices(2);
            
            valid_phototransformation=1; % Initialize
            %Issue warnings when transformation is not possible
            
            if newData<0
                warndlg('Rates must be > 0 !','Warning');
                valid_phototransformation=0;
            end
            
            if starting_state>=app.Fluorophorepar.Photophysics.converted_state && ending_state<app.Fluorophorepar.Photophysics.converted_state
                warndlg('Transformation from photoconverted state back to non-photoconverted state is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if app.Fluorophorepar.Photophysics.state_ids(starting_state)==app.Fluorophorepar.Photophysics.state_ids(ending_state)
                warndlg('Transformation between identical or rapidly exchanging states is not possible !','Warning');
                valid_phototransformation=0;
            end
            
            if valid_phototransformation==1
                app.kTpar(app.nonbleached_states,:) = app.kTTable.DisplayData;
            else
                app.kTTable.Data(event.Indices(1),event.Indices(2))=0; % Reset the value to zero
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            updateCancel(app.CallingApp)    
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            updateCancel(app.CallingApp)    
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThermalexchangeratessetupLabel = uilabel(app.UIFigure);
            app.ThermalexchangeratessetupLabel.Text = ' Thermal exchange rates set up:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.kTTable = uitable(app.UIFigure);
            app.kTTable.ColumnName = {'Fluo State Name'};
            app.kTTable.RowName = {'pKa '; 'Hill'};
            app.kTTable.ColumnEditable = true;
            app.kTTable.CellEditCallback = createCallbackFcn(app, @kTTableCellEdit, true);
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kTSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end