classdef sptPALMOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OversamplingforexchangeratesEditField  matlab.ui.control.NumericEditField
        OversamplingforexchangeratesEditFieldLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ShowDiffImageDuringAcquisitionSwitch  matlab.ui.control.Switch
        ShowDiffImageDuringAcquisitionSwitchLabel  matlab.ui.control.Label
        PreventDiffusionOutoftheFieldofViewSwitch  matlab.ui.control.Switch
        PreventDiffusionOutoftheFieldofViewSwitchLabel  matlab.ui.control.Label
        UseSubframeDiffusionSwitch     matlab.ui.control.Switch
        UseSubframeDiffusionSwitchLabel  matlab.ui.control.Label
        MoveNonphotoconvertedMoleculesSwitch  matlab.ui.control.Switch
        MoveNonphotoconvertedMoleculesSwitchLabel  matlab.ui.control.Label
        SubframeresolutionnmEditField  matlab.ui.control.NumericEditField
        SubframeresolutionnmEditFieldLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        sptPALMOptionsLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Optionspar =[]; % Initialize the parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            
            % Store main app in property for CloseRequestFcn to use
            app.Optionspar = mainapp.smis_gui_parameters.sptPALMOptions;
            if app.Optionspar.use_diffuse_psf==1
                app.SubframeresolutionnmEditField.Visible='on';
                app.SubframeresolutionnmEditFieldLabel.Visible='on';
                app.SubframeresolutionnmEditField.Value=app.Optionspar.diffuse_psf_radius;
                app.OversamplingforexchangeratesEditField.Visible='on';
                app.OversamplingforexchangeratesEditFieldLabel.Visible='on';
                if isfield(app.Optionspar,'ex_rates_min_oversampling') % Patch for SMIS 1.3
                    app.OversamplingforexchangeratesEditField.Value=app.Optionspar.ex_rates_min_oversampling;
                end
                app.UseSubframeDiffusionSwitch.Value='On';
            else
                app.SubframeresolutionnmEditField.Visible='off';
                app.SubframeresolutionnmEditFieldLabel.Visible='off';
                app.OversamplingforexchangeratesEditField.Visible='off';
                app.OversamplingforexchangeratesEditFieldLabel.Visible='off';
                app.UseSubframeDiffusionSwitch.Value='Off';
            end
            
            % Only display the following switch if some fluorophores are photoconvertible
            converted_states=zeros(1,mainapp.smis_gui_parameters.n_fluorophores);
            for i=1:numel(converted_states)
                converted_states(i)=mainapp.smis_gui_parameters.Fluorophores(i).Photophysics.converted_state;
            end
            if max(converted_states>1)
                app.MoveNonphotoconvertedMoleculesSwitch.Visible='on';
                app.MoveNonphotoconvertedMoleculesSwitchLabel.Visible='on';
                if app.Optionspar.move_non_activated_molecules==1
                    app.MoveNonphotoconvertedMoleculesSwitch.Value='On';
                else
                    app.MoveNonphotoconvertedMoleculesSwitch.Value='Off';
                end
            else
                app.MoveNonphotoconvertedMoleculesSwitch.Visible='off';
                app.MoveNonphotoconvertedMoleculesSwitchLabel.Visible='off';
            end
            
            % Update the other fields according to input value
            if app.Optionspar.prevent_diffusion_out_FOV==1
                app.PreventDiffusionOutoftheFieldofViewSwitch.Value='On';
            else
                app.PreventDiffusionOutoftheFieldofViewSwitch.Value='Off';
            end
            
            if app.Optionspar.show_diff_image==1
                app.ShowDiffImageDuringAcquisitionSwitch.Value='On';
            else
                app.ShowDiffImageDuringAcquisitionSwitch.Value='Off';
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updatesptPALMOptions(app.CallingApp, app.Optionspar)
            % Delete the dialog box
            delete(app)
        end

        % Callback function
        function move_non_activated_moleculesEditFieldValueChanged(app, event)
            value = app.move_non_activated_moleculesEditField.Value;
            app.EMCCDpar.det_EMCCD_gain=value;
        end

        % Value changed function: UseSubframeDiffusionSwitch
        function UseSubframeDiffusionSwitchValueChanged(app, event)
            value = app.UseSubframeDiffusionSwitch.Value;
            if strcmp(value,'On')==1
                app.Optionspar.use_diffuse_psf=1;
                app.SubframeresolutionnmEditField.Visible='on';
                app.SubframeresolutionnmEditFieldLabel.Visible='on';
                app.OversamplingforexchangeratesEditField.Visible='on';
                app.OversamplingforexchangeratesEditFieldLabel.Visible='on';
            else
                app.Optionspar.use_diffuse_psf=0;
                app.SubframeresolutionnmEditField.Visible='off';
                app.SubframeresolutionnmEditFieldLabel.Visible='off';
                app.OversamplingforexchangeratesEditField.Visible='off';
                app.OversamplingforexchangeratesEditFieldLabel.Visible='off';
            end
        end

        % Value changed function: MoveNonphotoconvertedMoleculesSwitch
        function MoveNonphotoconvertedMoleculesSwitchValueChanged(app, event)
            value = app.MoveNonphotoconvertedMoleculesSwitch.Value;
            
            if strcmp(value,'On')==1
                app.Optionspar.move_non_activated_molecules=1;
            else
                app.Optionspar.move_non_activated_molecules=0;
            end
        end

        % Value changed function: SubframeresolutionnmEditField
        function SubframeresolutionnmEditFieldValueChanged(app, event)
            app.Optionspar.diffuse_psf_radius = app.SubframeresolutionnmEditField.Value;
        end

        % Value changed function: PreventDiffusionOutoftheFieldofViewSwitch
        function PreventDiffusionOutoftheFieldofViewSwitchValueChanged(app, event)
            value = app.PreventDiffusionOutoftheFieldofViewSwitch.Value;
            
            if strcmp(value,'On')==1
                app.Optionspar.prevent_diffusion_out_FOV=1;
            else
                app.Optionspar.prevent_diffusion_out_FOV=0;
            end
        end

        % Value changed function: ShowDiffImageDuringAcquisitionSwitch
        function ShowDiffImageDuringAcquisitionSwitchValueChanged(app, event)
            value = app.ShowDiffImageDuringAcquisitionSwitch.Value;
            
            if strcmp(value,'On')==1
                app.Optionspar.show_diff_image=1;
            else
                app.Optionspar.show_diff_image=0;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Value changed function: OversamplingforexchangeratesEditField
        function OversamplingforexchangeratesEditFieldValueChanged(app, event)
            app.Optionspar.ex_rates_min_oversampling = app.OversamplingforexchangeratesEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.sptPALMOptionsLabel = uilabel(app.UIFigure);
            app.sptPALMOptionsLabel.Text = 'sptPALM Options';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SubframeresolutionnmEditFieldLabel = uilabel(app.UIFigure);
            app.SubframeresolutionnmEditFieldLabel.Text = 'Subframe resolution [nm]';
            app.SubframeresolutionnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubframeresolutionnmEditField.Limits = [0 Inf];
            app.SubframeresolutionnmEditField.ValueDisplayFormat = '%11.0d';
            app.SubframeresolutionnmEditField.ValueChangedFcn = createCallbackFcn(app, @SubframeresolutionnmEditFieldValueChanged, true);
            app.SubframeresolutionnmEditField.Tooltip = {'This value sets the resolution for the subframe diffusion calculation. The smaller the value the better, at the cost of increased computing time.'};
            app.SubframeresolutionnmEditField.Value = 10;
            app.MoveNonphotoconvertedMoleculesSwitchLabel = uilabel(app.UIFigure);
            app.MoveNonphotoconvertedMoleculesSwitchLabel.Text = 'Move Nonphotoconverted Molecules ';
            app.MoveNonphotoconvertedMoleculesSwitch = uiswitch(app.UIFigure, 'slider');
            app.MoveNonphotoconvertedMoleculesSwitch.ValueChangedFcn = createCallbackFcn(app, @MoveNonphotoconvertedMoleculesSwitchValueChanged, true);
            app.MoveNonphotoconvertedMoleculesSwitch.Tooltip = {'Set this switch to On if the molecules that have not been photoconverted are to be moved according to the diffusion states. As these molecules are typically not visible, this represents potentially non-useful extra calculations.'};
            app.UseSubframeDiffusionSwitchLabel = uilabel(app.UIFigure);
            app.UseSubframeDiffusionSwitchLabel.Text = 'Use Subframe Diffusion';
            app.UseSubframeDiffusionSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseSubframeDiffusionSwitch.ValueChangedFcn = createCallbackFcn(app, @UseSubframeDiffusionSwitchValueChanged, true);
            app.UseSubframeDiffusionSwitch.Tooltip = {'Use this option to perform subframe diffusion calculations. This is more realistic but represents a significant increase in computing time. As a consequence of this, the PSF of the moving molecules will be smeared according to the motion that occurs during frametime. '};
            app.PreventDiffusionOutoftheFieldofViewSwitchLabel = uilabel(app.UIFigure);
            app.PreventDiffusionOutoftheFieldofViewSwitchLabel.Text = 'Prevent Diffusion Out of the Field of View';
            app.PreventDiffusionOutoftheFieldofViewSwitch = uiswitch(app.UIFigure, 'slider');
            app.PreventDiffusionOutoftheFieldofViewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreventDiffusionOutoftheFieldofViewSwitchValueChanged, true);
            app.PreventDiffusionOutoftheFieldofViewSwitch.Tooltip = {'Use this option to prevent moving molecules to move out of the field of view. '};
            app.ShowDiffImageDuringAcquisitionSwitchLabel = uilabel(app.UIFigure);
            app.ShowDiffImageDuringAcquisitionSwitchLabel.Text = 'Show Diff Image During Acquisition';
            app.ShowDiffImageDuringAcquisitionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowDiffImageDuringAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowDiffImageDuringAcquisitionSwitchValueChanged, true);
            app.ShowDiffImageDuringAcquisitionSwitch.Tooltip = {'Use this option to visualize moving molecules in an extra Matlab figure during data acquisition.'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OversamplingforexchangeratesEditFieldLabel = uilabel(app.UIFigure);
            app.OversamplingforexchangeratesEditFieldLabel.Text = 'Oversampling for exchange rates';
            app.OversamplingforexchangeratesEditField = uieditfield(app.UIFigure, 'numeric');
            app.OversamplingforexchangeratesEditField.Limits = [0 Inf];
            app.OversamplingforexchangeratesEditField.ValueDisplayFormat = '%11.0d';
            app.OversamplingforexchangeratesEditField.ValueChangedFcn = createCallbackFcn(app, @OversamplingforexchangeratesEditFieldValueChanged, true);
            app.OversamplingforexchangeratesEditField.Tooltip = {'This value sets the oversampling factor for exchange rates between different diffusion states. It is possible to use a value << 1 to speed up calculations e.g. for PAINT simulations.'};
            app.OversamplingforexchangeratesEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sptPALMOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end